classdef histogram_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        HidezerointensitypixelsCheckBox  matlab.ui.control.CheckBox
        TabGroup                  matlab.ui.container.TabGroup
        RBGGammaTab               matlab.ui.container.Tab
        BlueGammaEditField        matlab.ui.control.NumericEditField
        BlueGammaEditFieldLabel   matlab.ui.control.Label
        GreenGammaEditField       matlab.ui.control.NumericEditField
        GreenGammaEditFieldLabel  matlab.ui.control.Label
        RedGammaEditField         matlab.ui.control.NumericEditField
        RedGammaEditFieldLabel    matlab.ui.control.Label
        BlueGamma                 matlab.ui.control.UIAxes
        GreenGamma                matlab.ui.control.UIAxes
        RedGamma                  matlab.ui.control.UIAxes
        WhiteGFPDICGammaTab       matlab.ui.container.Tab
        DICGammaEditField         matlab.ui.control.NumericEditField
        DICGammaEditFieldLabel    matlab.ui.control.Label
        WhiteGammaEditField       matlab.ui.control.NumericEditField
        WhiteGammaEditFieldLabel  matlab.ui.control.Label
        GFPGammaEditField         matlab.ui.control.NumericEditField
        GFPGammaEditFieldLabel    matlab.ui.control.Label
        DICGamma                  matlab.ui.control.UIAxes
        GFPGamma                  matlab.ui.control.UIAxes
        WhiteGamma                matlab.ui.control.UIAxes
        LowerDiv                  matlab.ui.control.HTML
        ButtonGroup               matlab.ui.container.ButtonGroup
        MAXLabel                  matlab.ui.control.Label
        MINLabel                  matlab.ui.control.Label
        LOCKLabel                 matlab.ui.control.Label
        RedReset                  matlab.ui.control.Button
        GreenReset                matlab.ui.control.Button
        BlueReset                 matlab.ui.control.Button
        WhiteReset                matlab.ui.control.Button
        GFPReset                  matlab.ui.control.Button
        DICReset                  matlab.ui.control.Button
        red_lock                  matlab.ui.control.Switch
        green_lock                matlab.ui.control.Switch
        blue_lock                 matlab.ui.control.Switch
        white_lock                matlab.ui.control.Switch
        gfp_lock                  matlab.ui.control.Switch
        dic_lock                  matlab.ui.control.Switch
        Switch_6Label             matlab.ui.control.Label
        Switch_5Label             matlab.ui.control.Label
        Switch_4Label             matlab.ui.control.Label
        Switch_3Label             matlab.ui.control.Label
        Switch_2Label             matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        red_max                   matlab.ui.control.Spinner
        red_min                   matlab.ui.control.Spinner
        green_max                 matlab.ui.control.Spinner
        green_min                 matlab.ui.control.Spinner
        blue_max                  matlab.ui.control.Spinner
        blue_min                  matlab.ui.control.Spinner
        white_max                 matlab.ui.control.Spinner
        white_min                 matlab.ui.control.Spinner
        gfp_max                   matlab.ui.control.Spinner
        gfp_min                   matlab.ui.control.Spinner
        dic_max                   matlab.ui.control.Spinner
        dic_min                   matlab.ui.control.Spinner
        REDCheckBox               matlab.ui.control.CheckBox
        GREENCheckBox             matlab.ui.control.CheckBox
        BLUECheckBox              matlab.ui.control.CheckBox
        WHITECheckBox             matlab.ui.control.CheckBox
        GFPCheckBox               matlab.ui.control.CheckBox
        DICCheckBox               matlab.ui.control.CheckBox
        SettingsButtonGroup_2     matlab.ui.container.ButtonGroup
        EditField                 matlab.ui.control.NumericEditField
        SetGuideButton            matlab.ui.control.Button
        BestFitButton             matlab.ui.control.Button
        GAMMALabel                matlab.ui.control.Label
        gamma_knob                matlab.ui.control.Knob
        GuideCheckBox             matlab.ui.control.CheckBox
        HTML                      matlab.ui.control.HTML
        UIAxes                    matlab.ui.control.UIAxes
        ImageAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        live_view; %
        parent_app; % Reference to main Neuropal window.
        image_data; % Passed from main
        image_neurons;
        repo_img; % Back up of image_data for resetting purposes.
        repo_gamma; % Back up of image_gamma for resetting purposes.
        image_gamma; % Passed from main
        image_view; % Passed from main
        repo = [255 0; 255 0; 255 0; 255 0; 255 0; 255 0;] % Back up of min-max values.
        red_guide; % Red values extracted from guide.mat.
        green_guide; % Green values extracted from guide.mat.
        blue_guide; % Blue values extracted from guide.mat.
        whites; % Greyscale image used for white extraction.
        histr; % The hist variables denote various histograms for reference.
        histg;
        histb;
        histw;
        histgfp;
        histdic;
        gfps;
        dics;
        preferences; % GUIPreferences passed from main.
        gamma_RGBW_DIC_GFP_index = 1:6; % the indices for gamma values
        red; % Color indices.
        green;
        blue;
        white;
        gfp;
        dic; 
    end

    methods (Access = private)
    
        function ClearLinesFromAxes(app)
            axesHandlesToChildObjects = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
            end  
        end

        function valupdate(app,chan,max_val,min_val)
            
            chanSet = {'r','g','b','w','gfp','dic'};
            %colSet = {'--r','--g','--b','--k','--y','--m'};
            lockSet = {app.red_lock.Value, app.green_lock.Value, app.blue_lock.Value, app.white_lock.Value, app.gfp_lock.Value, app.dic_lock.Value};
            arrSet = [app.red app.green app.blue app.white app.gfp app.dic];
            chanMap = containers.Map(chanSet,arrSet);
            %colMap = containers.Map(chanSet,colSet);
            lockMap = containers.Map(chanSet,lockSet);

            if lockMap(chan) ~= '🔒' %#ok<*BDSCA> 

                temp = app.repo_img(:,:,:,chanMap(chan));
                max_val = (max_val/255)*65535;
                
                temp(temp > max_val) = max_val;
                temp(temp < min_val) = min_val;
                
                app.image_data(:,:,:,chanMap(chan)) = temp;
                updateimage(app, app.image_data);

            else
                'Locked channel.' %#ok<NOPRT> 
            
            end

        end

        function updateimage(app,imagefile)

            % Determine the channel=color assignments for displaying.
            color_indices = [app.red, app.green, app.blue];

            % Draw the 3 color channels.
            app.image_view = app.image_data(:,:,:,color_indices);

            % Remove unchecked color channels.
            if ~app.REDCheckBox.Value % Red
                app.image_view(:,:,:,1) = 0;
            end
            if ~app.GREENCheckBox.Value % Green
                app.image_view(:,:,:,2) = 0;
            end
            if ~app.BLUECheckBox.Value % Blue
                app.image_view(:,:,:,3) = 0;
            end

            % Add in the white channel.
            if app.WHITECheckBox.Value % White

                % Compute the white channel.
                wchannel = app.image_data(:,:,:,app.white);

                % Adjust the gamma.
                W_i = app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index
                if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1
                    wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                end

                % Add the white channel.
                app.image_view = app.image_view + repmat(wchannel, [1,1,1,3]);
            end

            % Add in the DIC channel.
            if app.DICCheckBox.Value % DIC

                % Compute the DIC channel.
                dic_channel = app.image_data(:,:,:,app.dic);

                % Adjust the gamma.
                DIC_i = app.gamma_RGBW_DIC_GFP_index(5); % DIC channel gamma index
                if length(app.image_gamma) >= DIC_i && app.image_gamma(DIC_i) ~= 1
                    dic_channel = imadjustn(dic_channel,[],[], app.image_gamma(DIC_i));
                end

                % Add the DIC channel.
                app.image_view = app.image_view + repmat(dic_channel, [1,1,1,3]);
            end

            % Add in the GFP channel.
            if app.GFPCheckBox.Value % GFP

                % Compute the GFP channel.
                gfp_color = app.preferences.GFP_color;
                gfp_channel = app.image_data(:,:,:,app.gfp);

                % Adjust the gamma.
                GFP_i = app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index
                if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1
                    gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));
                end

                % Add the GFP channel.
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                gfp_channel(:,:,:,~gfp_color) = 0;
                app.image_view = app.image_view + gfp_channel;
            end

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            app.image_view = uint16(double(intmax('uint16')) * ...
                double(app.image_view)/double(max(app.image_view(:))));
            for c = 1:size(app.image_view, 4)
                if app.image_gamma(c) ~= 1
                    app.image_view(:,:,:,c) = ...
                        imadjustn(squeeze(app.image_view(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end

            hist_view = squeeze(max(app.image_view,[],3));
            hist_view = double(hist_view);
            hist_view = (hist_view./65535).*255;
            app.image_view = double(app.image_view)/double(max(app.image_view(:)));

            try
                im = image(app.ImageAxes,squeeze(max(app.image_view,[],3)));
                app.whites = im2gray(hist_view);
                %[pixel_count, gray_levels] = imhist(image_view);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                sprintf('%s %s', mat2str(size(imagefile)), class(imagefile))
                return;
            end  

            % Plot the histograms

            app.histr = histogram(app.UIAxes, hist_view(:,:,1), 'EdgeColor', 'red', 'DisplayStyle','stairs', 'BinMethod', 'integers');
            %histogram(app.RedGamma,hist_view(:,:,1), 'EdgeColor', 'red', 'BinMethod', 'integers');
            hold(app.UIAxes,'on')
            app.histg = histogram(app.UIAxes, hist_view(:,:,2), 'EdgeColor', 'green', 'DisplayStyle','stairs', 'BinMethod', 'integers');
            %histogram(app.GreenGamma,hist_view(:,:,2), 'EdgeColor', 'green', 'BinMethod', 'integers');
            app.histb = histogram(app.UIAxes, hist_view(:,:,3), 'EdgeColor', 'blue', 'DisplayStyle','stairs', 'BinMethod', 'integers');

            %{
            histogram(app.BlueGamma,hist_view(:,:,3), 'EdgeColor', 'blue', 'BinMethod', 'integers');

            histogram(app.WhiteGamma,(double(app.whites)./65535).*255, 'EdgeColor', 'white', 'BinMethod', 'integers');

            try
                histogram(app.GFPGamma,(double(gfp_channel)./65535).*255, 'EdgeColor', 'yellow', 'BinMethod', 'integers');
            catch
                'No GFP channel found.'
            end


            try
                histogram(app.DICGamma,(double(dic_channel)./65535).*255, 'EdgeColor', '#c2c2c2', 'BinMethod', 'integers');
            catch
                'No DIC channel found.'
            end
            %}
            
            if app.WHITECheckBox.Value
                try
                    app.histw = histogram(app.UIAxes, (double(app.whites)./65535).*255, 'EdgeColor', 'white', 'DisplayStyle','stairs', 'BinMethod', 'integers');
                    %xline(app.UIAxes,app.white_max.Value,'--k');
                    %xline(app.UIAxes,app.white_min.Value,'--k');
                catch
                    msgbox('Could not plot white channel.','Error.')
                end
            end

            if app.GFPCheckBox.Value
                try
                    app.histgfp = histogram(app.UIAxes, (double(gfp_channel)./65535).*255, 'EdgeColor', 'yellow', 'DisplayStyle','stairs', 'BinMethod', 'integers');
                    %histogram(app.GFPGamma,(double(gfp_channel)./65535).*255, 'EdgeColor', 'yellow', 'BinMethod', 'integers');
                    %xline(app.UIAxes,app.gfp_max.Value,'--y');
                    %xline(app.UIAxes,app.gfp_min.Value,'--y');
                catch
                    msgbox('Could not plot GFP channel.','Error.')
                end
            end

            if app.DICCheckBox.Value
                try
                    app.histdic = histogram(app.UIAxes, (double(dic_channel)./65535).*255, 'EdgeColor', '#c2c2c2', 'DisplayStyle','stairs', 'BinMethod', 'integers');
                    %histogram(app.DICGamma,(double(dic_channel)./65535).*255, 'EdgeColor', '#c2c2c2', 'BinMethod', 'integers');
                    %xline(app.UIAxes,app.dic_max.Value,'--m');
                    %xline(app.UIAxes,app.dic_min.Value,'--m');
                catch
                    msgbox('Could not plot DIC channel.','Error.')
                end
            end

            %{
            xline(app.UIAxes,app.red_max.Value,'--r');
            xline(app.UIAxes,app.red_min.Value,'--r');

            xline(app.UIAxes,app.green_max.Value,'--g');
            xline(app.UIAxes,app.green_min.Value,'--g');

            xline(app.UIAxes,app.blue_max.Value,'--b');
            xline(app.UIAxes,app.blue_min.Value,'--b');
            %}
            
            gamma_dict = dictionary(app.red,app.RedGamma,app.green,app.GreenGamma,app.blue,app.BlueGamma,app.white,app.WhiteGamma,app.gfp,app.GFPGamma,app.dic,app.DICGamma);

            for channel = [app.red,app.green,app.blue,app.white,app.gfp,app.dic]
                val_range = 1:max(hist_view,[],'all');
                V_out = ((val_range./max(hist_view,[],'all')).^app.image_gamma(channel)).*max(hist_view,[],'all');
                plot(gamma_dict(channel),val_range,V_out, 'w');
            end
                
            legend(app.UIAxes, 'Red','Green','Blue');

            % Get largest bin count
            maxr = max(app.histr.BinCounts);
            maxg = max(app.histg.BinCounts);
            maxb = max(app.histb.BinCounts);

            try
                maxw = max(app.histw.BinCounts);
            catch
                maxw = 0;
            end

            try
                maxgfp = max(app.histgfp.BinCounts);
            catch
                maxgfp = 0;
            end

            try
                maxdic = max(app.histdic.BinCounts);
            catch
                maxdic = 0;
            end
            
            maxcount = max([maxr maxg maxb maxw maxgfp maxdic]);
            
            % Set y axes limits based on largest bin count
            for ax=[app.UIAxes,app.RedGamma,app.GreenGamma,app.BlueGamma,app.DICGamma,app.GFPGamma,app.WhiteGamma]
                set(ax,'Color','#787878')
                hold(ax,'off')
            end

            app.UIAxes.YLim = [0 maxcount];
            app.UIAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            
            if app.HidezerointensitypixelsCheckBox.Value
                app.UIAxes.XLim = [1, app.UIAxes.XLim(2)];
                app.RedGamma.XLim = [1, app.RedGamma.XLim(2)];
                app.GreenGamma.XLim = [1, app.GreenGamma.XLim(2)];
                app.BlueGamma.XLim = [1, app.BlueGamma.XLim(2)];
                app.DICGamma.XLim = [1, app.DICGamma.XLim(2)];
                app.GFPGamma.XLim = [1, app.GFPGamma.XLim(2)];
                app.WhiteGamma.XLim = [1, app.WhiteGamma.XLim(2)];
            else
                app.UIAxes.XLim = [0, app.UIAxes.XLim(2)];
                app.RedGamma.XLim = [0, app.RedGamma.XLim(2)];
                app.GreenGamma.XLim = [0, app.GreenGamma.XLim(2)];
                app.BlueGamma.XLim = [0, app.BlueGamma.XLim(2)];
                app.DICGamma.XLim = [0, app.DICGamma.XLim(2)];
                app.GFPGamma.XLim = [0, app.GFPGamma.XLim(2)];
                app.WhiteGamma.XLim = [0, app.WhiteGamma.XLim(2)];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, neuropal_app, image_data, image_neurons, image_view, image_gamma, prefs, red, green, blue, white, dic, gfp)
            
            app.parent_app = neuropal_app;
            app.image_data = image_data;
            app.repo_img = image_data;
            app.image_neurons = image_neurons;
            app.image_view = image_view;
            app.repo_gamma = image_gamma;
            app.image_gamma = image_gamma;
            app.preferences = prefs;

            app.red = str2double(red);
            app.green = str2double(green);
            app.blue = str2double(blue);
            app.white = str2double(white);
            app.dic = str2double(dic);
            app.gfp = str2double(gfp);

            app.RedGammaEditField.Value = app.image_gamma(app.red);
            app.GreenGammaEditField.Value = app.image_gamma(app.green);
            app.BlueGammaEditField.Value = app.image_gamma(app.blue);
            app.WhiteGammaEditField.Value = app.image_gamma(app.white);
            app.GFPGammaEditField.Value = app.image_gamma(app.gfp);
            app.DICGammaEditField.Value = app.image_gamma(app.dic);
            
            %{
            app.red_min.Value = min(image_data(:,:,:,app.red),[],'all');
            app.red_max.Value = max(image_data(:,:,:,app.red),[],'all');
            app.green_min.Value = min(image_data(:,:,:,app.green),[],'all');
            app.green_max.Value = max(image_data(:,:,:,app.green),[],'all');
            app.blue_min.Value = min(image_data(:,:,:,app.blue),[],'all');
            app.blue_max.Value = max(image_data(:,:,:,app.blue),[],'all');
            app.white_min.Value = min(image_data(:,:,:,app.white),[],'all');
            app.white_max.Value = max(image_data(:,:,:,app.white),[],'all');
            app.gfp_min.Value = min(image_data(:,:,:,app.gfp),[],'all');
            app.gfp_max.Value = max(image_data(:,:,:,app.gfp),[],'all');
            app.dic_min.Value = min(image_data(:,:,:,app.dic),[],'all');
            app.dic_max.Value = max(image_data(:,:,:,app.dic),[],'all');
            %}
            
            app.image_gamma(4)
            app.gamma_knob.Value = app.image_gamma(4);
            app.EditField.Value = app.image_gamma(4);

            % Configure image axes
            %app.ImageAxes.Visible = 'off';
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, app.image_view);
            
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Value changed function: REDCheckBox
        function REDCheckBoxValueChanged(app, event)
            if app.REDCheckBox.Value
                app.red_max.Value = app.repo(1,1);
                app.red_min.Value = app.repo(1,2);
                updateimage(app, app.live_view);
            else
                app.repo(1,1) = app.red_max.Value;
                app.repo(1,2) = app.red_min.Value;
                app.red_max.Value = 0;
                app.red_min.Value = 0;
                updateimage(app, app.live_view);
            end
            drawnow;
        end

        % Value changed function: BLUECheckBox
        function BLUECheckBoxValueChanged(app, event)
            if app.BLUECheckBox.Value
                app.red_max.Value = app.repo(3,1);
                app.red_min.Value = app.repo(3,2);
                updateimage(app, app.live_view);
            else
                app.repo(3,1) = app.blue_max.Value;
                app.repo(3,2) = app.blue_min.Value;
                app.red_max.Value = 0;
                app.red_min.Value = 0;
                updateimage(app, app.live_view);
            end
            drawnow;
        end

        % Value changed function: GREENCheckBox
        function GREENCheckBoxValueChanged(app, event)
            if app.GREENCheckBox.Value
                app.green_max.Value = app.repo(2,1);
                app.green_min.Value = app.repo(2,2);
                updateimage(app, app.live_view);
            else
                app.repo(2,1) = app.green_max.Value;
                app.repo(2,2) = app.green_min.Value;
                app.green_max.Value = 0;
                app.green_min.Value = 0;
                updateimage(app, app.live_view);
            end
            drawnow;
        end

        % Value changed function: red_lock
        function red_lockValueChanged(app, event)
            if app.red_lock.Value == '🔒'
                set(app.red_min, 'Enable', 'off')
                set(app.red_max, 'Enable', 'off')
                set(app.RedGammaEditField, 'Enable', 'off')
            else
                set(app.red_min, 'Enable', 'on')
                set(app.red_max, 'Enable', 'on')
                set(app.RedGammaEditField, 'Enable', 'on')
            end
        end

        % Value changed function: green_lock
        function green_lockValueChanged(app, event)
            if app.green_lock.Value == '🔒'
                set(app.green_min, 'Enable', 'off')
                set(app.green_max, 'Enable', 'off')
                set(app.GreenGammaEditField, 'Enable', 'off')
            else
                set(app.green_min, 'Enable', 'on')
                set(app.green_max, 'Enable', 'on')
                set(app.GreenGammaEditField, 'Enable', 'on')
            end
        end

        % Value changed function: blue_lock
        function blue_lockValueChanged(app, event)
            if app.blue_lock.Value == '🔒'
                set(app.blue_min, 'Enable', 'off')
                set(app.blue_max, 'Enable', 'off')
                set(app.BlueGammaEditField, 'Enable', 'off')
            else
                set(app.blue_min, 'Enable', 'on')
                set(app.blue_max, 'Enable', 'on')
                set(app.BlueGammaEditField, 'Enable', 'on')
            end
        end

        % Value changed function: white_lock
        function white_lockValueChanged(app, event)
            if app.white_lock.Value == '🔒'
                set(app.white_min, 'Enable', 'off')
                set(app.white_max, 'Enable', 'off')
                set(app.WhiteGammaEditField, 'Enable', 'off')
            else
                set(app.white_min, 'Enable', 'on')
                set(app.white_max, 'Enable', 'on')
                set(app.WhiteGammaEditField, 'Enable', 'on')
            end
        end

        % Value changed function: gfp_lock
        function gfp_lockValueChanged(app, event)
            if app.gfp_lock.Value == '🔒'
                set(app.gfp_min, 'Enable', 'off')
                set(app.gfp_max, 'Enable', 'off')
                set(app.GFPGammaEditField, 'Enable', 'off')
            else
                set(app.gfp_min, 'Enable', 'on')
                set(app.gfp_max, 'Enable', 'on')
                set(app.GFPGammaEditField, 'Enable', 'on')
            end
        end

        % Value changed function: dic_lock
        function dic_lockValueChanged(app, event)
            if app.dic_lock.Value == '🔒'
                set(app.dic_min, 'Enable', 'off')
                set(app.dic_max, 'Enable', 'off')
                set(app.DICGammaEditField, 'Enable', 'off')
            else
                set(app.dic_min, 'Enable', 'on')
                set(app.dic_max, 'Enable', 'on')
                set(app.DICGammaEditField, 'Enable', 'on')
            end
        end

        % Value changed function: gamma_knob
        function gamma_knobValueChanged(app, event)
            app.EditField.Value = app.gamma_knob.Value;
            temp = imadjust(squeeze(max(app.image_view,[],3)),[],[],app.gamma_knob.Value);
            app.image_gamma = app.repo_gamma * app.gamma_knob.Value;
            updateimage(app, temp);
        end

        % Value changed function: red_max
        function red_maxValueChanged(app, event)
            valupdate(app,'r',app.red_max.Value, app.red_min.Value)
        end

        % Value changed function: red_min
        function red_minValueChanged(app, event)
            valupdate(app,'r',app.red_max.Value, app.red_min.Value)
        end

        % Value changed function: green_max
        function green_maxValueChanged(app, event)
            valupdate(app,'g',app.green_max.Value, app.green_min.Value)
        end

        % Value changed function: green_min
        function green_minValueChanged(app, event)
            valupdate(app,'g',app.green_max.Value, app.green_min.Value)
        end

        % Value changed function: WHITECheckBox
        function WHITECheckBoxValueChanged(app, event)
            if app.WHITECheckBox.Value
                app.white_max.Value = app.repo(4,1);
                app.white_min.Value = app.repo(4,2);
                updateimage(app, app.live_view);
            else
                app.repo(4,1) = app.white_max.Value;
                app.repo(4,2) = app.white_min.Value;
                app.white_max.Value = 0;
                app.white_min.Value = 0;
                updateimage(app, app.live_view);
            end
            drawnow;
        end

        % Value changed function: GFPCheckBox
        function GFPCheckBoxValueChanged(app, event)
            if app.GFPCheckBox.Value
                app.gfp_max.Value = app.repo(5,1);
                app.gfp_min.Value = app.repo(5,2);
                updateimage(app, app.live_view);
            else
                app.repo(5,1) = app.gfp_max.Value;
                app.repo(5,2) = app.gfp_min.Value;
                app.gfp_max.Value = 0;
                app.gfp_min.Value = 0;
                updateimage(app, app.live_view);
            end
            drawnow;
        end

        % Value changed function: DICCheckBox
        function DICCheckBoxValueChanged(app, event)
            if app.DICCheckBox.Value
                app.dic_max.Value = app.repo(6,1);
                app.dic_min.Value = app.repo(6,2);
                updateimage(app, app.live_view);
            else
                app.repo(6,1) = app.dic_max.Value;
                app.repo(6,2) = app.dic_min.Value;
                app.dic_max.Value = 0;
                app.dic_min.Value = 0;
                updateimage(app, app.live_view);
            end
            drawnow;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            lockCheck = [app.red_lock.Value, app.green_lock.Value, app.blue_lock.Value, app.white_lock.Value, app.gfp_lock.Value, app.dic_lock.Value];
            
            if any(lockCheck) == '🔒'
                msgbox('Several channels are locked -- please unlock them, then try again.')
            else
                app.parent_app.UpdateFromChild(app.image_data,app.image_neurons,app.image_gamma);
                delete(app)
            end
        end

        % Button pushed function: SetGuideButton
        function SetGuideButtonPushed(app, event)
            answer = questdlg('Saving this file as guide.mat will replace the histogram guide.','Warning!','Continue','Cancel', 'Cancel');
            present_array = app.image_view;

            switch answer
                case 'Continue'
                    save('guide.mat','present_array')
                case 'Cancel'
                    return
            end
        end

        % Callback function
        function gamma_knobValueChanging(app, event)
            app.EditField.Value = event.Value;
            temp = imadjust(squeeze(max(app.image_view,[],3)),[],[],app.event.Value);
            updateimage(app, temp);
        end

        % Value changed function: white_min
        function white_minValueChanged(app, event)
            valupdate(app,'w',app.white_max.Value,app.white_min.Value)
        end

        % Value changed function: white_max
        function white_maxValueChanged(app, event)
            valupdate(app,'w',app.white_max.Value,app.white_min.Value)
        end

        % Value changed function: gfp_min
        function gfp_minValueChanged(app, event)
            valupdate(app,'gfp',app.gfp_max.Value,app.gfp_min.Value)
        end

        % Value changed function: gfp_max
        function gfp_maxValueChanged(app, event)
            valupdate(app,'gfp',app.gfp_max.Value,app.gfp_min.Value)
        end

        % Value changed function: dic_min
        function dic_minValueChanged(app, event)
            valupdate(app,'dic',app.dic_max.Value,app.dic_min.Value)
        end

        % Value changed function: dic_max
        function dic_maxValueChanged(app, event)
            valupdate(app,'dic',app.dic_max.Value,app.dic_min.Value)
        end

        % Button pushed function: RedReset
        function RedResetButtonPushed(app, event)
            app.red_max.Value = 255;
            app.red_min.Value = 0;
            app.image_data(:,:,:,app.red) = app.repo_img(:,:,:,app.red);
            updateimage(app, app.live_view);
        end

        % Button pushed function: GreenReset
        function GreenResetButtonPushed(app, event)
            app.green_max.Value = 255;
            app.green_min.Value = 0;
            app.image_data(:,:,:,app.green) = app.repo_img(:,:,:,app.green);
            updateimage(app, app.live_view);
        end

        % Button pushed function: BlueReset
        function BlueResetButtonPushed(app, event)
            app.blue_max.Value = 255;
            app.blue_min.Value = 0;
            app.image_data(:,:,:,app.blue) = app.repo_img(:,:,:,app.blue);
            updateimage(app, app.live_view);
        end

        % Button pushed function: WhiteReset
        function WhiteResetButtonPushed(app, event)
            app.white_max.Value = 255;
            app.white_min.Value = 0;
            app.image_data(:,:,:,app.white) = app.repo_img(:,:,:,app.white);
            updateimage(app, app.live_view);
        end

        % Value changed function: GuideCheckBox
        function GuideCheckBoxValueChanged(app, event)
            if app.GuideCheckBox.Value
                guide = importdata('guide.mat');
                hold(app.UIAxes,'on')
                app.red_guide = histogram(app.UIAxes, guide(:,:,1), 'FaceColor', 'red', 'FaceAlpha',0.4,'EdgeAlpha',0, 'tag','redg');
                app.green_guide = histogram(app.UIAxes, guide(:,:,2), 'FaceColor', 'green', 'FaceAlpha',0.4,'EdgeAlpha',0, 'tag','greeng');
                app.blue_guide = histogram(app.UIAxes, guide(:,:,3), 'FaceColor', 'blue', 'FaceAlpha',0.4,'EdgeAlpha',0, 'tag','blueg');
                %app.histw = histogram(app.UIAxes, app.whites, 'FaceColor', 'white' 'FaceAlpha',0.4,'EdgeAlpha',0,'tag','whiteg');
            else
                set( app.red_guide, 'Visible', 'off' )
                set( app.green_guide, 'Visible', 'off' )
                set( app.blue_guide, 'Visible', 'off' )
                updateimage(app, app.live_view);
            end
        end

        % Value changed function: blue_min
        function blue_minValueChanged(app, event)
            valupdate(app,'b',app.blue_max.Value, app.blue_min.Value)
        end

        % Value changed function: blue_max
        function blue_maxValueChanged(app, event)
            valupdate(app,'b',app.blue_max.Value, app.blue_min.Value)
        end

        % Button pushed function: GFPReset
        function GFPResetButtonPushed(app, event)
            app.gfp_max.Value = 255;
            app.gfp_min.Value = 0;
            app.image_data(:,:,:,app.gfp) = app.repo_img(:,:,:,app.gfp);
            updateimage(app, app.live_view);
        end

        % Button pushed function: DICReset
        function DICResetButtonPushed(app, event)
            app.dic_max.Value = 255;
            app.dic_min.Value = 0;
            app.image_data(:,:,:,app.dic) = app.repo_img(:,:,:,app.dic);
            updateimage(app, app.live_view);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.gamma_knob.Value = app.EditField.Value;
        end

        % Value changed function: RedGammaEditField
        function RedGammaEditFieldValueChanged(app, event)
            app.image_gamma(app.red) = app.RedGammaEditField.Value;
            updateimage(app, app.live_view);
        end

        % Value changed function: GreenGammaEditField
        function GreenGammaEditFieldValueChanged(app, event)
            app.image_gamma(app.green) = app.GreenGammaEditField.Value;
            updateimage(app, app.live_view);
        end

        % Value changed function: BlueGammaEditField
        function BlueGammaEditFieldValueChanged(app, event)
            app.image_gamma(app.blue) = app.BlueGammaEditField.Value;
            updateimage(app, app.live_view);
        end

        % Value changed function: WhiteGammaEditField
        function WhiteGammaEditFieldValueChanged(app, event)
            app.image_gamma(app.white) = app.WhiteGammaEditField.Value;
            updateimage(app, app.live_view);
        end

        % Value changed function: GFPGammaEditField
        function GFPGammaEditFieldValueChanged(app, event)
            app.image_gamma(app.gfp) = app.GFPGammaEditField.Value;
            updateimage(app, app.live_view);
        end

        % Value changed function: DICGammaEditField
        function DICGammaEditFieldValueChanged(app, event)
            app.image_gamma(app.dic) = app.DICGammaEditField.Value;
            updateimage(app, app.live_view);
        end

        % Value changed function: HidezerointensitypixelsCheckBox
        function HidezerointensitypixelsCheckBoxValueChanged2(app, event)
            updateimage(app, app.live_view);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Worm Projection')
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'Intensity')
            ylabel(app.UIAxes, 'Pixels')
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<hr>';
            app.SettingsButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.SettingsButtonGroup_2.Title = 'Settings';
            app.GuideCheckBox = uicheckbox(app.SettingsButtonGroup_2);
            app.GuideCheckBox.ValueChangedFcn = createCallbackFcn(app, @GuideCheckBoxValueChanged, true);
            app.GuideCheckBox.Tooltip = {'Overlay histogram generated from NeuroPAL ID manual examples.'};
            app.GuideCheckBox.Text = 'Guide';
            app.gamma_knob = uiknob(app.SettingsButtonGroup_2, 'continuous');
            app.gamma_knob.Limits = [0 2];
            app.gamma_knob.MajorTicks = [0 1 2];
            app.gamma_knob.MajorTickLabels = {'0', '1', '2'};
            app.gamma_knob.ValueChangedFcn = createCallbackFcn(app, @gamma_knobValueChanged, true);
            app.gamma_knob.Value = 1;
            app.GAMMALabel = uilabel(app.SettingsButtonGroup_2);
            app.GAMMALabel.Text = 'GAMMA';
            app.BestFitButton = uibutton(app.SettingsButtonGroup_2, 'push');
            app.BestFitButton.Text = 'Best Fit';
            app.SetGuideButton = uibutton(app.SettingsButtonGroup_2, 'push');
            app.SetGuideButton.ButtonPushedFcn = createCallbackFcn(app, @SetGuideButtonPushed, true);
            app.SetGuideButton.Text = 'Set Guide';
            app.EditField = uieditfield(app.SettingsButtonGroup_2, 'numeric');
            app.EditField.ValueDisplayFormat = '%.2f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.DICCheckBox = uicheckbox(app.ButtonGroup);
            app.DICCheckBox.ValueChangedFcn = createCallbackFcn(app, @DICCheckBoxValueChanged, true);
            app.DICCheckBox.Text = 'DIC';
            app.GFPCheckBox = uicheckbox(app.ButtonGroup);
            app.GFPCheckBox.ValueChangedFcn = createCallbackFcn(app, @GFPCheckBoxValueChanged, true);
            app.GFPCheckBox.Text = 'GFP';
            app.WHITECheckBox = uicheckbox(app.ButtonGroup);
            app.WHITECheckBox.ValueChangedFcn = createCallbackFcn(app, @WHITECheckBoxValueChanged, true);
            app.WHITECheckBox.Text = 'WHITE';
            app.BLUECheckBox = uicheckbox(app.ButtonGroup);
            app.BLUECheckBox.ValueChangedFcn = createCallbackFcn(app, @BLUECheckBoxValueChanged, true);
            app.BLUECheckBox.Text = 'BLUE';
            app.BLUECheckBox.Value = true;
            app.GREENCheckBox = uicheckbox(app.ButtonGroup);
            app.GREENCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREENCheckBoxValueChanged, true);
            app.GREENCheckBox.Text = 'GREEN';
            app.GREENCheckBox.Value = true;
            app.REDCheckBox = uicheckbox(app.ButtonGroup);
            app.REDCheckBox.ValueChangedFcn = createCallbackFcn(app, @REDCheckBoxValueChanged, true);
            app.REDCheckBox.Text = 'RED';
            app.REDCheckBox.Value = true;
            app.dic_min = uispinner(app.ButtonGroup);
            app.dic_min.Limits = [0 255];
            app.dic_min.ValueChangedFcn = createCallbackFcn(app, @dic_minValueChanged, true);
            app.dic_max = uispinner(app.ButtonGroup);
            app.dic_max.Limits = [0 255];
            app.dic_max.ValueChangedFcn = createCallbackFcn(app, @dic_maxValueChanged, true);
            app.dic_max.Value = 255;
            app.gfp_min = uispinner(app.ButtonGroup);
            app.gfp_min.Limits = [0 255];
            app.gfp_min.ValueChangedFcn = createCallbackFcn(app, @gfp_minValueChanged, true);
            app.gfp_max = uispinner(app.ButtonGroup);
            app.gfp_max.Limits = [0 255];
            app.gfp_max.ValueChangedFcn = createCallbackFcn(app, @gfp_maxValueChanged, true);
            app.gfp_max.Value = 255;
            app.white_min = uispinner(app.ButtonGroup);
            app.white_min.Limits = [0 255];
            app.white_min.ValueChangedFcn = createCallbackFcn(app, @white_minValueChanged, true);
            app.white_max = uispinner(app.ButtonGroup);
            app.white_max.Limits = [0 255];
            app.white_max.ValueChangedFcn = createCallbackFcn(app, @white_maxValueChanged, true);
            app.white_max.Value = 255;
            app.blue_min = uispinner(app.ButtonGroup);
            app.blue_min.Limits = [0 255];
            app.blue_min.ValueChangedFcn = createCallbackFcn(app, @blue_minValueChanged, true);
            app.blue_max = uispinner(app.ButtonGroup);
            app.blue_max.Limits = [0 255];
            app.blue_max.ValueChangedFcn = createCallbackFcn(app, @blue_maxValueChanged, true);
            app.blue_max.Value = 255;
            app.green_min = uispinner(app.ButtonGroup);
            app.green_min.Limits = [0 255];
            app.green_min.ValueChangedFcn = createCallbackFcn(app, @green_minValueChanged, true);
            app.green_max = uispinner(app.ButtonGroup);
            app.green_max.Limits = [0 255];
            app.green_max.ValueChangedFcn = createCallbackFcn(app, @green_maxValueChanged, true);
            app.green_max.Value = 255;
            app.red_min = uispinner(app.ButtonGroup);
            app.red_min.Limits = [0 255];
            app.red_min.ValueChangedFcn = createCallbackFcn(app, @red_minValueChanged, true);
            app.red_max = uispinner(app.ButtonGroup);
            app.red_max.Limits = [0 255];
            app.red_max.ValueChangedFcn = createCallbackFcn(app, @red_maxValueChanged, true);
            app.red_max.Value = 255;
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Text = '';
            app.Switch_2Label = uilabel(app.ButtonGroup);
            app.Switch_2Label.Text = '';
            app.Switch_3Label = uilabel(app.ButtonGroup);
            app.Switch_3Label.Text = '';
            app.Switch_4Label = uilabel(app.ButtonGroup);
            app.Switch_4Label.Text = '';
            app.Switch_5Label = uilabel(app.ButtonGroup);
            app.Switch_5Label.Text = '';
            app.Switch_6Label = uilabel(app.ButtonGroup);
            app.Switch_6Label.Text = '';
            app.dic_lock = uiswitch(app.ButtonGroup, 'slider');
            app.dic_lock.Items = {' ', 'lock'};
            app.dic_lock.ValueChangedFcn = createCallbackFcn(app, @dic_lockValueChanged, true);
            app.dic_lock.Value = ' ';
            app.gfp_lock = uiswitch(app.ButtonGroup, 'slider');
            app.gfp_lock.Items = {' ', 'lock'};
            app.gfp_lock.ValueChangedFcn = createCallbackFcn(app, @gfp_lockValueChanged, true);
            app.gfp_lock.Value = ' ';
            app.white_lock = uiswitch(app.ButtonGroup, 'slider');
            app.white_lock.Items = {' ', 'lock'};
            app.white_lock.ValueChangedFcn = createCallbackFcn(app, @white_lockValueChanged, true);
            app.white_lock.Value = ' ';
            app.blue_lock = uiswitch(app.ButtonGroup, 'slider');
            app.blue_lock.Items = {' ', 'lock'};
            app.blue_lock.ValueChangedFcn = createCallbackFcn(app, @blue_lockValueChanged, true);
            app.blue_lock.Value = ' ';
            app.green_lock = uiswitch(app.ButtonGroup, 'slider');
            app.green_lock.Items = {' ', 'lock'};
            app.green_lock.ValueChangedFcn = createCallbackFcn(app, @green_lockValueChanged, true);
            app.green_lock.Value = ' ';
            app.red_lock = uiswitch(app.ButtonGroup, 'slider');
            app.red_lock.Items = {' ', 'lock'};
            app.red_lock.ValueChangedFcn = createCallbackFcn(app, @red_lockValueChanged, true);
            app.red_lock.Value = ' ';
            app.DICReset = uibutton(app.ButtonGroup, 'push');
            app.DICReset.ButtonPushedFcn = createCallbackFcn(app, @DICResetButtonPushed, true);
            app.DICReset.Text = '↺';
            app.GFPReset = uibutton(app.ButtonGroup, 'push');
            app.GFPReset.ButtonPushedFcn = createCallbackFcn(app, @GFPResetButtonPushed, true);
            app.GFPReset.Text = '↺';
            app.WhiteReset = uibutton(app.ButtonGroup, 'push');
            app.WhiteReset.ButtonPushedFcn = createCallbackFcn(app, @WhiteResetButtonPushed, true);
            app.WhiteReset.Text = '↺';
            app.BlueReset = uibutton(app.ButtonGroup, 'push');
            app.BlueReset.ButtonPushedFcn = createCallbackFcn(app, @BlueResetButtonPushed, true);
            app.BlueReset.Text = '↺';
            app.GreenReset = uibutton(app.ButtonGroup, 'push');
            app.GreenReset.ButtonPushedFcn = createCallbackFcn(app, @GreenResetButtonPushed, true);
            app.GreenReset.Text = '↺';
            app.RedReset = uibutton(app.ButtonGroup, 'push');
            app.RedReset.ButtonPushedFcn = createCallbackFcn(app, @RedResetButtonPushed, true);
            app.RedReset.Text = '↺';
            app.LOCKLabel = uilabel(app.ButtonGroup);
            app.LOCKLabel.Text = 'LOCK';
            app.MINLabel = uilabel(app.ButtonGroup);
            app.MINLabel.Text = 'MIN';
            app.MAXLabel = uilabel(app.ButtonGroup);
            app.MAXLabel.Text = 'MAX';
            app.LowerDiv = uihtml(app.UIFigure);
            app.LowerDiv.HTMLSource = '<hr>';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RBGGammaTab = uitab(app.TabGroup);
            app.RBGGammaTab.Title = 'RBG Gamma';
            app.RedGamma = uiaxes(app.RBGGammaTab);
            xlabel(app.RedGamma, 'Pixel Value')
            ylabel(app.RedGamma, 'Pixel Value')
            zlabel(app.RedGamma, 'Z')
            app.RedGamma.XLim = [0 255];
            app.RedGamma.YLim = [0 255];
            app.GreenGamma = uiaxes(app.RBGGammaTab);
            zlabel(app.GreenGamma, 'Z')
            app.GreenGamma.XLim = [0 255];
            app.GreenGamma.YLim = [0 255];
            app.BlueGamma = uiaxes(app.RBGGammaTab);
            zlabel(app.BlueGamma, 'Z')
            app.BlueGamma.XLim = [0 255];
            app.BlueGamma.YLim = [0 255];
            app.RedGammaEditFieldLabel = uilabel(app.RBGGammaTab);
            app.RedGammaEditFieldLabel.Text = 'Red Gamma =';
            app.RedGammaEditField = uieditfield(app.RBGGammaTab, 'numeric');
            app.RedGammaEditField.ValueChangedFcn = createCallbackFcn(app, @RedGammaEditFieldValueChanged, true);
            app.GreenGammaEditFieldLabel = uilabel(app.RBGGammaTab);
            app.GreenGammaEditFieldLabel.Text = 'Green Gamma =';
            app.GreenGammaEditField = uieditfield(app.RBGGammaTab, 'numeric');
            app.GreenGammaEditField.ValueChangedFcn = createCallbackFcn(app, @GreenGammaEditFieldValueChanged, true);
            app.BlueGammaEditFieldLabel = uilabel(app.RBGGammaTab);
            app.BlueGammaEditFieldLabel.Text = 'Blue Gamma =';
            app.BlueGammaEditField = uieditfield(app.RBGGammaTab, 'numeric');
            app.BlueGammaEditField.ValueChangedFcn = createCallbackFcn(app, @BlueGammaEditFieldValueChanged, true);
            app.WhiteGFPDICGammaTab = uitab(app.TabGroup);
            app.WhiteGFPDICGammaTab.Title = 'White / GFP / DIC Gamma';
            app.WhiteGamma = uiaxes(app.WhiteGFPDICGammaTab);
            xlabel(app.WhiteGamma, 'Pixel Value')
            ylabel(app.WhiteGamma, 'Pixel Value')
            zlabel(app.WhiteGamma, 'Z')
            app.WhiteGamma.XLim = [0 255];
            app.WhiteGamma.YLim = [0 255];
            app.WhiteGamma.ZLim = [0 255];
            app.GFPGamma = uiaxes(app.WhiteGFPDICGammaTab);
            zlabel(app.GFPGamma, 'Z')
            app.GFPGamma.XLim = [0 255];
            app.GFPGamma.YLim = [0 255];
            app.GFPGamma.ZLim = [0 255];
            app.DICGamma = uiaxes(app.WhiteGFPDICGammaTab);
            zlabel(app.DICGamma, 'Z')
            app.DICGamma.XLim = [0 255];
            app.DICGamma.YLim = [0 255];
            app.DICGamma.ZLim = [0 255];
            app.GFPGammaEditFieldLabel = uilabel(app.WhiteGFPDICGammaTab);
            app.GFPGammaEditFieldLabel.Text = 'GFP Gamma =';
            app.GFPGammaEditField = uieditfield(app.WhiteGFPDICGammaTab, 'numeric');
            app.GFPGammaEditField.ValueChangedFcn = createCallbackFcn(app, @GFPGammaEditFieldValueChanged, true);
            app.WhiteGammaEditFieldLabel = uilabel(app.WhiteGFPDICGammaTab);
            app.WhiteGammaEditFieldLabel.Text = 'White Gamma =';
            app.WhiteGammaEditField = uieditfield(app.WhiteGFPDICGammaTab, 'numeric');
            app.WhiteGammaEditField.ValueChangedFcn = createCallbackFcn(app, @WhiteGammaEditFieldValueChanged, true);
            app.DICGammaEditFieldLabel = uilabel(app.WhiteGFPDICGammaTab);
            app.DICGammaEditFieldLabel.Text = 'DIC Gamma =';
            app.DICGammaEditField = uieditfield(app.WhiteGFPDICGammaTab, 'numeric');
            app.DICGammaEditField.ValueChangedFcn = createCallbackFcn(app, @DICGammaEditFieldValueChanged, true);
            app.HidezerointensitypixelsCheckBox = uicheckbox(app.UIFigure);
            app.HidezerointensitypixelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HidezerointensitypixelsCheckBoxValueChanged2, true);
            app.HidezerointensitypixelsCheckBox.Text = 'Hide zero intensity pixels.';
            app.HidezerointensitypixelsCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogram_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end