classdef MIRO_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        OptionsTab                    matlab.ui.container.Tab
        GridLayout12                  matlab.ui.container.GridLayout
        ResultsDisplayOptionsPanel_2  matlab.ui.container.Panel
        CheckResolutionCheckBox_2     matlab.ui.control.CheckBox
        DisplayAnimationCheckBox_2    matlab.ui.control.CheckBox
        DisplayImagesCheckBox_2       matlab.ui.control.CheckBox
        SaveOutputPanel               matlab.ui.container.Panel
        OriginalImageCheckBox         matlab.ui.control.CheckBox
        ParametersCheckBox            matlab.ui.control.CheckBox
        SystemTab                     matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        InputPanel                    matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        AxialEditField                matlab.ui.control.NumericEditField
        AxialEditFieldLabel           matlab.ui.control.Label
        LateralEditField              matlab.ui.control.NumericEditField
        LateralEditFieldLabel         matlab.ui.control.Label
        SliceIntervalEditField        matlab.ui.control.NumericEditField
        SliceIntervalLabel            matlab.ui.control.Label
        SRCheckBox                    matlab.ui.control.CheckBox
        DCheckBox                     matlab.ui.control.CheckBox
        OpticsPanel                   matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        BinningEditField              matlab.ui.control.NumericEditField
        BinningEditFieldLabel         matlab.ui.control.Label
        CameraPixelEditField          matlab.ui.control.NumericEditField
        MagnificationEditField        matlab.ui.control.NumericEditField
        CameraPixelEditFieldLabel     matlab.ui.control.Label
        MagnificationEditFieldLabel   matlab.ui.control.Label
        NAEditField                   matlab.ui.control.NumericEditField
        NAEditFieldLabel              matlab.ui.control.Label
        WavelengthEditField           matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel      matlab.ui.control.Label
        CameraPanel                   matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        ExposureTimeEditField         matlab.ui.control.NumericEditField
        ExposureTimeLabel             matlab.ui.control.Label
        DarkCurrentEditField          matlab.ui.control.NumericEditField
        DarkCurrentLabel              matlab.ui.control.Label
        VarianceEditField             matlab.ui.control.NumericEditField
        VarianceEditFieldLabel        matlab.ui.control.Label
        OffsetEditField               matlab.ui.control.NumericEditField
        OffsetEditFieldLabel          matlab.ui.control.Label
        GainEditField_2               matlab.ui.control.NumericEditField
        GainEditField_2Label          matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        GeneralTab                    matlab.ui.container.Tab
        GridLayout8                   matlab.ui.container.GridLayout
        OptionalFilterPanel           matlab.ui.container.Panel
        GridLayout11                  matlab.ui.container.GridLayout
        StrengthEditField             matlab.ui.control.NumericEditField
        StrengthEditFieldLabel        matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        MicrolocalThresholdingPanel   matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        MaxScaleEditField             matlab.ui.control.NumericEditField
        MaxScaleEditFieldLabel        matlab.ui.control.Label
        hEditField                    matlab.ui.control.NumericEditField
        hEditFieldLabel               matlab.ui.control.Label
        Switch                        matlab.ui.control.RockerSwitch
        PreprocessingPanel            matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        MinimumResolutionEditField_2  matlab.ui.control.NumericEditField
        MinimumResolutionEditField_2Label  matlab.ui.control.Label
        STDEditField_2                matlab.ui.control.NumericEditField
        STDEditField_2Label           matlab.ui.control.Label
        kEditField                    matlab.ui.control.NumericEditField
        kEditFieldLabel               matlab.ui.control.Label
        FrameEditField                matlab.ui.control.NumericEditField
        FrameEditFieldLabel           matlab.ui.control.Label
        HotPixelCorrectionCheckBox_2  matlab.ui.control.CheckBox
        SpectralFilterCheckBox        matlab.ui.control.CheckBox
        ResolutionEstimationCheckBox  matlab.ui.control.CheckBox
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Slider                        matlab.ui.control.Slider
        SaveOutputButton              matlab.ui.control.Button
        LoadInputButton               matlab.ui.control.Button
        Switch_2                      matlab.ui.control.Switch
        StartButton                   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Properties that correspond to intermediate results
    properties (Access = private)
        inputImage = [];    % input image
        outputImage = [];   % output image
        P                   % Parameters
        fileName = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            MIRO_Default_Parameters;
            app.P = Parameters;
            app.P.OptionalFilter = 3;

            app.UIAxes.Visible = 'off';
        end

        % Button pushed function: LoadInputButton
        function LoadInputButtonPushed(app, event)
            [app.inputImage, ~, app.fileName] = ReadTiff([]);
            imagesc(app.UIAxes,app.inputImage(:,:,1));
            axis(app.UIAxes,'image')
            colormap(app.UIAxes,app.ColormapEditField.Value)
            app.Switch_2.Value = 'Raw';

            if size(app.inputImage,3)>1
                app.Slider.Enable = 1;
                app.Slider.Limits = [1, size(app.inputImage,3)];
                app.Slider.MinorTicks = [];
            else
                app.Slider.Enable = 0;
                app.Slider.Value = 1;
            end

            app.StartButton.Enable = 1;
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = round(app.Slider.Value);
            imagesc(app.UIAxes,app.inputImage(:,:,value));
            colormap(app.UIAxes,app.ColormapEditField.Value)
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if value(1) == 'R'
                imagesc(app.UIAxes,app.inputImage(:,:,round(app.Slider.Value)));
                colormap(app.UIAxes,app.ColormapEditField.Value)
            else
                imagesc(app.UIAxes,app.outputImage(:,:,round(app.Slider.Value)));
                colormap(app.UIAxes,app.ColormapEditField.Value)
            end
        end

        % Value changed function: ResolutionEstimationCheckBox
        function ResolutionEstimationCheckBoxValueChanged(app, event)
            value = app.ResolutionEstimationCheckBox.Value;
            app.P.ResolutionCheckAuto = value; 
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            value = app.FrameEditField.Value;
            app.P.ResolutionCheckStartingFrame = value;
        end

        % Value changed function: MinimumResolutionEditField_2
        function MinimumResolutionEditField_2ValueChanged(app, event)
            value = app.MinimumResolutionEditField_2.Value;
            app.P.kmin = value;
        end

        % Value changed function: HotPixelCorrectionCheckBox_2
        function HotPixelCorrectionCheckBox_2ValueChanged(app, event)
            value = app.HotPixelCorrectionCheckBox_2.Value;
            if value
                app.P.HS = app.STDEditField_2.Value;
            else
                app.P.HS = 0;
            end
        end

        % Value changed function: SpectralFilterCheckBox
        function SpectralFilterCheckBoxValueChanged(app, event)
            value = app.SpectralFilterCheckBox.Value;
            app.P.SpectralFilterOn = value;
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            value = app.kEditField.Value;
            app.P.k = value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value(1) == 'H'
                app.P.Mode = 'h';
            else
                app.P.Mode = 's';
            end
        end

        % Value changed function: hEditField
        function hEditFieldValueChanged(app, event)
            value = app.hEditField.Value;
            app.P.k = value;
        end

        % Value changed function: MaxScaleEditField
        function MaxScaleEditFieldValueChanged(app, event)
            value = app.MaxScaleEditField.Value;
            app.P.max_scale_factor = value;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'None'
                    app.P.OptionalFilter = 0;
                case 'Unbiased NLM'
                    app.P.OptionalFilter = 1;
                    app.P.LambdaNLM = app.StrengthEditField.Value;
                case 'Multiscale Wiener'
                    app.P.OptionalFilter = 2;
                    app.P.Wien(9) = app.StrengthEditField.Value;
                case 'Fast Wiener'
                    app.P.OptionalFilter = 3;
                    app.P.LambdaVideo = app.StrengthEditField.Value;
            end
        end

        % Value changed function: StrengthEditField
        function StrengthEditFieldValueChanged(app, event)
            value = app.StrengthEditField.Value;
            app.P.LambdaVideo = value;
            app.P.Wien(9) = value;
            app.P.LambdaNLM =  value;
        end

        % Value changed function: GainEditField_2
        function GainEditField_2ValueChanged(app, event)
            value = app.GainEditField_2.Value;
            app.P.Gain = value;
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
            value = app.OffsetEditField.Value;
            app.P.Offset= value;
        end

        % Value changed function: VarianceEditField
        function VarianceEditFieldValueChanged(app, event)
            value = app.VarianceEditField.Value;
            app.P.Var = value;
        end

        % Value changed function: DarkCurrentEditField
        function DarkCurrentEditFieldValueChanged(app, event)
            value = app.DarkCurrentEditField.Value;
            app.P.DarkCurrent = value;
        end

        % Value changed function: ExposureTimeEditField
        function ExposureTimeEditFieldValueChanged(app, event)
            value = app.ExposureTimeEditField.Value;
            app.P.ExpTime = value;
        end

        % Value changed function: WavelengthEditField
        function WavelengthEditFieldValueChanged(app, event)
            value = app.WavelengthEditField.Value;
            app.P.Lambda = value;
        end

        % Value changed function: NAEditField
        function NAEditFieldValueChanged(app, event)
            value = app.NAEditField.Value;
            app.P.NA = value;
        end

        % Value changed function: MagnificationEditField
        function MagnificationEditFieldValueChanged(app, event)
            value = app.MagnificationEditField.Value;
            app.P.Magnification = value;
        end

        % Value changed function: CameraPixelEditField
        function CameraPixelEditFieldValueChanged(app, event)
            value = app.CameraPixelEditField.Value;
            app.P.CameraPixelSize = value;
        end

        % Value changed function: BinningEditField
        function BinningEditFieldValueChanged(app, event)
            value = app.BinningEditField.Value;
            app.P.Binning = value;
        end

        % Value changed function: SliceIntervalEditField
        function SliceIntervalEditFieldValueChanged(app, event)
            value = app.SliceIntervalEditField.Value;
            app.P.SliceInterval = value;
        end

        % Value changed function: LateralEditField
        function LateralEditFieldValueChanged(app, event)
            value = app.LateralEditField.Value;
            app.P.SR_lateral = value;
        end

        % Value changed function: AxialEditField
        function AxialEditFieldValueChanged(app, event)
            value = app.AxialEditField.Value;
            app.P.SR_axial = value;
        end

        % Value changed function: DCheckBox
        function DCheckBoxValueChanged(app, event)
            value = app.DCheckBox.Value;
            app.P.is_3D = value;
        end

        % Value changed function: DisplayImagesCheckBox_2
        function DisplayImagesCheckBox_2ValueChanged(app, event)
            value = app.DisplayImagesCheckBox_2.Value;
            app.P.DisplayImages = value;
        end

        % Value changed function: DisplayAnimationCheckBox_2
        function DisplayAnimationCheckBox_2ValueChanged(app, event)
            value = app.DisplayAnimationCheckBox_2.Value;
            app.P.DisplayAnimation = value;
        end

        % Value changed function: CheckResolutionCheckBox_2
        function CheckResolutionCheckBox_2ValueChanged(app, event)
            value = app.CheckResolutionCheckBox_2.Value;
            app.P.ResolutionCheck = value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.outputImage = MIRO(app.inputImage,'Parameters',app.P);
            app.SaveOutputButton.Enable = 1;
            app.Switch_2.Enable = 1;
            app.Switch_2.Value = 'MIRO';
            if app.Slider.Enable
                app.Slider.Value = 1;
            end
            imagesc(app.UIAxes,app.outputImage(:,:,1));
            colormap(app.UIAxes,app.ColormapEditField.Value)
        end

        % Button pushed function: SaveOutputButton
        function SaveOutputButtonPushed(app, event)
            output = app.outputImage;
            save([app.fileName(1:end-4) '_MIRO_.mat'], 'output','-v7.3');
            if app.ParametersCheckBox.Value
                Parameters = app.P;
                save([app.fileName(1:end-4) '_MIRO_Parameters.mat'], 'Parameters','-v7.3');
            end
            if app.OriginalImageCheckBox.Value
                input = app.inputImage;
                save([app.fileName(1:end-4) '.mat'], 'input','-v7.3');
            end
        end

        % Callback function: not associated with a component
        function ColormapEditFieldValueChanged(app, event)
            value = app.ColormapEditField.Value;
            colormap(app.UIAxes,value)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.P.Gain = ReadTiff([]);
            app.GainEditField_2.Value = 0;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.P.Offset = ReadTiff([]);
            app.OffsetEditField.Value = 0;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.P.Var = ReadTiff([]);
            app.VarianceEditField.Value = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {575, 575};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {354, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Parameters';
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GridLayout8 = uigridlayout(app.GeneralTab);
            app.PreprocessingPanel = uipanel(app.GridLayout8);
            app.PreprocessingPanel.Title = 'Preprocessing';
            app.GridLayout9 = uigridlayout(app.PreprocessingPanel);
            app.ResolutionEstimationCheckBox = uicheckbox(app.GridLayout9);
            app.ResolutionEstimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResolutionEstimationCheckBoxValueChanged, true);
            app.ResolutionEstimationCheckBox.Text = 'Resolution Estimation';
            app.ResolutionEstimationCheckBox.Value = true;
            app.SpectralFilterCheckBox = uicheckbox(app.GridLayout9);
            app.SpectralFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpectralFilterCheckBoxValueChanged, true);
            app.SpectralFilterCheckBox.Text = 'Spectral Filter';
            app.SpectralFilterCheckBox.Value = true;
            app.HotPixelCorrectionCheckBox_2 = uicheckbox(app.GridLayout9);
            app.HotPixelCorrectionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @HotPixelCorrectionCheckBox_2ValueChanged, true);
            app.HotPixelCorrectionCheckBox_2.Text = 'Hot Pixel Correction';
            app.FrameEditFieldLabel = uilabel(app.GridLayout9);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.GridLayout9, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Value = 1;
            app.kEditFieldLabel = uilabel(app.GridLayout9);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.GridLayout9, 'numeric');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Value = 0.25;
            app.STDEditField_2Label = uilabel(app.GridLayout9);
            app.STDEditField_2Label.Text = 'STD';
            app.STDEditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.STDEditField_2.Value = 5;
            app.MinimumResolutionEditField_2Label = uilabel(app.GridLayout9);
            app.MinimumResolutionEditField_2Label.Text = {'Minimum'; 'Resolution'};
            app.MinimumResolutionEditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.MinimumResolutionEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinimumResolutionEditField_2ValueChanged, true);
            app.MinimumResolutionEditField_2.Value = 0.1;
            app.MicrolocalThresholdingPanel = uipanel(app.GridLayout8);
            app.MicrolocalThresholdingPanel.Title = 'Microlocal Thresholding';
            app.GridLayout10 = uigridlayout(app.MicrolocalThresholdingPanel);
            app.Switch = uiswitch(app.GridLayout10, 'rocker');
            app.Switch.Items = {'Soft', 'Hard'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Soft';
            app.hEditFieldLabel = uilabel(app.GridLayout10);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.GridLayout10, 'numeric');
            app.hEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.hEditField.Value = 1;
            app.MaxScaleEditFieldLabel = uilabel(app.GridLayout10);
            app.MaxScaleEditFieldLabel.Text = 'Max. Scale';
            app.MaxScaleEditField = uieditfield(app.GridLayout10, 'numeric');
            app.MaxScaleEditField.ValueChangedFcn = createCallbackFcn(app, @MaxScaleEditFieldValueChanged, true);
            app.MaxScaleEditField.Value = 6;
            app.OptionalFilterPanel = uipanel(app.GridLayout8);
            app.OptionalFilterPanel.Title = 'Optional Filter';
            app.GridLayout11 = uigridlayout(app.OptionalFilterPanel);
            app.DropDown = uidropdown(app.GridLayout11);
            app.DropDown.Items = {'None', 'Unbiased NLM', 'Multiscale Wiener', 'Fast Wiener'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Fast Wiener';
            app.StrengthEditFieldLabel = uilabel(app.GridLayout11);
            app.StrengthEditFieldLabel.Text = 'Strength';
            app.StrengthEditField = uieditfield(app.GridLayout11, 'numeric');
            app.StrengthEditField.ValueChangedFcn = createCallbackFcn(app, @StrengthEditFieldValueChanged, true);
            app.StrengthEditField.Value = 1;
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.GridLayout4 = uigridlayout(app.SystemTab);
            app.CameraPanel = uipanel(app.GridLayout4);
            app.CameraPanel.Title = 'Camera';
            app.GridLayout7 = uigridlayout(app.CameraPanel);
            app.Button_3 = uibutton(app.GridLayout7, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '...';
            app.Button_2 = uibutton(app.GridLayout7, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '...';
            app.Button = uibutton(app.GridLayout7, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.GainEditField_2Label = uilabel(app.GridLayout7);
            app.GainEditField_2Label.Text = 'Gain';
            app.GainEditField_2 = uieditfield(app.GridLayout7, 'numeric');
            app.GainEditField_2.ValueChangedFcn = createCallbackFcn(app, @GainEditField_2ValueChanged, true);
            app.GainEditField_2.Value = 2;
            app.OffsetEditFieldLabel = uilabel(app.GridLayout7);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.GridLayout7, 'numeric');
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.OffsetEditField.Value = 100;
            app.VarianceEditFieldLabel = uilabel(app.GridLayout7);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.GridLayout7, 'numeric');
            app.VarianceEditField.ValueChangedFcn = createCallbackFcn(app, @VarianceEditFieldValueChanged, true);
            app.VarianceEditField.Value = 2.56;
            app.DarkCurrentLabel = uilabel(app.GridLayout7);
            app.DarkCurrentLabel.Text = {'Dark'; 'Current'};
            app.DarkCurrentEditField = uieditfield(app.GridLayout7, 'numeric');
            app.DarkCurrentEditField.ValueChangedFcn = createCallbackFcn(app, @DarkCurrentEditFieldValueChanged, true);
            app.DarkCurrentEditField.Value = 0.06;
            app.ExposureTimeLabel = uilabel(app.GridLayout7);
            app.ExposureTimeLabel.Text = {'Exposure'; 'Time'};
            app.ExposureTimeEditField = uieditfield(app.GridLayout7, 'numeric');
            app.ExposureTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeEditFieldValueChanged, true);
            app.ExposureTimeEditField.Value = 0.1;
            app.OpticsPanel = uipanel(app.GridLayout4);
            app.OpticsPanel.Title = 'Optics';
            app.GridLayout5 = uigridlayout(app.OpticsPanel);
            app.WavelengthEditFieldLabel = uilabel(app.GridLayout5);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.GridLayout5, 'numeric');
            app.WavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthEditFieldValueChanged, true);
            app.WavelengthEditField.Value = 600;
            app.NAEditFieldLabel = uilabel(app.GridLayout5);
            app.NAEditFieldLabel.Text = 'N.A.';
            app.NAEditField = uieditfield(app.GridLayout5, 'numeric');
            app.NAEditField.ValueChangedFcn = createCallbackFcn(app, @NAEditFieldValueChanged, true);
            app.NAEditField.Value = 1.4;
            app.MagnificationEditFieldLabel = uilabel(app.GridLayout5);
            app.MagnificationEditFieldLabel.Text = 'Magnification';
            app.CameraPixelEditFieldLabel = uilabel(app.GridLayout5);
            app.CameraPixelEditFieldLabel.Text = {'Camera'; 'Pixel'};
            app.MagnificationEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MagnificationEditField.ValueChangedFcn = createCallbackFcn(app, @MagnificationEditFieldValueChanged, true);
            app.MagnificationEditField.Value = 100;
            app.CameraPixelEditField = uieditfield(app.GridLayout5, 'numeric');
            app.CameraPixelEditField.ValueChangedFcn = createCallbackFcn(app, @CameraPixelEditFieldValueChanged, true);
            app.CameraPixelEditField.Value = 6.5;
            app.BinningEditFieldLabel = uilabel(app.GridLayout5);
            app.BinningEditFieldLabel.Text = 'Binning';
            app.BinningEditField = uieditfield(app.GridLayout5, 'numeric');
            app.BinningEditField.ValueChangedFcn = createCallbackFcn(app, @BinningEditFieldValueChanged, true);
            app.BinningEditField.Value = 1;
            app.InputPanel = uipanel(app.GridLayout4);
            app.InputPanel.Title = 'Input';
            app.GridLayout6 = uigridlayout(app.InputPanel);
            app.DCheckBox = uicheckbox(app.GridLayout6);
            app.DCheckBox.ValueChangedFcn = createCallbackFcn(app, @DCheckBoxValueChanged, true);
            app.DCheckBox.Text = '3D';
            app.SRCheckBox = uicheckbox(app.GridLayout6);
            app.SRCheckBox.Text = 'SR';
            app.SliceIntervalLabel = uilabel(app.GridLayout6);
            app.SliceIntervalLabel.Text = 'Slice Interval';
            app.SliceIntervalEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SliceIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @SliceIntervalEditFieldValueChanged, true);
            app.SliceIntervalEditField.Value = 1;
            app.LateralEditFieldLabel = uilabel(app.GridLayout6);
            app.LateralEditFieldLabel.Text = 'Lateral';
            app.LateralEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LateralEditField.ValueChangedFcn = createCallbackFcn(app, @LateralEditFieldValueChanged, true);
            app.LateralEditField.Value = 1;
            app.AxialEditFieldLabel = uilabel(app.GridLayout6);
            app.AxialEditFieldLabel.Text = 'Axial';
            app.AxialEditField = uieditfield(app.GridLayout6, 'numeric');
            app.AxialEditField.ValueChangedFcn = createCallbackFcn(app, @AxialEditFieldValueChanged, true);
            app.AxialEditField.Value = 1;
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.GridLayout12 = uigridlayout(app.OptionsTab);
            app.SaveOutputPanel = uipanel(app.GridLayout12);
            app.SaveOutputPanel.Title = 'Save Output';
            app.ParametersCheckBox = uicheckbox(app.SaveOutputPanel);
            app.ParametersCheckBox.Text = 'Parameters';
            app.OriginalImageCheckBox = uicheckbox(app.SaveOutputPanel);
            app.OriginalImageCheckBox.Text = 'Original Image';
            app.ResultsDisplayOptionsPanel_2 = uipanel(app.GridLayout12);
            app.ResultsDisplayOptionsPanel_2.Title = 'Results Display Options';
            app.DisplayImagesCheckBox_2 = uicheckbox(app.ResultsDisplayOptionsPanel_2);
            app.DisplayImagesCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DisplayImagesCheckBox_2ValueChanged, true);
            app.DisplayImagesCheckBox_2.Text = 'Display Images';
            app.DisplayAnimationCheckBox_2 = uicheckbox(app.ResultsDisplayOptionsPanel_2);
            app.DisplayAnimationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DisplayAnimationCheckBox_2ValueChanged, true);
            app.DisplayAnimationCheckBox_2.Text = 'Display Animation';
            app.CheckResolutionCheckBox_2 = uicheckbox(app.ResultsDisplayOptionsPanel_2);
            app.CheckResolutionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckResolutionCheckBox_2ValueChanged, true);
            app.CheckResolutionCheckBox_2.Text = 'Check Resolution';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.StartButton = uibutton(app.GridLayout2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.Switch_2 = uiswitch(app.GridLayout2, 'slider');
            app.Switch_2.Items = {'Raw', 'MIRO'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Enable = 'off';
            app.Switch_2.Value = 'Raw';
            app.LoadInputButton = uibutton(app.GridLayout2, 'push');
            app.LoadInputButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInputButtonPushed, true);
            app.LoadInputButton.Text = 'Load Input';
            app.SaveOutputButton = uibutton(app.GridLayout2, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
            app.SaveOutputButton.Enable = 'off';
            app.SaveOutputButton.Text = 'Save Output';
            app.Slider = uislider(app.GridLayout2);
            app.Slider.Limits = [1 100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.Slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MIRO_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end