classdef nwbsave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        TechnicalSpecificationsTab      matlab.ui.container.Tab
        GridLayout4_2                   matlab.ui.container.GridLayout
        Panel_4                         matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Button                          matlab.ui.control.Button
        F3                              matlab.ui.control.EditField
        F2                              matlab.ui.control.EditField
        F1                              matlab.ui.control.EditField
        UITable                         matlab.ui.control.Table
        OpticalChannelReferencesLabel   matlab.ui.control.Label
        DeviceDescriptionTextArea       matlab.ui.control.TextArea
        DeviceDescriptionTextAreaLabel  matlab.ui.control.Label
        DeviceManufacturerEditField     matlab.ui.control.EditField
        DeviceManufacturerEditFieldLabel  matlab.ui.control.Label
        DeviceNameEditField             matlab.ui.control.EditField
        DeviceNameEditFieldLabel        matlab.ui.control.Label
        AuthorshipTab                   matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        RelatedPublications             matlab.ui.control.EditField
        RelatedPublicationsLabel        matlab.ui.control.Label
        DatadescriptionTextArea         matlab.ui.control.TextArea
        DatadescriptionTextAreaLabel    matlab.ui.control.Label
        InstitutionalAffiliationEditField  matlab.ui.control.EditField
        InstitutionalAffiliationEditFieldLabel  matlab.ui.control.Label
        AuthorLaboratoryEditField       matlab.ui.control.EditField
        AuthorLaboratoryEditFieldLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_5                         matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        CustomFileName                  matlab.ui.control.EditField
        CustomFileNameLabel             matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Hyperlink                       matlab.ui.control.Hyperlink
        OpenDANDIaftersavingCheckBox    matlab.ui.control.CheckBox
        NWBCompatibleDataLabel          matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        NeuroPALImageNode               matlab.ui.container.TreeNode
        NeuronIDsNode                   matlab.ui.container.TreeNode
        NeuronActivityNode              matlab.ui.container.TreeNode
        NeuronSegmentationsNode         matlab.ui.container.TreeNode
        GCaMPVideoNode                  matlab.ui.container.TreeNode
        GCaMPAnnotationsNode            matlab.ui.container.TreeNode
    end

    
    properties (Access = public)
        parent_app;
        image_neurons;
        neuron_activity_by_name;
        image_file;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_neurons, neuron_activity, image_file)
            app.parent_app = parent_app;
            app.image_neurons = image_neurons;
            app.neuron_activity_by_name = neuron_activity;
            app.image_file = image_file;
            GUI_prefs = Program.GUIPreferences.instance();

            if boolean(size(app.image_neurons.neurons,2)>0)
                app.Tree.CheckedNodes(end+1) = app.NeuronIDsNode;
            end

            if ~strcmp(app.parent_app.checkFileExistence(GUI_prefs.trace_path),'False')
                app.Tree.CheckedNodes(end+1) = app.GCaMPVideoNode;
            end

            if ~isempty(app.neuron_activity_by_name)
                app.Tree.CheckedNodes(end+1) = app.NeuronActivityNode;
            end

            if ~strcmp(app.parent_app.checkFileExistence([GUI_prefs.trace_path,'annotations.h5']),'False')
                app.Tree.CheckedNodes(end+1) = app.GCaMPAnnotationsNode;
            end

            if ~strcmp(app.parent_app.checkFileExistence(GUI_prefs.seg_path),'False')
                app.Tree.CheckedNodes(end+1) = app.NeuronSegmentationsNode;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            nwb_data = [];
            GUI_prefs = Program.GUIPreferences.instance();

            nwb_data.OpenDandi = app.OpenDANDIaftersavingCheckBox.Value;
            nwb_data.author = app.AuthorLaboratoryEditField.Value;
            nwb_data.institution = app.InstitutionalAffiliationEditField.Value;
            nwb_data.description = app.DatadescriptionTextArea.Value;
            nwb_data.related_pubs = app.RelatedPublications.Value;
            nwb_data.image_path = GUI_prefs.image_dir;
            nwb_data.mat_file = app.image_file;
            nwb_data.id_bool = string(boolean(size(app.image_neurons.neurons,2)>0));
            nwb_data.csv_bool = app.parent_app.checkFileExistence([GUI_prefs.image_dir,'data.csv']);
            nwb_data.gcamp_bool = app.parent_app.checkFileExistence(GUI_prefs.trace_path);
            nwb_data.annotation_bool = app.parent_app.checkFileExistence([GUI_prefs.trace_path,'annotations.h5']);
            nwb_data.segmentation_bool = app.parent_app.checkFileExistence(GUI_prefs.seg_path);
            nwb_data.proceed = 1;

            if ~isempty(app.neuron_activity_by_name)
                nwb_data.activity_bool = py.dict(app.neuron_activity_by_name);
            else
                nwb_data.activity_bool = 'False';
            end

            if ~isempty(app.DeviceNameEditField.Value)
                nwb_data.device_name = app.DeviceNameEditField.Value;
            end

            if ~isempty(app.DeviceManufacturerEditField.Value)
                nwb_data.manufacturer = app.DeviceManufacturerEditField.Value;
            end

            if ~isempty(app.DeviceDescriptionTextArea.Value)
                nwb_data.device_description = app.DeviceDescriptionTextArea.Value;
            end

            if ~isempty(app.UITable.Data)
                nwb_data.device_channels = app.UITable.Data;
            end

            if ~isempty(app.CustomFileName.Value)
                nwb_data.filename = app.CustomFileName.Value;
            end

            app.parent_app.process_nwb_data(nwb_data);
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            nwb_data = [];
            nwb_data.proceed = 0;
            app.parent_app.process_nwb_data(nwb_data);
            delete(app)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if ~app.CheckBox.Value
                set(app.CustomFileNameLabel,'Visible','off');
                set(app.CustomFileName,'Visible','on');
            else
                set(app.CustomFileNameLabel,'Visible','on');
                set(app.CustomFileName,'Visible','off');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UITable.Data(end+1) = [app.F1,app.F2,app.F3];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Tree = uitree(app.GridLayout, 'checkbox');
            app.Tree.Enable = 'off';
            app.NeuroPALImageNode = uitreenode(app.Tree);
            app.NeuroPALImageNode.Text = 'NeuroPAL Image';
            app.NeuronIDsNode = uitreenode(app.Tree);
            app.NeuronIDsNode.Text = 'Neuron IDs';
            app.NeuronActivityNode = uitreenode(app.Tree);
            app.NeuronActivityNode.Text = 'Neuron Activity';
            app.NeuronSegmentationsNode = uitreenode(app.Tree);
            app.NeuronSegmentationsNode.Text = 'Neuron Segmentations';
            app.GCaMPVideoNode = uitreenode(app.Tree);
            app.GCaMPVideoNode.Text = 'GCaMP Video';
            app.GCaMPAnnotationsNode = uitreenode(app.Tree);
            app.GCaMPAnnotationsNode.Text = 'GCaMP Annotations';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.NeuroPALImageNode];
            app.NWBCompatibleDataLabel = uilabel(app.GridLayout);
            app.NWBCompatibleDataLabel.Text = 'NWB-Compatible Data';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.OpenDANDIaftersavingCheckBox = uicheckbox(app.GridLayout3);
            app.OpenDANDIaftersavingCheckBox.Text = 'Open DANDI after saving.';
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.URL = 'https://www.dandiarchive.org/';
            app.Hyperlink.Text = '(?)';
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_5 = uipanel(app.GridLayout2);
            app.GridLayout3_2 = uigridlayout(app.Panel_5);
            app.CheckBox = uicheckbox(app.GridLayout3_2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CustomFileNameLabel = uilabel(app.GridLayout3_2);
            app.CustomFileNameLabel.Text = ' Custom File Name.';
            app.CustomFileName = uieditfield(app.GridLayout3_2, 'text');
            app.CustomFileName.Visible = 'off';
            app.CustomFileName.Placeholder = 'wormyworm.nwb';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AuthorshipTab = uitab(app.TabGroup);
            app.AuthorshipTab.Title = 'Authorship';
            app.GridLayout4 = uigridlayout(app.AuthorshipTab);
            app.AuthorLaboratoryEditFieldLabel = uilabel(app.GridLayout4);
            app.AuthorLaboratoryEditFieldLabel.Text = 'Author / Laboratory';
            app.AuthorLaboratoryEditField = uieditfield(app.GridLayout4, 'text');
            app.AuthorLaboratoryEditField.Value = 'null';
            app.InstitutionalAffiliationEditFieldLabel = uilabel(app.GridLayout4);
            app.InstitutionalAffiliationEditFieldLabel.Text = 'Institutional Affiliation';
            app.InstitutionalAffiliationEditField = uieditfield(app.GridLayout4, 'text');
            app.InstitutionalAffiliationEditField.Value = 'null';
            app.DatadescriptionTextAreaLabel = uilabel(app.GridLayout4);
            app.DatadescriptionTextAreaLabel.Text = 'Data description';
            app.DatadescriptionTextArea = uitextarea(app.GridLayout4);
            app.DatadescriptionTextArea.Value = {'null'};
            app.RelatedPublicationsLabel = uilabel(app.GridLayout4);
            app.RelatedPublicationsLabel.Text = 'Related Publications';
            app.RelatedPublications = uieditfield(app.GridLayout4, 'text');
            app.RelatedPublications.Value = 'null';
            app.TechnicalSpecificationsTab = uitab(app.TabGroup);
            app.TechnicalSpecificationsTab.Title = 'Technical Specifications';
            app.GridLayout4_2 = uigridlayout(app.TechnicalSpecificationsTab);
            app.DeviceNameEditFieldLabel = uilabel(app.GridLayout4_2);
            app.DeviceNameEditFieldLabel.Text = 'Device Name';
            app.DeviceNameEditField = uieditfield(app.GridLayout4_2, 'text');
            app.DeviceNameEditField.Placeholder = '(e.g. "Spinning Disk Confocal")';
            app.DeviceManufacturerEditFieldLabel = uilabel(app.GridLayout4_2);
            app.DeviceManufacturerEditFieldLabel.Text = 'Device Manufacturer';
            app.DeviceManufacturerEditField = uieditfield(app.GridLayout4_2, 'text');
            app.DeviceManufacturerEditField.Placeholder = '(e.g. Nikon)';
            app.DeviceDescriptionTextAreaLabel = uilabel(app.GridLayout4_2);
            app.DeviceDescriptionTextAreaLabel.Text = 'Device Description';
            app.DeviceDescriptionTextArea = uitextarea(app.GridLayout4_2);
            app.DeviceDescriptionTextArea.Placeholder = '(e.g. Ti-e 60x Objective, 1.2 NA Nikon CFI Plan Apochromat VC 60XC WI)';
            app.Panel_4 = uipanel(app.GridLayout4_2);
            app.GridLayout6 = uigridlayout(app.Panel_4);
            app.OpticalChannelReferencesLabel = uilabel(app.GridLayout6);
            app.OpticalChannelReferencesLabel.Text = 'Optical Channel References';
            app.UITable = uitable(app.GridLayout6);
            app.UITable.ColumnName = {'Protein'; 'Filter'; 'Wavelength'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.F1 = uieditfield(app.GridLayout6, 'text');
            app.F1.Placeholder = 'mTagBFP2';
            app.F2 = uieditfield(app.GridLayout6, 'text');
            app.F2.Placeholder = 'Semrock FF01-445/45-25 Brightline';
            app.F3 = uieditfield(app.GridLayout6, 'text');
            app.F3.Placeholder = '405-445-45m';
            app.Button = uibutton(app.GridLayout6, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nwbsave(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end