classdef activity_browser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        LoadMenu              matlab.ui.container.Menu
        ImportActivityMenu    matlab.ui.container.Menu
        ExportPlotsMenu       matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        ClearSelectionButton  matlab.ui.control.Button
        Tree                  matlab.ui.container.CheckBoxTree
        ShiftclicktoselectrangeCTRLclicktomultiselectLabel  matlab.ui.control.Label
        ActivityTrace         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        parent_app; % Description
        neuron_info;
        neuron_activity_by_name;
    end
    
    methods (Access = private)

        function addNode(app, parent, name)
                if ~any(strcmp(keys(app.neuron_activity_by_name),name))
                    %outputStr = '';
                    %for i = 1:(length(name)-1)
                    %    outputStr = [outputStr, name(i), '̶']; % Pseudo strikethrough.
                    %end
                    %name = outputStr;
                    data = 0;
                else
                    data = 1;
                end

                uitreenode(parent,'text', name, 'NodeData', data);
        end

        function init_tree(app)
            for n=1:size(app.Tree.Children)
                node = app.Tree.Children(n);
                switch node.Text
                    case 'Pharynx'
                        for i=1:size(node.Children)
                            kiddo = node.Children(i);
                            switch kiddo.Text
                                case 'Anterior'
                                    neurons = app.neuron_info.getAnteriorPharynx();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Posterior'
                                    neurons = app.neuron_info.getPosteriorPharynx();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                            end
                        end
                    case 'Head'
                        for i=1:size(node.Children)
                            kiddo = node.Children(i);
                            switch kiddo.Text
                                case 'Anterior (L)'
                                    neurons = app.neuron_info.getLeftAnteriorGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Anterior (R)'
                                    neurons = app.neuron_info.getRightAnteriorGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Dorsal'
                                    neurons = app.neuron_info.getDorsalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lateral (L)'
                                    neurons = app.neuron_info.getLeftLateralGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lateral (R)'
                                    neurons = app.neuron_info.getRightLateralGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Ventral'
                                    neurons = app.neuron_info.getVentralGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Retro-Vesicular'
                                    neurons = app.neuron_info.getRetroVesicularGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                            end
                        end
                    case 'Midbody'
                        for i=1:size(node.Children)
                            kiddo = node.Children(i);
                            switch kiddo.Text
                                case 'Anterior'
                                    neurons = app.neuron_info.getAnteriorMidbody();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Central'
                                    neurons = app.neuron_info.getCentralMidbody();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Posterior'
                                    neurons = app.neuron_info.getPosteriorMidbody();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Ventral Cord'
                                    neurons = app.neuron_info.getVentralNerveCord();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                            end
                        end
                    case 'Tail'
                        for i=1:size(node.Children)
                            kiddo = node.Children(i);
                            switch kiddo.Text
                                case 'Pre-Anal'
                                    neurons = app.neuron_info.getPreAnalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Dorso-Rectal'
                                    neurons = app.neuron_info.getDorsoRectalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lumbar (L)'
                                    neurons = app.neuron_info.getLeftLumbarGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lumbar (R)'
                                    neurons = app.neuron_info.getRightLumbarGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                            end
                        end
                    case 'Male Tail'
                        for i=1:size(node.Children)
                            kiddo = node.Children(i);
                            switch kiddo.Text
                                case 'Pre-Anal (L)'
                                    neurons = app.neuron_info.getLeftPreAnalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Pre-Anal (R)'
                                    neurons = app.neuron_info.getRightPreAnalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Rays (L)'
                                    neurons = app.neuron_info.getLeftRays();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Rays (R)'
                                    neurons = app.neuron_info.getRightRays();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Dorso-Rectal'
                                    neurons = app.neuron_info.getDorsoRectalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Cloacal (L)'
                                    neurons = app.neuron_info.getLeftCloacalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Cloacal (R)'
                                    neurons = app.neuron_info.getRightCloacalGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lumbar (L)'
                                    neurons = app.neuron_info.getLeftLumbarGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                                case 'Lumbar (R)'
                                    neurons = app.neuron_info.getRightLumbarGanglion();
                                    for k=1:size(neurons)
                                        app.addNode(kiddo,neurons{k});
                                    end
                            end
                        end
                end
            end
        end

        function output_txt = myupdatefcn(app, obj, event_obj, plot_neurons)
            % output_txt = {['Neuron: ', plot_neurons]};
            output_txt = sprintf('Neuron: %s', plot_neurons); % use sprintf for formatted string
        end

        function hightlightNeuron(app, src, ~)
            if ~strcmp(app.ClearSelectionButton.Visible,'on')
                app.ActivityTrace.UserData = app.ActivityTrace.Title.String;
            end
                
            clicked_tag = get(src, 'tag');

            for n=1:size(app.ActivityTrace.Children,1)

                if ~strcmp(app.ClearSelectionButton.Visible,'on')
                    app.ActivityTrace.Children(n).UserData = app.ActivityTrace.Children(n).Color;
                end

                if ~contains(app.ActivityTrace.Children(n).Tag, clicked_tag)
                    app.ActivityTrace.Children(n).Color = [0.4, 0.4, 0.4, 0.2];
                else
                    app.ActivityTrace.Children(n).Color = [1, 0, 0, 1];
                end

            end
            
            app.ActivityTrace.Title.String = [app.ActivityTrace.Title.String,['(Selected: ', clicked_tag, ')']];

            set(app.ClearSelectionButton, 'Visible', 'on');
            set(app.ClearSelectionButton, 'Enable', 'on');
        end

        function updateNeurons(app, plot_neurons, plot_features)

            %old_xlim = app.ActivityTrace.XLim;
            %old_ylim = app.ActivityTrace.YLim;

            if ~isempty(plot_neurons)
                title = app.titleGen(plot_neurons, plot_features);
                a = plot(app.ActivityTrace, app.neuron_activity_by_name(plot_neurons{1}), 'tag', ['Neuron: ' plot_neurons{1}]);
                set(a, 'ButtonDownFcn', {@app.hightlightNeuron});
                hold(app.ActivityTrace,'on');
                
                for n = 2:size(plot_neurons,2)
                    ind_neurons = plot(app.ActivityTrace, app.neuron_activity_by_name(plot_neurons{n}), 'tag', ['Neuron: ' plot_neurons{n}]);
                    set(ind_neurons, 'ButtonDownFcn', {@app.hightlightNeuron});
                end
                
                hold(app.ActivityTrace,'off');
    
            else
                title = {'Select Neuron(s) In Ganglion Directory'};
                sample_neuron = keys(app.neuron_activity_by_name);
                plot(app.ActivityTrace, zeros(max(size(app.neuron_activity_by_name(sample_neuron{1})))));
                
            end

            app.ActivityTrace.Title.String = strrep(string(strjoin(title)), newline, '');

            %app.ActivityTrace.XLim = old_xlim;
            %app.ActivityTrace.YLim = old_ylim;
        end

        function title = titleGen(app, neurons, ganglion)

            if max(size(neurons)) > 5
                tl_ganglion = {'Pharynx', 'Head', 'Midbody', 'Tail'};
                ml_ganglion = {'Anterior', 'Posterior', 'Anterior (L)', 'Anterior (R)', 'Posterior (L)', 'Posterior (R)', 'Dorsal', 'Lateral (L)', 'Lateral (R)', 'Ventral', 'Retro-Vesicular', 'Ventral Cord', 'Pre-Anal', 'Dorso-Rectal', 'Lumbar (L)', 'Lumbar (R)'};
                tl_checks = intersect(tl_ganglion,ganglion);
                ml_checks = intersect(ml_ganglion,ganglion);

                if ~isempty(tl_checks)
                    title = join(tl_checks,', ');

                    if size(ml_checks) == 1
                        title = [ml_checks, title];
                    end

                    title = ['Various', title, 'Neurons'];

                elseif ~isempty(ml_checks)
                    title = join(ml_checks,', ');
                    title = ['Various', title, 'Neurons'];

                else
                    title = 'Various Neurons';

                end
            else
                title = join(neurons,', ');
            end
        end

        function legend = legendGen(app, ax)
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, gang_tree, neuron_act, neuron_info)

            d = uiprogressdlg(app.UIFigure,'Title','Initializing activity browser...',...
        'Indeterminate','on');
            app.parent_app = parent_app;
            app.neuron_info = neuron_info;
            app.neuron_activity_by_name = neuron_act;
            arrayfun(@(x,y) copyobj(flipud(x.Children),y),gang_tree,app.Tree)
            app.init_tree()

            close(d);

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            plot_neurons = {};
            plot_features = {};

            for i = 1:length(checkedNodes)
                node = checkedNodes(i);
                if node.NodeData == 1
                    plot_neurons{end+1} = node.Text;
                    plot_features{end+1} = node.Parent.Text;
                    plot_features{end+1} = node.Parent.Parent.Text;
                end
            end

            plot_features = unique(plot_features);
            app.updateNeurons(plot_neurons, plot_features); 
        end

        % Button pushed function: ClearSelectionButton
        function ClearSelectionButtonPushed(app, event)
            for n=1:size(app.ActivityTrace.Children)
                app.ActivityTrace.Children(n).Color = app.ActivityTrace.Children(n).UserData;
            end

            app.ActivityTrace.Title.String = app.ActivityTrace.UserData;
            set(app.ClearSelectionButton, 'Visible', 'off');
            set(app.ClearSelectionButton, 'Enable', 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.ImportActivityMenu = uimenu(app.FileMenu);
            app.ImportActivityMenu.Text = 'Import Activity';
            app.ExportPlotsMenu = uimenu(app.FileMenu);
            app.ExportPlotsMenu.Text = 'Export Plots';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ActivityTrace = uiaxes(app.GridLayout);
            title(app.ActivityTrace, 'Select Neuron(s) In Ganglion Directory')
            xlabel(app.ActivityTrace, 't')
            ylabel(app.ActivityTrace, 'GCaMP Intensity')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ShiftclicktoselectrangeCTRLclicktomultiselectLabel = uilabel(app.GridLayout2);
            app.ShiftclicktoselectrangeCTRLclicktomultiselectLabel.Text = {'Shift-click to select range,'; 'CTRL-click to multi-select.'};
            app.Tree = uitree(app.GridLayout2, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.ClearSelectionButton = uibutton(app.GridLayout2, 'push');
            app.ClearSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSelectionButtonPushed, true);
            app.ClearSelectionButton.Enable = 'off';
            app.ClearSelectionButton.Visible = 'off';
            app.ClearSelectionButton.Text = 'Clear Selection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = activity_browser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end