classdef atlas_master < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        CheckpklButton          matlab.ui.control.Button
        FlipHorizontallyButton  matlab.ui.control.Button
        FlipVerticallyButton    matlab.ui.control.Button
        FlipXYButton            matlab.ui.control.Button
        SaveAtlasButton         matlab.ui.control.Button
        VerifyAtlasButton       matlab.ui.control.Button
        ConvertAtlasButton_2    matlab.ui.control.Button
        LoadAtlasButton         matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        atlas
        backup
        control
        org_file
        save_file
    end
    
    methods (Access = private)
        
        function plotControl(app)
            x = app.control.atlas.head.model.mu(:,1);
            y = app.control.atlas.head.model.mu(:,2);
            z = app.control.atlas.head.model.mu(:,3);
            colors = app.control.atlas.head.model.mu(:, 4:6);
            scatter3(app.UIAxes2, x, y, z, 'filled', 'ColorVariable', colors)
            daspect(app.UIAxes2, [1 1 1]);
        end
        
        function plotAtlas(app)
            x = app.atlas.mu(:,1);
            y = app.atlas.mu(:,2);
            z = app.atlas.mu(:,3);
            colors = app.atlas.mu(:, 4:6);
            scatter3(app.UIAxes, x, y, z, 'filled', 'ColorVariable', colors)
            daspect(app.UIAxes, [1 1 1]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.control = load('C:\Users\Yemini Laboratory\Documents\GitHub\NeuroPAL_ID\Data\Models\atlas_xx_rgb.mat');
            app.plotControl;
        end

        % Button pushed function: LoadAtlasButton
        function LoadAtlasButtonPushed(app, event)
            % This function loads a .pkl file and converts it into a MATLAB struct.
            %
            % Args:
            %   filename (string): Path to the .pkl file
            %
            % Returns:
            %   dataStruct (struct): MATLAB struct containing the data from the .pkl file

            [name, path, ~] = uigetfile(['E:\data\for_kevin\dataset' ';*.pkl;'], 'Select pkl file.');
            app.org_file = fullfile(path, name);
            app.save_file = fullfile('C:\Users\Yemini Laboratory\Documents\GitHub\NeuroPAL_ID\Data\Models', name);
            
            fileId = py.open(app.org_file, 'rb');
            
            % Use Python's pickle.load() to deserialize the .pkl file
            data = py.pickle.load(fileId);
        
            % Convert the Python dictionary to a MATLAB struct
            app.atlas = struct(data);
        end

        % Button pushed function: ConvertAtlasButton_2
        function ConvertAtlasButton_2Pushed(app, event)
            app.backup = app.atlas;
        
            % Convert bodypart from Python strings to MATLAB cell array of chars
            app.atlas.bodypart = string(app.backup.bodypart);
        
            % Convert mu and sigma from Python numpy.ndarray to MATLAB arrays
            app.atlas.mu = double(app.backup.mu);
            app.atlas.sigma = double(app.backup.sigma);
        
            % Convert names from Python list to MATLAB cell array of chars
            app.atlas.names = cellfun(@char, cell(app.backup.names), 'UniformOutput', false);
        
            % Convert aligned from Python numpy.ndarray to MATLAB array
            app.atlas.aligned = double(app.backup.aligned);
        
            % Convert description from Python strings to MATLAB char array
            app.atlas.description = char(app.backup.description);

            app.atlas.names = permute(app.atlas.names, [2 1]);

            app.plotAtlas();

        end

        % Button pushed function: VerifyAtlasButton
        function VerifyAtlasButtonPushed(app, event)
            if size(app.control.atlas.head.N,2) == size(app.atlas.names,2) && ...
                    size(app.control.atlas.head.model.mu,2) == size(app.atlas.mu,2) && ...
                    size(app.control.atlas.head.model.sigma, 1) == size(app.atlas.sigma, 1) && ...
                    size(app.control.atlas.head.model.sigma, 2) == size(app.atlas.sigma, 2)    
                app.UIAxes.Title.String = "Verified!";
            else
                app.UIAxes.Title.String = "Could not verify!";
            end
        end

        % Button pushed function: FlipXYButton
        function FlipXYButtonPushed(app, event)
            app.backup = app.atlas;
            app.atlas.mu(:, [1, 2]) = app.backup.mu(:, [2, 1]);
            app.plotAtlas;
        end

        % Button pushed function: FlipVerticallyButton
        function FlipVerticallyButtonPushed(app, event)
            app.backup = app.atlas;
            maxZ = max(app.backup.mu(:, 3));
            app.atlas.mu(:, 3) = maxZ - app.backup.mu(:, 3) + min(app.backup.mu(:, 3));
            app.plotAtlas;
        end

        % Button pushed function: SaveAtlasButton
        function SaveAtlasButtonPushed(app, event)
            app.control.atlas.head.N = app.atlas.names;
            app.control.atlas.head.model.mu = app.atlas.mu;
            app.control.atlas.head.model.sigma = app.atlas.sigma;
            target_path = strrep(app.org_file,'pkl', 'mat');
            target_struct = app.control;

            save(target_path, '-struct', 'target_struct');
        end

        % Button pushed function: FlipHorizontallyButton
        function FlipHorizontallyButtonPushed(app, event)
            app.backup = app.atlas;
            maxX = max(app.backup.mu(:, 1));
            app.atlas.mu(:, 1) = maxX - app.backup.mu(:, 1) + min(app.backup.mu(:, 1));
            app.plotAtlas;
        end

        % Button pushed function: CheckpklButton
        function CheckpklButtonPushed(app, event)
            [name, path, ~] = uigetfile(['E:\data\for_kevin\dataset' ';*.pkl;'], 'Select pkl file.');
            app.org_file = fullfile(path, name);
            app.save_file = fullfile('C:\Users\Yemini Laboratory\Documents\GitHub\NeuroPAL_ID\Data\Models', name);
            
            fileId = py.open(app.org_file, 'rb');
            
            % Use Python's pickle.load() to deserialize the .pkl file
            data = py.pickle.load(fileId);
            data
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Control Atlas')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadAtlasButton = uibutton(app.GridLayout, 'push');
            app.LoadAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAtlasButtonPushed, true);
            app.LoadAtlasButton.Text = 'Load Atlas';
            app.ConvertAtlasButton_2 = uibutton(app.GridLayout, 'push');
            app.ConvertAtlasButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConvertAtlasButton_2Pushed, true);
            app.ConvertAtlasButton_2.Text = 'Convert Atlas';
            app.VerifyAtlasButton = uibutton(app.GridLayout, 'push');
            app.VerifyAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyAtlasButtonPushed, true);
            app.VerifyAtlasButton.Text = 'Verify Atlas';
            app.SaveAtlasButton = uibutton(app.GridLayout, 'push');
            app.SaveAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAtlasButtonPushed, true);
            app.SaveAtlasButton.Text = 'Save Atlas';
            app.FlipXYButton = uibutton(app.GridLayout, 'push');
            app.FlipXYButton.ButtonPushedFcn = createCallbackFcn(app, @FlipXYButtonPushed, true);
            app.FlipXYButton.Text = 'Flip XY';
            app.FlipVerticallyButton = uibutton(app.GridLayout, 'push');
            app.FlipVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticallyButtonPushed, true);
            app.FlipVerticallyButton.Text = 'Flip Vertically';
            app.FlipHorizontallyButton = uibutton(app.GridLayout, 'push');
            app.FlipHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontallyButtonPushed, true);
            app.FlipHorizontallyButton.Text = 'Flip Horizontally';
            app.CheckpklButton = uibutton(app.GridLayout, 'push');
            app.CheckpklButton.ButtonPushedFcn = createCallbackFcn(app, @CheckpklButtonPushed, true);
            app.CheckpklButton.Text = 'Check pkl';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = atlas_master

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end