classdef autoseg_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HoveroverlabelsforexplanationPanel_2  matlab.ui.container.Panel
        GridLayout27                   matlab.ui.container.GridLayout
        Panel_24                       matlab.ui.container.Panel
        GridLayout30                   matlab.ui.container.GridLayout
        VoxelResolutionPanel_2         matlab.ui.container.Panel
        GridLayout19_2                 matlab.ui.container.GridLayout
        ButtonGroup_5                  matlab.ui.container.ButtonGroup
        ManuallysetzxyratioButton_2    matlab.ui.control.RadioButton
        AutosetzxyratioButton_2        matlab.ui.control.RadioButton
        Panel_23                       matlab.ui.container.Panel
        GridLayout20_2                 matlab.ui.container.GridLayout
        VoxelResolutionEditField_2     matlab.ui.control.NumericEditField
        BackgroundNoisePanel_2         matlab.ui.container.Panel
        GridLayout15_2                 matlab.ui.container.GridLayout
        GridLayout16_2                 matlab.ui.container.GridLayout
        AveragenoncellintensityEditField_2  matlab.ui.control.NumericEditField
        Panel_22                       matlab.ui.container.Panel
        GridLayout18_2                 matlab.ui.container.GridLayout
        MeasureNoiseButton_3           matlab.ui.control.Button
        NoiseFilterButtonGroup_2       matlab.ui.container.ButtonGroup
        ManualMeasureButton_2          matlab.ui.control.RadioButton
        AutomaticMeasureButton_2       matlab.ui.control.RadioButton
        NeuronFilterPanel_2            matlab.ui.container.Panel
        GridLayout28                   matlab.ui.container.GridLayout
        MinimumPixelVolumeEditField    matlab.ui.control.NumericEditField
        MinimumPixelVolumeEditFieldLabel  matlab.ui.control.Label
        MaximumPixelVolumeEditField    matlab.ui.control.NumericEditField
        MaximumPixelVolumeEditFieldLabel  matlab.ui.control.Label
        GridLayout29                   matlab.ui.container.GridLayout
        ResegmentRegionButton_2        matlab.ui.control.Button
        BlackOutToolButton_5           matlab.ui.control.Button
        RESETButton_3                  matlab.ui.control.Button
        GENERATEButton_3               matlab.ui.control.Button
        PreviewImageLabel              matlab.ui.control.Label
        Panel_19                       matlab.ui.container.Panel
        GridLayout25                   matlab.ui.container.GridLayout
        MaximumProjectionPanel         matlab.ui.container.Panel
        GridLayout21                   matlab.ui.container.GridLayout
        MaxProj                        matlab.ui.control.UIAxes
        TabGroup3                      matlab.ui.container.TabGroup
        SettingsTab                    matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        HoveroverlabelsforexplanationPanel  matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        Panel_10                       matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        DisplayModePanel               matlab.ui.container.Panel
        GridLayout24                   matlab.ui.container.GridLayout
        ButtonGroup_4                  matlab.ui.container.ButtonGroup
        AutoSegButton_2                matlab.ui.control.RadioButton
        OriginalButton_2               matlab.ui.control.RadioButton
        VoxelResolutionPanel           matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        ManuallysetzxyratioButton      matlab.ui.control.RadioButton
        AutosetzxyratioButton          matlab.ui.control.RadioButton
        Panel_17                       matlab.ui.container.Panel
        GridLayout20                   matlab.ui.container.GridLayout
        VoxelResolutionEditField       matlab.ui.control.NumericEditField
        NeuronFilterPanel              matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        MinimumPixelVolumeLabel        matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        MaximumPixelVolumeLabel        matlab.ui.control.Label
        MaxSize                        matlab.ui.control.NumericEditField
        BackgroundNoisePanel           matlab.ui.container.Panel
        GridLayout15                   matlab.ui.container.GridLayout
        GridLayout16                   matlab.ui.container.GridLayout
        AveragenoncellintensityEditField  matlab.ui.control.NumericEditField
        Panel_16                       matlab.ui.container.Panel
        GridLayout18                   matlab.ui.container.GridLayout
        MeasureNoiseButton_2           matlab.ui.control.Button
        NoiseFilterButtonGroup         matlab.ui.container.ButtonGroup
        ManualMeasureButton            matlab.ui.control.RadioButton
        AutomaticMeasureButton         matlab.ui.control.RadioButton
        Panel_9                        matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        ResegmentRegionButton          matlab.ui.control.Button
        ColorChannelsPanel             matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        GutAutofluorescenceCheckBox    matlab.ui.control.CheckBox
        LysosomeAutofluorescenceCheckBox  matlab.ui.control.CheckBox
        GFPCheckBox                    matlab.ui.control.CheckBox
        WhiteCheckBox                  matlab.ui.control.CheckBox
        BlueCheckBox                   matlab.ui.control.CheckBox
        GreenCheckBox                  matlab.ui.control.CheckBox
        RedCheckBox                    matlab.ui.control.CheckBox
        BlackOutToolButton_4           matlab.ui.control.Button
        AdjHistogramButton_2           matlab.ui.control.Button
        GENERATEButton_2               matlab.ui.control.Button
        RESETButton_2                  matlab.ui.control.Button
        zSlicesTab                     matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        Label_2                        matlab.ui.control.Label
        SelectAllButton                matlab.ui.control.Button
        zSlicesPanel                   matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        zSlicesListBox                 matlab.ui.control.ListBox
        ResultsTab                     matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        Panel_13                       matlab.ui.container.Panel
        GridLayout23                   matlab.ui.container.GridLayout
        OpenSegmentationFolderButton   matlab.ui.control.Button
        OpenDataFolderButton           matlab.ui.control.Button
        SuccessfullyIDd75neuronsLabel  matlab.ui.control.Label
        SuccessfullysegmentedneuronsLabel  matlab.ui.control.Label
        IdCount                        matlab.ui.control.NumericEditField
        SegCount                       matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        SaveDetectedNeuronsButton      matlab.ui.control.Button
        ExporttoCSVButton              matlab.ui.control.Button
        CreditTab_2                    matlab.ui.container.Tab
        GridLayout12                   matlab.ui.container.GridLayout
        Panel_14                       matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        CiteassuchLabel_2              matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        LaboratoryPageButton_2         matlab.ui.control.Button
        GithubRepositoryButton_2       matlab.ui.control.Button
        OriginalPaperButton_2          matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Panel_20                       matlab.ui.container.Panel
        GridLayout26                   matlab.ui.container.GridLayout
        Slider                         matlab.ui.control.Slider
        ImageAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        live_view; %
        parent_app; % Reference to main Neuropal window.
        image_data; % Passed from main
        image_neurons;
        repo_img;
        repo_view;
        image_gamma; % Passed from main
        image_view; % Passed from main
        gamma_RGBW_DIC_GFP_index;
        avg_nci;
        z_siz;
        image_raw;
        image_array;
        image_scale;
        auto_neurons;
        red; % Color indices.
        green;
        blue;
        white;
        gfp;
        dic;
        path_sep;
        full_path;
        data_path;
        seg_path;
        model_path;
        unet_path;
        weights_path;
    end

    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function UpdateFromChild(app, image_data, image_neurons,image_gamma)
            app.image_data = image_data;
            app.image_gamma = image_gamma;
            app.DrawImageData(round(app.Slider.Value))
        end

        function volume = solveVolume(app, x, y, z, r)
            % volume = sphereVolume(x, y, z, r)
            % x, y, z = microns/pixel for (x,y,z)
            
            % Convert to X-Y plan to pixels/microns.
            x_p = 1/x;
            y_p = 1/y;
            xy_p = (x_p + y_p) / 2;
            r_xy_p  = r * xy_p;
            
            % Compute the central circular volume.
            volume = pi * r_xy_p^2;
            
            % Compute non-central volume integral.
            %
            % Notes:
            % The Z-axis is low-res so work directly in microns then convert to pixels.
            % Assume symmetry so compute volume for one side, double it for both sides.
            slice = z;

            while slice < r
                slice_r = sqrt(r^2 - slice^2);
                volume = 2 * (pi * (slice_r * xy_p)^2) + volume;
                slice = slice + z;
            end
        end

        function CalcNCI(app)

            channel_list = struct('CheckBox',{app.RedCheckBox, app.GreenCheckBox, app.BlueCheckBox, app.WhiteCheckBox, app.GFPCheckBox},'Index',{1,2,3,4,5});
            chan_array = [];
            for i = 1:numel(channel_list)
                if channel_list(i).CheckBox.Value == 1
                    chan_array = [chan_array, channel_list(i).Index];
                end
            end

            % Read in the image
            img = app.image_data(:,:,:,chan_array);
            
            % Convert the image to a 1D array of intensities
            intensities = double(img(:));
            
            % Calculate the 90th percentile of intensities
            p90 = prctile(intensities, 90);

            app.AveragenoncellintensityEditField.Value = p90;
        end

        %% Draw the annotated image (image volume & neuron markers).
        function DrawImageData(app, slice)

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % Determine the color channel indices.
            red = app.red;
            green = app.green;
            blue = app.blue;
            white = app.white;
            dic = app.dic;
            gfp = app.gfp;

            % Determine the channel=color assignments for displaying.
            color_indices = [red, green, blue];

            % Draw the 3 color channels.
            app.image_view = app.image_data(:,:,slice,color_indices);
            max_proj = app.image_data(:,:,:,color_indices);

            % Remove unchecked color channels.
            if ~app.RedCheckBox.Value % Red
                app.image_view(:,:,slice,1) = 0;
                max_proj(:,:,:,1) = 0;
            end
            if ~app.GreenCheckBox.Value % Green
                app.image_view(:,:,slice,2) = 0;
                max_proj(:,:,:,2) = 0;
            end
            if ~app.BlueCheckBox.Value % Blue
                app.image_view(:,:,slice,3) = 0;
                max_proj(:,:,:,3) = 0;
            end

            % Add in the white channel.
            if app.WhiteCheckBox.Value % White

                % Compute the white channel.
                wchannel = app.image_data(:,:,slice,white);
                max_wchannel = app.image_data(:,:,:,white);

                % Adjust the gamma.
                W_i = app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index
                if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1
                    wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                    max_wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                end

                % Add the white channel.
                app.image_view = app.image_view + repmat(wchannel, [1,1,1,3]);
                max_proj = max_proj + repmat(max_wchannel, [1,1,1,3]);
            end

            % Add in the GFP channel.
            if app.GFPCheckBox.Value % GFP

                % Compute the GFP channel.
                gfp_color = Program.GUIPreferences.instance().GFP_color;
                gfp_channel = app.image_data(:,:,slice,gfp);
                max_gfp_channel = app.image_data(:,:,:,gfp);

                % Adjust the gamma.
                GFP_i = app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index
                if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1
                    gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));
                    max_gfp_channel = imadjustn(max_gfp_channel,[],[], app.image_gamma(GFP_i));
                end

                % Add the GFP channel.
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                gfp_channel(:,:,:,~gfp_color) = 0;
                app.image_view = app.image_view + gfp_channel;

                max_gfp_channel = repmat(max_gfp_channel, [1,1,1,3]);
                max_gfp_channel(:,:,:,~gfp_color) = 0;
                max_proj = max_proj + max_gfp_channel;
            end

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            app.image_view = uint16(double(intmax('uint16')) * ...
                double(app.image_view)/double(max(app.image_view(:))));
            for c = 1:size(app.image_view, 4)
                if app.image_gamma(c) ~= 1
                    app.image_view(:,:,:,c) = ...
                        imadjustn(squeeze(app.image_view(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end
            app.image_view = double(app.image_view)/double(max(app.image_view(:)));

            max_proj = uint16(double(intmax('uint16')) * ...
                double(max_proj)/double(max(max_proj(:))));
            for c = 1:size(max_proj, 4)
                if app.image_gamma(c) ~= 1
                    max_proj(:,:,:,c) = ...
                        imadjustn(squeeze(max_proj(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end
            max_proj = double(max_proj)/double(max(max_proj(:)));

            image(app.ImageAxes, squeeze(max(app.image_view,[],3)));
            image(app.MaxProj, squeeze(max(max_proj,[],3)));

            switch app.NoiseFilterButtonGroup.SelectedObject.Text
                case 'Automatic Measure'
                    app.CalcNCI()
            end

            if strcmp(app.ButtonGroup_4.SelectedObject.Text, 'Auto Seg')
                file_list = dir(fullfile(app.seg_path, 'auto_t1_z*.tif'));
                num_files = numel(file_list);
            
                % Extract z values from filenames and sort file_list
                z_values = zeros(1, num_files);
                for idx = 1:num_files
                    z_values(idx) = str2double(regexp(file_list(idx).name, '(?<=auto_t1_z)\d+', 'match'));
                end
                [~, sorted_indices] = sort(z_values);
                file_list = file_list(sorted_indices);
            
                sample_img = imread(fullfile(app.seg_path, file_list(1).name));
                [x, y] = size(sample_img);
                img_array = zeros(x, y, num_files);
                for idx = 1:num_files
                    img_array(:,:,idx) = imread(fullfile(app.seg_path, file_list(idx).name));
                end
                image(app.MaxProj, squeeze(max(img_array,[],3)));
                image(app.ImageAxes, squeeze(max(img_array(:,:,app.Slider.Value),[],3)));
            end


            app.DrawNeurons()

        end

        function DrawNeurons(app)
            if ~isempty(app.auto_neurons)
                for i = 1:size(app.auto_neurons, 1)
                    images.roi.Point(app.MaxProj,'Position',[app.auto_neurons(i,2) app.auto_neurons(i,1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',sprintf('Neuron #%.0f',i), 'LabelVisible','hover');
                    if abs(round(app.auto_neurons(i, 3), 0) - round(app.Slider.Value)) <= 1
                        images.roi.Point(app.ImageAxes,'Position',[app.auto_neurons(i,2) app.auto_neurons(i,1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',sprintf('Neuron #%.0f',i), 'LabelVisible','hover');
                    end
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_data, image_neurons, image_view, image_gamma, image_res, red, green, blue, white, dic, gfp, fullpath, gamma_RGBW_DIC_GFP_index)
            app.parent_app = parent_app;
            %app.image_data = Methods.Preprocess.zscore_frame(image_data);
            app.image_data = image_data;
            app.repo_img = app.image_data;
            app.image_neurons = image_neurons;
            %app.image_view = Methods.Preprocess.zscore_frame(image_view);
            app.image_view = image_view;
            app.repo_view = app.image_view;
            app.image_gamma = image_gamma;
            app.image_scale = image_res(3) / image_res(1);
            app.gamma_RGBW_DIC_GFP_index = gamma_RGBW_DIC_GFP_index;

            if ispc
                app.path_sep = '\';
            elseif ismac
                app.path_sep = '/';
            end

            app.full_path = fullfile(fullpath,'seg_data',app.path_sep);
            app.data_path = fullfile(app.full_path,'data',app.path_sep);
            app.seg_path = fullfile(app.full_path,'auto_vol1',app.path_sep);
            app.model_path = fullfile(app.full_path,'models',app.path_sep);
            app.unet_path = fullfile(app.full_path,'models',app.path_sep,'unet');
            app.weights_path = fullfile(app.full_path,'models',app.path_sep,'unet-weights');

            GUI_prefs = Program.GUIPreferences.instance();
            GUI_prefs.seg_path = app.full_path;
            GUI_prefs.save();

            app.red = str2double(red);
            app.green = str2double(green);
            app.blue = str2double(blue);
            app.white = str2double(white);
            app.dic = str2double(dic);
            app.gfp = str2double(gfp);

            %axis(app.ImageAxes, 'normal');

            app.z_siz = size(app.image_data, 3);
            app.CalcNCI()

            % Resize the figure to fit most of the screen size.
            screen_size = get(groot, 'ScreenSize');
            screen_size = screen_size(3:4);
            screen_margin = floor(screen_size .* [0.07,0.05]);
            figure_size(1:2) = screen_margin / 2;
            figure_size(3) = screen_size(1) - screen_margin(1);
            figure_size(4) = screen_size(2) - 2*screen_margin(2);
            drawnow; % need to draw before resizings
            app.UIFigure.Position = figure_size;
  
            app.VoxelResolutionEditField.Value = app.image_scale;
            app.EditField.Value = app.solveVolume(image_res(1), image_res(2), image_res(3), 1.8);
            app.MaxSize.Value = app.solveVolume(image_res(1), image_res(2), image_res(3), 2.5);

            C = {};
            ticks = [];
            min_ticks = [];

            for n = 1:size(app.image_data, 3)
                C{n} = ['Slice ' num2str(n)];

                if n == 1 || n == size(app.image_data,3) || ismember(n-2, ticks)
                    ticks = [ticks, n];
                else
                    min_ticks = [min_ticks, n];
                end

            end

            app.zSlicesListBox.Items = C;
            app.zSlicesListBox.ItemsData = 1:size(app.image_data,3);
            app.zSlicesListBox.Value = app.zSlicesListBox.ItemsData;

            app.Slider.MajorTicks = ticks;
            app.Slider.MinorTicks = min_ticks;
            app.Slider.Value = round(size(app.image_data, 3)/2);
            app.Slider.Limits = [1, size(app.image_data, 3)];
            
            app.MaxProj.XLim = [1, size(app.image_data, 2)];
            app.MaxProj.YLim = [1, size(app.image_data, 1)];

            app.ImageAxes.XLim = [1, size(app.image_data, 2)];
            app.ImageAxes.YLim = [1, size(app.image_data, 1)];

            app.DrawImageData(app.Slider.Value)

        end

        % Selection changed function: NoiseFilterButtonGroup
        function NoiseFilterNoiseFilterButtonGroupSelectionChanged(app, event)
            switch app.NoiseFilterButtonGroup.SelectedObject.Text
                case 'Automatic Measure'
                    set(app.AveragenoncellintensityEditField,'Editable','off')
                    app.CalcNCI()
                case 'Manual Measure'
                    set(app.AveragenoncellintensityEditField,'Editable','on')
            end
        end

        % Button pushed function: OriginalPaperButton_2
        function OriginalPaperButtonPushed(app, event)
            web('https://elifesciences.org/articles/59187')
        end

        % Button pushed function: GithubRepositoryButton_2
        function GithubRepositoryButtonPushed(app, event)
            web('https://github.com/WenChentao/3DeeCellTracker')
        end

        % Button pushed function: LaboratoryPageButton_2
        function LaboratoryPageButtonPushed(app, event)
            web('https://en.kokimura-lab.org/')
        end

        % Button pushed function: AdjHistogramButton_2
        function AdjHistogramButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Loading histogram...',...
        'Indeterminate','on');
            histogram_window(app, app.image_data, app.image_neurons, app.image_view, app.image_gamma, Program.GUIPreferences.instance(), app.parent_app.RDropDown.Value, app.parent_app.GDropDown.Value, app.parent_app.BDropDown.Value, app.parent_app.WDropDown.Value, app.parent_app.DICDropDown.Value, app.parent_app.GFPDropDown.Value);
            close(d)
        end

        % Button pushed function: GENERATEButton_2
        function GENERATEButtonPushed(app, event)
            check = uiconfirm(app.UIFigure,'Depending on your computer, this may take a while. Are you sure you want to proceed?','Warning!','Options',{'Yes','No'},'DefaultOption','Yes');

            if strcmp(check,'Yes') & (size(app.zSlicesListBox.Value) < size(app.image_data,3))
                slice_warn = uiconfirm(app.UIFigure,'Not all slices are selected. Proceed?','Warning!','Options',{'Yes','Yes, with all slices selected.','No'},'DefaultOption','No');

                switch slice_warn
                    case 'Yes, with all slices selected.'
                        image(app.ImageAxes, squeeze(max(app.image_view,[],3)));
                        app.z_siz = size(app.image_data,3);
                        app.zSlicesListBox.Value = 1:size(app.image_data,3);

                        for i = 1:size(app.auto_neurons, 1)
                            images.roi.Point(app.ImageAxes,'Position',[app.auto_neurons(i,2) app.auto_neurons(i,1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',sprintf('Neuron #%.0f',i), 'LabelVisible','hover');
                        end

                    case 'No'
                        check = 'No';
                end

            end
            
            switch check
                case 'Yes'

                    d = uiprogressdlg(app.UIFigure,'Title','Processing data...',...
        'Indeterminate','on');

                    dirs = {app.data_path, app.seg_path, app.model_path, app.unet_path, app.weights_path, fullfile(app.full_path,'track_information'), fullfile(app.full_path,'track_results_SingleMode'), fullfile(app.full_path,'anim')};

                    if isdeployed
                        dep_parent = 'lib';
                    else
                        dep_parent = 'External_Dependencies';
                    end

                    for n = 1:length(dirs)
                        directory = char(dirs(n));
                        if ~exist(directory, 'dir')
                            [status, msg, msgid] = mkdir(directory);
                            if status ~= 1
                                fprintf('Failed to create directory %s: %s\n', directory, msg);
                            end
                        end

                        if strcmp(directory, app.model_path)
                            % Move 'unet3_pretrained.h5' to the newly created directory
                            source_file = fullfile(dep_parent,app.path_sep,'models',app.path_sep,'unet3_pretrained.h5');
                            destination_file = fullfile(directory, 'unet3_pretrained.h5');
                            copyfile(source_file, destination_file);

                        elseif strcmp(directory, app.unet_path)
                            % Move all files in the 'unet' folder to the newly created directory
                            source_dir = fullfile(dep_parent,app.path_sep,'models',app.path_sep,'unet');
                            destination_dir = directory;
                            copyfile(fullfile(source_dir, '*'), destination_dir);

                        elseif strcmp(directory, app.weights_path) 
                            % Move all files in the 'unet_weights' folder to the newly created directory
                            source_dir = fullfile(dep_parent,app.path_sep,'models',app.path_sep,'unet_weights');
                            destination_dir = directory;
                            copyfile(fullfile(source_dir, '*'), destination_dir);

                        end
                    end


                    files = dir(fullfile(app.data_path,'*.*'));  % Get a list of all files in the folder
                    for i = 1:length(files)  % Loop through each file
                        file = fullfile(app.data_path,files(i).name);  % Get the full path to the file
                        if ~files(i).isdir  % Only delete files, not directories
                            delete(file);  % Delete the file
                        end
                    end
                    
                    channel_list = struct('CheckBox',{app.RedCheckBox, app.GreenCheckBox, app.BlueCheckBox, app.WhiteCheckBox, app.GFPCheckBox},'Index',{1,2,3,4,5});
                    chan_array = [];
                    for i = 1:numel(channel_list)
                        if channel_list(i).CheckBox.Value == 1
                            chan_array = [chan_array, channel_list(i).Index];
                        end
                    end
                    
                    % Initialize variables to store bounding box coordinates
                    boundingBox = struct('minRow', Inf, 'maxRow', -Inf, 'minCol', Inf, 'maxCol', -Inf);
                    
                    % Find the bounding box excluding areas with all pixels equal to zero
                    for n = min(app.zSlicesListBox.Value):max(app.zSlicesListBox.Value)
                        subdata = app.image_data(:,:,n,chan_array);
                        subdata = squeeze(subdata);
                        subdata = max(subdata,[],3); % Take max value of RGB channels as intensity
                        % Update bounding box coordinates
                        [row, col] = find(subdata > 0);
                        boundingBox.minRow = min(boundingBox.minRow, min(row));
                        boundingBox.maxRow = max(boundingBox.maxRow, max(row));
                        boundingBox.minCol = min(boundingBox.minCol, min(col));
                        boundingBox.maxCol = max(boundingBox.maxCol, max(col));
                    end

                    % Save slices as TIF files within the bounding box
                    for n = min(app.zSlicesListBox.Value):max(app.zSlicesListBox.Value)
                        name = join([app.data_path, 'npal_','worm_','c1_',int2str(n-min(app.zSlicesListBox.Value)), '.tif']);
                        subdata = app.image_data(boundingBox.minRow:boundingBox.maxRow, boundingBox.minCol:boundingBox.maxCol, n, chan_array);
                        subdata = squeeze(subdata);
                        subdata = max(subdata,[],3); % Take max value of RGB channels as intensity
                        subdata = uint8(subdata*1.2); % Convert to 8-bit integer
                        imwrite(subdata, name, 'tif', 'WriteMode', 'overwrite');
                    end

                    close(d)

                    d = uiprogressdlg(app.UIFigure,'Title','Segmenting slices...',...
                                                'Indeterminate','on');
                    
                    if isdeployed
                        if ispc % Check if running on Windows
                            system([pwd,'\lib\bin\windows\npal_seg.exe ',app.full_path,sprintf(' %f %f %f %f', app.EditField.Value, app.VoxelResolutionEditField.Value, app.AveragenoncellintensityEditField.Value, app.MaxSize.Value)]);
                        elseif ismac % Check if running on macOS
                            system([pwd,'/lib/bin/macos/npal_seg" ',app.full_path,sprintf('  %f %f %f %f', app.EditField.Value, app.VoxelResolutionEditField.Value, app.AveragenoncellintensityEditField.Value, app.MaxSize.Value)]);
                        else % Otherwise
                            disp('Operating system not supported')
                        end
                    else
                        [pwd,sprintf('/External_Dependencies/3dct/CellTracker/python/npal_seg.py %s %f %f %f %f', app.full_path, app.EditField.Value, app.VoxelResolutionEditField.Value, app.AveragenoncellintensityEditField.Value, app.MaxSize.Value)]
                        pyrunfile([pwd,sprintf('/External_Dependencies/3dct/CellTracker/python/npal_seg.py %s %f %f %f %f', app.full_path, app.EditField.Value, app.VoxelResolutionEditField.Value, app.AveragenoncellintensityEditField.Value, app.MaxSize.Value)])
                    end

                    close(d)

                    d = uiprogressdlg(app.UIFigure,'Title','Loading neurons...',...
                            'Indeterminate','on');

                    % Load the neuron information from the CSV file
                    neuron_info = readmatrix(join([app.full_path, 'neuron_info.csv']));
                    
                    % Initialize empty array to store the triplets of numbers
                    if ~isempty(app.auto_neurons)
                        preserve_neurons = uiconfirm(app.UIFigure,'Do you want to keep previously detected neurons?','Warning!','Options',{'Yes','No'},'DefaultOption','No');
                        switch preserve_neurons
                            case 'Yes'
                                app.auto_neurons = app.auto_neurons;
                            case 'No'
                                app.auto_neurons = zeros(size(neuron_info, 1), 3);
                        end
                    else
                        app.auto_neurons = zeros(size(neuron_info, 1), 3);
                    end

                    % Check if bounding box is defined
                    boundingBoxDefined = boundingBox.minRow ~= Inf && boundingBox.maxRow ~= -Inf && boundingBox.minCol ~= Inf && boundingBox.maxCol ~= -Inf;
                    
                    % Loop through each row and extract the coordinates
                    for i = 1:size(neuron_info, 1)
                        currentRow = neuron_info(i,:);
                        
                        % Pad coordinates if bounding box is defined
                        if boundingBoxDefined
                            currentRow(1) = currentRow(1) + boundingBox.minRow - 1;
                            currentRow(2) = currentRow(2) + boundingBox.minCol - 1;
                        end
                        
                        app.auto_neurons(i,:) = currentRow;
                        coord_str = sprintf('(%.0f, %.0f, %.0f)', currentRow(1), currentRow(2), currentRow(3));
                        app.UITable.Data{i,1} = i;
                        app.UITable.Data{i,2} = '';
                        app.UITable.Data{i,3} = '';
                        app.UITable.Data{i,4} = coord_str;
                    end

                    
                    % Update the segmentation count in the app
                    app.SegCount.Value = size(neuron_info, 1);

                    %set(app.AutoSegButton,'Enable',"On")

                    close(d)

                    app.image_data = app.repo_img;
                    app.DrawImageData(app.Slider.Value);

                    set(app.AutoSegButton_2,'Enable','On');

                    seg_open = uiconfirm(app.UIFigure,'Segmentation finished. Open results folder?','NeuroPAL','Options',{'Yes','No'},'DefaultOption','No');

                    switch seg_open
                        case 'Yes'
                            if ispc
                                winopen(app.seg_path);
                            elseif ismac
                                cmd = ['open ', app.seg_path];
                                system(cmd);
                            end
                    end

                case 'No'
                    return
            end
        end

        % Button pushed function: RESETButton_2
        function RESETButtonPushed(app, event)
            app.image_data = app.repo_img;
            app.image_view = app.repo_view;
            app.UITable.Data = {};
            app.auto_neurons = [];
            app.SegCount.Value = 0;
            app.RedCheckBox.Value = 1;
            app.GreenCheckBox.Value = 1;
            app.BlueCheckBox.Value = 1;
            app.WhiteCheckBox.Value = 0;
            app.GFPCheckBox.Value = 0;
            app.LysosomeAutofluorescenceCheckBox.Value = 0;
            app.GutAutofluorescenceCheckBox.Value = 0;
            app.Slider.Value = round(size(app.image_data, 3)/2);
            app.DrawImageData(round(app.Slider.Value));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Clicked callback: zSlicesListBox
        function zSliceszSlicesListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.z_siz = max(app.zSlicesListBox.Value) - min(app.zSlicesListBox.Value);
        end

        % Value changed function: zSlicesListBox
        function zSliceszSlicesListBoxValueChanged(app, event)
            z_slice = max(app.zSlicesListBox.Value);
        end

        % Selection changed function: ButtonGroup_4
        function DisplayButtonGroupSelectionChanged(app, event)
        app.DrawImageData(app.Slider.Value);
        end

        % Button pushed function: ExporttoCSVButton
        function ExporttoCSVButtonPushed(app, event)
            file_info = [app.data_path,'*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Save neuron table.');
            writetable(app.UITable.Data,join([path,name]))
        end

        % Button pushed function: SaveDetectedNeuronsButton
        function SaveDetectedNeuronsButtonPushed(app, event)
            check = uiconfirm(app.UIFigure,'This will add all of the auto-detected neurons into the main volume. Are you sure?','Confirmation','Options',{'Yes','No'},'DefaultOption','No');

            switch check
                case 'Yes'
                    d = uiprogressdlg(app.UIFigure,'Title','Saving neurons...',...
                            'Indeterminate','on');
                    app.parent_app.ImpNeurons(app.auto_neurons);
                    close(d);
                    delete(app)
                case 'No'
                    return
            end
        end

        % Value changed function: RedCheckBox
        function RedCheckBoxValueChanged(app, event)
            if app.RedCheckBox.Value == 1
                app.image_data(:,:,:,app.red) = app.repo_img(:,:,:,app.red);
                app.DrawImageData(round(app.Slider.Value))
            else
                app.image_data(:,:,:,app.red) = zeros(size(app.repo_img(:,:,:,app.red)));
                app.DrawImageData(round(app.Slider.Value))
            end
        end

        % Value changed function: GreenCheckBox
        function GreenCheckBoxValueChanged(app, event)
            if app.GreenCheckBox.Value == 1
                app.image_data(:,:,:,app.green) = app.repo_img(:,:,:,app.green);
                app.DrawImageData(round(app.Slider.Value))
            else
                app.image_data(:,:,:,app.green) = zeros(size(app.repo_img(:,:,:,app.green)));
                app.DrawImageData(round(app.Slider.Value))
            end
        end

        % Value changed function: BlueCheckBox
        function BlueCheckBoxValueChanged(app, event)
            if app.BlueCheckBox.Value == 1
                app.image_data(:,:,:,app.blue) = app.repo_img(:,:,:,app.blue);
                app.DrawImageData(round(app.Slider.Value))
            else
                app.image_data(:,:,:,app.blue) = zeros(size(app.repo_img(:,:,:,app.blue)));
                app.DrawImageData(round(app.Slider.Value))
            end
        end

        % Value changed function: WhiteCheckBox
        function WhiteCheckBoxValueChanged(app, event)
            if app.WhiteCheckBox.Value == 1
                app.image_data(:,:,:,app.white) = app.repo_img(:,:,:,app.white);
                app.DrawImageData(round(app.Slider.Value))
            else
                app.image_data(:,:,:,app.white) = zeros(size(app.repo_img(:,:,:,app.white)));
                app.DrawImageData(round(app.Slider.Value))
            end
        end

        % Value changed function: GFPCheckBox
        function GFPCheckBoxValueChanged(app, event)
            if app.GFPCheckBox.Value == 1
                app.image_data(:,:,:,app.gfp) = app.repo_img(:,:,:,app.gfp);
                app.DrawImageData(round(app.Slider.Value))
            else
                app.image_data(:,:,:,app.gfp) = zeros(size(app.repo_img(:,:,:,app.gfp)));
                app.DrawImageData(round(app.Slider.Value))
            end
        end

        % Value changed function: Slider
        function SliderValueChanged2(app, event)
            app.Slider.Value = round(app.Slider.Value);
            delete(findobj(app.ImageAxes,'Type','images.roi.Point'))
            app.DrawImageData(app.Slider.Value);
        end

        % Button pushed function: BlackOutToolButton_4
        function BlackOutToolButton_3Pushed(app, event)
            uiwait(msgbox('Click and drag your cursor on the image to select the region to ignore during segmentation.','Instructions'))
            
            roi = drawfreehand(app.ImageAxes,'Color','black','StripeColor','m');
            mask = createMask(roi,app.image_view);
            
            mask = ~mask;
            mask = double(mask);
            
            replacement_value = app.AveragenoncellintensityEditField.Value;
            
            % Define the size of the border to consider for noise calculation
            border_size = 5;
            
            % Create a mask for the border region
            border_mask = ones(size(mask));
            border_mask(border_size+1:end-border_size, border_size+1:end-border_size) = 0;
            
            % Calculate the standard deviation of the pixel intensities in the border region
            border_pixel_std = zeros(1, 3);
            for i = 1:3 % Iterate over color channels
                channel_data = app.image_view(:,:,i);
                border_pixel_std(i) = std(channel_data(logical(border_mask)));
            end
            
            for n = 1:size(app.image_data,3)
                color_indices = [1, 2, 3];
                for idx = color_indices
                    % Generate random noise based on the standard deviation of the border region
                    noise = normrnd(0, border_pixel_std(idx), size(app.image_data(:,:,n,idx)));
            
                    % Reduce the noise amplitude by 10%
                    noise = noise * 0.5;
            
                    % Apply a Gaussian filter to soften the noise by 10%
                    gaussian_filter = fspecial('gaussian', [5 5], 0.7);
                    noise = imfilter(noise, gaussian_filter, 'replicate');
            
                    % Apply the adjusted noise to the ROI
                    app.image_data(:,:,n,idx) = app.image_data(:,:,n,idx).*mask + (1-mask).*(replacement_value*0.7 + noise);
                end
            end
            
            app.DrawImageData(app.Slider.Value)

        end

        % Button pushed function: MeasureNoiseButton_2
        function MeasureNoiseButtonPushed(app, event)
            uiwait(msgbox('Click and drag your cursor on the image to select a region and calculate its average intensity.','Instructions'))
                        
            roi = drawfreehand(app.ImageAxes,'Color','black','StripeColor','m');
            mask = createMask(roi,app.image_view);
            
            channel_list = struct('CheckBox',{app.RedCheckBox, app.GreenCheckBox, app.BlueCheckBox, app.WhiteCheckBox, app.GFPCheckBox},'Index',{1,2,3,4,5});
            chan_array = [];
            slice_avg = [];
            
            for i = 1:numel(channel_list)
                if channel_list(i).CheckBox.Value == 1
                    chan_array = [chan_array channel_list(i).Index];
                end
            end
            
            % Get the pixel values of the selected channels within the ROI for the slice matching the value of app.Slider.Value
            subdata = app.image_data(:,:,round(app.Slider.Value),chan_array);
            subdata = mean(subdata, 4);
            slice_avg = [slice_avg mean(subdata(mask))*100];
            
            % Get the average intensity of pixels within the ROI for the selected slice
            app.AveragenoncellintensityEditField.Value = mean(slice_avg);
            delete(roi)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

            % What did the user press?
            key = event.Key;
            switch key

                case '0' % Toggle the ID atlas.
                    app.ColorAtlasCheckBox.Value = ~app.ColorAtlasCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '1' % Toggle the red channel.
                    app.RedCheckBox.Value = ~app.RedCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '2' % Toggle the green channel.
                    app.GreenCheckBox.Value = ~app.GreenCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '3' % Toggle the blue channel.
                    app.BlueCheckBox.Value = ~app.BlueCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '4' % Toggle the white channel.
                    app.WhiteCheckBox.Value = ~app.WhiteCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '5' % Toggle the DIC channel.
                    app.DICCheckBox.Value = ~app.DICCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '6' % Toggle the GFP channel.
                    app.GFPCheckBox.Value = ~app.GFPCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case 'leftarrow' % Go up in the z stack.
                    if app.Slider.Value > 1
                        zEvent.Value = app.Slider.Value - 1;
                        app.Slider.Value = round(app.Slider.Value - 1);
                        app.SliderValueChanged2(zEvent);
                    end
                    %disp(key);

                case 'rightarrow' % Go down in the z stack.
                    if app.Slider.Value < app.Slider.Limits(2)
                        zEvent.Value = app.Slider.Value + 1;
                        app.Slider.Value = round(app.Slider.Value + 1);
                        app.SliderValueChanged2(zEvent);
                    end
                    %disp(key);

                case 'uparrow' % Zoom in.
                    zoom(app.ImageAxes, 1 + 0.2);

                case 'downarrow' % Zoom out.
                    zoom(app.ImageAxes, 1 - 0.2);

                case {'h','H'} % Toggle half zoom.
                    % Reset x-axis limits.
                    app.ImageAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> lower half.
                    ylims = app.ImageAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/2
                        app.ImageAxes.YLim = [ceil(size(app.image_data, 1)/2), ...
                            size(app.image_data, 1)];

                        % Lower -> upper half.
                    else
                        app.ImageAxes.YLim = [1, floor(size(app.image_data, 1)/2)];
                    end

                case {'j','J'} % Toggle third zoom.
                    % Reset x-axis limits.
                    app.ImageAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> middle third.
                    ylims = app.ImageAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/3
                        app.ImageAxes.YLim = [ceil(size(app.image_data, 1)/3), ...
                            floor(size(app.image_data, 1)*2/3)];

                        % Middle -> lower third.
                    elseif ylims(1) < size(app.image_data, 1)*2/3
                        app.ImageAxes.YLim = [ceil(size(app.image_data, 1)*2/3), ...
                            size(app.image_data, 1)];

                        % Lower -> upper third.
                    else
                        app.ImageAxes.YLim = [1, floor(size(app.image_data, 1)/3)];
                    end

                case {'k','K'} % Toggle quarter zoom.
                    % Reset x-axis limits.
                    app.ImageAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> upper-middle quarter.
                    ylims = app.ImageAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/4
                        app.ImageAxes.YLim = [ceil(size(app.image_data, 1)/4), ...
                            floor(size(app.image_data, 1)*2/4)];

                        % Upper-middle -> lower-middle quarter.
                    elseif ylims(1) < size(app.image_data, 1)*2/4
                        app.ImageAxes.YLim = [ceil(size(app.image_data, 1)*2/4), ...
                            floor(size(app.image_data, 1)*3/4)];

                        % Lower-middle -> lower quarter.
                    elseif ylims(1) < size(app.image_data, 1)*3/4
                        app.ImageAxes.YLim = [ceil(size(app.image_data, 1)*3/4), ...
                            size(app.image_data, 1)];

                        % Lower -> upper quarter.
                    else
                        app.ImageAxes.YLim = [1, floor(size(app.image_data, 1)/4)];
                    end

                case {'p','P'} % Toggle image panning.

                    % Toggle image panning.
                    if app.is_panning
                        pan(app.ImageAxes,'off');
                        pause(0.5);
                        RestorePointer(app);
                        app.is_panning = false;
                    else
                        pan(app.ImageAxes,'on');
                        pause(0.5);
                        app.is_panning = true;
                    end

                case {'r','R'} % Reset the figure to center.
                    app.ImageAxes.XLim = [1, size(app.image_data, 2)];
                    app.ImageAxes.YLim = [1, size(app.image_data, 1)];
                    %app.ImageAxes.XLim = [1, size(app.image_data, 2)];
                    %app.ImageAxes.YLim = [1, size(app.image_data, 1)];
                    %app.ImageAxes.XAxisLocation = 'bottom';
                    %app.ImageAxes.YAxisLocation = 'left';

                    %case {'x','X'} % Switch mouse to crosshairs.
                    %zoom(app.ImageAxes,'on');
                    %pause(0.2);
                    %zoom(app.ImageAxes,'off');

                otherwise
                    % Do nothing.
            end

        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.zSlicesListBox.Value = app.zSlicesListBox.ItemsData;
        end

        % Button pushed function: ResegmentRegionButton
        function ResegmentRegionButtonPushed(app, event)
            roi = drawfreehand(app.ImageAxes,'Color','black','StripeColor','m');
            mask = createMask(roi,app.image_view);

            neuron_mask = ~mask;
            neuron_mask = double(mask);
            mask = double(mask);

            for i = flip(1:size(app.auto_neurons, 1))
                if neuron_mask(round(app.auto_neurons(i,1)),round(app.auto_neurons(i,2))) == 1
                    app.auto_neurons(i,:) = [];
                end
            end
        
            for n = 1:size(app.image_data,3)
                color_indices = [1, 2, 3];
                app.image_data(:,:,:,color_indices) = double(app.image_data(:,:,:,color_indices)).*mask;
            end
    
            app.GENERATEButtonPushed(matlab.ui.eventdata.ButtonPushedData);

        end

        % Button pushed function: OpenDataFolderButton
        function OpenDataFolderButtonPushed(app, event)
            if ispc
                winopen(app.data_path);
            elseif ismac
                cmd = ['open ', app.data_path];
                system(cmd);
            end            
        end

        % Button pushed function: OpenSegmentationFolderButton
        function OpenSegmentationFolderButtonPushed(app, event)
            if ispc
                winopen(app.seg_path);
            elseif ismac
                cmd = ['open ', app.seg_path];
                system(cmd);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Auto-Segmentation v1.0';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Panel_20 = uipanel(app.UIFigure);
            app.GridLayout26 = uigridlayout(app.Panel_20);
            app.ImageAxes = uiaxes(app.GridLayout26);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.Slider = uislider(app.GridLayout26);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged2, true);
            app.Slider.MinorTicks = [];
            app.Panel_19 = uipanel(app.UIFigure);
            app.GridLayout25 = uigridlayout(app.Panel_19);
            app.TabGroup3 = uitabgroup(app.GridLayout25);
            app.SettingsTab = uitab(app.TabGroup3);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout14 = uigridlayout(app.SettingsTab);
            app.HoveroverlabelsforexplanationPanel = uipanel(app.GridLayout14);
            app.HoveroverlabelsforexplanationPanel.Title = '(Hover over labels for explanation.)';
            app.GridLayout = uigridlayout(app.HoveroverlabelsforexplanationPanel);
            app.Panel_9 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_9);
            app.RESETButton_2 = uibutton(app.GridLayout2, 'push');
            app.RESETButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton_2.Text = 'RESET';
            app.GENERATEButton_2 = uibutton(app.GridLayout2, 'push');
            app.GENERATEButton_2.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton_2.Text = 'GENERATE';
            app.AdjHistogramButton_2 = uibutton(app.GridLayout2, 'push');
            app.AdjHistogramButton_2.ButtonPushedFcn = createCallbackFcn(app, @AdjHistogramButtonPushed, true);
            app.AdjHistogramButton_2.Text = 'Adj. Histogram';
            app.BlackOutToolButton_4 = uibutton(app.GridLayout2, 'push');
            app.BlackOutToolButton_4.ButtonPushedFcn = createCallbackFcn(app, @BlackOutToolButton_3Pushed, true);
            app.BlackOutToolButton_4.Text = 'Black Out Tool';
            app.ColorChannelsPanel = uipanel(app.GridLayout2);
            app.ColorChannelsPanel.Title = 'Color Channels';
            app.GridLayout9 = uigridlayout(app.ColorChannelsPanel);
            app.RedCheckBox = uicheckbox(app.GridLayout9);
            app.RedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedCheckBoxValueChanged, true);
            app.RedCheckBox.Text = 'Red';
            app.RedCheckBox.Value = true;
            app.GreenCheckBox = uicheckbox(app.GridLayout9);
            app.GreenCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreenCheckBoxValueChanged, true);
            app.GreenCheckBox.Text = 'Green';
            app.GreenCheckBox.Value = true;
            app.BlueCheckBox = uicheckbox(app.GridLayout9);
            app.BlueCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlueCheckBoxValueChanged, true);
            app.BlueCheckBox.Text = 'Blue';
            app.BlueCheckBox.Value = true;
            app.WhiteCheckBox = uicheckbox(app.GridLayout9);
            app.WhiteCheckBox.ValueChangedFcn = createCallbackFcn(app, @WhiteCheckBoxValueChanged, true);
            app.WhiteCheckBox.Text = 'White';
            app.GFPCheckBox = uicheckbox(app.GridLayout9);
            app.GFPCheckBox.ValueChangedFcn = createCallbackFcn(app, @GFPCheckBoxValueChanged, true);
            app.GFPCheckBox.Text = 'GFP';
            app.LysosomeAutofluorescenceCheckBox = uicheckbox(app.GridLayout9);
            app.LysosomeAutofluorescenceCheckBox.Enable = 'off';
            app.LysosomeAutofluorescenceCheckBox.Text = 'Lysosome Autofluorescence';
            app.GutAutofluorescenceCheckBox = uicheckbox(app.GridLayout9);
            app.GutAutofluorescenceCheckBox.Enable = 'off';
            app.GutAutofluorescenceCheckBox.Text = 'Gut Autofluorescence';
            app.ResegmentRegionButton = uibutton(app.GridLayout2, 'push');
            app.ResegmentRegionButton.ButtonPushedFcn = createCallbackFcn(app, @ResegmentRegionButtonPushed, true);
            app.ResegmentRegionButton.Text = 'Re-segment Region';
            app.Panel_10 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_10);
            app.BackgroundNoisePanel = uipanel(app.GridLayout3);
            app.BackgroundNoisePanel.Title = 'Background Noise';
            app.GridLayout15 = uigridlayout(app.BackgroundNoisePanel);
            app.NoiseFilterButtonGroup = uibuttongroup(app.GridLayout15);
            app.NoiseFilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoiseFilterNoiseFilterButtonGroupSelectionChanged, true);
            app.AutomaticMeasureButton = uiradiobutton(app.NoiseFilterButtonGroup);
            app.AutomaticMeasureButton.Tooltip = {'Uses 95th percentile of dimmest pixels.'};
            app.AutomaticMeasureButton.Text = 'Automatic Measure';
            app.AutomaticMeasureButton.Value = true;
            app.ManualMeasureButton = uiradiobutton(app.NoiseFilterButtonGroup);
            app.ManualMeasureButton.Tooltip = {'Manually define background intensity.'};
            app.ManualMeasureButton.Text = 'Manual Measure';
            app.Panel_16 = uipanel(app.GridLayout15);
            app.GridLayout18 = uigridlayout(app.Panel_16);
            app.MeasureNoiseButton_2 = uibutton(app.GridLayout18, 'push');
            app.MeasureNoiseButton_2.ButtonPushedFcn = createCallbackFcn(app, @MeasureNoiseButtonPushed, true);
            app.MeasureNoiseButton_2.Text = 'Measure Noise';
            app.GridLayout16 = uigridlayout(app.GridLayout15);
            app.AveragenoncellintensityEditField = uieditfield(app.GridLayout16, 'numeric');
            app.AveragenoncellintensityEditField.Editable = 'off';
            app.NeuronFilterPanel = uipanel(app.GridLayout3);
            app.NeuronFilterPanel.Title = 'Neuron Filter';
            app.GridLayout17 = uigridlayout(app.NeuronFilterPanel);
            app.MaxSize = uieditfield(app.GridLayout17, 'numeric');
            app.MaxSize.Tooltip = {'The maximum expected volume of neurons in your image, in pixels. '};
            app.MaximumPixelVolumeLabel = uilabel(app.GridLayout17);
            app.MaximumPixelVolumeLabel.Tooltip = {'The maximum expected volume of neurons in your image, in pixels. '};
            app.MaximumPixelVolumeLabel.Text = 'Maximum Pixel Volume';
            app.EditField = uieditfield(app.GridLayout17, 'numeric');
            app.EditField.Tooltip = {'The minimum expected volume of neurons in your image, in pixels. '};
            app.MinimumPixelVolumeLabel = uilabel(app.GridLayout17);
            app.MinimumPixelVolumeLabel.Tooltip = {'The minimum expected volume of neurons in your image, in pixels. '};
            app.MinimumPixelVolumeLabel.Text = 'Minimum Pixel Volume';
            app.VoxelResolutionPanel = uipanel(app.GridLayout3);
            app.VoxelResolutionPanel.Title = 'Voxel Resolution';
            app.GridLayout19 = uigridlayout(app.VoxelResolutionPanel);
            app.Panel_17 = uipanel(app.GridLayout19);
            app.GridLayout20 = uigridlayout(app.Panel_17);
            app.VoxelResolutionEditField = uieditfield(app.GridLayout20, 'numeric');
            app.VoxelResolutionEditField.Editable = 'off';
            app.ButtonGroup_3 = uibuttongroup(app.GridLayout19);
            app.AutosetzxyratioButton = uiradiobutton(app.ButtonGroup_3);
            app.AutosetzxyratioButton.Tooltip = {'Automatically calculate z-xy ratio.'};
            app.AutosetzxyratioButton.Text = 'Auto-set z-xy ratio';
            app.AutosetzxyratioButton.Value = true;
            app.ManuallysetzxyratioButton = uiradiobutton(app.ButtonGroup_3);
            app.ManuallysetzxyratioButton.Tooltip = {'Manually define z-xy ratio.'};
            app.ManuallysetzxyratioButton.Text = 'Manually set z-xy ratio';
            app.DisplayModePanel = uipanel(app.GridLayout3);
            app.DisplayModePanel.Title = 'Display Mode';
            app.GridLayout24 = uigridlayout(app.DisplayModePanel);
            app.ButtonGroup_4 = uibuttongroup(app.GridLayout24);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.OriginalButton_2 = uiradiobutton(app.ButtonGroup_4);
            app.OriginalButton_2.Text = 'Original';
            app.OriginalButton_2.Value = true;
            app.AutoSegButton_2 = uiradiobutton(app.ButtonGroup_4);
            app.AutoSegButton_2.Enable = 'off';
            app.AutoSegButton_2.Text = 'Auto Seg';
            app.zSlicesTab = uitab(app.TabGroup3);
            app.zSlicesTab.Title = 'z-Slices';
            app.GridLayout7 = uigridlayout(app.zSlicesTab);
            app.zSlicesPanel = uipanel(app.GridLayout7);
            app.zSlicesPanel.Title = 'z-Slices';
            app.GridLayout10 = uigridlayout(app.zSlicesPanel);
            app.zSlicesListBox = uilistbox(app.GridLayout10);
            app.zSlicesListBox.Multiselect = 'on';
            app.zSlicesListBox.ValueChangedFcn = createCallbackFcn(app, @zSliceszSlicesListBoxValueChanged, true);
            app.zSlicesListBox.ClickedFcn = createCallbackFcn(app, @zSliceszSlicesListBoxClicked, true);
            app.zSlicesListBox.Value = {'Item 1'};
            app.SelectAllButton = uibutton(app.GridLayout7, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.Label_2 = uilabel(app.GridLayout7);
            app.Label_2.Text = {'Only selected slices will be segmented.'; 'Hold SHIFT to select a range. Hold CTRL to multiple-select individual slices.'};
            app.ResultsTab = uitab(app.TabGroup3);
            app.ResultsTab.Title = 'Results';
            app.GridLayout11 = uigridlayout(app.ResultsTab);
            app.ExporttoCSVButton = uibutton(app.GridLayout11, 'push');
            app.ExporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoCSVButtonPushed, true);
            app.ExporttoCSVButton.Text = 'Export to CSV';
            app.SaveDetectedNeuronsButton = uibutton(app.GridLayout11, 'push');
            app.SaveDetectedNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDetectedNeuronsButtonPushed, true);
            app.SaveDetectedNeuronsButton.Text = 'Save Detected Neurons';
            app.UITable = uitable(app.GridLayout11);
            app.UITable.ColumnName = {'Neuron'; 'Suspected ID'; 'Confidence (%)'; 'Coords'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true false];
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.UITable.Multiselect = 'off';
            app.Panel_13 = uipanel(app.GridLayout11);
            app.GridLayout23 = uigridlayout(app.Panel_13);
            app.SegCount = uieditfield(app.GridLayout23, 'numeric');
            app.SegCount.Editable = 'off';
            app.IdCount = uieditfield(app.GridLayout23, 'numeric');
            app.IdCount.Editable = 'off';
            app.SuccessfullysegmentedneuronsLabel = uilabel(app.GridLayout23);
            app.SuccessfullysegmentedneuronsLabel.Text = 'Successfully segmented neurons:';
            app.SuccessfullyIDd75neuronsLabel = uilabel(app.GridLayout23);
            app.SuccessfullyIDd75neuronsLabel.Text = 'Successfully ID''d (75%+) neurons:';
            app.OpenDataFolderButton = uibutton(app.GridLayout23, 'push');
            app.OpenDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataFolderButtonPushed, true);
            app.OpenDataFolderButton.Text = 'Open Data Folder';
            app.OpenSegmentationFolderButton = uibutton(app.GridLayout23, 'push');
            app.OpenSegmentationFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSegmentationFolderButtonPushed, true);
            app.OpenSegmentationFolderButton.Text = 'Open Segmentation Folder';
            app.CreditTab_2 = uitab(app.TabGroup3);
            app.CreditTab_2.Title = 'Credit';
            app.GridLayout12 = uigridlayout(app.CreditTab_2);
            app.Label = uilabel(app.GridLayout12);
            app.Label.Text = 'Segmentation relies on a modified version of 3DeeCellTracker, a deep-learning based pipeline for tracking cells in 3D time lapse images of deforming/moving organs developed by Chentao Wen, Takuya Miura, Venkatakaushik Voleti, Kazushi Yamaguchi, Motosuke Tsutsumi, Kei Yamamoto, Kohei Otomo, Yukako Fujie, Takayuki Teramoto, Takeshi Ishihara, Kazuhiro Aoki, Tomomi Nemoto, Elizabeth MC Hillman, and Koutarou D Kimura.';
            app.OriginalPaperButton_2 = uibutton(app.GridLayout12, 'push');
            app.OriginalPaperButton_2.ButtonPushedFcn = createCallbackFcn(app, @OriginalPaperButtonPushed, true);
            app.OriginalPaperButton_2.Text = 'Original Paper';
            app.GithubRepositoryButton_2 = uibutton(app.GridLayout12, 'push');
            app.GithubRepositoryButton_2.ButtonPushedFcn = createCallbackFcn(app, @GithubRepositoryButtonPushed, true);
            app.GithubRepositoryButton_2.Text = 'Github Repository';
            app.LaboratoryPageButton_2 = uibutton(app.GridLayout12, 'push');
            app.LaboratoryPageButton_2.ButtonPushedFcn = createCallbackFcn(app, @LaboratoryPageButtonPushed, true);
            app.LaboratoryPageButton_2.Text = 'Laboratory Page';
            app.Panel_14 = uipanel(app.GridLayout12);
            app.GridLayout13 = uigridlayout(app.Panel_14);
            app.TextArea_2 = uitextarea(app.GridLayout13);
            app.TextArea_2.Value = {'@article{'; 'author = {Wen, Chentao and Miura, Takuya and Voleti, Venkatakaushik and Yamaguchi, Kazushi and Tsutsumi, Motosuke and Yamamoto, Kei and Otomo, Kohei and Fujie, Yukako and Teramoto, Takayuki and Ishihara, Takeshi and Aoki, Kazuhiro and Nemoto, Tomomi and Hillman, Elizabeth MC and Kimura, Koutarou D},'; 'doi = {10.7554/eLife.59187},'; 'journal = {eLife},'; 'month = {mar},'; 'title = {{3DeeCellTracker, a deep learning-based pipeline for segmenting and tracking cells in 3D time lapse images}},'; 'volume = {10},'; 'year = {2021}'; '}'};
            app.CiteassuchLabel_2 = uilabel(app.GridLayout13);
            app.CiteassuchLabel_2.Text = 'Cite as such:';
            app.MaximumProjectionPanel = uipanel(app.GridLayout25);
            app.MaximumProjectionPanel.Title = 'Maximum Projection';
            app.GridLayout21 = uigridlayout(app.MaximumProjectionPanel);
            app.MaxProj = uiaxes(app.GridLayout21);
            app.MaxProj.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MaxProj.XTickLabel = '';
            app.MaxProj.YTickLabel = '';
            app.PreviewImageLabel = uilabel(app.UIFigure);
            app.PreviewImageLabel.Text = 'Preview Image';
            app.HoveroverlabelsforexplanationPanel_2 = uipanel(app.UIFigure);
            app.HoveroverlabelsforexplanationPanel_2.Title = '(Hover over labels for explanation.)';
            app.GridLayout27 = uigridlayout(app.HoveroverlabelsforexplanationPanel_2);
            app.GridLayout29 = uigridlayout(app.GridLayout27);
            app.GENERATEButton_3 = uibutton(app.GridLayout29, 'push');
            app.GENERATEButton_3.Text = 'GENERATE';
            app.RESETButton_3 = uibutton(app.GridLayout29, 'push');
            app.RESETButton_3.Text = 'RESET';
            app.BlackOutToolButton_5 = uibutton(app.GridLayout29, 'push');
            app.BlackOutToolButton_5.Text = 'Black Out Tool';
            app.ResegmentRegionButton_2 = uibutton(app.GridLayout29, 'push');
            app.ResegmentRegionButton_2.Text = 'Re-segment Region';
            app.Panel_24 = uipanel(app.GridLayout27);
            app.GridLayout30 = uigridlayout(app.Panel_24);
            app.NeuronFilterPanel_2 = uipanel(app.GridLayout30);
            app.NeuronFilterPanel_2.Title = 'Neuron Filter';
            app.GridLayout28 = uigridlayout(app.NeuronFilterPanel_2);
            app.MaximumPixelVolumeEditFieldLabel = uilabel(app.GridLayout28);
            app.MaximumPixelVolumeEditFieldLabel.Text = 'Maximum Pixel Volume';
            app.MaximumPixelVolumeEditField = uieditfield(app.GridLayout28, 'numeric');
            app.MinimumPixelVolumeEditFieldLabel = uilabel(app.GridLayout28);
            app.MinimumPixelVolumeEditFieldLabel.Text = 'Minimum Pixel Volume';
            app.MinimumPixelVolumeEditField = uieditfield(app.GridLayout28, 'numeric');
            app.BackgroundNoisePanel_2 = uipanel(app.GridLayout30);
            app.BackgroundNoisePanel_2.Title = 'Background Noise';
            app.GridLayout15_2 = uigridlayout(app.BackgroundNoisePanel_2);
            app.NoiseFilterButtonGroup_2 = uibuttongroup(app.GridLayout15_2);
            app.AutomaticMeasureButton_2 = uiradiobutton(app.NoiseFilterButtonGroup_2);
            app.AutomaticMeasureButton_2.Tooltip = {'Uses 95th percentile of dimmest pixels.'};
            app.AutomaticMeasureButton_2.Text = 'Automatic Measure';
            app.AutomaticMeasureButton_2.Value = true;
            app.ManualMeasureButton_2 = uiradiobutton(app.NoiseFilterButtonGroup_2);
            app.ManualMeasureButton_2.Tooltip = {'Manually define background intensity.'};
            app.ManualMeasureButton_2.Text = 'Manual Measure';
            app.Panel_22 = uipanel(app.GridLayout15_2);
            app.GridLayout18_2 = uigridlayout(app.Panel_22);
            app.MeasureNoiseButton_3 = uibutton(app.GridLayout18_2, 'push');
            app.MeasureNoiseButton_3.Text = 'Measure Noise';
            app.GridLayout16_2 = uigridlayout(app.GridLayout15_2);
            app.AveragenoncellintensityEditField_2 = uieditfield(app.GridLayout16_2, 'numeric');
            app.AveragenoncellintensityEditField_2.Editable = 'off';
            app.VoxelResolutionPanel_2 = uipanel(app.GridLayout30);
            app.VoxelResolutionPanel_2.Title = 'Voxel Resolution';
            app.GridLayout19_2 = uigridlayout(app.VoxelResolutionPanel_2);
            app.Panel_23 = uipanel(app.GridLayout19_2);
            app.GridLayout20_2 = uigridlayout(app.Panel_23);
            app.VoxelResolutionEditField_2 = uieditfield(app.GridLayout20_2, 'numeric');
            app.VoxelResolutionEditField_2.Editable = 'off';
            app.ButtonGroup_5 = uibuttongroup(app.GridLayout19_2);
            app.AutosetzxyratioButton_2 = uiradiobutton(app.ButtonGroup_5);
            app.AutosetzxyratioButton_2.Tooltip = {'Automatically calculate z-xy ratio.'};
            app.AutosetzxyratioButton_2.Text = 'Auto-set z-xy ratio';
            app.AutosetzxyratioButton_2.Value = true;
            app.ManuallysetzxyratioButton_2 = uiradiobutton(app.ButtonGroup_5);
            app.ManuallysetzxyratioButton_2.Tooltip = {'Manually define z-xy ratio.'};
            app.ManuallysetzxyratioButton_2.Text = 'Manually set z-xy ratio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autoseg_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end