classdef channel_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_4                        matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        Slider                         matlab.ui.control.Slider
        MaximumProjectionPanel         matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        MaxProj                        matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        RESETButton                    matlab.ui.control.Button
        SaveResultsButton              matlab.ui.control.Button
        RGBValueChartPanel             matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        Label_2                        matlab.ui.control.Label
        gut_b                          matlab.ui.control.NumericEditField
        gut_g                          matlab.ui.control.NumericEditField
        gut_r                          matlab.ui.control.NumericEditField
        lyso_b                         matlab.ui.control.NumericEditField
        lyso_g                         matlab.ui.control.NumericEditField
        lyso_r                         matlab.ui.control.NumericEditField
        gfp_b                          matlab.ui.control.NumericEditField
        gfp_g                          matlab.ui.control.NumericEditField
        gfp_r                          matlab.ui.control.NumericEditField
        white_b                        matlab.ui.control.NumericEditField
        white_g                        matlab.ui.control.NumericEditField
        white_r                        matlab.ui.control.NumericEditField
        blue_b                         matlab.ui.control.NumericEditField
        blue_g                         matlab.ui.control.NumericEditField
        blue_r                         matlab.ui.control.NumericEditField
        green_r                        matlab.ui.control.NumericEditField
        green_g                        matlab.ui.control.NumericEditField
        green_b                        matlab.ui.control.NumericEditField
        red_b                          matlab.ui.control.NumericEditField
        red_g                          matlab.ui.control.NumericEditField
        red_r                          matlab.ui.control.NumericEditField
        gut_measure                    matlab.ui.control.Button
        lyso_measure                   matlab.ui.control.Button
        gfp_measure                    matlab.ui.control.Button
        white_measure                  matlab.ui.control.Button
        blue_measure                   matlab.ui.control.Button
        green_measure                  matlab.ui.control.Button
        red_measure                    matlab.ui.control.Button
        BLabel                         matlab.ui.control.Label
        GLabel                         matlab.ui.control.Label
        RLabel                         matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        GutCheckbox                    matlab.ui.control.CheckBox
        LysoCheckbox                   matlab.ui.control.CheckBox
        GFPCheckbox                    matlab.ui.control.CheckBox
        WCheckbox                      matlab.ui.control.CheckBox
        BCheckbox                      matlab.ui.control.CheckBox
        GCheckbox                      matlab.ui.control.CheckBox
        RCheckbox                      matlab.ui.control.CheckBox
        ChannelLabel                   matlab.ui.control.Label
        GutAutofluorescenceLabel       matlab.ui.control.Label
        LysosomeAutofluroescenceLabel  matlab.ui.control.Label
        GFPLabel                       matlab.ui.control.Label
        WhiteLabel                     matlab.ui.control.Label
        BlueLabel                      matlab.ui.control.Label
        GreenLabel                     matlab.ui.control.Label
        RedLabel                       matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        CurrentSliceEditField          matlab.ui.control.NumericEditField
        CurrentSliceEditFieldLabel     matlab.ui.control.Label
        DropperradiusSpinner           matlab.ui.control.NumericEditField
        DropperRadiusEditFieldLabel    matlab.ui.control.Label
        SigmagaussEditField            matlab.ui.control.NumericEditField
        SigmagaussEditFieldLabel       matlab.ui.control.Label
        tomovethroughzstacktozoomptopanrtoresetLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        parent_app; % Description
        image_data;
        repo_data;
        image_view;
        image_gamma;
        info;
        red; % Color indices.
        green;
        blue;
        white;
        gfp;
        dic; 
    end
    
    methods (Access = private)
        
        %% Draw the annotated image (image volume & neuron markers).
        function DrawImageData(app, slice)

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % Determine the color channel indices.
            red = app.red;
            green = app.green;
            blue = app.blue;
            white = app.white;
            dic = app.dic;
            gfp = app.gfp;

            % Determine the channel=color assignments for displaying.
            color_indices = [red, green, blue];

            % Draw the 3 color channels.
            app.image_view = app.image_data(:,:,slice,color_indices);
            max_proj = app.image_data(:,:,:,color_indices);

            % Remove unchecked color channels.
            if ~app.RCheckbox.Value % Red
                app.image_view(:,:,slice,1) = 0;
                max_proj(:,:,:,1) = 0;
            end
            if ~app.GCheckbox.Value % Green
                app.image_view(:,:,slice,2) = 0;
                max_proj(:,:,:,2) = 0;
            end
            if ~app.BCheckbox.Value % Blue
                app.image_view(:,:,slice,3) = 0;
                max_proj(:,:,:,3) = 0;
            end

            % Add in the white channel.
            if app.WCheckbox.Value % White

                % Compute the white channel.
                wchannel = app.image_data(:,:,slice,white);
                max_wchannel = max_proj(:,:,:,white);

                % Adjust the gamma.
                W_i = app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index
                if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1
                    wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                    max_wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                end

                % Add the white channel.
                app.image_view = app.image_view + repmat(wchannel, [1,1,1,3]);
                max_proj = max_proj + repmat(max_wchannel, [1,1,1,3]);
            end

            % Add in the GFP channel.
            if app.GFPCheckbox.Value % GFP

                % Compute the GFP channel.
                gfp_color = Program.GUIPreferences.instance().GFP_color;
                gfp_channel = app.image_data(:,:,slice,gfp);
                max_gfp_channel = app.image_data(:,:,:,gfp);

                % Adjust the gamma.
                GFP_i = app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index
                if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1
                    gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));
                    max_gfp_channel = imadjustn(max_gfp_channel,[],[], app.image_gamma(GFP_i));
                end

                % Add the GFP channel.
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                gfp_channel(:,:,slice,~gfp_color) = 0;
                app.image_view = app.image_view + gfp_channel;

                max_gfp_channel = repmat(max_gfp_channel, [1,1,1,3]);
                max_gfp_channel(:,:,:,~gfp_color) = 0;
                max_proj = max_proj + max_gfp_channel;
            end

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            app.image_view = uint16(double(intmax('uint16')) * ...
                double(app.image_view)/double(max(app.image_view(:))));
            for c = 1:size(app.image_view, 4)
                if app.image_gamma(c) ~= 1
                    app.image_view(:,:,:,c) = ...
                        imadjustn(squeeze(app.image_view(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end
            app.image_view = double(app.image_view)/double(max(app.image_view(:)));

            max_proj = uint16(double(intmax('uint16')) * ...
                double(max_proj)/double(max(max_proj(:))));
            for c = 1:size(max_proj, 4)
                if app.image_gamma(c) ~= 1
                    max_proj(:,:,:,c) = ...
                        imadjustn(squeeze(max_proj(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end
            max_proj = double(max_proj)/double(max(max_proj(:)));

            % Redraw the max projection.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            image(app.UIAxes, squeeze(max(app.image_view,[],3)));
            image(app.MaxProj, squeeze(max(max_proj,[],3)));
        end

        function [image_stack_filtered] = debleed(app, main_data, loc_pixel, sigma_gauss,size_selection, gfp_flag, channel)
            % debleed.m  remove spectral crostalk of images based on a linear spectral crosstalk remover.
            %   Inputs:
            %       main_data: the struct loaded from the data.mat path, which is saved
            %           according the neuroPAL format [struct]
            %
            %       loc_pixels: the x,y,z location of pure color pixels and background in the image stack. The
            %           format is R;G;B;bg.  [4x3 array]
            %
            %       sigma_gauss: Sigma for 3D gaussian filtering [float]
            %
            %       size_selection: size/2 of square used to sample pixels of pure colors [int>0]
            %
            %       gfp_flag: flag to check if the spectral crosstalk from the GFP
            %           channel needs to be removed [boolean]
            %
            %   Output:
            %       image_stack_filtered: corrected image stack [array]

            d = uiprogressdlg(app.UIFigure,'Title','Normalizing image...',...
        'Indeterminate','on');
            main_data = Methods.Preprocess.zscore_frame(main_data);
            
            close(d)
                
            d = uiprogressdlg(app.UIFigure,'Title','Debleeding colors...',...
        'Indeterminate','on');

            persistent blurredImg
            if ~isempty(blurredImg)
                sigma_gauss = 0.0001;
            else
                blurredImg = 1;
            end

            image = double(main_data);
            image(image<0) = 0;
            colors = app.info.RGBW(1:3); % only include rgb 
            channel = find(colors==channel);
            
            % if GFP correction, use the pure red coordinate for gfp channel, as the
            % overlap there is negligible. add GFP channel to color list
            %if gfp_flag
            %    colors = [colors; app.info.GFP];
            %    loc_pixels = [loc_pixels(1,:);loc_pixels(4,:)];
            %end
            
            image_original = zeros(length(colors), size(image,1),size(image,2),size(image,3));
            
            % stack relevant channels and normalize
            image_stack_filtered = zeros(length(colors), size(image,1),size(image,2),size(image,3));
            for i = 1:length(colors)
                image_stack_filtered(i,:,:,:) = imgaussfilt3(image(:,:,:,colors(i))./max(image(:,:,:,colors(i)),[],'all').*65535, sigma_gauss);
                image_original(i,:,:,:) = image(:,:,:,colors(i));
            end


            % construct background array (based on last row of loc_pixels)
            bg = double(mean(image_stack_filtered(:,loc_pixel(end,2)-size_selection:loc_pixel(end,2)+size_selection,loc_pixel(end,1)-size_selection:loc_pixel(end,1)+size_selection,loc_pixel(end,3)),[2,3]));
            for ii=1:size(bg)
                image_stack_filtered(ii,:,:,:) = image_stack_filtered(ii,:,:,:) - bg(ii); 
            end
            
            %channels to debleed
            channels_to_debleed = [1,2,3]~=channel;
            
            % compute linear scaling for spectral crosstalk
            
            scale_crosstalk = mean(image_stack_filtered(:,loc_pixel(1,2)-size_selection:loc_pixel(1,2)+size_selection,loc_pixel(1,1)-size_selection:loc_pixel(1,1)+size_selection,loc_pixel(1,3)),[2,3]);
            
            scale_crosstalk(channels_to_debleed) = scale_crosstalk(channels_to_debleed)/scale_crosstalk(~channels_to_debleed);
            scale_crosstalk(~channels_to_debleed) = scale_crosstalk(~channels_to_debleed)/scale_crosstalk(~channels_to_debleed);
            
            for ii = 1:length(channels_to_debleed)
                image_stack_filtered(ii,:,:,:) = image_stack_filtered(ii,:,:,:) - channels_to_debleed(ii)*scale_crosstalk(ii)* image_stack_filtered(~channels_to_debleed,:,:,:);
            end
            
            
            % Normalize corrected image again
            for i = 1:length(colors)
                image_stack_filtered(i,:,:,:) = uint16(image_stack_filtered(i,:,:,:)./max(image_stack_filtered(i,:,:,:),[],'all').*65535);
            end
            
            close(d)
            d = uiprogressdlg(app.UIFigure,'Title','Permuting results...',...
        'Indeterminate','on');
            % Permute image to get same format as input
            image_stack_filtered = permute(image_stack_filtered, [4,2,3,1]);
            image_stack_filtered = permute(image_stack_filtered,[2,3,1,4]);
            image_stack_filtered = Methods.Preprocess.zscore_frame(image_stack_filtered);
            app.image_data(:,:,:,app.red) = image_stack_filtered(:,:,:,1);
            app.image_data(:,:,:,app.green) = image_stack_filtered(:,:,:,2);
            app.image_data(:,:,:,app.blue) = image_stack_filtered(:,:,:,3);

            close(d)
            d = uiprogressdlg(app.UIFigure,'Title','Updating image...',...
        'Indeterminate','on');
            app.DrawImageData(round(app.Slider.Value));
            set(app.SaveResultsButton,'Enable','On');
            close(d)
        
        end


        function [bg_pixels] = bg_check(app)
            set(app.Slider,'Enable','Off')
            set(app.CurrentSliceEditField,'Enable','Off')

            persistent hasRunBefore
            if ~isempty(hasRunBefore)
                bg_pixels = hasRunBefore;
                set(app.Slider,'Enable','On')
                set(app.CurrentSliceEditField,'Enable','On')
                return
            end

            check = uiconfirm(app.UIFigure,'Click on the pixel on this slice that best represents background.','Confirmation','Options',{'OK', 'Select different slice'},'DefaultOption','OK');

            switch check
                case 'OK'

                    color_roi = drawpoint(app.UIAxes);
                    pos = round(color_roi.Position);
                    delete(color_roi);

                    bg_pixels = [pos(1), pos(2), round(app.Slider.Value)];
                    hasRunBefore = bg_pixels;

                case 'Select different slice'
                    return
            end

            set(app.Slider,'Enable','On')
            set(app.CurrentSliceEditField,'Enable','On')

        end

        function [r, g, b] = dropper(app, pos)
            radius = app.DropperradiusSpinner.Value;
            img_r = app.image_data(:,:,app.Slider.Value,app.red);
            img_g = app.image_data(:,:,app.Slider.Value,app.green);
            img_b = app.image_data(:,:,app.Slider.Value,app.blue);
           
            % Calculate the average value of pixels within the range
            r = mean(mean(img_r(pos(1)-radius:pos(1)+radius, pos(2)-radius:pos(2)+radius)));
            g = mean(mean(img_g(pos(1)-radius:pos(1)+radius, pos(2)-radius:pos(2)+radius)));
            b = mean(mean(img_b(pos(1)-radius:pos(1)+radius, pos(2)-radius:pos(2)+radius)));
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_data, image_gamma, info, red, green, blue, white, gfp, dic)
            app.parent_app = parent_app;
            app.image_data = image_data;
            app.image_gamma = image_gamma;
            app.repo_data = image_data;
            app.info = info;

            app.red = str2double(red);
            app.green = str2double(green);
            app.blue = str2double(blue);
            app.white = str2double(white);
            app.gfp = str2double(gfp);
            app.dic = str2double(dic);

            app.UIAxes.YDir = 'normal';

            app.MaxProj.XLim = [1, size(app.image_data, 2)];
            app.MaxProj.YLim = [1, size(app.image_data, 1)];

            app.UIAxes.XLim = [1, size(app.image_data, 2)];
            app.UIAxes.YLim = [1, size(app.image_data, 1)];


            ticks = [];
            min_ticks = [];

            for n = 1:size(app.image_data, 3)
                if n == 1 || n == size(app.image_data,3) || ismember(n-2, ticks)
                    ticks = [ticks, n];
                else
                    min_ticks = [min_ticks, n];
                end
            end

            app.Slider.MajorTicks = ticks;
            app.Slider.MinorTicks = min_ticks;
            app.Slider.Value = round(size(app.image_data, 3)/2);
            app.Slider.Limits = [1, size(app.image_data, 3)];

            app.CurrentSliceEditField.Limits = app.Slider.Limits;
            app.CurrentSliceEditField.Value = app.Slider.Value;

            % Resize the figure to fit most of the screen size.
            screen_size = get(groot, 'ScreenSize');
            screen_size = screen_size(3:4);
            screen_margin = floor(screen_size .* [0.07,0.05]);
            figure_size(1:2) = screen_margin / 2;
            figure_size(3) = screen_size(1) - screen_margin(1);
            figure_size(4) = screen_size(2) - 2*screen_margin(2);
            drawnow; % need to draw before resizing
            app.UIFigure.Position = figure_size;

            app.DrawImageData(app.Slider.Value);
        end

        % Button pushed function: red_measure
        function red_measureButtonPushed(app, event)
            set(app.Slider,'Enable','Off')
            set(app.CurrentSliceEditField,'Enable','Off')
            check = uiconfirm(app.UIFigure,'Click on the pixel on this slice that best represents red.','Confirmation','Options',{'OK', 'Select different slice'},'DefaultOption','OK');

            switch check
                case 'OK'

                    color_roi = drawpoint(app.UIAxes);
                    pos = round(color_roi.Position);
                    delete(color_roi);

                    pixels = [pos(1), pos(2), round(app.Slider.Value)];

                    r = impixel(app.image_data(:,:,app.Slider.Value,app.red), pos(1), pos(2));
                    g = impixel(app.image_data(:,:,app.Slider.Value,app.green), pos(1), pos(2));
                    b = impixel(app.image_data(:,:,app.Slider.Value,app.blue), pos(1), pos(2));

                    app.red_r.Value = r(1);
                    app.red_g.Value = g(1);
                    app.red_b.Value = b(1);

                    if (app.red_r.Value ~= 0) && (app.green_r.Value ~= 0) && (app.blue_r.Value ~= 0)
                        set(app.DebleedColorsButton,'Enable','On')
                    end

                case 'Select different slice'
                    set(app.Slider,'Enable','On')
                    set(app.CurrentSliceEditField,'Enable','On')
                    return
            end

            bg_pixels = app.bg_check();
            loc_pixels = [pixels; bg_pixels];

            set(app.Slider,'Enable','On')
            set(app.CurrentSliceEditField,'Enable','On')

            app.debleed(app.image_data, loc_pixels, app.SigmagaussEditField.Value, app.DropperradiusSpinner.Value, app.GFPCheckbox.Value, app.red);

        end

        % Button pushed function: green_measure
        function green_measureButtonPushed(app, event)
            set(app.Slider,'Enable','Off')
            set(app.CurrentSliceEditField,'Enable','Off')
            check = uiconfirm(app.UIFigure,'Click on the pixel on this slice that best represents green.','Confirmation','Options',{'OK', 'Select different slice'},'DefaultOption','OK');

            switch check
                case 'OK'

                    color_roi = drawpoint(app.UIAxes);
                    pos = round(color_roi.Position);
                    delete(color_roi);

                    pixels = [pos(1), pos(2), round(app.Slider.Value)];

                    r = impixel(app.image_data(:,:,app.Slider.Value,app.red), pos(1), pos(2));
                    g = impixel(app.image_data(:,:,app.Slider.Value,app.green), pos(1), pos(2));
                    b = impixel(app.image_data(:,:,app.Slider.Value,app.blue), pos(1), pos(2));

                    app.green_r.Value = r(1);
                    app.green_g.Value = g(1);
                    app.green_b.Value = b(1);

                    if (app.red_r.Value ~= 0) && (app.green_r.Value ~= 0) && (app.blue_r.Value ~= 0)
                        set(app.DebleedColorsButton,'Enable','On')
                    end

                case 'Select different slice'
                    return
            end

            bg_pixels = app.bg_check();
            loc_pixels = [pixels; bg_pixels];

            set(app.Slider,'Enable','On')
            set(app.CurrentSliceEditField,'Enable','On')

            app.debleed(app.image_data, loc_pixels, app.SigmagaussEditField.Value, app.DropperradiusSpinner.Value, app.GFPCheckbox.Value, app.green);

        end

        % Button pushed function: blue_measure
        function blue_measureButtonPushed(app, event)
            set(app.Slider,'Enable','Off')
            set(app.CurrentSliceEditField,'Enable','Off')
            check = uiconfirm(app.UIFigure,'Click on the pixel on this slice that best represents blue.','Confirmation','Options',{'OK', 'Select different slice'},'DefaultOption','OK');

            switch check
                case 'OK'

                    color_roi = drawpoint(app.UIAxes);
                    pos = round(color_roi.Position);
                    delete(color_roi);

                    pixels = [pos(1), pos(2), round(app.Slider.Value)];

                    r = impixel(app.image_data(:,:,app.Slider.Value,app.red), pos(1), pos(2));
                    g = impixel(app.image_data(:,:,app.Slider.Value,app.green), pos(1), pos(2));
                    b = impixel(app.image_data(:,:,app.Slider.Value,app.blue), pos(1), pos(2));

                    app.blue_r.Value = r(1);
                    app.blue_g.Value = g(1);
                    app.blue_b.Value = b(1);

                    if (app.red_r.Value ~= 0) && (app.green_r.Value ~= 0) && (app.blue_r.Value ~= 0)
                        set(app.DebleedColorsButton,'Enable','On')
                    end

                case 'Select different slice'
                    return
            end

            bg_pixels = app.bg_check();
            loc_pixels = [pixels; bg_pixels];

            set(app.Slider,'Enable','On')
            set(app.CurrentSliceEditField,'Enable','On')

            app.debleed(app.image_data, loc_pixels, app.SigmagaussEditField.Value, app.DropperradiusSpinner.Value, app.GFPCheckbox.Value, app.blue);

        end

        % Button pushed function: white_measure
        function white_measureButtonPushed(app, event)
            set(app.Slider,'Enable','Off')
            set(app.CurrentSliceEditField,'Enable','Off')
            check = uiconfirm(app.UIFigure,'Click on the pixel on this slice that best represents white.','Confirmation','Options',{'OK', 'Select different slice'},'DefaultOption','OK');

            switch check
                case 'OK'

                    color_roi = drawpoint(app.UIAxes);
                    pos = round(color_roi.Position);
                    delete(color_roi);

                    app.white_x.Value = pos(1);
                    app.white_y.Value = pos(2);
                    app.white_z.Value = round(app.Slider.Value);

                    r = impixel(app.image_data(:,:,app.Slider.Value,app.red), pos(1), pos(2));
                    g = impixel(app.image_data(:,:,app.Slider.Value,app.green), pos(1), pos(2));
                    b = impixel(app.image_data(:,:,app.Slider.Value,app.blue), pos(1), pos(2));

                    app.white_r.Value = r(1);
                    app.white_g.Value = g(1);
                    app.white_b.Value = b(1);

                case 'Select different slice'
                    return
            end

            set(app.Slider,'Enable','On')
            set(app.CurrentSliceEditField,'Enable','On')

        end

        % Button pushed function: gut_measure
        function gut_measureButtonPushed(app, event)
            set(app.Slider,'Enable','Off')
            set(app.CurrentSliceEditField,'Enable','Off')
            check = uiconfirm(app.UIFigure,'Click on the pixel on this slice that best represents gut autofluorescence.','Confirmation','Options',{'OK', 'Select different slice'},'DefaultOption','OK');

            switch check
                case 'OK'

                    color_roi = drawpoint(app.UIAxes);
                    pos = round(color_roi.Position);
                    delete(color_roi);

                    app.gut_x.Value = pos(1);
                    app.gut_y.Value = pos(2);
                    app.gut_z.Value = round(app.Slider.Value);

                    r = impixel(app.image_data(:,:,app.Slider.Value,app.red), pos(1), pos(2));
                    g = impixel(app.image_data(:,:,app.Slider.Value,app.green), pos(1), pos(2));
                    b = impixel(app.image_data(:,:,app.Slider.Value,app.blue), pos(1), pos(2));

                    app.gut_r.Value = r(1);
                    app.gut_g.Value = g(1);
                    app.gut_b.Value = b(1);
                    
                case 'Select different slice'
                    return
            end

            set(app.Slider,'Enable','On')
            set(app.CurrentSliceEditField,'Enable','On')

        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            app.parent_app.UpdateFromChild(app.image_data);
            delete(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key

                case 'leftarrow' % Go up in the z stack.
                    if app.Slider.Value > 1
                        zEvent.Value = app.Slider.Value - 1;
                        app.Slider.Value = app.Slider.Value - 1;
                        app.SliderValueChanged(zEvent);
                    end
                    %disp(key);

                case 'rightarrow' % Go down in the z stack.
                    if app.Slider.Value < app.Slider.Limits(2)
                        zEvent.Value = app.Slider.Value + 1;
                        app.Slider.Value = app.Slider.Value + 1;
                        app.SliderValueChanged(zEvent);
                    end
                    %disp(key);

                case 'uparrow' % Zoom in.
                    zoom(app.UIAxes, 1 + 0.2);

                case 'downarrow' % Zoom out.
                    zoom(app.UIAxes, 1 - 0.2);

                case {'h','H'} % Toggle half zoom.
                    % Reset x-axis limits.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> lower half.
                    ylims = app.UIAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/2
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)/2), ...
                            size(app.image_data, 1)];

                        % Lower -> upper half.
                    else
                        app.UIAxes.YLim = [1, floor(size(app.image_data, 1)/2)];
                    end

                case {'j','J'} % Toggle third zoom.
                    % Reset x-axis limits.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> middle third.
                    ylims = app.UIAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/3
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)/3), ...
                            floor(size(app.image_data, 1)*2/3)];

                        % Middle -> lower third.
                    elseif ylims(1) < size(app.image_data, 1)*2/3
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)*2/3), ...
                            size(app.image_data, 1)];

                        % Lower -> upper third.
                    else
                        app.UIAxes.YLim = [1, floor(size(app.image_data, 1)/3)];
                    end

                case {'k','K'} % Toggle quarter zoom.
                    % Reset x-axis limits.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> upper-middle quarter.
                    ylims = app.UIAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/4
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)/4), ...
                            floor(size(app.image_data, 1)*2/4)];

                        % Upper-middle -> lower-middle quarter.
                    elseif ylims(1) < size(app.image_data, 1)*2/4
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)*2/4), ...
                            floor(size(app.image_data, 1)*3/4)];

                        % Lower-middle -> lower quarter.
                    elseif ylims(1) < size(app.image_data, 1)*3/4
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)*3/4), ...
                            size(app.image_data, 1)];

                        % Lower -> upper quarter.
                    else
                        app.UIAxes.YLim = [1, floor(size(app.image_data, 1)/4)];
                    end

                case {'p','P'} % Toggle image panning.

                    % Toggle image panning.
                    if app.is_panning
                        pan(app.UIAxes,'off');
                        pause(0.5);
                        RestorePointer(app);
                        app.is_panning = false;
                    else
                        pan(app.UIAxes,'on');
                        pause(0.5);
                        app.is_panning = true;
                    end

                case {'r','R'} % Reset the figure to center.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];
                    app.UIAxes.YLim = [1, size(app.image_data, 1)];
                    %app.UIAxes.XLim = [1, size(app.image_data, 2)];
                    %app.UIAxes.YLim = [1, size(app.image_data, 1)];
                    %app.UIAxes.XAxisLocation = 'bottom';
                    %app.UIAxes.YAxisLocation = 'left';

                    %case {'x','X'} % Switch mouse to crosshairs.
                    %zoom(app.UIAxes,'on');
                    %pause(0.2);

                otherwise
                    % Do nothing.
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value = round(app.Slider.Value);
            app.CurrentSliceEditField.Value = app.Slider.Value;
            app.DrawImageData(app.Slider.Value);
        end

        % Value changed function: CurrentSliceEditField
        function CurrentSliceEditFieldValueChanged(app, event)
            app.CurrentSliceEditField.Value = round(app.CurrentSliceEditField.Value);
            app.Slider.Value = app.CurrentSliceEditField.Value;
            app.DrawImageData(app.CurrentSliceEditField.Value);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.image_data = app.repo_data;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTickLabel = '';
            app.Panel_3 = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.tomovethroughzstacktozoomptopanrtoresetLabel = uilabel(app.GridLayout2);
            app.tomovethroughzstacktozoomptopanrtoresetLabel.Text = {'🠜/🠞 to move through z-stack, 🠝/🠟 to zoom,'; '[ p ] to pan, [ r ] to reset. '};
            app.SigmagaussEditFieldLabel = uilabel(app.GridLayout2);
            app.SigmagaussEditFieldLabel.Tooltip = {'Sigma for 3D gaussian filtering. (Higher for better debleed, lower for less blur.)'};
            app.SigmagaussEditFieldLabel.Text = 'Sigma gauss';
            app.SigmagaussEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SigmagaussEditField.Tooltip = {'Sigma for 3D gaussian filtering. (Higher for better debleed, lower for less blur.)'};
            app.SigmagaussEditField.Value = 0.3;
            app.DropperRadiusEditFieldLabel = uilabel(app.GridLayout2);
            app.DropperRadiusEditFieldLabel.Text = 'Dropper Radius';
            app.DropperradiusSpinner = uieditfield(app.GridLayout2, 'numeric');
            app.DropperradiusSpinner.ValueDisplayFormat = '%.0f';
            app.DropperradiusSpinner.Editable = 'off';
            app.CurrentSliceEditFieldLabel = uilabel(app.GridLayout2);
            app.CurrentSliceEditFieldLabel.Text = 'Current Slice';
            app.CurrentSliceEditField = uieditfield(app.GridLayout2, 'numeric');
            app.CurrentSliceEditField.ValueDisplayFormat = '%.0f';
            app.CurrentSliceEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentSliceEditFieldValueChanged, true);
            app.RGBValueChartPanel = uipanel(app.UIFigure);
            app.RGBValueChartPanel.Title = 'RGB Value Chart';
            app.GridLayout = uigridlayout(app.RGBValueChartPanel);
            app.RedLabel = uilabel(app.GridLayout);
            app.RedLabel.Text = 'Red';
            app.GreenLabel = uilabel(app.GridLayout);
            app.GreenLabel.Text = 'Green';
            app.BlueLabel = uilabel(app.GridLayout);
            app.BlueLabel.Text = 'Blue';
            app.WhiteLabel = uilabel(app.GridLayout);
            app.WhiteLabel.Text = 'White';
            app.GFPLabel = uilabel(app.GridLayout);
            app.GFPLabel.Text = 'GFP';
            app.LysosomeAutofluroescenceLabel = uilabel(app.GridLayout);
            app.LysosomeAutofluroescenceLabel.Text = 'Lysosome Autofluroescence';
            app.GutAutofluorescenceLabel = uilabel(app.GridLayout);
            app.GutAutofluorescenceLabel.Text = 'Gut Autofluorescence';
            app.ChannelLabel = uilabel(app.GridLayout);
            app.ChannelLabel.Text = 'Channel';
            app.RCheckbox = uicheckbox(app.GridLayout);
            app.RCheckbox.Text = '';
            app.RCheckbox.Value = true;
            app.GCheckbox = uicheckbox(app.GridLayout);
            app.GCheckbox.Text = '';
            app.GCheckbox.Value = true;
            app.BCheckbox = uicheckbox(app.GridLayout);
            app.BCheckbox.Text = '';
            app.BCheckbox.Value = true;
            app.WCheckbox = uicheckbox(app.GridLayout);
            app.WCheckbox.Enable = 'off';
            app.WCheckbox.Text = '';
            app.GFPCheckbox = uicheckbox(app.GridLayout);
            app.GFPCheckbox.Enable = 'off';
            app.GFPCheckbox.Text = '';
            app.LysoCheckbox = uicheckbox(app.GridLayout);
            app.LysoCheckbox.Enable = 'off';
            app.LysoCheckbox.Text = '';
            app.GutCheckbox = uicheckbox(app.GridLayout);
            app.GutCheckbox.Enable = 'off';
            app.GutCheckbox.Text = '';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = ' 👁';
            app.RLabel = uilabel(app.GridLayout);
            app.RLabel.Text = 'R';
            app.GLabel = uilabel(app.GridLayout);
            app.GLabel.Text = 'G';
            app.BLabel = uilabel(app.GridLayout);
            app.BLabel.Text = 'B';
            app.red_measure = uibutton(app.GridLayout, 'push');
            app.red_measure.ButtonPushedFcn = createCallbackFcn(app, @red_measureButtonPushed, true);
            app.red_measure.Text = '➤';
            app.green_measure = uibutton(app.GridLayout, 'push');
            app.green_measure.ButtonPushedFcn = createCallbackFcn(app, @green_measureButtonPushed, true);
            app.green_measure.Text = '➤';
            app.blue_measure = uibutton(app.GridLayout, 'push');
            app.blue_measure.ButtonPushedFcn = createCallbackFcn(app, @blue_measureButtonPushed, true);
            app.blue_measure.Text = '➤';
            app.white_measure = uibutton(app.GridLayout, 'push');
            app.white_measure.ButtonPushedFcn = createCallbackFcn(app, @white_measureButtonPushed, true);
            app.white_measure.Text = '➤';
            app.gfp_measure = uibutton(app.GridLayout, 'push');
            app.gfp_measure.Enable = 'off';
            app.gfp_measure.Text = '➤';
            app.lyso_measure = uibutton(app.GridLayout, 'push');
            app.lyso_measure.Enable = 'off';
            app.lyso_measure.Text = '➤';
            app.gut_measure = uibutton(app.GridLayout, 'push');
            app.gut_measure.ButtonPushedFcn = createCallbackFcn(app, @gut_measureButtonPushed, true);
            app.gut_measure.Enable = 'off';
            app.gut_measure.Text = '➤';
            app.red_r = uieditfield(app.GridLayout, 'numeric');
            app.red_r.Editable = 'off';
            app.red_g = uieditfield(app.GridLayout, 'numeric');
            app.red_g.Editable = 'off';
            app.red_b = uieditfield(app.GridLayout, 'numeric');
            app.red_b.Editable = 'off';
            app.green_b = uieditfield(app.GridLayout, 'numeric');
            app.green_b.Editable = 'off';
            app.green_g = uieditfield(app.GridLayout, 'numeric');
            app.green_g.Editable = 'off';
            app.green_r = uieditfield(app.GridLayout, 'numeric');
            app.green_r.Editable = 'off';
            app.blue_r = uieditfield(app.GridLayout, 'numeric');
            app.blue_r.Editable = 'off';
            app.blue_g = uieditfield(app.GridLayout, 'numeric');
            app.blue_g.Editable = 'off';
            app.blue_b = uieditfield(app.GridLayout, 'numeric');
            app.blue_b.Editable = 'off';
            app.white_r = uieditfield(app.GridLayout, 'numeric');
            app.white_r.Editable = 'off';
            app.white_g = uieditfield(app.GridLayout, 'numeric');
            app.white_g.Editable = 'off';
            app.white_b = uieditfield(app.GridLayout, 'numeric');
            app.white_b.Editable = 'off';
            app.gfp_r = uieditfield(app.GridLayout, 'numeric');
            app.gfp_r.Editable = 'off';
            app.gfp_r.Enable = 'off';
            app.gfp_g = uieditfield(app.GridLayout, 'numeric');
            app.gfp_g.Editable = 'off';
            app.gfp_g.Enable = 'off';
            app.gfp_b = uieditfield(app.GridLayout, 'numeric');
            app.gfp_b.Editable = 'off';
            app.gfp_b.Enable = 'off';
            app.lyso_r = uieditfield(app.GridLayout, 'numeric');
            app.lyso_r.Editable = 'off';
            app.lyso_r.Enable = 'off';
            app.lyso_g = uieditfield(app.GridLayout, 'numeric');
            app.lyso_g.Editable = 'off';
            app.lyso_g.Enable = 'off';
            app.lyso_b = uieditfield(app.GridLayout, 'numeric');
            app.lyso_b.Editable = 'off';
            app.lyso_b.Enable = 'off';
            app.gut_r = uieditfield(app.GridLayout, 'numeric');
            app.gut_r.Editable = 'off';
            app.gut_r.Enable = 'off';
            app.gut_g = uieditfield(app.GridLayout, 'numeric');
            app.gut_g.Editable = 'off';
            app.gut_g.Enable = 'off';
            app.gut_b = uieditfield(app.GridLayout, 'numeric');
            app.gut_b.Editable = 'off';
            app.gut_b.Enable = 'off';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '💉';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.SaveResultsButton = uibutton(app.GridLayout3, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.RESETButton = uibutton(app.GridLayout3, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.MaximumProjectionPanel = uipanel(app.UIFigure);
            app.MaximumProjectionPanel.Title = 'Maximum Projection';
            app.GridLayout4 = uigridlayout(app.MaximumProjectionPanel);
            app.MaxProj = uiaxes(app.GridLayout4);
            app.MaxProj.XTickLabel = '';
            app.MaxProj.YTickLabel = '';
            app.Panel_4 = uipanel(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.Slider = uislider(app.GridLayout5);
            app.Slider.MajorTicks = 0;
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = 0;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channel_selector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end