classdef downsample_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Warning_3                    matlab.ui.control.Label
        Warning_2                    matlab.ui.control.Label
        Warning                      matlab.ui.control.Label
        DetailsPanel                 matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        ApplyButton                  matlab.ui.control.Button
        ResetButton_2                matlab.ui.control.Button
        MaintainaspectratioCheckBox  matlab.ui.control.CheckBox
        Panel_3                      matlab.ui.container.Panel
        zSlicesEditField             matlab.ui.control.NumericEditField
        zSlicesEditFieldLabel        matlab.ui.control.Label
        xFactorEditField             matlab.ui.control.NumericEditField
        xFactorEditFieldLabel        matlab.ui.control.Label
        yFactorEditField             matlab.ui.control.NumericEditField
        yFactorEditFieldLabel        matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        Switch                       matlab.ui.control.Switch
        Panel_2                      matlab.ui.container.Panel
        MainPreview                  matlab.ui.control.UIAxes
        ZPreview1                    matlab.ui.control.UIAxes
        ZPreview2                    matlab.ui.control.UIAxes
        ZPreview3                    matlab.ui.control.UIAxes
        ZPreview4                    matlab.ui.control.UIAxes
        ZPreview5                    matlab.ui.control.UIAxes
        ZPreview6                    matlab.ui.control.UIAxes
        ZPreview7                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        parent_app;
        image_data;
        repo_img;
        image_view;
        repo_view;
        image_gamma;
        z_previews;
        image_neurons;
    end
    
    methods (Access = private)

        function down(app)
            app.image_view = imresize(app.repo_view,[size(app.image_data, 1)*app.yFactorEditField.Value size(app.image_data, 2)*app.xFactorEditField.Value]);
            UpdatePreview(app, app.image_view);
        end

        function UpdatePreview(app,image_view)

            image(app.MainPreview,image_view);

            if ~app.Switch.Value
                app.MainPreview.XLimMode = "manual";
                app.MainPreview.YLimMode = "manual";
            end

            set(app.Warning, 'Visible', 'off')
            set(app.Warning_2, 'Visible', 'off')
            set(app.Warning_3, 'Visible', 'off')

            if app.zSlicesEditField.Value > 100
                vis_z = [];
                col_z = '#f70f0f';
                set(app.Warning, 'Visible', 'on')
                set(app.Warning_2, 'Visible', 'on')
                set(app.Warning_3, 'Visible', 'on')
                uialert(app.UIFigure,"A high number of z-slices can slow some of NeuroPAL_ID's functions.",'Warning!');
            elseif app.zSlicesEditField.Value > 50
                vis_z = [1];
                set(app.Warning_2, 'Visible', 'on')
                col_z = '#fa5a5a';
            elseif app.zSlicesEditField.Value > 20
                vis_z = [1,7];
                col_z = '#FFFFFF';
            elseif app.zSlicesEditField.Value > 10
                vis_z = [1,4,7];
                col_z = '#FFFFFF';
            elseif app.zSlicesEditField.Value > 5
                vis_z = [1,2,6,7];
                col_z = '#FFFFFF';
            elseif app.zSlicesEditField.Value == 2
                vis_z = [1,2,4,6,7];
                col_z = '#FFFFFF';
            else
                vis_z = [1,2,3,5,6,7];
                col_z = '#FFFFFF';
            end

            for n = 1:length(app.z_previews)
                set(app.z_previews(n),'Color',col_z)
                pos = app.z_previews(n);
                pos = pos.Position;
                if ismember(n,vis_z)
                    pos = [pos(1), pos(2), 0, 0];
                    set(app.z_previews(n), 'Position', pos)
                else
                    pos = [pos(1), pos(2), 92, 82];
                    set(app.z_previews(n), 'Position', pos)
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_data, image_view, image_gamma, image_neurons)
            app.parent_app = parent_app;
            app.image_data = image_data;    
            app.image_gamma = image_gamma;
            app.repo_img = image_data;
            app.image_view = squeeze(max(image_view,[],3));
            app.repo_view = squeeze(max(image_view,[],3));

            app.zSlicesEditField.Value = size(app.image_data, 3);
            app.z_previews = [app.ZPreview1, app.ZPreview2, app.ZPreview3, app.ZPreview4, app.ZPreview5, app.ZPreview6, app.ZPreview7];

            app.image_neurons = image_neurons;

            axis(app.MainPreview, 'image');
            UpdatePreview(app, app.image_view);
        end

        % Value changed function: yFactorEditField
        function yFactorEditFieldValueChanged(app, event)
            if app.MaintainaspectratioCheckBox.Value
                app.xFactorEditField.Value = app.yFactorEditField.Value;
            end
            app.down();            
        end

        % Value changed function: xFactorEditField
        function xFactorEditFieldValueChanged(app, event)
            if app.MaintainaspectratioCheckBox.Value
                app.yFactorEditField.Value = app.xFactorEditField.Value;
            end
            app.down();            
        end

        % Value changed function: zSlicesEditField
        function zSlicesEditFieldValueChanged(app, event)
            if app.zSlicesEditField.Value > size(app.image_data, 3)
                uialert(app.UIFigure,"Can't set more z-slices than are present in the original image.","Error!")
                app.zSlicesEditField.Value = size(app.image_data, 3);
            end
            
            UpdatePreview(app, app.image_view);          
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if app.Switch.Value == 'Lock Axes'
                axis(app.MainPreview, 'manual');
                app.MainPreview.XLimMode = "manual";
                app.MainPreview.YLimMode = "manual";
                app.MainPreview.XLim = [0,size(app.repo_img.Position(3))];
                app.MainPreview.YLim = [0,size(app.repo_img.Position(4))];
            else
                app.MainPreview.XLimMode = "auto";
                app.MainPreview.YLimMode = "auto";
            end
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.image_data = app.repo_img;
            app.image_view = app.repo_view;
            UpdatePreview(app, app.repo_view);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Applying changes...','Indeterminate','on');
            similarity_idx = zeros(1,size(app.image_data,3));
            
            app.image_data = imresize(app.image_data,[size(app.image_data, 1)*app.yFactorEditField.Value size(app.image_data, 2)*app.xFactorEditField.Value]);

            removed_slices = [];
            
            for n = 1:size(app.image_data,3)
                color_indices = [1, 2, 3];
                pre_xy = app.image_data(:,:,:,color_indices);
                xy = pre_xy(:,:,n,:,:);
                if n < 2       
                    xy_1 = pre_xy(:,:,n+1,:,:);    
                    xy = abs(xy-xy_1);
                else
                    xy_1 = pre_xy(:,:,n-1,:,:);
                    xy = abs(xy-xy_1);
                end
            
                similarity_idx(n) = mean(sum(squeeze(xy),3),'all');
            end
            
            z_diff = size(app.image_data, 3) - app.zSlicesEditField.Value;
            
            if z_diff ~= 0
                [~, min_idx] = mink(similarity_idx, length(similarity_idx));
                min_idx = sort(min_idx, 'descend');
                removed_slices = min_idx(1:z_diff); 
                
                for n = 1:z_diff
                    app.image_data(:,:,min_idx(n),:,:) = [];
                end
            
                for n = 1:size(app.image_neurons.neurons, 2)
                    %app.image_neurons.neurons(n).position
                    %sprintf('Before: (%.f, %.f, %.f)', app.image_neurons.neurons(n).position(1),app.image_neurons.neurons(n).position(2),app.image_neurons.neurons(n).position(3))
                    app.image_neurons.neurons(n).position(1) = app.image_neurons.neurons(n).position(1)*app.yFactorEditField.Value;
                    app.image_neurons.neurons(n).position(2) = app.image_neurons.neurons(n).position(2)*app.xFactorEditField.Value;
                    
                    current_z = app.image_neurons.neurons(n).position(3);
                    
                    if ismember(current_z, removed_slices)
                        remaining_slices = setdiff(1:size(app.image_data, 3), removed_slices);
                        [~, closest_idx] = min(abs(remaining_slices - current_z));
                        closest_slice = remaining_slices(closest_idx);
                        app.image_neurons.neurons(n).position(3) = closest_slice;
                    end

                    %sprintf('After: (%.f, %.f, %.f)', app.image_neurons.neurons(n).position(1),app.image_neurons.neurons(n).position(2),app.image_neurons.neurons(n).position(3))
                    %app.image_neurons.neurons(n).position
                end

                app.parent_app.UpdateFromChild(app.image_data, app.image_neurons, app.image_gamma);
            end

            close(d)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ZPreview7 = uiaxes(app.UIFigure);
            app.ZPreview7.XAxisLocation = 'top';
            app.ZPreview7.XTick = [];
            app.ZPreview7.YAxisLocation = 'right';
            app.ZPreview7.YTick = [];
            app.ZPreview6 = uiaxes(app.UIFigure);
            app.ZPreview6.XAxisLocation = 'top';
            app.ZPreview6.XTick = [];
            app.ZPreview6.YAxisLocation = 'right';
            app.ZPreview6.YTick = [];
            app.ZPreview5 = uiaxes(app.UIFigure);
            app.ZPreview5.XAxisLocation = 'top';
            app.ZPreview5.XTick = [];
            app.ZPreview5.YAxisLocation = 'right';
            app.ZPreview5.YTick = [];
            app.ZPreview4 = uiaxes(app.UIFigure);
            app.ZPreview4.XAxisLocation = 'top';
            app.ZPreview4.XTick = [];
            app.ZPreview4.YAxisLocation = 'right';
            app.ZPreview4.YTick = [];
            app.ZPreview3 = uiaxes(app.UIFigure);
            app.ZPreview3.XAxisLocation = 'top';
            app.ZPreview3.XTick = [];
            app.ZPreview3.YAxisLocation = 'right';
            app.ZPreview3.YTick = [];
            app.ZPreview2 = uiaxes(app.UIFigure);
            app.ZPreview2.XAxisLocation = 'top';
            app.ZPreview2.XTick = [];
            app.ZPreview2.YAxisLocation = 'right';
            app.ZPreview2.YTick = [];
            app.ZPreview1 = uiaxes(app.UIFigure);
            app.ZPreview1.XAxisLocation = 'top';
            app.ZPreview1.XTick = [];
            app.ZPreview1.YAxisLocation = 'right';
            app.ZPreview1.YTick = [];
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.MainPreview = uiaxes(app.Panel_2);
            app.Switch = uiswitch(app.Panel, 'slider');
            app.Switch.Items = {'Free Axes', 'Lock Axes'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Free Axes';
            app.DetailsPanel = uipanel(app.UIFigure);
            app.DetailsPanel.Title = 'Details';
            app.GridLayout = uigridlayout(app.DetailsPanel);
            app.Panel_3 = uipanel(app.GridLayout);
            app.yFactorEditFieldLabel = uilabel(app.Panel_3);
            app.yFactorEditFieldLabel.Text = 'y-Factor';
            app.yFactorEditField = uieditfield(app.Panel_3, 'numeric');
            app.yFactorEditField.ValueChangedFcn = createCallbackFcn(app, @yFactorEditFieldValueChanged, true);
            app.yFactorEditField.Value = 1;
            app.xFactorEditFieldLabel = uilabel(app.Panel_3);
            app.xFactorEditFieldLabel.Text = 'x-Factor';
            app.xFactorEditField = uieditfield(app.Panel_3, 'numeric');
            app.xFactorEditField.ValueChangedFcn = createCallbackFcn(app, @xFactorEditFieldValueChanged, true);
            app.xFactorEditField.Tooltip = {'Not the one with Simon Cowell... thankfully.'};
            app.xFactorEditField.Value = 1;
            app.zSlicesEditFieldLabel = uilabel(app.Panel_3);
            app.zSlicesEditFieldLabel.Text = 'z-Slices';
            app.zSlicesEditField = uieditfield(app.Panel_3, 'numeric');
            app.zSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @zSlicesEditFieldValueChanged, true);
            app.MaintainaspectratioCheckBox = uicheckbox(app.GridLayout);
            app.MaintainaspectratioCheckBox.Text = ' Maintain aspect ratio.';
            app.ResetButton_2 = uibutton(app.GridLayout, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Warning = uilabel(app.UIFigure);
            app.Warning.Visible = 'off';
            app.Warning.Text = '!';
            app.Warning_2 = uilabel(app.UIFigure);
            app.Warning_2.Visible = 'off';
            app.Warning_2.Text = '!';
            app.Warning_3 = uilabel(app.UIFigure);
            app.Warning_3.Visible = 'off';
            app.Warning_3.Text = '!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = downsample_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end