classdef flexneurodisp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Toolbar     matlab.ui.container.Toolbar
        PushTool    matlab.ui.container.toolbar.PushTool
        GridLayout  matlab.ui.container.GridLayout
        BR_Panel    matlab.ui.container.Panel
        BR_Grid     matlab.ui.container.GridLayout
        BR_Button   matlab.ui.control.Button
        BL_Panel    matlab.ui.container.Panel
        BL_Grid     matlab.ui.container.GridLayout
        BL_Button   matlab.ui.control.Button
        TR_Panel    matlab.ui.container.Panel
        TR_Grid     matlab.ui.container.GridLayout
        TR_Button   matlab.ui.control.Button
        TL_Panel    matlab.ui.container.Panel
        TL_Grid     matlab.ui.container.GridLayout
        TL_Button   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function AddNeuron(app,event)
            caller = event.Source;
            grid_space = caller.Parent.Parent.Layout;

            if strcmp(caller.Tag,'selector')
                app.LoadNeuron(caller, event)
            elseif strcmp(caller.Tag,'in_graph')
                app.SelectNeuron(caller, 'in_graph')
            elseif strcmp(caller.Tag,'add_graph')
                app.LoadNeuron(caller, event, 'add_graph')
            else
                if grid_space.Column < max(size(app.GridLayout.ColumnWidth)) & grid_space.Row < max(size(app.GridLayout.RowHeight))
                    app.SelectNeuron(caller)
                else
                    app.SelectNeuron(caller)
                    %app.CreateNeuron(caller,event)
                end
            end
        end

        function SelectNeuron(app,caller,tag)

            if ~exist('tag','var')
                tag = 'selector';
                g_parent = app.UIFigure;
                parent = caller.Parent;
            else
                tag = 'add_graph';
                g_parent = caller.Parent;
                parent = caller.Parent.Parent;
            end

            set(caller,'Visible','Off');

            % Create DropDown
            DropDown = uidropdown(g_parent);
            DropDown.Parent = parent;
            DropDown.Position = caller.Position;
            DropDown.Items = {'Sample Neuron #1','Sample Neuron #2','Sample Neuron #3'};
            DropDown.Placeholder = 'Select Neuron';
            DropDown.Tag = tag;
            DropDown.ValueChangedFcn = createCallbackFcn(app, @AddNeuron, true);
        end

        function LoadNeuron(app,caller,event,tag)

            if ~exist('tag','var')
                % Create new 
                new_grid = uigridlayout(caller.Parent.Parent);
                delete(caller.Parent)
                new_grid.ColumnWidth = {'1x', '1x', '1x', '1x', '1x'};
                new_grid.RowHeight = {'1x', '1x', '1x', '1x', '1x', '1x'};
    
                % Create UIAxes
                new_axes = uiaxes(new_grid);
                title(new_axes, event.Value)
                xlabel(new_axes, 't')
                ylabel(new_axes, 'Ca+')
                zlabel(new_axes, 'Z')
                new_axes.Layout.Row = [1 6];
                new_axes.Layout.Column = [1 5];
    
                % Create AddNeuronButton
                new_button = uibutton(new_grid, 'push');
                new_button.Layout.Row = 2;
                new_button.Layout.Column = 5;
                new_button.Text = 'Add Neuron';
                new_button.Tag = 'in_graph';
                new_button.ButtonPushedFcn = createCallbackFcn(app, @AddNeuron, true);
            else
                targAx = caller.Parent.Children(2).Children(1);
                title(targAx, [targAx.Title.String,', ', event.Value])
            end
        end

        function CreateNeuron(app,caller,event)
            % Create TL_Panel
            new_panel = uipanel(app.GridLayout);

            if grid_space(1) == max(size(app.GridLayout.ColumnWidth))
                new_panel.Layout.Column = grid_space(2)+1;
            else
                new_panel.Layout.Column = grid_space;
            end

            if grid_space(2) == max(size(app.GridLayout.RowHeight))
                new_panel.Layout.Row = grid_space(1)+1;
            end

            % Create TL_Grid
            new_grid = uigridlayout(app.TL_Panel);
            new_grid.ColumnWidth = {'1x'};
            new_grid.RowHeight = {'1x'};

            % Create TL_Button
            new_button = uibutton(app.TL_Grid, 'push');
            new_button.ButtonPushedFcn = createCallbackFcn(app, @AddNeuron, true);
            new_button.FontWeight = 'bold';
            new_button.Layout.Row = 1;
            new_button.Layout.Column = 1;
            new_button.Text = 'Add Neuron';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: TL_Button
        function TL_ButtonPushed(app, event)
            app.AddNeuron(event)
        end

        % Button pushed function: TR_Button
        function TR_ButtonPushed(app, event)
            app.AddNeuron(event)
        end

        % Callback function: not associated with a component
        function BL_ButtonPushed(app, event)
            app.AddNeuron(event)
        end

        % Callback function: not associated with a component
        function BR_ButtonPushed(app, event)
            app.AddNeuron(event)
        end

        % Button pushed function: BL_Button
        function BL_ButtonPushed2(app, event)
            app.AddNeuron(event)
        end

        % Button pushed function: BR_Button
        function BR_ButtonPushed2(app, event)
            app.AddNeuron(event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TL_Panel = uipanel(app.GridLayout);
            app.TL_Grid = uigridlayout(app.TL_Panel);
            app.TL_Button = uibutton(app.TL_Grid, 'push');
            app.TL_Button.ButtonPushedFcn = createCallbackFcn(app, @TL_ButtonPushed, true);
            app.TL_Button.Text = 'Add Neuron';
            app.TR_Panel = uipanel(app.GridLayout);
            app.TR_Grid = uigridlayout(app.TR_Panel);
            app.TR_Button = uibutton(app.TR_Grid, 'push');
            app.TR_Button.ButtonPushedFcn = createCallbackFcn(app, @TR_ButtonPushed, true);
            app.TR_Button.Text = 'Add Neuron';
            app.BL_Panel = uipanel(app.GridLayout);
            app.BL_Grid = uigridlayout(app.BL_Panel);
            app.BL_Button = uibutton(app.BL_Grid, 'push');
            app.BL_Button.ButtonPushedFcn = createCallbackFcn(app, @BL_ButtonPushed2, true);
            app.BL_Button.Text = 'Add Neuron';
            app.BR_Panel = uipanel(app.GridLayout);
            app.BR_Grid = uigridlayout(app.BR_Panel);
            app.BR_Button = uibutton(app.BR_Grid, 'push');
            app.BR_Button.ButtonPushedFcn = createCallbackFcn(app, @BR_ButtonPushed2, true);
            app.BR_Button.Text = 'Add Neuron';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flexneurodisp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end