classdef histogram_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ButtonGroup            matlab.ui.container.ButtonGroup
        dic_lock               matlab.ui.control.Switch
        Switch_6Label          matlab.ui.control.Label
        gfp_lock               matlab.ui.control.Switch
        Switch_5Label          matlab.ui.control.Label
        white_lock             matlab.ui.control.Switch
        Switch_4Label          matlab.ui.control.Label
        blue_lock              matlab.ui.control.Switch
        Switch_3Label          matlab.ui.control.Label
        green_lock             matlab.ui.control.Switch
        Switch_2Label          matlab.ui.control.Label
        red_lock               matlab.ui.control.Switch
        Label                  matlab.ui.control.Label
        MAXLabel               matlab.ui.control.Label
        MINLabel               matlab.ui.control.Label
        dic_max                matlab.ui.control.Spinner
        dic_min                matlab.ui.control.Spinner
        DICCheckBox            matlab.ui.control.CheckBox
        gfp_max                matlab.ui.control.Spinner
        gfp_min                matlab.ui.control.Spinner
        GFPCheckBox            matlab.ui.control.CheckBox
        white_max              matlab.ui.control.Spinner
        white_min              matlab.ui.control.Spinner
        blue_max               matlab.ui.control.Spinner
        blue_min               matlab.ui.control.Spinner
        green_max              matlab.ui.control.Spinner
        green_min              matlab.ui.control.Spinner
        red_max                matlab.ui.control.Spinner
        red_min                matlab.ui.control.Spinner
        WHITECheckBox          matlab.ui.control.CheckBox
        BLUECheckBox           matlab.ui.control.CheckBox
        GREENCheckBox          matlab.ui.control.CheckBox
        REDCheckBox            matlab.ui.control.CheckBox
        SettingsButtonGroup_2  matlab.ui.container.ButtonGroup
        EditField              matlab.ui.control.NumericEditField
        RESETButton            matlab.ui.control.Button
        BestFitButton          matlab.ui.control.Button
        GAMMALabel             matlab.ui.control.Label
        gamma_knob             matlab.ui.control.Knob
        GuideCheckBox          matlab.ui.control.CheckBox
        HTML                   matlab.ui.control.HTML
        UIAxes                 matlab.ui.control.UIAxes
        ImageAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        wormImg; % Worm image.
        image_gamma; % Main app's gamma var.
        imagefile; % Image backup.
        histr;
        histg;
        histb;
        histq;
        histw;
        histgfp;
        histdic;
        image_prefs;
    end

    methods (Access = private)
        
        function save(app)

            app.image_prefs.RGBW(:,:,1)(app.image_prefs.RGBW(:,:,1) < app.red_min.Value) = app.red_min.Value;
            app.image_prefs.RGBW(:,:,1)(app.image_prefs.RGBW(:,:,1) > app.red_max.Value) = app.red_max.Value;

            app.image_prefs.RGBW(:,:,2)(app.image_prefs.RGBW(:,:,2) < app.green_min.Value) = app.green_min.Value;
            app.image_prefs.RGBW(:,:,2)(app.image_prefs.RGBW(:,:,2) > app.green_max.Value) = app.green_max.Value;

            app.image_prefs.RGBW(:,:,3)(app.image_prefs.RGBW(:,:,3) < app.blue_min.Value) = app.blue_min.Value;
            app.image_prefs.RGBW(:,:,3)(app.image_prefs.RGBW(:,:,3) > app.blue_max.Value) = app.blue_max.Value;

            app.image_prefs.RGBW(:,:,4)(app.image_prefs.RGBW(:,:,4) < app.white_min.Value) = app.white_min.Value;
            app.image_prefs.RGBW(:,:,4)(app.image_prefs.RGBW(:,:,4) > app.white_max.Value) = app.white_max.Value;
            
            app.image_prefs.gfp(app.image_prefs.gfp < app.gfp_min.Value) = app.gfp_min.Value;
            app.image_prefs.gfp(app.image_prefs.gfp > app.gfp_max.Value) = app.gfp_max.Value;
            
            app.image_prefs.dic(app.image_prefs.dic < app.dic_min.Value) = app.dic_min.Value;
            app.image_prefs.dic(app.image_prefs.dic > app.dic_max.Value) = app.dic_max.Value;

            app.image_prefs.gamma(1:end) = app.image_prefs.gamma * app.gamma_knob.Value;

        end

        function ClearLinesFromAxes(app)
            axesHandlesToChildObjects = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
            end  
        end

        function valupdate(app,chan,var,val)
            
            chanSet = {'r','g','b','w','gfp','dic'};
            colSet = {'--r','--g','--b','--k','--y','--m'};
            arrSet = [1 2 3 4 5 6];
            chanMap = containers.Map(chanSet,arrSet);
            colMap = containers.Map(chanSet,colSet);

            temp = app.imagefile(:,:,chanMap(chan))
            %ClearLinesFromAxes(app)
            %xline(app.UIAxes,val,colMap(chan));
            %drawnow;

            if var == 'max'
                temp(temp > val) = val;
            elseif var == 'min'
                temp(temp < val) = val;
            else
            end
            
            app.wormImg(:,:,chanMap(chan)) = temp
            updateimage(app, app.wormImg);

        end

        function reset(app)

            app.red_min.Value = min(app.image_prefs.RGBW(:,:,1));
            app.red_max.Value = max(app.image_prefs.RGBW(:,:,1));

            app.green_min.Value = min(app.image_prefs.RGBW(:,:,2));
            app.green_max.Value = max(app.image_prefs.RGBW(:,:,2));

            app.blue_min.Value = min(app.image_prefs.RGBW(:,:,3));
            app.blue_max.Value = max(app.image_prefs.RGBW(:,:,3));

            app.gfp_min.Value = min(app.image_prefs.gfp);
            app.gfp_max.Value = max(app.image_prefs.gfp);
            
            app.dic_min.Value = min(app.image_prefs.dic);
            app.dic_max.Value = max(app.image_prefs.dic); 

        end

        function updateimage(app,imagefile)

            try
                clf(app.UIAXes)
                clf(app.ImageAxes)
            end

            try
                im = image(app.ImageAxes,imagefile);
                %[pixel_count, gray_levels] = imhist(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                sprintf('%s %s', mat2str(size(imagefile)), class(imagefile))
                return;
            end  
            
            hold(app.UIAxes,'on')

            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Plot all histograms with the same data for grayscale
                    %app.histq = histogram(app.UIAxes, gray_levels, 'EdgeColor', 'black', 'FaceAlpha', 0.65,'EdgeAlpha',0);
                    app.histr = histogram(app.UIAxes, imagefile,'DisplayStyle','stairs', 'EdgeColor', 'red', 'FaceAlpha', 0,'EdgeAlpha',0.9);
                    app.histg = histogram(app.UIAxes, imagefile, 'EdgeColor', 'green', 'FaceAlpha', 0,'EdgeAlpha',0.9,'DisplayStyle','stairs');
                    app.histb = histogram(app.UIAxes, imagefile, 'EdgeColor', 'blue', 'FaceAlpha', 0,'EdgeAlpha',0.9,'DisplayStyle','stairs');
                    app.histw = histogram(app.UIAxes, imagefile, 'EdgeColor', 'white', 'FaceAlpha', 0,'EdgeAlpha',1,'DisplayStyle','stairs');
                    app.histgfp = histogram(app.UIAxes, app.image_prefs.gfp, 'EdgeColor', 'yellow', 'FaceAlpha', 0,'EdgeAlpha',0.9,'DisplayStyle','stairs');
                    app.histdic = histogram(app.UIAxes, app.image_prefs.dic, 'EdgeColor', 'magenta', 'FaceAlpha', 0,'EdgeAlpha',0.9,'DisplayStyle','stairs');

                case 3                    
                    % Plot the histograms
                    %app.histq = histogram(app.UIAxes, gray_levels, 'EdgeColor', 'black', 'FaceAlpha', 0.65,'EdgeAlpha',0);
                    app.histr = histogram(app.UIAxes, imagefile(:,:,1), 'EdgeColor', 'red', 'FaceAlpha', 0,'EdgeAlpha',0.9,'DisplayStyle','stairs');
                    app.histg = histogram(app.UIAxes, imagefile(:,:,2), 'EdgeColor', 'green', 'FaceAlpha', 0,'EdgeAlpha',0.9,'DisplayStyle','stairs');
                    app.histb = histogram(app.UIAxes, imagefile(:,:,3), 'EdgeColor', 'blue', 'FaceAlpha', 0,'EdgeAlpha',0.9,'DisplayStyle','stairs');
                    app.histw = histogram(app.UIAxes, imagefile(:,:,4), 'EdgeColor', 'white', 'FaceAlpha', 0,'EdgeAlpha',0.5,'DisplayStyle','stairs');
                    app.histgfp = histogram(app.UIAxes, app.image_prefs.gfp, 'EdgeColor', 'yellow', 'FaceAlpha', 0,'EdgeAlpha',0.9,'DisplayStyle','stairs');
                    app.histdic = histogram(app.UIAxes, app.image_prefs.dic, 'EdgeColor', 'magenta', 'FaceAlpha', 0,'EdgeAlpha',0.9,'DisplayStyle','stairs');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    sprintf('%s %s', mat2str(size(imagefile)), class(imagefile))
                    return;

            end
                
                legend(app.UIAxes, 'Red','Green','Blue','White','GFP','DIC')

                % Get largest bin count
                %maxq = max(app.histq.BinCounts);
                maxr = max(app.histr.BinCounts);
                maxg = max(app.histg.BinCounts);
                maxb = max(app.histb.BinCounts);
                maxw = max(app.histw.BinCounts);
                maxgfp = max(app.histgfp.BinCounts);
                maxdic = max(app.histdic.BinCounts);
                maxcount = max([maxr maxg maxb maxw maxgfp maxdic]);
                
                % Set y axes limits based on largest bin count
                app.UIAxes.YLim = [0 maxcount];
                app.UIAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, wormImg, image_gamma, image_prefs)
            
            app.imagefile = wormImg;
            app.wormImg = wormImg;
            app.image_gamma = image_gamma;
            app.image_prefs = image_prefs;

            reset()
            
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, app.wormImg);
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Value changed function: REDCheckBox
        function REDCheckBoxValueChanged(app, event)
            if app.REDCheckBox.Value
                app.red_max.Value = max(app.image_prefs.RGBW(:,:,1))
                app.red_min.Value = min(app.image_prefs.RGBW(:,:,1))
                set( app.histr, 'Visible', 'on' )
            else
                save(app)
                app.red_max.Value = 0.01
                app.red_min.Value = 0
                set( app.histr, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: BLUECheckBox
        function BLUECheckBoxValueChanged(app, event)
            if app.BLUECheckBox.Value
                app.blue_max.Value = max(app.image_prefs.RGBW(:,:,3))
                app.blue_min.Value = min(app.image_prefs.RGBW(:,:,3))
                set( app.histb, 'Visible', 'on' )
            else
                save(app)
                app.blue_max.Value = 0.01
                app.blue_min.Value = 0
                set( app.histb, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: GREENCheckBox
        function GREENCheckBoxValueChanged(app, event)
            if app.GREENCheckBox.Value
                app.green_max.Value = max(app.image_prefs.RGBW(:,:,2))
                app.green_min.Value = min(app.image_prefs.RGBW(:,:,2))
                set( app.histg, 'Visible', 'on' )
            else
                save(app)
                app.green_max.Value = 0.01
                app.green_min.Value = 0
                set( app.histg, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: red_lock
        function red_lockValueChanged(app, event)
            if app.red_lock.Value == '🔒'
                set(app.red_min, 'Enable', 'off')
                set(app.red_max, 'Enable', 'off')
            else
                set(app.red_min, 'Enable', 'on')
                set(app.red_max, 'Enable', 'on')
            end
        end

        % Value changed function: green_lock
        function green_lockValueChanged(app, event)
            if app.green_lock.Value == '🔒'
                set(app.green_min, 'Enable', 'off')
                set(app.green_max, 'Enable', 'off')
            else
                set(app.green_min, 'Enable', 'on')
                set(app.green_max, 'Enable', 'on')
            end
        end

        % Value changed function: blue_lock
        function blue_lockValueChanged(app, event)
            if app.blue_lock.Value == '🔒'
                set(app.blue_min, 'Enable', 'off')
                set(app.blue_max, 'Enable', 'off')
            else
                set(app.blue_min, 'Enable', 'on')
                set(app.blue_max, 'Enable', 'on')
            end
        end

        % Value changed function: white_lock
        function white_lockValueChanged(app, event)
            if app.white_lock.Value == '🔒'
                set(app.white_min, 'Enable', 'off')
                set(app.white_max, 'Enable', 'off')
            else
                set(app.white_min, 'Enable', 'on')
                set(app.white_max, 'Enable', 'on')
            end
        end

        % Value changed function: gfp_lock
        function gfp_lockValueChanged(app, event)
            if app.gfp_lock.Value == '🔒'
                set(app.gfp_min, 'Enable', 'off')
                set(app.gfp_max, 'Enable', 'off')
            else
                set(app.gfp_min, 'Enable', 'on')
                set(app.gfp_max, 'Enable', 'on')
            end
        end

        % Value changed function: dic_lock
        function dic_lockValueChanged(app, event)
            if app.dic_lock.Value == '🔒'
                set(app.dic_min, 'Enable', 'off')
                set(app.dic_max, 'Enable', 'off')
            else
                set(app.dic_min, 'Enable', 'on')
                set(app.dic_max, 'Enable', 'on')
            end
        end

        % Value changed function: gamma_knob
        function gamma_knobValueChanged(app, event)
            temp = imadjust(app.imagefile,[],[],app.gamma_knob.Value);
            app.EditField.Value = app.gamma_knob.Value;
            save(app)
            updateimage(app, temp);
        end

        % Value changed function: red_max
        function red_maxValueChanged(app, event)
            valupdate(app,'r','max',app.red_max.Value)
        end

        % Value changed function: red_min
        function red_minValueChanged(app, event)
            valupdate(app,'r','min',app.red_min.Value)
        end

        % Value changed function: green_max
        function green_maxValueChanged(app, event)
            valupdate(app,'g','max',app.green_max.Value)
        end

        % Value changed function: green_min
        function green_minValueChanged(app, event)
            valupdate(app,'g','min',app.green_min.Value)
        end

        % Value changed function: WHITECheckBox
        function WHITECheckBoxValueChanged(app, event)
            if app.WHITECheckBox.Value
                app.white_max.Value = max(app.image_prefs.RGBW(:,:,4))
                app.white_min.Value = min(app.image_prefs.RGBW(:,:,4))
                set( app.histw, 'Visible', 'on' )
            else
                save(app)
                app.white_max.Value = 0.01
                app.white_min.Value = 0
                set( app.histw, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: GFPCheckBox
        function GFPCheckBoxValueChanged(app, event)
            if app.GFPCheckBox.Value
                app.gfp_max.Value = max(app.image_prefs.gfp)
                app.gfp_min.Value = min(app.image_prefs.gfp)
                set( app.histgfp, 'Visible', 'on' )
            else
                save(app)
                app.gfp_max.Value = 0.01
                app.gfp_min.Value = 0
                set( app.histgfp, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: DICCheckBox
        function DICCheckBoxValueChanged(app, event)
            if app.DICCheckBox.Value
                app.dic_max.Value = max(app.image_prefs.dic)
                app.dic_min.Value = min(app.image_prefs.dic)
                set( app.histdic, 'Visible', 'on' )
            else
                save(app)
                app.dic_max.Value = 0.01
                app.dic_min.Value = 0
                set( app.histdic, 'Visible', 'off' )
            end
            drawnow;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            lockCheck = [app.red_lock.Value, app.green_lock.Value, app.blue_lock.Value, app.white_lock.Value, app.gfp_lock.Value, app.dic_lock.Value]
            
            if any(lockCheck) == '🔒'
                msgbox('Several channels are locked -- please unlock them, then try again.')
            else
                save(app)
                delete(app)
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.wormImg = app.imagefile;
            reset()
            updateimage(app, app.wormImg);
        end

        % Value changing function: gamma_knob
        function gamma_knobValueChanging(app, event)
            app.EditField.Value = event.Value;
            temp = imadjust(app.imagefile,[],[],app.event.Value);
            updateimage(app, temp);
        end

        % Value changed function: white_min
        function white_minValueChanged(app, event)
            valupdate(app,'w','min',app.white_min.Value)
        end

        % Value changed function: white_max
        function white_maxValueChanged(app, event)
            valupdate(app,'w','max',app.white_max.Value)
        end

        % Value changed function: gfp_min
        function gfp_minValueChanged(app, event)
            valupdate(app,'gfp','min',app.gfp_min.Value)
        end

        % Value changed function: gfp_max
        function gfp_maxValueChanged(app, event)
            valupdate(app,'gfp','max',app.gfp_max.Value)
        end

        % Value changed function: dic_min
        function dic_minValueChanged(app, event)
            valupdate(app,'dic','min',app.dic_min.Value)
        end

        % Value changed function: dic_max
        function dic_maxValueChanged(app, event)
            valupdate(app,'dic','max',app.dic_max.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Worm Projection')
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'Intensity')
            ylabel(app.UIAxes, 'Pixels')
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<hr>';
            app.SettingsButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.SettingsButtonGroup_2.Title = 'Settings';
            app.GuideCheckBox = uicheckbox(app.SettingsButtonGroup_2);
            app.GuideCheckBox.Tooltip = {'Overlay histogram generated from NeuroPAL ID manual examples.'};
            app.GuideCheckBox.Text = 'Guide';
            app.gamma_knob = uiknob(app.SettingsButtonGroup_2, 'continuous');
            app.gamma_knob.Limits = [0 1];
            app.gamma_knob.MajorTicks = [0 0.5 1];
            app.gamma_knob.ValueChangedFcn = createCallbackFcn(app, @gamma_knobValueChanged, true);
            app.gamma_knob.ValueChangingFcn = createCallbackFcn(app, @gamma_knobValueChanging, true);
            app.GAMMALabel = uilabel(app.SettingsButtonGroup_2);
            app.GAMMALabel.Text = 'GAMMA';
            app.BestFitButton = uibutton(app.SettingsButtonGroup_2, 'push');
            app.BestFitButton.Text = 'Best Fit';
            app.RESETButton = uibutton(app.SettingsButtonGroup_2, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.EditField = uieditfield(app.SettingsButtonGroup_2, 'numeric');
            app.EditField.ValueDisplayFormat = '%.2f';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.REDCheckBox = uicheckbox(app.ButtonGroup);
            app.REDCheckBox.ValueChangedFcn = createCallbackFcn(app, @REDCheckBoxValueChanged, true);
            app.REDCheckBox.Text = 'RED';
            app.REDCheckBox.Value = true;
            app.GREENCheckBox = uicheckbox(app.ButtonGroup);
            app.GREENCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREENCheckBoxValueChanged, true);
            app.GREENCheckBox.Text = 'GREEN';
            app.GREENCheckBox.Value = true;
            app.BLUECheckBox = uicheckbox(app.ButtonGroup);
            app.BLUECheckBox.ValueChangedFcn = createCallbackFcn(app, @BLUECheckBoxValueChanged, true);
            app.BLUECheckBox.Text = 'BLUE';
            app.BLUECheckBox.Value = true;
            app.WHITECheckBox = uicheckbox(app.ButtonGroup);
            app.WHITECheckBox.ValueChangedFcn = createCallbackFcn(app, @WHITECheckBoxValueChanged, true);
            app.WHITECheckBox.Text = 'WHITE';
            app.WHITECheckBox.Value = true;
            app.red_min = uispinner(app.ButtonGroup);
            app.red_min.Step = 0.1;
            app.red_min.Limits = [0 1];
            app.red_min.ValueDisplayFormat = '%.2f';
            app.red_min.ValueChangedFcn = createCallbackFcn(app, @red_minValueChanged, true);
            app.red_min.Value = 0.5;
            app.red_max = uispinner(app.ButtonGroup);
            app.red_max.Step = 0.1;
            app.red_max.Limits = [0 1];
            app.red_max.ValueDisplayFormat = '%.2f';
            app.red_max.ValueChangedFcn = createCallbackFcn(app, @red_maxValueChanged, true);
            app.red_max.Value = 0.5;
            app.green_min = uispinner(app.ButtonGroup);
            app.green_min.Step = 0.1;
            app.green_min.Limits = [0 1];
            app.green_min.ValueDisplayFormat = '%.2f';
            app.green_min.ValueChangedFcn = createCallbackFcn(app, @green_minValueChanged, true);
            app.green_min.Value = 0.5;
            app.green_max = uispinner(app.ButtonGroup);
            app.green_max.Step = 0.1;
            app.green_max.Limits = [0 1];
            app.green_max.ValueDisplayFormat = '%.2f';
            app.green_max.ValueChangedFcn = createCallbackFcn(app, @green_maxValueChanged, true);
            app.green_max.Value = 0.5;
            app.blue_min = uispinner(app.ButtonGroup);
            app.blue_min.Step = 0.1;
            app.blue_min.Limits = [0 1];
            app.blue_min.ValueDisplayFormat = '%.2f';
            app.blue_min.Value = 0.5;
            app.blue_max = uispinner(app.ButtonGroup);
            app.blue_max.Step = 0.1;
            app.blue_max.Limits = [0 1];
            app.blue_max.ValueDisplayFormat = '%.2f';
            app.blue_max.Value = 0.5;
            app.white_min = uispinner(app.ButtonGroup);
            app.white_min.Step = 0.1;
            app.white_min.Limits = [0 1];
            app.white_min.ValueDisplayFormat = '%.2f';
            app.white_min.ValueChangedFcn = createCallbackFcn(app, @white_minValueChanged, true);
            app.white_min.Value = 0.5;
            app.white_max = uispinner(app.ButtonGroup);
            app.white_max.Step = 0.1;
            app.white_max.Limits = [0 1];
            app.white_max.ValueDisplayFormat = '%.2f';
            app.white_max.ValueChangedFcn = createCallbackFcn(app, @white_maxValueChanged, true);
            app.white_max.Value = 0.5;
            app.GFPCheckBox = uicheckbox(app.ButtonGroup);
            app.GFPCheckBox.ValueChangedFcn = createCallbackFcn(app, @GFPCheckBoxValueChanged, true);
            app.GFPCheckBox.Text = 'GFP';
            app.GFPCheckBox.Value = true;
            app.gfp_min = uispinner(app.ButtonGroup);
            app.gfp_min.Step = 0.1;
            app.gfp_min.Limits = [0 1];
            app.gfp_min.ValueDisplayFormat = '%.2f';
            app.gfp_min.ValueChangedFcn = createCallbackFcn(app, @gfp_minValueChanged, true);
            app.gfp_min.Value = 0.5;
            app.gfp_max = uispinner(app.ButtonGroup);
            app.gfp_max.Step = 0.1;
            app.gfp_max.Limits = [0 1];
            app.gfp_max.ValueDisplayFormat = '%.2f';
            app.gfp_max.ValueChangedFcn = createCallbackFcn(app, @gfp_maxValueChanged, true);
            app.gfp_max.Value = 0.5;
            app.DICCheckBox = uicheckbox(app.ButtonGroup);
            app.DICCheckBox.ValueChangedFcn = createCallbackFcn(app, @DICCheckBoxValueChanged, true);
            app.DICCheckBox.Text = 'DIC';
            app.DICCheckBox.Value = true;
            app.dic_min = uispinner(app.ButtonGroup);
            app.dic_min.Step = 0.1;
            app.dic_min.Limits = [0 1];
            app.dic_min.ValueDisplayFormat = '%.2f';
            app.dic_min.ValueChangedFcn = createCallbackFcn(app, @dic_minValueChanged, true);
            app.dic_min.Value = 0.5;
            app.dic_max = uispinner(app.ButtonGroup);
            app.dic_max.Step = 0.1;
            app.dic_max.Limits = [0 1];
            app.dic_max.ValueDisplayFormat = '%.2f';
            app.dic_max.ValueChangedFcn = createCallbackFcn(app, @dic_maxValueChanged, true);
            app.dic_max.Value = 0.5;
            app.MINLabel = uilabel(app.ButtonGroup);
            app.MINLabel.Text = 'MIN';
            app.MAXLabel = uilabel(app.ButtonGroup);
            app.MAXLabel.Text = 'MAX';
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Text = '';
            app.red_lock = uiswitch(app.ButtonGroup, 'slider');
            app.red_lock.Items = {' ', '🔒'};
            app.red_lock.ValueChangedFcn = createCallbackFcn(app, @red_lockValueChanged, true);
            app.red_lock.Value = ' ';
            app.Switch_2Label = uilabel(app.ButtonGroup);
            app.Switch_2Label.Text = '';
            app.green_lock = uiswitch(app.ButtonGroup, 'slider');
            app.green_lock.Items = {' ', '🔒'};
            app.green_lock.ValueChangedFcn = createCallbackFcn(app, @green_lockValueChanged, true);
            app.green_lock.Value = ' ';
            app.Switch_3Label = uilabel(app.ButtonGroup);
            app.Switch_3Label.Text = '';
            app.blue_lock = uiswitch(app.ButtonGroup, 'slider');
            app.blue_lock.Items = {' ', '🔒'};
            app.blue_lock.ValueChangedFcn = createCallbackFcn(app, @blue_lockValueChanged, true);
            app.blue_lock.Value = ' ';
            app.Switch_4Label = uilabel(app.ButtonGroup);
            app.Switch_4Label.Text = '';
            app.white_lock = uiswitch(app.ButtonGroup, 'slider');
            app.white_lock.Items = {' ', '🔒'};
            app.white_lock.ValueChangedFcn = createCallbackFcn(app, @white_lockValueChanged, true);
            app.white_lock.Value = ' ';
            app.Switch_5Label = uilabel(app.ButtonGroup);
            app.Switch_5Label.Text = '';
            app.gfp_lock = uiswitch(app.ButtonGroup, 'slider');
            app.gfp_lock.Items = {' ', '🔒'};
            app.gfp_lock.ValueChangedFcn = createCallbackFcn(app, @gfp_lockValueChanged, true);
            app.gfp_lock.Value = ' ';
            app.Switch_6Label = uilabel(app.ButtonGroup);
            app.Switch_6Label.Text = '';
            app.dic_lock = uiswitch(app.ButtonGroup, 'slider');
            app.dic_lock.Items = {' ', '🔒'};
            app.dic_lock.ValueChangedFcn = createCallbackFcn(app, @dic_lockValueChanged, true);
            app.dic_lock.Value = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogram_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end