classdef histogram_window_live < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ButtonGroup            matlab.ui.container.ButtonGroup
        LOCKLabel              matlab.ui.control.Label
        DICReset               matlab.ui.control.Button
        GFPReset               matlab.ui.control.Button
        WhiteReset             matlab.ui.control.Button
        BlueReset              matlab.ui.control.Button
        GreenReset             matlab.ui.control.Button
        RedReset               matlab.ui.control.Button
        dic_lock               matlab.ui.control.Switch
        Switch_6Label          matlab.ui.control.Label
        gfp_lock               matlab.ui.control.Switch
        Switch_5Label          matlab.ui.control.Label
        white_lock             matlab.ui.control.Switch
        Switch_4Label          matlab.ui.control.Label
        blue_lock              matlab.ui.control.Switch
        Switch_3Label          matlab.ui.control.Label
        green_lock             matlab.ui.control.Switch
        Switch_2Label          matlab.ui.control.Label
        red_lock               matlab.ui.control.Switch
        Label                  matlab.ui.control.Label
        MAXLabel               matlab.ui.control.Label
        MINLabel               matlab.ui.control.Label
        dic_max                matlab.ui.control.Spinner
        dic_min                matlab.ui.control.Spinner
        DICCheckBox            matlab.ui.control.CheckBox
        gfp_max                matlab.ui.control.Spinner
        gfp_min                matlab.ui.control.Spinner
        GFPCheckBox            matlab.ui.control.CheckBox
        white_max              matlab.ui.control.Spinner
        white_min              matlab.ui.control.Spinner
        blue_max               matlab.ui.control.Spinner
        blue_min               matlab.ui.control.Spinner
        green_max              matlab.ui.control.Spinner
        green_min              matlab.ui.control.Spinner
        red_max                matlab.ui.control.Spinner
        red_min                matlab.ui.control.Spinner
        WHITECheckBox          matlab.ui.control.CheckBox
        BLUECheckBox           matlab.ui.control.CheckBox
        GREENCheckBox          matlab.ui.control.CheckBox
        REDCheckBox            matlab.ui.control.CheckBox
        SettingsButtonGroup_2  matlab.ui.container.ButtonGroup
        EditField              matlab.ui.control.NumericEditField
        SetGuideButton         matlab.ui.control.Button
        BestFitButton          matlab.ui.control.Button
        GAMMALabel             matlab.ui.control.Label
        gamma_knob             matlab.ui.control.Knob
        GuideCheckBox          matlab.ui.control.CheckBox
        HTML                   matlab.ui.control.HTML
        UIAxes                 matlab.ui.control.UIAxes
        ImageAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        live_view; % Worm image.
        image_data;
        image_gamma; % Main app's gamma var.
        image_view; % Image backup.
        repo = [0 0; 0 0; 0 0; 0 0; 0 0; 0 0;]
        red_guide;
        green_guide;
        blue_guide;
        whites;
        histr;
        histg;
        histb;
        histw;
        histgfp;
        histdic;
        gfps;
        dics;
        preferences;
        gamma_RGBW_DIC_GFP_index = 1:6; % the indices for gamma values
        red;
        green;
        blue;
        white;
        gfp;
        dic; 
    end

    methods (Access = private)
    
        function ClearLinesFromAxes(app)
            axesHandlesToChildObjects = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(axesHandlesToChildObjects)
                delete(axesHandlesToChildObjects);
            end  
        end

        function reset(app)
            
            app.live_view = squeeze(max(app.image_view,[],3));

            try 
                valupdate(app,'r','max', max(app.image_view(:,:,1)));
                valupdate(app,'r','min', min(app.image_view(:,:,1)));
                valupdate(app,'g','max', max(app.image_view(:,:,2)));
                valupdate(app,'g','min', min(app.image_view(:,:,2)));
                valupdate(app,'b','max', max(app.image_view(:,:,3)));
                valupdate(app,'b','min', min(app.image_view(:,:,3)));
                valupdate(app,'w','max', max(app.whites));
                valupdate(app,'w','min', min(app.whites));
            end

            app.red_max.Value = 1;
            app.red_min.Value = 0;
            app.green_max.Value = 1;
            app.green_min.Value = 0;
            app.blue_max.Value = 1;
            app.blue_min.Value = 0;
            app.white_max.Value = 1;
            app.white_min.Value = 0;
        
            temp = app.image_view(:,:,1);

            updateimage(app, app.live_view);
        end

        function valupdate(app,chan,var,val)
            
            chanSet = {'r','g','b','w','gfp','dic'};
            %colSet = {'--r','--g','--b','--k','--y','--m'};
            lockSet = {app.red_lock.Value, app.green_lock.Value, app.blue_lock.Value, app.white_lock.Value, app.gfp_lock.Value, app.dic_lock.Value};
            arrSet = [1 2 3 4 5 6];
            chanMap = containers.Map(chanSet,arrSet);
            %colMap = containers.Map(chanSet,colSet);
            lockMap = containers.Map(chanSet,lockSet);

            if lockMap(chan) ~= '🔒' %#ok<*BDSCA> 
                
                if ismember(chan,['r','g','b'])
                    temp = app.live_view(:,:,chanMap(chan));
                elseif chan == 'w'
                    temp = app.whites;
                elseif chan == 'gfp'
                    temp = app.gfps;
                elseif chan =='dics'
                    temp = app.dics;
                end

                if var == 'max'
                    temp(temp > val) = val;
                elseif var == 'min'
                    temp(temp < val) = val;
                end
                
                app.live_view(:,:,chanMap(chan)) = temp;
                updateimage(app, app.live_view);

            else
                'Locked channel.' %#ok<NOPRT> 
            
            end

        end

        function updateimage(app,imagefile)
            
            %{
            if (app.WHITECheckBox.Value || app.DICCheckBox.Value || app.GFPCheckBox.Value)
                % Determine the channel=color assignments for displaying.
                color_indices = [app.red, app.green, app.blue];
    
                temp_view = app.image_data(:,:,:,color_indices);
    
                if app.WHITECheckBox.Value
                    % Compute the white channel.
                    wchannel = app.image_data(:,:,:,app.white);
                    wchannel(wchannel > app.white_max.Value) = app.white_max.Value;
    
                    % Adjust the gamma.
                    W_i = app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index
                    if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1
                        wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                    end
    
                    % Add the white channel.
                    temp_view = temp_view + repmat(wchannel, [1,1,1,3]);
                    
                    temp_view = double(temp_view)/double(max(temp_view(:)));
                end
    
                if app.DICCheckBox.Value
                    % Compute the DIC channel.
                    dic_channel = app.image_data(:,:,:,app.dic);
                    dic_channel(dic_channel > app.dic_max.Value) = app.dic_max.Value;
    
                    % Adjust the gamma.
                    DIC_i = app.gamma_RGBW_DIC_GFP_index(5); % DIC channel gamma index
                    if length(app.image_gamma) >= DIC_i && app.image_gamma(DIC_i) ~= 1
                        dic_channel = imadjustn(dic_channel,[],[], app.image_gamma(DIC_i));
                    end
    
                    % Add the DIC channel.
                    temp_view = temp_view + repmat(dic_channel, [1,1,1,3]);
                    
                    temp_view = double(temp_view)/double(max(temp_view(:)));
    
                    % Adjust the gamma.
                    % Note: the image only shows RGB. We added the other channels
                    % (W, DIC, GFP) to the RGB in order to show these as well.
                    temp_view = uint16(double(intmax('uint16')) * ...
                        double(temp_view)/double(max(temp_view(:))));
                    for c = 1:size(temp_view, 4)
                        if app.image_gamma(c) ~= 1
                            temp_view(:,:,:,c) = ...
                                imadjustn(squeeze(temp_view(:,:,:,c)),[],[], ...
                                app.image_gamma(c));
                        end
                    end
                end
    
                if app.GFPCheckBox.Value
                    % Compute the GFP channel.
                    gfp_color = app.preferences.instance().GFP_color;
                    gfp_channel = app.image_data(:,:,:,app.gfp);
                    gfp_channel(gfp_channel > app.gfp_max.Value) = app.gfp_max.Value;
    
                    % Adjust the gamma.
                    GFP_i = app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index
                    if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1
                        gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));
                    end
    
                    % Add the GFP channel.
                    gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                    gfp_channel(:,:,:,~gfp_color) = 0;
                    temp_view = temp_view + gfp_channel;
                end
    
                % Adjust the gamma.
                % Note: the image only shows RGB. We added the other channels
                % (W, DIC, GFP) to the RGB in order to show these as well.
                temp_view = uint16(double(intmax('uint16')) * ...
                    double(temp_view)/double(max(temp_view(:))));
                for c = 1:size(temp_view, 4)
                    if app.image_gamma(c) ~= 1
                        temp_view(:,:,:,c) = ...
                            imadjustn(squeeze(temp_view(:,:,:,c)),[],[], ...
                            app.image_gamma(c));
                    end
                end
                
                temp_view = double(temp_view)/double(max(temp_view(:)));
    
                imagefile = squeeze(max(temp_view,[],3));
            end
            
            %}

            try
                im = image(app.ImageAxes,imagefile);
                app.whites = im2gray(imagefile);
                %[pixel_count, gray_levels] = imhist(image_view);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                sprintf('%s %s', mat2str(size(imagefile)), class(imagefile))
                return;
            end  
            
            % Create histograms based on number of color channels

            % Plot the histograms
            app.histr = histogram(app.UIAxes, imagefile(:,:,1), 'EdgeColor', 'red', 'DisplayStyle','stairs');
            hold(app.UIAxes,'on')
            app.histg = histogram(app.UIAxes, imagefile(:,:,2), 'EdgeColor', 'green', 'DisplayStyle','stairs');
            app.histb = histogram(app.UIAxes, imagefile(:,:,3), 'EdgeColor', 'blue', 'DisplayStyle','stairs');
            app.histw = histogram(app.UIAxes, app.whites, 'EdgeColor', 'white', 'DisplayStyle','stairs');

            try
                app.histgfp = histogram(app.UIAxes, gfp_channel, 'EdgeColor', 'yellow', 'FaceAlpha', 0,'EdgeAlpha',0.9,'DisplayStyle','stairs');
                app.histdic = histogram(app.UIAxes, dic_channel, 'EdgeColor', 'magenta', 'FaceAlpha', 0,'EdgeAlpha',0.9,'DisplayStyle','stairs');
            end
                
            legend(app.UIAxes, 'Red','Green','Blue','White')

            % Get largest bin count
            maxr = max(app.histr.BinCounts);
            maxg = max(app.histg.BinCounts);
            maxb = max(app.histb.BinCounts);
            maxw = max(app.histw.BinCounts);

            try
                maxgfp = max(app.histgfp.BinCounts);
                maxdic = max(app.histdic.BinCounts);
                maxcount = max([maxr maxg maxb maxw maxgfp maxdic]);
            catch
                maxcount = max([maxr maxg maxb maxw]);
            end
            
            % Set y axes limits based on largest bin count
            set(app.UIAxes,'Color','#787878')
            app.UIAxes.YLim = [0 maxcount];
            app.UIAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');

            hold(app.UIAxes,'off')
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, neuropal_app, image_data, wormImg, image_gamma, gfps, dics, prefs, red, green, blue, white, gfp, dic)
            
            app.image_data = image_data;
            app.image_view = wormImg;
            app.live_view = squeeze(max(wormImg,[],3));
            app.gfps = gfps;
            app.dics = dics;
            app.image_gamma = image_gamma;
            app.preferences = prefs;
            app.red = red;
            app.green = green;
            app.blue = blue;
            app.white = white;
            app.gfp = gfp;
            app.dic = dic;            

            % Configure image axes
            %app.ImageAxes.Visible = 'off';
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, app.live_view);
            reset(app);
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Value changed function: REDCheckBox
        function REDCheckBoxValueChanged(app, event)
            if app.REDCheckBox.Value
                app.red_max.Value = app.repo(1,1);
                app.red_min.Value = app.repo(2,1);
                set( app.histr, 'Visible', 'on' )
            else
                app.repo(1,1) = app.red_max.Value;
                app.repo(2,1) = app.red_min.Value;
                app.red_max.Value = 0;
                app.red_min.Value = 0;
                set( app.histr, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: BLUECheckBox
        function BLUECheckBoxValueChanged(app, event)
            if app.BLUECheckBox.Value
                app.red_max.Value = app.repo(1,3);
                app.red_min.Value = app.repo(2,3);
                set( app.histb, 'Visible', 'on' )
            else
                app.repo(1,3) = app.blue_max.Value;
                app.repo(2,3) = app.blue_min.Value;
                app.red_max.Value = 0;
                app.red_min.Value = 0;
                set( app.histb, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: GREENCheckBox
        function GREENCheckBoxValueChanged(app, event)
            if app.GREENCheckBox.Value
                app.green_max.Value = app.repo(1,2);
                app.green_min.Value = app.repo(2,2);
                set( app.histg, 'Visible', 'on' )
            else
                app.repo(1,2) = app.green_max.Value;
                app.repo(2,2) = app.green_min.Value;
                app.green_max.Value = 0;
                app.green_min.Value = 0;
                set( app.histg, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: red_lock
        function red_lockValueChanged(app, event)
            if app.red_lock.Value == '🔒'
                set(app.red_min, 'Enable', 'off')
                set(app.red_max, 'Enable', 'off')
            else
                set(app.red_min, 'Enable', 'on')
                set(app.red_max, 'Enable', 'on')
            end
        end

        % Value changed function: green_lock
        function green_lockValueChanged(app, event)
            if app.green_lock.Value == '🔒'
                set(app.green_min, 'Enable', 'off')
                set(app.green_max, 'Enable', 'off')
            else
                set(app.green_min, 'Enable', 'on')
                set(app.green_max, 'Enable', 'on')
            end
        end

        % Value changed function: blue_lock
        function blue_lockValueChanged(app, event)
            if app.blue_lock.Value == '🔒'
                set(app.blue_min, 'Enable', 'off')
                set(app.blue_max, 'Enable', 'off')
            else
                set(app.blue_min, 'Enable', 'on')
                set(app.blue_max, 'Enable', 'on')
            end
        end

        % Value changed function: white_lock
        function white_lockValueChanged(app, event)
            if app.white_lock.Value == '🔒'
                set(app.white_min, 'Enable', 'off')
                set(app.white_max, 'Enable', 'off')
            else
                set(app.white_min, 'Enable', 'on')
                set(app.white_max, 'Enable', 'on')
            end
        end

        % Value changed function: gfp_lock
        function gfp_lockValueChanged(app, event)
            if app.gfp_lock.Value == '🔒'
                set(app.gfp_min, 'Enable', 'off')
                set(app.gfp_max, 'Enable', 'off')
            else
                set(app.gfp_min, 'Enable', 'on')
                set(app.gfp_max, 'Enable', 'on')
            end
        end

        % Value changed function: dic_lock
        function dic_lockValueChanged(app, event)
            if app.dic_lock.Value == '🔒'
                set(app.dic_min, 'Enable', 'off')
                set(app.dic_max, 'Enable', 'off')
            else
                set(app.dic_min, 'Enable', 'on')
                set(app.dic_max, 'Enable', 'on')
            end
        end

        % Value changed function: gamma_knob
        function gamma_knobValueChanged(app, event)
            temp = imadjust(squeeze(max(app.image_view,[],3)),[],[],app.gamma_knob.Value);
            app.EditField.Value = app.gamma_knob.Value;
            
            updateimage(app, temp);
        end

        % Value changed function: red_max
        function red_maxValueChanged(app, event)
            valupdate(app,'r','max',app.red_max.Value)
        end

        % Value changed function: red_min
        function red_minValueChanged(app, event)
            valupdate(app,'r','min',app.red_min.Value)
        end

        % Value changed function: green_max
        function green_maxValueChanged(app, event)
            valupdate(app,'g','max',app.green_max.Value)
        end

        % Value changed function: green_min
        function green_minValueChanged(app, event)
            valupdate(app,'g','min',app.green_min.Value)
        end

        % Value changed function: WHITECheckBox
        function WHITECheckBoxValueChanged(app, event)
            if app.WHITECheckBox.Value
                app.white_max.Value = app.repo(1,4);
                app.white_min.Value = app.repo(2,4);
                set( app.histw, 'Visible', 'on' )
            else
                app.repo(1,4) = app.white_max.Value;
                app.repo(2,4) = app.white_min.Value;
                app.white_max.Value = 0;
                app.white_min.Value = 0;
                set( app.histw, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: GFPCheckBox
        function GFPCheckBoxValueChanged(app, event)
            if app.GFPCheckBox.Value
                app.gfp_max.Value = app.repo(1,5);
                app.gfp_min.Value = app.repo(2,5);
                set( app.histgfp, 'Visible', 'on' )
            else
                app.repo(1,5) = app.gfp_max.Value;
                app.repo(2,5) = app.gfp_min.Value;
                app.gfp_max.Value = 0;
                app.gfp_min.Value = 0;
                set( app.histgfp, 'Visible', 'off' )
            end
            drawnow;
        end

        % Value changed function: DICCheckBox
        function DICCheckBoxValueChanged(app, event)
            if app.DICCheckBox.Value
                app.dic_max.Value = app.repo(1,6);
                app.dic_min.Value = app.repo(2,6);
                set( app.histdic, 'Visible', 'on' )
            else
                app.repo(1,6) = app.dic_max.Value;
                app.repo(2,6) = app.dic_min.Value;
                app.dic_max.Value = 0;
                app.dic_min.Value = 0;
                set( app.histdic, 'Visible', 'off' )
            end
            drawnow;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            lockCheck = [app.red_lock.Value, app.green_lock.Value, app.blue_lock.Value, app.white_lock.Value, app.gfp_lock.Value, app.dic_lock.Value];
            
            if any(lockCheck) == '🔒'
                msgbox('Several channels are locked -- please unlock them, then try again.')
            else
                delete(app)
            end
        end

        % Button pushed function: SetGuideButton
        function SetGuideButtonPushed(app, event)
            answer = questdlg('Saving this file as guide.mat will replace the histogram guide.','Warning!','Continue','Cancel', 'Cancel');
            present_array = app.live_view;

            switch answer
                case 'Continue'
                    save('guide.mat','present_array')
                case 'Cancel'
                    return
            end
        end

        % Callback function
        function gamma_knobValueChanging(app, event)
            app.EditField.Value = event.Value;
            temp = imadjust(squeeze(max(app.image_view,[],3)),[],[],app.event.Value);
            updateimage(app, temp);
        end

        % Value changed function: white_min
        function white_minValueChanged(app, event)
            valupdate(app,'w','min',app.white_min.Value)
        end

        % Value changed function: white_max
        function white_maxValueChanged(app, event)
            valupdate(app,'w','max',app.white_max.Value)
        end

        % Value changed function: gfp_min
        function gfp_minValueChanged(app, event)
            valupdate(app,'gfp','min',app.gfp_min.Value)
        end

        % Value changed function: gfp_max
        function gfp_maxValueChanged(app, event)
            valupdate(app,'gfp','max',app.gfp_max.Value)
        end

        % Value changed function: dic_min
        function dic_minValueChanged(app, event)
            valupdate(app,'dic','min',app.dic_min.Value)
        end

        % Value changed function: dic_max
        function dic_maxValueChanged(app, event)
            valupdate(app,'dic','max',app.dic_max.Value)
        end

        % Button pushed function: RedReset
        function RedResetButtonPushed(app, event)
            app.red_max.Value = 1;
            app.red_min.Value = 0;
            temp = squeeze(max(app.image_view,[],3));
            app.live_view(:,:,1) = temp(:,:,1);
            updateimage(app, app.live_view);
        end

        % Button pushed function: GreenReset
        function GreenResetButtonPushed(app, event)
            app.green_max.Value = 1;
            app.green_min.Value = 0;
            temp = squeeze(max(app.image_view,[],3));
            app.live_view(:,:,2) = temp(:,:,2);
            updateimage(app, app.live_view);
        end

        % Button pushed function: BlueReset
        function BlueResetButtonPushed(app, event)
            app.blue_max.Value = 1;
            app.blue_min.Value = 0;
            temp = squeeze(max(app.image_view,[],3));
            app.live_view(:,:,3) = temp(:,:,3);
            updateimage(app, app.live_view);
        end

        % Button pushed function: WhiteReset
        function WhiteResetButtonPushed(app, event)
            app.white_max.Value = 1;
            app.white_min.Value = 0;
            %app.live_view(:,:,1) = app.image_view(:,:,1);
            updateimage(app, app.live_view);
        end

        % Value changed function: GuideCheckBox
        function GuideCheckBoxValueChanged(app, event)
            if app.GuideCheckBox.Value
                guide = importdata('guide.mat');
                hold(app.UIAxes,'on')
                app.red_guide = histogram(app.UIAxes, guide(:,:,1), 'FaceColor', 'red', 'FaceAlpha',0.4,'EdgeAlpha',0, 'tag','redg');
                app.green_guide = histogram(app.UIAxes, guide(:,:,2), 'FaceColor', 'green', 'FaceAlpha',0.4,'EdgeAlpha',0, 'tag','greeng');
                app.blue_guide = histogram(app.UIAxes, guide(:,:,3), 'FaceColor', 'blue', 'FaceAlpha',0.4,'EdgeAlpha',0, 'tag','blueg');
                %app.histw = histogram(app.UIAxes, app.whites, 'FaceColor', 'white' 'FaceAlpha',0.4,'EdgeAlpha',0,'tag','whiteg');
            else
                set( app.red_guide, 'Visible', 'off' )
                set( app.green_guide, 'Visible', 'off' )
                set( app.blue_guide, 'Visible', 'off' )
                updateimage(app, app.live_view);
            end
        end

        % Value changed function: blue_min
        function blue_minValueChanged(app, event)
            valupdate(app,'b','min',app.blue_max.Value)
        end

        % Value changed function: blue_max
        function blue_maxValueChanged(app, event)
            valupdate(app,'b','max',app.blue_max.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Worm Projection')
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'Intensity')
            ylabel(app.UIAxes, 'Pixels')
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<hr>';
            app.SettingsButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.SettingsButtonGroup_2.Title = 'Settings';
            app.GuideCheckBox = uicheckbox(app.SettingsButtonGroup_2);
            app.GuideCheckBox.ValueChangedFcn = createCallbackFcn(app, @GuideCheckBoxValueChanged, true);
            app.GuideCheckBox.Tooltip = {'Overlay histogram generated from NeuroPAL ID manual examples.'};
            app.GuideCheckBox.Text = 'Guide';
            app.gamma_knob = uiknob(app.SettingsButtonGroup_2, 'continuous');
            app.gamma_knob.Limits = [0 1];
            app.gamma_knob.MajorTicks = [0 0.5 1];
            app.gamma_knob.ValueChangedFcn = createCallbackFcn(app, @gamma_knobValueChanged, true);
            app.gamma_knob.Value = 1;
            app.GAMMALabel = uilabel(app.SettingsButtonGroup_2);
            app.GAMMALabel.Text = 'GAMMA';
            app.BestFitButton = uibutton(app.SettingsButtonGroup_2, 'push');
            app.BestFitButton.Text = 'Best Fit';
            app.SetGuideButton = uibutton(app.SettingsButtonGroup_2, 'push');
            app.SetGuideButton.ButtonPushedFcn = createCallbackFcn(app, @SetGuideButtonPushed, true);
            app.SetGuideButton.Text = 'Set Guide';
            app.EditField = uieditfield(app.SettingsButtonGroup_2, 'numeric');
            app.EditField.ValueDisplayFormat = '%.2f';
            app.EditField.Value = 1;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.REDCheckBox = uicheckbox(app.ButtonGroup);
            app.REDCheckBox.ValueChangedFcn = createCallbackFcn(app, @REDCheckBoxValueChanged, true);
            app.REDCheckBox.Text = 'RED';
            app.REDCheckBox.Value = true;
            app.GREENCheckBox = uicheckbox(app.ButtonGroup);
            app.GREENCheckBox.ValueChangedFcn = createCallbackFcn(app, @GREENCheckBoxValueChanged, true);
            app.GREENCheckBox.Text = 'GREEN';
            app.GREENCheckBox.Value = true;
            app.BLUECheckBox = uicheckbox(app.ButtonGroup);
            app.BLUECheckBox.ValueChangedFcn = createCallbackFcn(app, @BLUECheckBoxValueChanged, true);
            app.BLUECheckBox.Text = 'BLUE';
            app.BLUECheckBox.Value = true;
            app.WHITECheckBox = uicheckbox(app.ButtonGroup);
            app.WHITECheckBox.ValueChangedFcn = createCallbackFcn(app, @WHITECheckBoxValueChanged, true);
            app.WHITECheckBox.Text = 'WHITE';
            app.WHITECheckBox.Value = true;
            app.red_min = uispinner(app.ButtonGroup);
            app.red_min.Step = 0.1;
            app.red_min.Limits = [0 1];
            app.red_min.ValueDisplayFormat = '%.2f';
            app.red_min.ValueChangedFcn = createCallbackFcn(app, @red_minValueChanged, true);
            app.red_min.Value = 0.5;
            app.red_max = uispinner(app.ButtonGroup);
            app.red_max.Step = 0.1;
            app.red_max.Limits = [0 1];
            app.red_max.ValueDisplayFormat = '%.2f';
            app.red_max.ValueChangedFcn = createCallbackFcn(app, @red_maxValueChanged, true);
            app.red_max.Value = 0.5;
            app.green_min = uispinner(app.ButtonGroup);
            app.green_min.Step = 0.1;
            app.green_min.Limits = [0 1];
            app.green_min.ValueDisplayFormat = '%.2f';
            app.green_min.ValueChangedFcn = createCallbackFcn(app, @green_minValueChanged, true);
            app.green_min.Value = 0.5;
            app.green_max = uispinner(app.ButtonGroup);
            app.green_max.Step = 0.1;
            app.green_max.Limits = [0 1];
            app.green_max.ValueDisplayFormat = '%.2f';
            app.green_max.ValueChangedFcn = createCallbackFcn(app, @green_maxValueChanged, true);
            app.green_max.Value = 0.5;
            app.blue_min = uispinner(app.ButtonGroup);
            app.blue_min.Step = 0.1;
            app.blue_min.Limits = [0 1];
            app.blue_min.ValueDisplayFormat = '%.2f';
            app.blue_min.ValueChangedFcn = createCallbackFcn(app, @blue_minValueChanged, true);
            app.blue_min.Value = 0.5;
            app.blue_max = uispinner(app.ButtonGroup);
            app.blue_max.Step = 0.1;
            app.blue_max.Limits = [0 1];
            app.blue_max.ValueDisplayFormat = '%.2f';
            app.blue_max.ValueChangedFcn = createCallbackFcn(app, @blue_maxValueChanged, true);
            app.blue_max.Value = 0.5;
            app.white_min = uispinner(app.ButtonGroup);
            app.white_min.Step = 0.1;
            app.white_min.Limits = [0 1];
            app.white_min.ValueDisplayFormat = '%.2f';
            app.white_min.ValueChangedFcn = createCallbackFcn(app, @white_minValueChanged, true);
            app.white_min.Value = 0.5;
            app.white_max = uispinner(app.ButtonGroup);
            app.white_max.Step = 0.1;
            app.white_max.Limits = [0 1];
            app.white_max.ValueDisplayFormat = '%.2f';
            app.white_max.ValueChangedFcn = createCallbackFcn(app, @white_maxValueChanged, true);
            app.white_max.Value = 0.5;
            app.GFPCheckBox = uicheckbox(app.ButtonGroup);
            app.GFPCheckBox.ValueChangedFcn = createCallbackFcn(app, @GFPCheckBoxValueChanged, true);
            app.GFPCheckBox.Text = 'GFP';
            app.gfp_min = uispinner(app.ButtonGroup);
            app.gfp_min.Step = 0.1;
            app.gfp_min.Limits = [0 1];
            app.gfp_min.ValueDisplayFormat = '%.2f';
            app.gfp_min.ValueChangedFcn = createCallbackFcn(app, @gfp_minValueChanged, true);
            app.gfp_max = uispinner(app.ButtonGroup);
            app.gfp_max.Step = 0.1;
            app.gfp_max.Limits = [0 1];
            app.gfp_max.ValueDisplayFormat = '%.2f';
            app.gfp_max.ValueChangedFcn = createCallbackFcn(app, @gfp_maxValueChanged, true);
            app.DICCheckBox = uicheckbox(app.ButtonGroup);
            app.DICCheckBox.ValueChangedFcn = createCallbackFcn(app, @DICCheckBoxValueChanged, true);
            app.DICCheckBox.Text = 'DIC';
            app.dic_min = uispinner(app.ButtonGroup);
            app.dic_min.Step = 0.1;
            app.dic_min.Limits = [0 1];
            app.dic_min.ValueDisplayFormat = '%.2f';
            app.dic_min.ValueChangedFcn = createCallbackFcn(app, @dic_minValueChanged, true);
            app.dic_max = uispinner(app.ButtonGroup);
            app.dic_max.Step = 0.1;
            app.dic_max.Limits = [0 1];
            app.dic_max.ValueDisplayFormat = '%.2f';
            app.dic_max.ValueChangedFcn = createCallbackFcn(app, @dic_maxValueChanged, true);
            app.MINLabel = uilabel(app.ButtonGroup);
            app.MINLabel.Text = 'MIN';
            app.MAXLabel = uilabel(app.ButtonGroup);
            app.MAXLabel.Text = 'MAX';
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Text = '';
            app.red_lock = uiswitch(app.ButtonGroup, 'slider');
            app.red_lock.Items = {' ', '🔒'};
            app.red_lock.ValueChangedFcn = createCallbackFcn(app, @red_lockValueChanged, true);
            app.red_lock.Value = ' ';
            app.Switch_2Label = uilabel(app.ButtonGroup);
            app.Switch_2Label.Text = '';
            app.green_lock = uiswitch(app.ButtonGroup, 'slider');
            app.green_lock.Items = {' ', '🔒'};
            app.green_lock.ValueChangedFcn = createCallbackFcn(app, @green_lockValueChanged, true);
            app.green_lock.Value = ' ';
            app.Switch_3Label = uilabel(app.ButtonGroup);
            app.Switch_3Label.Text = '';
            app.blue_lock = uiswitch(app.ButtonGroup, 'slider');
            app.blue_lock.Items = {' ', '🔒'};
            app.blue_lock.ValueChangedFcn = createCallbackFcn(app, @blue_lockValueChanged, true);
            app.blue_lock.Value = ' ';
            app.Switch_4Label = uilabel(app.ButtonGroup);
            app.Switch_4Label.Text = '';
            app.white_lock = uiswitch(app.ButtonGroup, 'slider');
            app.white_lock.Items = {' ', '🔒'};
            app.white_lock.ValueChangedFcn = createCallbackFcn(app, @white_lockValueChanged, true);
            app.white_lock.Value = ' ';
            app.Switch_5Label = uilabel(app.ButtonGroup);
            app.Switch_5Label.Text = '';
            app.gfp_lock = uiswitch(app.ButtonGroup, 'slider');
            app.gfp_lock.Items = {' ', '🔒'};
            app.gfp_lock.ValueChangedFcn = createCallbackFcn(app, @gfp_lockValueChanged, true);
            app.gfp_lock.Value = ' ';
            app.Switch_6Label = uilabel(app.ButtonGroup);
            app.Switch_6Label.Text = '';
            app.dic_lock = uiswitch(app.ButtonGroup, 'slider');
            app.dic_lock.Items = {' ', '🔒'};
            app.dic_lock.ValueChangedFcn = createCallbackFcn(app, @dic_lockValueChanged, true);
            app.dic_lock.Value = ' ';
            app.RedReset = uibutton(app.ButtonGroup, 'push');
            app.RedReset.ButtonPushedFcn = createCallbackFcn(app, @RedResetButtonPushed, true);
            app.RedReset.Text = '↺';
            app.GreenReset = uibutton(app.ButtonGroup, 'push');
            app.GreenReset.ButtonPushedFcn = createCallbackFcn(app, @GreenResetButtonPushed, true);
            app.GreenReset.Text = '↺';
            app.BlueReset = uibutton(app.ButtonGroup, 'push');
            app.BlueReset.ButtonPushedFcn = createCallbackFcn(app, @BlueResetButtonPushed, true);
            app.BlueReset.Text = '↺';
            app.WhiteReset = uibutton(app.ButtonGroup, 'push');
            app.WhiteReset.ButtonPushedFcn = createCallbackFcn(app, @WhiteResetButtonPushed, true);
            app.WhiteReset.Text = '↺';
            app.GFPReset = uibutton(app.ButtonGroup, 'push');
            app.GFPReset.Text = '↺';
            app.DICReset = uibutton(app.ButtonGroup, 'push');
            app.DICReset.Text = '↺';
            app.LOCKLabel = uilabel(app.ButtonGroup);
            app.LOCKLabel.Text = 'LOCK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogram_window_live(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end