classdef newtrace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuronActivityTracingUIFigure  matlab.ui.Figure
        GridSearchPanel                matlab.ui.container.Panel
        GridLayout54                   matlab.ui.container.GridLayout
        CancelGridSearchButton         matlab.ui.control.Button
        StartGridSearchButton          matlab.ui.control.Button
        Panel_66                       matlab.ui.container.Panel
        GridLayout57                   matlab.ui.container.GridLayout
        minutesLabel                   matlab.ui.control.Label
        EditField_8                    matlab.ui.control.NumericEditField
        CalculateButton                matlab.ui.control.Button
        EstimatedSearchtimeLabel       matlab.ui.control.Label
        SearchrangesPanel              matlab.ui.container.Panel
        GridLayout56                   matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        VideoPreprocessingPanel        matlab.ui.container.Panel
        GridLayout55                   matlab.ui.container.GridLayout
        ChannelstoincludeLabel         matlab.ui.control.Label
        ListBox_3                      matlab.ui.control.ListBox
        OnlyappliestogridsearchLabel   matlab.ui.control.Label
        DownsamplingFactortEditField   matlab.ui.control.NumericEditField
        DownsamplingFactortEditFieldLabel  matlab.ui.control.Label
        DownsamplingFactorzEditField   matlab.ui.control.NumericEditField
        DownsamplingFactorzEditFieldLabel  matlab.ui.control.Label
        DownsamplingFactorxyEditField  matlab.ui.control.NumericEditField
        DownsamplingFactorxyEditFieldLabel  matlab.ui.control.Label
        TrackingSelectionPanel_2       matlab.ui.container.Panel
        GridLayout49_2                 matlab.ui.container.GridLayout
        TrackspecificregionButton_2    matlab.ui.control.Button
        WorldlinestoincludeLabel       matlab.ui.control.Label
        ListBox_2                      matlab.ui.control.ListBox
        StartingFrameLabel_2           matlab.ui.control.Label
        EditField_7                    matlab.ui.control.NumericEditField
        EndFrameLabel_2                matlab.ui.control.Label
        EditField_6                    matlab.ui.control.NumericEditField
        AdvancedParameterPanel         matlab.ui.container.Panel
        NeuronMarkerGrid_2             matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        RegularizationLossCoefficientsTab  matlab.ui.container.Tab
        GridLayout35                   matlab.ui.container.GridLayout
        DescriptionLabel               matlab.ui.control.Label
        ParameterLabel                 matlab.ui.control.Label
        GridSearchLabel                matlab.ui.control.Label
        nn_max_grid                    matlab.ui.control.CheckBox
        l_n_mode_grid                  matlab.ui.control.CheckBox
        l_n_grid                       matlab.ui.control.CheckBox
        l_t_grid                       matlab.ui.control.CheckBox
        l_d_grid                       matlab.ui.control.CheckBox
        nn_max                         matlab.ui.control.NumericEditField
        EditFieldLabel_5               matlab.ui.control.Label
        nn_maxLabel                    matlab.ui.control.Label
        l_n_mode                       matlab.ui.control.DropDown
        MethodtouseforcalculatingNLabel  matlab.ui.control.Label
        NmodeLabel                     matlab.ui.control.Label
        l_n                            matlab.ui.control.NumericEditField
        EditField4Label                matlab.ui.control.Label
        NLabel                         matlab.ui.control.Label
        l_t                            matlab.ui.control.NumericEditField
        EditField2Label                matlab.ui.control.Label
        tLabel                         matlab.ui.control.Label
        l_d                            matlab.ui.control.NumericEditField
        EditField3Label                matlab.ui.control.Label
        dLabel                         matlab.ui.control.Label
        GradientLearningRateTab        matlab.ui.container.Tab
        GridLayout32                   matlab.ui.container.GridLayout
        DescriptionLabel_2             matlab.ui.control.Label
        ParameterLabel_2               matlab.ui.control.Label
        GridSearchLabel_2              matlab.ui.control.Label
        z_comp_grid                    matlab.ui.control.CheckBox
        lr_ceil_grid                   matlab.ui.control.CheckBox
        lr_floor_grid                  matlab.ui.control.CheckBox
        lr_coeff_grid                  matlab.ui.control.CheckBox
        clip_grad_grid                 matlab.ui.control.CheckBox
        ZCompLabel                     matlab.ui.control.Label
        z_comp                         matlab.ui.control.NumericEditField
        Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel  matlab.ui.control.Label
        clip_grad                      matlab.ui.control.NumericEditField
        EditFieldLabel_7               matlab.ui.control.Label
        clip_gradLabel                 matlab.ui.control.Label
        lr_coeff                       matlab.ui.control.NumericEditField
        EditField6Label                matlab.ui.control.Label
        LRCoeffLabel                   matlab.ui.control.Label
        lr_floor                       matlab.ui.control.NumericEditField
        Minimumvalueforinitiallearningratedefault002EditFieldLabel  matlab.ui.control.Label
        LRFloorLabel                   matlab.ui.control.Label
        lr_ceiling                     matlab.ui.control.NumericEditField
        EditFieldLabel_6               matlab.ui.control.Label
        lr_ceilingLabel                matlab.ui.control.Label
        RetrievalFunctionTab           matlab.ui.container.Tab
        GridLayout50                   matlab.ui.container.GridLayout
        TextArea_6                     matlab.ui.control.TextArea
        OpenZephIRdocumentationButton  matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        OpenGridSearchMenuButton       matlab.ui.control.Button
        AdjustNeuronMarkerAlignmentPanel  matlab.ui.container.Panel
        NeuronMarkerGrid               matlab.ui.container.GridLayout
        Panel_67                       matlab.ui.container.Panel
        GridLayout48_2                 matlab.ui.container.GridLayout
        EnableinvolumeeditingCheckBox  matlab.ui.control.CheckBox
        EnableClickDragCheckBox        matlab.ui.control.CheckBox
        ShowallneuronsCheckBox_2       matlab.ui.control.CheckBox
        OverlayFrameMIPCheckBox_2      matlab.ui.control.CheckBox
        OverlayNeuroPALvolCheckBox_2   matlab.ui.control.CheckBox
        OverlaylastIDdframeCheckBox    matlab.ui.control.CheckBox
        AutojumponIDCheckBox_2         matlab.ui.control.CheckBox
        ResetMarkerPositionsButton     matlab.ui.control.Button
        Panel_64                       matlab.ui.container.Panel
        GridLayout53_2                 matlab.ui.container.GridLayout
        MarkerSizeSlider_2             matlab.ui.control.Slider
        MarkerSizeLabel_2              matlab.ui.control.Label
        SelectNeuronSubsetButton       matlab.ui.control.Button
        CancelButton_2                 matlab.ui.control.Button
        ImportNeuronsfromCSVButton     matlab.ui.control.Button
        MoveNeuronMarkersPanel         matlab.ui.container.Panel
        GridLayout16_2                 matlab.ui.container.GridLayout
        Panel_60                       matlab.ui.container.Panel
        GridLayout18                   matlab.ui.container.GridLayout
        degreesatatimeLabel            matlab.ui.control.Label
        RotateEditField                matlab.ui.control.NumericEditField
        pixelsandLabel                 matlab.ui.control.Label
        MoveLabel                      matlab.ui.control.Label
        MoveEditField                  matlab.ui.control.NumericEditField
        FlipHorizontallyButton         matlab.ui.control.Button
        Panel_61                       matlab.ui.container.Panel
        GridLayout27                   matlab.ui.container.GridLayout
        Left                           matlab.ui.control.Button
        Up                             matlab.ui.control.Button
        Right                          matlab.ui.control.Button
        Down                           matlab.ui.control.Button
        CCW                            matlab.ui.control.Button
        CW                             matlab.ui.control.Button
        CenterHorizontallyButton       matlab.ui.control.Button
        CenterVerticallyButton         matlab.ui.control.Button
        FlipVerticallyButton           matlab.ui.control.Button
        SaveButton_2                   matlab.ui.control.Button
        DeformNeuronMarkersPanel       matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        ScaleEditField                 matlab.ui.control.NumericEditField
        ScaleEditFieldLabel            matlab.ui.control.Label
        WidthSpinner                   matlab.ui.control.Spinner
        WidthSpinnerLabel              matlab.ui.control.Label
        HeightSpinner                  matlab.ui.control.Spinner
        HeightSpinnerLabel             matlab.ui.control.Label
        GridLayout                     matlab.ui.container.GridLayout
        WorldlineInformationPanel_2    matlab.ui.container.Panel
        GridLayout51                   matlab.ui.container.GridLayout
        WorldlineTree                  matlab.ui.container.CheckBoxTree
        IDdNode                        matlab.ui.container.TreeNode
        UnIDdNode                      matlab.ui.container.TreeNode
        Panel_62                       matlab.ui.container.Panel
        GridLayout52                   matlab.ui.container.GridLayout
        AssignrandomcolorstoblanksButton  matlab.ui.control.Button
        Panel_30                       matlab.ui.container.Panel
        GridLayout40                   matlab.ui.container.GridLayout
        Panel_32                       matlab.ui.container.Panel
        GridLayout42                   matlab.ui.container.GridLayout
        Panel_35                       matlab.ui.container.Panel
        GridLayout44                   matlab.ui.container.GridLayout
        tSlider                        matlab.ui.control.Slider
        Panel_34                       matlab.ui.container.Panel
        GridLayout43                   matlab.ui.container.GridLayout
        tEditField                     matlab.ui.control.NumericEditField
        tEditFieldLabel                matlab.ui.control.Label
        Panel_31                       matlab.ui.container.Panel
        GridLayout41                   matlab.ui.container.GridLayout
        zEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        WorkflowPanel                  matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        InsertlastIDdFrameButton       matlab.ui.control.Button
        Panel_24                       matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        ExtractActivityButton          matlab.ui.control.Button
        TrackNeuronsButton             matlab.ui.control.Button
        RecommendFramesButton          matlab.ui.control.Button
        SaveResultsButton              matlab.ui.control.Button
        InsertNeuroPALNeuronsButton    matlab.ui.control.Button
        NeuronInformationPanel         matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        ProvenanceEditField            matlab.ui.control.EditField
        ProvenanceEditFieldLabel       matlab.ui.control.Label
        WorldlineIDEditField           matlab.ui.control.NumericEditField
        WorldlineIDEditFieldLabel      matlab.ui.control.Label
        ColorButton                    matlab.ui.control.Button
        ColorLabel                     matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        ZCoordinateEditField           matlab.ui.control.NumericEditField
        ZCoordinateEditFieldLabel      matlab.ui.control.Label
        YCoordinateEditField           matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel      matlab.ui.control.Label
        XCoordinateEditField           matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel      matlab.ui.control.Label
        VolumeViewerPanel              matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        vert_zSlider                   matlab.ui.control.Slider
        ySlider                        matlab.ui.control.Slider
        hor_zSlider                    matlab.ui.control.Slider
        xSlider                        matlab.ui.control.Slider
        yzAxes                         matlab.ui.control.UIAxes
        xzAxes                         matlab.ui.control.UIAxes
        xyAxes                         matlab.ui.control.UIAxes
        DefaultTabGroup                matlab.ui.container.TabGroup
        AnnotationSupportTab           matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        ManipulateNeuronsButton        matlab.ui.control.Button
        Panel_63                       matlab.ui.container.Panel
        GridLayout53                   matlab.ui.container.GridLayout
        MarkerSizeSlider               matlab.ui.control.Slider
        MarkerSizeLabel                matlab.ui.control.Label
        Panel_40                       matlab.ui.container.Panel
        GridLayout48                   matlab.ui.container.GridLayout
        ShowallneuronsCheckBox         matlab.ui.control.CheckBox
        OverlayFrameMIPCheckBox        matlab.ui.control.CheckBox
        OverlayNeuroPALvolCheckBox     matlab.ui.control.CheckBox
        OverlaylastIDdframeCheckBox_2  matlab.ui.control.CheckBox
        AutojumponIDCheckBox           matlab.ui.control.CheckBox
        AutosegmentFrameButton         matlab.ui.control.Button
        HotkeysPanel                   matlab.ui.container.Panel
        GridLayout47                   matlab.ui.container.GridLayout
        BwdFramesEditField             matlab.ui.control.NumericEditField
        FwdFramesEditField             matlab.ui.control.NumericEditField
        TextArea_4                     matlab.ui.control.TextArea
        InsertHKButton                 matlab.ui.control.StateButton
        DropDown_2                     matlab.ui.control.DropDown
        TextArea_5                     matlab.ui.control.TextArea
        TextArea_3                     matlab.ui.control.TextArea
        TextArea_2                     matlab.ui.control.TextArea
        TextArea                       matlab.ui.control.TextArea
        JumpHKButton                   matlab.ui.control.StateButton
        DeleteHKButton                 matlab.ui.control.StateButton
        JmpFrameEditField_2            matlab.ui.control.NumericEditField
        Frame2HKButton                 matlab.ui.control.StateButton
        JmpFrameEditField_1            matlab.ui.control.NumericEditField
        Frame1HKButton                 matlab.ui.control.StateButton
        ColorControlPanel              matlab.ui.container.Panel
        GridLayout16                   matlab.ui.container.GridLayout
        BSlider                        matlab.ui.control.Slider
        BSliderLabel                   matlab.ui.control.Label
        GSlider                        matlab.ui.control.Slider
        GSliderLabel                   matlab.ui.control.Label
        RSlider                        matlab.ui.control.Slider
        RSliderLabel                   matlab.ui.control.Label
        BookmarkedFramesPanel          matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        DeleteBookmarkButton           matlab.ui.control.Button
        BookmarkNameEditField          matlab.ui.control.EditField
        SetBookmarkButton              matlab.ui.control.Button
        GotoBookmarkButton             matlab.ui.control.Button
        BookmarkListBox                matlab.ui.control.ListBox
        ParametersTab                  matlab.ui.container.Tab
        GridLayout58                   matlab.ui.container.GridLayout
        BasicParameterPanel            matlab.ui.container.Panel
        ParameterGridLayout            matlab.ui.container.GridLayout
        WormMotionPanel                matlab.ui.container.Panel
        GridLayout5_2                  matlab.ui.container.GridLayout
        MotionSlider                   matlab.ui.control.Slider
        HoveroverlabelsforfurtherdetailsorguidanceLabel  matlab.ui.control.Label
        AdvSetTab                      matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        GridLayout31_2                 matlab.ui.container.GridLayout
        TrackingSelectionPanel         matlab.ui.container.Panel
        GridLayout49                   matlab.ui.container.GridLayout
        TrackspecificneuronsButton     matlab.ui.control.Button
        TrackspecificregionButton      matlab.ui.control.Button
        StartingFrameLabel             matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        EndFrameLabel                  matlab.ui.control.Label
        EditField_4                    matlab.ui.control.NumericEditField
        UseGPUSwitch                   matlab.ui.control.Switch
        UseGPUSwitchLabel              matlab.ui.control.Label
        MotionPredictionSwitch         matlab.ui.control.Switch
        MotionPredictionSwitchLabel    matlab.ui.control.Label
        AllowRotationsSwitch           matlab.ui.control.Switch
        AllowRotationsSwitchLabel      matlab.ui.control.Label
        ImageMotionTab                 matlab.ui.container.Tab
        GridLayout34                   matlab.ui.container.GridLayout
        dimmer_ratio                   matlab.ui.control.NumericEditField
        CoefficientfordimmingnonfoveatedregionsEditFieldLabel  matlab.ui.control.Label
        dimmer_ratioLabel              matlab.ui.control.Label
        grid_shape                     matlab.ui.control.NumericEditField
        SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel  matlab.ui.control.Label
        grid_shapeLabel                matlab.ui.control.Label
        fovea_sigma                    matlab.ui.control.NumericEditField
        EditFieldLabel_4               matlab.ui.control.Label
        foveasigmaLabel                matlab.ui.control.Label
        ComputationTab                 matlab.ui.container.Tab
        GridLayout31                   matlab.ui.container.GridLayout
        sort_mode                      matlab.ui.control.DropDown
        DropDown_2Label                matlab.ui.control.Label
        sort_modeLabel                 matlab.ui.control.Label
        n_chunks                       matlab.ui.control.NumericEditField
        NumberofstepstodividetheforwardpassintoEditFieldLabel  matlab.ui.control.Label
        n_chunksLabel                  matlab.ui.control.Label
        n_epoch_d                      matlab.ui.control.NumericEditField
        EditField2_2Label              matlab.ui.control.Label
        n_epoch                        matlab.ui.control.NumericEditField
        n_epoch_dLabel                 matlab.ui.control.Label
        NumberofiterationsforimageregistrationLREditFieldLabel  matlab.ui.control.Label
        n_epochLabel                   matlab.ui.control.Label
        ExportparametersButton         matlab.ui.control.Button
        EditadvancedparametersButton   matlab.ui.control.Button
        ImportparametersButton         matlab.ui.control.Button
        EnableadvancedparametersCheckBox  matlab.ui.control.CheckBox
        ActivityTab                    matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        ActivityAxes                   matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        LoadStimulusFileButton         matlab.ui.control.Button
        ActivityListBox                matlab.ui.control.ListBox
        SaveExitButton                 matlab.ui.control.Button
        ScanforIssuesButton            matlab.ui.control.Button
        CreditTab                      matlab.ui.container.Tab
        GridLayout14_2                 matlab.ui.container.GridLayout
        CitationTextArea               matlab.ui.control.TextArea
        CitationTextAreaLabel          matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        GithubRepositoryButton         matlab.ui.control.Button
        ViewPreprintButton             matlab.ui.control.Button
        LaboratoryPageButton           matlab.ui.control.Button
        Label_21                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        %% Video properties
        video_path    % Path to input video
        video_array   % 4D video array
        video_info    % Structure containing video metadata.
        video_neurons % 4D neuron array
        id_frames     % Keeps track of IDd frames
        activity      % Struct containing per-neuron activity

        %% Neuron Manipulation properties
        repo_neurons  % Backup 4D neuron array
        sel_neurons   % Keeps track of neurons being manipulated

        %% Volume properties
        parent_app    % Handle for main GUI
        image_data    % NeuroPAL volume image data
        image_view    % Rendered volume image
        image_neurons % NeuroPAL volume neuron data

        %% ZephIR properties
        annotations   % 4D array containing all annotations.
        worldlines    % Struct containing all worldlines.
        arguments     % Struct containing ZephIR arguments.

        %% GUI properties
        mouse_clicked % Track mouse click state.
        ctrl_state    % Tracks whether control key is down.

        xy_xline      % Variable serving as GUI handle.
        xz_xline      % Variable serving as GUI handle.
        yz_xline      % Variable serving as GUI handle.

        xy_yline      % Variable serving as GUI handle.
        xz_yline      % Variable serving as GUI handle.
        yz_yline      % Variable serving as GUI handle.

        script_dir    % Local working directory, changes based on deployment & OS.
        script_ext    % Deployment- & OS-specific extension for scripts/executables.
    end
    
    methods (Access = private)
        
        %% Loading functions
        %  Turn a path into a video array.

        function [struct, arr] = load_h5(app, path)
            info = h5info(path);

            if ~any(strcmp({info.Datasets.Name}, 'data')) && ~any(strcmp({info.Datasets.Name}, 'times'))
                uiconfirm(app.NeuronActivityTracingUIFigure,'.h5 file structure is invalid. Please refer to documentation.','Error');
                return
            end

            size_info = h5info(path,'/data');
            arr = h5read(path,'/data',[1 1 1 1 1], size_info.ChunkSize);

            struct = {};
            struct.file = size_info.Filename;           % Path to file
            struct.nx = size_info.Dataspace.Size(2);    % Height
            struct.ny = size_info.Dataspace.Size(1);    % Width
            struct.nz = size_info.Dataspace.Size(3);    % Slice count
            struct.nc = size_info.Dataspace.Size(4);    % Channel count
            struct.nt = size_info.Dataspace.Size(5);  % Frame count
            struct.aspect_ratio = struct.ny/struct.nx;  % Aspect Ratio
            struct.ChunkSize = size_info.ChunkSize;     % Chunk Size
            struct.cached = 1;                          % Frames cached in app.video_array

            app.video_array = arr;
            app.video_info = struct;

            if struct.nt < 10
                uiconfirm(app.NeuronActivityTracingUIFigure,'Your video contains too few frames for filters to be applied during activity extraction. ZephIR will skip these when you run the extraction, which may affect your results.','Warning!');
            end

            [parent_path, ~, ~] = fileparts(path);
            fileType = exist(fullfile(parent_path, 'annotations.h5'), 'file');

            if fileType == 2
                check = uiconfirm(app.NeuronActivityTracingUIFigure, 'Existing annotations.h5 file detected. Load annotations?', 'NeuroPAL_ID','Options',['Yes','No']);
                if strcmp(check, 'Yes')
                    app.load_annotations();
                end
            end
        end

        function arr = load_nwb(app, path)
            app.compatibility_handler(path);
        end
        
        function arr = load_nd2(app, path)
            app.compatibility_handler(path);
        end

        function arr = searchNWB(app, whole_file, picked, prev_fig, val)
            all_properties = properties(whole_file);
            
            if ~exist('picked', 'var')
                delete(prev_fig)
                fig = figure('Position', [100, 100, 300, 400], ... % Position and size: [left bottom width height]
                             'Name', 'NWB Browser', ...
                             'NumberTitle', 'off');
    
                listbox = uicontrol('Style', 'listbox', ...
                                    'Units', 'normalized', ...
                                    'Position', [0.1, 0.1, 0.8, 0.8], ... % Position and size: [left bottom width height]
                                    'String', all_properties);
            else
                if class(whole_file.(val)) == types.untyped.Set
                    delete(prev_fig)
                else
                    arr = whole_file.(val);
                    delete(prev_fig)
                end
            end
        end

        %% Savior functions
        %  Handle incompatibilities between input and ZephIR.

        function code = compatibility_handler(app, path)
            check = uiconfirm(app.NeuronActivityTracingUIFigure,"The tracking algorithm assumes that your video file is an HDF5 file with two datasets: 'times', featuring time stamps, and 'data', featuring a 5D (t, c, z, y, x) array. You need to either edit the retrieval function or convert your video file. Convert video file now?","Warning!","Options",["Yes","No"]);
            switch check
                case 'Yes'
                    app.convert_HDF5(path);
                case 'No'
                    uiconfirm(app.NeuronActivityTracingUIFigure,"Tracking will not work unless you edit the retrieval function. You can do so by enabling advanced parameters and clicking into the appropriate tab.")
            end
        end

        function convert_HDF5(app, path)
            [filepath, name, format] = fileparts(path);

            title = sprintf('Building data structure from %s...', path);
            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title',title,'Indeterminate','off');

            switch format
                case '.h5'
                    % This should never happen, but we're thorough.
                    check = uiconfirm(app.NeuronActivityTracingUIFigure,"File is already in HDF5 format. If you're seeing this, something has gone very wrong.","Error!","Options", ["OK"]);
                case '.nwb'        
                    % Read NWB file
                    whole_file = nwbRead(path);

                    if any(ismember(whole_file.acquisition.keys, 'CalciumImageSeries'))
                        gc_data = whole_file.acquisition.get('CalciumImageSeries').data;
                    elseif any(ismember(whole_file.processing.keys,'GCaMPVideo'))
                        gc_data = whole_file.processing.get('GCaMPVideo').nwbdatainterface.get('CalciumImageSeries').data;
                    else
                        uiconfirm(app.NeuronActivityTracingUIFigure, sprintf('Could not find video in %s', path), 'Error');
                        delete(app);
                        return

                        %{
                        check = uiconfirm(app.NeuronActivityTracingUIFigure, sprintf('Could not find video in %s', path), 'Error','Options',["Manually specify volume","Cancel"]);
                        
                        switch check
                            case 'Cancel'
                                delete(app);
                                return
                            otherwise
                                gc_data = app.searchNWB(whole_file);
                        end
                        %}
                    end

                    data_pipe = gc_data.internal;

                    % Get dimensions
                    bitDepth = str2num(data_pipe.dataType(5:end));
                    dims = data_pipe.dims;
                    nt = dims(5);
                    nc = dims(1);
                    nz = dims(2);
                    ny = dims(3);
                    nx = dims(4);

                    % Create the HDF5 dataset
                    h5create(fullfile(filepath, 'data.h5'), '/data', [ny nx nz nc nt], 'ChunkSize', [ny nx nz nc 1], 'Datatype', 'uint8');
                    h5create(fullfile(filepath, 'data.h5'), '/times', nt, 'Datatype', 'double');

                    switch bitDepth
                        case {8, 16, 32, 64}
                            scale_factor = double(intmax('uint8')) / double(intmax(sprintf('uint%.f', bitDepth)));
                        case 12
                            scale_factor = double(intmax('uint8')) / 4096;
                        case 20
                            scale_factor = double(intmax('uint8')) / 1048576;
                        case 24
                            scale_factor = double(intmax('uint8')) / 16777216;
                        otherwise
                            error('Bit depth %s failed conversion to uint8.', data_pipe.dataType);
                    end
                    
                    d.Message = 'Processing frames...';
                    d.Value = 0;

                    for t = 1:nt
                        d.Value = t / nt;

                        % Read chunk
                        %chunk = uint8(gc_data(1:nc, 1:nz, 1:ny, 1:nx, t) * scale_factor);
                        chunk = uint8(gc_data(1:nc, 1:nz, 1:ny, 1:nx, t));
                        chunk = permute(chunk, [3 4 2 1 5]);

                        % Write chunk to HDF5
                        h5write(fullfile(filepath, 'data.h5'), '/data', chunk, [1, 1, 1, 1, t], [ny nx nz nc 1]);

                        if d.CancelRequested
                            break;
                        end
                    end

                    %{
                    if any(ismember(whole_file.processing.keys, 'NeuroPAL'))
                        if any(ismember(whole_file.processing.get('NeuroPAL').nwbdatainterface.keys, 'TrackedNeurons'))
                            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Loading neurons...','Indeterminate','on');
                            
                            if isempty(app.video_neurons)
                                app.video_neurons = struct('provenance', {}, 'worldline', {}, 'rois', {});
                            end
                
                            frame_results = whole_file.processing.get('NeuroPAL').nwbdatainterface.get('TrackedNeurons').planesegmentation.get('TrackedNeuronROIs').voxel_mask.data.load().weight;
                            prov_results = 'NWB';
                            wlid_results = whole_file.processing.get('NeuroPAL').nwbdatainterface.get('TrackedNeurons').planesegmentation.get('TrackedNeuronROIs').vectordata.load();
                            x_results = whole_file.processing.get('NeuroPAL').nwbdatainterface.get('TrackedNeurons').planesegmentation.get('TrackedNeuronROIs').voxel_mask.data.load().x;
                            y_results = whole_file.processing.get('NeuroPAL').nwbdatainterface.get('TrackedNeurons').planesegmentation.get('TrackedNeuronROIs').voxel_mask.data.load().y;
                            z_results = whole_file.processing.get('NeuroPAL').nwbdatainterface.get('TrackedNeurons').planesegmentation.get('TrackedNeuronROIs').voxel_mask.data.load().z;
                    
                            for n=1:results_info.Datasets(1).Dataspace.Size
                                worldline = struct('name', {{}}, 'node', {{}}, 'color', {{}}, 'style', {{}}, 'id', {{}});
                                rois = struct();
                                idx = size(app.video_neurons, 2) + 1;
                                existing_worldline = 0;
                    
                                target_wl = wlid_results(n);
                                target_frame = frames_results(n);
                                target_x = round(x_results(n) * app.video_info.nx);
                                target_y = round(y_results(n) * app.video_info.ny);
                                target_z = round(z_results(n));
                    
                                worldline.name = wlid_results(n);
                                worldline.color = [0.8, 0.8, 0.8];
                                
                                rois(target_frame).x_slice = target_x;
                                rois(target_frame).y_slice = target_y;
                                rois(target_frame).z_slice = target_z;
                                
                                rois(target_frame).xy_pos = [target_x target_y];
                                rois(target_frame).xz_pos = [target_x target_z];
                                rois(target_frame).yz_pos = [target_z target_y];
                                
                                rois(target_frame).xy = drawpoint(app.xyAxes, 'Position', [target_x target_y], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                                rois(target_frame).xz = drawpoint(app.xzAxes, 'Position', [target_x target_z], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                                rois(target_frame).yz = drawpoint(app.yzAxes, 'Position', [target_z target_y], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);                    
                    
                                for i=1:size(app.video_neurons, 2)
                                    if strcmp(app.video_neurons(i).worldline.name, worldline.name)
                                        idx = i;
                                        existing_worldline = 1;
                                        break
                                    end
                                end
                    
                                if existing_worldline
                                    app.video_neurons(idx).rois(target_frame) = rois(target_frame);
                                else
                                    provenance = prov_results(n);
                                    worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);
                                    worldline.style = uistyle("FontColor", worldline.color);
                                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);
                                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                                    app.video_neurons(end+1) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});
                                end
                
                                if ~any(app.id_frames == app.tSlider.Value)
                                    app.id_frames = [app.id_frames; target_frame];
                                end
                            end
                
                            app.visual_composer();
                            close(d)
                        end
                    end
                    %}
                case '.nd2'
                    bfImg = BioformatsImage(path);
                    nt = bfImg.sizeT;
                    nc = bfImg.sizeC;
                    nz = bfImg.sizeZ;
                    ny = bfImg.height;
                    nx = bfImg.width;

                    % Create the HDF5 dataset
                    h5create(fullfile(filepath, 'data.h5'), '/data', [ny nx nz nc nt], 'ChunkSize', [ny nx nz nc 1], 'Datatype', 'uint8');
                    h5create(fullfile(filepath, 'data.h5'), '/times', nt, 'Datatype', 'double');
            
                    % Initialize the entire dataset array with correct dimension order
                    all_data = zeros(ny, nx, nz, nc, nt, 'uint8');
                    
                    % Collect data for all time points
                    channelNames = bfImg.channelNames;

                    switch bfImg.bitDepth
                        case {8, 16, 32, 64}
                            scale_factor = double(intmax('uint8')) / double(intmax(sprintf('uint%.f', bfImg.bitDepth)));
                        case 12
                            scale_factor = double(intmax('uint8')) / 4096;
                        case 20
                            scale_factor = double(intmax('uint8')) / 1048576;
                        case 24
                            scale_factor = double(intmax('uint8')) / 16777216;
                        otherwise
                            error('Bit depth %.f failed conversion to uint8.', bfImg.bitDepth);
                    end
                    
                    d.Message = 'Processing frames...';
                    d.Value = 0;

                    for t = 1:nt
                        d.Value = t / nt;
                        for c = 1:nc
                            channel = channelNames{c};
                            for z = 1:nz
                                % Read and scale the data
                                plane_data = getPlane(bfImg, z, channel, t);
                                all_data(:, :, z, c, t) = uint8(plane_data * scale_factor);
                            end
                        end
                        
                        if d.CancelRequested
                            break;
                        end
                    end

                    % Write the entire dataset at once to HDF5 with corrected dimension order
                    h5write(fullfile(filepath, 'data.h5'), '/data', all_data);
            end
            close(d)

            app.video_path = fullfile(filepath, 'data.h5');
            [app.video_info, app.video_array] = app.load_h5(app.video_path);
        end


        %% Pre-processing functions
        %  Create annotations.h5, worldlines.h5, metadata.json

        function load_annotations(app)
            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Processing results...','Indeterminate','on');
            
            if isempty(app.video_neurons)
                app.video_neurons = struct('provenance', {}, 'worldline', {}, 'rois', {});
            end

            [path, ~, ~] = fileparts(app.video_path);
            results_info = h5info(fullfile(path, 'annotations.h5'));

            frame_results = h5read(fullfile(path, 'annotations.h5'), '/t_idx');
            prov_results = h5read(fullfile(path, 'annotations.h5'), '/provenance');
            wlid_results = h5read(fullfile(path, 'annotations.h5'), '/worldline_id');
            x_results = h5read(fullfile(path, 'annotations.h5'), '/x');
            y_results = h5read(fullfile(path, 'annotations.h5'), '/y');
            z_results = h5read(fullfile(path, 'annotations.h5'), '/z');
    
            wl_idx = h5read(fullfile(path, 'worldlines.h5'), '/id');
            wl_name = h5read(fullfile(path, 'worldlines.h5'), '/name');
            wl_col = h5read(fullfile(path, 'worldlines.h5'), '/color');
    
            for n=1:results_info.Datasets(1).Dataspace.Size
                worldline = struct('name', {{}}, 'node', {{}}, 'color', {{}}, 'style', {{}}, 'id', {{}});
                rois = struct();
                idx = size(app.video_neurons, 2) + 1;
                existing_worldline = 0;
    
                target_wl = wl_idx(wl_idx == wlid_results(n)) + 1;
                target_frame = frame_results(n)+1;
                target_x = round(x_results(n) * app.video_info.nx);
                target_y = round(y_results(n) * app.video_info.ny);
                target_z = round(z_results(n));
    
                worldline.name = wl_name(target_wl);
                worldline.color = wl_col(target_wl);

                if isstring(worldline.color)
                    worldline.color = [0.8, 0.8, 0.8];
                end
                
                rois(target_frame).x_slice = target_x;
                rois(target_frame).y_slice = target_y;
                rois(target_frame).z_slice = target_z;
                
                rois(target_frame).xy_pos = [target_x target_y];
                rois(target_frame).xz_pos = [target_x target_z];
                rois(target_frame).yz_pos = [target_z target_y];
                
                rois(target_frame).xy = drawpoint(app.xyAxes, 'Position', [target_x target_y], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(target_frame).xz = drawpoint(app.xzAxes, 'Position', [target_x target_z], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(target_frame).yz = drawpoint(app.yzAxes, 'Position', [target_z target_y], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);                    
    
                for i=1:size(app.video_neurons, 2)
                    if strcmp(app.video_neurons(i).worldline.name, worldline.name)
                        idx = i;
                        existing_worldline = 1;
                        break
                    end
                end
    
                if existing_worldline
                    app.video_neurons(idx).rois(target_frame) = rois(target_frame);
                else
                    provenance = prov_results(n);
                    worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);
                    worldline.style = uistyle("FontColor", worldline.color);
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    app.video_neurons(end+1) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});
                end

                if ~any(app.id_frames == app.tSlider.Value)
                    app.id_frames = [app.id_frames; target_frame];
                end
            end

            app.visual_composer();
            close(d)
        end

        function create_worldlines(app)
        end

        function create_metadata(app)
            % Extract necessary information from the video_info structure
            metadata.shape_t = app.video_info.nt;
            metadata.shape_c = app.video_info.nc;
            metadata.shape_z = app.video_info.nz;
            metadata.shape_y = app.video_info.ny;
            metadata.shape_x = app.video_info.nx;
            
            % Convert the MATLAB structure to JSON text
            jsonText = jsonencode(metadata);
            
            [path, name, ext] = fileparts(app.video_path);

            % Write the JSON text to a file
            fileId = fopen(fullfile(path, 'metadata.json'), 'w');
            if fileId == -1
                error('Failed to open file for writing.');
            end
            fprintf(fileId, '%s', jsonText);
            fclose(fileId);
        end

        function arg_string = compile_arguments(app)
            app.arguments = struct( ...
                'dataset', app.video_path, ...
                'load_checkpoint', 0, ...
                'load_args', 1, ...
                'allow_rotation', app.AllowRotationsSwitch.Value, ...
                'clip_grad', app.clip_grad.Value, ...
                'cuda', app.UseGPUSwitch.Value, ...
                'dimmer_ratio', app.dimmer_ratio.Value, ...
                'exclude_self', 1, ...
                'fovea_sigma', app.fovea_sigma.Value, ...
                'gamma', 2, ...
                'grid_shape', app.grid_shape.Value, ...
                'include_all', 1, ...
                'lambda_d', app.l_d.Value, ...
                'lambda_n', app.l_n.Value, ...
                'lambda_n_mode', app.l_n_mode.Value, ...
                'lambda_t', app.l_t.Value, ...
                'load_nn', 1, ...
                'lr_ceiling', app.lr_ceiling.Value, ...
                'lr_coef', app.lr_coeff.Value, ...
                'lr_floor', app.lr_floor.Value, ...
                'motion_predict', app.MotionPredictionSwitch.Value, ...
                'n_chunks', app.n_chunks.Value, ...
                'n_epoch', app.n_epoch.Value, ...
                'n_epoch_d', app.n_epoch_d.Value, ...
                'n_frame', 1, ...
                'nn_max', app.nn_max.Value, ...
                'save_mode', 'o', ...
                'sort_mode', app.sort_mode.Value, ...
                'z_compensator', app.z_comp.Value);


            % Initialize an empty string for the command line arguments
            arg_string = '';
            
            % Retrieve all field names from the struct
            fields = fieldnames(app.arguments);
            
            % Iterate through each field
            for i = 1:numel(fields)
                field = fields{i}; % Current field name
                value = app.arguments.(field); % Value of the current field
                
                % Check if the value is a numeric type
                if isnumeric(value)
                    valueStr = num2str(value);
                elseif islogical(value)
                    valueStr = num2str(value * 1);
                else
                    valueStr = value;
                end
                
                % Append the current argument to the command line string
                if ischar(value) || isstring(value)
                    arg_string = [arg_string, ' --', field, ' "', valueStr, '"'];
                else
                    arg_string = [arg_string, ' --', field, '=', valueStr];
                end
            end
            
            % Remove the leading space
            if ~isempty(arg_string)
                arg_string = strtrim(arg_string);
            end
        end

        %% Rendering functions
        %  Visualize arrays.

        function visual_composer(app, t, z, y, x)

            if ~exist ('t', 'var')
                t = round(app.tSlider.Value);
            end

            if app.OverlaylastIDdframeCheckBox_2.Value
                earlier_frames = app.id_frames(app.id_frames < app.tSlider.Value);
                t = max(earlier_frames);
            end

            if ~exist ('z', 'var')
                z = round(app.hor_zSlider.Value);
            end

            if ~exist('y', 'var')
                y = round(app.xSlider.Value);
            end

            if ~exist ('x', 'var')
                x = round(app.video_info.ny-app.ySlider.Value);
            end

            %fprintf("Loading Frame %.f with Slice %.f where x = %.f and y = %.f.\n", t, z, y, x)

            if t > app.video_info.nt || t < 0
                uiconfirm(app.NeuronActivityTracingUIFigure,'Target frame exceeds video frame count.','Error');
                return
            elseif z > app.video_info.nz || z < 0
                uiconfirm(app.NeuronActivityTracingUIFigure,'Target slice exceeds video slice count.','Error');
                return
            end

            target_frame = h5read(app.video_info.file,'/data',[1 1 1 1 t], app.video_info.ChunkSize);
            %target_frame = permute(target_frame, [2 1 3 4 5]);

            switch app.video_info.nc
                case 1
                    zero_frame = zeros([app.video_info.nx, app.video_info.ny, app.video_info.nz, 3]);
                    zero_frame(:, :, :, 2) = target_frame;
                    target_frame = zero_frame;
                case 2
                    target_frame = cat(4, target_frame, zeros([app.video_info.ny, app.video_info.nx, app.video_info.nz, 1]));
            end

            if app.OverlayFrameMIPCheckBox.Value
                xy_arr = target_frame(:, :, 1, :);

                for j=2:app.video_info.nz
                    xy_arr = xy_arr + target_frame(:, :, j, :);
                end

                xz_arr = target_frame(:, y, :, :);
                yz_arr = target_frame(x, :, :, :);
            else
                xy_arr = target_frame(:, :, z, :);
                xz_arr = target_frame(:, y, :, :);
                yz_arr = target_frame(x, :, :, :);

                xy_arr(:, :, :, 1) = xy_arr(:, :, :, 1) * app.RSlider.Value;
                xy_arr(:, :, :, 2) = xy_arr(:, :, :, 2) * app.GSlider.Value;
                xy_arr(:, :, :, 3) = xy_arr(:, :, :, 3) * app.BSlider.Value;
                
                xz_arr(:, :, :, 1) = xz_arr(:, :, :, 1) * app.RSlider.Value;
                xz_arr(:, :, :, 2) = xz_arr(:, :, :, 2) * app.GSlider.Value;
                xz_arr(:, :, :, 3) = xz_arr(:, :, :, 3) * app.BSlider.Value;
                
                yz_arr(:, :, :, 1) = yz_arr(:, :, :, 1) * app.RSlider.Value;
                yz_arr(:, :, :, 2) = yz_arr(:, :, :, 2) * app.GSlider.Value;
                yz_arr(:, :, :, 3) = yz_arr(:, :, :, 3) * app.BSlider.Value;
            end

            xy_arr = squeeze(xy_arr);
            xz_arr = squeeze(xz_arr);
            yz_arr = permute(squeeze(yz_arr),[2, 1, 3]);

            xy_img = image(app.xyAxes, xy_arr);
            xz_img = image(app.xzAxes, yz_arr);
            yz_img = image(app.yzAxes, xz_arr);

            app.xEditField.Value = app.xSlider.Value;
            app.yEditField.Value = app.video_info.ny-app.ySlider.Value;
            app.zEditField.Value = round(app.hor_zSlider.Value);
            app.tEditField.Value = app.tSlider.Value;

            xy_img.ButtonDownFcn = {@app.ImageClicked};
            xz_img.ButtonDownFcn = {@app.ImageClicked};
            yz_img.ButtonDownFcn = {@app.ImageClicked};

            app.xyAxes.XLim = [1, size(xy_arr, 2)];
            app.xyAxes.YLim = [1, size(xy_arr, 1)];
            app.xzAxes.XLim = [1, size(yz_arr, 2)];
            app.xzAxes.YLim = [1, size(yz_arr, 1)];
            app.yzAxes.XLim = [1, size(xz_arr, 2)];
            app.yzAxes.YLim = [1, size(xz_arr, 1)];

            delete(app.xy_yline)
            delete(app.yz_yline)
            delete(app.xz_yline)

            delete(app.xy_xline)
            delete(app.yz_xline)
            delete(app.xz_xline)

            %app.xzAxes.Position(3) = app.xyAxes.Position(3);
            %app.yzAxes.Position(4) = app.xyAxes.Position(4);

            app.xy_yline = yline(app.xyAxes, x, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.yz_yline = yline(app.yzAxes, x, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.xz_yline = yline(app.xzAxes, app.xzAxes.YLim(2)*(z/app.video_info.nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);

            app.xy_xline = xline(app.xyAxes, y, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.yz_xline = xline(app.yzAxes, app.yzAxes.XLim(2)*(z/app.video_info.nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.xz_xline = xline(app.xzAxes, y, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);

            delete(findobj(app.xyAxes,'Type','images.roi.Point'));
            delete(findobj(app.yzAxes,'Type','images.roi.Point'));
            delete(findobj(app.xzAxes,'Type','images.roi.Point'));

            if any(app.id_frames == app.tSlider.Value)
                app.roi_draw(app.xSlider.Value, app.ySlider.Value, z, t)
            end
        end

        function roi_draw(app, x, y, z, t)
            if app.ShowallneuronsCheckBox.Value
                threshold = 999;
            else
                threshold = 2;
            end

            for n=1:size(app.video_neurons, 2)
                if t <= size(app.video_neurons(n).rois, 2)
                    if z <= app.video_neurons(n).rois(t).z_slice + threshold && z >= app.video_neurons(n).rois(t).z_slice - threshold
                        app.video_neurons(n).rois(t).xy = drawpoint(app.xyAxes, 'Position', app.video_neurons(n).rois(t).xy_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);
                        addlistener(app.video_neurons(n).rois(t).xy, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));
                        addlistener(app.video_neurons(n).rois(t).xy, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));
                    end
    
                    if y <= app.video_neurons(n).rois(t).y_slice + threshold && y >= app.video_neurons(n).rois(t).y_slice - threshold
                        app.video_neurons(n).rois(t).xz = drawpoint(app.xzAxes, 'Position', app.video_neurons(n).rois(t).xz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);
                        addlistener(app.video_neurons(n).rois(t).xz, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));
                        addlistener(app.video_neurons(n).rois(t).xz, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));
                    end
    
                    if x <= app.video_neurons(n).rois(t).x_slice + threshold && x >= app.video_neurons(n).rois(t).x_slice - threshold
                        app.video_neurons(n).rois(t).yz = drawpoint(app.yzAxes, 'Position', app.video_neurons(n).rois(t).yz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);
                        addlistener(app.video_neurons(n).rois(t).yz, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));
                        addlistener(app.video_neurons(n).rois(t).yz, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));
                    end
                end
            end
        end

        function arr_mixer(app, arr1, alpha1, arr2, alpha2)
        end

        %% Logical Trackers
        %  Keep track of global annotation structure.

        function frame_state(app)
        end

        function output = query_state(app, obj, mode, state)
        end

        function isNodeFound = app.searchUITreeNodeByName(app, node, nodeName)
            % searchUITreeNodeByName Recursively searches for a node with the given name
            % within a uitreenode structure.
            %
            % Arguments:
            % node     - The current uitreenode to inspect (start with the root node).
            % nodeName - The name of the node to search for.
            %
            % Returns:
            % isNodeFound - A logical value indicating whether the node was found (true) or not (false).
        
            % Initialize the flag to false.
            isNodeFound = [false, ''];
        
            % Check if the current node's name matches the target name.
            if strcmp(node.Text, nodeName)
                % If a match is found, set the flag to true and return.
                isNodeFound = [true, node];
                return;
            end
        
            % If the current node has children, recursively search each child.
            for i = 1:numel(node.Children)
                isNodeFound = app.searchUITreeNodeByName(node.Children(i), nodeName);
                % If the node is found in a subtree, break the loop and return.
                if isNodeFound(1)
                    break;
                end
            end
        end


        %% Utility Functions
        %  Incidentals for GUI handling.

        function InitClickStates(app)
            app.mouse_clicked.double_click_delay = 0.9;
            app.mouse_clicked.click = false;
        end

        function ImageClicked(app, src, event)
            point = event.IntersectionPoint;
            double_click = false;

            switch src.Parent.Tag
                case 'xy'
                    app.xSlider.Value = point(1) + 2;
                    app.ySlider.Value = app.video_info.ny-point(2)+3;
                case 'xz'
                    app.xSlider.Value = point(1) + 1.5;
                    app.hor_zSlider.Value = point(2);
                case 'yz'
                    app.ySlider.Value = app.video_info.ny-point(2) + 3;
                    app.hor_zSlider.Value = point(1);
            end

            switch event.Button
                case 1
                    if app.mouse_clicked.click
                        disp('case 1, app.mouse_clicked.click = True, upper!');
                        double_click = true;
                    else
                        app.mouse_clicked.click = true;
                        pause(app.mouse_clicked.double_click_delay);
                        if app.mouse_clicked.click
                            app.visual_composer();
                        end
                    end
                    app.mouse_clicked.click = false;

                case 2
                    double_click = true;
                    disp('case 2');
            end

            % Add a neuron where the user clicked.
            if double_click
                disp('double_click = True');
                app.add_worldline();
            end

            app.visual_composer();
        end

        function add_worldline(app, neuron)
            if isempty(app.video_neurons)
                app.video_neurons = struct('provenance', {}, 'worldline', {}, 'rois', {});
            end

            provenance = 'NPAL';
            worldline = struct('name', {{}}, 'node', {{}}, 'color', {{}}, 'style', {{}}, 'id', {{}});
            rois = struct();
            idx = size(app.video_neurons, 2) + 1;
            existing_worldline = 0;


            % Generate or extract worldline name
            if ~exist('neuron','var')
                worldline.name = string(size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1)+1);
            else
                if ~isempty(neuron.annotation)
                    worldline.name = neuron.annotation;
                else
                    worldline.name = string(size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1)+1);
                end
            end

            % Check if this worldline already exists
            for n=1:size(app.video_neurons, 2)
                if strcmp(app.video_neurons(n).worldline.name, worldline.name)
                    idx = n;
                    existing_worldline = 1;
                    break
                end
            end

            % Populate worldline info
            if ~exist('neuron','var')

                rois(app.tSlider.Value).x_slice = app.xSlider.Value;
                rois(app.tSlider.Value).y_slice = app.ySlider.Value;
                rois(app.tSlider.Value).z_slice = app.hor_zSlider.Value;
                
                rois(app.tSlider.Value).xy_pos = [app.xSlider.Value app.video_info.ny-app.ySlider.Value];
                rois(app.tSlider.Value).xz_pos = [app.xSlider.Value app.hor_zSlider.Value];
                rois(app.tSlider.Value).yz_pos = [app.hor_zSlider.Value app.video_info.ny-app.ySlider.Value];
    
                rois(app.tSlider.Value).xy = drawpoint(app.xyAxes, 'Position', [app.xSlider.Value app.video_info.ny-app.ySlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).xz = drawpoint(app.xzAxes, 'Position', [app.xSlider.Value app.hor_zSlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).yz = drawpoint(app.yzAxes, 'Position', [app.hor_zSlider.Value app.video_info.ny-app.ySlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);

                if existing_worldline
                    app.video_neurons(idx).rois(app.tSlider.Value) = rois(app.tSlider.Value);
                else
                    worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);
                    worldline.color = [0.8, 0.8, 0.8];
                    worldline.style = uistyle("FontColor", worldline.color);
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    app.video_neurons(idx) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});
                end

                if app.AutojumponIDCheckBox.Value
                    app.jumpToUnIDd('frame');
                else
                    app.edit_worldline(size(app.video_neurons, 2), 'select');
                end
            else
                % Extract neuron color. If invalid, use color_readout.
                % If also invalid, use default worldline color (grey).
                if ~isnan(neuron.color(1)) && max(neuron.color) <= 1 && min(neuron.color) >= 0
                    worldline.color = neuron.color(1:3);
                elseif ~isnan(neuron.color_readout(1)) && max(neuron.color_readout) <= 1 && min(neuron.color_readout) >= 0
                    worldline.color = neuron.color_readout(1:3);
                else
                    worldline.color = [0.8, 0.8, 0.8];
                end

                rois(app.tSlider.Value).x_slice = neuron.position(2);
                rois(app.tSlider.Value).y_slice = neuron.position(1);
                rois(app.tSlider.Value).z_slice = neuron.position(3);
                
                rois(app.tSlider.Value).xy_pos = [neuron.position(2) neuron.position(1)];
                rois(app.tSlider.Value).xz_pos = [neuron.position(2) neuron.position(3)];
                rois(app.tSlider.Value).yz_pos = [neuron.position(3) neuron.position(1)];
    
                rois(app.tSlider.Value).xy = drawpoint(app.xyAxes, 'Position', rois(app.tSlider.Value).xy_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).xz = drawpoint(app.xzAxes, 'Position', rois(app.tSlider.Value).xz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).yz = drawpoint(app.yzAxes, 'Position', rois(app.tSlider.Value).yz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                
                if existing_worldline
                    app.video_neurons(idx).rois(app.tSlider.Value) = rois(app.tSlider.Value);
                else
                    if Neurons.Hermaphrodite.isCell(worldline.name)
                        worldline.node = uitreenode(app.IDdNode, "Text", worldline.name);
                    else
                        worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);
                    end

                    worldline.style = uistyle("FontColor", worldline.color);
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    app.video_neurons(idx) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});
                end
            end

            if ~any(app.id_frames == app.tSlider.Value)
                app.id_frames = [app.id_frames; app.tSlider.Value];
            end
        end

        function edit_worldline(app, idx, mode, new_val)
            app.visual_composer();

            switch mode
                case 'select'
                    app.NameEditField.Value = app.video_neurons(idx).worldline.name;
                    app.XCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos(1);
                    app.YCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos(2);
                    app.ZCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).z_slice;

                    app.xSlider.Value = app.XCoordinateEditField.Value;
                    app.ySlider.Value = app.video_info.ny - app.YCoordinateEditField.Value;
                    app.hor_zSlider.Value = app.ZCoordinateEditField.Value;

                    app.WorldlineIDEditField.Value = idx;
                    app.ColorButton.BackgroundColor = app.video_neurons(idx).worldline.color;
                    app.ProvenanceEditField.Value = app.video_neurons(idx).provenance;
                case 'save'
                    d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Saving worldline...','Indeterminate','on');
                    existing_idx = -1;

                    for i = 1:length(app.video_neurons)
                        if strcmp(app.video_neurons(i).worldline.name{1}, app.NameEditField.Value)
                            existing_idx = i;
                            break;
                        end
                    end

                    if existing_idx == -1
                        app.video_neurons(idx).worldline.name = app.NameEditField.Value;
                        app.video_neurons(idx).worldline.node.Text = app.video_neurons(idx).worldline.name;
                        app.video_neurons(idx).provenance = app.ProvenanceEditField.Value;
                        app.edit_worldline(idx, 'position', [app.XCoordinateEditField.Value app.YCoordinateEditField.Value app.ZCoordinateEditField.Value])

                        if Neurons.Hermaphrodite.isCell(app.video_neurons(idx).worldline.name)
                            move(app.video_neurons(idx).worldline.node, app.WorldlineTree.Children(1).Children(end), 'after');
                        else
                            move(app.video_neurons(idx).worldline.node, app.WorldlineTree.Children(2).Children(end), 'after');
                        end
                    else
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).x_slice = app.video_neurons(idx).rois(app.tSlider.Value).x_slice;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).y_slice = app.video_neurons(idx).rois(app.tSlider.Value).y_slice;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).z_slice = app.video_neurons(idx).rois(app.tSlider.Value).z_slice;
                        
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).xy_pos = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).xz_pos = app.video_neurons(idx).rois(app.tSlider.Value).xz_pos;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).yz_pos = app.video_neurons(idx).rois(app.tSlider.Value).yz_pos;

                        delete(app.video_neurons(idx).worldline.node);
                        app.video_neurons(idx) = [];
                    end
                    close(d)
                case 'color'
                    app.video_neurons(idx).worldline.color = new_val;
                    app.video_neurons(idx).worldline.style = uistyle("FontColor", app.video_neurons(idx).worldline.color);
                    addStyle(app.WorldlineTree, app.video_neurons(idx).worldline.style, "node", app.video_neurons(idx).worldline.node);
                case 'name'
                    app.video_neurons(idx).worldline.name = new_val;
                    app.video_neurons(idx).worldline.node.Text = new_val;
                case 'position'
                    app.video_neurons(idx).rois(app.tSlider.Value).x_slice = new_val(1);
                    app.video_neurons(idx).rois(app.tSlider.Value).y_slice = new_val(2);
                    app.video_neurons(idx).rois(app.tSlider.Value).z_slice = new_val(3);
                    
                    app.video_neurons(idx).rois(app.tSlider.Value).xy_pos = [new_val(1) new_val(2)];
                    app.video_neurons(idx).rois(app.tSlider.Value).xz_pos = [new_val(1) new_val(3)];
                    app.video_neurons(idx).rois(app.tSlider.Value).yz_pos = [new_val(3) new_val(2)];
                case 'delete'
                    app.video_neurons(idx) = [];
            end
            
            app.visual_composer();
        end

        function roi_moved(app, src, n, evt)
            switch src
                case app.xyAxes
                    app.edit_worldline(n, 'position', [evt.CurrentPosition(1), evt.CurrentPosition(2), app.video_neurons(n).rois(app.tSlider.Value).z_slice]);
                case app.xzAxes
                    app.edit_worldline(n, 'position', [evt.CurrentPosition(1), app.video_neurons(n).rois(app.tSlider.Value).y_slice, evt.CurrentPosition(2)]);
                case app.yzAxes
                    app.edit_worldline(n, 'position', [app.video_neurons(n).rois(app.tSlider.Value).x_slice, evt.CurrentPosition(2), evt.CurrentPosition(1)]);
            end
        end

        function jumpToUnIDd(app, mode)
            switch mode
                case 'frame'
                case 'tree'
                case 'video'
            end
        end

        function export_arguments(app, filename)
            % Compile arguments.
            app.compile_arguments();

            % Convert the app.arguments struct to a JSON string
            jsonStr = jsonencode(app.arguments);
            
            % Open a file for writing.
            fileId = fopen(filename, 'wt');
            
            % Check if the file was opened successfully
            if fileId == -1
                error('Failed to open file for writing: %s', filename);
            end
            
            % Write the JSON string to the file
            fwrite(fileId, jsonStr, 'char');
            
            % Close the file
            fclose(fileId);
        end

        function import_arguments(app, json)
            % Read the entire content of the JSON file into a string
            jsonStr = fileread(json);
            
            % Check if the JSON string is empty
            if isempty(jsonStr)
                error('The JSON file is empty: %s', json);
            end
            
            % Convert the JSON string back into a MATLAB struct
            app.arguments = jsondecode(jsonStr);
        end

        % Define the callback function for the save button click
        function saveButtonPushed(app, src, fig, colorButton, targetNode)
            newStyle = uistyle("FontColor",colorButton.BackgroundColor);
            addStyle(app.WorldlineTree,newStyle,"node", app.WorldlineTree.SelectedNodes);
            delete(fig)
        end

        % Define the callback function for the cancel button click
        function cancelButtonPushed(app, src, fig)
            delete(fig)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, video_path, image_data, image_view, image_neurons)
            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Loading video...','Indeterminate','on');
            app.parent_app = parent_app;
            app.video_path = video_path;
            app.image_data = image_data;
            app.image_view = image_view;
            app.image_neurons = image_neurons;

            if ~isdeployed
                app.script_dir = fullfile(pwd, '/+Wrapper/');
                app.script_ext = '.py';
            else
                if ispc
                    app.script_dir = fullfile(pwd, '\lib\bin\windows\');
                    app.script_ext = '.exe';
                elseif ismac
                    ctfroot_path = ctfroot;
                    for i = 1:4
                        ctfroot_path = fileparts(ctfroot_path);
                    end
                    app.script_dir = fullfile(ctfroot_path, 'lib/bin/macos/');
                    app.script_ext = '';
                end
            end

            %% GUI Initialization

            % Click Handler
            app.InitClickStates();

            app.AdjustNeuronMarkerAlignmentPanel.Parent = app.DefaultTabGroup.Parent;
            app.AdjustNeuronMarkerAlignmentPanel.Layout = app.DefaultTabGroup.Layout;

            app.AdvancedParameterPanel.Parent = app.BasicParameterPanel.Parent;
            app.AdvancedParameterPanel.Layout = app.BasicParameterPanel.Layout;
            
            app.GridSearchPanel.Parent = app.AdvancedParameterPanel.Parent;
            app.GridSearchPanel.Layout = app.AdvancedParameterPanel.Layout;

            %% Load Video

            % Isolate file format
            format = strsplit(app.video_path,'.');
            format = format(end);

            % Select loading function based on file format
            switch char(format)
                case 'h5'
                    app.load_h5(app.video_path);
                case 'nwb'
                    app.load_nwb(app.video_path);
                case 'nd2'
                    app.load_nd2(app.video_path);
            end

            app.xyAxes.XLim = [1, app.video_info.nx];
            app.xyAxes.YLim = [1, app.video_info.ny];
            %xy_aspectRatio = app.video_info.nx / app.video_info.ny;
            %app.xyAxes.DataAspectRatio = [1, 1/xy_aspectRatio, 1];

            % Define slider limits and values based on video
            app.tSlider.Limits = [1, app.video_info.nt];
            app.ActivityAxes.XTick = 0:app.video_info.nt;
            app.ActivityAxes.XTickLabel = 0:app.video_info.nt;
            app.tSlider.MinorTicks = [];
            app.tSlider.Value = 1;

            app.vert_zSlider.Limits = [1, app.video_info.nz];
            app.vert_zSlider.Value = round(app.video_info.nz/2);

            app.hor_zSlider.Limits = [1, app.video_info.nz];
            app.hor_zSlider.Value = round(app.video_info.nz/2);

            app.xSlider.Limits = [1, app.video_info.nx];
            app.xSlider.Value = round(app.video_info.nx/2);

            app.ySlider.Limits = [1, app.video_info.ny];
            app.ySlider.Value = round(app.video_info.ny/2);

            % Render Frame 1
            app.visual_composer(1, round(app.video_info.nz/2), round(app.video_info.ny/2), round(app.video_info.nx/2));
        end

        % Key press function: NeuronActivityTracingUIFigure
        function NeuronActivityTracingUIFigureKeyPress(app, event)
            key = event.Key;

            switch key

                case 'leftarrow' % Go up in the z stack.
                    if app.ZSlider.Value > 1
                        zEvent.Value = app.ZSlider.Value - 1;
                        app.ZSliderValueChanging(zEvent);
                    end
                    %disp(key);

                case 'rightarrow' % Go down in the z stack.
                    if app.ZSlider.Value < app.ZSlider.Limits(2)
                        zEvent.Value = app.ZSlider.Value + 1;
                        app.ZSliderValueChanging(zEvent);
                    end
                    %disp(key);

                case 'uparrow' % Zoom in.
                    zoom(app.XY, 1 + app.zoom_percent);

                case 'downarrow' % Zoom out.
                    zoom(app.XY, 1 - app.zoom_percent);

                case 'f2'
                    app.add_worldline();

                case {'h','H'} % Toggle half zoom.
                    % Reset x-axis limits.
                    app.XY.XLim = [1, size(app.image_data, 2)];

                    % Upper -> lower half.
                    ylims = app.XY.YLim;
                    if ylims(1) < size(app.image_data, 1)/2
                        app.XY.YLim = [ceil(size(app.image_data, 1)/2), ...
                            size(app.image_data, 1)];

                        % Lower -> upper half.
                    else
                        app.XY.YLim = [1, floor(size(app.image_data, 1)/2)];
                    end

                case {'j','J'} % Toggle third zoom.
                    % Reset x-axis limits.
                    app.XY.XLim = [1, size(app.image_data, 2)];

                    % Upper -> middle third.
                    ylims = app.XY.YLim;
                    if ylims(1) < size(app.image_data, 1)/3
                        app.XY.YLim = [ceil(size(app.image_data, 1)/3), ...
                            floor(size(app.image_data, 1)*2/3)];

                        % Middle -> lower third.
                    elseif ylims(1) < size(app.image_data, 1)*2/3
                        app.XY.YLim = [ceil(size(app.image_data, 1)*2/3), ...
                            size(app.image_data, 1)];

                        % Lower -> upper third.
                    else
                        app.XY.YLim = [1, floor(size(app.image_data, 1)/3)];
                    end

                case {'k','K'} % Toggle quarter zoom.
                    % Reset x-axis limits.
                    app.XY.XLim = [1, size(app.image_data, 2)];

                    % Upper -> upper-middle quarter.
                    ylims = app.XY.YLim;
                    if ylims(1) < size(app.image_data, 1)/4
                        app.XY.YLim = [ceil(size(app.image_data, 1)/4), ...
                            floor(size(app.image_data, 1)*2/4)];

                        % Upper-middle -> lower-middle quarter.
                    elseif ylims(1) < size(app.image_data, 1)*2/4
                        app.XY.YLim = [ceil(size(app.image_data, 1)*2/4), ...
                            floor(size(app.image_data, 1)*3/4)];

                        % Lower-middle -> lower quarter.
                    elseif ylims(1) < size(app.image_data, 1)*3/4
                        app.XY.YLim = [ceil(size(app.image_data, 1)*3/4), ...
                            size(app.image_data, 1)];

                        % Lower -> upper quarter.
                    else
                        app.XY.YLim = [1, floor(size(app.image_data, 1)/4)];
                    end

                case {'p','P'} % Toggle image panning.

                    % Toggle image panning.
                    if app.is_panning
                        pan(app.XY,'off');
                        pause(0.5);
                        RestorePointer(app);
                        app.is_panning = false;
                    else
                        pan(app.XY,'on');
                        pause(0.5);
                        app.is_panning = true;
                    end

                case {'r','R'} % Reset the figure to center.
                    app.XY.XLim = [1, size(app.image_data, 2)];
                    app.XY.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XLim = [1, size(app.image_data, 2)];
                    %app.XY.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XAxisLocation = 'bottom';
                    %app.XY.YAxisLocation = 'left';

                    %case {'x','X'} % Switch mouse to crosshairs.
                    %zoom(app.XY,'on');
                    %pause(0.2);
                    %zoom(app.XY,'off');

                case {'i','I'} % Toggle showing neuron IDs.
                    app.show_labels = ~app.show_labels;
                    DrawZSlice(app, app.ZSlider, app.image_view, app.XY);

                case {'a', 'A'} % Toggle autocompleting neuron names.
                    if app.ctrl_state
                        app.tSlider.Value = app.tSlider.Value + app.FwdFramesEditField.Value;
                    end

                case {'b','B'} % Toggle showing neuron birth times.
                    ToggleNeuronBirthTimes(app, event);

                case {'d','D'}
                    if app.ctrl_state
                        app.tSlider.Value = app.tSlider.Value - app.BwdFramesEditField.Value;
                    end

                case 'control'
                    app.ctrl_state = 1;

                case '0'
                    if app.ctrl_state
                        app.jumpToUnIDd('frame');
                    end

                case '1'
                    if app.ctrl_state
                        app.tSlider.Value = app.JmpFrameEditField_1.Value;
                    end

                case '2'
                    if app.ctrl_state
                        app.tSlider.Value = app.JmpFrameEditField_2.Value;
                    end

                otherwise
                    % Do nothing.
            end

        end

        % Button pushed function: RecommendFramesButton
        function RecommendFramesButtonPushed(app, event)
            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Analyzing frames...','Indeterminate','on');
            
            if isdeployed
                cmd = [fullfile(app.script_dir, sprintf('recommend_frames%s', app.script_ext)),sprintf(' --channel=1 --dataset="%s" --nx=%.f --ny=%.f --nz=%.f --nc=%.f --nt=%.f',app.video_path, app.video_info.nx, app.video_info.ny, app.video_info.nz, app.video_info.nc, app.video_info.nt)]
                [status, output] = system(sprintf('%s', cmd));
                t_ref = splitlines(output);
                t_ref = t_ref(end-1);
                t_ref = t_ref{1};
                t_ref = t_ref(2:end-1);
                t_ref = split(t_ref, ', ');
                close(d)
    
                d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Creating bookmarks...','Indeterminate','on');
    
                for n=1:size(t_ref,1)
                    bmLabel = sprintf('Recommended Frame #%.f (Frame %.f)', n, str2num(t_ref{n})+1);
                    bmFrame = str2num(t_ref{n});
                    
                    app.BookmarkListBox.Items = [app.BookmarkListBox.Items, bmLabel];
                    if size(app.BookmarkListBox.ItemsData)>0
                        app.BookmarkListBox.ItemsData = [app.BookmarkListBox.ItemsData, bmFrame+1];
                    else
                        app.BookmarkListBox.ItemsData = [bmFrame+1];
                    end
                end

                close(d)
            else
                t_ref = pyrunfile([fullfile(app.script_dir, sprintf('recommend_frames%s', app.script_ext)),sprintf(' --channel=1 --dataset="%s" --nx=%.f --ny=%.f --nz=%.f --nc=%.f --nt=%.f',app.video_path, app.video_info.nx, app.video_info.ny, app.video_info.nz, app.video_info.nc, app.video_info.nt)],'t_ref');
                close(d)
    
                d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Creating bookmarks...','Indeterminate','on');
    
                for n=1:size(t_ref,2)
                    bmLabel = sprintf('Recommended Frame #%.f (Frame %.f)', n, double(t_ref(n))+1);
                    bmFrame = double(t_ref(n));
                    
                    app.BookmarkListBox.Items = [app.BookmarkListBox.Items, bmLabel];
                    if size(app.BookmarkListBox.ItemsData)>0
                        app.BookmarkListBox.ItemsData = [app.BookmarkListBox.ItemsData, bmFrame+1];
                    else
                        app.BookmarkListBox.ItemsData = [bmFrame+1];
                    end
                end
                close(d)
            end
        end

        % Button pushed function: TrackNeuronsButton
        function TrackNeuronsButtonPushed(app, event)
            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Saving your edits...','Indeterminate','on');
            annos = app.video_neurons; 
            info = app.video_info;
            save(fullfile(pwd, 'cache.mat'), 'annos');
            save(fullfile(pwd, 'meta_cache.mat'), 'info');
            app.create_metadata();
            close(d)
            
            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Converting annotations...','Indeterminate','on');
            if isdeployed
                sprintf('%s %s %s %s', fullfile(app.script_dir, sprintf('converter%s', app.script_ext)), fullfile(pwd, 'cache.mat'), fullfile(pwd, 'meta_cache.mat'), app.video_path)
            else 
                py_cmd = sprintf('%s %s %s %s', fullfile(app.script_dir, sprintf('converter%s', app.script_ext)), fullfile(pwd, 'cache.mat'), fullfile(pwd, 'meta_cache.mat'), app.video_path)
                pyrunfile(py_cmd);
            end
            close(d)

            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Compiling arguments...','Indeterminate','on');
            args = app.compile_arguments();
            close(d)

            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Tracking neurons...','Indeterminate','on');
            if isdeployed
                [status, ~] = system(sprintf('%s %s', fullfile(app.script_dir, sprintf('main%s', app.script_ext)), args));
            else
                py_cmd = sprintf('python %s %s', fullfile(app.script_dir, sprintf('main%s', app.script_ext)), args)
                [status, ~] = system(py_cmd, '-echo');
                status
            end
            app.load_annotations();
            close(d)

            switch status
                case 0
                    app.load_annotations();
                otherwise
                    error('Failed to run tracking routine.');
            end
        end

        % Button pushed function: ExtractActivityButton
        function ExtractActivityButtonPushed(app, event)
            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Extracting activity...','Indeterminate','on');
            if isdeployed
                system(sprintf('%s --channel=1 --dataset=%s', fullfile(app.script_dir, sprintf('extract_traces%s', app.script_ext)), app.video_path))
            else
                py_cmd = sprintf('%s --channel=1 --dataset=%s', fullfile(app.script_dir, sprintf('extract_traces%s', app.script_ext)), app.video_path)
                pyrunfile(py_cmd);            
            end

            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Loading activity...', 'Indeterminate','on');
            app.activity = struct();

            [path, ~, ~] = fileparts(app.video_path);

            % Read the CSV file into a table
            tbl = readtable(fullfile(path, 'results.csv'), ReadVariableNames=true);
            tbl
            
            % Loop through each row of the table
            for i = 1:height(tbl)
                % Ensure the worldline name is a string and valid field name
                worldlineName = string(tbl.worldline{i});

                if ~isfield(app.activity, worldlineName)
                    % If this worldline name is not yet a key in the struct, add it
                    app.activity.(worldlineName) = [];
                end

                T = tbl(i, 4:end);
                app.activity.(worldlineName) = table2array(T(1, :));
                app.ActivityListBox.Items = [app.ActivityListBox.Items, worldlineName];
    
                if size(app.ActivityListBox.ItemsData)>0
                    app.ActivityListBox.ItemsData = [app.ActivityListBox.ItemsData, worldlineName];
                else
                    app.ActivityListBox.ItemsData = [worldlineName];
                end
            end

            app.activity

            close(d)
        end

        % Button pushed function: InsertlastIDdFrameButton
        function InsertlastIDdFrameButtonPushed(app, event)
            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title',"Importing from last ID'd frame...",'Indeterminate','on');
            earlier_frames = app.id_frames(app.id_frames < app.tSlider.Value);

            for n=1:size(app.video_neurons, 2)
                app.video_neurons(n).rois(app.tSlider.Value) = app.video_neurons(n).rois(max(earlier_frames));  
                app.id_frames = [app.id_frames; app.tSlider.Value];
            end

            app.visual_composer();
            close(d)
        end

        % Key release function: NeuronActivityTracingUIFigure
        function NeuronActivityTracingUIFigureKeyRelease(app, event)
            key = event.Key;
            
            switch key
                case 'control'
                    app.ctrl_state = 0;
            end
        end

        % Button pushed function: AssignrandomcolorstoblanksButton
        function AssignrandomcolorstoblanksButtonPushed(app, event)
            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Updating colors...','Indeterminate','on');
            for n=1:size(app.video_neurons, 2)
                if app.video_neurons(n).worldline.color == [0.8, 0.8, 0.8]
                    app.video_neurons(n).worldline.color = rand(1, 3);
                    app.video_neurons(n).worldline.style = uistyle("FontColor", app.video_neurons(n).worldline.color);
                    addStyle(app.WorldlineTree, app.video_neurons(n).worldline.style, "node", app.video_neurons(n).worldline.node);
                end
            end
            app.visual_composer();
            close(d)
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            app.visual_composer();
        end

        % Value changed function: GSlider
        function GSliderValueChanged(app, event)
            app.visual_composer();
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            app.visual_composer();
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            app.xSlider.Value = round(app.xSlider.Value);
            app.visual_composer();
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            app.ySlider.Value = round(app.ySlider.Value);
            app.visual_composer();
        end

        % Value changed function: hor_zSlider
        function hor_zSliderValueChanged(app, event)
            app.hor_zSlider.Value = round(app.hor_zSlider.Value);
            app.vert_zSlider.Value = round(app.hor_zSlider.Value);
            app.visual_composer();
        end

        % Value changed function: vert_zSlider
        function vert_zSliderValueChanged(app, event)
            app.vert_zSlider.Value = round(app.vert_zSlider.Value);
            app.hor_zSlider.Value = round(app.vert_zSlider.Limits(2)-app.vert_zSlider.Value);
            app.visual_composer();
        end

        % Value changed function: tSlider
        function tSliderValueChanged(app, event)
            app.tSlider.Value = round(app.tSlider.Value);
            app.visual_composer();
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            delete(app.xz_xline);
            delete(app.xy_xline);
            app.xz_xline = xline(app.xzAxes, round(event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.xy_xline = xline(app.xyAxes, round(event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Value changing function: hor_zSlider
        function hor_zSliderValueChanging(app, event)
            delete(app.yz_xline);
            app.yz_xline = xline(app.yzAxes, app.yzAxes.XLim(2)*(round(event.Value)/app.video_info.nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Value changing function: vert_zSlider
        function vert_zSliderValueChanging(app, event)
            delete(app.xz_yline);
            app.xz_yline = yline(app.xzAxes, app.xzAxes.YLim(2)-app.xzAxes.YLim(2)*(round(event.Value)/app.video_info.nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            delete(app.yz_yline)
            delete(app.xy_yline)
            app.yz_yline = yline(app.yzAxes, round(app.video_info.ny-event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.xy_yline = yline(app.xyAxes, round(app.video_info.ny-event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Size changed function: NeuronActivityTracingUIFigure
        function NeuronActivityTracingUIFigureSizeChanged(app, event)
            %height = app.GridLayout2.RowHeight(1);
            %width = app.GridLayout2.ColumnWidth(1);
            %height = height{1}
            %width = width{1}
            %if width/height > app.video_info.aspect_ratio
            %    app.GridLayout2.ColumnWidth(1) = {app.video_info.aspect_ratio * height};
            %elseif width/height < app.video_info.aspect_ratio
            %    app.GridLayout2.RowHeight(1) = {app.video_info.aspect_ratio * width};
            %end
            
            %app.xyAxes.XLim = [1, app.video_info.nx];
            %app.xyAxes.YLim = [1, app.video_info.ny];
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            app.ColorButton.BackgroundColor = uisetcolor(app.ColorButton.BackgroundColor, 'Select Color');
            app.edit_worldline(app.WorldlineIDEditField.Value, 'color', app.ColorButton.BackgroundColor);
        end

        % Selection changed function: WorldlineTree
        function WorldlineTreeSelectionChanged(app, event)
            app.edit_worldline(app.WorldlineTree.SelectedNodes.NodeData, 'select');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.edit_worldline(app.WorldlineIDEditField.Value, 'delete');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.edit_worldline(app.WorldlineIDEditField.Value, 'save');
        end

        % Button pushed function: SetBookmarkButton
        function SetBookmarkButtonPushed(app, event)
            bm_frame = round(app.tSlider.Value);

            if ~isempty(app.BookmarkNameEditField.Value)
                bm_name = app.BookmarkNameEditField.Value;
            else
                bm_name = sprintf('Bookmark #%.f', size(app.BookmarkListBox.Items,2)+1);
            end

            app.BookmarkListBox.Items = [app.BookmarkListBox.Items, sprintf("%s (Frame %.f)", bm_name, bm_frame)];

            if size(app.BookmarkListBox.ItemsData)>0
                app.BookmarkListBox.ItemsData = [app.BookmarkListBox.ItemsData, bm_frame];
            else
                app.BookmarkListBox.ItemsData = [bm_frame];
            end

            app.BookmarkNameEditField.Value = '';
        end

        % Button pushed function: GotoBookmarkButton
        function GotoBookmarkButtonPushed(app, event)
            if ~isempty(app.BookmarkListBox.Value)
                app.tSlider.Value = app.BookmarkListBox.Value;
                app.visual_composer();
            else
                uiconfirm(app.NeuronActivityTracingUIFigure,"Error", 'No bookmark selected.');
            end
        end

        % Button pushed function: ViewPreprintButton
        function ViewPreprintButtonPushed(app, event)
            web('https://www.biorxiv.org/content/10.1101/2022.07.18.500485v1')
        end

        % Button pushed function: GithubRepositoryButton
        function GithubRepositoryButtonPushed(app, event)
            web('https://github.com/venkatachalamlab/ZephIR')
        end

        % Button pushed function: LaboratoryPageButton
        function LaboratoryPageButtonPushed(app, event)
            web('https://venkatachalamlab.org/')
        end

        % Value changed function: MarkerSizeSlider, MarkerSizeSlider_2
        function MarkerSizeSliderValueChanged(app, event)
            app.visual_composer();
        end

        % Button pushed function: InsertNeuroPALNeuronsButton
        function InsertNeuroPALNeuronsButtonPushed(app, event)
            d = uiprogressdlg(app.NeuronActivityTracingUIFigure,'Title','Importing neurons...','Indeterminate','on');
            
            target_neurons = app.image_neurons.neurons;
            target_positions = app.image_neurons.get_positions;
            
            if ~all(inpolygon(app.video_info.nx, app.video_info.ny, target_positions(2), target_positions(1)))
                uiconfirm(app.NeuronActivityTracingUIFigure, 'Volume dimensions do not match video dimensions. Imported array will be resized.', 'Warning!');

                vol_nx = size(app.image_view, 2);
                vol_ny = size(app.image_view, 1);

                x_ratio = app.video_info.nx / vol_nx;
                y_ratio = app.video_info.ny / vol_ny;

                for n=1:size(target_neurons, 2)
                    target_neurons(n).position(2) = target_neurons(n).position(2) * x_ratio;
                    target_neurons(n).position(1) = target_neurons(n).position(1) * y_ratio;
                    app.add_worldline(target_neurons(n))
                    target_neurons(n).position(2) = target_neurons(n).position(2) / x_ratio;
                    target_neurons(n).position(1) = target_neurons(n).position(1) / y_ratio;
                end
            else
                for n=1:size(app.image_neurons.neurons, 2)
                    app.add_worldline(target_neurons(n))
                end
            end

            app.visual_composer();
            close(d)
        end

        % Button pushed function: ManipulateNeuronsButton
        function ManipulateNeuronsButtonPushed(app, event)
            set(app.AdjustNeuronMarkerAlignmentPanel, 'Visible', 'on');
            set(app.DefaultTabGroup, 'Visible', 'off');
            
            app.repo_neurons = app.video_neurons;
            app.sel_neurons = 1:size(app.video_neurons, 2);
            app.sel_neurons = app.sel_neurons';
        end

        % Button pushed function: CancelButton_2
        function CancelButton_2Pushed(app, event)
            app.video_neurons = app.repo_neurons;
            set(app.DefaultTabGroup, 'Visible', 'on');
            set(app.AdjustNeuronMarkerAlignmentPanel, 'Visible', 'off');
            app.visual_composer();
        end

        % Button pushed function: ResetMarkerPositionsButton
        function ResetMarkerPositionsButtonPushed(app, event)
            app.video_neurons = app.repo_neurons;
            app.visual_composer();
        end

        % Button pushed function: SelectNeuronSubsetButton
        function SelectNeuronSubsetButtonPushed(app, event)
            uiwait(msgbox('Click and drag your cursor on the image to select the neurons you want to manipulate.','Instructions'))
            roi = drawfreehand(app.xyAxes,'Color','white','StripeColor','m');

            xVertices = roi.Position(:, 1);
            yVertices = roi.Position(:, 2);
            
            app.sel_neurons = [];

            for n=1:size(app.video_neurons, 2)
                try
                    [in, on] = inpolygon(app.video_neurons(n).rois(app.tSlider.Value).xy_pos(1), app.video_neurons(n).rois(app.tSlider.Value).xy_pos(2), xVertices, yVertices);
                    if  in || on
                        app.sel_neurons = [app.sel_neurons; n];
                    end
                end
            end

            delete(roi);
        end

        % Button pushed function: Up
        function UpButtonPushed(app, event)
            for n=1:size(app.sel_neurons)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice - app.MoveEditField.Value;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).yz_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: Down
        function DownButtonPushed(app, event)
            for n=1:size(app.sel_neurons)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice + app.MoveEditField.Value;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).yz_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            for n=1:size(app.sel_neurons)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice + app.MoveEditField.Value;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xz_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            for n=1:size(app.sel_neurons)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice - app.MoveEditField.Value;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xz_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: FlipHorizontallyButton
        function FlipHorizontallyButtonPushed(app, event)
            min = Inf;
            max = -Inf;
            for n=1:size(app.sel_neurons,1)
                try
                    if app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) < min
                        min = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    end
    
                    if app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) > max
                        max = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    end
                end
            end

            mid = mean([max, min]);


            for n=1:size(app.sel_neurons,1)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice = 2 * mid - app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xz_pos(1) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: FlipVerticallyButton
        function FlipVerticallyButtonPushed(app, event)
            min = Inf;
            max = -Inf;
            
            for n=1:size(app.sel_neurons,1)
                try
                    if app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) < min
                        max = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    end
    
                    if app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) > max
                        min = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    end
                end
            end

            mid = mean([max, min]);

            for n=1:size(app.sel_neurons,1)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice = 2 * mid - app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).yz_pos(2) = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice;
                end
            end

            app.visual_composer;
        end

        % Button pushed function: CenterVerticallyButton
        function CenterVerticallyButtonPushed(app, event)
            % Calculate the midpoint of the frame width
            mid = app.video_info.ny / 2;
            
            % Calculate the current mean position of selected neurons
            currentMean = 0;
            for n=1:size(app.sel_neurons,1)
                try
                    currentMean = currentMean + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2);
                end
            end
            currentMean = currentMean / size(app.sel_neurons,1);
            
            % Calculate the shift needed to center the neurons
            shiftAmount = mid - currentMean;
            
            % Apply the shift to all selected neurons
            for n=1:size(app.sel_neurons,1)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) + shiftAmount;
                        
                    % Assuming you want to adjust the x_slice and xz_pos similarly
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).y_slice + shiftAmount;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).yz_pos(2) = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).yz_pos(2) + shiftAmount;
                end
            end
            
            % Call the visual composer to update the visualization
            app.visual_composer;
        end

        % Button pushed function: CenterHorizontallyButton
        function CenterHorizontallyButtonPushed(app, event)
            % Calculate the midpoint of the frame width
            mid = app.video_info.nx / 2;
            
            % Calculate the current mean position of selected neurons
            currentMean = 0;
            for n=1:size(app.sel_neurons,1)
                try
                    currentMean = currentMean + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1);
                end
            end
            currentMean = currentMean / size(app.sel_neurons,1);
            
            % Calculate the shift needed to center the neurons
            shiftAmount = mid - currentMean;
            
            % Apply the shift to all selected neurons
            for n=1:size(app.sel_neurons,1)
                try
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) + shiftAmount;
                        
                    % Assuming you want to adjust the x_slice and xz_pos similarly
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).x_slice + shiftAmount;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xz_pos(1) = ...
                        app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xz_pos(1) + shiftAmount;
                end
            end
            
            % Call the visual composer to update the visualization
            app.visual_composer;
        end

        % Button pushed function: CCW
        function CCWButtonPushed(app, event)
            % Convert angle from degrees to radians
            theta = app.RotateEditField.Value * (pi / 180);
            
            % Calculate the centroid of selected neurons
            centroidX = 0;
            centroidY = 0;
            for n = 1:size(app.sel_neurons, 1)
                centroidX = centroidX + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1);
                centroidY = centroidY + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2);
            end
            centroidX = centroidX / size(app.sel_neurons, 1);
            centroidY = centroidY / size(app.sel_neurons, 1);
            
            % Rotation matrix for counterclockwise rotation
            R = [cos(theta) -sin(theta); sin(theta) cos(theta)];
            
            % Rotate each selected neuron
            for n = 1:size(app.sel_neurons, 1)
                try
                    % Get the current position
                    posX = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1);
                    posY = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2);
                    
                    % Translate point to origin (centroid based)
                    posX = posX - centroidX;
                    posY = posY - centroidY;
                    
                    % Rotate point
                    rotatedPos = R * [posX; posY];
                    
                    % Translate point back
                    rotatedPos(1) = rotatedPos(1) + centroidX;
                    rotatedPos(2) = rotatedPos(2) + centroidY;
                    
                    % Update position
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = rotatedPos(1);
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = rotatedPos(2);
                end
            end
            
            % Call the visual composer to update the visualization
            app.visual_composer;
        end

        % Button pushed function: CW
        function CWButtonPushed(app, event)
            % Calculate the centroid of the selected neurons
            centroidX = 0;
            centroidY = 0;
            for n = 1:size(app.sel_neurons,1)
                centroidX = centroidX + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1);
                centroidY = centroidY + app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2);
            end
            centroidX = centroidX / size(app.sel_neurons,1);
            centroidY = centroidY / size(app.sel_neurons,1);
            
            % Convert degrees to radians for rotation
            jRad = app.RotateEditField.Value * (pi / 180);
            
            % Rotate each neuron around the centroid
            for n = 1:size(app.sel_neurons,1)
                try
                    % Translate point to origin (relative to centroid)
                    x = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) - centroidX;
                    y = app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) - centroidY;
                    
                    % Apply rotation
                    xRotated = x * cos(jRad) + y * sin(jRad);
                    yRotated = -x * sin(jRad) + y * cos(jRad);
                    
                    % Translate point back
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(1) = xRotated + centroidX;
                    app.video_neurons(app.sel_neurons(n)).rois(app.tSlider.Value).xy_pos(2) = yRotated + centroidY;
                end
            end
            
            % Call the visual composer to update the visualization
            app.visual_composer;
        end

        % Value changed function: EnableadvancedparametersCheckBox
        function EnableadvancedparametersCheckBoxValueChanged(app, event)
            switch app.EnableadvancedparametersCheckBox.Value
                case 0
                    set(app.EditadvancedparametersButton, 'Enable', 'off');
                case 1
                    if isempty(app.EnableadvancedparametersCheckBox.Tag)
                        uiconfirm(app.NeuronActivityTracingUIFigure, "Editing advanced parameters can significantly affect performance and multiply processing time. Make sure you know what you're doing.", 'Warning!');
                        app.EnableadvancedparametersCheckBox.Tag = 'Warning issued.';
                    end

                    set(app.EditadvancedparametersButton, 'Enable', 'on');
            end
        end

        % Button pushed function: EditadvancedparametersButton
        function EditadvancedparametersButtonPushed(app, event)
            set(app.AdvancedParameterPanel, 'Visible', 'on');
            set(app.BasicParameterPanel, 'Visible', 'off');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            set(app.AdvancedParameterPanel, 'Visible', 'off');
            set(app.BasicParameterPanel, 'Visible', 'on');
        end

        % Button pushed function: OpenGridSearchMenuButton
        function OpenGridSearchMenuButtonPushed(app, event)
            set(app.AdvancedParameterPanel, 'Visible', 'off');
            set(app.GridSearchPanel, 'Visible', 'on');
        end

        % Button pushed function: CancelGridSearchButton
        function CancelGridSearchButtonPushed(app, event)
            set(app.AdvancedParameterPanel, 'Visible', 'on');
            set(app.GridSearchPanel, 'Visible', 'off');
        end

        % Value changed function: ShowallneuronsCheckBox
        function ShowallneuronsCheckBoxValueChanged(app, event)
            app.ShowallneuronsCheckBox_2.Value = app.ShowallneuronsCheckBox.Value;
            app.visual_composer();
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            set(app.DefaultTabGroup, 'Visible', 'on');
            set(app.AdjustNeuronMarkerAlignmentPanel, 'Visible', 'off');
        end

        % Button pushed function: DeleteBookmarkButton
        function DeleteBookmarkButtonPushed(app, event)
            if ~isempty(app.BookmarkListBox.Value)
                [~,idx] = ismember(app.BookmarkListBox.Value, app.BookmarkListBox.Items);
                app.BookmarkListBox.Items(idx) = [];
            else
                uiconfirm(app.NeuronActivityTracingUIFigure,"Error", 'No bookmark selected.');
            end
        end

        % Value changed function: OverlayFrameMIPCheckBox
        function OverlayFrameMIPCheckBoxValueChanged(app, event)
            app.OverlayFrameMIPCheckBox_2.Value = app.OverlayFrameMIPCheckBox.Value;
            app.visual_composer();
        end

        % Value changed function: OverlaylastIDdframeCheckBox_2
        function OverlaylastIDdframeCheckBox_2ValueChanged(app, event)
            app.OverlaylastIDdframeCheckBox.Value = app.OverlaylastIDdframeCheckBox_2.Value;
            app.visual_composer();
        end

        % Value changed function: OverlayFrameMIPCheckBox_2
        function OverlayFrameMIPCheckBox_2ValueChanged(app, event)
            app.OverlayFrameMIPCheckBox.Value = app.OverlayFrameMIPCheckBox_2.Value;
            app.visual_composer();
        end

        % Value changed function: OverlaylastIDdframeCheckBox
        function OverlaylastIDdframeCheckBoxValueChanged(app, event)
            app.OverlaylastIDdframeCheckBox_2.Value = app.OverlaylastIDdframeCheckBox.Value;
            app.visual_composer();
        end

        % Value changed function: ShowallneuronsCheckBox_2
        function ShowallneuronsCheckBox_2ValueChanged(app, event)
            app.ShowallneuronsCheckBox.Value = app.ShowallneuronsCheckBox_2.Value;
            app.visual_composer();
        end

        % Button pushed function: ExportparametersButton
        function ExportparametersButtonPushed(app, event)
            [path, ~, ~] = fileparts(app.video_path);
            app.export_arguments(fullfile(path, 'arguments.json'));
        end

        % Button pushed function: ImportparametersButton
        function ImportparametersButtonPushed(app, event)
            [path, ~, ~] = fileparts(app.video_path);
            file_info = [path ';*.json'];
            [name, path, ~] = uigetfile(file_info, 'Select json file.');
            app.import_arguments(fullfile(path, name));
        end

        % Value changed function: ActivityListBox
        function ActivityListBoxValueChanged(app, event)
            value = app.ActivityListBox.Value;
            activity_color = [0, 0, 0];

            for idx = 1:size(app.video_neurons, 2)  
                if contains(string(app.video_neurons(idx).worldline.name), value)
                    activity_color = app.video_neurons(idx).worldline.color;
                    break
                end
            end
            
            plot(app.ActivityAxes, app.activity.(value), Color=activity_color)
            app.ActivityAxes.XLim = [1, app.video_info.nt];
            app.ActivityAxes.YLim = [0, max(max(app.activity.(value)), 1)];
            title(app.ActivityAxes, sprintf('%s Activity over Time', value));
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NeuronActivityTracingUIFigure = uifigure('Visible', 'off');
            app.NeuronActivityTracingUIFigure.Name = 'Neuron Activity Tracing';
            app.NeuronActivityTracingUIFigure.SizeChangedFcn = createCallbackFcn(app, @NeuronActivityTracingUIFigureSizeChanged, true);
            app.NeuronActivityTracingUIFigure.KeyPressFcn = createCallbackFcn(app, @NeuronActivityTracingUIFigureKeyPress, true);
            app.NeuronActivityTracingUIFigure.KeyReleaseFcn = createCallbackFcn(app, @NeuronActivityTracingUIFigureKeyRelease, true);
            app.GridLayout = uigridlayout(app.NeuronActivityTracingUIFigure);
            app.DefaultTabGroup = uitabgroup(app.GridLayout);
            app.AnnotationSupportTab = uitab(app.DefaultTabGroup);
            app.AnnotationSupportTab.Title = 'Annotation Support';
            app.GridLayout7 = uigridlayout(app.AnnotationSupportTab);
            app.BookmarkedFramesPanel = uipanel(app.GridLayout7);
            app.BookmarkedFramesPanel.Title = 'Bookmarked Frames';
            app.GridLayout8 = uigridlayout(app.BookmarkedFramesPanel);
            app.BookmarkListBox = uilistbox(app.GridLayout8);
            app.BookmarkListBox.Items = {};
            app.BookmarkListBox.Value = {};
            app.GotoBookmarkButton = uibutton(app.GridLayout8, 'push');
            app.GotoBookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @GotoBookmarkButtonPushed, true);
            app.GotoBookmarkButton.Text = 'Go to Bookmark';
            app.SetBookmarkButton = uibutton(app.GridLayout8, 'push');
            app.SetBookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @SetBookmarkButtonPushed, true);
            app.SetBookmarkButton.Text = 'Set Bookmark';
            app.BookmarkNameEditField = uieditfield(app.GridLayout8, 'text');
            app.BookmarkNameEditField.Placeholder = 'Bookmark Name';
            app.DeleteBookmarkButton = uibutton(app.GridLayout8, 'push');
            app.DeleteBookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBookmarkButtonPushed, true);
            app.DeleteBookmarkButton.Text = 'Delete Bookmark';
            app.ColorControlPanel = uipanel(app.GridLayout7);
            app.ColorControlPanel.Title = 'Color Control';
            app.GridLayout16 = uigridlayout(app.ColorControlPanel);
            app.RSliderLabel = uilabel(app.GridLayout16);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.GridLayout16);
            app.RSlider.Limits = [0 10];
            app.RSlider.MajorTicks = [];
            app.RSlider.MajorTickLabels = {''};
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.MinorTicks = [];
            app.RSlider.Value = 1;
            app.GSliderLabel = uilabel(app.GridLayout16);
            app.GSliderLabel.Text = 'G';
            app.GSlider = uislider(app.GridLayout16);
            app.GSlider.Limits = [0 10];
            app.GSlider.MajorTicks = [];
            app.GSlider.MajorTickLabels = {''};
            app.GSlider.ValueChangedFcn = createCallbackFcn(app, @GSliderValueChanged, true);
            app.GSlider.MinorTicks = [];
            app.GSlider.Value = 1;
            app.BSliderLabel = uilabel(app.GridLayout16);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.GridLayout16);
            app.BSlider.Limits = [0 10];
            app.BSlider.MajorTicks = [];
            app.BSlider.MajorTickLabels = {''};
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.BSlider.MinorTicks = [];
            app.BSlider.Value = 1;
            app.HotkeysPanel = uipanel(app.GridLayout7);
            app.HotkeysPanel.Title = 'Hotkeys';
            app.GridLayout47 = uigridlayout(app.HotkeysPanel);
            app.Frame1HKButton = uibutton(app.GridLayout47, 'state');
            app.Frame1HKButton.Text = 'CTRL+1';
            app.JmpFrameEditField_1 = uieditfield(app.GridLayout47, 'numeric');
            app.JmpFrameEditField_1.Value = 1;
            app.Frame2HKButton = uibutton(app.GridLayout47, 'state');
            app.Frame2HKButton.Text = 'CTRL+2';
            app.JmpFrameEditField_2 = uieditfield(app.GridLayout47, 'numeric');
            app.JmpFrameEditField_2.Value = 2;
            app.DeleteHKButton = uibutton(app.GridLayout47, 'state');
            app.DeleteHKButton.Text = 'CTRL+a';
            app.JumpHKButton = uibutton(app.GridLayout47, 'state');
            app.JumpHKButton.Text = 'CTRL+0';
            app.TextArea = uitextarea(app.GridLayout47);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Value = {'Jump'; 'to Frame'};
            app.TextArea_2 = uitextarea(app.GridLayout47);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Value = {'Jump'; 'to Frame'};
            app.TextArea_3 = uitextarea(app.GridLayout47);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Enable = 'off';
            app.TextArea_3.Value = {'Jump'; 'to Un-ID''d'};
            app.TextArea_5 = uitextarea(app.GridLayout47);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Enable = 'off';
            app.TextArea_5.Value = {'Backward n'; 'Frames'};
            app.DropDown_2 = uidropdown(app.GridLayout47);
            app.DropDown_2.Items = {'In Frame', 'Cross Frame'};
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = 'In Frame';
            app.InsertHKButton = uibutton(app.GridLayout47, 'state');
            app.InsertHKButton.Text = 'CTRL+d';
            app.TextArea_4 = uitextarea(app.GridLayout47);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Enable = 'off';
            app.TextArea_4.Value = {'Forward n'; 'Frames'};
            app.FwdFramesEditField = uieditfield(app.GridLayout47, 'numeric');
            app.FwdFramesEditField.Value = 10;
            app.BwdFramesEditField = uieditfield(app.GridLayout47, 'numeric');
            app.BwdFramesEditField.Value = 10;
            app.AutosegmentFrameButton = uibutton(app.GridLayout7, 'push');
            app.AutosegmentFrameButton.Enable = 'off';
            app.AutosegmentFrameButton.Text = 'Autosegment Frame';
            app.Panel_40 = uipanel(app.GridLayout7);
            app.GridLayout48 = uigridlayout(app.Panel_40);
            app.AutojumponIDCheckBox = uicheckbox(app.GridLayout48);
            app.AutojumponIDCheckBox.Text = 'Auto-jump on ID.';
            app.OverlaylastIDdframeCheckBox_2 = uicheckbox(app.GridLayout48);
            app.OverlaylastIDdframeCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @OverlaylastIDdframeCheckBox_2ValueChanged, true);
            app.OverlaylastIDdframeCheckBox_2.Text = 'Overlay last ID''d frame.';
            app.OverlayNeuroPALvolCheckBox = uicheckbox(app.GridLayout48);
            app.OverlayNeuroPALvolCheckBox.Enable = 'off';
            app.OverlayNeuroPALvolCheckBox.Text = 'Overlay NeuroPAL vol.';
            app.OverlayFrameMIPCheckBox = uicheckbox(app.GridLayout48);
            app.OverlayFrameMIPCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayFrameMIPCheckBoxValueChanged, true);
            app.OverlayFrameMIPCheckBox.Text = 'Overlay Frame MIP.';
            app.ShowallneuronsCheckBox = uicheckbox(app.GridLayout48);
            app.ShowallneuronsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowallneuronsCheckBoxValueChanged, true);
            app.ShowallneuronsCheckBox.Text = 'Show all neurons.';
            app.Panel_63 = uipanel(app.GridLayout7);
            app.GridLayout53 = uigridlayout(app.Panel_63);
            app.MarkerSizeLabel = uilabel(app.GridLayout53);
            app.MarkerSizeLabel.Text = 'Marker Size';
            app.MarkerSizeSlider = uislider(app.GridLayout53);
            app.MarkerSizeSlider.Limits = [1 30];
            app.MarkerSizeSlider.MajorTicks = [];
            app.MarkerSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSliderValueChanged, true);
            app.MarkerSizeSlider.MinorTicks = [];
            app.MarkerSizeSlider.Value = 5;
            app.ManipulateNeuronsButton = uibutton(app.GridLayout7, 'push');
            app.ManipulateNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @ManipulateNeuronsButtonPushed, true);
            app.ManipulateNeuronsButton.Text = 'Manipulate Neurons';
            app.ParametersTab = uitab(app.DefaultTabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout58 = uigridlayout(app.ParametersTab);
            app.BasicParameterPanel = uipanel(app.GridLayout58);
            app.ParameterGridLayout = uigridlayout(app.BasicParameterPanel);
            app.EnableadvancedparametersCheckBox = uicheckbox(app.ParameterGridLayout);
            app.EnableadvancedparametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableadvancedparametersCheckBoxValueChanged, true);
            app.EnableadvancedparametersCheckBox.Text = 'Enable advanced parameters.';
            app.ImportparametersButton = uibutton(app.ParameterGridLayout, 'push');
            app.ImportparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ImportparametersButtonPushed, true);
            app.ImportparametersButton.Text = 'Import parameters';
            app.EditadvancedparametersButton = uibutton(app.ParameterGridLayout, 'push');
            app.EditadvancedparametersButton.ButtonPushedFcn = createCallbackFcn(app, @EditadvancedparametersButtonPushed, true);
            app.EditadvancedparametersButton.Enable = 'off';
            app.EditadvancedparametersButton.Text = 'Edit advanced parameters';
            app.ExportparametersButton = uibutton(app.ParameterGridLayout, 'push');
            app.ExportparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ExportparametersButtonPushed, true);
            app.ExportparametersButton.Text = 'Export parameters';
            app.AdvSetTab = uitabgroup(app.ParameterGridLayout);
            app.GeneralTab = uitab(app.AdvSetTab);
            app.GeneralTab.Title = 'General';
            app.GridLayout31_2 = uigridlayout(app.GeneralTab);
            app.AllowRotationsSwitchLabel = uilabel(app.GridLayout31_2);
            app.AllowRotationsSwitchLabel.Text = 'Allow Rotations';
            app.AllowRotationsSwitch = uiswitch(app.GridLayout31_2, 'slider');
            app.MotionPredictionSwitchLabel = uilabel(app.GridLayout31_2);
            app.MotionPredictionSwitchLabel.Text = 'Motion Prediction';
            app.MotionPredictionSwitch = uiswitch(app.GridLayout31_2, 'slider');
            app.UseGPUSwitchLabel = uilabel(app.GridLayout31_2);
            app.UseGPUSwitchLabel.Text = 'Use GPU';
            app.UseGPUSwitch = uiswitch(app.GridLayout31_2, 'slider');
            app.TrackingSelectionPanel = uipanel(app.GridLayout31_2);
            app.TrackingSelectionPanel.Title = 'Tracking Selection';
            app.GridLayout49 = uigridlayout(app.TrackingSelectionPanel);
            app.EditField_4 = uieditfield(app.GridLayout49, 'numeric');
            app.EndFrameLabel = uilabel(app.GridLayout49);
            app.EndFrameLabel.Text = 'End Frame:';
            app.EditField_5 = uieditfield(app.GridLayout49, 'numeric');
            app.StartingFrameLabel = uilabel(app.GridLayout49);
            app.StartingFrameLabel.Text = 'Starting Frame:';
            app.TrackspecificregionButton = uibutton(app.GridLayout49, 'push');
            app.TrackspecificregionButton.Text = 'Track specific region';
            app.TrackspecificneuronsButton = uibutton(app.GridLayout49, 'push');
            app.TrackspecificneuronsButton.Text = 'Track specific neurons';
            app.ImageMotionTab = uitab(app.AdvSetTab);
            app.ImageMotionTab.Title = 'Image & Motion';
            app.GridLayout34 = uigridlayout(app.ImageMotionTab);
            app.foveasigmaLabel = uilabel(app.GridLayout34);
            app.foveasigmaLabel.Text = 'fovea sigma';
            app.EditFieldLabel_4 = uilabel(app.GridLayout34);
            app.EditFieldLabel_4.Text = 'Sigma for Gaussian mask over foveated regions of the descriptors.';
            app.fovea_sigma = uieditfield(app.GridLayout34, 'numeric');
            app.fovea_sigma.Tooltip = {'Decreasing this can help prioritize keeping a keypoint at the center. Increase to a large number or set at -1 to disable.'};
            app.fovea_sigma.Value = 2.5;
            app.grid_shapeLabel = uilabel(app.GridLayout34);
            app.grid_shapeLabel.Text = 'grid_shape';
            app.SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel = uilabel(app.GridLayout34);
            app.SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel.Text = 'Size of the image descriptors in the xy-plane in pixels.';
            app.grid_shape = uieditfield(app.GridLayout34, 'numeric');
            app.grid_shape.Value = 25;
            app.dimmer_ratioLabel = uilabel(app.GridLayout34);
            app.dimmer_ratioLabel.Text = 'dimmer_ratio';
            app.CoefficientfordimmingnonfoveatedregionsEditFieldLabel = uilabel(app.GridLayout34);
            app.CoefficientfordimmingnonfoveatedregionsEditFieldLabel.Text = 'Coefficient for dimming non-foveated regions.';
            app.dimmer_ratio = uieditfield(app.GridLayout34, 'numeric');
            app.ComputationTab = uitab(app.AdvSetTab);
            app.ComputationTab.Title = 'Computation';
            app.GridLayout31 = uigridlayout(app.ComputationTab);
            app.n_epochLabel = uilabel(app.GridLayout31);
            app.n_epochLabel.Text = 'n_epoch';
            app.NumberofiterationsforimageregistrationLREditFieldLabel = uilabel(app.GridLayout31);
            app.NumberofiterationsforimageregistrationLREditFieldLabel.Text = 'Number of iterations for image registration, LR.';
            app.n_epoch_dLabel = uilabel(app.GridLayout31);
            app.n_epoch_dLabel.Text = 'n_epoch_d';
            app.n_epoch = uieditfield(app.GridLayout31, 'numeric');
            app.n_epoch.ValueDisplayFormat = '%.0f';
            app.n_epoch.Value = 40;
            app.EditField2_2Label = uilabel(app.GridLayout31);
            app.EditField2_2Label.Text = 'Number of iterations for feature detection regularization, LD.';
            app.n_epoch_d = uieditfield(app.GridLayout31, 'numeric');
            app.n_epoch_d.ValueDisplayFormat = '%.0f';
            app.n_epoch_d.Value = 10;
            app.n_chunksLabel = uilabel(app.GridLayout31);
            app.n_chunksLabel.Text = 'n_chunks';
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel = uilabel(app.GridLayout31);
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel.Text = 'Number of steps to divide the forward pass into.';
            app.n_chunks = uieditfield(app.GridLayout31, 'numeric');
            app.n_chunks.ValueDisplayFormat = '%.0f';
            app.n_chunks.Value = 10;
            app.sort_modeLabel = uilabel(app.GridLayout31);
            app.sort_modeLabel.Text = 'sort_mode';
            app.DropDown_2Label = uilabel(app.GridLayout31);
            app.DropDown_2Label.Text = 'Method for sorting frames and determining parent-child branches.';
            app.sort_mode = uidropdown(app.GridLayout31);
            app.sort_mode.Items = {'similarity', 'linear', 'depth'};
            app.sort_mode.Editable = 'on';
            app.sort_mode.Tooltip = {'''similarity'' minimizes distance between parent and child.'; '''linear'' branches out from reference frames linearly forwards and backwards, with every parent-child one frame apart, until it reaches the first frame, last frame, or another branch. Simplest and fastest.'; '''depth'' uses shortest-path grid search, then sorts frames based on depth in the resulting parent-child tree. This can scale up to O(n4) in computation with number of frames.'};
            app.sort_mode.Value = 'similarity';
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel = uilabel(app.ParameterGridLayout);
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel.Text = 'Hover over labels for further details or guidance.';
            app.WormMotionPanel = uipanel(app.ParameterGridLayout);
            app.WormMotionPanel.Title = 'Worm Motion';
            app.GridLayout5_2 = uigridlayout(app.WormMotionPanel);
            app.MotionSlider = uislider(app.GridLayout5_2);
            app.MotionSlider.Limits = [0 3];
            app.MotionSlider.MajorTicks = [0 1 2 3];
            app.MotionSlider.MajorTickLabels = {'None', 'Low', 'Mid', 'High'};
            app.MotionSlider.MinorTicks = [];
            app.ActivityTab = uitab(app.DefaultTabGroup);
            app.ActivityTab.Title = 'Activity';
            app.GridLayout3 = uigridlayout(app.ActivityTab);
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.ScanforIssuesButton = uibutton(app.GridLayout4, 'push');
            app.ScanforIssuesButton.Text = 'Scan for Issues';
            app.SaveExitButton = uibutton(app.GridLayout4, 'push');
            app.SaveExitButton.Text = 'Save & Exit';
            app.ActivityListBox = uilistbox(app.GridLayout4);
            app.ActivityListBox.Items = {};
            app.ActivityListBox.ValueChangedFcn = createCallbackFcn(app, @ActivityListBoxValueChanged, true);
            app.ActivityListBox.Value = {};
            app.LoadStimulusFileButton = uibutton(app.GridLayout4, 'push');
            app.LoadStimulusFileButton.Text = 'Load Stimulus File';
            app.Panel_2 = uipanel(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.ActivityAxes = uiaxes(app.GridLayout5);
            title(app.ActivityAxes, 'Neuron {x} Activity')
            xlabel(app.ActivityAxes, 'Frame')
            ylabel(app.ActivityAxes, 'df/f')
            zlabel(app.ActivityAxes, 'Z')
            app.ActivityAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ActivityAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.CreditTab = uitab(app.DefaultTabGroup);
            app.CreditTab.Title = 'Credit';
            app.GridLayout14_2 = uigridlayout(app.CreditTab);
            app.Label_21 = uilabel(app.GridLayout14_2);
            app.Label_21.Text = 'Tracing relies on a modified version of Zephir, a multiple object tracking algorithm based on image registration and developed by James Yu et al. ZephIR tracks keypoints in a 2D or 3D movie by registering image descriptors sampled around each keypoint. ';
            app.LaboratoryPageButton = uibutton(app.GridLayout14_2, 'push');
            app.LaboratoryPageButton.ButtonPushedFcn = createCallbackFcn(app, @LaboratoryPageButtonPushed, true);
            app.LaboratoryPageButton.Text = 'Laboratory Page';
            app.ViewPreprintButton = uibutton(app.GridLayout14_2, 'push');
            app.ViewPreprintButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPreprintButtonPushed, true);
            app.ViewPreprintButton.Text = 'View Preprint';
            app.GithubRepositoryButton = uibutton(app.GridLayout14_2, 'push');
            app.GithubRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @GithubRepositoryButtonPushed, true);
            app.GithubRepositoryButton.Text = 'Github Repository';
            app.Image2 = uiimage(app.GridLayout14_2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Data', 'Images', 'ZephIR_Logo.png');
            app.CitationTextAreaLabel = uilabel(app.GridLayout14_2);
            app.CitationTextAreaLabel.Text = 'Citation';
            app.CitationTextArea = uitextarea(app.GridLayout14_2);
            app.CitationTextArea.Value = {'Versatile Multiple Object Tracking in Sparse 2D/3D Videos Via Diffeomorphic Image Registration'; 'James Yu, Amin Nejatbakhsh, Mahdi Torkashvand, Sahana Gangadharan, Maedeh Seyedolmohadesin, Jinmahn Kim, Liam Paninski, Vivek Venkatachalam bioRxiv 2022.07.18.500485; doi: https://doi.org/10.1101/2022.07.18.500485'; 'This article is a preprint and has not been certified by peer review'};
            app.VolumeViewerPanel = uipanel(app.GridLayout);
            app.VolumeViewerPanel.Title = 'Volume Viewer';
            app.GridLayout2 = uigridlayout(app.VolumeViewerPanel);
            app.xyAxes = uiaxes(app.GridLayout2);
            app.xyAxes.XTickLabel = '';
            app.xyAxes.YTickLabel = '';
            app.xyAxes.Tag = 'xy';
            app.xzAxes = uiaxes(app.GridLayout2);
            app.xzAxes.XTickLabel = '';
            app.xzAxes.YTickLabel = '';
            app.xzAxes.Tag = 'xz';
            app.yzAxes = uiaxes(app.GridLayout2);
            app.yzAxes.XTickLabel = '';
            app.yzAxes.YTickLabel = '';
            app.yzAxes.Tag = 'yz';
            app.xSlider = uislider(app.GridLayout2);
            app.xSlider.MajorTicks = [];
            app.xSlider.MajorTickLabels = {''};
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.xSlider.MinorTicks = [];
            app.xSlider.Value = 50;
            app.hor_zSlider = uislider(app.GridLayout2);
            app.hor_zSlider.MajorTicks = [];
            app.hor_zSlider.MajorTickLabels = {''};
            app.hor_zSlider.ValueChangedFcn = createCallbackFcn(app, @hor_zSliderValueChanged, true);
            app.hor_zSlider.ValueChangingFcn = createCallbackFcn(app, @hor_zSliderValueChanging, true);
            app.hor_zSlider.MinorTicks = [];
            app.hor_zSlider.Value = 50;
            app.ySlider = uislider(app.GridLayout2);
            app.ySlider.MajorTicks = [];
            app.ySlider.MajorTickLabels = {''};
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.ySlider.MinorTicks = [];
            app.ySlider.Value = 50;
            app.vert_zSlider = uislider(app.GridLayout2);
            app.vert_zSlider.MajorTicks = [];
            app.vert_zSlider.MajorTickLabels = {''};
            app.vert_zSlider.ValueChangedFcn = createCallbackFcn(app, @vert_zSliderValueChanged, true);
            app.vert_zSlider.ValueChangingFcn = createCallbackFcn(app, @vert_zSliderValueChanging, true);
            app.vert_zSlider.MinorTicks = [];
            app.vert_zSlider.Value = 50;
            app.NeuronInformationPanel = uipanel(app.GridLayout);
            app.NeuronInformationPanel.Title = 'Neuron Information';
            app.GridLayout9 = uigridlayout(app.NeuronInformationPanel);
            app.XCoordinateEditFieldLabel = uilabel(app.GridLayout9);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.GridLayout9, 'numeric');
            app.YCoordinateEditFieldLabel = uilabel(app.GridLayout9);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ZCoordinateEditFieldLabel = uilabel(app.GridLayout9);
            app.ZCoordinateEditFieldLabel.Text = 'Z Coordinate';
            app.ZCoordinateEditField = uieditfield(app.GridLayout9, 'numeric');
            app.Button = uibutton(app.GridLayout9, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '✔';
            app.Button_2 = uibutton(app.GridLayout9, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '🗑';
            app.ColorLabel = uilabel(app.GridLayout9);
            app.ColorLabel.Text = 'Color';
            app.ColorButton = uibutton(app.GridLayout9, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = '';
            app.WorldlineIDEditFieldLabel = uilabel(app.GridLayout9);
            app.WorldlineIDEditFieldLabel.Text = 'Worldline ID';
            app.WorldlineIDEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ProvenanceEditFieldLabel = uilabel(app.GridLayout9);
            app.ProvenanceEditFieldLabel.Text = 'Provenance';
            app.ProvenanceEditField = uieditfield(app.GridLayout9, 'text');
            app.ProvenanceEditField.Placeholder = 'NPAL';
            app.NameEditFieldLabel = uilabel(app.GridLayout9);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout9, 'text');
            app.NameEditField.Placeholder = 'Neuron Name';
            app.WorkflowPanel = uipanel(app.GridLayout);
            app.WorkflowPanel.Title = 'Workflow';
            app.GridLayout13 = uigridlayout(app.WorkflowPanel);
            app.InsertNeuroPALNeuronsButton = uibutton(app.GridLayout13, 'push');
            app.InsertNeuroPALNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @InsertNeuroPALNeuronsButtonPushed, true);
            app.InsertNeuroPALNeuronsButton.Text = 'Insert NeuroPAL Neurons';
            app.SaveResultsButton = uibutton(app.GridLayout13, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.Panel_24 = uipanel(app.GridLayout13);
            app.GridLayout14 = uigridlayout(app.Panel_24);
            app.RecommendFramesButton = uibutton(app.GridLayout14, 'push');
            app.RecommendFramesButton.ButtonPushedFcn = createCallbackFcn(app, @RecommendFramesButtonPushed, true);
            app.RecommendFramesButton.Text = 'Recommend Frames';
            app.TrackNeuronsButton = uibutton(app.GridLayout14, 'push');
            app.TrackNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @TrackNeuronsButtonPushed, true);
            app.TrackNeuronsButton.Text = 'Track Neurons';
            app.ExtractActivityButton = uibutton(app.GridLayout14, 'push');
            app.ExtractActivityButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractActivityButtonPushed, true);
            app.ExtractActivityButton.Text = 'Extract Activity';
            app.InsertlastIDdFrameButton = uibutton(app.GridLayout13, 'push');
            app.InsertlastIDdFrameButton.ButtonPushedFcn = createCallbackFcn(app, @InsertlastIDdFrameButtonPushed, true);
            app.InsertlastIDdFrameButton.Text = 'Insert last ID''d Frame';
            app.Panel_30 = uipanel(app.GridLayout);
            app.GridLayout40 = uigridlayout(app.Panel_30);
            app.Panel_31 = uipanel(app.GridLayout40);
            app.GridLayout41 = uigridlayout(app.Panel_31);
            app.xEditFieldLabel = uilabel(app.GridLayout41);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GridLayout41, 'numeric');
            app.yEditFieldLabel = uilabel(app.GridLayout41);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.GridLayout41, 'numeric');
            app.zEditFieldLabel = uilabel(app.GridLayout41);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.GridLayout41, 'numeric');
            app.Panel_32 = uipanel(app.GridLayout40);
            app.GridLayout42 = uigridlayout(app.Panel_32);
            app.Panel_34 = uipanel(app.GridLayout42);
            app.GridLayout43 = uigridlayout(app.Panel_34);
            app.tEditFieldLabel = uilabel(app.GridLayout43);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.GridLayout43, 'numeric');
            app.Panel_35 = uipanel(app.GridLayout42);
            app.GridLayout44 = uigridlayout(app.Panel_35);
            app.tSlider = uislider(app.GridLayout44);
            app.tSlider.ValueChangedFcn = createCallbackFcn(app, @tSliderValueChanged, true);
            app.WorldlineInformationPanel_2 = uipanel(app.GridLayout);
            app.WorldlineInformationPanel_2.Title = 'Worldline Information';
            app.GridLayout51 = uigridlayout(app.WorldlineInformationPanel_2);
            app.Panel_62 = uipanel(app.GridLayout51);
            app.GridLayout52 = uigridlayout(app.Panel_62);
            app.AssignrandomcolorstoblanksButton = uibutton(app.GridLayout52, 'push');
            app.AssignrandomcolorstoblanksButton.ButtonPushedFcn = createCallbackFcn(app, @AssignrandomcolorstoblanksButtonPushed, true);
            app.AssignrandomcolorstoblanksButton.Text = 'Assign random colors to blanks';
            app.WorldlineTree = uitree(app.GridLayout51, 'checkbox');
            app.WorldlineTree.SelectionChangedFcn = createCallbackFcn(app, @WorldlineTreeSelectionChanged, true);
            app.IDdNode = uitreenode(app.WorldlineTree);
            app.IDdNode.Text = 'ID''d';
            app.UnIDdNode = uitreenode(app.WorldlineTree);
            app.UnIDdNode.Text = 'Un-ID''d';
            app.AdjustNeuronMarkerAlignmentPanel = uipanel(app.NeuronActivityTracingUIFigure);
            app.AdjustNeuronMarkerAlignmentPanel.Title = 'Adjust Neuron Marker Alignment';
            app.AdjustNeuronMarkerAlignmentPanel.Visible = 'off';
            app.NeuronMarkerGrid = uigridlayout(app.AdjustNeuronMarkerAlignmentPanel);
            app.DeformNeuronMarkersPanel = uipanel(app.NeuronMarkerGrid);
            app.DeformNeuronMarkersPanel.Title = 'Deform Neuron Markers';
            app.GridLayout17 = uigridlayout(app.DeformNeuronMarkersPanel);
            app.HeightSpinnerLabel = uilabel(app.GridLayout17);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout17);
            app.WidthSpinnerLabel = uilabel(app.GridLayout17);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.GridLayout17);
            app.ScaleEditFieldLabel = uilabel(app.GridLayout17);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.GridLayout17, 'numeric');
            app.SaveButton_2 = uibutton(app.NeuronMarkerGrid, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.MoveNeuronMarkersPanel = uipanel(app.NeuronMarkerGrid);
            app.MoveNeuronMarkersPanel.Title = 'Move Neuron Markers';
            app.GridLayout16_2 = uigridlayout(app.MoveNeuronMarkersPanel);
            app.FlipVerticallyButton = uibutton(app.GridLayout16_2, 'push');
            app.FlipVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticallyButtonPushed, true);
            app.FlipVerticallyButton.Text = 'Flip Vertically';
            app.CenterVerticallyButton = uibutton(app.GridLayout16_2, 'push');
            app.CenterVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @CenterVerticallyButtonPushed, true);
            app.CenterVerticallyButton.Text = 'Center Vertically';
            app.CenterHorizontallyButton = uibutton(app.GridLayout16_2, 'push');
            app.CenterHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @CenterHorizontallyButtonPushed, true);
            app.CenterHorizontallyButton.Text = 'Center Horizontally';
            app.Panel_61 = uipanel(app.GridLayout16_2);
            app.GridLayout27 = uigridlayout(app.Panel_61);
            app.CW = uibutton(app.GridLayout27, 'push');
            app.CW.ButtonPushedFcn = createCallbackFcn(app, @CWButtonPushed, true);
            app.CW.Text = '↻';
            app.CCW = uibutton(app.GridLayout27, 'push');
            app.CCW.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCW.Text = '↺';
            app.Down = uibutton(app.GridLayout27, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.Down.Text = '⬇️';
            app.Right = uibutton(app.GridLayout27, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Text = '➡️';
            app.Up = uibutton(app.GridLayout27, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.Up.Text = '⬆️';
            app.Left = uibutton(app.GridLayout27, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Text = '⬅️';
            app.FlipHorizontallyButton = uibutton(app.GridLayout16_2, 'push');
            app.FlipHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontallyButtonPushed, true);
            app.FlipHorizontallyButton.Text = 'Flip Horizontally';
            app.Panel_60 = uipanel(app.GridLayout16_2);
            app.GridLayout18 = uigridlayout(app.Panel_60);
            app.MoveEditField = uieditfield(app.GridLayout18, 'numeric');
            app.MoveEditField.ValueDisplayFormat = '%.0f';
            app.MoveEditField.Value = 5;
            app.MoveLabel = uilabel(app.GridLayout18);
            app.MoveLabel.Text = 'Move ';
            app.pixelsandLabel = uilabel(app.GridLayout18);
            app.pixelsandLabel.Text = 'pixels and';
            app.RotateEditField = uieditfield(app.GridLayout18, 'numeric');
            app.RotateEditField.Value = 2;
            app.degreesatatimeLabel = uilabel(app.GridLayout18);
            app.degreesatatimeLabel.Text = 'degrees at a time.';
            app.ImportNeuronsfromCSVButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.ImportNeuronsfromCSVButton.Text = 'Import Neurons from CSV';
            app.CancelButton_2 = uibutton(app.NeuronMarkerGrid, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_2Pushed, true);
            app.CancelButton_2.Text = 'Cancel';
            app.SelectNeuronSubsetButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.SelectNeuronSubsetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNeuronSubsetButtonPushed, true);
            app.SelectNeuronSubsetButton.Text = 'Select Neuron Subset';
            app.Panel_64 = uipanel(app.NeuronMarkerGrid);
            app.GridLayout53_2 = uigridlayout(app.Panel_64);
            app.MarkerSizeLabel_2 = uilabel(app.GridLayout53_2);
            app.MarkerSizeLabel_2.Text = 'Marker Size';
            app.MarkerSizeSlider_2 = uislider(app.GridLayout53_2);
            app.MarkerSizeSlider_2.Limits = [1 30];
            app.MarkerSizeSlider_2.MajorTicks = [];
            app.MarkerSizeSlider_2.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSliderValueChanged, true);
            app.MarkerSizeSlider_2.MinorTicks = [];
            app.MarkerSizeSlider_2.Value = 5;
            app.ResetMarkerPositionsButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.ResetMarkerPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMarkerPositionsButtonPushed, true);
            app.ResetMarkerPositionsButton.Text = 'Reset Marker Positions';
            app.Panel_67 = uipanel(app.NeuronMarkerGrid);
            app.GridLayout48_2 = uigridlayout(app.Panel_67);
            app.AutojumponIDCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.AutojumponIDCheckBox_2.Text = 'Auto-jump on ID.';
            app.OverlaylastIDdframeCheckBox = uicheckbox(app.GridLayout48_2);
            app.OverlaylastIDdframeCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlaylastIDdframeCheckBoxValueChanged, true);
            app.OverlaylastIDdframeCheckBox.Text = 'Overlay last ID''d frame.';
            app.OverlayNeuroPALvolCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.OverlayNeuroPALvolCheckBox_2.Text = 'Overlay NeuroPAL vol.';
            app.OverlayFrameMIPCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.OverlayFrameMIPCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @OverlayFrameMIPCheckBox_2ValueChanged, true);
            app.OverlayFrameMIPCheckBox_2.Text = 'Overlay Frame MIP.';
            app.ShowallneuronsCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.ShowallneuronsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowallneuronsCheckBox_2ValueChanged, true);
            app.ShowallneuronsCheckBox_2.Text = 'Show all neurons.';
            app.EnableClickDragCheckBox = uicheckbox(app.GridLayout48_2);
            app.EnableClickDragCheckBox.Text = 'Enable Click & Drag.';
            app.EnableinvolumeeditingCheckBox = uicheckbox(app.GridLayout48_2);
            app.EnableinvolumeeditingCheckBox.Text = 'Enable in-volume editing.';
            app.EnableinvolumeeditingCheckBox.Value = true;
            app.AdvancedParameterPanel = uipanel(app.NeuronActivityTracingUIFigure);
            app.AdvancedParameterPanel.Visible = 'off';
            app.NeuronMarkerGrid_2 = uigridlayout(app.AdvancedParameterPanel);
            app.OpenGridSearchMenuButton = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.OpenGridSearchMenuButton.ButtonPushedFcn = createCallbackFcn(app, @OpenGridSearchMenuButtonPushed, true);
            app.OpenGridSearchMenuButton.Text = 'Open Grid Search Menu';
            app.SaveButton = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OpenZephIRdocumentationButton = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.OpenZephIRdocumentationButton.Text = 'Open ZephIR documentation';
            app.TabGroup2 = uitabgroup(app.NeuronMarkerGrid_2);
            app.RegularizationLossCoefficientsTab = uitab(app.TabGroup2);
            app.RegularizationLossCoefficientsTab.Title = 'Regularization & Loss Coefficients';
            app.GridLayout35 = uigridlayout(app.RegularizationLossCoefficientsTab);
            app.dLabel = uilabel(app.GridLayout35);
            app.dLabel.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.dLabel.Text = 'λd';
            app.EditField3Label = uilabel(app.GridLayout35);
            app.EditField3Label.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.EditField3Label.Text = 'Coefficient for feature detection loss, λD. ';
            app.l_d = uieditfield(app.GridLayout35, 'numeric');
            app.l_d.ValueDisplayFormat = '%.2f';
            app.l_d.Enable = 'off';
            app.l_d.Value = -1;
            app.tLabel = uilabel(app.GridLayout35);
            app.tLabel.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.tLabel.Text = 'λt';
            app.EditField2Label = uilabel(app.GridLayout35);
            app.EditField2Label.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.EditField2Label.Text = 'Coefficient for temporal smoothing loss, λT, enforcing a 0th-order linear fit for intensity over n_frame frames.';
            app.l_t = uieditfield(app.GridLayout35, 'numeric');
            app.l_t.ValueDisplayFormat = '%.2f';
            app.l_t.Enable = 'off';
            app.l_t.Value = -1;
            app.NLabel = uilabel(app.GridLayout35);
            app.NLabel.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.NLabel.Text = 'λN';
            app.EditField4Label = uilabel(app.GridLayout35);
            app.EditField4Label.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.EditField4Label.Text = 'Coefficient for spring constant for intra-keypoint spatial regularization, λN.';
            app.l_n = uieditfield(app.GridLayout35, 'numeric');
            app.l_n.ValueDisplayFormat = '%.2f';
            app.l_n.Enable = 'off';
            app.l_n.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.l_n.Value = 5;
            app.NmodeLabel = uilabel(app.GridLayout35);
            app.NmodeLabel.Tooltip = {'''disp'': use inter-keypoint displacements'; '''norm'': use inter-keypoint distances (rotation is not penalized)'; '''ljp'': use a Lenard-Jones potential on inter-keypoint distances (collapsing onto the same position is highly penalized)'; 'default: ''disp'''};
            app.NmodeLabel.Text = 'λN mode';
            app.MethodtouseforcalculatingNLabel = uilabel(app.GridLayout35);
            app.MethodtouseforcalculatingNLabel.Text = 'Method to use for calculating LN.';
            app.l_n_mode = uidropdown(app.GridLayout35);
            app.l_n_mode.Items = {'disp', 'norm', 'ljp'};
            app.l_n_mode.Editable = 'on';
            app.l_n_mode.Enable = 'off';
            app.l_n_mode.Value = 'disp';
            app.nn_maxLabel = uilabel(app.GridLayout35);
            app.nn_maxLabel.Tooltip = {'default: 5'};
            app.nn_maxLabel.Text = 'nn_max';
            app.EditFieldLabel_5 = uilabel(app.GridLayout35);
            app.EditFieldLabel_5.Tooltip = {'default: 5'};
            app.EditFieldLabel_5.Text = 'Maximum number of neighboring keypoints to be connected by springs for calculating λN.';
            app.nn_max = uieditfield(app.GridLayout35, 'numeric');
            app.nn_max.ValueDisplayFormat = '%.2f';
            app.nn_max.Enable = 'off';
            app.nn_max.Tooltip = {'default: 5'};
            app.l_d_grid = uicheckbox(app.GridLayout35);
            app.l_d_grid.Text = '';
            app.l_t_grid = uicheckbox(app.GridLayout35);
            app.l_t_grid.Text = '';
            app.l_n_grid = uicheckbox(app.GridLayout35);
            app.l_n_grid.Text = '';
            app.l_n_mode_grid = uicheckbox(app.GridLayout35);
            app.l_n_mode_grid.Text = '';
            app.nn_max_grid = uicheckbox(app.GridLayout35);
            app.nn_max_grid.Text = '';
            app.GridSearchLabel = uilabel(app.GridLayout35);
            app.GridSearchLabel.Text = 'Grid Search?';
            app.ParameterLabel = uilabel(app.GridLayout35);
            app.ParameterLabel.Text = 'Parameter';
            app.DescriptionLabel = uilabel(app.GridLayout35);
            app.DescriptionLabel.Text = 'Description';
            app.GradientLearningRateTab = uitab(app.TabGroup2);
            app.GradientLearningRateTab.Title = 'Gradient & Learning Rate';
            app.GridLayout32 = uigridlayout(app.GradientLearningRateTab);
            app.lr_ceilingLabel = uilabel(app.GridLayout32);
            app.lr_ceilingLabel.Text = 'lr_ceiling';
            app.EditFieldLabel_6 = uilabel(app.GridLayout32);
            app.EditFieldLabel_6.Text = 'Maximum value for initial learning rate. Note that learning rate decays by a factor of 0.5 every 10 epochs.';
            app.lr_ceiling = uieditfield(app.GridLayout32, 'numeric');
            app.lr_ceiling.Enable = 'off';
            app.lr_ceiling.Value = 0.2;
            app.LRFloorLabel = uilabel(app.GridLayout32);
            app.LRFloorLabel.Text = 'LR Floor';
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel = uilabel(app.GridLayout32);
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel.Text = 'Minimum value for initial learning rate. default: 0.02';
            app.lr_floor = uieditfield(app.GridLayout32, 'numeric');
            app.lr_floor.ValueDisplayFormat = '%.2f';
            app.lr_floor.Enable = 'off';
            app.lr_floor.Value = 0.02;
            app.LRCoeffLabel = uilabel(app.GridLayout32);
            app.LRCoeffLabel.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.LRCoeffLabel.Text = 'LR Coeff';
            app.EditField6Label = uilabel(app.GridLayout32);
            app.EditField6Label.Text = 'Initial learning rate, times the distance between current frame and its parent.';
            app.lr_coeff = uieditfield(app.GridLayout32, 'numeric');
            app.lr_coeff.ValueDisplayFormat = '%.2f';
            app.lr_coeff.Enable = 'off';
            app.lr_coeff.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.lr_coeff.Value = 2;
            app.clip_gradLabel = uilabel(app.GridLayout32);
            app.clip_gradLabel.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.clip_gradLabel.Text = 'clip_grad';
            app.EditFieldLabel_7 = uilabel(app.GridLayout32);
            app.EditFieldLabel_7.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.EditFieldLabel_7.Text = 'Max value for gradients for gradient descent. -1 to uncap.';
            app.clip_grad = uieditfield(app.GridLayout32, 'numeric');
            app.clip_grad.ValueDisplayFormat = '%.2f';
            app.clip_grad.Enable = 'off';
            app.clip_grad.Value = -1;
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel = uilabel(app.GridLayout32);
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Text = 'Multiply gradients in the z-axis by (1 + z_compensator).';
            app.z_comp = uieditfield(app.GridLayout32, 'numeric');
            app.z_comp.ValueDisplayFormat = '%.2f';
            app.z_comp.Enable = 'off';
            app.z_comp.Value = -1;
            app.ZCompLabel = uilabel(app.GridLayout32);
            app.ZCompLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.ZCompLabel.Text = 'Z Comp.';
            app.clip_grad_grid = uicheckbox(app.GridLayout32);
            app.clip_grad_grid.Text = '';
            app.lr_coeff_grid = uicheckbox(app.GridLayout32);
            app.lr_coeff_grid.Text = '';
            app.lr_floor_grid = uicheckbox(app.GridLayout32);
            app.lr_floor_grid.Text = '';
            app.lr_ceil_grid = uicheckbox(app.GridLayout32);
            app.lr_ceil_grid.Text = '';
            app.z_comp_grid = uicheckbox(app.GridLayout32);
            app.z_comp_grid.Text = '';
            app.GridSearchLabel_2 = uilabel(app.GridLayout32);
            app.GridSearchLabel_2.Text = 'Grid Search?';
            app.ParameterLabel_2 = uilabel(app.GridLayout32);
            app.ParameterLabel_2.Text = 'Parameter';
            app.DescriptionLabel_2 = uilabel(app.GridLayout32);
            app.DescriptionLabel_2.Text = 'Description';
            app.RetrievalFunctionTab = uitab(app.TabGroup2);
            app.RetrievalFunctionTab.Title = 'Retrieval Function';
            app.GridLayout50 = uigridlayout(app.RetrievalFunctionTab);
            app.TextArea_6 = uitextarea(app.GridLayout50);
            app.TextArea_6.Value = {'Test'};
            app.GridSearchPanel = uipanel(app.NeuronActivityTracingUIFigure);
            app.GridSearchPanel.Visible = 'off';
            app.GridLayout54 = uigridlayout(app.GridSearchPanel);
            app.TrackingSelectionPanel_2 = uipanel(app.GridLayout54);
            app.TrackingSelectionPanel_2.Title = 'Tracking Selection';
            app.GridLayout49_2 = uigridlayout(app.TrackingSelectionPanel_2);
            app.EditField_6 = uieditfield(app.GridLayout49_2, 'numeric');
            app.EndFrameLabel_2 = uilabel(app.GridLayout49_2);
            app.EndFrameLabel_2.Text = 'End Frame:';
            app.EditField_7 = uieditfield(app.GridLayout49_2, 'numeric');
            app.StartingFrameLabel_2 = uilabel(app.GridLayout49_2);
            app.StartingFrameLabel_2.Text = 'Starting Frame:';
            app.ListBox_2 = uilistbox(app.GridLayout49_2);
            app.WorldlinestoincludeLabel = uilabel(app.GridLayout49_2);
            app.WorldlinestoincludeLabel.Text = 'Worldlines to include:';
            app.TrackspecificregionButton_2 = uibutton(app.GridLayout49_2, 'push');
            app.TrackspecificregionButton_2.Text = 'Track specific region';
            app.VideoPreprocessingPanel = uipanel(app.GridLayout54);
            app.VideoPreprocessingPanel.Title = 'Video Pre-processing';
            app.GridLayout55 = uigridlayout(app.VideoPreprocessingPanel);
            app.DownsamplingFactorxyEditFieldLabel = uilabel(app.GridLayout55);
            app.DownsamplingFactorxyEditFieldLabel.Text = 'Downsampling Factor (xy)';
            app.DownsamplingFactorxyEditField = uieditfield(app.GridLayout55, 'numeric');
            app.DownsamplingFactorzEditFieldLabel = uilabel(app.GridLayout55);
            app.DownsamplingFactorzEditFieldLabel.Text = 'Downsampling Factor (z)';
            app.DownsamplingFactorzEditField = uieditfield(app.GridLayout55, 'numeric');
            app.DownsamplingFactortEditFieldLabel = uilabel(app.GridLayout55);
            app.DownsamplingFactortEditFieldLabel.Text = 'Downsampling Factor (t)';
            app.DownsamplingFactortEditField = uieditfield(app.GridLayout55, 'numeric');
            app.OnlyappliestogridsearchLabel = uilabel(app.GridLayout55);
            app.OnlyappliestogridsearchLabel.Text = '(Only applies to grid search.)';
            app.ListBox_3 = uilistbox(app.GridLayout55);
            app.ChannelstoincludeLabel = uilabel(app.GridLayout55);
            app.ChannelstoincludeLabel.Text = 'Channels to include:';
            app.SearchrangesPanel = uipanel(app.GridLayout54);
            app.SearchrangesPanel.Title = 'Search ranges';
            app.GridLayout56 = uigridlayout(app.SearchrangesPanel);
            app.UITable = uitable(app.GridLayout56);
            app.UITable.ColumnName = {'Parameter'; 'Low End'; 'High End'};
            app.UITable.RowName = {};
            app.EstimatedSearchtimeLabel = uilabel(app.GridLayout54);
            app.EstimatedSearchtimeLabel.Text = 'Estimated Search time:';
            app.CalculateButton = uibutton(app.GridLayout54, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.Panel_66 = uipanel(app.GridLayout54);
            app.GridLayout57 = uigridlayout(app.Panel_66);
            app.EditField_8 = uieditfield(app.GridLayout57, 'numeric');
            app.minutesLabel = uilabel(app.GridLayout57);
            app.minutesLabel.Text = 'minutes.';
            app.StartGridSearchButton = uibutton(app.GridLayout54, 'push');
            app.StartGridSearchButton.Text = 'Start Grid Search';
            app.CancelGridSearchButton = uibutton(app.GridLayout54, 'push');
            app.CancelGridSearchButton.ButtonPushedFcn = createCallbackFcn(app, @CancelGridSearchButtonPushed, true);
            app.CancelGridSearchButton.Text = 'Cancel Grid Search';
            % Show the figure after all components are created
            app.NeuronActivityTracingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newtrace(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuronActivityTracingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuronActivityTracingUIFigure)
        end
    end
end