classdef nwbsave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SaveasNWBFileUIFigure           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        MetadataLabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        AuthorTab                       matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        RelatedPublications             matlab.ui.control.EditField
        RelatedPublicationsLabel        matlab.ui.control.Label
        DatadescriptionTextArea         matlab.ui.control.TextArea
        DatadescriptionTextAreaLabel    matlab.ui.control.Label
        InstitutionalAffiliationEditField  matlab.ui.control.EditField
        InstitutionalAffiliationEditFieldLabel  matlab.ui.control.Label
        AuthorLaboratoryEditField       matlab.ui.control.EditField
        AuthorLaboratoryEditFieldLabel  matlab.ui.control.Label
        WormTab                         matlab.ui.container.Tab
        GridLayout8_2                   matlab.ui.container.GridLayout
        SubjectNotesTextArea            matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        BodypartDropDown                matlab.ui.control.DropDown
        BodypartDropDownLabel           matlab.ui.control.Label
        CultivationtempCEditField       matlab.ui.control.NumericEditField
        CultivationtempCEditField_2Label  matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDown_2Label              matlab.ui.control.Label
        AgeDropDown                     matlab.ui.control.DropDown
        AgeDropDown_2Label              matlab.ui.control.Label
        SessionDateDatePicker           matlab.ui.control.DatePicker
        SessionDateDatePicker_2Label    matlab.ui.control.Label
        WormIdentifierEditField         matlab.ui.control.EditField
        WormIdentifierEditFieldLabel    matlab.ui.control.Label
        StrainEditField                 matlab.ui.control.EditField
        StrainEditField_2Label_2        matlab.ui.control.Label
        MicroscopeTab                   matlab.ui.container.Tab
        GridLayout4_2                   matlab.ui.container.GridLayout
        DeviceManufacturerEditFieldLabel_2  matlab.ui.control.Label
        DeviceUITable                   matlab.ui.control.Table
        Panel_18                        matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        EditButton                      matlab.ui.control.Button
        RemoveButton                    matlab.ui.control.Button
        AddHardwareDeviceButton         matlab.ui.control.Button
        HardwareDescriptionTextArea     matlab.ui.control.TextArea
        HardwareDescriptionTextAreaLabel  matlab.ui.control.Label
        ManufacturerEditField           matlab.ui.control.EditField
        ManufacturerEditFieldLabel      matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ChannelsTab                     matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        Panel_24                        matlab.ui.container.Panel
        GridLayout18_2                  matlab.ui.container.GridLayout
        EmissionLabel_2                 matlab.ui.control.Label
        EmFilterHighEditField           matlab.ui.control.NumericEditField
        FilterHighEditField_2Label      matlab.ui.control.Label
        EmFilterLowEditField            matlab.ui.control.NumericEditField
        FilterLowEditField_2Label       matlab.ui.control.Label
        EmLambda                        matlab.ui.control.NumericEditField
        EditField_9Label                matlab.ui.control.Label
        Panel_23                        matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        ExcitationLabel                 matlab.ui.control.Label
        ExFilterHighEditField           matlab.ui.control.NumericEditField
        FilterHighEditFieldLabel        matlab.ui.control.Label
        ExFilterLowEditField            matlab.ui.control.NumericEditField
        FilterLowEditFieldLabel         matlab.ui.control.Label
        ExLambda                        matlab.ui.control.NumericEditField
        EditField_7Label                matlab.ui.control.Label
        Panel_20                        matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        RemoveButton_2                  matlab.ui.control.Button
        EditButton_2                    matlab.ui.control.Button
        AddOpticalChannelButton         matlab.ui.control.Button
        GridLayout6                     matlab.ui.container.GridLayout
        OpticalUITable                  matlab.ui.control.Table
        OpticalChannelReferencesLabel   matlab.ui.control.Label
        FilterEditField                 matlab.ui.control.EditField
        FilterEditFieldLabel            matlab.ui.control.Label
        FluorophoreEditField            matlab.ui.control.EditField
        FluorophoreEditFieldLabel       matlab.ui.control.Label
        NotesTab                        matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        NPALVolumeTab                   matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        Panel_19                        matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        NpalNotes                       matlab.ui.control.EditField
        VolumeDescriptionLabel          matlab.ui.control.Label
        NpalHardwareDeviceDropDown      matlab.ui.control.DropDown
        HardwareDeviceDropDownLabel     matlab.ui.control.Label
        VideoVolumeTab                  matlab.ui.container.Tab
        GridLayout12_2                  matlab.ui.container.GridLayout
        TrackingNotesLabel              matlab.ui.control.Label
        NpalNotes_3                     matlab.ui.control.EditField
        Panel_25                        matlab.ui.container.Panel
        GridLayout14_4                  matlab.ui.container.GridLayout
        NpalNotes_2                     matlab.ui.control.EditField
        VideoVolumeDescriptionLabel     matlab.ui.control.Label
        VideoHardwareDeviceDropDown     matlab.ui.control.DropDown
        VideoHardwareDeviceLabel        matlab.ui.control.Label
        NeuronDataTab                   matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        StimulusFileLabel               matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        NeuronalActivityDescriptionLabel  matlab.ui.control.Label
        SegNotes_2                      matlab.ui.control.EditField
        Panel_21                        matlab.ui.container.Panel
        GridLayout14_3                  matlab.ui.container.GridLayout
        SegNotes                        matlab.ui.control.EditField
        NeuroPALIDsDescriptionLabel     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_5                         matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        CustomFileName                  matlab.ui.control.EditField
        CustomFileNameLabel             matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Hyperlink                       matlab.ui.control.Hyperlink
        OpenDANDIaftersavingCheckBox    matlab.ui.control.CheckBox
        AvailableDataLabel              matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        NeuroPALVolumeNode              matlab.ui.container.TreeNode
        VideoVolumeNode                 matlab.ui.container.TreeNode
        TrackingROIsNode                matlab.ui.container.TreeNode
        NeuronsNode                     matlab.ui.container.TreeNode
        NeuronalROIsNode                matlab.ui.container.TreeNode
        NeuronalIdentitiesNode          matlab.ui.container.TreeNode
        NeuronalActivityNode            matlab.ui.container.TreeNode
        StimulusFileNode                matlab.ui.container.TreeNode
    end

    
    properties (Access = public)
        parent_app;
        image_neurons;
        neuron_activity_by_name;
        image_file;
        devices;
        opt_chans;

        csvfile;
        image_prefs;
        image_data;
        image_data_zscored;
        image_um_scale;
    end
    
    methods (Access = private)
        
        function create_nwb_file(app, metadata)
            % Load the .mat file
            npal_file = load(metadata.mat_path);
            npal_vol = npal_file.data;
            metadata.npal_shape = size(npal_file.data);
            metadata.rgbw = npal_file.prefs(1).rgbw;
            metadata.grid_spacing = npal_file.info(1).grid_spacing;
            
            reference_frame = ['worm ', metadata.worm_bodypart];
            
            % Generate file. Assuming gen_file is a custom function you have in MATLAB
            nwbfile = gen_file(metadata);
            
            % Populate subject data
            % Assuming CElegansSubject is a custom class or function you've defined in MATLAB
            nwbfile.subject = types.ndx_multichannel_volume.CElegansSubject(...
                'subject_id', metadata.worm_identifier, ...
                'age', metadata.worm_age, ...
                'date_of_birth', metadata.worm_date, ...
                'growth_stage', metadata.worm_age, ...
                'cultivation_temp', metadata.cultivation_temp, ...
                'description', metadata.data_description, ...
                'species', 'http://purl.obolibrary.org/obo/NCBITaxon_6239', ...
                'sex', metadata.worm_sex, ...
                'strain', metadata.worm_strain);
            
            % Populate device data
            for eachDevice = 1:length(metadata.devices)
                if eachDevice == 1
                    device = nwbfile.create_device(...
                        'name', metadata.devices{eachDevice}.name, ...
                        'description', metadata.devices{eachDevice}.description, ...
                        'manufacturer', metadata.devices{eachDevice}.manufacturer);
                else
                    nwbfile.create_device(...
                        'name', metadata.devices{eachDevice}.name, ...
                        'description', metadata.devices{eachDevice}.description, ...
                        'manufacturer', metadata.devices{eachDevice}.manufacturer);
                end
            end
            
            % Populate imaging data
            % Assuming create_im_vol is a custom function you've defined in MATLAB
            [ImagingVol, OptChannelRefs, OptChannels] = app.create_im_vol(device, metadata, ...
                                                                      'grid_spacing', metadata.grid_spacing, ...
                                                                      'reference_frame', reference_frame);

            image = app.create_image(npal_vol, metadata, ImagingVol, OptChannelRefs);
            nwbfile.add_acquisition(image)
        end

        function [imaging_vol, orderOpticalChannels, OptChannels] = create_im_vol(app, device, metadata, gridSpacing, gridSpacingUnit, originCoords, originCoordsUnit, referenceFrame)
            
            if ~exist('gridSpacing', 'var')
                gridSpacing = [0.3208, 0.3208, 0.75];
            end

            if ~exist('gridSpacingUnit', 'var')
                gridSpacingUnit = 'micrometers';
            end

            if ~exist('originCoords', 'var')
                originCoords = [0, 0, 0];
            end

            if ~exist('originCoordsUnit', 'var')
                originCoordsUnit = 'micrometers';
            end

            if ~exist('referenceFrame', 'var')
                referenceFrame = 'Worm head';
            end
            
            OptChannels = struct([]);
            OptChanRefData = {};
            
            for eachChannel = 1:length(metadata.channels)
                OptChan = types.ndx_multichannel_volume.OpticalChannelPlus( ...
                    'name', metadata.channels{eachChannel}.fluorophore, ...
                    'description', metadata.channels{eachChannel}.filter, ...
                    'excitation_lambda', str2double(metadata.channels{eachChannel}.ex_lambda), ...
                    'excitation_range', [str2double(metadata.channels{eachChannel}.ex_low), str2double(metadata.channels{eachChannel}.ex_high)], ...
                    'emission_lambda', str2double(metadata.channels{eachChannel}.em_lambda), ...
                    'emission_range', [str2double(metadata.channels{eachChannel}.em_low), str2double(metadata.channels{eachChannel}.em_high)] ...
                    );
                
                OptChannels = [OptChannels, OptChan];
                OptChanRefData{end+1} = sprintf('%s-%s-%dnm', metadata.channels{eachChannel}.ex_lambda, metadata.channels{eachChannel}.em_lambda, str2double(metadata.channels{eachChannel}.em_high) - str2double(metadata.channels{eachChannel}.em_low));
            end
            
            orderOpticalChannels = types.ndx_multichannel_volume.OpticalChannelReferences( ...
                'name', 'order_optical_channels', ...
                'channels', OptChanRefData);

            imaging_vol = types.ndx_multichannel_volume.ImagingVolume( ...
                'name', 'ImagingVolume', ...
                'optical_channel_plus', OptChannels, ...
                'order_optical_channels', orderOpticalChannels, ...
                'description', 'NeuroPAL image of C elegans brain', ...
                'device', device, ...
                'location', metadata.worm_bodypart, ...
                'grid_spacing', gridSpacing, ...
                'grid_spacing_unit', gridSpacingUnit, ...
                'origin_coords', originCoords, ...
                'origin_coords_unit', originCoordsUnit, ...
                'reference_frame', referenceFrame);
        end

        function image = create_image(app, data, metadata, imaging_volume, opt_chan_refs)
            image = types.ndx_multichannel_volume.MultiChannelVolume( ...
                'name', 'NeuroPALImageRaw', ...
                'description', metadata.npal_volume_notes, ...
                'RGBW_channels', metadata.rgbw, ...
                'data', data.transpose(1, 0, 2, 3), ...
                'imaging_volume', imaging_volume ...
                );
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_neurons, neuron_activity, image_file, csvfile, image_prefs, image_data_zscored, image_um_scale, image_data)
            app.parent_app = parent_app;
            app.image_neurons = image_neurons;
            app.neuron_activity_by_name = neuron_activity;
            app.image_file = image_file;
            
            app.csvfile = csvfile;
            app.image_prefs = image_prefs;
            app.image_data = image_data;
            app.image_data_zscored = image_data_zscored;
            app.image_um_scale = image_um_scale;

            app.AgeDropDown.Value = app.parent_app.AgeDropDown.Value;
            app.BodypartDropDown.Value = app.parent_app.BodyDropDown.Value;
            app.SexDropDown.Value = app.parent_app.SexDropDown.Value;
            app.StrainEditField.Value = app.StrainEditField.Value;
            app.SubjectNotesTextArea.Value = app.parent_app.NotesEditField.Value;

            GUI_prefs = Program.GUIPreferences.instance();

            if size(app.image_neurons.neurons,2)>0
                app.Tree.CheckedNodes(end+1) = app.NeuronalROIsNode;
            end

            if ~strcmp(app.parent_app.checkFileExistence(GUI_prefs.seg_path),'False')
                app.Tree.CheckedNodes(end+1) = app.NeuronalIdentitiesNode;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            d = uiprogressdlg(app.SaveasNWBFileUIFigure,'Title','Saving NWB file...','Indeterminate','off');
            GUI_prefs = Program.GUIPreferences.instance();
            
            % Load the .mat file
            metadata.npal_shape = size(app.image_data);
            metadata.rgbw = app.image_prefs.RGBW;
            metadata.worm_date = datetime(posixtime(app.SessionDateDatePicker.Value),'ConvertFrom','posixtime', 'Format', 'yyyy-MM-dd HH:mm:ss');

            nwbfile = NwbFile( ...
                'session_description', app.DatadescriptionTextArea.Value, ...
                'identifier', app.WormIdentifierEditField.Value, ...
                'session_start_time', metadata.worm_date, ...
                'general_lab', app.AuthorLaboratoryEditField.Value, ...
                'general_institution', app.InstitutionalAffiliationEditField.Value, ...
                'general_related_publications', app.RelatedPublications.Value);
            
            reference_frame = ['Worm ', app.BodypartDropDown.Value];
            
            % Populate subject data
            nwbfile.general_subject = types.ndx_multichannel_volume.CElegansSubject(...
                'subject_id', app.WormIdentifierEditField.Value, ...
                'age', app.AgeDropDown.Value, ...
                'date_of_birth', metadata.worm_date, ...
                'growth_stage', app.AgeDropDown.Value, ...
                'cultivation_temp', app.CultivationtempCEditField.Value, ...
                'description', app.SubjectNotesTextArea.Value, ...
                'species', 'http://purl.obolibrary.org/obo/NCBITaxon_6239', ...
                'sex', app.SexDropDown.Value, ...
                'strain', app.StrainEditField.Value);

            devices = [];
            
            % Populate device data
            for eachDevice = 1:size(app.DeviceUITable.Data, 1)
                name = app.DeviceUITable.Data(eachDevice, 1);
                desc = app.DeviceUITable.Data(eachDevice, 2);
                manu = app.DeviceUITable.Data(eachDevice, 3);

                new_device = types.core.Device(...
                        'name', name{1}, ...
                        'description', desc{1}, ...
                        'manufacturer', manu{1} ...
                        );
            
                devices = [devices; new_device];

                if strcmp(app.NpalHardwareDeviceDropDown.Value, name{1})
                    npal_device = new_device;
                end
            end            

            % Populate channel data
            OptChannels = [];
            OptChanRefData = [];
            
            % Populate device data
            for eachChannel = 1:size(app.OpticalUITable.Data, 1)
                name = app.OpticalUITable.Data(eachChannel, 1);
                desc = app.OpticalUITable.Data(eachChannel, 2);
                ex_lambda = app.OpticalUITable.Data(eachChannel, 3);
                ex_low = app.OpticalUITable.Data(eachChannel, 4);
                ex_high = app.OpticalUITable.Data(eachChannel, 5);
                ex_range = [str2num(ex_low{1}), str2num(ex_high{1})];
                em_lambda = app.OpticalUITable.Data(eachChannel, 6);
                em_low = app.OpticalUITable.Data(eachChannel, 7);
                em_high = app.OpticalUITable.Data(eachChannel, 8);
                em_range = [str2num(em_low{1}), str2num(em_high{1})];
            
                OptChan = types.ndx_multichannel_volume.OpticalChannelPlus( ...
                    'name', name, ...
                    'description', desc, ...
                    'excitation_lambda', ex_lambda, ...
                    'excitation_range', ex_range, ...
                    'emission_lambda', em_lambda, ...
                    'emission_range', em_range ...
                    );
                
                OptChannels = [OptChannels, OptChan];
                new_line = sprintf('%s-%s-%dnm', ex_lambda{1}, em_lambda{1}, str2num(em_high{1}) - str2num(em_low{1}));
                OptChanRefData = [OptChanRefData, new_line];
            end           
            
            orderOpticalChannels = types.ndx_multichannel_volume.OpticalChannelReferences( ...
                'name', 'order_optical_channels', ...
                'channels', OptChanRefData);

            % Build NeuroPAL imaging volume.
            ImagingVol = types.ndx_multichannel_volume.ImagingVolume( ...
                'name', 'ImagingVolume', ...
                'optical_channel_plus', OptChannels, ...
                'order_optical_channels', orderOpticalChannels, ...
                'description', 'NeuroPAL image of C elegans brain', ...
                'device', npal_device, ...
                'location', app.BodypartDropDown.Value, ...
                'grid_spacing', app.image_um_scale, ...
                'grid_spacing_unit', 'micrometers', ...
                'origin_coords', [0, 0, 0], ...
                'origin_coords_unit', 'micrometers', ...
                'reference_frame', reference_frame);

            % Add NeuroPAL image to appropriate imaging volume.
            image = types.ndx_multichannel_volume.MultiChannelVolume( ...
                'name', 'NeuroPALImageRaw', ...
                'description', app.NpalNotes.Value, ...
                'RGBW_channels', metadata.rgbw, ...
                'data', app.image_data, ...
                'imaging_volume', ImagingVol ...
                );

            neuroPAL_module = types.core.ProcessingModule( ...
                'name', 'NeuroPAL', ...
                'description', app.NpalNotes.Value);

            neuroPAL_module.nwbdatainterface = types.untyped.Set(orderOpticalChannels, OptChannels);

            voxel_mask = [];
            neuron_ids = {};

            for n=1:size(app.image_neurons.neurons,2)
                voxel_mask = [voxel_mask; app.image_neurons.neurons(n).position];
                neuron_ids{end+1} = app.image_neurons.neurons(n).annotation;
            end

            voxel_mask = types.hdmf_common.VectorData('data', voxel_mask, 'description', 'Neuron Positions');
            %neuron_ids = types.hdmf_common.VectorData('data', char(neuron_ids'), 'description', 'Neuron IDs');
            neuron_ids = neuron_ids';
            
            vs = types.core.PlaneSegmentation( ...
                'colnames', {'voxel_mask'}, ...
                'name', 'NeuroPALNeurons', ...
                'description', 'Neuron centers for multichannel volumetric image', ...
                'imaging_plane', types.untyped.SoftLink(ImagingVol), ...
                'voxel_mask', voxel_mask);

            img_seg = types.core.ImageSegmentation('PlaneSegmentation', vs);
            
            nid = types.ndx_multichannel_volume.SegmentationLabels( ...
                'description', 'Neuron labels corresponding to neuron ROIs.', ...
                'labels', neuron_ids, ...
                'ImageSegmentation', img_seg);

            neuroPAL_module.nwbdatainterface.set('ImageSegmentation', img_seg);
            neuroPAL_module.nwbdatainterface.set('SegmentationLabels', nid);
            nwbfile.acquisition.set('NeuroPALImageRaw', image);
            nwbfile.acquisition.set('ImagingVolume', ImagingVol);
            nwbfile.processing.set('NeuroPAL', neuroPAL_module);

            if ~isempty(app.CustomFileName.Value)
                if ~endsWith(app.CustomFileName.Value, '.nwb')
                    path = fullfile(GUI_prefs.image_dir, [app.CustomFileName.Value, '.nwb']);
                else
                    path = fullfile(GUI_prefs.image_dir, app.CustomFileName.Value);
                end
            else
                [~, og_name, ~] = fileparts(app.image_file);
                path = fullfile(GUI_prefs.image_dir, [og_name, '.nwb']);
            end 

            if ~exist(path, 'file')
                nwbExport(nwbfile, path);
            else
                existing_nwb = nwbRead(path);
                existing_nwb.acquisition = types.untyped.Set(existing_nwb.acquisition, nwbfile.acquisition);
                existing_nwb.processing = types.untyped.Set(existing_nwb.processing, nwbfile.processing);
                existing_nwb.general_subject = nwbfile.general_subject;
                nwbExport(nwbfile, strrep(path, '.nwb', '-new.nwb'))
            end

            uiconfirm(app.SaveasNWBFileUIFigure, sprintf('Saved file to %s', path), 'Success!');

            % Options
            if app.OpenDANDIaftersavingCheckBox.Value
                web('https://www.dandiarchive.org/');
            end

            close(d)
            %delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            nwb_data = [];
            nwb_data.proceed = 0;
            app.parent_app.process_nwb_data(nwb_data);
            delete(app)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            Program.GUIHandling.mutually_exclusive(event, {app.CustomFileName, app.CustomFileNameLabel}, 'Visible');
        end

        % Callback function
        function ButtonPushed(app, event)
            app.OpticalUITable.Data(end+1) = [app.F1,app.F2,app.F3];
        end

        % Button pushed function: AddHardwareDeviceButton
        function AddHardwareDeviceButtonPushed(app, event)
            device = struct('name', char(app.NameEditField.Value), 'manu', char(app.ManufacturerEditField.Value), 'desc', char(app.HardwareDescriptionTextArea.Value));
            Program.GUIHandling.device_handler(app, 'add', device);
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            Program.GUIHandling.device_handler(app, 'edit', app.DeviceUITable.Selection);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            Program.GUIHandling.device_handler(app, 'remove', app.DeviceUITable.Selection);
        end

        % Button pushed function: AddOpticalChannelButton
        function AddOpticalChannelButtonPushed(app, event)
            channel = struct( ...
                'fluorophore', char(app.FluorophoreEditField.Value), ...
                'filter', char(app.FilterEditField.Value), ...
                'excitation_lambda', char(num2str(app.ExLambda.Value)), ...
                'excitation_filter_low', char(num2str(app.ExFilterLowEditField.Value)), ...
                'excitation_filter_high', char(num2str(app.ExFilterHighEditField.Value)), ...
                'emmission_lambda', char(num2str(app.EmLambda.Value)), ...
                'emmission_filter_low', char(num2str(app.EmFilterLowEditField.Value)), ...
                'emmission_filter_high', char(num2str(app.EmFilterHighEditField.Value)));

            Program.GUIHandling.channel_handler(app, 'add', channel);
        end

        % Button pushed function: EditButton_2
        function EditButton_2Pushed(app, event)
            Program.GUIHandling.channel_handler(app, 'edit', channel);
        end

        % Button pushed function: RemoveButton_2
        function RemoveButton_2Pushed(app, event)
            Program.GUIHandling.channel_handler(app, 'remove', channel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SaveasNWBFileUIFigure = uifigure('Visible', 'off');
            app.SaveasNWBFileUIFigure.Name = 'Save as NWB File';
            app.GridLayout = uigridlayout(app.SaveasNWBFileUIFigure);
            app.Tree = uitree(app.GridLayout, 'checkbox');
            app.Tree.Editable = 'on';
            app.NeuroPALVolumeNode = uitreenode(app.Tree);
            app.NeuroPALVolumeNode.Text = 'NeuroPAL Volume';
            app.VideoVolumeNode = uitreenode(app.Tree);
            app.VideoVolumeNode.Text = 'Video Volume';
            app.TrackingROIsNode = uitreenode(app.VideoVolumeNode);
            app.TrackingROIsNode.Text = 'Tracking ROIs';
            app.NeuronsNode = uitreenode(app.Tree);
            app.NeuronsNode.Text = 'Neurons';
            app.NeuronalROIsNode = uitreenode(app.NeuronsNode);
            app.NeuronalROIsNode.Text = 'Neuronal ROIs';
            app.NeuronalIdentitiesNode = uitreenode(app.NeuronsNode);
            app.NeuronalIdentitiesNode.Text = 'Neuronal Identities';
            app.NeuronalActivityNode = uitreenode(app.NeuronsNode);
            app.NeuronalActivityNode.Text = 'Neuronal Activity';
            app.StimulusFileNode = uitreenode(app.NeuronalActivityNode);
            app.StimulusFileNode.Text = 'Stimulus File';
            app.AvailableDataLabel = uilabel(app.GridLayout);
            app.AvailableDataLabel.Text = 'Available Data';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.OpenDANDIaftersavingCheckBox = uicheckbox(app.GridLayout3);
            app.OpenDANDIaftersavingCheckBox.Text = 'Open DANDI after saving.';
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.URL = 'https://www.dandiarchive.org/';
            app.Hyperlink.Text = '(?)';
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel_5 = uipanel(app.GridLayout2);
            app.GridLayout3_2 = uigridlayout(app.Panel_5);
            app.CheckBox = uicheckbox(app.GridLayout3_2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CustomFileNameLabel = uilabel(app.GridLayout3_2);
            app.CustomFileNameLabel.Text = ' Custom File Name.';
            app.CustomFileName = uieditfield(app.GridLayout3_2, 'text');
            app.CustomFileName.Visible = 'off';
            app.CustomFileName.Placeholder = 'data.nwb';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AuthorTab = uitab(app.TabGroup);
            app.AuthorTab.Title = 'Author';
            app.GridLayout4 = uigridlayout(app.AuthorTab);
            app.AuthorLaboratoryEditFieldLabel = uilabel(app.GridLayout4);
            app.AuthorLaboratoryEditFieldLabel.Text = 'Author / Laboratory';
            app.AuthorLaboratoryEditField = uieditfield(app.GridLayout4, 'text');
            app.AuthorLaboratoryEditField.Placeholder = '(e.g. "Lorem Ipsum Labs")';
            app.InstitutionalAffiliationEditFieldLabel = uilabel(app.GridLayout4);
            app.InstitutionalAffiliationEditFieldLabel.Text = 'Institutional Affiliation';
            app.InstitutionalAffiliationEditField = uieditfield(app.GridLayout4, 'text');
            app.InstitutionalAffiliationEditField.Placeholder = '(e.g. "Sample University")';
            app.DatadescriptionTextAreaLabel = uilabel(app.GridLayout4);
            app.DatadescriptionTextAreaLabel.Text = 'Data description';
            app.DatadescriptionTextArea = uitextarea(app.GridLayout4);
            app.DatadescriptionTextArea.Placeholder = '(e.g. description of experiment, notes, etc.)';
            app.RelatedPublicationsLabel = uilabel(app.GridLayout4);
            app.RelatedPublicationsLabel.Text = 'Related Publications';
            app.RelatedPublications = uieditfield(app.GridLayout4, 'text');
            app.RelatedPublications.Placeholder = '(e.g. a DOI, a link, etc.)';
            app.WormTab = uitab(app.TabGroup);
            app.WormTab.Title = 'Worm';
            app.GridLayout8_2 = uigridlayout(app.WormTab);
            app.StrainEditField_2Label_2 = uilabel(app.GridLayout8_2);
            app.StrainEditField_2Label_2.Text = 'Strain';
            app.StrainEditField = uieditfield(app.GridLayout8_2, 'text');
            app.WormIdentifierEditFieldLabel = uilabel(app.GridLayout8_2);
            app.WormIdentifierEditFieldLabel.Text = 'Worm Identifier';
            app.WormIdentifierEditField = uieditfield(app.GridLayout8_2, 'text');
            app.WormIdentifierEditField.Placeholder = '0000';
            app.SessionDateDatePicker_2Label = uilabel(app.GridLayout8_2);
            app.SessionDateDatePicker_2Label.Text = 'Session Date';
            app.SessionDateDatePicker = uidatepicker(app.GridLayout8_2);
            app.AgeDropDown_2Label = uilabel(app.GridLayout8_2);
            app.AgeDropDown_2Label.Text = 'Age';
            app.AgeDropDown = uidropdown(app.GridLayout8_2);
            app.AgeDropDown.Items = {'Adult', 'L4', 'L3', 'L2', 'L1', '3-Fold'};
            app.AgeDropDown.Value = 'Adult';
            app.SexDropDown_2Label = uilabel(app.GridLayout8_2);
            app.SexDropDown_2Label.Text = 'Sex';
            app.SexDropDown = uidropdown(app.GridLayout8_2);
            app.SexDropDown.Items = {'XX', 'XO'};
            app.SexDropDown.Value = 'XX';
            app.CultivationtempCEditField_2Label = uilabel(app.GridLayout8_2);
            app.CultivationtempCEditField_2Label.Text = 'Cultivation temp (°C)';
            app.CultivationtempCEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.BodypartDropDownLabel = uilabel(app.GridLayout8_2);
            app.BodypartDropDownLabel.Text = 'Body part';
            app.BodypartDropDown = uidropdown(app.GridLayout8_2);
            app.BodypartDropDown.Items = {'Whole Worm', 'Head', 'Midbody', 'Anterior Midbody', 'Central Midbody', 'Posterior Midbody', 'Tail'};
            app.BodypartDropDown.Value = 'Whole Worm';
            app.NotesTextAreaLabel = uilabel(app.GridLayout8_2);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.SubjectNotesTextArea = uitextarea(app.GridLayout8_2);
            app.MicroscopeTab = uitab(app.TabGroup);
            app.MicroscopeTab.Title = 'Microscope';
            app.GridLayout4_2 = uigridlayout(app.MicroscopeTab);
            app.NameEditFieldLabel = uilabel(app.GridLayout4_2);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout4_2, 'text');
            app.NameEditField.Placeholder = '(e.g. "Spinning Disk Confocal")';
            app.ManufacturerEditFieldLabel = uilabel(app.GridLayout4_2);
            app.ManufacturerEditFieldLabel.Text = 'Manufacturer';
            app.ManufacturerEditField = uieditfield(app.GridLayout4_2, 'text');
            app.ManufacturerEditField.Placeholder = '(e.g. Nikon)';
            app.HardwareDescriptionTextAreaLabel = uilabel(app.GridLayout4_2);
            app.HardwareDescriptionTextAreaLabel.Text = 'Hardware Description';
            app.HardwareDescriptionTextArea = uitextarea(app.GridLayout4_2);
            app.HardwareDescriptionTextArea.Placeholder = '(e.g. Ti-e 60x Objective, 1.2 NA Nikon CFI Plan Apochromat VC 60XC WI)';
            app.AddHardwareDeviceButton = uibutton(app.GridLayout4_2, 'push');
            app.AddHardwareDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @AddHardwareDeviceButtonPushed, true);
            app.AddHardwareDeviceButton.Text = 'Add Hardware Device';
            app.Panel_18 = uipanel(app.GridLayout4_2);
            app.GridLayout13 = uigridlayout(app.Panel_18);
            app.RemoveButton = uibutton(app.GridLayout13, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.EditButton = uibutton(app.GridLayout13, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeviceUITable = uitable(app.GridLayout4_2);
            app.DeviceUITable.ColumnName = {'Name'; 'Manufacturer'; 'Description'};
            app.DeviceUITable.RowName = {};
            app.DeviceUITable.SelectionType = 'row';
            app.DeviceUITable.ColumnEditable = true;
            app.DeviceManufacturerEditFieldLabel_2 = uilabel(app.GridLayout4_2);
            app.DeviceManufacturerEditFieldLabel_2.Text = 'Device List';
            app.ChannelsTab = uitab(app.TabGroup);
            app.ChannelsTab.Title = 'Channels';
            app.GridLayout15 = uigridlayout(app.ChannelsTab);
            app.FluorophoreEditFieldLabel = uilabel(app.GridLayout15);
            app.FluorophoreEditFieldLabel.Text = 'Fluorophore';
            app.FluorophoreEditField = uieditfield(app.GridLayout15, 'text');
            app.FluorophoreEditField.Placeholder = '(e.g. "mTagBFP2")';
            app.FilterEditFieldLabel = uilabel(app.GridLayout15);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.FilterEditField = uieditfield(app.GridLayout15, 'text');
            app.FilterEditField.Placeholder = '(e.g. "Semrock FF01-445/45-25 Brightline")';
            app.GridLayout6 = uigridlayout(app.GridLayout15);
            app.OpticalChannelReferencesLabel = uilabel(app.GridLayout6);
            app.OpticalChannelReferencesLabel.Text = 'Optical Channel References';
            app.OpticalUITable = uitable(app.GridLayout6);
            app.OpticalUITable.ColumnName = {'Protein'; 'Filter'; 'Ex. λ'; 'Ex. Low'; 'Ex. High'; 'Em. λ'; 'Em. Low'; 'Em. High'};
            app.OpticalUITable.RowName = {};
            app.OpticalUITable.SelectionType = 'row';
            app.OpticalUITable.ColumnEditable = true;
            app.AddOpticalChannelButton = uibutton(app.GridLayout15, 'push');
            app.AddOpticalChannelButton.ButtonPushedFcn = createCallbackFcn(app, @AddOpticalChannelButtonPushed, true);
            app.AddOpticalChannelButton.Text = 'Add Optical Channel';
            app.Panel_20 = uipanel(app.GridLayout15);
            app.GridLayout16 = uigridlayout(app.Panel_20);
            app.EditButton_2 = uibutton(app.GridLayout16, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditButton_2Pushed, true);
            app.EditButton_2.Text = 'Edit';
            app.RemoveButton_2 = uibutton(app.GridLayout16, 'push');
            app.RemoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_2Pushed, true);
            app.RemoveButton_2.Text = 'Remove';
            app.Panel_23 = uipanel(app.GridLayout15);
            app.GridLayout18 = uigridlayout(app.Panel_23);
            app.EditField_7Label = uilabel(app.GridLayout18);
            app.EditField_7Label.Text = 'λ';
            app.ExLambda = uieditfield(app.GridLayout18, 'numeric');
            app.FilterLowEditFieldLabel = uilabel(app.GridLayout18);
            app.FilterLowEditFieldLabel.Text = 'Filter Low';
            app.ExFilterLowEditField = uieditfield(app.GridLayout18, 'numeric');
            app.FilterHighEditFieldLabel = uilabel(app.GridLayout18);
            app.FilterHighEditFieldLabel.Text = 'Filter High';
            app.ExFilterHighEditField = uieditfield(app.GridLayout18, 'numeric');
            app.ExcitationLabel = uilabel(app.GridLayout18);
            app.ExcitationLabel.Text = 'Excitation';
            app.Panel_24 = uipanel(app.GridLayout15);
            app.GridLayout18_2 = uigridlayout(app.Panel_24);
            app.EditField_9Label = uilabel(app.GridLayout18_2);
            app.EditField_9Label.Text = 'λ';
            app.EmLambda = uieditfield(app.GridLayout18_2, 'numeric');
            app.FilterLowEditField_2Label = uilabel(app.GridLayout18_2);
            app.FilterLowEditField_2Label.Text = 'Filter Low';
            app.EmFilterLowEditField = uieditfield(app.GridLayout18_2, 'numeric');
            app.FilterHighEditField_2Label = uilabel(app.GridLayout18_2);
            app.FilterHighEditField_2Label.Text = 'Filter High';
            app.EmFilterHighEditField = uieditfield(app.GridLayout18_2, 'numeric');
            app.EmissionLabel_2 = uilabel(app.GridLayout18_2);
            app.EmissionLabel_2.Text = 'Emission';
            app.NotesTab = uitab(app.TabGroup);
            app.NotesTab.Title = 'Notes';
            app.GridLayout7 = uigridlayout(app.NotesTab);
            app.TabGroup2 = uitabgroup(app.GridLayout7);
            app.NPALVolumeTab = uitab(app.TabGroup2);
            app.NPALVolumeTab.Title = 'NPAL Volume';
            app.GridLayout12 = uigridlayout(app.NPALVolumeTab);
            app.HardwareDeviceDropDownLabel = uilabel(app.GridLayout12);
            app.HardwareDeviceDropDownLabel.Text = 'Hardware Device';
            app.NpalHardwareDeviceDropDown = uidropdown(app.GridLayout12);
            app.NpalHardwareDeviceDropDown.Items = {};
            app.NpalHardwareDeviceDropDown.Placeholder = 'Select device...';
            app.NpalHardwareDeviceDropDown.Value = {};
            app.Panel_19 = uipanel(app.GridLayout12);
            app.GridLayout14 = uigridlayout(app.Panel_19);
            app.VolumeDescriptionLabel = uilabel(app.GridLayout14);
            app.VolumeDescriptionLabel.Text = 'Volume Description';
            app.NpalNotes = uieditfield(app.GridLayout14, 'text');
            app.NpalNotes.Placeholder = 'Insert description here...';
            app.VideoVolumeTab = uitab(app.TabGroup2);
            app.VideoVolumeTab.Title = 'Video Volume';
            app.GridLayout12_2 = uigridlayout(app.VideoVolumeTab);
            app.VideoHardwareDeviceLabel = uilabel(app.GridLayout12_2);
            app.VideoHardwareDeviceLabel.Text = 'Video Hardware Device';
            app.VideoHardwareDeviceDropDown = uidropdown(app.GridLayout12_2);
            app.VideoHardwareDeviceDropDown.Items = {};
            app.VideoHardwareDeviceDropDown.Placeholder = 'Select Device';
            app.VideoHardwareDeviceDropDown.Value = {};
            app.Panel_25 = uipanel(app.GridLayout12_2);
            app.GridLayout14_4 = uigridlayout(app.Panel_25);
            app.VideoVolumeDescriptionLabel = uilabel(app.GridLayout14_4);
            app.VideoVolumeDescriptionLabel.Text = 'Video Volume Description';
            app.NpalNotes_2 = uieditfield(app.GridLayout14_4, 'text');
            app.NpalNotes_2.Placeholder = 'Insert description here...';
            app.NpalNotes_3 = uieditfield(app.GridLayout12_2, 'text');
            app.NpalNotes_3.Placeholder = 'Insert notes here...';
            app.TrackingNotesLabel = uilabel(app.GridLayout12_2);
            app.TrackingNotesLabel.Text = 'Tracking Notes';
            app.NeuronDataTab = uitab(app.TabGroup2);
            app.NeuronDataTab.Title = 'Neuron Data';
            app.GridLayout17 = uigridlayout(app.NeuronDataTab);
            app.Panel_21 = uipanel(app.GridLayout17);
            app.GridLayout14_3 = uigridlayout(app.Panel_21);
            app.NeuroPALIDsDescriptionLabel = uilabel(app.GridLayout14_3);
            app.NeuroPALIDsDescriptionLabel.Text = 'NeuroPAL IDs Description';
            app.SegNotes = uieditfield(app.GridLayout14_3, 'text');
            app.SegNotes.Placeholder = 'Insert description here...';
            app.SegNotes_2 = uieditfield(app.GridLayout17, 'text');
            app.SegNotes_2.Placeholder = 'Insert description here...';
            app.NeuronalActivityDescriptionLabel = uilabel(app.GridLayout17);
            app.NeuronalActivityDescriptionLabel.Text = 'Neuronal Activity Description';
            app.DropDown = uidropdown(app.GridLayout17);
            app.DropDown.Items = {};
            app.DropDown.Placeholder = 'Select file...';
            app.DropDown.Value = {};
            app.StimulusFileLabel = uilabel(app.GridLayout17);
            app.StimulusFileLabel.Text = 'Stimulus File';
            app.MetadataLabel = uilabel(app.GridLayout);
            app.MetadataLabel.Text = 'Metadata';
            % Show the figure after all components are created
            app.SaveasNWBFileUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nwbsave(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SaveasNWBFileUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SaveasNWBFileUIFigure)
        end
    end
end