classdef rotation_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TransformImageUIFigure  matlab.ui.Figure
        FlipButtonGroup_2       matlab.ui.container.ButtonGroup
        FlipVertically          matlab.ui.control.Button
        FlipHorizontally        matlab.ui.control.Button
        SettingsButtonGroup     matlab.ui.container.ButtonGroup
        ShowguidelinesCheckBox  matlab.ui.control.CheckBox
        ResetButton             matlab.ui.control.Button
        MoveBothButton          matlab.ui.control.RadioButton
        MoveNeuronsButton       matlab.ui.control.RadioButton
        MoveImageButton         matlab.ui.control.RadioButton
        ButtonGroup             matlab.ui.container.ButtonGroup
        RotateButtonGroup       matlab.ui.container.ButtonGroup
        RotateAntiClockwise     matlab.ui.control.Button
        RotateClockwise         matlab.ui.control.Button
        EditField               matlab.ui.control.NumericEditField
        Rotation                matlab.ui.control.Knob
        MirrorButtonGroup       matlab.ui.container.ButtonGroup
        ImageButton             matlab.ui.control.Button
        NeuronsButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        parent_app;
        image_data;
        image_view;
        repo_img; % Description
        image_gamma; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, neuropal_app, image_data, image_view, image_gamma)
            app.parent_app = neuropal_app;
            app.image_data = image_data;
            app.image_view = image_view;
            app.repo_img = image_data;
            app.image_gamma = image_gamma;
        end

        % Value changed function: Rotation
        function RotationValueChanged(app, event)
            app.EditField.Value = app.Rotation.Value;
            
            %{
            pyrun('from transforms3d import _gohlketransforms');
            pyrun('origin, xaxis, yaxis, zaxis = [0, 0, 0], [1, 0, 0], [0, 1, 0], [0, 0, 1]');
            pyrun('Rx = _gohlketransforms.rotation_matrix(angle,xaxis)',angle=app.Rotation.Value);
            pyrun('Ry = _gohlketransforms.rotation_matrix(angle,yaxis)',angle=app.Rotation.Value);
            pyrun('Rz = _gohlketransforms.rotation_matrix(angle,zaxis)',angle=app.Rotation.Value);
            conc_rot = pyrun('arr = _gohlketransforms.concatenate_matrices(Rx, Ry, Rz)', 'arr');
            %}

            app.image_view = imrotate(app.image_view,app.Rotation.Value);

            app.parent_app.UpdateFromChild('view',app.image_data,app.image_view,app.image_gamma);
            
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            app.parent_app.Mirror('image');
        end

        % Button pushed function: NeuronsButton
        function NeuronsButtonPushed(app, event)
            app.parent_app.Mirror('neurons');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.parent_app.UpdateFromChild('base',app.repo_img, app.image_gamma);
        end

        % Button pushed function: FlipVertically
        function FlipVerticallyButtonPushed(app, event)
            if app.MoveBothButton.Value
                app.parent_app.RotateVertical('inclusive');
            elseif app.MoveImageButton.Value
                app.parent_app.RotateVertical('excl_image');
            elseif app.MoveNeuronsButton.Value
                app.parent_app.RotateVertical('excl_neurons');
            end
        end

        % Button pushed function: FlipHorizontally
        function FlipHorizontallyButtonPushed(app, event)
            if app.MoveBothButton.Value
                app.parent_app.RotateHorizontal('inclusive');
            elseif app.MoveImageButton.Value
                app.parent_app.RotateHorizontal('excl_image');
            elseif app.MoveNeuronsButton.Value
                app.parent_app.RotateHorizontal('excl_neurons');
            end
        end

        % Button pushed function: RotateClockwise
        function RotateClockwisePushed(app, event)
            if app.MoveBothButton.Value
                app.parent_app.RotateClockwise('inclusive');
            elseif app.MoveImageButton.Value
                app.parent_app.RotateClockwise('excl_image');
            elseif app.MoveNeuronsButton.Value
                app.parent_app.RotateClockwise('excl_neurons');
            end
        end

        % Button pushed function: RotateAntiClockwise
        function RotateAntiClockwisePushed(app, event)
            if app.MoveBothButton.Value
                app.parent_app.RotateAntiClockwise('inclusive');
            elseif app.MoveImageButton.Value
                app.parent_app.RotateAntiClockwise('excl_image');
            elseif app.MoveNeuronsButton.Value
                app.parent_app.RotateAntiClockwise('excl_neurons');
            end
        end

        % Value changing function: Rotation
        function RotationValueChanging(app, event)
            app.EditField.Value = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TransformImageUIFigure = uifigure('Visible', 'off');
            app.TransformImageUIFigure.Name = 'Transform Image';
            app.MirrorButtonGroup = uibuttongroup(app.TransformImageUIFigure);
            app.MirrorButtonGroup.Title = 'Mirror';
            app.NeuronsButton = uibutton(app.MirrorButtonGroup, 'push');
            app.NeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @NeuronsButtonPushed, true);
            app.NeuronsButton.Text = 'Neurons';
            app.ImageButton = uibutton(app.MirrorButtonGroup, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.RotateButtonGroup = uibuttongroup(app.TransformImageUIFigure);
            app.RotateButtonGroup.Title = 'Rotate';
            app.Rotation = uiknob(app.RotateButtonGroup, 'continuous');
            app.Rotation.Limits = [0 360];
            app.Rotation.MajorTicks = [0 90 180 270 360];
            app.Rotation.MajorTickLabels = {'0', '90', '180', '270', '360'};
            app.Rotation.ValueChangedFcn = createCallbackFcn(app, @RotationValueChanged, true);
            app.Rotation.ValueChangingFcn = createCallbackFcn(app, @RotationValueChanging, true);
            app.EditField = uieditfield(app.RotateButtonGroup, 'numeric');
            app.RotateClockwise = uibutton(app.RotateButtonGroup, 'push');
            app.RotateClockwise.ButtonPushedFcn = createCallbackFcn(app, @RotateClockwisePushed, true);
            app.RotateClockwise.Text = 'Clockwise';
            app.RotateAntiClockwise = uibutton(app.RotateButtonGroup, 'push');
            app.RotateAntiClockwise.ButtonPushedFcn = createCallbackFcn(app, @RotateAntiClockwisePushed, true);
            app.RotateAntiClockwise.Text = 'Anti-Clockwise';
            app.SettingsButtonGroup = uibuttongroup(app.TransformImageUIFigure);
            app.SettingsButtonGroup.Title = 'Settings';
            app.ButtonGroup = uibuttongroup(app.SettingsButtonGroup);
            app.MoveImageButton = uiradiobutton(app.SettingsButtonGroup);
            app.MoveImageButton.Text = 'Move Image';
            app.MoveNeuronsButton = uiradiobutton(app.SettingsButtonGroup);
            app.MoveNeuronsButton.Text = 'Move Neurons';
            app.MoveBothButton = uiradiobutton(app.SettingsButtonGroup);
            app.MoveBothButton.Text = 'Move Both';
            app.MoveBothButton.Value = true;
            app.ResetButton = uibutton(app.SettingsButtonGroup, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ShowguidelinesCheckBox = uicheckbox(app.SettingsButtonGroup);
            app.ShowguidelinesCheckBox.Enable = 'off';
            app.ShowguidelinesCheckBox.Text = 'Show guidelines';
            app.FlipButtonGroup_2 = uibuttongroup(app.TransformImageUIFigure);
            app.FlipButtonGroup_2.Title = 'Flip';
            app.FlipHorizontally = uibutton(app.FlipButtonGroup_2, 'push');
            app.FlipHorizontally.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontallyButtonPushed, true);
            app.FlipHorizontally.Text = 'Horizontally';
            app.FlipVertically = uibutton(app.FlipButtonGroup_2, 'push');
            app.FlipVertically.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticallyButtonPushed, true);
            app.FlipVertically.Text = 'Vertically';
            % Show the figure after all components are created
            app.TransformImageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rotation_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TransformImageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TransformImageUIFigure)
        end
    end
end