classdef stackedplotChildren < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        changestyleButton  matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        PlotButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        s % Description
    end
    
    methods (Access = public)
        
        function update_plot(app)
            subplot_idx = 1;
            line_idx = 2;
            ax_children = findobj(app.s.NodeChildren, 'Type','Axes');
            set(app.s.NodeChildren(app.s.NodeChildren == ax_children(subplot_idx)).Children(line_idx),'Linestyle',':');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            X = randi([0,10],[10,2]);
            T1 = table(X,X);
            app.s = stackedplot(app.GridLayout,T1);
            drawnow % Need to include this in R2022b, but not R2023a
        end

        % Button pushed function: changestyleButton
        function changestyleButtonPushed(app, event)
            app.update_plot()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.changestyleButton = uibutton(app.UIFigure, 'push');
            app.changestyleButton.ButtonPushedFcn = createCallbackFcn(app, @changestyleButtonPushed, true);
            app.changestyleButton.Text = 'change style';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stackedplotChildren

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end