classdef trace_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroPAL_IDTraceWindowUIFigure  matlab.ui.Figure
        AdjustNeuronMarkerAlignmentPanel  matlab.ui.container.Panel
        NeuronMarkerGrid                matlab.ui.container.GridLayout
        CancelAlignmentButton           matlab.ui.control.Button
        ImportfromCSVButton             matlab.ui.control.Button
        MoveNeuronMarkersPanel          matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        FlipHorizontallyButton          matlab.ui.control.Button
        Panel_15                        matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        Left                            matlab.ui.control.Button
        Up                              matlab.ui.control.Button
        Right                           matlab.ui.control.Button
        Down                            matlab.ui.control.Button
        CCW                             matlab.ui.control.Button
        CW                              matlab.ui.control.Button
        Panel_13                        matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        MoveLabel                       matlab.ui.control.Label
        MoveEditField_2                 matlab.ui.control.NumericEditField
        pixelsatatimeLabel              matlab.ui.control.Label
        CenterHorizontallyButton        matlab.ui.control.Button
        CenterVerticallyButton          matlab.ui.control.Button
        FlipVerticallyButton            matlab.ui.control.Button
        SaveResultButton                matlab.ui.control.Button
        ResetMarkerPositionsButton      matlab.ui.control.Button
        TransformNeuronMarkersPanel     matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        WidthSpinner                    matlab.ui.control.Spinner
        WidthSpinnerLabel               matlab.ui.control.Label
        HeightSpinner                   matlab.ui.control.Spinner
        HeightSpinnerLabel              matlab.ui.control.Label
        MainTraceGrid                   matlab.ui.container.GridLayout
        ClicktoselectvideofileButton    matlab.ui.control.Button
        VolumeViewerPanel               matlab.ui.container.Panel
        VolumeViewerGrid                matlab.ui.container.GridLayout
        SizeWarningLabel                matlab.ui.control.Label
        VolumeViewerControlPanel        matlab.ui.container.Panel
        VolumeViewerControlGrid         matlab.ui.container.GridLayout
        AlphaSlider                     matlab.ui.control.Slider
        NeuroPALImageLabel              matlab.ui.control.Label
        GCaMPVideoLabel                 matlab.ui.control.Label
        DisplayNeuronsPanel             matlab.ui.container.Panel
        DisplayNeuronsGrid              matlab.ui.container.GridLayout
        FliphorCheckBox                 matlab.ui.control.CheckBox
        Rotate90CheckBox                matlab.ui.control.CheckBox
        FlipvertCheckBox                matlab.ui.control.CheckBox
        DisplayNeuronsCheckBox          matlab.ui.control.CheckBox
        Npal_Axes                       matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        CentralControlTab               matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Panel_16                        matlab.ui.container.Panel
        GridLayout30                    matlab.ui.container.GridLayout
        Tree                            matlab.ui.container.CheckBoxTree
        MetadatajsonNode                matlab.ui.container.TreeNode
        AnnotationsNode                 matlab.ui.container.TreeNode
        WorldlinesNode                  matlab.ui.container.TreeNode
        DataNode                        matlab.ui.container.TreeNode
        FilesLoadedLabel                matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        UseMATLABbrowserCheckBox        matlab.ui.control.CheckBox
        TrackNeuronsButton              matlab.ui.control.Button
        ExtractActivityTracesButton     matlab.ui.control.Button
        SaveResultsButton               matlab.ui.control.Button
        AdjustAlignmentButton           matlab.ui.control.Button
        RecommendFramesButton           matlab.ui.control.Button
        Panel_8                         matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        WormMotionPanel                 matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        MotionSlider                    matlab.ui.control.Slider
        BookmarkedFramesPanel           matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        GotobookmarkButton              matlab.ui.control.Button
        SetbookmarkButton               matlab.ui.control.Button
        BmNameInput                     matlab.ui.control.EditField
        ListBox                         matlab.ui.control.ListBox
        AdvancedSettingsTab             matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Panel_17                        matlab.ui.container.Panel
        GridLayout36                    matlab.ui.container.GridLayout
        AdvSetTab                       matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GridLayout31                    matlab.ui.container.GridLayout
        sort_mode                       matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        sort_modeLabel                  matlab.ui.control.Label
        n_chunks                        matlab.ui.control.NumericEditField
        NumberofstepstodividetheforwardpassintoEditFieldLabel  matlab.ui.control.Label
        n_chunksLabel                   matlab.ui.control.Label
        n_epoch_d                       matlab.ui.control.NumericEditField
        EditField2_2Label               matlab.ui.control.Label
        n_epoch                         matlab.ui.control.NumericEditField
        n_epoch_dLabel                  matlab.ui.control.Label
        NumberofiterationsforimageregistrationLREditFieldLabel  matlab.ui.control.Label
        n_epochLabel                    matlab.ui.control.Label
        use_gpu                         matlab.ui.control.DropDown
        SetsdevicetoGPUandenablesCUDAuseDropDownLabel  matlab.ui.control.Label
        UseGPULabel                     matlab.ui.control.Label
        RegularizationLossCoefficientsTab  matlab.ui.container.Tab
        GridLayout35                    matlab.ui.container.GridLayout
        nn_max                          matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        nn_maxLabel                     matlab.ui.control.Label
        l_n_mode                        matlab.ui.control.DropDown
        MethodtouseforcalculatingNLabel  matlab.ui.control.Label
        NmodeLabel                      matlab.ui.control.Label
        l_n                             matlab.ui.control.NumericEditField
        EditField4Label                 matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        l_t                             matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        tLabel                          matlab.ui.control.Label
        l_d                             matlab.ui.control.NumericEditField
        EditField3Label                 matlab.ui.control.Label
        dLabel                          matlab.ui.control.Label
        GradientLearningRateTab         matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        ZCompLabel                      matlab.ui.control.Label
        z_comp                          matlab.ui.control.NumericEditField
        Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel  matlab.ui.control.Label
        clip_grad                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        clip_gradLabel                  matlab.ui.control.Label
        lr_coeff                        matlab.ui.control.NumericEditField
        EditField6Label                 matlab.ui.control.Label
        LRCoeffLabel                    matlab.ui.control.Label
        lr_floor                        matlab.ui.control.NumericEditField
        Minimumvalueforinitiallearningratedefault002EditFieldLabel  matlab.ui.control.Label
        LRFloorLabel                    matlab.ui.control.Label
        lr_ceiling                      matlab.ui.control.NumericEditField
        EditFieldLabel_3                matlab.ui.control.Label
        lr_ceilingLabel                 matlab.ui.control.Label
        ImageMotionTab                  matlab.ui.container.Tab
        GridLayout34                    matlab.ui.container.GridLayout
        dimmer_ratio                    matlab.ui.control.NumericEditField
        CoefficientfordimmingnonfoveatedregionsEditFieldLabel  matlab.ui.control.Label
        dimmer_ratioLabel               matlab.ui.control.Label
        motion_predict                  matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        motion_predictLabel             matlab.ui.control.Label
        grid_shape                      matlab.ui.control.NumericEditField
        SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel  matlab.ui.control.Label
        grid_shapeLabel                 matlab.ui.control.Label
        fovea_sigma                     matlab.ui.control.NumericEditField
        EditFieldLabel_4                matlab.ui.control.Label
        foveasigmaLabel                 matlab.ui.control.Label
        MiscellaneousTab                matlab.ui.container.Tab
        GridLayout37                    matlab.ui.container.GridLayout
        EnableManualZephirSettingsCheckBox  matlab.ui.control.CheckBox
        HoveroverlabelsforfurtherdetailsorguidanceLabel  matlab.ui.control.Label
        CreditTab                       matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        CitationTextArea                matlab.ui.control.TextArea
        CitationTextAreaLabel           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        GithubRepositoryButton          matlab.ui.control.Button
        ViewPreprintButton              matlab.ui.control.Button
        LaboratoryPageButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        parent_app;
        image_data;
        image_view;
        repo_neurons;
        image_neurons;
        path;
        dir;
        neuron_table;
        red; % Color indices.
        green;
        blue;
        white;
        gfp;
        dic;
        image_gamma;

        % Legacy, remove during waxing.
        data_store;
        v_master;
        v_data;ff
        zen_chunk;
        master_path;
        vidArray;
        vreader;
        timerObj;
        alignment_data;

        % Dynamic transformation tool
        neuronBox;
        symbolBox;
        symbols;

        % Drag manager
        downspy;
        upspy;
        movespy;

        active_symbol;
        origin;

        % Paths
        data_file;
        metadata_file;
        worldlines_file;
        annotation_file;
    end
    
    methods (Access = private)

        %% Video Loading Functions

        % Main Loading Function
        function data_packer(app, data_file)
            % Create new Tree
            newTree = uitree(app.GridLayout30, 'checkbox');
            newTree.Layout.Row = 2;
            newTree.Layout.Column = 1;
            
            % Create an array to store the checked nodes
            checkedNodes = {};

            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Scanning for metadata...','Indeterminate','on');
            [json_status, checkedNodes] = app.loadJSON(newTree, checkedNodes, app.master_path);
            [world_status, checkedNodes] = app.loadWorld(newTree, checkedNodes, app.master_path);
            [anno_status, checkedNodes] = app.loadAnnotations(newTree, checkedNodes, app.master_path);
            close(d)

            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Scanning for data...','Indeterminate','on');
            if endsWith(data_file, 'h5')
                app.loadH5(newTree, checkedNodes, app.master_path, data_file, json_status, world_status, anno_status);
            elseif endsWith(data_file, 'nwb')
                app.loadNWB(newTree, checkedNodes, app.master_path, data_file, json_status, world_status, anno_status);
            elseif endsWith(data_file, 'nd2')
                app.loadND2(newTree, checkedNodes, app.master_path, data_file, json_status, world_status, anno_status);
            end
            close(d)
            
            % Set the checked nodes
            newTree.CheckedNodes = [checkedNodes{:}];
            
            % Assign Checked Nodes Changed Function
            newTree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            
            % Replace the existing Tree with the new one
            delete(app.Tree);
            app.Tree = newTree;
            set(app.Tree, 'Enable', 'off');
        end

        % Metadata Loading Functions
        function [status, checkedNodes] = loadAnnotations(app, newTree, checkedNodes, directory_path)
            if exist(fullfile(directory_path, 'annotations.h5'), 'file')
                newNode = uitreenode(newTree);
                newNode.Text = 'Annotations.h5';
                checkedNodes{end+1} = newNode;
                fullfile(directory_path, 'annotations.h5')
                app.annotation_file = fullfile(directory_path, 'annotations.h5');
                status = 1;
            else
                status = 0;
            end
        end
        function [status, checkedNodes] = loadWorld(app, newTree, checkedNodes, directory_path)
            if exist(fullfile(directory_path, 'worldlines.h5'), 'file')
                newNode = uitreenode(newTree);
                newNode.Text = 'Worldlines.h5';
                checkedNodes{end+1} = newNode;
                app.worldlines_file = fullfile(directory_path, 'worldlines.h5');
                status = 1;
            else
                status = 0;
            end
        end
        function [status, checkedNodes] = loadJSON(app, newTree, checkedNodes, directory_path)
            if exist(fullfile(directory_path, 'metadata.json'),'file')
                newNode = uitreenode(newTree);
                newNode.Text = 'Metadata.json';
                checkedNodes{end+1} = newNode;
                app.metadata_file = fullfile(directory_path, 'metadata.json');
                status = 1;
            else
                status = 0;
            end
        end

        % Data Loading Functions
        function loadND2(app, newTree, checkedNodes, directory_path, data_file, json_status, world_status, anno_status)
            title = sprintf('Detected nd2 file. Building data structure from %s...', data_file);
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title',title, 'Message', 'Processing...', 'Cancelable', 'on');
            bfImg = BioformatsImage(fullfile(directory_path,data_file));
            nT = bfImg.sizeT;
            nC = bfImg.sizeC;
            nZ = bfImg.sizeZ;
            nY = bfImg.height;
            nX = bfImg.width;

            if json_status == 0                
                % Create the metadata struct
                metadata = struct('shape_t', nT, 'shape_c', nC, 'shape_z', nZ, 'shape_y', nY, 'shape_x', nX);
                
                % Write the metadata to a JSON file
                jsonStr = jsonencode(metadata);
                jsonFile = fopen(fullfile(directory_path, 'metadata.json'), 'w');
                fwrite(jsonFile, jsonStr);
                fclose(jsonFile);
                app.metadata_file = fullfile(directory_path, 'metadata.json');
            end
            
            % Create HDF5 datasets with correct dimension order
            h5create(fullfile(directory_path, 'data.h5'), '/data', [nT, nC, nZ, nX, nY], 'Datatype', 'uint8');
            h5create(fullfile(directory_path, 'data.h5'), '/times', [nT], 'Datatype', 'double');
            
            % Time vector
            time_vector = 1:nT;
            h5write(fullfile(directory_path, 'data.h5'), '/times', time_vector);
            
            % Initialize the entire dataset array with correct dimension order
            all_data = zeros(nY, nX, nZ, nC, nT, 'uint8');
            
            % Collect data for all time points
            channelNames = bfImg.channelNames;
            scale_factor = double(intmax('uint8')) / double(intmax('uint16'));
            
            for t = 1:nT
                for c = 1:nC
                    channel = channelNames{c};
                    for z = 1:nZ
                        % Read and scale the data
                        plane_data = getPlane(bfImg, z, channel, t);
                        % Note the order of indices to match HDF5's expected layout
                        all_data(:, :, z, c, t) = uint8(double(plane_data) * scale_factor);
                    end
                end
            end
            
            % Write the entire dataset at once to HDF5 with corrected dimension order
            h5write(fullfile(directory_path, 'data.h5'), '/data', permute(all_data, [5, 4, 3, 2, 1]));

            %{

            if json_status == 0                
                % Create the metadata struct
                metadata = struct('shape_t', nT, 'shape_c', nC, 'shape_z', nZ, 'shape_y', nY, 'shape_x', nX);
                
                % Write the metadata to a JSON file
                jsonStr = jsonencode(metadata);
                jsonFile = fopen(fullfile(directory_path, 'metadata.json'), 'w');
                fwrite(jsonFile, jsonStr);
                fclose(jsonFile);
                app.metadata_file = fullfile(directory_path, 'metadata.json');
            end
            
            % Chunk dimensions
            chunk_dims = [1, 1, nZ, nY, nX]; % Update chunk size to include all Z-slices for a given time point
            
            % Create HDF5 datasets
            h5create(fullfile(directory_path,'chunked_data.h5'), '/data', [nT, nC, nZ, nY, nX], 'Datatype', 'uint8');
            h5create(fullfile(directory_path,'chunked_data.h5'), '/times', [nT], 'Datatype', 'double');
            
            % Time vector
            time_vector = 1:nT;
            h5write(fullfile(directory_path,'chunked_data.h5'), '/times', time_vector);
            
            % Write data chunk-by-chunk
            channelNames = bfImg.channelNames;
            scale_factor = double(intmax('uint8')) / double(intmax('uint16'));
            
            for t = 1:nT
                time_point_data = zeros(nC, nZ, nY, nX, 'uint8');
                
                % Collect all Z-slices for this time point
                for c = 1:nC
                    channel = channelNames{c};
                    for z = 1:nZ
                        time_point_data(c, z, :, :) = [getPlane(bfImg, z, channel, t)];
                    end
                end

                % Scale to uint8
                time_point_data = uint8(double(time_point_data) * scale_factor);
                time_point_data = reshape(time_point_data,[1, size(time_point_data)]);

                % Write to HDF5
                start = [t, 1, 1, 1, 1];
                count = [1, nC, nZ, nY, nX];

                h5write(fullfile(directory_path,'chunked_data.h5'), '/data', uint8(time_point_data), start, count);
                d.Value = t / nT;
            end

            info = h5info(fullfile(directory_path,'chunked_data.h5'));
            size_info = h5info(fullfile(directory_path, 'chunked_data.h5'),'/data');
            size_info
            size_info.ChunkSize
            app.alignment_data = h5read(fullfile(directory_path,'chunked_data.h5'),'/data',[1 1 1 1 1], size_info.ChunkSize);
            %}

            % Access information about the data.h5 file
            info = h5info(fullfile(directory_path, 'data.h5'));
            size_info = h5info(fullfile(directory_path, 'data.h5'), '/data');
            size_info % Display the size information
            
            % Since the entire dataset is written at once, app.alignment_data should read the entire data
            app.alignment_data = h5read(fullfile(directory_path, 'data.h5'), '/data');

            if ~any(strcmp({info.Datasets.Name}, 'data')) && ~any(strcmp({info.Datasets.Name}, 'times'))
                uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'.h5 file structure is invalid. Please refer to documentation.','Error');
                return
            end

            newNode = uitreenode(newTree);
            newNode.Text = data_file;
            %app.data_file = app.convertHDF5(fullfile(directory_path,'data.h5'));
            app.data_file = fullfile(directory_path,'data.h5');
            checkedNodes{end+1} = newNode;
            
            blank_tbl = array2table(zeros(1,6), 'VariableNames', {'X', 'Y', 'Z', 'Frame', 'TrackID', 'Name'});

            % Save the new table to a new CSV file
            writetable(blank_tbl, fullfile(pwd, 'temp.csv'));
            csv_path = fullfile(pwd, 'temp.csv');
            
            cmd = sprintf('%s/External_Dependencies/ZephIR/annotation-converter.py "%s" "%s" "%s" "%s" "%s"', pwd, directory_path, directory_path, directory_path, directory_path, csv_path)
            pyrunfile(cmd)
            
            newNode = uitreenode(newTree);
            newNode.Text = 'Annotations.h5';
            checkedNodes{end+1} = newNode;
            app.worldlines_file = fullfile(directory_path, 'worldlines.h5');
            app.annotation_file = fullfile(directory_path, 'annotations.h5');

        end
        function loadNWB(app, newTree, checkedNodes, directory_path, data_file, json_status, world_status, anno_status)
            title = sprintf('Detected nwb file. Building data structure from %s...', data_file);
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title',title,'Indeterminate','on');
            %try

            % Initialize
            whole_file = nwbRead(fullfile(directory_path, data_file));
            gc_data = whole_file.processing.get('GCaMPVideo').nwbdatainterface.get('CalciumImageSeries').data;
            chunk_size = gc_data.chunkSize;  % [1 8 96 120 1]
            data_pipe = gc_data.internal;
            
            % Get dimensions
            dims = data_pipe.dims;  % Assuming dims is [2 64 768 960 487] based on your output

            if json_status == 0                
                % Extract the dimensions
                shape_t = dims(5);
                shape_c = 2;
                shape_z = dims(2);
                shape_y = dims(3);
                shape_x = dims(4);
                
                % Create the metadata struct
                metadata = struct('shape_t', shape_t, 'shape_c', shape_c, 'shape_z', shape_z, 'shape_y', shape_y, 'shape_x', shape_x);
                
                % Write the metadata to a JSON file
                jsonStr = jsonencode(metadata);
                jsonFile = fopen(fullfile(directory_path, data_file(1:end-4), 'metadata.json'), 'w');
                fwrite(jsonFile, jsonStr);
                fclose(jsonFile);
                app.metadata_file = fullfile(directory_path, data_file(1:end-4), 'metadata.json');
            end
            
            %{
            % Create the HDF5 dataset
            h5create('data.h5', '/data', dims, 'ChunkSize', chunk_size);
            
            % Loop through the data in chunks
            for t = 1:chunk_size(5):dims(5)
                t_end = min(t + chunk_size(5) - 1, dims(5));
                for x = 1:chunk_size(4):dims(4)
                    x_end = min(x + chunk_size(4) - 1, dims(4));
                    for y = 1:chunk_size(3):dims(3)
                        y_end = min(y + chunk_size(3) - 1, dims(3));
                        for z = 1:chunk_size(2):dims(2)
                            z_end = min(z + chunk_size(2) - 1, dims(2));
                            for c = 1:chunk_size(1):dims(1)
                                c_end = min(c + chunk_size(1) - 1, dims(1));
                                
                                % Read chunk
                                chunk_data = gc_data(c:c_end, z:z_end, y:y_end, x:x_end, t:t_end);
                                
                                % Write chunk to HDF5
                                h5write('data.h5', '/data', chunk_data, [c z y x t], [c_end-c+1, z_end-z+1, y_end-y+1, x_end-x+1, t_end-t+1]);
                            end
                        end
                    end
                end
            end
            %}

            % Remove .nwb from data_file
            folder_name = strrep(data_file, '.nwb', '');
            
            % Get list of all folders in directory_path
            all_folders = dir(directory_path);
            all_folders = all_folders([all_folders.isdir]);  % Filter out files, keep only directories
            
            % Initialize folder_path as empty
            folder_path = '';
            
            % Search for folder
            for i = 1:length(all_folders)
                if strcmp(all_folders(i).name, folder_name)
                    folder_path = fullfile(directory_path, folder_name);
                    break;
                end
            end

            if anno_status == 0
                try
                    % Direct load
                    raise ValueError
                catch
                    app.master_path = folder_path;
                    
                    % Define file paths
                    neuron_centers = fullfile(folder_path, 'All_Signals.csv');
                    cell_id_path = fullfile(folder_path, 'Cell_ID.csv');
                        
                    
                    metadata = jsondecode(fileread(app.metadata_file));
                    
                    % Read the CSV file into a table
                    tbl = readtable(neuron_centers,'PreserveVariableNames',true);
                    
                    if exist(cell_id_path,'file')
                        id_tbl = readtable(cell_id_path,'PreserveVariableNames',true);
                    else
                        id_tbl = tbl;
                    end
                    
                    id_col_names = id_tbl.Properties.VariableNames;
                    track_label = id_col_names(find(contains(lower(id_col_names), 'track')));
                    frame_label = id_col_names(find(contains(lower(id_col_names), 'frame')));
                    y_label = id_col_names(find(contains(lower(id_col_names), 'y')));
                    x_label = id_col_names(find(contains(lower(id_col_names), 'x')));
                    z_label = id_col_names(find(contains(lower(id_col_names), 'z')));
                    track_label = track_label{1};
                    frame_label = frame_label{1};
                    y_label = y_label{1};
                    x_label = x_label{1};
                    z_label = z_label{1};
                    
                    % Extract the columns of interest
                    new_tbl = tbl(:, {x_label, y_label, z_label, frame_label, track_label});
                    
                    % Correct frame resets
                    frames = new_tbl.Frame;
                    offset = 0;
                    prev_frame = frames(1);
                    
                    for i = 2:length(frames)
                        curr_frame = frames(i);
                        
                        if curr_frame < prev_frame
                            offset = prev_frame;
                        end
                        
                        new_tbl.Frame(i) = curr_frame + offset;
                        prev_frame = new_tbl.Frame(i);
                    end
                    
                    % Initialize Name column as cell array
                    new_tbl.Name = cell(height(new_tbl), 1);
                    
                    % Check if Cell_ID.csv exists
                    if exist(cell_id_path, 'file')
                        name_label = id_col_names(find(contains(lower(id_col_names), 'name')));
                        name_label = name_label{1};
                        
                        % Read Cell_ID.csv into a table
                        opts = detectImportOptions(cell_id_path);
                        opts = setvartype(opts, name_label, 'char');
                        opts.PreserveVariableNames = true;  % Preserving variable names
                        cell_id_tbl = readtable(cell_id_path, opts);
                        
                        new_tbl.Properties.VariableNames
                        % Find matching Track ID and populate Name column
                        [isMember, idxB] = ismember(new_tbl.(track_label), cell_id_tbl.(track_label));
                        name_col = cell_id_tbl.(find(contains(lower(id_col_names), 'name')));
                        new_tbl.Name(isMember) = num2cell(name_col(idxB(isMember)));
                    end
                    
                    new_tbl.Properties.VariableNames
                    new_tbl.Properties.VariableNames = {'X','Y','Z','Frame','TrackID', 'Name'};
                    
                    % Save the new table to a new CSV file
                    writetable(new_tbl, fullfile(pwd, 'temp.csv'));
                    csv_path = fullfile(pwd, 'temp.csv');
                    
                    cmd = sprintf('%s/External_Dependencies/ZephIR/annotation-converter.py "%s" "%s" "%s" "%s" "%s"', pwd, fullfile(directory_path,folder_name), [directory_path,app.data_file], fullfile(directory_path, folder_name), fullfile(directory_path,folder_name), csv_path)
                    pyrunfile(cmd)
                    
                    newNode = uitreenode(newTree);
                    newNode.Text = 'Annotations.h5';
                    checkedNodes{end+1} = newNode;
                    app.worldlines_file = fullfile(directory_path, 'worldlines.h5');
                    app.annotation_file = fullfile(directory_path, folder_name, 'annotations.h5');
                end
            end

            gcamp_vid = fullfile(folder_path, 'FullRes', 'video-array.h5');
            info = h5info(gcamp_vid);
            size_info = h5info(gcamp_vid,'/dataset');
            app.alignment_data = h5read(gcamp_vid,'/dataset',[1 1 1 1 1], chunk_size);
            if ~any(strcmp({info.Datasets.Name}, 'dataset')) && ~any(strcmp({info.Datasets.Name}, 'times'))
                uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'.h5 file structure is invalid. Please refer to documentation.','Error');
                return
            end

            newNode = uitreenode(newTree);
            newNode.Text = data_file;
            app.data_file = fullfile(directory_path,'data.h5');
            checkedNodes{end+1} = newNode;
        end
        function loadH5(app, newTree, checkedNodes, directory_path, data_file, json_status, world_status, anno_status)
            title = sprintf('Loading %s...', data_file);
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title',title,'Indeterminate','on');
            info = h5info(fullfile(directory_path, data_file));
            size_info = h5info(fullfile(directory_path, data_file),'/data');
            app.alignment_data = h5read(fullfile(directory_path, data_file),'/data',[1 1 1 1 1], size_info.ChunkSize);
            if ~any(strcmp({info.Datasets.Name}, 'data')) && ~any(strcmp({info.Datasets.Name}, 'times'))
                uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'.h5 file structure is invalid. Please refer to documentation.','Error');
                return
            end

            newNode = uitreenode(newTree);
            newNode.Text = data_file;
            app.data_file = fullfile(directory_path,'data.h5');
            app.data_file = data_file;
            checkedNodes{end+1} = newNode;
        end

        % Sets HDF5 storage layout to contiguous.
        function outFile = convertHDF5(app, inFile)
            % Input validation
            if ~isfile(inFile)
                error('Input file does not exist');
            end
            
            % Determine the output file name
            [pathstr, name, ~] = fileparts(inFile);
            outFile = fullfile(pathstr, 'data.h5');
            
            % Get info about the input file
            fileInfo = h5info(inFile);
            
            % Create the output file
            fidOut = H5F.create(outFile, 'H5F_ACC_TRUNC', 'H5P_DEFAULT', 'H5P_DEFAULT');
            
            % Loop through each dataset in the input file
            for i = 1:numel(fileInfo.Datasets)
                dataset = fileInfo.Datasets(i);
                
                % Corrected rank computation
                rank = length(dataset.Dataspace.Size);
                
                % Corrected dataspace creation
                space_id = H5S.create_simple(rank, fliplr(dataset.Dataspace.Size), []);
         
                
                % Create a dataset creation property list
                dcpl_id = H5P.create('H5P_DATASET_CREATE');
                H5P.set_layout(dcpl_id, 'H5D_CONTIGUOUS');
                H5P.set_alloc_time(dcpl_id, 'H5D_ALLOC_TIME_LATE');
                
                % Create the dataset in the output file
                dataset_id = H5D.create(fidOut, dataset.Name, ...
                                        H5T.copy('H5T_NATIVE_DOUBLE'), ...
                                        space_id, dcpl_id);
                
                % Determine chunk size based on available memory or other criteria
                chunkSize = [100, 100];  % Example chunk size, adjust as necessary
                
                % Read and write the data in chunks
                for startRow = 1:chunkSize(1):dataset.Dataspace.Size(1)
                    for startCol = 1:chunkSize(2):dataset.Dataspace.Size(2)
                        % Define the hyperslab selection
                        count = min(chunkSize, dataset.Dataspace.Size - [startRow, startCol] + 1);
                        offset = [startRow-1, startCol-1];
                        H5S.select_hyperslab(space_id, 'H5S_SELECT_SET', offset, [], count, []);
                        
                        % Read the chunk from the input file
                        data = H5D.read(dataset_id, 'H5ML_DEFAULT', space_id, space_id, 'H5P_DEFAULT');
                        
                        % Write the chunk to the output file
                        H5D.write(dataset_id, 'H5ML_DEFAULT', space_id, space_id, 'H5P_DEFAULT', data);
                    end
                end
                
                % Close the HDF5 handles
                H5D.close(dataset_id);
                H5P.close(dcpl_id);
                H5S.close(space_id);
            end
            
            % Close the output file
            H5F.close(fidOut);
        end

        %% Wrappers

        % ZephIR python wrapper
        function output = zeph_wrapper(app, routine)
            app.master_path
            switch routine
                case 'recommend_frames'
                    title = 'Analyzing frames...';
                    d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title',title,'Indeterminate','on');
                    [pwd,'/External_Dependencies/ZephIR/recommend_frames.py ',sprintf('--channel=1 --dataset="%s"',app.master_path)]
                    output = pyrunfile([pwd,'/External_Dependencies/ZephIR/recommend_frames.py ',sprintf('--channel=1 --dataset="%s"',app.master_path)],'t_ref');
                case 'annotator'
                    title = 'Loading annotator...';
                    d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title',title,'Indeterminate','on');
                    %pyrunfile([pwd,'/venv/Lib/site-packages/zephir/annotator/main.py ',sprintf('--dataset=%s',app.master_path),'--port=5001'])
                    if ismac
                        cmd = ['python "', pwd,'/venv/lib/python3.9/site-packages/zephir/annotator/main.py" ',sprintf('--dataset="%s"',app.master_path),' --port=5001 &']
                    else
                        cmd = ['python "', pwd,'/venv/Lib/site-packages/zephir/annotator/main.py" ',sprintf('--port=5001 --dataset="%s"',app.master_path),' &']
                    end
                    system(cmd,'-echo')
                case 'tracker'
                    title = 'Tracking neurons...';
                    d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title',title,'Indeterminate','on');
                    pyrunfile([pwd,'/venv/Lib/site-packages/zephir/main.py ',sprintf('--dataset="%s"',app.master_path),'--load_args=True'])
                case 'tracer'
                    title = 'Calculating traces...';
                    d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title',title,'Indeterminate','on');
                    pyrunfile([pwd,'/venv/Lib/site-packages/zephir/methods/extract_traces.py ',sprintf('--dataset="%s"',app.master_path),'--channel=1'])
            end

            %python_command = ['External_Dependencies\ZephIR\example\npal-wrapper.py ',app.master_path, ' ', routine]
            %pyrunfile(python_command);
            
            close(d)
        end

        % Kiosk handlers
        function openMinimalBrowser(app)

            %app.NeuroPAL_IDTraceWindowUIFigure.WindowState = 'maximized';
            
            screenSize = get(0, 'ScreenSize');
            axesPosition = app.UIAxes.Position;
            gridPosition = app.MainTraceGrid.Position;
            figurePosition = app.NeuroPAL_IDTraceWindowUIFigure.Position;
            x = figurePosition(1) + axesPosition(1);
            y = screenSize(4) - (figurePosition(2) + axesPosition(2) + axesPosition(4));
            width = axesPosition(3);
            height = gridPosition(4);
            position = [x, y, width, height];
            
            % Absolute path to the packaged Electron application
            [pathToThisFile, ~, ~] = fileparts(mfilename('fullpath'));
            args = sprintf('--args --x %d --y %d --width %d --height %d', position(1), position(2), position(3), position(4));

            if ismac
                exePath = fullfile('/Users/yemini-lab/Documents/GitHub/NeuroPAL_ID/External_Dependencies/ZephIR/web-wrap', 'wrap-browser-darwin-arm64', 'wrap-browser.app');
                batchFile = sprintf('open "%s" %s', exePath, args);
            else
                exePath = fullfile(pwd,'web-wrap', 'wrap-browser-win32-x64', 'wrap-browser.exe');
                batchFile = 'startElectronApp.bat';
                fid = fopen(batchFile, 'wt');
                fprintf(fid, 'start "" "%s" %s', exePath, args);
                fclose(fid);
            end

            system(batchFile);
            delete(batchFile);

        end
        function browserCommand(app, cmd, arg)
            if ~exist('arg','var')
                arg = 0;
            else
                sanitized_arg = replace(arg, ' ', '%20');
                sanitized_arg = replace(sanitized_arg, '\n', '%0A');
                sanitized_arg = replace(sanitized_arg, '/', '%2F');
            end

            switch cmd
                case 'select'
                    cmd_url = sprintf('http://localhost:5003/selectElement?class=%s', sanitized_arg);
                case 'type'
                    cmd_url = sprintf('http://localhost:5003/textInput?input=%s', sanitized_arg);
                case 'execute'
                    cmd_url = sprintf('http://localhost:5003/executeJS?code=%s', sanitized_arg);
                case 'rpc'
                    cmd_url = sprintf('http://localhost:5003/rpc?%s', sanitized_arg)
                case 'move'
                    screenSize = get(0, 'ScreenSize');
                    axesPosition = app.UIAxes.Position;
                    gridPosition = app.MainTraceGrid.Position;
                    figurePosition = app.NeuroPAL_IDTraceWindowUIFigure.Position;
                    x = figurePosition(1) + axesPosition(1);
                    y = screenSize(4) - (figurePosition(2) + axesPosition(2) + axesPosition(4));
                    width = axesPosition(3);
                    height = gridPosition(4);
                    position = [x, y, width, height];
                    cmd_url = sprintf('http://localhost:5003/updatePosition?x=%d&y=%d&width=%d&height=%d', position(1), position(2), position(3), position(4));  
            end
            webread(cmd_url); % Send HTTP request
        end
        function startBrowser(app)
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Loading browser...','Indeterminate','on');
            app.zeph_wrapper('annotator');

            if app.UseMATLABbrowserCheckBox.Value == 1
                web('http://localhost:5001/','-new','-notoolbar', '-noaddressbox');
            else
                app.openMinimalBrowser();
                % Timer to update coordinates
                app.timerObj = timer('TimerFcn', @(~,~) app.browserCommand('move'), 'Period', 0.1, 'ExecutionMode', 'fixedRate');
                start(app.timerObj);
            end
            close(d)
        end
        function closeBrowser(app)
            try
                % Delete the position update timer
                delete(app.timerObj);
                webread('http://localhost:5002/close');
            catch
                warning('Failed to close Electron window');
            end

            if app.UseMATLABbrowserCheckBox.Value == 1
                com.mathworks.mlservices.MatlabDesktopServices.getDesktop.closeGroup('Web Browser')
            end
        end

        %% MIP & Neuron Control

        function DrawNeurons(app)
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Drawing neurons...','Indeterminate','on');

            extantNeurons = findobj(app.Npal_Axes,'Type','images.roi.Point');
            delete(extantNeurons);

            draw_axes = [];
            if app.DisplayNeuronsCheckBox.Value
                draw_axes = [draw_axes app.Npal_Axes];
            end
            if app.AdjustNeuronMarkerAlignmentPanel.Visible
                draw_axes = [draw_axes app.Npal_Axes];
            end

            if ~isempty(draw_axes)
                for n = 1:size(app.repo_neurons.neurons,2)
                    targ_neuron = app.repo_neurons.neurons(n);
                    images.roi.Point(app.Npal_Axes,'Position',[targ_neuron.position(2) targ_neuron.position(1)], 'Color',[0 1 0], 'MarkerSize',3, 'LineWidth',1, 'Label',targ_neuron.annotation, 'LabelVisible','hover');
                end
            end

            close(d)
        end

        function updateVolume(app)
            gcamp_alpha = app.AlphaSlider.Value;
            npal_alpha = 100 - gcamp_alpha;
            hold(app.Npal_Axes,'off')
            npal_img = squeeze(max(app.image_view,[],3));
            gcamp_img = squeeze(max(app.alignment_data,[],3));
            if ~isempty(gcamp_img) && gcamp_alpha > 0 && npal_alpha > 0
                % Ensure the images are in double format for computations
                npal_img = double(npal_img);
                gcamp_img = double(gcamp_img);
                
                if app.FlipvertCheckBox.Value
                    gcamp_img = flipud(gcamp_img);
                end

                if app.FliphorCheckBox.Value
                    gcamp_img = fliplr(gcamp_img);
                end

                if app.Rotate90CheckBox.Value
                    gcamp_img = rot90(gcamp_img);
                end

                % Get the size of the images
                [height1, width1, ~] = size(npal_img);
                [height2, width2, ~] = size(gcamp_img);
                % If the sizes are not equal, resize the first image to match the second
                if height1 ~= height2 || width1 ~= width2
                    set(app.SizeWarningLabel,'Visible','on');
                    npal_img = imresize(npal_img, [height2, width2]);
                else
                    set(app.SizeWarningLabel,'Visible','off');
                end
                % Normalize the images to [0,1] range
                npal_img = npal_img / max(npal_img(:));
                gcamp_img = gcamp_img / max(gcamp_img(:));
                % Alpha blending
                result_img = npal_alpha * npal_img + gcamp_alpha * gcamp_img;
                % Normalize the result image to [0,1] range
                result_img = result_img / max(result_img(:));
                image(app.Npal_Axes, result_img);
                app.Npal_Axes.XLim = [1, size(result_img, 2)];
                app.Npal_Axes.YLim = [1, size(result_img, 1)];
            else
                if gcamp_alpha == 0
                    image(app.Npal_Axes, npal_img);
                    app.Npal_Axes.XLim = [1, size(npal_img, 2)];
                    app.Npal_Axes.YLim = [1, size(npal_img, 1)];
                elseif npal_alpha == 0
                    image(app.Npal_Axes, gcamp_img);
                    app.Npal_Axes.XLim = [1, size(gcamp_img, 2)];
                    app.Npal_Axes.YLim = [1, size(gcamp_img, 1)];
                end
            end

            %app.MainTraceGrid.RowHeight(end) = app.Npal_Axes.YLim

            if app.DisplayNeuronsCheckBox.Value
                app.DrawNeurons();
            end
        end

        function rotation_handler(app, theta)
            % Find bounding box
            min_x = Inf; max_x = -Inf;
            min_y = Inf; max_y = -Inf;
            for n = 1:size(app.repo_neurons.neurons,2)
                pos = app.repo_neurons.neurons(n).position;
                if pos(1) < min_x, min_x = pos(1); end
                if pos(1) > max_x, max_x = pos(1); end
                if pos(2) < min_y, min_y = pos(2); end
                if pos(2) > max_y, max_y = pos(2); end
            end
            
            % Compute center of bounding box
            center_x = (min_x + max_x) / 2;
            center_y = (min_y + max_y) / 2;
            
            % Translate all neurons to origin
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1) - center_x;
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2) - center_y;
            end
            
            % Compute rotation matrix
            theta = theta * pi / 180;
            R = [cos(theta) -sin(theta); sin(theta) cos(theta)]; % 2D rotation matrix
            
            % Apply rotation matrix to each position vector
            for n = 1:size(app.repo_neurons.neurons,2)
                position = app.repo_neurons.neurons(n).position(1:2); % extract x and y coordinates
                position = R * position'; % apply rotation matrix
                app.repo_neurons.neurons(n).position(1:2) = position'; % update x and y coordinates
            end
            
            % Translate all neurons back to their original position
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1) + center_x;
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2) + center_y;
            end
        end

        function control_text = controlRender(app, ax, x, y, symbol, tag)        
            if any(contains(["flip-hor","flip-vert","rotate"],tag))
                size = 18;
            else
                size = 25;
            end
        
            control_text = text(x, y, symbol, 'Color', 'white', ...
                                               'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                                               'Parent', ax,'FontSize', size, 'Tag', tag);
            control_text.Units = 'pixels';
            %control_text.ButtonDownFcn = app.neuronControls();
        end

        function [rois, box] = roiPull(app, ax)
            % Find all images.roi.Point objects within the axes
            rois = findobj(ax, 'Type', 'images.roi.Point');
        
            minX = Inf;
            minY = Inf;
            maxX = -Inf;
            maxY = -Inf;
        
            % Iterate through the ROIs to calculate bounding box
            for roi = rois'
                pos = roi.Position;
                minX = min(minX, pos(1));
                minY = min(minY, pos(2));
                maxX = max(maxX, pos(1));
                maxY = max(maxY, pos(2));
            end
        
            box = [minX, minY, maxX, maxY];
        end

        function repositionSymbols(app, mode)
        
            % Retrieve all the control symbols
            symbols = app.symbols;
            symbols = symbols';
            
            if strcmp(mode,'new')         
                boundingBox = findobj(app.neuronBox.Children,'Type','Rectangle');
                minX = boundingBox.Position(1);
                minY = boundingBox.Position(2);
                maxX = minX + boundingBox.Position(3);
                maxY = minY + boundingBox.Position(4);
                centerX = (minX + maxX) / 2;
                centerY = (minY + maxY) / 2;
        
                % Reposition the symbols based on their tags
                for symbol = symbols'
                    symbol.Units = 'data';
                    sz_shift = symbol.FontSize;
                    symbol.FontSize = 20;
                    switch symbol.Tag
                        case 'moveLat'
                            symbol.FontSize = 30;
                            if symbol.Position(1) < centerX % Left side
                                symbol.Position = [minX, centerY];
                            else % Right side
                                symbol.Position = [maxX, centerY];
                            end
                        case 'moveVert'
                            symbol.FontSize = 30;
                            if symbol.Position(2) < centerY % Top side
                                symbol.Position = [centerX, maxY];
                            else % Bottom side
                                symbol.Position = [centerX, minY];
                            end
                        case 'jumpLeft'
                            symbol.Position = [minX-sz_shift, centerY+1]; % Adjusted position
                            symbol.Rotation = 90;
                        case 'jumpRight'
                            symbol.Position = [maxX+sz_shift, centerY+1]; % Adjusted position
                            symbol.Rotation = -90;
                        case 'jumpDown'
                            symbol.Position = [centerX, maxY+sz_shift*0.9]; % Adjusted position
                        case 'jumpUp'
                            symbol.Position = [centerX, minY-sz_shift*1.1]; % Adjusted position
                        case 'scaleFree'
                            symbol.Position = [minX+sz_shift/4, minY-sz_shift*0.75];
                        case 'moveFree'
                            %symbol.Position = [minX+sz_shift*1.25, minY-sz_shift*0.75];
                            symbol.Position = [minX+sz_shift/4, minY-sz_shift*0.75];
                        case 'flip-hor'
                            symbol.Position = [minX+sz_shift/2, maxY+sz_shift];
                        case 'flip-vert'
                            symbol.Position = [minX+2*sz_shift, maxY+sz_shift];
                        case 'rotate'
                            symbol.Position = [minX+3.35*sz_shift, maxY+sz_shift];
                    end
                    symbol.UserData = symbol.Position;
                end
            elseif strcmp(mode,'save') 
                % Reposition the symbols based on their tags
                for symbol = symbols'
                    symbol.UserData = symbol.Position;
                end
            else
                for symbol = symbols'
                    symbol.Units = 'data';
                    x_diff = (abs(mode(1))-abs(mode(3)));
                    y_diff = (abs(mode(2))-abs(mode(4)));
                    symbol.Position(1) = symbol.UserData(1) - (mode(1)+x_diff);
                    symbol.Position(2) = symbol.UserData(2) - (mode(2)-y_diff);
                    %disp(sprintf('%s: x_diff is %.f = %.f - %.f, y_diff is %.f = %.f - %.f.', symbol.String,x_diff,abs(mode(1)),abs(mode(3)),y_diff,abs(mode(3)),abs(mode(4))))
                    %disp(sprintf('%s: x1 is %.f, + an extra %.f due to scale, y1 is %.f, - an extra %.f due to scale.', symbol.String,mode(1),x_diff,mode(2),y_diff))
                    %disp(sprintf('%s: delta-x is %.f = (%.f + %.f), delta-y is %.f = (%.f - %.f).', symbol.String, symbol.Position(1),symbol.UserData(1),mode(1)+x_diff, symbol.Position(2),symbol.UserData(2),mode(2)+y_diff))
                end
            end
        end

        function drawNeuronBox(app, axes_handle)
            [rois, box] = app.roiPull(axes_handle);
        
            % Check if no neurons are found or width or height is negative
            if isempty(rois)
                choice = uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure, 'Neuron ROIs were found outside of the GCaMP-aligned grid. Do you want to center them?', 'Warning!', 'Options', {'Yes', 'No'}, ...
                    'DefaultOption', 2);
                if strcmp(choice, 'Yes')
                    % Calculate the scaling factor based on the sizes of the images
                    npal_img_size = size(squeeze(max(app.image_view, [], 3)),2);
                    scale_factor = axes_handle.XLim(2)./npal_img_size;
            
                    % Set the scaling factor in the app
                    app.ScaleEditField.Value = scale_factor;
                    myEvent.Origin = 'Value set programmatically';
                    app.ScaleEditField.ValueChangedFcn(app.ScaleEditField, myEvent);

                    app.DrawNeurons();
            
                    [rois, box] = app.roiPull(axes_handle);

                    % Check again if width or height is non-positive
                    if isempty(rois)
                        error('Could not rescale neuron positions.');
                    end
                else
                    return
                end
            end

            minX = box(1);
            minY = box(2);
            maxX = box(3);
            maxY = box(4);
        
            % Create the bounding box with transparent fill and bright pink dashed outline
            boundingBox = rectangle('Position', [minX, minY, maxX - minX, maxY - minY], ...
                                    'EdgeColor', [1, 0, 1], 'LineStyle', '-', 'LineWidth', 1, 'FaceColor', 'none', 'Parent', axes_handle);
        
            % Add symbols as previously defined
            centerY = (minY + maxY) / 2;
            centerX = (minX + maxX) / 2;
            app.symbols = [];
            app.symbols = [app.symbols, app.controlRender(axes_handle, minX, centerY, '⬌', 'moveLat')];
            app.symbols = [app.symbols, app.controlRender(axes_handle, maxX, centerY, '⬌', 'moveLat')];
            app.symbols = [app.symbols, app.controlRender(axes_handle, centerX, minY, '⬍', 'moveVert')];
            app.symbols = [app.symbols, app.controlRender(axes_handle, centerX, maxY, '⬍', 'moveVert')];
            app.symbols = [app.symbols, app.controlRender(axes_handle, minX-20, centerY, '⊼', 'jumpLeft')];
            app.symbols = [app.symbols, app.controlRender(axes_handle, maxX+20, centerY, '⊼', 'jumpRight')];
            app.symbols = [app.symbols, app.controlRender(axes_handle, centerX, centerY-20, '⊼', 'jumpUp')];
            app.symbols = [app.symbols, app.controlRender(axes_handle, centerX, maxY+20, '⊻', 'jumpDown')];
            app.symbols = [app.symbols, app.controlRender(axes_handle, maxX+20, maxY+20, '✥', 'moveFree')];
            %app.symbols = [app.symbols, app.controlRender(axes_handle, minX-20, minY-20, '✥', 'moveFree')];
            %app.symbols = [app.symbols, app.controlRender(axes_handle, maxX+20, maxY+20, '⇱', 'scaleFree')];
            special_symbols = {'↹', '⭿', '⭯'};
            tags = {'flip-hor', 'flip-vert', 'rotate'};
            for i = 1:length(special_symbols)
                app.symbols = [app.symbols, app.controlRender(axes_handle, minX+25+((i-1)*25), maxY+(25), special_symbols{i}, tags{i})];
            end
        
            % Group the bounding box, ROIs, and symbols
            app.neuronBox = hgtransform('Parent', axes_handle);
            set(boundingBox, 'Parent', app.neuronBox);
            set(rois, 'Parent', app.neuronBox);

            % Call the new function to reposition the symbols
            app.repositionSymbols('new');
            app.symbolBox = hgtransform('Parent', axes_handle);
            %set(app.neuronBox, 'Parent', app.symbolBox);
            set(app.symbols, 'Parent', app.symbolBox);
        end

        function center = get_center(app)
            bbox = app.neuronBox.Children(end);
            pos_diff = app.updateCoords(app.neuronBox.Children(end).Position,app.neuronBox.Matrix,1);
            cur_pos = bbox.Position + pos_diff;
            center = [cur_pos(1)+cur_pos(3)/2,cur_pos(2)+cur_pos(4)/2];
        end

        function moveNeuronBox(app, action, x, y)
            if ~exist('y','var')
                y = 0;
            elseif ~exist('x','var')
                x = 0;
            end

            switch action
                case 'scale'
                    z = 0.001;
                    app.neuronBox.Matrix = app.neuronBox.Matrix*makehgtform(action,[x, y, z]);
                case 'zrotate'
                    pre_center = app.get_center();
                    app.neuronBox.Matrix = app.neuronBox.Matrix*makehgtform(action, x);
                    post_center = app.get_center();
                    diff_center = pre_center-post_center;
                    app.neuronBox.Matrix = app.neuronBox.Matrix*makehgtform(action, x,'translate', [diff_center(2)*2, 0,0]);
                    return
                otherwise
                    z = 0;
                    app.neuronBox.Matrix = app.neuronBox.Matrix*makehgtform(action,[x, y, z]);
            end

            trans_diff = app.neuronBox.Matrix-app.symbolBox.Matrix;
            app.symbolBox.Matrix = app.symbolBox.Matrix*makehgtform('translate',[trans_diff(1,4),trans_diff(2,4),0]);
        end

        function pos_diff = updateCoords(app, coords, matrix, diff_toggle)
            if size(coords,2)<4
                coords(3:4) = [0,0];
            else
                coords(3:4) = [coords(1)+coords(3),coords(2)-coords(4)];
            end
            
            pos_in = [coords(1:2);coords(3:4)];
            pos_out = zeros(size([pos_in(1:2);pos_in(3:4)]));
            for i=1:size(pos_in, 1)
                ptsH = [pos_in(i,1); pos_in(i,2); 0; 1;];
                  p2 = matrix*ptsH;
                  pOut = [p2(1,:) ./ p2(4,:); ...
                          p2(2,:) ./ p2(4,:); ...
                          p2(3,:) ./ p2(4,:); ...
                          ];
                  pos_out(i,1) = pOut(1);
                  pos_out(i,2) = pOut(2);
            end

            if diff_toggle
                pos_diff = coords - reshape(pos_out.', 1, []);
            else
                pos_diff = reshape(pos_out.', 1, []);
            end
        end

        function controller = withinHelper(app,target,cursor)
            controller = [];
            for n=1:size(target,2)  
                target(n).Units = 'pixels';      
                x = [target(n).Position(1), target(n).Position(2), target(n).Position(1)+target(n).FontSize*1.20, target(n).Position(2)+target(n).FontSize*0.80];
                %disp(sprintf("%s x-position = [%.f, %.f], y-position = [%.f, %.f].",target(n).String,x(1),(x(3)),x(2),(x(4))))

                pos = cursor;
                if (pos(1)>x(1)&pos(1)<(x(3))&pos(2)>x(2)&pos(2)<(x(4)))
                    %disp(sprintf("🢄 x-position = %.f, y-position = %.f. <--------- MATCH!",pos(1),pos(2)))
                    controller = target(n);
                else
                    %disp(sprintf("🢄 x-position = %.f, y-position = %.f.",pos(1),pos(2)))
                end
                target(n).Units = 'data';
            end
        end

        % Manage click & drag events.
        function TraceDragManager (app, mode, ~)
            if app.DisplayNeuronsCheckBox.Value
                pos = get(app.NeuroPAL_IDTraceWindowUIFigure, 'CurrentPoint');
                switch mode
                    case 'down'
                        target = app.parent_app.grab_land(app.NeuroPAL_IDTraceWindowUIFigure, pos, 'matlab.ui.control.UIAxes', 'volume-viewer');
                        if ~isempty(target)
                            app.origin = pos;
                            app.active_symbol = app.withinHelper(app.symbols, pos);
                            
                            if isa(app.active_symbol,'matlab.graphics.primitive.Text')
                                set(app.NeuroPAL_IDTraceWindowUIFigure, 'Pointer', 'custom', 'PointerShapeCData', NaN(16,16))
                                drawnow;
    
                                app.active_symbol.Color = [1 0 0];
                                app.active_symbol.FontSize = app.active_symbol.FontSize + 2;
        
                                switch app.active_symbol.Tag
                                    case 'flip-hor'
                                        app.FlipHorizontallyButton.ButtonPushedFcn;
                                    case 'flip-vert'
                                        app.FlipVerticallyButton.ButtonPushedFcn;
                                    case 'jumpLeft'
                                        current_x = app.active_symbol.Position(1)+app.symbolBox.Matrix(1,4);
                                        app.moveNeuronBox('translate',current_x*(-1),0)
                                    case 'jumpRight'
                                        current_x = app.active_symbol.Position(1)+app.symbolBox.Matrix(1,4);
                                        app.moveNeuronBox('translate',abs(app.Npal_Axes.XLim(2)-current_x),0)
                                    case 'jumpUp'
                                        current_y = app.active_symbol.Position(2)+app.symbolBox.Matrix(2,4);
                                        app.moveNeuronBox('translate',0,current_y*(-1))
                                    case 'jumpDown'
                                        current_y = app.active_symbol.Position(2)+app.symbolBox.Matrix(2,4);
                                        app.moveNeuronBox('translate',0,app.Npal_Axes.YLim(2)-current_y)
                                    otherwise
                                        app.NeuroPAL_IDTraceWindowUIFigure.WindowButtonMotionFcn = @(src, event) app.TraceDragManager('move', event);
                                        return
                                end

                                app.active_symbol.Color = [1 1 1];
                                app.active_symbol.FontSize = app.active_symbol.FontSize - 2;
                            end
                        end
                    case 'move'
                        action = 'translate';
                        switch app.active_symbol.Tag
                            case 'rotate'
                                action = 'zrotate';
                                x = pos(1)-app.origin(1);
                                x = x*(pi/180);
                                y = 0;
                            case 'scaleFree'
                                action = 'scale';
                                x = abs((app.origin(1)-pos(1))/100);
                                y = abs((app.origin(1)-pos(1))/100);
                            case 'moveLat'
                                x = (pos(1)-app.origin(1));
                                y = 0;
                            case 'moveVert'
                                x = 0;
                                y = (app.origin(2)-pos(2));
                            case 'moveFree'
                                x = (pos(1)-app.origin(1));
                                y = (app.origin(2)-pos(2));
                        end
                        app.moveNeuronBox(action, x, y);
                        app.origin = pos;
                    case 'up'
                        app.active_symbol.Color = [1 1 1];
                        app.active_symbol.FontSize = app.active_symbol.FontSize - 2;
                        app.NeuroPAL_IDTraceWindowUIFigure.WindowButtonMotionFcn = @(src, event) 1+1;
                        set(app.NeuroPAL_IDTraceWindowUIFigure, 'Pointer', 'arrow')
                        drawnow;

                        %app.repositionSymbols('save');
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_data, image_view, image_neurons, path, dir, red, green, blue, white, dic, gfp, image_gamma)
            app.parent_app = parent_app;
            app.image_data = image_data;
            app.image_view = image_view;
            app.repo_neurons = image_neurons;
            app.image_neurons = image_neurons;
            app.image_gamma = image_gamma;
            app.path = path;
            app.dir = dir;

            app.red = str2double(red);
            app.green = str2double(green);
            app.blue = str2double(blue);
            app.white = str2double(white);
            app.dic = str2double(dic);
            app.gfp = str2double(gfp);

            app.parent_app.CELL_ID.WindowState = 'minimized';
            app.NeuroPAL_IDTraceWindowUIFigure.Position = app.parent_app.CELL_ID.OuterPosition;

            app.updateVolume()
        end

        % Button pushed function: ViewPreprintButton
        function ViewPreprintButtonPushed(app, event)
            web('https://www.biorxiv.org/content/10.1101/2022.07.18.500485v1')
        end

        % Button pushed function: GithubRepositoryButton
        function GithubRepositoryButtonPushed(app, event)
            web('https://github.com/venkatachalamlab/ZephIR')
        end

        % Button pushed function: LaboratoryPageButton
        function LaboratoryPageButtonPushed(app, event)
            web('https://venkatachalamlab.org/')
        end

        % Button pushed function: ClicktoselectvideofileButton
        function ClicktoselectvideofileButtonPushed(app, event)
            % Prompt user to select video file
            [file,path] = uigetfile({'*.h5;*.nwb;*.nd2','Supported Files (*.h5, *.nwb, *.nd2)'},'Select GCaMP file');
    
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Loading data...', 'Indeterminate','on');

            GUI_prefs = Program.GUIPreferences.instance();
            GUI_prefs.trace_path = path;
            GUI_prefs.save();

            app.master_path = path;
            app.data_packer(file);
            app.startBrowser();
            app.UIAxes.Color = [0, 0, 0];

            set(app.AlphaSlider, 'Enable', 'On');
            set(app.SetbookmarkButton,'Enable','On');
            set(app.AdjustAlignmentButton,'Enable','On');
            set(app.TrackNeuronsButton,'Enable','On');
            set(app.ExtractActivityTracesButton,'Enable','On');
            set(app.SaveResultsButton,'Enable','On');
            set(app.RecommendFramesButton,'Enable','On');
            set(app.MotionSlider,'Enable','On');
            set(app.FilesLoadedLabel,'Enable','On');
            set(app.WormMotionPanel,'Enable','On');
            set(app.BookmarkedFramesPanel,'Enable','On');
            set(app.ListBox,'Enable','On');
            set(app.EnableManualZephirSettingsCheckBox,'Enable','On');
            set(app.DisplayNeuronsCheckBox,'Enable','On');
            set(app.FlipvertCheckBox,'Enable','On');
            set(app.FliphorCheckBox,'Enable','On');
            set(app.Rotate90CheckBox,'Enable','On');
            set(app.ClicktoselectvideofileButton,'Visible','Off');
            close(d)
        end

        % Button pushed function: TrackNeuronsButton
        function TrackNeuronsButtonPushed(app, event)
            check = uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'Depending on your computer, this may take a while. Are you sure you want to proceed?','Warning!','Options',{'Yes','No'},'DefaultOption','Yes');

            switch check
                case 'Yes'
    
                    d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Processing arguments...','Indeterminate','on');
                    
                    % Initialize a struct to store the argument values
                    args = struct();
                    
                    % --help and --version are set to false by default
                    args.("--help") = false;
                    args.("--version") = false;
                    
                    % Set default values for some arguments
                    %{
                    args.("--dataset") = ".";
                    args.("--t_track") = "null";
                    args.("--exclusive_prov") = "null";
                    args.("--n_ref") = "null";
                    args.("--t_ignore") = "null";
                    args.("--wlid_ref") = "null";
                    args.("--t_ref") = "null";
                    args.("--n_frame") = "1";
                    args.("--include_all") = "True"; % Include_all is set to True by default
                    args.("--load_checkpoint") = "False"; % Load_checkpoint is set to False by default
                    args.("--exclude_self") = "True"; % Exclude_self is set to True by default
                    args.("--gamma") = "2"; % Gamma is set to 2 by default
                    args.("--allow_rotation") = "False"; % Allow_rotation is set to False by default
                    %}
                    
                    % Get values from the UI elements and set them in the args struct
                    args.("--n_epoch_d") = num2str(app.n_epoch_d.Value);
                    args.("--fovea_sigma") = num2str(app.fovea_sigma.Value);
                    args.("--lr_coef") = num2str(app.lr_coeff.Value);
                    args.("--lambda_d") = num2str(app.l_d.Value);
                    args.("--lr_ceiling") = num2str(app.lr_ceiling.Value);
                    args.("--nn_max") = num2str(app.nn_max.Value);
                    args.("--z_compensator") = num2str(app.z_comp.Value);
                    args.("--lambda_n") = num2str(app.l_n.Value);
                    args.("--n_epoch") = num2str(app.n_epoch.Value);
                    args.("--lr_floor") = num2str(app.lr_floor.Value);
                    args.("--channel") = "1"; % Channel is set to 1 by default
                    args.("--load_nn") = "False"; % Load_nn is set to False by default
                    args.("--n_chunks") = num2str(app.n_chunks.Value);
                    args.("--motion_predict") = app.motion_predict.Value;
                    args.("--load_args") = "True"; % Load_args is set to True by default
                    args.("--lambda_t") = num2str(app.l_t.Value);
                    args.("--lambda_n_mode") = app.l_n_mode.Value;
                    args.("--sort_mode") = app.sort_mode.Value;
                    args.("--grid_shape") = num2str(app.grid_shape.Value);
                    args.("--clip_grad") = num2str(app.clip_grad.Value);
                    args.("--cuda") = app.use_gpu.Value; % Cuda is set to True by default
                    args.("--dimmer_ratio") = num2str(app.dimmer_ratio.Value);
                    
                    % Write the args struct to the "args.json" file
                    jsonStr = jsonencode(args);
                    fid = fopen('External_Dependencies\ZephIR\example\data\args.json', 'w');
                    fprintf(fid, '%s', jsonStr);
                    fclose(fid);

                    copyfile('External_Dependencies\ZephIR\example\data\args.json',[app.master_path,'args.json']);

                    close(d)
                    
                    app.closeBrowser();

                    try
                        d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Running tracker...','Indeterminate','on');
                        
                        app.zeph_wrapper('tracker');
                        
                        close(d)
                    catch
                        uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'run_tracker failed.','Error');
                    end
        
                    app.startBrowser();

                case 'No'
                    return
            end
        end

        % Button pushed function: ExtractActivityTracesButton
        function ExtractActivityTracesButtonPushed(app, event)
            app.closeBrowser();

            try
                d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Running tracer...','Indeterminate','on');
                
                app.zeph_wrapper('tracer');
                
                close(d)
            catch
                uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'run_tracer failed.','Error');
            end

            app.startBrowser();
        end

        % Value changed function: EnableManualZephirSettingsCheckBox
        function EnableManualZephirSettingsCheckBoxValueChanged(app, event)
            if app.EnableManualZephirSettingsCheckBox.Value == 1
                app.closeBrowser()
                check = uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'These are complicated settings that may yield unexpected results. We do not recommend changing them unless you have read the documentation and know what you are doing. Are you sure you want to proceed?','Warning!','Options',{'Yes', 'No'},'DefaultOption','No');

                switch check
                    case 'Yes'
                        % Well, we tried to warn them.
                    case 'No'
                        app.EnableManualZephirSettingsCheckBox.Value = 0;
                        return
                end
                app.startBrowser()

                set(app.use_gpu,'Enable','On')
                set(app.n_epoch,'Enable','On')
                set(app.n_epoch_d,'Enable','On')
                set(app.n_chunks,'Enable','On')
                set(app.clip_grad,'Enable','On')
                set(app.l_t,'Enable','On')
                set(app.l_d,'Enable','On')
                set(app.l_n,'Enable','On')
                set(app.z_comp,'Enable','On')
                set(app.lr_coeff,'Enable','On')
                set(app.lr_floor,'Enable','On')
                set(app.l_n_mode,'Enable','On')
                set(app.nn_max,'Enable','On')
                set(app.lr_ceiling,'Enable','On')
                set(app.fovea_sigma,'Enable','On')
                set(app.sort_mode,'Enable','On')
                set(app.grid_shape,'Enable','On')
                set(app.motion_predict,'Enable','On')
                set(app.dimmer_ratio,'Enable','On')
            else
                set(app.n_epoch,'Enable','Off')
                set(app.n_epoch_d,'Enable','Off')
                set(app.n_chunks,'Enable','Off')
                set(app.use_gpu,'Enable','Off')
                set(app.clip_grad,'Enable','Off')
                set(app.l_t,'Enable','Off')
                set(app.l_d,'Enable','Off')
                set(app.l_n,'Enable','Off')
                set(app.z_comp,'Enable','Off')
                set(app.lr_coeff,'Enable','Off')
                set(app.lr_floor,'Enable','Off')
                set(app.l_n_mode,'Enable','Off')
                set(app.nn_max,'Enable','Off')
                set(app.lr_ceiling,'Enable','Off')
                set(app.fovea_sigma,'Enable','Off')
                set(app.sort_mode,'Enable','Off')
                set(app.grid_shape,'Enable','Off')
                set(app.motion_predict,'Enable','Off')
                set(app.dimmer_ratio,'Enable','On')
            end
        end

        % Button pushed function: SetbookmarkButton
        function SetbookmarkButtonPushed(app, event)
            if isempty(app.BmNameInput.Value)
                bmLabel = ['Bookmark #',int2str(size(app.ListBox.Items,2)+1)];
            else
                bmLabel = app.BmNameInput.Value;
            end

            url = 'http://localhost:5001/current_time_point';
            response = webread(url);

            bmFrame = response.time_point;
            app.ListBox.Items = [app.ListBox.Items, sprintf("%s (Frame %.f)",bmLabel,bmFrame)];
            if size(app.ListBox.ItemsData)>0
                app.ListBox.ItemsData = [app.ListBox.ItemsData, bmFrame];
            else
                app.ListBox.ItemsData = [bmFrame];
            end

            app.BmNameInput.Value = '';
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            target_frame = round(event.InteractionInformation.Item);
            url = 'http://localhost:5001/current_time_point';
            response = webread(url);
        end

        % Button pushed function: Up
        function UpPushed(app, event)
            app.moveNeuronBox('translate', 0, app.MoveEditField_2.Value);
        end

        % Button pushed function: AdjustAlignmentButton
        function AdjustAlignmentButtonPushed(app, event)
            app.closeBrowser()
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Loading alignment interface...','Indeterminate','on');
            set(app.AdjustNeuronMarkerAlignmentPanel,'Visible','On');
            app.AdjustNeuronMarkerAlignmentPanel.Position = app.TabGroup.Position;
            app.DisplayNeuronsCheckBox.Value = 1;
            app.drawNeuronBox(app.Npal_Axes);
            app.downspy = addlistener(app.NeuroPAL_IDTraceWindowUIFigure, 'WindowMousePress', @(src, event) app.TraceDragManager('down', event));
            app.upspy = addlistener(app.NeuroPAL_IDTraceWindowUIFigure, 'WindowMouseRelease', @(src, event) app.TraceDragManager('up', event));
            close(d)
            app.startBrowser()
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            set(app.AdjustNeuronMarkerAlignmentPanel,'Visible','Off');

            % Preallocate cell arrays for names and positions
            names = cell(length(app.image_neurons.neurons), 1);
            positions = zeros(length(app.image_neurons.neurons), 3);
            pos_diff = app.updateCoords(app.neuronBox.Children(end).Position,app.neuronBox.Matrix,1);

            % Flip all neurons vertically around the center point
            for n = 1:size(app.image_neurons.neurons, 2)
                app.image_neurons.neurons(n).position(1:2) = app.image_neurons.neurons(n).position(1:2)+pos_diff(1:2);
                names{n} = app.image_neurons.neurons(n).annotation;
                positions(n, :) = app.image_neurons.neurons(n).position;
            end

            app.neuronBox.Matrix = eye(4);
            num_slices = size(app.image_data,3);

            url = 'http://localhost:5001/current_time_point';
            response = webread(url);

            current_frame = response.time_point;

            %{
            % Convert positions to strings
            positions_str = cellfun(@(x) sprintf('%f, %f, %f', x(2)/app.Npal_Axes.XLim(2), x(1)/app.Npal_Axes.YLim(2), x(3)/num_slices), num2cell(positions, 2), 'UniformOutput', false);

            url = 'http://localhost:5001/current_time_point';
            response = webread(url);

            current_frame = response.time_point;

            % Create a table
            T = table(names, positions_str, 'VariableNames', {'name', 'positions'});
            
            % Write the table to a CSV file
            writetable(T, 'temp.csv');

            % Get the absolute path to the CSV file
            csv_path = fullfile(pwd, 'temp.csv');

            cmd = [pwd,sprintf('/External_Dependencies/ZephIR/annotation-converter.py %s %s %s %s %s %s %s', app.master_path, [app.master_path,app.data_file], app.annotation_file, app.metadata_file, app.dir, csv_path, current_frame)];
            pyrunfile(cmd)
            %}

            x_pos = cellfun(@(x) x(2), num2cell(positions, 2), 'UniformOutput', false);
            y_pos = cellfun(@(x) x(1), num2cell(positions, 2), 'UniformOutput', false);
            z_pos = cellfun(@(x) x(3), num2cell(positions, 2), 'UniformOutput', false);
            t_id = 1:size(x_pos);
            new_tbl = table(x_pos, y_pos, z_pos, t_id.', names, 'VariableNames', {'X','Y','Z','TrackID', 'Name'});

            % Save the new table to a new CSV file
            writetable(new_tbl, fullfile(pwd, 'temp.csv'));
            csv_path = fullfile(pwd, 'temp.csv');
            
            cmd = sprintf('%s/External_Dependencies/ZephIR/annotation-converter.py "%s" "%s" "%s" "%s" "%s" %.f', pwd, app.master_path, app.master_path, app.master_path, app.master_path, csv_path, current_frame)
            pyrunfile(cmd)

            app.UIAxes = uiaxes(app.MainTraceGrid);
            app.UIAxes.XColor = 'none';
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YColor = 'none';
            app.UIAxes.YTick = [];
            app.UIAxes.ZColor = 'none';
            app.UIAxes.Color = 'none';
            app.UIAxes.Layout.Row = [1 2];
            app.UIAxes.Layout.Column = 2;
            delete(app.downspy)
            delete(app.upspy)
            delete(app.movespy)
            delete(app.neuronBox)
            delete(app.symbols)
            app.closeBrowser();
            system('taskkill /F /IM annotator.exe');
            app.startBrowser();
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            app.moveNeuronBox('translate', app.MoveEditField_2.Value, 0);
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            app.moveNeuronBox('translate', (-1)*app.MoveEditField_2.Value, 0);
        end

        % Button pushed function: Down
        function DownButtonPushed(app, event)
            app.moveNeuronBox('translate', 0, (-1)*app.MoveEditField_2.Value);
        end

        % Button pushed function: FlipHorizontallyButton
        function FlipHorizontallyButtonPushed(app, event)
            app.SaveResultButton.ButtonPushedFcn;

            % Find the center of the bounding box
            boundingBox = app.neuronBox.Children(end);
            minY = boundingBox.Position(2);
            maxY = minY + boundingBox.Position(4);
            centerY = (minY + maxY) / 2;

            % Flip all neurons horizontally around the center point
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                new_pos = [pos(1), 2*centerY-pos(2), pos(3)];
                app.image_neurons.neurons(n).position = new_pos;
            end
        
            app.drawNeuronBox(app.Npal_Axes);
        end

        % Button pushed function: FlipVerticallyButton
        function FlipVerticallyButtonPushed(app, event)
            app.SaveResultButton.ButtonPushedFcn;
            
            % Find the center of the bounding box
            boundingBox = app.neuronBox.Children(end);
            minX = boundingBox.Position(1);
            maxX = minX + boundingBox.Position(3);
            centerX = (minX + maxX) / 2;
        
            % Flip all neurons vertically around the center point
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                new_pos = [2*centerX-pos(1), pos(2), pos(3)];
                app.image_neurons.neurons(n).position = new_pos;
            end
        
            app.drawNeuronBox(app.Npal_Axes);
        end

        % Button pushed function: CCW
        function CCWButtonPushed(app, event)
            % Find bounding box
            min_x = Inf; max_x = -Inf;
            min_y = Inf; max_y = -Inf;
            for n = 1:size(app.repo_neurons.neurons,2)
                pos = app.repo_neurons.neurons(n).position;
                if pos(1) < min_x, min_x = pos(1); end
                if pos(1) > max_x, max_x = pos(1); end
                if pos(2) < min_y, min_y = pos(2); end
                if pos(2) > max_y, max_y = pos(2); end
            end
            
            % Compute center of bounding box
            center_x = (min_x + max_x) / 2;
            center_y = (min_y + max_y) / 2;
            
            % Translate all neurons to origin
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1) - center_x;
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2) - center_y;
            end
            
            % Compute rotation matrix
            theta = pi/2; % rotation angle (90 degrees)
            R = [cos(theta) -sin(theta); sin(theta) cos(theta)]; % 2D rotation matrix
            
            % Apply rotation matrix to each position vector
            for n = 1:size(app.repo_neurons.neurons,2)
                position = app.repo_neurons.neurons(n).position(1:2); % extract x and y coordinates
                position = R * position'; % apply rotation matrix
                app.repo_neurons.neurons(n).position(1:2) = position'; % update x and y coordinates
            end
            
            % Translate all neurons back to their original position
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1) + center_x;
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2) + center_y;
            end
            
            app.updateVolume()
            app.DrawNeurons();
        end

        % Button pushed function: CenterHorizontallyButton
        function CenterHorizontallyButtonPushed(app, event)
            % Find the vertical extent of the neurons
            min_y = Inf; max_y = -Inf;
            for n = 1:size(app.repo_neurons.neurons,2)
                pos = app.repo_neurons.neurons(n).position;
                if pos(2) < min_y, min_y = pos(2); end
                if pos(2) > max_y, max_y = pos(2); end
            end
            
            % Compute the center of the UIAxes
            center_y = mean(app.Npal_Axes.YLim);
            
            % Compute the displacement needed to center the neurons vertically
            dy = center_y - (min_y + max_y) / 2;
            
            % Translate all neurons by the computed displacement
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2) + dy;
            end
            
            app.updateVolume()
            app.DrawNeurons();
        end

        % Button pushed function: CenterVerticallyButton
        function CenterVerticallyButtonPushed(app, event)
            % Find the horizontal extent of the neurons
            min_x = Inf; max_x = -Inf;
            for n = 1:size(app.repo_neurons.neurons,2)
                pos = app.repo_neurons.neurons(n).position;
                if pos(1) < min_x, min_x = pos(1); end
                if pos(1) > max_x, max_x = pos(1); end
            end
            
            % Compute the center of the UIAxes
            center_x = mean(app.Npal_Axes.XLim);
            
            % Compute the displacement needed to center the neurons horizontally
            dx = center_x - (min_x + max_x) / 2;
            
            % Translate all neurons by the computed displacement
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1) + dx;
            end
            
            app.updateVolume()
            app.DrawNeurons();
        end

        % Close request function: NeuroPAL_IDTraceWindowUIFigure
        function NeuroPAL_IDTraceWindowUIFigureCloseRequest(app, event)
            try
                app.NeuroPAL_IDTraceWindowUIFigure.WindowState = 'normal';
                app.closeBrowser();
            catch
                'No browser detected.'
            end

            app.parent_app.CELL_ID.WindowState = 'normal';
            app.repo_neurons = app.image_neurons;
            delete(app)
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            % Get the scaling factor from the TransformScale slider
            scale_factor = app.ScaleEditField.Value;
        
            % Find the center of the bounding box
            bbox = [inf, -inf, inf, -inf, inf, -inf];
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                bbox(1) = min(bbox(1), pos(1));
                bbox(2) = max(bbox(2), pos(1));
                bbox(3) = min(bbox(3), pos(2));
                bbox(4) = max(bbox(4), pos(2));
                bbox(5) = min(bbox(5), pos(3));
                bbox(6) = max(bbox(6), pos(3));
            end
            center = [(bbox(1)+bbox(2))/2, (bbox(3)+bbox(4))/2, (bbox(5)+bbox(6))/2];
        
            % Scale all neurons around the center point
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                displacement = pos - center;
                new_displacement = displacement * scale_factor;
                app.image_neurons.neurons(n).position = center + new_displacement;
            end

            app.updateVolume();
            
            if app.DisplayNeuronsCheckBox.Value && exist("app.neuronBox","var")
                app.drawNeuronBox(app.Npal_Axes)
            end
        end

        % Value changed function: MotionSlider
        function MotionSliderValueChanged(app, event)
            app.MotionSlider.Value = round(app.MotionSlider.Value);

            switch app.MotionSlider.Value
                case 0
                    app.l_n.Value = 2.0;
                    app.lr_floor.Value = 0.01;
                    app.z_comp.Value = 0.5;
                    app.lr_ceiling.Value = 0.1;
                    app.lr_coeff.Value = 1.0;
                    app.sort_mode.Value = 'linear';
                case 1
                case 2
                    app.clip_grad.Value = 0.2;
                    app.l_n.Value = 0.1;
                    app.lr_floor.Value = 0.01;
                    app.lr_ceiling.Value = 0.1;
                case 3
                    app.clip_grad.Value = 1;
                    app.l_n_mode.Value = 'norm';
                    app.lr_floor.Value = 0.1;
                    app.z_comp.Value = 4.0;
                    app.fovea_sigma.Value = 10;
                    app.grid_shape.Value = 49;
                    app.lr_ceiling.Value = 0.1;
                    app.motion_predict.Value = 'True';
            end
            
        end

        % Button pushed function: RecommendFramesButton
        function RecommendFramesButtonPushed(app, event)
            app.closeBrowser();

            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Analyzing video...','Indeterminate','on');
            t_ref = double(app.zeph_wrapper('recommend_frames'));
            t_ref
            close(d)

            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Creating bookmarks...','Indeterminate','on');
            size(t_ref)
            for n=1:size(t_ref,2)
                bmLabel = sprintf('Recommended Frame #%.f (Frame %.f)', int2str(n), t_ref(n))
                bmFrame = t_ref(n)
                
                app.ListBox.Items = [app.ListBox.Items, bmLabel];
                if size(app.ListBox.ItemsData)>0
                    app.ListBox.ItemsData = [app.ListBox.ItemsData, bmFrame];
                else
                    app.ListBox.ItemsData = [bmFrame];
                end
            end
            close(d)

            app.startBrowser();
        end

        % Button pushed function: ResetMarkerPositionsButton
        function ResetMarkerPositionsButtonPushed(app, event)
            app.closeBrowser();
            
            app.repo_neurons.neurons = app.image_neurons.neurons;
            app.AdjustNeuronMarkerAlignmentPanel.Position = app.TabGroup.Position;
            app.DisplayNeuronsCheckBox.Value = 1;
            delete(app.neuronBox)
            app.drawNeuronBox(app.Npal_Axes);
            delete(app.downspy)
            delete(app.upspy)
            app.downspy = addlistener(app.NeuroPAL_IDTraceWindowUIFigure, 'WindowMousePress', @(src, event) app.TraceDragManager('down', event));
            app.upspy = addlistener(app.NeuroPAL_IDTraceWindowUIFigure, 'WindowMouseRelease', @(src, event) app.TraceDragManager('up', event));
        end

        % Callback function
        function ResetTransformButtonPushed(app, event)
            app.repo_neurons.neurons = app.image_neurons.neurons;
            app.DrawNeurons();
        end

        % Key press function: NeuroPAL_IDTraceWindowUIFigure
        function NeuroPAL_IDTraceWindowUIFigureKeyPress(app, event)
            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % What did the user press?
            key = event.Key;
            switch key

                case '0' % Toggle the ID atlas.
                    app.ColorAtlasCheckBox.Value = ~app.ColorAtlasCheckBox.Value;

                case '1' % Toggle the red channel.
                    app.RCheckBox.Value = ~app.RCheckBox.Value;

                case '2' % Toggle the green channel.
                    app.GCheckBox.Value = ~app.GCheckBox.Value;

                case '3' % Toggle the blue channel.
                    app.BCheckBox.Value = ~app.BCheckBox.Value;

                case '4' % Toggle the white channel.
                    app.WCheckBox.Value = ~app.WCheckBox.Value;

                case '5' % Toggle the DIC channel.
                    app.DICCheckBox.Value = ~app.DICCheckBox.Value;

                case '6' % Toggle the GFP channel.
                    app.GFPCheckBox.Value = ~app.GFPCheckBox.Value;

                case 'leftarrow' % Go up in the z stack.
                    if app.AlphaSlider.Value > 1
                        zEvent.Value = app.AlphaSlider.Value - 1;
                        app.AlphaSliderValueChanged(zEvent);
                    end
                    %disp(key);

                case 'rightarrow' % Go down in the z stack.
                    if app.AlphaSlider.Value < app.AlphaSlider.Limits(2)
                        zEvent.Value = app.AlphaSlider.Value + 1;
                        app.AlphaSliderValueChanged(zEvent);
                    end
                    %disp(key);

                case 'uparrow' % Zoom in.
                    zoom(app.Npal_Axes, 1 + 0.2);

                case 'downarrow' % Zoom out.
                    zoom(app.Npal_Axes, 1 - 0.2);

                case {'h','H'} % Toggle half zoom.
                    % Reset x-axis limits.
                    app.Npal_Axes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> lower half.
                    ylims = app.Npal_Axes.YLim;
                    if ylims(1) < size(app.image_data, 1)/2
                        app.Npal_Axes.YLim = [ceil(size(app.image_data, 1)/2), ...
                            size(app.image_data, 1)];

                        % Lower -> upper half.
                    else
                        app.Npal_Axes.YLim = [1, floor(size(app.image_data, 1)/2)];
                    end

                case {'j','J'} % Toggle third zoom.
                    % Reset x-axis limits.
                    app.Npal_Axes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> middle third.
                    ylims = app.Npal_Axes.YLim;
                    if ylims(1) < size(app.image_data, 1)/3
                        app.Npal_Axes.YLim = [ceil(size(app.image_data, 1)/3), ...
                            floor(size(app.image_data, 1)*2/3)];

                        % Middle -> lower third.
                    elseif ylims(1) < size(app.image_data, 1)*2/3
                        app.Npal_Axes.YLim = [ceil(size(app.image_data, 1)*2/3), ...
                            size(app.image_data, 1)];

                        % Lower -> upper third.
                    else
                        app.Npal_Axes.YLim = [1, floor(size(app.image_data, 1)/3)];
                    end

                case {'k','K'} % Toggle quarter zoom.
                    % Reset x-axis limits.
                    app.Npal_Axes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> upper-middle quarter.
                    ylims = app.Npal_Axes.YLim;
                    if ylims(1) < size(app.image_data, 1)/4
                        app.Npal_Axes.YLim = [ceil(size(app.image_data, 1)/4), ...
                            floor(size(app.image_data, 1)*2/4)];

                        % Upper-middle -> lower-middle quarter.
                    elseif ylims(1) < size(app.image_data, 1)*2/4
                        app.Npal_Axes.YLim = [ceil(size(app.image_data, 1)*2/4), ...
                            floor(size(app.image_data, 1)*3/4)];

                        % Lower-middle -> lower quarter.
                    elseif ylims(1) < size(app.image_data, 1)*3/4
                        app.Npal_Axes.YLim = [ceil(size(app.image_data, 1)*3/4), ...
                            size(app.image_data, 1)];

                        % Lower -> upper quarter.
                    else
                        app.Npal_Axes.YLim = [1, floor(size(app.image_data, 1)/4)];
                    end

                case {'p','P'} % Toggle image panning.

                    % Toggle image panning.
                    if app.is_panning
                        pan(app.Npal_Axes,'off');
                        pause(0.5);
                        RestorePointer(app);
                        app.is_panning = false;
                    else
                        pan(app.Npal_Axes,'on');
                        pause(0.5);
                        app.is_panning = true;
                    end

                case {'r','R'} % Reset the figure to center.
                    app.Npal_Axes.XLim = [1, size(app.image_data, 2)];
                    app.Npal_Axes.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XLim = [1, size(app.image_data, 2)];
                    %app.XY.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XAxisLocation = 'bottom';
                    %app.XY.YAxisLocation = 'left';

                    %case {'x','X'} % Switch mouse to crosshairs.
                    %zoom(app.XY,'on');
                    %pause(0.2);
                    %zoom(app.XY,'off');

                otherwise
                    % Do nothing.
            end
        end

        % Callback function
        function SaveNeuronIDsButtonPushed(app, event)
            set(app.IDCurrentFramePanel,'Enable','Off');
        end

        % Value changed function: UseMATLABbrowserCheckBox
        function UseMATLABbrowserCheckBoxValueChanged(app, event)
            if ~app.ClicktoselectvideofileButton.Visible
                app.closeBrowser()
                app.startBrowser()
            end
        end

        % Value changed function: DisplayNeuronsCheckBox
        function DisplayNeuronsCheckBoxValueChanged(app, event)
            app.updateVolume();
        end

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)
            app.updateVolume();
        end

        % Button pushed function: GotobookmarkButton
        function GotobookmarkButtonPushed(app, event)
            try                
                if ~isempty(app.ListBox.value)
                    url = 'http://localhost:5001/current_time_point';
                    response = webread(url);
                    app.browserCommand('rpc',sprintf('method=jump_to_frame&state=%.f&arg=%.f',string(app.ListBox.Value),string(app.ListBox.Value)))
                else
                    uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,"Error", 'No bookmark selected.');
                end
            catch ME
                fprintf('Error occurred: %s\n', ME.message);
            end
        end

        % Value changed function: Rotate90CheckBox
        function Rotate90CheckBoxValueChanged(app, event)
            app.updateVolume();
        end

        % Value changed function: FlipvertCheckBox
        function FlipvertCheckBoxValueChanged(app, event)
            app.updateVolume();
        end

        % Value changed function: FliphorCheckBox
        function FliphorCheckBoxValueChanged(app, event)
            app.updateVolume();
        end

        % Button pushed function: CancelAlignmentButton
        function CancelAlignmentButtonPushed(app, event)
            set(app.AdjustNeuronMarkerAlignmentPanel,'Visible','Off');

            app.UIAxes = uiaxes(app.MainTraceGrid);
            app.UIAxes.XColor = 'none';
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YColor = 'none';
            app.UIAxes.YTick = [];
            app.UIAxes.ZColor = 'none';
            app.UIAxes.Color = 'none';
            app.UIAxes.Layout.Row = [1 2];
            app.UIAxes.Layout.Column = 2;
            delete(app.downspy)
            delete(app.upspy)
            delete(app.movespy)
            delete(app.neuronBox)
            delete(app.symbols)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NeuroPAL_IDTraceWindowUIFigure = uifigure('Visible', 'off');
            app.NeuroPAL_IDTraceWindowUIFigure.Name = 'NeuroPAL_ID Trace Window';
            app.NeuroPAL_IDTraceWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @NeuroPAL_IDTraceWindowUIFigureCloseRequest, true);
            app.NeuroPAL_IDTraceWindowUIFigure.KeyPressFcn = createCallbackFcn(app, @NeuroPAL_IDTraceWindowUIFigureKeyPress, true);
            app.MainTraceGrid = uigridlayout(app.NeuroPAL_IDTraceWindowUIFigure);
            app.UIAxes = uiaxes(app.MainTraceGrid);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.TabGroup = uitabgroup(app.MainTraceGrid);
            app.CentralControlTab = uitab(app.TabGroup);
            app.CentralControlTab.Title = 'Central Control';
            app.Panel_4 = uipanel(app.CentralControlTab);
            app.GridLayout12 = uigridlayout(app.Panel_4);
            app.Panel_8 = uipanel(app.GridLayout12);
            app.GridLayout8 = uigridlayout(app.Panel_8);
            app.BookmarkedFramesPanel = uipanel(app.GridLayout8);
            app.BookmarkedFramesPanel.Enable = 'off';
            app.BookmarkedFramesPanel.Title = 'Bookmarked Frames';
            app.GridLayout13 = uigridlayout(app.BookmarkedFramesPanel);
            app.ListBox = uilistbox(app.GridLayout13);
            app.ListBox.Items = {};
            app.ListBox.Enable = 'off';
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            app.BmNameInput = uieditfield(app.GridLayout13, 'text');
            app.BmNameInput.Placeholder = 'Bookmark Name';
            app.SetbookmarkButton = uibutton(app.GridLayout13, 'push');
            app.SetbookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @SetbookmarkButtonPushed, true);
            app.SetbookmarkButton.Enable = 'off';
            app.SetbookmarkButton.Text = 'Set bookmark';
            app.GotobookmarkButton = uibutton(app.GridLayout13, 'push');
            app.GotobookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @GotobookmarkButtonPushed, true);
            app.GotobookmarkButton.Text = 'Go to bookmark';
            app.WormMotionPanel = uipanel(app.GridLayout8);
            app.WormMotionPanel.Enable = 'off';
            app.WormMotionPanel.Title = 'Worm Motion';
            app.GridLayout5 = uigridlayout(app.WormMotionPanel);
            app.MotionSlider = uislider(app.GridLayout5);
            app.MotionSlider.Limits = [0 3];
            app.MotionSlider.MajorTicks = [0 1 2 3];
            app.MotionSlider.MajorTickLabels = {'None', 'Low', 'Mid', 'High'};
            app.MotionSlider.ValueChangedFcn = createCallbackFcn(app, @MotionSliderValueChanged, true);
            app.MotionSlider.MinorTicks = [];
            app.MotionSlider.Enable = 'off';
            app.Panel_6 = uipanel(app.GridLayout12);
            app.GridLayout29 = uigridlayout(app.Panel_6);
            app.RecommendFramesButton = uibutton(app.GridLayout29, 'push');
            app.RecommendFramesButton.ButtonPushedFcn = createCallbackFcn(app, @RecommendFramesButtonPushed, true);
            app.RecommendFramesButton.Enable = 'off';
            app.RecommendFramesButton.Text = 'Recommend Frames';
            app.AdjustAlignmentButton = uibutton(app.GridLayout29, 'push');
            app.AdjustAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustAlignmentButtonPushed, true);
            app.AdjustAlignmentButton.Enable = 'off';
            app.AdjustAlignmentButton.Text = 'Adjust Alignment';
            app.SaveResultsButton = uibutton(app.GridLayout29, 'push');
            app.SaveResultsButton.Enable = 'off';
            app.SaveResultsButton.Text = 'Save Results';
            app.ExtractActivityTracesButton = uibutton(app.GridLayout29, 'push');
            app.ExtractActivityTracesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractActivityTracesButtonPushed, true);
            app.ExtractActivityTracesButton.Enable = 'off';
            app.ExtractActivityTracesButton.Text = 'Extract Activity Traces';
            app.TrackNeuronsButton = uibutton(app.GridLayout29, 'push');
            app.TrackNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @TrackNeuronsButtonPushed, true);
            app.TrackNeuronsButton.Enable = 'off';
            app.TrackNeuronsButton.Text = 'Track Neurons';
            app.UseMATLABbrowserCheckBox = uicheckbox(app.GridLayout29);
            app.UseMATLABbrowserCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseMATLABbrowserCheckBoxValueChanged, true);
            app.UseMATLABbrowserCheckBox.Text = 'Use MATLAB browser';
            app.Panel_16 = uipanel(app.GridLayout12);
            app.GridLayout30 = uigridlayout(app.Panel_16);
            app.FilesLoadedLabel = uilabel(app.GridLayout30);
            app.FilesLoadedLabel.Enable = 'off';
            app.FilesLoadedLabel.Text = 'Files Loaded';
            app.Tree = uitree(app.GridLayout30, 'checkbox');
            app.Tree.Enable = 'off';
            app.DataNode = uitreenode(app.Tree);
            app.DataNode.Text = 'Data';
            app.WorldlinesNode = uitreenode(app.Tree);
            app.WorldlinesNode.Text = 'Worldlines';
            app.AnnotationsNode = uitreenode(app.Tree);
            app.AnnotationsNode.Text = 'Annotations';
            app.MetadatajsonNode = uitreenode(app.Tree);
            app.MetadatajsonNode.Text = 'Metadata.json';
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.GridLayout = uigridlayout(app.AdvancedSettingsTab);
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel = uilabel(app.GridLayout);
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel.Text = 'Hover over labels for further details or guidance.';
            app.Panel_17 = uipanel(app.GridLayout);
            app.GridLayout36 = uigridlayout(app.Panel_17);
            app.EnableManualZephirSettingsCheckBox = uicheckbox(app.GridLayout36);
            app.EnableManualZephirSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableManualZephirSettingsCheckBoxValueChanged, true);
            app.EnableManualZephirSettingsCheckBox.Enable = 'off';
            app.EnableManualZephirSettingsCheckBox.Text = ' Enable Manual Zephir Settings';
            app.AdvSetTab = uitabgroup(app.GridLayout36);
            app.GeneralTab = uitab(app.AdvSetTab);
            app.GeneralTab.Title = 'General';
            app.GridLayout31 = uigridlayout(app.GeneralTab);
            app.UseGPULabel = uilabel(app.GridLayout31);
            app.UseGPULabel.Text = 'Use GPU';
            app.SetsdevicetoGPUandenablesCUDAuseDropDownLabel = uilabel(app.GridLayout31);
            app.SetsdevicetoGPUandenablesCUDAuseDropDownLabel.Text = 'Sets device to GPU and enables CUDA use.';
            app.use_gpu = uidropdown(app.GridLayout31);
            app.use_gpu.Items = {'True', 'False'};
            app.use_gpu.Editable = 'on';
            app.use_gpu.Enable = 'off';
            app.use_gpu.Value = 'True';
            app.n_epochLabel = uilabel(app.GridLayout31);
            app.n_epochLabel.Text = 'n_epoch';
            app.NumberofiterationsforimageregistrationLREditFieldLabel = uilabel(app.GridLayout31);
            app.NumberofiterationsforimageregistrationLREditFieldLabel.Text = 'Number of iterations for image registration, LR.';
            app.n_epoch_dLabel = uilabel(app.GridLayout31);
            app.n_epoch_dLabel.Text = 'n_epoch_d';
            app.n_epoch = uieditfield(app.GridLayout31, 'numeric');
            app.n_epoch.ValueDisplayFormat = '%.0f';
            app.n_epoch.Enable = 'off';
            app.n_epoch.Value = 40;
            app.EditField2_2Label = uilabel(app.GridLayout31);
            app.EditField2_2Label.Text = 'Number of iterations for feature detection regularization, LD.';
            app.n_epoch_d = uieditfield(app.GridLayout31, 'numeric');
            app.n_epoch_d.ValueDisplayFormat = '%.0f';
            app.n_epoch_d.Enable = 'off';
            app.n_epoch_d.Value = 10;
            app.n_chunksLabel = uilabel(app.GridLayout31);
            app.n_chunksLabel.Text = 'n_chunks';
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel = uilabel(app.GridLayout31);
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel.Text = 'Number of steps to divide the forward pass into.';
            app.n_chunks = uieditfield(app.GridLayout31, 'numeric');
            app.n_chunks.ValueDisplayFormat = '%.0f';
            app.n_chunks.Enable = 'off';
            app.n_chunks.Value = 10;
            app.sort_modeLabel = uilabel(app.GridLayout31);
            app.sort_modeLabel.Text = 'sort_mode';
            app.DropDown_2Label = uilabel(app.GridLayout31);
            app.DropDown_2Label.Text = 'Method for sorting frames and determining parent-child branches.';
            app.sort_mode = uidropdown(app.GridLayout31);
            app.sort_mode.Items = {'similarity', 'linear', 'depth'};
            app.sort_mode.Enable = 'off';
            app.sort_mode.Tooltip = {'''similarity'' minimizes distance between parent and child.'; '''linear'' branches out from reference frames linearly forwards and backwards, with every parent-child one frame apart, until it reaches the first frame, last frame, or another branch. Simplest and fastest.'; '''depth'' uses shortest-path grid search, then sorts frames based on depth in the resulting parent-child tree. This can scale up to O(n4) in computation with number of frames.'};
            app.sort_mode.Value = 'similarity';
            app.RegularizationLossCoefficientsTab = uitab(app.AdvSetTab);
            app.RegularizationLossCoefficientsTab.Title = 'Regularization & Loss Coefficients';
            app.GridLayout35 = uigridlayout(app.RegularizationLossCoefficientsTab);
            app.dLabel = uilabel(app.GridLayout35);
            app.dLabel.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.dLabel.Text = 'λd';
            app.EditField3Label = uilabel(app.GridLayout35);
            app.EditField3Label.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.EditField3Label.Text = 'Coefficient for feature detection loss, λD. ';
            app.l_d = uieditfield(app.GridLayout35, 'numeric');
            app.l_d.ValueDisplayFormat = '%.2f';
            app.l_d.Enable = 'off';
            app.l_d.Value = -1;
            app.tLabel = uilabel(app.GridLayout35);
            app.tLabel.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.tLabel.Text = 'λt';
            app.EditField2Label = uilabel(app.GridLayout35);
            app.EditField2Label.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.EditField2Label.Text = 'Coefficient for temporal smoothing loss, λT, enforcing a 0th-order linear fit for intensity over n_frame frames.';
            app.l_t = uieditfield(app.GridLayout35, 'numeric');
            app.l_t.ValueDisplayFormat = '%.2f';
            app.l_t.Enable = 'off';
            app.l_t.Value = -1;
            app.NLabel = uilabel(app.GridLayout35);
            app.NLabel.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.NLabel.Text = 'λN';
            app.EditField4Label = uilabel(app.GridLayout35);
            app.EditField4Label.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.EditField4Label.Text = 'Coefficient for spring constant for intra-keypoint spatial regularization, λN.';
            app.l_n = uieditfield(app.GridLayout35, 'numeric');
            app.l_n.ValueDisplayFormat = '%.2f';
            app.l_n.Enable = 'off';
            app.l_n.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.l_n.Value = 5;
            app.NmodeLabel = uilabel(app.GridLayout35);
            app.NmodeLabel.Tooltip = {'''disp'': use inter-keypoint displacements'; '''norm'': use inter-keypoint distances (rotation is not penalized)'; '''ljp'': use a Lenard-Jones potential on inter-keypoint distances (collapsing onto the same position is highly penalized)'; 'default: ''disp'''};
            app.NmodeLabel.Text = 'λN mode';
            app.MethodtouseforcalculatingNLabel = uilabel(app.GridLayout35);
            app.MethodtouseforcalculatingNLabel.Text = 'Method to use for calculating LN.';
            app.l_n_mode = uidropdown(app.GridLayout35);
            app.l_n_mode.Items = {'disp', 'norm', 'ljp'};
            app.l_n_mode.Editable = 'on';
            app.l_n_mode.Enable = 'off';
            app.l_n_mode.Value = 'disp';
            app.nn_maxLabel = uilabel(app.GridLayout35);
            app.nn_maxLabel.Tooltip = {'default: 5'};
            app.nn_maxLabel.Text = 'nn_max';
            app.EditFieldLabel_2 = uilabel(app.GridLayout35);
            app.EditFieldLabel_2.Tooltip = {'default: 5'};
            app.EditFieldLabel_2.Text = 'Maximum number of neighboring keypoints to be connected by springs for calculating λN.';
            app.nn_max = uieditfield(app.GridLayout35, 'numeric');
            app.nn_max.ValueDisplayFormat = '%.2f';
            app.nn_max.Enable = 'off';
            app.nn_max.Tooltip = {'default: 5'};
            app.GradientLearningRateTab = uitab(app.AdvSetTab);
            app.GradientLearningRateTab.Title = 'Gradient & Learning Rate';
            app.GridLayout32 = uigridlayout(app.GradientLearningRateTab);
            app.lr_ceilingLabel = uilabel(app.GridLayout32);
            app.lr_ceilingLabel.Text = 'lr_ceiling';
            app.EditFieldLabel_3 = uilabel(app.GridLayout32);
            app.EditFieldLabel_3.Text = 'Maximum value for initial learning rate. Note that learning rate decays by a factor of 0.5 every 10 epochs.';
            app.lr_ceiling = uieditfield(app.GridLayout32, 'numeric');
            app.lr_ceiling.Enable = 'off';
            app.lr_ceiling.Value = 0.2;
            app.LRFloorLabel = uilabel(app.GridLayout32);
            app.LRFloorLabel.Text = 'LR Floor';
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel = uilabel(app.GridLayout32);
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel.Text = 'Minimum value for initial learning rate. default: 0.02';
            app.lr_floor = uieditfield(app.GridLayout32, 'numeric');
            app.lr_floor.ValueDisplayFormat = '%.2f';
            app.lr_floor.Enable = 'off';
            app.lr_floor.Value = 0.02;
            app.LRCoeffLabel = uilabel(app.GridLayout32);
            app.LRCoeffLabel.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.LRCoeffLabel.Text = 'LR Coeff';
            app.EditField6Label = uilabel(app.GridLayout32);
            app.EditField6Label.Text = 'Coefficient for initial learning rate, multiplied by the distance between current frame and its parent.';
            app.lr_coeff = uieditfield(app.GridLayout32, 'numeric');
            app.lr_coeff.ValueDisplayFormat = '%.2f';
            app.lr_coeff.Enable = 'off';
            app.lr_coeff.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.lr_coeff.Value = 2;
            app.clip_gradLabel = uilabel(app.GridLayout32);
            app.clip_gradLabel.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.clip_gradLabel.Text = 'clip_grad';
            app.EditFieldLabel = uilabel(app.GridLayout32);
            app.EditFieldLabel.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.EditFieldLabel.Text = 'Maximum value for gradients for gradient descent. Use -1 to uncap. default: 1.0';
            app.clip_grad = uieditfield(app.GridLayout32, 'numeric');
            app.clip_grad.ValueDisplayFormat = '%.2f';
            app.clip_grad.Enable = 'off';
            app.clip_grad.Value = -1;
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel = uilabel(app.GridLayout32);
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Text = 'Multiply gradients in the z-axis by (1 + z_compensator).';
            app.z_comp = uieditfield(app.GridLayout32, 'numeric');
            app.z_comp.ValueDisplayFormat = '%.2f';
            app.z_comp.Enable = 'off';
            app.z_comp.Value = -1;
            app.ZCompLabel = uilabel(app.GridLayout32);
            app.ZCompLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.ZCompLabel.Text = 'Z Comp.';
            app.ImageMotionTab = uitab(app.AdvSetTab);
            app.ImageMotionTab.Title = 'Image & Motion';
            app.GridLayout34 = uigridlayout(app.ImageMotionTab);
            app.foveasigmaLabel = uilabel(app.GridLayout34);
            app.foveasigmaLabel.Text = 'fovea sigma';
            app.EditFieldLabel_4 = uilabel(app.GridLayout34);
            app.EditFieldLabel_4.Text = 'Sigma for Gaussian mask over foveated regions of the descriptors.';
            app.fovea_sigma = uieditfield(app.GridLayout34, 'numeric');
            app.fovea_sigma.Enable = 'off';
            app.fovea_sigma.Tooltip = {'Decreasing this can help prioritize keeping a keypoint at the center. Increase to a large number or set at -1 to disable.'};
            app.fovea_sigma.Value = 2.5;
            app.grid_shapeLabel = uilabel(app.GridLayout34);
            app.grid_shapeLabel.Text = 'grid_shape';
            app.SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel = uilabel(app.GridLayout34);
            app.SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel.Text = 'Size of the image descriptors in the xy-plane in pixels.';
            app.grid_shape = uieditfield(app.GridLayout34, 'numeric');
            app.grid_shape.Enable = 'off';
            app.grid_shape.Value = 25;
            app.motion_predictLabel = uilabel(app.GridLayout34);
            app.motion_predictLabel.Text = 'motion_predict';
            app.DropDownLabel = uilabel(app.GridLayout34);
            app.DropDownLabel.Text = 'Enable parent-child flow field to predict low-freq. motion & initialize new keypoints pos. for current frame.';
            app.motion_predict = uidropdown(app.GridLayout34);
            app.motion_predict.Items = {'True', 'False'};
            app.motion_predict.Enable = 'off';
            app.motion_predict.Value = 'False';
            app.dimmer_ratioLabel = uilabel(app.GridLayout34);
            app.dimmer_ratioLabel.Text = 'dimmer_ratio';
            app.CoefficientfordimmingnonfoveatedregionsEditFieldLabel = uilabel(app.GridLayout34);
            app.CoefficientfordimmingnonfoveatedregionsEditFieldLabel.Text = 'Coefficient for dimming non-foveated regions.';
            app.dimmer_ratio = uieditfield(app.GridLayout34, 'numeric');
            app.dimmer_ratio.Enable = 'off';
            app.MiscellaneousTab = uitab(app.AdvSetTab);
            app.MiscellaneousTab.Title = 'Miscellaneous';
            app.GridLayout37 = uigridlayout(app.MiscellaneousTab);
            app.CreditTab = uitab(app.TabGroup);
            app.CreditTab.Title = 'Credit';
            app.GridLayout14 = uigridlayout(app.CreditTab);
            app.Label = uilabel(app.GridLayout14);
            app.Label.Text = 'Tracing relies on a modified version of Zephir, a multiple object tracking algorithm based on image registration and developed by James Yu et al. ZephIR tracks keypoints in a 2D or 3D movie by registering image descriptors sampled around each keypoint. ';
            app.LaboratoryPageButton = uibutton(app.GridLayout14, 'push');
            app.LaboratoryPageButton.ButtonPushedFcn = createCallbackFcn(app, @LaboratoryPageButtonPushed, true);
            app.LaboratoryPageButton.Text = 'Laboratory Page';
            app.ViewPreprintButton = uibutton(app.GridLayout14, 'push');
            app.ViewPreprintButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPreprintButtonPushed, true);
            app.ViewPreprintButton.Text = 'View Preprint';
            app.GithubRepositoryButton = uibutton(app.GridLayout14, 'push');
            app.GithubRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @GithubRepositoryButtonPushed, true);
            app.GithubRepositoryButton.Text = 'Github Repository';
            app.Image2 = uiimage(app.GridLayout14);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'External_Dependencies', 'zephir', 'zephir-logo.png');
            app.CitationTextAreaLabel = uilabel(app.GridLayout14);
            app.CitationTextAreaLabel.Text = 'Citation';
            app.CitationTextArea = uitextarea(app.GridLayout14);
            app.CitationTextArea.Value = {'Versatile Multiple Object Tracking in Sparse 2D/3D Videos Via Diffeomorphic Image Registration'; 'James Yu, Amin Nejatbakhsh, Mahdi Torkashvand, Sahana Gangadharan, Maedeh Seyedolmohadesin, Jinmahn Kim, Liam Paninski, Vivek Venkatachalam bioRxiv 2022.07.18.500485; doi: https://doi.org/10.1101/2022.07.18.500485'; 'This article is a preprint and has not been certified by peer review'};
            app.VolumeViewerPanel = uipanel(app.MainTraceGrid);
            app.VolumeViewerPanel.Title = 'Volume Viewer';
            app.VolumeViewerGrid = uigridlayout(app.VolumeViewerPanel);
            app.Npal_Axes = uiaxes(app.VolumeViewerGrid);
            app.Npal_Axes.XTickLabel = '';
            app.Npal_Axes.YTick = [0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Npal_Axes.YTickLabel = '';
            app.Npal_Axes.Tag = 'volume-viewer';
            app.VolumeViewerControlPanel = uipanel(app.VolumeViewerGrid);
            app.VolumeViewerControlGrid = uigridlayout(app.VolumeViewerControlPanel);
            app.DisplayNeuronsPanel = uipanel(app.VolumeViewerControlGrid);
            app.DisplayNeuronsGrid = uigridlayout(app.DisplayNeuronsPanel);
            app.DisplayNeuronsCheckBox = uicheckbox(app.DisplayNeuronsGrid);
            app.DisplayNeuronsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayNeuronsCheckBoxValueChanged, true);
            app.DisplayNeuronsCheckBox.Enable = 'off';
            app.DisplayNeuronsCheckBox.Text = 'Display Neurons';
            app.FlipvertCheckBox = uicheckbox(app.DisplayNeuronsGrid);
            app.FlipvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipvertCheckBoxValueChanged, true);
            app.FlipvertCheckBox.Enable = 'off';
            app.FlipvertCheckBox.Text = 'Flip vert.';
            app.Rotate90CheckBox = uicheckbox(app.DisplayNeuronsGrid);
            app.Rotate90CheckBox.ValueChangedFcn = createCallbackFcn(app, @Rotate90CheckBoxValueChanged, true);
            app.Rotate90CheckBox.Enable = 'off';
            app.Rotate90CheckBox.Text = 'Rotate 90';
            app.FliphorCheckBox = uicheckbox(app.DisplayNeuronsGrid);
            app.FliphorCheckBox.ValueChangedFcn = createCallbackFcn(app, @FliphorCheckBoxValueChanged, true);
            app.FliphorCheckBox.Enable = 'off';
            app.FliphorCheckBox.Text = 'Flip hor.';
            app.GCaMPVideoLabel = uilabel(app.VolumeViewerControlGrid);
            app.GCaMPVideoLabel.Text = 'GCaMP Video';
            app.NeuroPALImageLabel = uilabel(app.VolumeViewerControlGrid);
            app.NeuroPALImageLabel.Text = 'NeuroPAL Image';
            app.AlphaSlider = uislider(app.VolumeViewerControlGrid);
            app.AlphaSlider.MajorTicks = [];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.AlphaSlider.MinorTicks = [];
            app.AlphaSlider.Enable = 'off';
            app.SizeWarningLabel = uilabel(app.VolumeViewerGrid);
            app.SizeWarningLabel.Visible = 'off';
            app.SizeWarningLabel.Text = 'Warning: NeuroPAL volume and GCaMP video sizes do not match, resized for visualization purposes.';
            app.ClicktoselectvideofileButton = uibutton(app.MainTraceGrid, 'push');
            app.ClicktoselectvideofileButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoselectvideofileButtonPushed, true);
            app.ClicktoselectvideofileButton.Text = 'Click to select video file.';
            app.AdjustNeuronMarkerAlignmentPanel = uipanel(app.NeuroPAL_IDTraceWindowUIFigure);
            app.AdjustNeuronMarkerAlignmentPanel.Title = 'Adjust Neuron Marker Alignment';
            app.AdjustNeuronMarkerAlignmentPanel.Visible = 'off';
            app.NeuronMarkerGrid = uigridlayout(app.AdjustNeuronMarkerAlignmentPanel);
            app.TransformNeuronMarkersPanel = uipanel(app.NeuronMarkerGrid);
            app.TransformNeuronMarkersPanel.Title = 'Transform Neuron Markers';
            app.GridLayout17 = uigridlayout(app.TransformNeuronMarkersPanel);
            app.HeightSpinnerLabel = uilabel(app.GridLayout17);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout17);
            app.WidthSpinnerLabel = uilabel(app.GridLayout17);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.GridLayout17);
            app.ScaleEditFieldLabel = uilabel(app.GridLayout17);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.GridLayout17, 'numeric');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ResetMarkerPositionsButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.ResetMarkerPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMarkerPositionsButtonPushed, true);
            app.ResetMarkerPositionsButton.Text = 'Reset Marker Positions';
            app.SaveResultButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = 'Save Result';
            app.MoveNeuronMarkersPanel = uipanel(app.NeuronMarkerGrid);
            app.MoveNeuronMarkersPanel.Title = 'Move Neuron Markers';
            app.GridLayout16 = uigridlayout(app.MoveNeuronMarkersPanel);
            app.FlipVerticallyButton = uibutton(app.GridLayout16, 'push');
            app.FlipVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticallyButtonPushed, true);
            app.FlipVerticallyButton.Text = 'Flip Vertically';
            app.CenterVerticallyButton = uibutton(app.GridLayout16, 'push');
            app.CenterVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @CenterVerticallyButtonPushed, true);
            app.CenterVerticallyButton.Text = 'Center Vertically';
            app.CenterHorizontallyButton = uibutton(app.GridLayout16, 'push');
            app.CenterHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @CenterHorizontallyButtonPushed, true);
            app.CenterHorizontallyButton.Text = 'Center Horizontally';
            app.Panel_13 = uipanel(app.GridLayout16);
            app.GridLayout18 = uigridlayout(app.Panel_13);
            app.pixelsatatimeLabel = uilabel(app.GridLayout18);
            app.pixelsatatimeLabel.Text = 'pixels at a time.';
            app.MoveEditField_2 = uieditfield(app.GridLayout18, 'numeric');
            app.MoveEditField_2.ValueDisplayFormat = '%.0f';
            app.MoveEditField_2.Value = 5;
            app.MoveLabel = uilabel(app.GridLayout18);
            app.MoveLabel.Text = 'Move ';
            app.Panel_15 = uipanel(app.GridLayout16);
            app.GridLayout27 = uigridlayout(app.Panel_15);
            app.CW = uibutton(app.GridLayout27, 'push');
            app.CW.Text = '↻';
            app.CCW = uibutton(app.GridLayout27, 'push');
            app.CCW.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCW.Text = '↺';
            app.Down = uibutton(app.GridLayout27, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.Down.Text = '⬇️';
            app.Right = uibutton(app.GridLayout27, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Text = '➡️';
            app.Up = uibutton(app.GridLayout27, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpPushed, true);
            app.Up.Text = '⬆️';
            app.Left = uibutton(app.GridLayout27, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Text = '⬅️';
            app.FlipHorizontallyButton = uibutton(app.GridLayout16, 'push');
            app.FlipHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontallyButtonPushed, true);
            app.FlipHorizontallyButton.Text = 'Flip Horizontally';
            app.ImportfromCSVButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.ImportfromCSVButton.Text = 'Import from CSV';
            app.CancelAlignmentButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.CancelAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @CancelAlignmentButtonPushed, true);
            app.CancelAlignmentButton.Text = 'Cancel Alignment';
            % Show the figure after all components are created
            app.NeuroPAL_IDTraceWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace_window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NeuroPAL_IDTraceWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.NeuroPAL_IDTraceWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroPAL_IDTraceWindowUIFigure)
        end
    end
end