classdef visualize_light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CELL_ID                         matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        PreprocessingMenu               matlab.ui.container.Menu
        ArtifactRemovalMenu             matlab.ui.container.Menu
        ManualMenu                      matlab.ui.container.Menu
        AutomaticMenu                   matlab.ui.container.Menu
        DownsampleImageMenu             matlab.ui.container.Menu
        ThresholdImageMenu              matlab.ui.container.Menu
        HistogramMatchingMenu           matlab.ui.container.Menu
        AutoHistogramMenu               matlab.ui.container.Menu
        ImageMenu                       matlab.ui.container.Menu
        NormalizeColorsMenu             matlab.ui.container.Menu
        ColorRegisterMenu               matlab.ui.container.Menu
        AdjustGammaMenu                 matlab.ui.container.Menu
        AllChannels                     matlab.ui.container.Menu
        IndividualChannels              matlab.ui.container.Menu
        AdjustBrightnessMenu            matlab.ui.container.Menu
        AdjustHistogramMenu             matlab.ui.container.Menu
        RotateAllMenu                   matlab.ui.container.Menu
        RotateAllHorizontalMenu         matlab.ui.container.Menu
        RotateAllVerticalMenu           matlab.ui.container.Menu
        RotateAllClockwiseMenu          matlab.ui.container.Menu
        RotateAllAntiClockwiseMenu      matlab.ui.container.Menu
        RotateImageMenu                 matlab.ui.container.Menu
        RotateImageHorizontalMenu       matlab.ui.container.Menu
        RotateImageVerticalMenu         matlab.ui.container.Menu
        RotateImageClockwiseMenu        matlab.ui.container.Menu
        RotateImageAntiClockwiseMenu    matlab.ui.container.Menu
        RotateNeuronsMenu               matlab.ui.container.Menu
        RotateNeuronsHorizontalMenu     matlab.ui.container.Menu
        RotateNeuronsVerticalMenu       matlab.ui.container.Menu
        RotateNeuronsClockwiseMenu      matlab.ui.container.Menu
        RotateNeuronsAntiClockwiseMenu  matlab.ui.container.Menu
        MirrorImageMenu                 matlab.ui.container.Menu
        MirrorNeuronsMenu               matlab.ui.container.Menu
        NeuronsMenu                     matlab.ui.container.Menu
        ImportneuronsfromMenu           matlab.ui.container.Menu
        CSVfileMenu                     matlab.ui.container.Menu
        NWBfileMenu                     matlab.ui.container.Menu
        ImportactivityfromMenu          matlab.ui.container.Menu
        NWBfileMenu_2                   matlab.ui.container.Menu
        ZephIRoutputMenu                matlab.ui.container.Menu
        FlipLRNeuronsMenu               matlab.ui.container.Menu
        AutoSegmentationMenu            matlab.ui.container.Menu
        NeuronActivityMenu              matlab.ui.container.Menu
        TraceActivityMenu_2             matlab.ui.container.Menu
        DisplayNeuronActivityMenu       matlab.ui.container.Menu
        BatchDetectIDNeuronsMenu        matlab.ui.container.Menu
        DeleteUserIDsMenu               matlab.ui.container.Menu
        DeleteModelIDsMenu              matlab.ui.container.Menu
        ToggleAutoIDUpdatesMenu         matlab.ui.container.Menu
        ToggleNeuronDetectionMenu       matlab.ui.container.Menu
        DisplayMenu                     matlab.ui.container.Menu
        ToggleBirthTimesMenu            matlab.ui.container.Menu
        NeuronDotsMenu                  matlab.ui.container.Menu
        GFPColorMenu                    matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        SaveIDInfoMenu                  matlab.ui.container.Menu
        SaveIDImageMenu                 matlab.ui.container.Menu
        SaveNWBMenu                     matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        CheckforUpdatesMenu             matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        InstructionsMenu                matlab.ui.container.Menu
        KeyboardShortcutsMenu           matlab.ui.container.Menu
        NeuroPALWebsiteMenu             matlab.ui.container.Menu
        ReportaBugMenu                  matlab.ui.container.Menu
        DebugMenu                       matlab.ui.container.Menu
        LogtesteventMenu                matlab.ui.container.Menu
        LogfadeeventMenu                matlab.ui.container.Menu
        PlaceholderActivitytabLabel     matlab.ui.control.Label
        PlaceholderTrackingtabsLabel    matlab.ui.control.Label
        PlaceholderButtonsCovertabsifnodataloadedLabel  matlab.ui.control.Label
        ProcessingButton                matlab.ui.control.Button
        IdButton                        matlab.ui.control.Button
        TrackingButton                  matlab.ui.control.Button
        GridSearchPanel                 matlab.ui.container.Panel
        GridLayout54                    matlab.ui.container.GridLayout
        CancelGridSearchButton          matlab.ui.control.Button
        StartGridSearchButton           matlab.ui.control.Button
        Panel_47                        matlab.ui.container.Panel
        GridLayout57                    matlab.ui.container.GridLayout
        minutesLabel                    matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        EstimatedSearchtimeLabel        matlab.ui.control.Label
        SearchrangesPanel               matlab.ui.container.Panel
        GridLayout56                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        VideoPreprocessingPanel         matlab.ui.container.Panel
        GridLayout55                    matlab.ui.container.GridLayout
        ChannelstoincludeLabel          matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        OnlyappliestogridsearchLabel    matlab.ui.control.Label
        DownsamplingFactortEditField    matlab.ui.control.NumericEditField
        DownsamplingFactortEditFieldLabel  matlab.ui.control.Label
        DownsamplingFactorzEditField    matlab.ui.control.NumericEditField
        DownsamplingFactorzEditFieldLabel  matlab.ui.control.Label
        DownsamplingFactorxyEditField   matlab.ui.control.NumericEditField
        DownsamplingFactorxyEditFieldLabel  matlab.ui.control.Label
        TrackingSelectionPanel_2        matlab.ui.container.Panel
        GridLayout49_2                  matlab.ui.container.GridLayout
        TrackspecificregionButton_2     matlab.ui.control.Button
        WorldlinestoincludeLabel        matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        StartingFrameLabel_2            matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        EndFrameLabel_2                 matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        AdvancedParameterPanel          matlab.ui.container.Panel
        NeuronMarkerGrid_2              matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        RegularizationLossCoefficientsTab  matlab.ui.container.Tab
        GridLayout35                    matlab.ui.container.GridLayout
        DescriptionLabel                matlab.ui.control.Label
        ParameterLabel                  matlab.ui.control.Label
        GridSearchLabel                 matlab.ui.control.Label
        nn_max_grid                     matlab.ui.control.CheckBox
        l_n_mode_grid                   matlab.ui.control.CheckBox
        l_n_grid                        matlab.ui.control.CheckBox
        l_t_grid                        matlab.ui.control.CheckBox
        l_d_grid                        matlab.ui.control.CheckBox
        nn_max                          matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        nn_maxLabel                     matlab.ui.control.Label
        l_n_mode                        matlab.ui.control.DropDown
        MethodtouseforcalculatingNLabel  matlab.ui.control.Label
        NmodeLabel                      matlab.ui.control.Label
        l_n                             matlab.ui.control.NumericEditField
        EditField4Label                 matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        l_t                             matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        tLabel                          matlab.ui.control.Label
        l_d                             matlab.ui.control.NumericEditField
        EditField3Label                 matlab.ui.control.Label
        dLabel                          matlab.ui.control.Label
        GradientLearningRateTab         matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        DescriptionLabel_2              matlab.ui.control.Label
        ParameterLabel_2                matlab.ui.control.Label
        GridSearchLabel_2               matlab.ui.control.Label
        z_comp_grid                     matlab.ui.control.CheckBox
        lr_ceil_grid                    matlab.ui.control.CheckBox
        lr_floor_grid                   matlab.ui.control.CheckBox
        lr_coeff_grid                   matlab.ui.control.CheckBox
        clip_grad_grid                  matlab.ui.control.CheckBox
        ZCompLabel                      matlab.ui.control.Label
        z_comp                          matlab.ui.control.NumericEditField
        Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel  matlab.ui.control.Label
        clip_grad                       matlab.ui.control.NumericEditField
        EditFieldLabel_4                matlab.ui.control.Label
        clip_gradLabel                  matlab.ui.control.Label
        lr_coeff                        matlab.ui.control.NumericEditField
        EditField6Label                 matlab.ui.control.Label
        LRCoeffLabel                    matlab.ui.control.Label
        lr_floor                        matlab.ui.control.NumericEditField
        Minimumvalueforinitiallearningratedefault002EditFieldLabel  matlab.ui.control.Label
        LRFloorLabel                    matlab.ui.control.Label
        lr_ceiling                      matlab.ui.control.NumericEditField
        EditFieldLabel_3                matlab.ui.control.Label
        lr_ceilingLabel                 matlab.ui.control.Label
        RetrievalFunctionTab            matlab.ui.container.Tab
        GridLayout50                    matlab.ui.container.GridLayout
        TextArea_6                      matlab.ui.control.TextArea
        OpenZephIRdocumentationButton   matlab.ui.control.Button
        CancelButton_2                  matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        OpenGridSearchMenuButton        matlab.ui.control.Button
        AdjustNeuronMarkerAlignmentPanel  matlab.ui.container.Panel
        NeuronMarkerGrid                matlab.ui.container.GridLayout
        Panel_44                        matlab.ui.container.Panel
        GridLayout48_2                  matlab.ui.container.GridLayout
        EnableinvolumeeditingCheckBox   matlab.ui.control.CheckBox
        EnableClickDragCheckBox         matlab.ui.control.CheckBox
        ShowallneuronsCheckBox_2        matlab.ui.control.CheckBox
        OverlayFrameMIPCheckBox_2       matlab.ui.control.CheckBox
        OverlayNeuroPALvolCheckBox_2    matlab.ui.control.CheckBox
        OverlaylastIDdframeCheckBox_2   matlab.ui.control.CheckBox
        AutojumponIDCheckBox_2          matlab.ui.control.CheckBox
        ResetMarkerPositionsButton      matlab.ui.control.Button
        Panel_43                        matlab.ui.container.Panel
        GridLayout53_2                  matlab.ui.container.GridLayout
        MarkerSizeSlider_2              matlab.ui.control.Slider
        MarkerSizeLabel_2               matlab.ui.control.Label
        SelectNeuronSubsetButton        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        ImportNeuronsfromCSVButton      matlab.ui.control.Button
        MoveNeuronMarkersPanel          matlab.ui.container.Panel
        GridLayout16_3                  matlab.ui.container.GridLayout
        Panel_46                        matlab.ui.container.Panel
        GridLayout18_2                  matlab.ui.container.GridLayout
        degreesatatimeLabel             matlab.ui.control.Label
        RotateEditField                 matlab.ui.control.NumericEditField
        pixelsandLabel                  matlab.ui.control.Label
        MoveLabel                       matlab.ui.control.Label
        MoveEditField                   matlab.ui.control.NumericEditField
        FlipHorizontallyButton          matlab.ui.control.Button
        Panel_45                        matlab.ui.container.Panel
        GridLayout27_2                  matlab.ui.container.GridLayout
        Left                            matlab.ui.control.Button
        Up                              matlab.ui.control.Button
        Right                           matlab.ui.control.Button
        Down                            matlab.ui.control.Button
        CCW                             matlab.ui.control.Button
        CW                              matlab.ui.control.Button
        CenterHorizontallyButton        matlab.ui.control.Button
        CenterVerticallyButton          matlab.ui.control.Button
        FlipVerticallyButton            matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        DeformNeuronMarkersPanel        matlab.ui.container.Panel
        GridLayout17_2                  matlab.ui.container.GridLayout
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        WidthSpinner                    matlab.ui.control.Spinner
        WidthSpinnerLabel               matlab.ui.control.Label
        HeightSpinner                   matlab.ui.control.Spinner
        HeightSpinnerLabel              matlab.ui.control.Label
        HoverLabel                      matlab.ui.control.Label
        GridLayout59                    matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        NeuroPALIDTab                   matlab.ui.container.Tab
        IdGridLayout                    matlab.ui.container.GridLayout
        Panel_9                         matlab.ui.container.Panel
        IdMetadataGridLayout            matlab.ui.container.GridLayout
        Panel_26                        matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        NotesEditField                  matlab.ui.control.EditField
        NotesEditFieldLabel             matlab.ui.control.Label
        StrainEditField                 matlab.ui.control.EditField
        StrainEditFieldLabel            matlab.ui.control.Label
        AgeDropDown                     matlab.ui.control.DropDown
        AgeDropDownLabel                matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexLabel                        matlab.ui.control.Label
        BodyDropDown                    matlab.ui.control.DropDown
        BodyLabel                       matlab.ui.control.Label
        SaveLabel                       matlab.ui.control.Label
        SaveIDsButton                   matlab.ui.control.Button
        FileLabel                       matlab.ui.control.Label
        OpenButton                      matlab.ui.control.Button
        Panel_30                        matlab.ui.container.Panel
        GridLayout24_2                  matlab.ui.container.GridLayout
        ColorAtlasCheckBox              matlab.ui.control.CheckBox
        ColorAtlasCheckBoxLabel         matlab.ui.control.Label
        AutoNameCheckBox                matlab.ui.control.CheckBox
        AutoNameLabel                   matlab.ui.control.Label
        MouseClickDropDown              matlab.ui.control.DropDown
        ClicksLabel                     matlab.ui.control.Label
        UserIDButton                    matlab.ui.control.Button
        IDEditField                     matlab.ui.control.EditField
        UserLabel                       matlab.ui.control.Label
        AutoIDButton                    matlab.ui.control.Button
        AutoLabel                       matlab.ui.control.Label
        AutoIDAllButton                 matlab.ui.control.Button
        AutoDetectButton                matlab.ui.control.Button
        NeuronsLabel                    matlab.ui.control.Label
        AutoIDDropDown                  matlab.ui.control.DropDown
        XYPanel                         matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        XY                              matlab.ui.control.UIAxes
        Panel_8                         matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        Panel_16                        matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        ZSlider                         matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        ZCenterEditField                matlab.ui.control.NumericEditField
        CenterZLabel                    matlab.ui.control.Label
        ZRightLabel                     matlab.ui.control.Label
        FlipZButton                     matlab.ui.control.Button
        Panel_10                        matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        ZAxisDropDown                   matlab.ui.control.DropDown
        ZLeftLabel                      matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        TabGroup4                       matlab.ui.container.TabGroup
        MaximumIntensityProjectionTab   matlab.ui.container.Tab
        GridLayout76                    matlab.ui.container.GridLayout
        MaxProjection                   matlab.ui.control.UIAxes
        NeuronActivityTab               matlab.ui.container.Tab
        GridLayout75                    matlab.ui.container.GridLayout
        LoadNeuronActivityButton        matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        GanglionNeuronIDLabel           matlab.ui.control.Label
        GanglionTree                    matlab.ui.container.Tree
        PharynxNode                     matlab.ui.container.TreeNode
        AnteriorNode                    matlab.ui.container.TreeNode
        PosteriorNode                   matlab.ui.container.TreeNode
        HeadNode                        matlab.ui.container.TreeNode
        AnteriorLNode                   matlab.ui.container.TreeNode
        AnteriorRNode                   matlab.ui.container.TreeNode
        DorsalNode                      matlab.ui.container.TreeNode
        LateralLNode                    matlab.ui.container.TreeNode
        LateralRNode                    matlab.ui.container.TreeNode
        VentralNode                     matlab.ui.container.TreeNode
        RetroVesicularNode              matlab.ui.container.TreeNode
        MidbodyNode                     matlab.ui.container.TreeNode
        AnteriorNode_2                  matlab.ui.container.TreeNode
        CentralNode                     matlab.ui.container.TreeNode
        PosteriorNode_2                 matlab.ui.container.TreeNode
        VentralCordNode                 matlab.ui.container.TreeNode
        TailNode                        matlab.ui.container.TreeNode
        PreAnalNode                     matlab.ui.container.TreeNode
        DorsoRectalNode                 matlab.ui.container.TreeNode
        LumbarLNode                     matlab.ui.container.TreeNode
        LumbarRNode                     matlab.ui.container.TreeNode
        SidePanel                       matlab.ui.container.Panel
        SideGrid                        matlab.ui.container.GridLayout
        ChannelPanel                    matlab.ui.container.Panel
        ChannelGrid                     matlab.ui.container.GridLayout
        Panel_18                        matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        RDropDown                       matlab.ui.control.DropDown
        RCheckBox                       matlab.ui.control.CheckBox
        RLabel                          matlab.ui.control.Label
        Panel_21                        matlab.ui.container.Panel
        GridLayout26_3                  matlab.ui.container.GridLayout
        WLabel                          matlab.ui.control.Label
        WDropDown                       matlab.ui.control.DropDown
        WCheckBox                       matlab.ui.control.CheckBox
        Panel_20                        matlab.ui.container.Panel
        GridLayout26_2                  matlab.ui.container.GridLayout
        BDropDown                       matlab.ui.control.DropDown
        BCheckBox                       matlab.ui.control.CheckBox
        BLabel                          matlab.ui.control.Label
        Panel_24                        matlab.ui.container.Panel
        GridLayout26_6                  matlab.ui.container.GridLayout
        GFPDropDown                     matlab.ui.control.DropDown
        GFPCheckBox                     matlab.ui.control.CheckBox
        GFPLabel                        matlab.ui.control.Label
        Panel_19                        matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        GDropDown                       matlab.ui.control.DropDown
        GCheckBox                       matlab.ui.control.CheckBox
        GLabel                          matlab.ui.control.Label
        Panel_23                        matlab.ui.container.Panel
        GridLayout26_5                  matlab.ui.container.GridLayout
        DICLabel                        matlab.ui.control.Label
        DICCheckBox                     matlab.ui.control.CheckBox
        DICDropDown                     matlab.ui.control.DropDown
        NeuronRankPanel                 matlab.ui.container.Panel
        NeuronRankGrid                  matlab.ui.container.GridLayout
        Panel_17                        matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        NextNeuronDropDown              matlab.ui.control.DropDown
        NextNeuronLabel                 matlab.ui.control.Label
        Panel_25                        matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        NeuronRankedConfidenceLabel     matlab.ui.control.Label
        UserNeuronIDsListBoxLabel       matlab.ui.control.Label
        UserNeuronIDsListBox            matlab.ui.control.ListBox
        NeuronListBoxPanel              matlab.ui.container.Panel
        NeuronListBoxGrid               matlab.ui.container.GridLayout
        UnIDdLabel                      matlab.ui.control.Label
        IDdNeuronsListBox               matlab.ui.control.ListBox
        IDdLabel                        matlab.ui.control.Label
        UnIDdNeuronsListBox             matlab.ui.control.ListBox
        VideoTrackingTab                matlab.ui.container.Tab
        VideoGridLayout                 matlab.ui.container.GridLayout
        DefaultTabGroup                 matlab.ui.container.TabGroup
        AnnotationSupportTab            matlab.ui.container.Tab
        GridLayout7_2                   matlab.ui.container.GridLayout
        ManipulateNeuronsButton         matlab.ui.control.Button
        Panel_40                        matlab.ui.container.Panel
        GridLayout53                    matlab.ui.container.GridLayout
        MarkerSizeSlider                matlab.ui.control.Slider
        MarkerSizeLabel                 matlab.ui.control.Label
        Panel_39                        matlab.ui.container.Panel
        GridLayout48                    matlab.ui.container.GridLayout
        ShowallneuronsCheckBox          matlab.ui.control.CheckBox
        OverlayFrameMIPCheckBox         matlab.ui.control.CheckBox
        OverlayNeuroPALvolCheckBox      matlab.ui.control.CheckBox
        OverlaylastIDdframeCheckBox     matlab.ui.control.CheckBox
        AutojumponIDCheckBox            matlab.ui.control.CheckBox
        AutosegmentFrameButton          matlab.ui.control.Button
        HotkeysPanel                    matlab.ui.container.Panel
        GridLayout47                    matlab.ui.container.GridLayout
        BwdFramesEditField              matlab.ui.control.NumericEditField
        FwdFramesEditField              matlab.ui.control.NumericEditField
        TextArea_5                      matlab.ui.control.TextArea
        InsertHKButton                  matlab.ui.control.StateButton
        DropDown_2                      matlab.ui.control.DropDown
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        JumpHKButton                    matlab.ui.control.StateButton
        DeleteHKButton                  matlab.ui.control.StateButton
        JmpFrameEditField_2             matlab.ui.control.NumericEditField
        Frame2HKButton                  matlab.ui.control.StateButton
        JmpFrameEditField               matlab.ui.control.NumericEditField
        Frame1HKButton                  matlab.ui.control.StateButton
        ColorControlPanel               matlab.ui.container.Panel
        GridLayout16_2                  matlab.ui.container.GridLayout
        BSlider                         matlab.ui.control.Slider
        BSliderLabel                    matlab.ui.control.Label
        GSlider                         matlab.ui.control.Slider
        GSliderLabel                    matlab.ui.control.Label
        RSlider                         matlab.ui.control.Slider
        RSliderLabel                    matlab.ui.control.Label
        BookmarkedFramesPanel           matlab.ui.container.Panel
        GridLayout8_2                   matlab.ui.container.GridLayout
        BookmarkListBox                 matlab.ui.control.ListBox
        DeleteBookmarkButton            matlab.ui.control.Button
        BookmarkNameEditField           matlab.ui.control.EditField
        SetBookmarkButton               matlab.ui.control.Button
        GotoBookmarkButton              matlab.ui.control.Button
        ParametersTab                   matlab.ui.container.Tab
        GridLayout58                    matlab.ui.container.GridLayout
        BasicParameterPanel             matlab.ui.container.Panel
        ParameterGridLayout             matlab.ui.container.GridLayout
        WormMotionPanel                 matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        MotionSlider                    matlab.ui.control.Slider
        HoveroverlabelsforfurtherdetailsorguidanceLabel  matlab.ui.control.Label
        AdvSetTab                       matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GridLayout31                    matlab.ui.container.GridLayout
        TrackingSelectionPanel          matlab.ui.container.Panel
        GridLayout49                    matlab.ui.container.GridLayout
        TrackspecificneuronsButton      matlab.ui.control.Button
        TrackspecificregionButton       matlab.ui.control.Button
        StartingFrameLabel              matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EndFrameLabel                   matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        UseGPUSwitch                    matlab.ui.control.Switch
        UseGPUSwitchLabel               matlab.ui.control.Label
        MotionPredictionSwitch          matlab.ui.control.Switch
        MotionPredictionSwitchLabel     matlab.ui.control.Label
        AllowRotationsSwitch            matlab.ui.control.Switch
        AllowRotationsSwitchLabel       matlab.ui.control.Label
        ImageMotionTab                  matlab.ui.container.Tab
        GridLayout34                    matlab.ui.container.GridLayout
        dimmer_ratio                    matlab.ui.control.NumericEditField
        CoefficientfordimmingnonfoveatedregionsEditFieldLabel  matlab.ui.control.Label
        dimmer_ratioLabel               matlab.ui.control.Label
        grid_shape                      matlab.ui.control.NumericEditField
        SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel  matlab.ui.control.Label
        grid_shapeLabel                 matlab.ui.control.Label
        fovea_sigma                     matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        foveasigmaLabel                 matlab.ui.control.Label
        ComputationTab                  matlab.ui.container.Tab
        GridLayout31_2                  matlab.ui.container.GridLayout
        sort_mode                       matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        sort_modeLabel                  matlab.ui.control.Label
        n_chunks                        matlab.ui.control.NumericEditField
        NumberofstepstodividetheforwardpassintoEditFieldLabel  matlab.ui.control.Label
        n_chunksLabel                   matlab.ui.control.Label
        n_epoch_d                       matlab.ui.control.NumericEditField
        EditField2_2Label               matlab.ui.control.Label
        n_epoch                         matlab.ui.control.NumericEditField
        n_epoch_dLabel                  matlab.ui.control.Label
        NumberofiterationsforimageregistrationLREditFieldLabel  matlab.ui.control.Label
        n_epochLabel                    matlab.ui.control.Label
        ExportparametersButton          matlab.ui.control.Button
        EditadvancedparametersButton    matlab.ui.control.Button
        ImportparametersButton          matlab.ui.control.Button
        EnableadvancedparametersCheckBox  matlab.ui.control.CheckBox
        ActivityTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_42                        matlab.ui.container.Panel
        GridLayout5_2                   matlab.ui.container.GridLayout
        ActivityAxes                    matlab.ui.control.UIAxes
        Panel_41                        matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ActivityListBox                 matlab.ui.control.ListBox
        LoadStimulusFileButton          matlab.ui.control.Button
        SaveExitButton                  matlab.ui.control.Button
        ScanforIssuesButton             matlab.ui.control.Button
        CreditTab                       matlab.ui.container.Tab
        GridLayout14_3                  matlab.ui.container.GridLayout
        CitationTextArea                matlab.ui.control.TextArea
        CitationTextAreaLabel           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        GithubRepositoryButton          matlab.ui.control.Button
        ViewPreprintButton              matlab.ui.control.Button
        LaboratoryPageButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Panel_55                        matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        vert_zSlider                    matlab.ui.control.Slider
        ySlider                         matlab.ui.control.Slider
        hor_zSlider                     matlab.ui.control.Slider
        xSlider                         matlab.ui.control.Slider
        xyAxes                          matlab.ui.control.UIAxes
        xzAxes                          matlab.ui.control.UIAxes
        yzAxes                          matlab.ui.control.UIAxes
        Panel_34                        matlab.ui.container.Panel
        GridLayout40                    matlab.ui.container.GridLayout
        Panel_36                        matlab.ui.container.Panel
        GridLayout42                    matlab.ui.container.GridLayout
        Panel_38                        matlab.ui.container.Panel
        GridLayout44                    matlab.ui.container.GridLayout
        tSlider                         matlab.ui.control.Slider
        Panel_37                        matlab.ui.container.Panel
        GridLayout43                    matlab.ui.container.GridLayout
        tEditField                      matlab.ui.control.NumericEditField
        tEditFieldLabel                 matlab.ui.control.Label
        Panel_35                        matlab.ui.container.Panel
        GridLayout41                    matlab.ui.container.GridLayout
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        WorkflowPanel                   matlab.ui.container.Panel
        GridLayout13_2                  matlab.ui.container.GridLayout
        InsertlastIDdFrameButton        matlab.ui.control.Button
        Panel_33                        matlab.ui.container.Panel
        GridLayout14_2                  matlab.ui.container.GridLayout
        ExtractActivityButton           matlab.ui.control.Button
        TrackNeuronsButton              matlab.ui.control.Button
        RecommendFramesButton           matlab.ui.control.Button
        SaveResultsButton               matlab.ui.control.Button
        InsertNeuroPALNeuronsButton     matlab.ui.control.Button
        WorldlineInformationPanel       matlab.ui.container.Panel
        GridLayout51                    matlab.ui.container.GridLayout
        WorldlineTree                   matlab.ui.container.CheckBoxTree
        IDdNode                         matlab.ui.container.TreeNode
        UnIDdNode                       matlab.ui.container.TreeNode
        Panel_32                        matlab.ui.container.Panel
        GridLayout52                    matlab.ui.container.GridLayout
        AssignrandomcolorstoblanksButton  matlab.ui.control.Button
        NeuronInformationPanel          matlab.ui.container.Panel
        GridLayout9_2                   matlab.ui.container.GridLayout
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ProvenanceEditField             matlab.ui.control.EditField
        ProvenanceEditFieldLabel        matlab.ui.control.Label
        WorldlineIDEditField            matlab.ui.control.NumericEditField
        WorldlineIDEditFieldLabel       matlab.ui.control.Label
        ColorButton                     matlab.ui.control.Button
        ColorLabel                      matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ZCoordinateEditField            matlab.ui.control.NumericEditField
        ZCoordinateEditFieldLabel       matlab.ui.control.Label
        YCoordinateEditField            matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel       matlab.ui.control.Label
        XCoordinateEditField            matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel       matlab.ui.control.Label
        ImageProcessingTab              matlab.ui.container.Tab
        ProcessingGridLayout            matlab.ui.container.GridLayout
        ProcAxPanel                     matlab.ui.container.Panel
        ProcAxGrid                      matlab.ui.container.GridLayout
        proc_vert_zSlider               matlab.ui.control.Slider
        proc_ySlider                    matlab.ui.control.Slider
        proc_hor_zSlider                matlab.ui.control.Slider
        proc_xSlider                    matlab.ui.control.Slider
        proc_xyAxes                     matlab.ui.control.UIAxes
        proc_xzAxes                     matlab.ui.control.UIAxes
        proc_yzAxes                     matlab.ui.control.UIAxes
        ProcSidePanel                   matlab.ui.container.Panel
        ProcSideGrid                    matlab.ui.container.GridLayout
        ImageManipulationPanel          matlab.ui.container.Panel
        GridLayout66                    matlab.ui.container.GridLayout
        Panel_54                        matlab.ui.container.Panel
        GridLayout68                    matlab.ui.container.GridLayout
        CropImageButton                 matlab.ui.control.Button
        RegisterColorsButton            matlab.ui.control.Button
        NormalizeColorsButton           matlab.ui.control.Button
        ToggleVolumePanel               matlab.ui.container.Panel
        GridLayout77                    matlab.ui.container.GridLayout
        ProcVideoButton                 matlab.ui.control.StateButton
        ProcColormapButton              matlab.ui.control.StateButton
        ToggleChannelsPanel             matlab.ui.container.Panel
        ToggleChannelGrid               matlab.ui.container.GridLayout
        Panel_66                        matlab.ui.container.Panel
        GridLayout25_2                  matlab.ui.container.GridLayout
        ProcRDropDown                   matlab.ui.control.DropDown
        ProcRCheckBox                   matlab.ui.control.CheckBox
        ProcRLabel                      matlab.ui.control.Label
        Panel_64                        matlab.ui.container.Panel
        GridLayout26_10                 matlab.ui.container.GridLayout
        ProcBDropDown                   matlab.ui.control.DropDown
        ProcBCheckBox                   matlab.ui.control.CheckBox
        ProcBLabel                      matlab.ui.control.Label
        Panel_62                        matlab.ui.container.Panel
        GridLayout26_8                  matlab.ui.container.GridLayout
        ProcGDropDown                   matlab.ui.control.DropDown
        ProcGCheckBox                   matlab.ui.control.CheckBox
        ProcGLabel                      matlab.ui.control.Label
        Panel_65                        matlab.ui.container.Panel
        GridLayout26_11                 matlab.ui.container.GridLayout
        ProcWLabel                      matlab.ui.control.Label
        ProcWDropDown                   matlab.ui.control.DropDown
        ProcWCheckBox                   matlab.ui.control.CheckBox
        Panel_63                        matlab.ui.container.Panel
        GridLayout26_9                  matlab.ui.container.GridLayout
        ProcGFPDropDown                 matlab.ui.control.DropDown
        ProcGFPCheckBox                 matlab.ui.control.CheckBox
        ProcGFPLabel                    matlab.ui.control.Label
        Panel_61                        matlab.ui.container.Panel
        GridLayout26_7                  matlab.ui.container.GridLayout
        ProcDICLabel                    matlab.ui.control.Label
        ProcDICCheckBox                 matlab.ui.control.CheckBox
        ProcDICDropDown                 matlab.ui.control.DropDown
        DownsamplingPanel               matlab.ui.container.Panel
        GridLayout_2                    matlab.ui.container.GridLayout
        ApplyButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Panel_49                        matlab.ui.container.Panel
        GridLayout62                    matlab.ui.container.GridLayout
        MaintainaspectratioCheckBox     matlab.ui.control.CheckBox
        zSlicesEditField                matlab.ui.control.NumericEditField
        zSlicesEditFieldLabel           matlab.ui.control.Label
        xFactorEditField                matlab.ui.control.NumericEditField
        xFactorEditFieldLabel           matlab.ui.control.Label
        yFactorEditField                matlab.ui.control.NumericEditField
        yFactorEditFieldLabel           matlab.ui.control.Label
        Panel_50                        matlab.ui.container.Panel
        GridLayout63                    matlab.ui.container.GridLayout
        Panel_57                        matlab.ui.container.Panel
        GridLayout64                    matlab.ui.container.GridLayout
        Panel_59                        matlab.ui.container.Panel
        GridLayout78                    matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        ChannelHistogramsTab            matlab.ui.container.Tab
        HistogramGrid                   matlab.ui.container.GridLayout
        f_hist_ax                       matlab.ui.control.UIAxes
        e_hist_ax                       matlab.ui.control.UIAxes
        d_hist_ax                       matlab.ui.control.UIAxes
        c_hist_ax                       matlab.ui.control.UIAxes
        b_hist_ax                       matlab.ui.control.UIAxes
        a_hist_ax                       matlab.ui.control.UIAxes
        ChannelGammasTab                matlab.ui.container.Tab
        GridLayout71                    matlab.ui.container.GridLayout
        Panel_71                        matlab.ui.container.Panel
        GridLayout72_6                  matlab.ui.container.GridLayout
        GFPEditField                    matlab.ui.control.NumericEditField
        GFPEditFieldLabel               matlab.ui.control.Label
        Panel_70                        matlab.ui.container.Panel
        GridLayout72_5                  matlab.ui.container.GridLayout
        DICEditField                    matlab.ui.control.NumericEditField
        DICEditFieldLabel               matlab.ui.control.Label
        Panel_69                        matlab.ui.container.Panel
        GridLayout72_4                  matlab.ui.container.GridLayout
        WhiteEditField                  matlab.ui.control.NumericEditField
        WhiteEditFieldLabel             matlab.ui.control.Label
        Panel_68                        matlab.ui.container.Panel
        GridLayout72_3                  matlab.ui.container.GridLayout
        BlueEditField                   matlab.ui.control.NumericEditField
        BlueEditFieldLabel              matlab.ui.control.Label
        Panel_67                        matlab.ui.container.Panel
        GridLayout72_2                  matlab.ui.container.GridLayout
        GreenEditField                  matlab.ui.control.NumericEditField
        GreenLabel                      matlab.ui.control.Label
        Panel_60                        matlab.ui.container.Panel
        GridLayout72                    matlab.ui.container.GridLayout
        RedEditField                    matlab.ui.control.NumericEditField
        RedEditFieldLabel               matlab.ui.control.Label
        RedGamma                        matlab.ui.control.UIAxes
        RedGamma_2                      matlab.ui.control.UIAxes
        RedGamma_3                      matlab.ui.control.UIAxes
        RedGamma_4                      matlab.ui.control.UIAxes
        RedGamma_5                      matlab.ui.control.UIAxes
        RedGamma_6                      matlab.ui.control.UIAxes
        Panel_56                        matlab.ui.container.Panel
        GridLayout69                    matlab.ui.container.GridLayout
        Panel_73                        matlab.ui.container.Panel
        GridLayout74                    matlab.ui.container.GridLayout
        SingleHistogramCheckBox         matlab.ui.control.CheckBox
        AutoHistogramButton             matlab.ui.control.Button
        HistogramMatchingButton         matlab.ui.control.Button
        AdjustHistogramsButton          matlab.ui.control.Button
        Panel_72                        matlab.ui.container.Panel
        GridLayout73                    matlab.ui.container.GridLayout
        NoisethresholdKnob              matlab.ui.control.Knob
        NoisethresholdKnobLabel         matlab.ui.control.Label
        Panel_52                        matlab.ui.container.Panel
        GridLayout65                    matlab.ui.container.GridLayout
        ResetButton_2                   matlab.ui.control.Button
        SaveButton_3                    matlab.ui.control.Button
        LogTab                          matlab.ui.container.Tab
        LogGrid                         matlab.ui.container.GridLayout
        Log                             matlab.ui.control.TextArea
        UpperLogPanel                   matlab.ui.container.Panel
        UpperLogGrid                    matlab.ui.container.GridLayout
        LogControlPanel                 matlab.ui.container.GridLayout
        LoadedFilesPanel_2              matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        FileListTree                    matlab.ui.container.CheckBoxTree
        DataNode                        matlab.ui.container.TreeNode
        DataMetadataNode                matlab.ui.container.TreeNode
        VolumeNode                      matlab.ui.container.TreeNode
        NeuronsNode                     matlab.ui.container.TreeNode
        ProcessingNode                  matlab.ui.container.TreeNode
        DownsamplingNode                matlab.ui.container.TreeNode
        SegmentationNode                matlab.ui.container.TreeNode
        ModelNode                       matlab.ui.container.TreeNode
        NeuronInfoNode                  matlab.ui.container.TreeNode
        ActivityNode                    matlab.ui.container.TreeNode
        ZephIRNode                      matlab.ui.container.TreeNode
        VideoNode                       matlab.ui.container.TreeNode
        AnnotationsNode                 matlab.ui.container.TreeNode
        ZephIRMetaDataNode              matlab.ui.container.TreeNode
        ActivityCSVNode                 matlab.ui.container.TreeNode
        OutputNode                      matlab.ui.container.TreeNode
        NeuroPALArrayNode               matlab.ui.container.TreeNode
        NeuroPALNeuronIDsNode           matlab.ui.container.TreeNode
        Panel_2                         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        EnabledebugmenuCheckBox         matlab.ui.control.CheckBox
        EnablelognotificationsCheckBox  matlab.ui.control.CheckBox
        EnablelogCheckBox               matlab.ui.control.CheckBox
        OpenlogfileButton               matlab.ui.control.Button
        LogFilterButtonGroup            matlab.ui.container.ButtonGroup
        PythoneventsonlyButton          matlab.ui.control.RadioButton
        MATLABeventsonlyButton          matlab.ui.control.RadioButton
        SystemeventsonlyButton          matlab.ui.control.RadioButton
        ShowalleventsButton             matlab.ui.control.RadioButton
        SubmitbugreportButton           matlab.ui.control.Button
        OpenRepositoryButton            matlab.ui.control.Button
        LogLists                        matlab.ui.container.TabGroup
        SystemInfoTab                   matlab.ui.container.Tab
        SystemInfoGrid                  matlab.ui.container.GridLayout
        DependenciesPanel_2             matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        RequirementsTree                matlab.ui.container.CheckBoxTree
        MATLABNode                      matlab.ui.container.TreeNode
        mlapptoolsNode                  matlab.ui.container.TreeNode
        bioformatsToolboxNode           matlab.ui.container.TreeNode
        ImageProcessingToolboxNode      matlab.ui.container.TreeNode
        StatisticsandMachineLearningToolboxNode  matlab.ui.container.TreeNode
        PythonNode                      matlab.ui.container.TreeNode
        pyNWBNode                       matlab.ui.container.TreeNode
        ZephIRDependencyNode            matlab.ui.container.TreeNode
        DCellTrackerNode                matlab.ui.container.TreeNode
        MiscellaneousNode               matlab.ui.container.TreeNode
        WebWrapNode                     matlab.ui.container.TreeNode
        DCTEditFieldLabel               matlab.ui.control.Label
        CellTrackerEditField            matlab.ui.control.EditField
        ZephIRLabel                     matlab.ui.control.Label
        ZephIREditField                 matlab.ui.control.EditField
        NeuroPALLabel                   matlab.ui.control.Label
        NeuroPALEditField               matlab.ui.control.EditField
        PythonEditFieldLabel            matlab.ui.control.Label
        PythonEditField                 matlab.ui.control.EditField
        MATLABEditFieldLabel            matlab.ui.control.Label
        MATLABEditField                 matlab.ui.control.EditField
        OperatingSystemEditField        matlab.ui.control.EditField
        OperatingSystemEditFieldLabel   matlab.ui.control.Label
        VolTraceParent                  matlab.ui.container.Panel
        VolTraceHelperGrid              matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        DropDown                        matlab.ui.control.DropDown
        LoadStimuliButton               matlab.ui.control.Button
        AddPlotButton                   matlab.ui.control.Button
        DeletePlotDropDown              matlab.ui.control.DropDown
        DeleteNeuronDropDown            matlab.ui.control.DropDown
    end


    properties (Access = private)

        %% Program info.

        % The log of user activity for code evaluation/optimization.
        log

        % Version info.
        version = Program.ProgramInfo.version;

        % Are we using the parallelization toolbox?
        is_parallel = true;
        parallel_pool;


        %% Image info.

        % The default parameters for the program & matching pursuit.
        params
        mp_params

        % File locations for the open image.
        image_file = [];
        id_file = [];

        % The image preferences for loading/saving.
        image_prefs = [];

        % The image info
        image_info = [];

        % Worm information.
        worm = [];
        neuron_info = Neurons.Hermaphrodite;

        % The open image of the worm.
        % A pixel matrix of (x,y,z, color channel).
        image_name
        image_data
        image_data_zscored = [];
        image_view % a subset of the image that shows only what we're viewing, a matrix of (x,y,z, subset of color channels)
        image_um_scale = [1,1,1]; % image micron scale (x,y,z)
        image_gamma = ones(6, 1); % the gamma adjustment for the image
        gamma_RGBW_DIC_GFP_index = 1:6; % the indices for gamma values

        % Reconstructed image from gaussian approximations of neuron positions, shapes, & colors.
        % A reconstructed pixel matrix of (x,y,z, color channel).
        recon
        recon_view  % a subset of the reconstructed image that shows only what we're viewing, a matrix of (x,y,z, subset of color channels)

        % The neurons in the image.
        image_neurons

        % Which neuron is selected.
        selected_neuron

        % Mouse click states (a hack to detect double clicks).
        % Note: initialization is performed by startupFcn due construction issues.
        mouse_clicked;

        % Zoom percent for the keyboard shortcut.
        zoom_percent = 0.2;

        % Toggle image panning.
        is_panning = false;

        % Mouse position correction (the cursor is offset incorrectly).
        mouse_offset = [-1,1];


        %% Neuron info.

        % Neuron marker GUI attributes.
        % Note: initialization is performed by startupFcn due construction issues.
        neuron_marker;

        % Hermaphrodite and male neuron info.
        hermaphrodite_neurons;
        male_neurons;

        % Toggle neuron labels.
        show_labels = false;
        label_color = [1,1,0];
        label_fontsize = 12;
        label_fontweight = 'bold';

        % Are we opening a file?
        is_opening_file = false;

        % Are we saving a user ID?
        is_saving_ID = false;

        neuron_activity;
        neuron_activity_by_name;
        activity_var;
        movie_var;
        framerate;
        activity_table;
        VolTrace;
        nwb_data;
        stimuli;

        color_tracker;
        style_tracker;

        %% Processing Properties

        % Vertical & Horizontal lines drawn on volume
        proc_xy_yline; % Horizontal line on XY axes
        proc_yz_yline; % Horizontal line on YZ axes
        proc_xz_yline; % Horizontal line on XZ axes

        proc_xy_xline; % Vertical line on XY axes
        proc_yz_xline; % Vertical line on YZ axes
        proc_xz_xline; % Vertical line on XZ axes

        % Character maps
        dropdownMap;   % Maps channel dropdown values to color names
        colorMap;      % Maps channel dropdown values to color shorthand


        %% Tracking Properties

        % Video properties
        video_path    % Path to input video
        video_array   % 4D video array
        video_info    % Structure containing video metadata.
        video_neurons % 4D neuron array
        id_frames     % Keeps track of IDd frames
        activity      % Struct containing per-neuron activity

        % Neuron Manipulation properties
        repo_neurons  % Backup 4D neuron array
        sel_neurons   % Keeps track of neurons being manipulated

        % Volume properties
        parent_app    % Handle for main GUI

        % ZephIR properties
        annotations   % 4D array containing all annotations.
        worldlines    % Struct containing all worldlines.
        arguments     % Struct containing ZephIR arguments.

        % GUI properties
        ctrl_state    % Tracks whether control key is down.

        xy_xline      % Variable serving as GUI handle.
        xz_xline      % Variable serving as GUI handle.
        yz_xline      % Variable serving as GUI handle.

        xy_yline      % Variable serving as GUI handle.
        xz_yline      % Variable serving as GUI handle.
        yz_yline      % Variable serving as GUI handle.

        script_dir    % Local working directory, changes based on deployment & OS.
        script_ext    % Deployment- & OS-specific extension for scripts/executables.
    end

    methods (Access = private)

        %% NeuroPAL ID Functions

        function InitClickStates(app)
            %% Initialize the mouse click states (a hack to detect double clicks).
            % Note: initialization is performed by startupFcn due construction issues.

            app.mouse_clicked.double_click_delay = 0.3;
            app.mouse_clicked.click = false;
        end


        function InitNeuronMarker(app)
            %% Initialize the neuron marker GUI attributes.
            % Note: initialization is performed by startupFcn due construction issues.

            app.neuron_marker.shape = 'c';
            app.neuron_marker.color.edge = [0,0,0];
        end


        function DisableImageGUI(app)
            %% No image loaded, disable all image GUI functionality.

            % Disable the menu items.
            app.ImageMenu.Enable = 'off';
            app.PreprocessingMenu.Enable = 'off';

            % Disable the worm info.
            app.BodyDropDown.Enable = 'off';
            app.AgeDropDown.Enable = 'off';
            app.SexDropDown.Enable = 'off';
            app.StrainEditField.Enable = 'off';
            app.NotesEditField.Enable = 'off';

            % Disable the color channels.
            app.RCheckBox.Enable = 'off';
            app.GCheckBox.Enable = 'off';
            app.BCheckBox.Enable = 'off';
            app.WCheckBox.Enable = 'off';
            app.DICCheckBox.Enable = 'off';
            app.GFPCheckBox.Enable = 'off';
            app.RDropDown.Enable = 'off';
            app.GDropDown.Enable = 'off';
            app.BDropDown.Enable = 'off';
            app.WDropDown.Enable = 'off';
            app.DICDropDown.Enable = 'off';
            app.GFPDropDown.Enable = 'off';

            % Disable the neuron detection info.
            app.AutoDetectButton.Enable = 'off';
            app.MouseClickDropDown.Enable = 'off';

            % Disable the image info.
            app.ZSlider.Enable = 'off';
            app.ZAxisDropDown.Enable = 'off';
            app.FlipZButton.Enable = 'off';
            app.ZCenterEditField.Enable = 'off';

            % Disable the neuron info.
            DisableNeuronGUI(app);
        end


        function EnableImageGUI(app)
            %% Image loaded, enable all image GUI functionality.

            % Enable the menu items.
            app.ImageMenu.Enable = 'on';
            app.PreprocessingMenu.Enable = 'on';

            % Enable the worm info.
            app.BodyDropDown.Enable = 'on';
            app.AgeDropDown.Enable = 'on';
            app.SexDropDown.Enable = 'on';
            app.StrainEditField.Enable = 'on';
            app.NotesEditField.Enable = 'on';

            % Enable the color channels.
            app.RCheckBox.Enable = 'on';
            app.GCheckBox.Enable = 'on';
            app.BCheckBox.Enable = 'on';
            app.WCheckBox.Enable = 'on';
            app.DICCheckBox.Enable = 'on';
            app.GFPCheckBox.Enable = 'on';
            app.RDropDown.Enable = 'on';
            app.GDropDown.Enable = 'on';
            app.BDropDown.Enable = 'on';
            app.WDropDown.Enable = 'on';
            app.DICDropDown.Enable = 'on';
            app.GFPDropDown.Enable = 'on';

            % Enable the neuron detection info.
            app.AutoDetectButton.Enable = 'on';
            app.MouseClickDropDown.Enable = 'on';

            % Enable the image info.
            app.ZSlider.Enable = 'on';
            app.ZAxisDropDown.Enable = 'on';
            app.FlipZButton.Enable = 'on';
            app.ZCenterEditField.Enable = 'on';
        end


        function DisableNeuronGUI(app)
            %% No neurons present, disable all neuron GUI functionality.

            % Disable the menu items.
            app.AnalysisMenu.Enable = 'off';
            app.RotateImageMenu.Enable = 'off';
            app.RotateNeuronsMenu.Enable = 'off';
            app.DeleteUserIDsMenu.Enable = 'off';
            app.DeleteModelIDsMenu.Enable = 'off';
            app.SaveIDImageMenu.Enable = 'off';

            % Disable the neuron info.
            app.SaveIDsButton.Enable = 'off';
            app.AutoIDAllButton.Enable = 'off';
            app.AutoIDButton.Enable = 'off';
            app.UserIDButton.Enable = 'off';
            app.ColorAtlasCheckBox.Enable = 'off';
            app.NextNeuronDropDown.Enable = 'off';
            app.UserNeuronIDsListBox.Enable = 'off';
        end


        function EnableNeuronGUI(app)
            %% Neurons present, enable all neuron GUI functionality.

            % Enable the menu items.
            app.AnalysisMenu.Enable = 'on';
            app.RotateImageMenu.Enable = 'on';
            app.RotateNeuronsMenu.Enable = 'on';
            app.DeleteUserIDsMenu.Enable = 'on';
            app.DeleteModelIDsMenu.Enable = 'on';
            app.SaveIDImageMenu.Enable = 'on';

            % Enable the neuron info.
            app.SaveIDsButton.Enable = 'on';
            app.AutoIDAllButton.Enable = 'on';
            app.AutoIDButton.Enable = 'on';
            app.UserIDButton.Enable = 'on';
            app.ColorAtlasCheckBox.Enable = 'on';
            app.NextNeuronDropDown.Enable = 'on';
            app.UserNeuronIDsListBox.Enable = 'on';
        end


        function EnableActivityGUI(app)
            %% Activity present, enable all activity GUI functionality.

            app.DisplayNeuronActivityMenu.Enable = 'on';
        end


        function DrawImageData(app)
            %% Draw the annotated image (image volume & neuron markers).

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            app.logEvent('Main','Drawing image...', 1);

            % Determine the color channel indices.
            red = str2double(app.RDropDown.Value);
            green = str2double(app.GDropDown.Value);
            blue = str2double(app.BDropDown.Value);
            white = str2double(app.WDropDown.Value);
            dic = str2double(app.DICDropDown.Value);
            gfp = str2double(app.GFPDropDown.Value);

            % Determine the channel=color assignments for displaying.
            color_indices = [red, green, blue];

            % Draw the 3 color channels.
            app.image_view = app.image_data(:,:,:,color_indices);

            % Remove unchecked color channels.
            if ~app.RCheckBox.Value % Red
                app.image_view(:,:,:,1) = 0;
            end
            if ~app.GCheckBox.Value % Green
                app.image_view(:,:,:,2) = 0;
            end
            if ~app.BCheckBox.Value % Blue
                app.image_view(:,:,:,3) = 0;
            end

            % Add in the white channel.
            if app.WCheckBox.Value % White

                % Compute the white channel.
                wchannel = app.image_data(:,:,:,white);

                % Adjust the gamma.
                W_i = app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index
                if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1
                    wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                end

                % Add the white channel.
                app.image_view = app.image_view + repmat(wchannel, [1,1,1,3]);
            end

            % Add in the DIC channel.
            if app.DICCheckBox.Value % DIC

                % Compute the DIC channel.
                dic_channel = app.image_data(:,:,:,dic);

                % Adjust the gamma.
                DIC_i = app.gamma_RGBW_DIC_GFP_index(5); % DIC channel gamma index
                if length(app.image_gamma) >= DIC_i && app.image_gamma(DIC_i) ~= 1
                    dic_channel = imadjustn(dic_channel,[],[], app.image_gamma(DIC_i));
                end

                % Add the DIC channel.
                app.image_view = app.image_view + repmat(dic_channel, [1,1,1,3]);
            end

            % Add in the GFP channel.
            if app.GFPCheckBox.Value % GFP

                % Compute the GFP channel.
                gfp_color = Program.GUIPreferences.instance().GFP_color;
                gfp_channel = app.image_data(:,:,:,gfp);

                % Adjust the gamma.
                GFP_i = app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index
                if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1
                    gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));
                end

                % Add the GFP channel.
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                gfp_channel(:,:,:,~gfp_color) = 0;
                app.image_view = app.image_view + gfp_channel;
            end

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            app.image_view = uint16(double(intmax('uint16')) * ...
                double(app.image_view)/double(max(app.image_view(:))));
            for c = 1:size(app.image_view, 4)
                if app.image_gamma(c) ~= 1
                    app.image_view(:,:,:,c) = ...
                        imadjustn(squeeze(app.image_view(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end
            app.image_view = double(app.image_view)/double(max(app.image_view(:)));

            % Redraw the max projection.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            image(app.MaxProjection, squeeze(max(app.image_view,[],3)));

            % Redraw the Z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end


        function DrawZSlice(app, slider, view, ax)
            %% Draw the neurons in this z-slice.

            % Sanity check the Z slice value.
            z = round(app.ZSlider.Value);
            app.logEvent('Main',sprintf('Drawing slice %s...', string(z)), 0);
            app.ZSlider.Value = z;

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % Flip the Z-axis.
            z_num = z;
            if app.image_prefs.is_Z_flip
                z = size(app.image_data,3) - z + 1;
            end

            % Where are we in Z?
            background_color = [0.94,0.94,0.94];
            LD_color = [0,1,1]; % left or dorsal color
            RV_color = [1,0,1]; % right or ventral color
            z_center_thresh = 1; % center +/- 1 slice
            z_center = app.image_prefs.z_center;
            if z_num >= z_center - z_center_thresh && z_num <= z_center + z_center_thresh
                app.XYPanel.BackgroundColor = background_color;
                app.ZLeftLabel.BackgroundColor = background_color;
                app.ZRightLabel.BackgroundColor = background_color;
            elseif z_num < z_center - z_center_thresh
                app.XYPanel.BackgroundColor = LD_color;
                app.ZLeftLabel.BackgroundColor = LD_color;
                app.ZRightLabel.BackgroundColor = background_color;
            else % if z > z_center + z_center_thresh
                app.XYPanel.BackgroundColor = RV_color;
                app.ZLeftLabel.BackgroundColor = background_color;
                app.ZRightLabel.BackgroundColor = RV_color;
            end

            % Clear the contents of the axis to draw the new Z-slice.
            cla(ax);
            % Create the slice at z for displaying in the axis.
            xy = squeeze(view(:,:,z,:,:));
            % Display the current slice in the XY axis.
            gui_image = image(xy, 'Parent', ax); hold(ax, 'on');

            if strcmp(app.TabGroup.SelectedTab.Title, 'Image Processing') & app.ProcColormapButton.Value
                image(xy, 'Parent', app.proc_xyAxes);
            end

            % Add the AddNeuron function as mouse click listener.
            gui_image.ButtonDownFcn = {@app.ImageClicked};

            % Redraw the neurons in this z slice.
            if ~isempty(app.image_neurons) && ~isempty(app.image_neurons.neurons)

                % Which neurons belong in this z-slice?
                neuron_locations = app.image_neurons.get_positions();
                neuron_marker_colors = app.image_neurons.get_marker_colors();
                neuron_marker_sizes = app.image_neurons.get_marker_sizes();
                neuron_line_size = Program.GUIPreferences.instance().neuron_dot.line;

                % Since z (which is found by MP) can be a continuous value
                % to find all the neurons in the current slice we find the
                % ones that lie in the interval [z-z_dot_view, z+z_dot_view]. Finally we
                % change the x and y dimension to fix the inconsistent
                % behavior of Matlab figures for scatter and image function.
                z_dot_view = 1.5;
                current_z_indices = neuron_locations(:,3)>z-z_dot_view & neuron_locations(:,3)<z+z_dot_view;
                positions = neuron_locations(current_z_indices, 1:2);

                % Draw the neuron markers.
                neuron_marker_plot = scatter(ax, positions(:, 2), positions(:, 1), ...
                    neuron_marker_sizes(current_z_indices), ...
                    neuron_marker_colors(current_z_indices, :), ...
                    'filled', 'MarkerEdgeColor', app.neuron_marker.color.edge, ...
                    'LineWidth', neuron_line_size);

                % Are we showing the neuron annotations?
                if app.show_labels

                    % Get the labels.
                    labels = app.image_neurons.get_annotations();
                    labels = labels(current_z_indices);

                    % Get the ON/OFF annotations.
                    is_on = app.image_neurons.get_is_annotations_on();
                    is_on = is_on(current_z_indices);

                    % Get the confidences.
                    confidences = app.image_neurons.get_annotation_confidences();
                    confidences = confidences(current_z_indices);

                    % Get the emphasized neurons.
                    is_emphasized = app.image_neurons.get_is_emphasized();
                    is_emphasized = is_emphasized(current_z_indices);

                    % Remove empty labels.
                    is_label = ~cellfun('isempty', labels);
                    labels = labels(is_label);
                    is_on = is_on(is_label);
                    confidences = confidences(is_label);
                    is_emphasized = is_emphasized(is_label);

                    % Add ON/OFF & confidence to the labels.
                    for i = 1:length(confidences)

                        % Is the neuron ON/OFF.
                        switch is_on(i)
                            case false
                                labels{i} = [labels{i} '-OFF'];
                            case true
                                labels{i} = [labels{i} '-ON'];
                        end

                        % Is the user uncertain about the ID?
                        if confidences(i) <= 0.5
                            labels{i} = [labels{i} '?'];
                        end

                        % Is the neuron emphasized?
                        if is_emphasized(i)
                            labels{i} = [labels{i} '!'];
                        end
                    end

                    % Draw the labels.
                    DrawImageLabels(app, positions(is_label,:), labels);
                end

                % Setup the mouse-click callback.
                neuron_marker_plot.ButtonDownFcn = {@app.NeuronClicked};
            end

            % Draw the color atlas.
            if app.ColorAtlasCheckBox.Value

                % Do we have the atlas info?
                if isempty(app.image_neurons.get_aligned_xyzRGBs())

                    % Uncheck the atlas.
                    app.ColorAtlasCheckBox.Value = false;

                    % Warn the user.
                    uialert(app.CELL_ID, ...
                        'Please press "Auto-ID All" to create the neuron ID atlas!', ...
                        'No Atlas', 'Icon', 'warning');

                    % Draw the neuron ID atlas.
                else
                    Methods.AutoId.instance().visualize(...
                        app.image_neurons, app.worm, 'ax', app.XY, 'z', z);
                end
            end
        end


        function DrawImageLabels(app, positions, labels, varargin)
            %% Draw text labels on the image.

            % Are the left & right labels pre-determined?
            left_i = [];
            if ~isempty(varargin)
                left_i = varargin{1};
            end

            % Determine the left & right labels.
            if isempty(left_i)
                center_y = size(app.image_view, 2) / 2;
                left_i = positions(:,2) <= center_y;
            end
            right_i = ~left_i;

            % Draw the labels on the left side.
            if sum(left_i) > 0
                left_pos = positions(left_i,1:2);
                left_pos(:,2) = left_pos(:,2) + 4;
                left_labels = cellfun(@(x) strcat('\leftarrow', x), labels(left_i), 'UniformOutput', false);
                text(app.XY, left_pos(:,2), left_pos(:,1), left_labels, ...
                    'Color', app.label_color, 'FontSize', app.label_fontsize, ...
                    'FontWeight', app.label_fontweight, 'HorizontalAlignment', 'left');
            end

            % Draw the labels on the right side.
            if sum(right_i) > 0
                right_pos = positions(right_i,1:2);
                right_pos(:,2) = right_pos(:,2) - 4;
                right_labels = cellfun(@(x) strcat(x, '\rightarrow'), labels(right_i), 'UniformOutput', false);
                text(app.XY, right_pos(:,2), right_pos(:,1), right_labels, ...
                    'Color', app.label_color, 'FontSize', app.label_fontsize, ...
                    'FontWeight', app.label_fontweight, 'HorizontalAlignment', 'right');
            end
        end


        function ImageClicked(app, image_neurons, hit)
            %% The image was clicked. Add a neuron (on middle or double-click) or delete image artifacts (if selected).

            double_click = false; % did the user double-click?

            switch hit.Button
                case 1
                    % Check for a double click.
                    if app.mouse_clicked.click
                        %disp('double click');
                        double_click = true;

                        % Single click.
                    else
                        app.mouse_clicked.click = true;
                        pause(app.mouse_clicked.double_click_delay);
                        if app.mouse_clicked.click
                            %disp('single click');
                        end
                    end
                    app.mouse_clicked.click = false;

                case 2
                    double_click = true;
            end

            % Add a neuron where the user clicked.
            if double_click
                switch app.MouseClickDropDown.Value
                    case app.MouseClickDropDown.Items{1} % Add a neuron where the user clicked.
                        app.logEvent('NeuroPAL_ID','Adding neuron...',1);
                        app.image_neurons.add_meta_data('auto_detect', false);
                        AddNeuron(app, hit);

                    case app.MouseClickDropDown.Items{2} % Auto detect a neuron where the user clicked.
                        app.logEvent('NeuroPAL_ID','Auto-detecting neuron...',1);
                        app.image_neurons.add_meta_data('auto_detect', true);
                        AddNeuron(app, hit);
                end
            end
        end


        function AddNeuron(app, hit)
            %% Add a neuron to a z-slice.

            % Where did the user click?
            mouse_pos = flip(hit.IntersectionPoint(1:2)) + app.mouse_offset;

            % Read the z slice from the ZSlider.
            z = getZSlice(app, round(app.ZSlider.Value));
            point = [mouse_pos, z];

            app.logEvent('Main',sprintf('Adding neuron @ %s...', string(point)), 0);
            % Create & add the neuron.
            app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                point, app.mp_params.hnsz, app.image_um_scale');

            % Save the updated IDs.
            SaveIDToFile(app);

            % Redraw the image.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Enable the neuron GUI.
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                EnableNeuronGUI(app);
            end
        end


        function NeuronClicked(app, image_neurons, hit)
            %% Select or delete neurons where the user clicked.

            select_neuron = false; % select the neuron where the user clicked?
            delete_neuron = false; % delete the neuron where the user clicked?

            % Where did the user click?
            mouse_pos = flip(hit.IntersectionPoint(1:2));

            % Where are we in Z?
            z = getZSlice(app, round(app.ZSlider.Value));
            point = [mouse_pos, z];

            % Find the closest neuron to the mouse click.
            positions = app.image_neurons.get_positions();
            [~, neuron_i] = min(mean((positions-point).^2,2));

            switch hit.Button
                case 1 % left click

                    % Check for a double click.
                    if app.mouse_clicked.click
                        delete_neuron = true;

                        % Single click.
                    else
                        app.mouse_clicked.click = true;
                        pause(app.mouse_clicked.double_click_delay);
                        if app.mouse_clicked.click
                            select_neuron = true;
                        end
                    end
                    app.mouse_clicked.click = false;

                case 3 % right click
                    delete_neuron = true;
            end

            % Select the neuron.
            if select_neuron
                SelectNeuron(app, neuron_i);

                % Delete the neuron from the list.
            elseif delete_neuron
                DeleteNeuron(app, neuron_i);
            end
        end


        function drawStimuli(app)
            % Get the axes of the stackedplot
            ax = findobj(app.VolTrace.NodeChildren, "Type", "axes");
        
            % Loop over each stimulus
            for i = 1:size(app.stimuli, 1)-1
                % Get the stimulus time and name
                time = app.stimuli{i, 1};
                name = app.stimuli{i, 2}{1};
        
                % If the stimulus is 'CTX', set color to white
                if strcmp(name, 'CTX')
                    color = [1 1 1];
                else
                    % Sum the ASCII values of the name's characters
                    asciiSum = sum(double(name));
        
                    % Use mod to wrap the sum into a range [0, 1]
                    hashValue = mod(asciiSum, 256) / 255;
        
                    % Generate a color that is not too similar to white
                    color = [hashValue, abs(0.5 - hashValue), abs(1 - hashValue)];
                end
        
                text_color = color * 0.5;  % darker variant
        
                % Get the end time of the current stimulus
                end_time = app.stimuli{i+1, 1};
        
                % Add the stimulus name as text
                text(ax(end), time + (end_time - time) / 2, ax(end).YLim(2), name, 'HorizontalAlignment', 'right', 'VerticalAlignment', 'middle', 'FontSize', 8, 'Color', text_color, 'Rotation', 90);
        
                % Loop over each subplot
                for j = 1:numel(ax)
                    % Draw a rectangle on the subplot using patch
                    patch(ax(j), 'XData', [time, end_time, end_time, time], 'YData', [ax(j).YLim(1), ax(j).YLim(1), ax(j).YLim(2), ax(j).YLim(2)], 'FaceColor', color, 'EdgeColor', 'none', 'FaceAlpha', 0.6);
                end
            end
        
            for n=1:numel(ax)
                set(ax(n),'children',flipud(get(ax(n),'children')))
            end
        end


        function SendFocus(app, ui_element)
            %% Send focus to a UI element.
            % Hack: Matlab App Designer!!!
            focus(ui_element);
        end


        function RestorePointer(app)
            %% Restore the mouse pointer.
            % Hack: Matlab App Designer!!!
            js_code = ['var elementToChange = document.getElementsByTagName("body")[0];' ...
                'elementToChange.style.cursor = "url(''cursor:default''), auto";'];
            hWin = mlapptools.getWebWindow(app.CELL_ID);
            hWin.executeJS(js_code);
        end


        function SelectNeuron(app, neuron_i)
            %% Select a neuron from the z-slice image.

            % Update the neuron plot marker to show that it's selected.
            % Find the index of selected neuron in the list of displayed
            % neurons that are in the current slice. The index of neuron
            % in the app.image_neurons.neurons (which contains all the neurons) is
            % different from its index in the list of current z neurons.

            % Unselect the selected neuron.
            if app.selected_neuron == neuron_i
                UnselectNeuron(app);
                return;
            end

            % Unselect the previously selected neuron.
            if ~isempty(app.selected_neuron)
                app.image_neurons.neurons(app.selected_neuron).is_selected = false;
            end

            % Select the neuron.
            app.selected_neuron = neuron_i;
            app.image_neurons.neurons(app.selected_neuron).is_selected = true;

            % Flip the Z-axis.
            z = app.image_neurons.neurons(app.selected_neuron).position(3);
            z = getZSlice(app, z);

            % Redraw the Z-slice.
            app.ZSlider.Value = z;
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);

            % If the AutoID is already run and the IDs are present.
            is_IDEditField_set = false;
            if ~isempty(app.image_neurons.neurons) ...
                    && isempty(app.image_neurons.neurons(neuron_i).annotation) ...
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)

                % Initialize the IDs & probabilities.
                ids = app.image_neurons.neurons(neuron_i).probabilistic_ids;
                probs = app.image_neurons.neurons(neuron_i).probabilistic_probs();
                probs = round(probs * 100);

                % Remove zero probability choices.
                probs_i = probs > 0;
                ids = ids(probs_i);
                probs = probs(probs_i);

                % Concatenate names and probabilities to display them.
                str = strcat(ids, '=', arrayfun(@(x) num2str(x, '%d%% '), probs, 'UniformOutput', 0));

                % Update the GUI.
                app.AutoIDDropDown.Items = str;
                name = app.image_neurons.neurons(neuron_i).deterministic_id;
                name = upper(name);
                if ~app.AutoNameCheckBox.Value || ...
                        strcmp(name, 'ARTIFACT') || strcmp(name, 'NAN')
                    app.IDEditField.Value = '';
                else
                    app.IDEditField.Value = name;
                end
                is_IDEditField_set = true;

                % Select the appropriate Auto ID.
                neuron_rank = app.image_neurons.neurons(neuron_i).rank;
                if ~isempty(neuron_rank)
                    app.UserNeuronIDsListBox.Value = neuron_rank;
                    %app.UserNeuronIDsListBox.Value = app.UserNeuronIDsListBox.Items{neuron_rank};
                end
            else
                % Select nothing.
                app.AutoIDDropDown.Items = {};
                app.UserNeuronIDsListBox.Value = {};
            end

            % Show the user annotation.
            user_ID = app.image_neurons.neurons(neuron_i).annotation;
            if ~isempty(user_ID)

                % Is the neuron ON/OFF?
                switch app.image_neurons.neurons(neuron_i).is_annotation_on
                    case false
                        user_ID = [user_ID '-OFF'];
                    case true
                        user_ID = [user_ID '-ON'];
                end

                % Is the user uncertain about the ID?
                if app.image_neurons.neurons(neuron_i).annotation_confidence <= 0.5
                    user_ID = [user_ID '?'];
                end

                % Is the neuron emphasized?
                if app.image_neurons.neurons(neuron_i).is_emphasized
                    user_ID = [user_ID '!'];
                end

                % Display the user ID.
                app.IDEditField.Value = user_ID;
                is_IDEditField_set = true;
            end

            % Empty the ID field.
            if ~is_IDEditField_set
                app.IDEditField.Value = '';
            end

            % Enable the input fields.
            app.AutoIDDropDown.Enable = 'on';
            app.IDEditField.Enable = 'on';
            app.AutoIDButton.Enable = 'on';
            app.UserIDButton.Enable = 'on';

            % If the neuron is unID'd, send focus to the ID text field.
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            if isempty(user_ID)
                SendFocus(app, app.IDEditField);
            end
        end


        function SelectNearestUnIDdNeuron(app, neuron_i)
            %% Select the nearest neuron in need of a user ID.

            % Select the nearest neuron in need of a user ID by z, then (x,y).
            [~, neuron_i] = app.image_neurons.nearest_unannotated(neuron_i);

            % No more neurons to ID.
            if isempty(neuron_i)
                uialert(app.CELL_ID, ...
                    'All neurons have been assigned an ID!',...
                    'All Neurons ID''d', 'Icon', 'success');
                return;
            end

            % Auto ID the nearest neuron.
            auto_ID = [];
            if ~isempty(app.image_neurons.neurons) ...
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)
                auto_ID = app.image_neurons.neurons(neuron_i).deterministic_id;
            end

            % Select the nearest neuron.
            SelectNeuron(app, neuron_i);
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {[auto_ID '???']}, 0);

            % Send focus to the ID text field.
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            %SendFocus(app, app.IDEditField);
        end


        function SelectProbabilisticUnIDdNeuron(app, neuron_i)
            %% Select the most uncertain neuron in need of a user ID.

            % No more neurons to ID.
            if app.image_neurons.num_neurons() == app.image_neurons.num_user_id_neurons()
                uialert(app.CELL_ID, ...
                    'All neurons have been assigned an ID!',...
                    'All Neurons ID''d', 'Icon', 'success');
                return;
            end

            % Store the currently selcted neuron.
            old_neuron_i = neuron_i;

            % Select the nearest neuron in need of a user ID by z, then (x,y).
            [~, neuron_i] = app.image_neurons.find_rank(1);

            % Is the neuron already selected?
            if app.image_neurons.num_user_unid_neurons() > 1 && neuron_i == old_neuron_i
                [~, neuron_i] = app.image_neurons.find_rank(2);
            end

            % No more neurons to ID.
            if isempty(neuron_i)
                uialert(app.CELL_ID, ...
                    'All neurons have been assigned an ID!',...
                    'All Neurons ID''d', 'Icon', 'success');
                return;
            end

            % Auto ID the most uncertain neuron.
            auto_ID = [];
            if ~isempty(app.image_neurons.neurons) ...
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)
                auto_ID = app.image_neurons.neurons(neuron_i).deterministic_id;
            end

            % Select the most uncertain neuron.
            SelectNeuron(app, neuron_i);
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {[auto_ID '???']}, 0);

            % Send focus to the ID text field.
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            %SendFocus(app, app.IDEditField);
        end


        function UnselectNeuron(app, varargin)
            %% Unselect the currently selected neuron. Optional argument, re-draw the image?
            %% Note: Matlab buffers re-draws so, if you want them to execute in an orderly fashion,
            %% you need to only make one call.

            % Unselect the selected neuron.
            if ~isempty(app.selected_neuron) && app.image_neurons.neurons(app.selected_neuron).is_selected == true

                % Unselect the neuron.
                app.image_neurons.neurons(app.selected_neuron).is_selected = false;
                app.selected_neuron = [];

                % Disable the ID fields.
                app.AutoIDDropDown.Items = {''};
                app.AutoIDDropDown.Value = '';
                app.IDEditField.Value = '';

                % Disable the input fields.
                app.AutoIDDropDown.Enable = 'off';
                app.IDEditField.Enable = 'off';
                app.AutoIDButton.Enable = 'off';
                app.UserIDButton.Enable = 'off';

                % Redraw the Z-slice.
                is_redraw = true; % re-draw the image?
                if ~isempty(varargin)
                    is_redraw = varargin{1};
                end
                if is_redraw

                    % Give the GUI time to update.
                    pause(0.2);

                    % Re-draw.
                    DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
                end
            end
        end


        function DeleteNeuron(app, neuron_i)
            %% Delete a neuron from the z-slice image.

            % Did the neuron have an ID?
            neuron = app.image_neurons.neurons(neuron_i).annotation;
            if ~isempty(neuron)
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" is ID''d!'], 'Do you want delete the neuron?'}, ...
                    'Neuron ID Deleted', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Keep the neuron.
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Unselect all neurons.
            app.UnselectNeuron(false); % wait for re-draw
            %app.UnselectNeuron();

            % NO LONGER USED!!! UPDATEAUTOIDMODEL HANDLES THIS.
            % Delete the neuron from the GUI list.
            %app.UserNeuronIDsListBox.Value = {};
            %uncertain_idx = app.image_neurons.neurons(neuron_i).rank;
            %if ~isempty(uncertain_idx)
            %    app.UserNeuronIDsListBox.Items = ...
            %        {app.UserNeuronIDsListBox.Items{1:(uncertain_idx-1)}, ...
            %        app.UserNeuronIDsListBox.Items{(uncertain_idx+1):end}};
            %end

            % Delete the neuron from the master list.
            app.image_neurons.del_neuron(neuron_i);

            % Save the updated IDs.
            app.SaveIDToFile();

            % Redraw the auto ID list.
            app.DrawAutoIDList();

            % Redraw the Z-slice.
            app.DrawZSlice(app.ZSlider, app.image_view, app.XY);

            % Update the ID'd neuron count.
            app.UpdateNeuronLists();
        end


        function DrawAutoIDList(app)
            %% Draw the auto ID neuron uncertainty list.

            % Initialize the probabilities.
            prob_IDs = app.image_neurons.get_probabilistic_ids();
            prob_vals = app.image_neurons.get_probabilistic_probs();
            ranks = app.image_neurons.get_ranks();
            if isempty(ranks)
                return;
            end

            % Construct the ranked strings.
            neurons = app.image_neurons.neurons;
            prob_vals = round(prob_vals * 100);
            prob_strs = [];
            prob_ranks = [];
            for i = 1:length(neurons)

                % Is the neuron ranked?
                neuron = neurons(i);
                if neuron.rank > 0 % && isempty(neuron.annotation)

                    % Display the most probable neuron.
                    prob_IDs = neuron.probabilistic_ids;
                    prob_vals = round(neuron.probabilistic_probs * 100);
                    prob_strs{end+1} = sprintf('%s=%d%%', prob_IDs{1}, prob_vals(1));
                    prob_ranks(end+1) = neuron.rank;

                    % Display alternative neurons.
                    alt_i = find(prob_vals(2:end) > 0) + 1;
                    if ~isempty(alt_i)
                        prob_strs{end} = [prob_strs{end}, '   or  '];
                    end
                    for j = 1:length(alt_i)
                        k = alt_i(j);
                        if j > 1
                            prob_strs{end} = [prob_strs{end}, ','];
                        end
                        prob_strs{end} = [prob_strs{end}, sprintf(' %s=%d%%', ...
                            prob_IDs{k}, prob_vals(k))];
                    end
                end
            end

            % Draw the auto ID neuron uncertainty list.
            [~, sort_i] = sort(prob_ranks);
            app.UserNeuronIDsListBox.Items = prob_strs(sort_i);
            app.UserNeuronIDsListBox.ItemsData = 1:length(sort_i);
            app.UserNeuronIDsListBox.Value = {};
        end


        function UpdateNeuronLists(app)
            %% Update the user ID'd neurons.

            % Show the selected neurons.
            selectedNodes = app.GanglionTree.SelectedNodes;
            neurons = {};
            for i = 1:length(selectedNodes)
                node = selectedNodes(i);
                if isempty(node.Children) % Child node on the tree.
                    neurons = union(neurons, GetChildNodeNeurons(app, node));
                else % Parent node on the tree.
                    neurons = union(neurons, GetParentNodeNeurons(app, node));
                end
            end
            neurons = sort(neurons);

            % None of the neurons have IDs.
            if isempty(app.image_neurons) || isempty(app.image_neurons.neurons)
                app.UserNeuronIDsListBoxLabel.Text = 'User Neuron IDs';
                app.IDdLabel.Text = '     ID''d     ';
                app.UserNeuronIDsListBox.Items = {};
                app.UserNeuronIDsListBox.ItemsData = [];
                app.UnIDdLabel.Text = sprintf('Un-ID''d = %d', length(neurons));
                app.UnIDdNeuronsListBox.Items = neurons;

                % Which neurons have IDs?
            else
                % Show the neuron counts.
                user_id_neurons = app.image_neurons.user_id_neurons();
                app.UserNeuronIDsListBoxLabel.Text = sprintf('User Neuron IDs = %d/%d', ...
                    length(user_id_neurons), app.image_neurons.num_neurons());

                % Remove emphasized neurons. They're not really ID'd.
                is_emphasized = vertcat(user_id_neurons.is_emphasized);
                user_id_neurons = user_id_neurons(~is_emphasized);

                % Separate the neurons into those with & without user IDs.
                user_id_names = vertcat({user_id_neurons.annotation});
                [id_neurons, ~, user_id_i] = intersect(neurons, user_id_names);
                unid_neurons =  setdiff(neurons, id_neurons);

                % Keep a copy of the neuron names.
                id_neuron_names = id_neurons;

                % Add ON/OFF & confidences to the neuron names.
                for i = 1:length(user_id_i)

                    % Is the neuron ON/OFF?
                    switch user_id_neurons(user_id_i(i)).is_annotation_on
                        case false
                            id_neurons{i} = [id_neurons{i} '-OFF'];
                        case true
                            id_neurons{i} = [id_neurons{i} '-ON'];
                    end

                    % Is the user uncertain about the ID?
                    if user_id_neurons(user_id_i(i)).annotation_confidence <= 0.5
                        id_neurons{i} = [id_neurons{i} ' ?'];
                    end
                end

                % Add the neuron birth times.
                if Program.GUIPreferences.instance().is_show_birth_times

                    % Initialize the neuron info.
                    neuron_data = app.hermaphrodite_neurons;
                    if contains(lower(app.worm.sex), 'xo')
                        neuron_data = app.male_neurons;
                    end

                    % Add birth times to the ID'd neurons.
                    [~, id_i, ~] = intersect(neuron_data.names, id_neuron_names);
                    if ~isempty(id_i)
                        id_stages = neuron_data.birth_stages(id_i);
                        id_neurons = cellfun(@(x,y) ...
                            sprintf('%s (%s)', x, y), ...
                            id_neurons, id_stages, ...
                            'UniformOutput', false);
                    end

                    % Add birth times to the ID'd neurons.
                    [~, unid_i, ~] = intersect(neuron_data.names, unid_neurons);
                    if ~isempty(unid_i)
                        unid_stages = neuron_data.birth_stages(unid_i);
                        unid_neurons = cellfun(@(x,y) ...
                            sprintf('%s (%s)', x, y), ...
                            unid_neurons, unid_stages, ...
                            'UniformOutput', false);
                    end
                end

                % Update the GUI.
                black = [0,0,0];
                gray = [0.95,0.95,0.95];
                app.IDdLabel.BackgroundColor = gray;
                app.IDdLabel.FontColor = black;
                app.UnIDdLabel.BackgroundColor = gray;
                app.UnIDdLabel.FontColor = black;
                app.IDdLabel.Text = sprintf('ID''d = %d', length(id_neurons));
                app.IDdNeuronsListBox.Items = id_neurons;
                app.UnIDdLabel.Text = sprintf('Un-ID''d = %d', length(unid_neurons));
                app.UnIDdNeuronsListBox.Items = unid_neurons;
            end
        end

        function UpdateSexNeuronLists(app)
            %% Update the neuron lists.
            
            % Is the worm a male?
            is_male = contains(lower(app.worm.sex), 'xo');

            % Find the male tail node.
            tree = app.GanglionTree;
            names = {tree.Children.Text};
            male_i = find(strcmp(names,'Male Tail'));

            % Add the male tail.
            if is_male && isempty(male_i)

                % Unselect all ganglia & neurons.
                app.GanglionTree.SelectedNodes = [];
                UnselectNeuron(app);

                % Remove the hermaphrodite tail.
                hermaphrodite_i = find(strcmp(names,'Tail'));
                if ~isempty(hermaphrodite_i)
                    tree.Children(hermaphrodite_i).delete();
                end

                % Add the male tail.
                app.neuron_info = Neurons.Male;
                ganglia = uitreenode(tree,'Text','Male Tail');
                uitreenode(ganglia,'Text','Pre-Anal (L)');
                uitreenode(ganglia,'Text','Pre-Anal (R)');
                uitreenode(ganglia,'Text','Rays (L)');
                uitreenode(ganglia,'Text','Rays (R)');
                uitreenode(ganglia,'Text','Dorso-Rectal');
                uitreenode(ganglia,'Text','Cloacal (L)');
                uitreenode(ganglia,'Text','Cloacal (R)');
                uitreenode(ganglia,'Text','Lumbar (L)');
                uitreenode(ganglia,'Text','Lumbar (R)');
                UpdateNeuronLists(app);

                % Remove the male tail.
            elseif ~is_male && ~isempty(male_i)

                % Unselect all ganglia & neurons.
                app.GanglionTree.SelectedNodes = [];
                UnselectNeuron(app);

                % Remove the male tail.
                tree.Children(male_i).delete();
                UpdateNeuronLists(app);

                % Add the hermaphrodite tail.
                app.neuron_info = Neurons.Hermaphrodite;
                ganglia = uitreenode(tree,'Text','Tail');
                uitreenode(ganglia,'Text','Pre-Anal');
                uitreenode(ganglia,'Text','Dorso-Rectal');
                uitreenode(ganglia,'Text','Lumbar (L)');
                uitreenode(ganglia,'Text','Lumbar (R)');
                UpdateNeuronLists(app);
            end
        end


        function neurons = GetParentNodeNeurons(app, node)
            %% Get the neurons associated with this parent node.

            neurons = [];
            switch node.Text
                case 'Pharynx'
                    neurons = app.neuron_info.getAnteriorPharynx();
                    neurons = union(neurons, app.neuron_info.getPosteriorPharynx());
                case 'Head'
                    neurons = app.neuron_info.getLeftAnteriorGanglion();
                    neurons = union(neurons, app.neuron_info.getRightAnteriorGanglion());
                    neurons = union(neurons, app.neuron_info.getDorsalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftLateralGanglion());
                    neurons = union(neurons, app.neuron_info.getRightLateralGanglion());
                    neurons = union(neurons, app.neuron_info.getVentralGanglion());
                    neurons = union(neurons, app.neuron_info.getRetroVesicularGanglion());
                case 'Midbody'
                    neurons = app.neuron_info.getAnteriorMidbody();
                    neurons = union(neurons, app.neuron_info.getCentralMidbody());
                    neurons = union(neurons, app.neuron_info.getPosteriorMidbody());
                    neurons = union(neurons, app.neuron_info.getVentralNerveCord());
                case 'Tail'
                    neurons = app.neuron_info.getPreAnalGanglion();
                    neurons = union(neurons, app.neuron_info.getDorsoRectalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftLumbarGanglion());
                    neurons = union(neurons, app.neuron_info.getRightLumbarGanglion());
                case 'Male Tail'
                    neurons = app.neuron_info.getLeftPreAnalGanglion();
                    neurons = union(neurons, app.neuron_info.getRightPreAnalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftRays());
                    neurons = union(neurons, app.neuron_info.getRightRays());
                    neurons = union(neurons, app.neuron_info.getDorsoRectalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftCloacalGanglion());
                    neurons = union(neurons, app.neuron_info.getRightCloacalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftLumbarGanglion());
                    neurons = union(neurons, app.neuron_info.getRightLumbarGanglion());
            end
        end


        function neurons = GetChildNodeNeurons(app, node)
            %% Get the neurons associated with this child node.

            neurons = [];
            switch node.Parent.Text
                case 'Pharynx'
                    switch node.Text
                        case 'Anterior'
                            neurons = app.neuron_info.getAnteriorPharynx();
                        case 'Posterior'
                            neurons = app.neuron_info.getPosteriorPharynx();
                    end
                case 'Head'
                    switch node.Text
                        case 'Anterior (L)'
                            neurons = app.neuron_info.getLeftAnteriorGanglion();
                        case 'Anterior (R)'
                            neurons = app.neuron_info.getRightAnteriorGanglion();
                        case 'Dorsal'
                            neurons = app.neuron_info.getDorsalGanglion();
                        case 'Lateral (L)'
                            neurons = app.neuron_info.getLeftLateralGanglion();
                        case 'Lateral (R)'
                            neurons = app.neuron_info.getRightLateralGanglion();
                        case 'Ventral'
                            neurons = app.neuron_info.getVentralGanglion();
                        case 'Retro-Vesicular'
                            neurons = app.neuron_info.getRetroVesicularGanglion();
                    end
                case 'Midbody'
                    switch node.Text
                        case 'Anterior'
                            neurons = app.neuron_info.getAnteriorMidbody();
                        case 'Central'
                            neurons = app.neuron_info.getCentralMidbody();
                        case 'Posterior'
                            neurons = app.neuron_info.getPosteriorMidbody();
                        case 'Ventral Cord'
                            neurons = app.neuron_info.getVentralNerveCord();
                    end
                case 'Tail'
                    switch node.Text
                        case 'Pre-Anal'
                            neurons = app.neuron_info.getPreAnalGanglion();
                        case 'Dorso-Rectal'
                            neurons = app.neuron_info.getDorsoRectalGanglion();
                        case 'Lumbar (L)'
                            neurons = app.neuron_info.getLeftLumbarGanglion();
                        case 'Lumbar (R)'
                            neurons = app.neuron_info.getRightLumbarGanglion();
                    end
                case 'Male Tail'
                    switch node.Text
                        case 'Pre-Anal (L)'
                            neurons = app.neuron_info.getLeftPreAnalGanglion();
                        case 'Pre-Anal (R)'
                            neurons = app.neuron_info.getRightPreAnalGanglion();
                        case 'Rays (L)'
                            neurons = app.neuron_info.getLeftRays();
                        case 'Rays (R)'
                            neurons = app.neuron_info.getRightRays();
                        case 'Dorso-Rectal'
                            neurons = app.neuron_info.getDorsoRectalGanglion();
                        case 'Cloacal (L)'
                            neurons = app.neuron_info.getLeftCloacalGanglion();
                        case 'Cloacal (R)'
                            neurons = app.neuron_info.getRightCloacalGanglion();
                        case 'Lumbar (L)'
                            neurons = app.neuron_info.getLeftLumbarGanglion();
                        case 'Lumbar (R)'
                            neurons = app.neuron_info.getRightLumbarGanglion();
                    end
            end
        end


        function SaveUserID(app, user_ID, neuron_i)
            %% Save the user ID for a neuron.

            % Depending on the question mark set the annotation confidence and the neuron name.
            % The marker colors will change accordingly -> see Neuron.m
            %   Examples:
            %       'AVR?' -> 50% confidence if it's AVR or not.
            %       '?' -> 0% confidence about the neuron.
            %       'AVR' -> 100% confidence that it's AVR.

            % Are we already saving an ID?
            if app.is_saving_ID
                return;
            end
            app.is_saving_ID = true;

            % Are any neurons selected?
            if ~exist("neuron_i","var")
                neuron_i = app.selected_neuron;
            end

            app.logEvent('Main',sprintf('Saving ID %s for neuron %s...', user_ID, string(neuron_i)), 1);

            if isempty(neuron_i)
                app.is_saving_ID = false;
                return;
            end

            % Get rid of the whitespace and uppercase the ID.
            user_ID = upper(strtrim(user_ID));

            % Did the user input an empty ID (e.g., "")?
            if isempty(user_ID)

                % Are we deleting the neuron's existing ID?
                neuron = app.image_neurons.neurons(neuron_i).annotation;
                if ~isempty(neuron)
                    answer = uiconfirm(app.CELL_ID, ...
                        {['"' neuron '" is ID''d!'], 'Do you want delete the ID?'}, ...
                        'Neuron ID Deleted', 'Options', {'Yes', 'Cancel'}, ...
                        'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                    % Keep the ID.
                    if strcmpi(answer, 'cancel')
                        UnselectNeuron(app);
                        app.is_saving_ID = false;
                        return;
                    end

                    % Delete the ID.
                    app.image_neurons.neurons(neuron_i).annotate('', 0, nan);
                end


                % Save the updated IDs.
                SaveIDToFile(app);

                % Update the ID'd neuron count.
                UpdateNeuronLists(app);

                % Clear the ID field.
                UnselectNeuron(app);
                app.is_saving_ID = false;

                % Select the next neuron in need of a user ID.
                if strcmp(app.NextNeuronDropDown.Value, 'Nearest')
                    app.SelectNearestUnIDdNeuron(neuron_i);
                else
                    app.SelectProbabilisticUnIDdNeuron(neuron_i);
                end

                % Done.
                return;
            end

            % Is the user emphasizing this neuron?
            % Note: as one example, neurons may be emphasized in mutants.
            neuron = user_ID;
            is_emphasized = false;
            if neuron(end) == '!'
                is_emphasized = true;
                neuron = neuron(1:(end-1));
            end

            % Is the user uncertain about the ID?
            is_uncertain = false;
            if neuron(end) == '?'
                is_uncertain = true;
                neuron = neuron(1:(end-1));
            end

            % Is the neuron ON/OFF?
            [neuron, is_on] = app.neuron_info.stripOnOff(neuron);

            % Did the user input a correct neuron name?
            % Note: emphasized neurons can exist as duplicates.
            %if ~app.neuron_info.isNeuron(neuron)
            if is_emphasized
                if ~Neurons.Hermaphrodite.isCell(neuron) && ...
                        ~Neurons.Male.isCell(neuron)
                    uiconfirm(app.CELL_ID, ...
                        {['"' neuron '" is not a cell type!'], ...
                        ['Did you forget the "L" or "R"?']}, ...
                        'Neuron Name Error', 'Options', {'Ok'}, ...
                        'DefaultOption', 1, 'CancelOption', 1, ...
                        'Icon', 'error');
                    SendFocus(app, app.IDEditField);
                    app.is_saving_ID = false;
                    return;
                    %else
                    % Add the emphasis back onto the neuron.
                    % Note: this has 2 side effects
                    % 1. We can assign the neuron name more than once.
                    % 2. The neuron assignment will not affect our predictions.
                    %neuron = [neuron '!'];
                end
            elseif ~app.neuron_info.isCell(neuron)
                uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" is not a neuron in ' app.worm.sex ' animals!'], ...
                    ['Did you forget the "L" or "R", or is the sex incorrect?']}, ...
                    'Neuron Name Error', 'Options', {'Ok'}, ...
                    'DefaultOption', 1, 'CancelOption', 1, ...
                    'Icon', 'error');
                SendFocus(app, app.IDEditField);
                app.is_saving_ID = false;
                return;
            end

            % Has the neuron already been assigned?
            prev_assigned_neuron = app.image_neurons.find_user_id_neuron(neuron);
            if ~isempty(prev_assigned_neuron) && ~is_emphasized && ...
                    app.image_neurons.neurons(neuron_i) ~= prev_assigned_neuron

                % Are we re-assigning the ID?
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" was assigned to a different neuron!'], ...
                    'Do you want delete the previously assigned ID?'}, ...
                    'Neuron ID Re-Assigned', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Cancel.
                if strcmpi(answer, 'cancel')
                    SendFocus(app, app.IDEditField);
                    app.is_saving_ID = false;
                    return;
                end

                % Delete the previous ID.
                prev_assigned_neuron.annotate('', 0, nan);
            end

            % What did the user enter?
            app.image_neurons.neurons(neuron_i).annotation = neuron;
            app.image_neurons.neurons(neuron_i).is_annotation_on = is_on;
            app.image_neurons.neurons(neuron_i).is_emphasized = is_emphasized;
            if is_uncertain
                app.image_neurons.neurons(neuron_i).annotation_confidence = 0.5;
            else
                app.image_neurons.neurons(neuron_i).annotation_confidence = 1;
            end
            if ~isempty(app.image_neurons.neurons(neuron_i).probabilistic_probs)
                app.image_neurons.neurons(neuron_i).deterministic_id = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_ids{1} = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(1) = 1;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(2:end) = 0;
            end
            if ~isempty(app.image_neurons.neurons(neuron_i).rank)
                app.image_neurons.rank_neuron_max(neuron_i);
            end

            % Update the auto-ID model.
            UpdateAutoIDModel(app);

            % Update ID confidences
            UpdateNeuronIDConfidence(app);

            % Save the updated IDs.
            SaveIDToFile(app);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Done.
            UnselectNeuron(app);
            app.is_saving_ID = false;

            % Select the next neuron in need of a user ID.
            if strcmp(app.NextNeuronDropDown.Value, 'Nearest')
                app.SelectNearestUnIDdNeuron(neuron_i);
            else
                app.SelectProbabilisticUnIDdNeuron(neuron_i);
            end

            % Show the annotated neuron's ID.
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {user_ID}, 1);
        end


        function SaveAutoID(app, neuron)
            %% Save the auto ID for a neuron.

            % Are we already saving an ID?
            if app.is_saving_ID
                return;
            end
            app.is_saving_ID = true;

            % Are any neurons selected?
            neuron_i = app.selected_neuron;
            if isempty(neuron_i)
                app.is_saving_ID = false;
                return;
            end

            % Has the neuron already been assigned?
            prev_assigned_neuron = app.image_neurons.find_user_id_neuron(neuron);
            if ~isempty(prev_assigned_neuron) && app.image_neurons.neurons(neuron_i) ~= prev_assigned_neuron

                % Are we re-assigning the ID?
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" was assigned to a different neuron!'], ...
                    'Do you want delete the previously assigned ID?'}, ...
                    'Neuron ID Re-Assigned', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Cancel.
                if strcmpi(answer, 'cancel')
                    SendFocus(app, app.IDEditField);
                    app.is_saving_ID = false;
                    return;
                end

                % Delete the previous ID.
                prev_assigned_neuron.annotate('', 0, nan);
            end

            app.logEvent('Main',sprintf('Saving automatic ID %s for neuron %s...', neuron, string(neuron_i)), 1);

            % Annotate the neuron.
            app.image_neurons.neurons(neuron_i).annotation = neuron;
            app.image_neurons.neurons(neuron_i).is_annotation_on = nan;
            app.image_neurons.neurons(neuron_i).annotation_confidence = 1;
            app.image_neurons.neurons(neuron_i).is_emphasized = false;
            if ~isempty(app.image_neurons.neurons(neuron_i).probabilistic_probs)
                app.image_neurons.neurons(neuron_i).deterministic_id = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_ids{1} = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(1) = 1;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(2:end) = 0;
            end
            if ~isempty(app.image_neurons.neurons(neuron_i).rank)
                app.image_neurons.rank_neuron_max(neuron_i);
            end

            % Update the auto-ID model.
            UpdateAutoIDModel(app);

            % Save the updated IDs.
            SaveIDToFile(app);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Done.
            UnselectNeuron(app);
            app.is_saving_ID = false;

            % Select the next neuron in need of a user ID.
            if strcmp(app.NextNeuronDropDown.Value, 'Nearest')
                app.SelectNearestUnIDdNeuron(neuron_i);
            else
                app.SelectProbabilisticUnIDdNeuron(neuron_i);
            end

            % Show the annotated neuron's ID.
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {neuron}, 1);
        end


        function UpdateAutoIDModel(app)
            %% Update the model for neuron ID.

            % Update the log_likelihood, uncertainty, and neuron ranks,
            % then update the list of neurons accordingly.
            worm = app.worm;
            if Program.GUIPreferences.instance().is_autoID_updates && ...
                    Methods.AutoId.isBodyID(worm.sex, worm.body)
                Methods.AutoId.instance().update_id(...
                    app.image_neurons, worm);
            end
            DrawAutoIDList(app);
        end


        function UpdateNeuronIDConfidence(app)
            %% Update the neuorn ID confidences.

            % Update the confidence computations that each labeled neuron
            % is correctly identified.
            worm = app.worm;
            if ~Methods.AutoId.isBodyID(worm.sex, worm.body)
                return;
            end
            Methods.AutoId.instance().update_confidences(...
                app.image_neurons, worm);
        end

        
        function SaveIDToFile(app)
            %% Save the IDs to a file.

            version = Program.ProgramInfo.version;
            mp_params = app.mp_params;
            mp_params.k = length(app.image_neurons.neurons);
            neurons = app.image_neurons;
            save(app.id_file, 'version', 'neurons', 'mp_params');
            %sp = app.image_neurons.to_superpixel();
            %save(app.id_file, 'version', 'sp', 'mp_params');
        end


        function z = getZSlice(app, z)
            %% Get the z slice (correcting for the flipped axis).

            if app.image_prefs.is_Z_flip
                z = size(app.image_data,3) - z + 1;
            end
        end


        function lMoving(~, source)
            % Function that responds to the movement of the time sliders

            % Always keep the position rounded to 1 decimal place
            source.Position(1,1) = round(source.Position(1,1),1);
            source.Position(2,1) = round(source.Position(2,1),1);
            % Add any other action here triggered by the movement of the sliders
        end


        function DispNeuron(app,event)
            caller = event.Source;
            grid_space = caller.Parent.Parent.Layout;

            if strcmp(caller.Tag,'selector')
                app.LoadNeuron(caller, event)
            elseif strcmp(caller.Tag,'in_graph')
                app.PickNeuron(caller, 'in_graph')
            elseif strcmp(caller.Tag,'add_graph')
                app.LoadNeuron(caller, event, 'add_graph')
            else
                if grid_space.Column < max(size(app.VolTraceHelperGrid.ColumnWidth)) & grid_space.Row < max(size(app.VolTraceHelperGrid.RowHeight))
                    app.PickNeuron(caller)
                else
                    app.PickNeuron(caller)
                    %app.CreateNeuron(caller,event)
                end
            end
        end


        function PickNeuron(app,caller,tag)

            if ~exist('tag','var')
                tag = 'selector';
                g_parent = app.CELL_ID;
                parent = caller.Parent;
            elseif strcmp(tag,'col') | strcmp(tag,'row')
                'tbd'
            else
                tag = 'add_graph';
                g_parent = caller.Parent;
                parent = caller.Parent.Parent;
            end

            set(caller,'Visible','Off');

            % Create DropDown
            DropDown = uidropdown(g_parent);
            DropDown.Parent = parent;
            DropDown.Position = caller.Position;
            DropDown.Items = {'Sample Neuron #1','Sample Neuron #2','Sample Neuron #3'};
            DropDown.Placeholder = 'Select Neuron';
            DropDown.Tag = tag;
            DropDown.ValueChangedFcn = createCallbackFcn(app, @DispNeuron, true);
        end


        function LoadNeuron(app,caller,event,tag)

            if ~exist('tag','var')
                % Create new 
                new_grid = uigridlayout(caller.Parent.Parent);
                delete(caller.Parent)
                new_grid.ColumnWidth = {'1x', '1x', '1x', '1x', '1x'};
                new_grid.RowHeight = {'1x', '1x', '1x', '1x', '1x', '1x'};
    
                % Create UIAxes
                new_axes = uiaxes(new_grid);
                title(new_axes, event.Value)
                xlabel(new_axes, 't')
                ylabel(new_axes, 'Ca+')
                zlabel(new_axes, 'Z')
                new_axes.Layout.Row = [1 6];
                new_axes.Layout.Column = [1 5];
    
                % Create AddNeuronButton
                new_button = uibutton(new_grid, 'push');
                new_button.Layout.Row = 2;
                new_button.Layout.Column = 5;
                new_button.Text = 'Add Neuron';
                new_button.Tag = 'in_graph';
                new_button.ButtonPushedFcn = createCallbackFcn(app, @DispNeuron, true);
            else
                targAx = caller.Parent.Children(2).Children(1);
                title(targAx, [targAx.Title.String,', ', event.Value])
            end
        end


        function CreateNeuron(app,caller,event)
            % Create TL_Panel
            new_panel = uipanel(app.VolTraceHelperGrid);

            if grid_space(1) == max(size(app.VolTraceHelperGrid.ColumnWidth))
                new_panel.Layout.Column = grid_space(2)+1;
            else
                new_panel.Layout.Column = grid_space;
            end

            if grid_space(2) == max(size(app.VolTraceHelperGrid.RowHeight))
                new_panel.Layout.Row = grid_space(1)+1;
            end

            % Create TL_Grid
            new_grid = uigridlayout(app.TL_Panel);
            new_grid.ColumnWidth = {'1x'};
            new_grid.RowHeight = {'1x'};

            % Create TL_Button
            new_button = uibutton(app.TL_Grid, 'push');
            new_button.ButtonPushedFcn = createCallbackFcn(app, @AddNeuron, true);
            new_button.FontWeight = 'bold';
            new_button.Layout.Row = 1;
            new_button.Layout.Column = 1;
            new_button.Text = 'Add Neuron';
        end
   

        function initBrowser(app, neuron)        
            if ~exist('neuron','var')
                % Create the 1D axes
                targ_neuron = {['null']};
            else
                targ_neuron = {[neuron]};
            end

            app.VolTrace = stackedplot(app.VolTraceHelperGrid,app.activity_table,targ_neuron);
            drawnow
            app.formatStack()

        end


        function formatStack(app)
            sample_neuron = keys(app.neuron_activity_by_name);
            length = max(size(app.neuron_activity_by_name(sample_neuron{1})));

            app.VolTrace.GridVisible = 'on';
            app.VolTrace.XLabel = 't';
            app.VolTrace.Layout.Row = [1, size(app.VolTraceHelperGrid.RowHeight,2)];
            app.VolTrace.Layout.Column = [1,2];

            if ~isempty(app.framerate)
                % Add a listener for changes in 'XLim' property
                stAxes = findobj(app.VolTrace.NodeChildren, 'Type','Axes');
                addlistener(stAxes, 'XLim', 'PostSet', @(src, event) updateXTicks(app, length));
                
                % Initial setup
                updateXTicks(app, app.framerate);
            end
        end


        function updateXTicks(app, framerate)
            % Get the x-axis data
            xData = app.VolTrace.XData;
        
            % Determine the type of x-axis data
            if all(xData >= 1e9) % Assuming Unix timestamps
                timeInSeconds = (xData - xData(1)) / 1000; % Convert to seconds from milliseconds
            elseif max(xData) <= length(xData) % Assuming frame counts
                timeInSeconds = xData / framerate; % Convert to seconds using framerate
            else % Assuming seconds
                timeInSeconds = xData;
            end
        
            % Convert to MM:SS format
            minutes = floor(timeInSeconds / 60);
            seconds = mod(timeInSeconds, 60);
            tickLabels = arrayfun(@(m, s) sprintf('%02d:%02d', m, s), minutes, seconds, 'UniformOutput', false);
        
            % Find the underlying axes and set the tick values and labels
            stAxes = findobj(app.VolTrace.NodeChildren, 'Type','Axes');
            set(stAxes, 'XTick', xData, 'XTickLabel', tickLabels);
        end
   

        function updateBrowser(app, neuron, subplot)
            try
                if ~any(contains(app.VolTrace.DisplayVariables,neuron))
                    if ~exist('subplot','var')
                        subplot = 1;
                    end
        
                    old_xlim = app.VolTrace.XLimits;
        
                    if ~isempty(app.VolTrace.DisplayVariables)
                        subplot_vars = app.VolTrace.DisplayVariables(subplot);
                        new_vars = [];
                        for n=1:size(app.VolTrace.DisplayVariables(subplot),2)
                            if ~strcmp(subplot_vars{n},'null')
                                new_vars = [string(new_vars),string(subplot_vars{n})];
                            end
                        end
                        new_vars = [new_vars,string(neuron)];
                        app.VolTrace.DisplayVariables(subplot) = {new_vars};
                    end
        
                    subplot_idx = size(app.VolTrace.DisplayVariables,2)-(subplot-1);
                    subplot_idx = max(1,size(app.VolTrace.LineProperties,1)-(subplot_idx-1));
                    target_color = rgb2hsv(app.neuron_info.getNeuronColor(neuron));
        
                    if strcmp(neuron(end),'L')
                        target_color(2) = target_color(2)*0.6;
                        target_style = '--';
                    else
                        target_color(3) = target_color(3)*0.6;
                        target_style = '-';
                    end
                    target_color = hsv2rgb(target_color);
                    
                    if isempty(app.color_tracker) || length(app.color_tracker) < subplot_idx || isempty(app.color_tracker{subplot_idx})
                        app.color_tracker{subplot_idx} = [target_color];
                        app.style_tracker{subplot_idx} = [{target_style}];
                    else
                        app.color_tracker{subplot_idx} = [app.color_tracker{subplot_idx}; target_color]; 
                        app.style_tracker{subplot_idx} = [app.style_tracker{subplot_idx}, target_style];
                    end
        
                    app.VolTrace.LineProperties(subplot_idx).LineStyle = app.style_tracker{subplot_idx};   
                    app.VolTrace.LineProperties(subplot_idx).Color = app.color_tracker{subplot_idx};   
                    app.VolTrace.XLimits = old_xlim;
                    drawnow;
        
                    app.drawStimuli();
                else
                    uiconfirm(app.CELL_ID, "Neuron already plotted.", "Error!");
                end
            catch
                uiconfirm(app.CELL_ID, sprintf("Neuron %s has no activity to load.", neuron), "Error!")
            end
        end


        %% Processing Tab Functions

        function drawProcImage(app)

            if app.ProcVideoButton.Enable
                app.ProcLoadVideo();
            elseif app.ProcColormapButton.Enable
                app.ProcLoadVol();
            else
                uiconfirm(app.CELL_ID, 'No volume selected!', 'Error');
            end

        end

        function ProcLoadVideo(app)
            % Load video volume into proc axes
        end

        function ProcLoadVol(app, x, y, z)
            app.DrawImageData();
            target_frame = app.image_view;
            target_truth = app.image_data;
            threshold_value = (app.NoisethresholdKnob.Value/double(max(app.image_data, [], 'all')))*double(max(target_frame, [], 'all'));
            target_truth(target_truth < app.NoisethresholdKnob.Value) = 0;
            target_frame(target_frame < threshold_value) = 0;

            if ~exist ('z', 'var')
                z = round(app.proc_hor_zSlider.Value);
            end

            if ~exist('y', 'var')
                y = round(app.proc_xSlider.Value);
            end

            if ~exist ('x', 'var')
                x = round(size(app.image_data, 1)-app.proc_ySlider.Value);
            end

            app.proc_xyAxes.XLim = [1, size(app.image_data, 2)];
            app.proc_xyAxes.YLim = [1, size(app.image_data, 1)];

            app.proc_xzAxes.XLim = [1, size(app.image_data, 2)];
            app.proc_xzAxes.YLim = [1, size(app.image_data, 3)];

            app.proc_yzAxes.XLim = [1, size(app.image_data, 3)];
            app.proc_yzAxes.YLim = [1, size(app.image_data, 1)];

            xy = squeeze(target_frame(:,:,z,:,:));
            xz = squeeze(target_frame(:,y,:,:,:));
            yz = squeeze(target_frame(x,:,:,:,:));

            image(xy, 'Parent', app.proc_xyAxes);
            image(flipud(rot90(yz)), 'Parent', app.proc_xzAxes);
            image(xz, 'Parent', app.proc_yzAxes);
            
            app.proc_xy_yline = yline(app.proc_xyAxes, x, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_yz_yline = yline(app.proc_yzAxes, x, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_xz_yline = yline(app.proc_xzAxes, app.proc_xzAxes.YLim(2)*(z/size(app.image_data, 3)), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);

            app.proc_xy_xline = xline(app.proc_xyAxes, y, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_yz_xline = xline(app.proc_yzAxes, app.proc_yzAxes.XLim(2)*(z/size(app.image_data, 3)), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_xz_xline = xline(app.proc_xzAxes, y, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);

            % Draw histograms
            for n=1:size(app.image_data, 4)
                switch n
                    case 1
                        set(app.a_hist_ax, 'Visible', 'on')
                        app.a_hist_ax.Title.String = sprintf("%s Histogram", app.dropdownMap(num2str(n)));
                        histogram(app.a_hist_ax, target_truth(:, :, :, n), 'FaceColor', app.colorMap(num2str(n)), 'EdgeColor', app.colorMap(num2str(n)));
                    case 2
                        set(app.b_hist_ax, 'Visible', 'on')
                        app.b_hist_ax.Title.String = sprintf("%s Histogram", app.dropdownMap(num2str(n)));
                        histogram(app.b_hist_ax, target_truth(:, :, :, n), 'FaceColor', app.colorMap(num2str(n)), 'EdgeColor', app.colorMap(num2str(n)));
                    case 3
                        set(app.c_hist_ax, 'Visible', 'on')
                        app.c_hist_ax.Title.String = sprintf("%s Histogram", app.dropdownMap(num2str(n)));
                        histogram(app.c_hist_ax, target_truth(:, :, :, n), 'FaceColor', app.colorMap(num2str(n)), 'EdgeColor', app.colorMap(num2str(n)));
                    case 4
                        set(app.d_hist_ax, 'Visible', 'on')
                        app.d_hist_ax.Title.String = sprintf("%s Histogram", app.dropdownMap(num2str(n)));
                        histogram(app.d_hist_ax, target_truth(:, :, :, n), 'FaceColor', app.colorMap(num2str(n)), 'EdgeColor', app.colorMap(num2str(n)));
                    case 5
                        set(app.e_hist_ax, 'Visible', 'on')
                        app.e_hist_ax.Title.String = sprintf("%s Histogram", app.dropdownMap(num2str(n)));
                        histogram(app.e_hist_ax, target_truth(:, :, :, n), 'FaceColor', app.colorMap(num2str(n)), 'EdgeColor', app.colorMap(num2str(n)));
                    case 6
                        set(app.f_hist_ax, 'Visible', 'on')
                        app.f_hist_ax.Title.String = sprintf("%s Histogram", app.dropdownMap(num2str(n)));
                        histogram(app.f_hist_ax, target_truth(:, :, :, n), 'FaceColor', app.colorMap(num2str(n)), 'EdgeColor', app.colorMap(num2str(n)));
                end
            end
        end

        %% Activity Tab Functions

        function addNode(app, parent, name)
            %% Add nodes to activity tab tree. (DEFUNCT)

                if ~any(strcmp(keys(app.neuron_activity_by_name),name))
                    %outputStr = '';
                    %for i = 1:(length(name)-1)
                    %    outputStr = [outputStr, name(i), '̶']; % Pseudo strikethrough.
                    %end
                    %name = outputStr;
                    data = 0;
                else
                    data = 1;
                end

                uitreenode(parent,'text', name, 'NodeData', data);
        end


        function hightlightNeuron(app, src, ~)
            %% Highlight neurons in activity tab plot. (DEFUNCT)
            if ~strcmp(app.ClearSelectionButton.Visible,'on')
                app.BrowserTrace.UserData = app.BrowserTrace.Title.String;
            end
                
            clicked_tag = get(src, 'tag');

            for n=1:size(app.BrowserTrace.Children,1)

                if ~strcmp(app.ClearSelectionButton.Visible,'on')
                    app.BrowserTrace.Children(n).UserData = app.BrowserTrace.Children(n).Color;
                end

                if ~contains(app.BrowserTrace.Children(n).Tag, clicked_tag)
                    app.BrowserTrace.Children(n).Color = [0.4, 0.4, 0.4, 0.2];
                else
                    app.BrowserTrace.Children(n).Color = [1, 0, 0, 1];
                end

            end
            
            app.BrowserTrace.Title.String = [app.BrowserTrace.Title.String,['(Selected: ', clicked_tag, ')']];

            set(app.ClearSelectionButton, 'Visible', 'on');
            set(app.ClearSelectionButton, 'Enable', 'on');
        end


        function updateNeurons(app, plot_neurons, plot_features)
            %% Update neurons in activity tab.

            %old_xlim = app.BrowserTrace.XLim;
            %old_ylim = app.BrowserTrace.YLim;

            if ~isempty(plot_neurons)
                title = app.titleGen(plot_neurons, plot_features);
                a = plot(app.BrowserTrace, app.neuron_activity_by_name(plot_neurons{1}), 'tag', ['Neuron: ' plot_neurons{1}]);
                set(a, 'ButtonDownFcn', {@app.hightlightNeuron});
                hold(app.BrowserTrace,'on');
                
                for n = 2:size(plot_neurons,2)
                    ind_neurons = plot(app.BrowserTrace, app.neuron_activity_by_name(plot_neurons{n}), 'tag', ['Neuron: ' plot_neurons{n}]);
                    set(ind_neurons, 'ButtonDownFcn', {@app.hightlightNeuron});
                end
                
                hold(app.BrowserTrace,'off');
    
            else
                title = {'Select Neuron(s) In Ganglion Directory'};
                sample_neuron = keys(app.neuron_activity_by_name);
                plot(app.BrowserTrace, zeros(max(size(app.neuron_activity_by_name(sample_neuron{1})))));
                
            end

            app.BrowserTrace.Title.String = strrep(string(strjoin(title)), newline, '');

            %app.BrowserTrace.XLim = old_xlim;
            %app.BrowserTrace.YLim = old_ylim;
        end


        function title = titleGen(app, neurons, ganglion)
            %% Generate title for browser plot.

            if max(size(neurons)) > 5
                tl_ganglion = {'Pharynx', 'Head', 'Midbody', 'Tail'};
                ml_ganglion = {'Anterior', 'Posterior', 'Anterior (L)', 'Anterior (R)', 'Posterior (L)', 'Posterior (R)', 'Dorsal', 'Lateral (L)', 'Lateral (R)', 'Ventral', 'Retro-Vesicular', 'Ventral Cord', 'Pre-Anal', 'Dorso-Rectal', 'Lumbar (L)', 'Lumbar (R)'};
                tl_checks = intersect(tl_ganglion,ganglion);
                ml_checks = intersect(ml_ganglion,ganglion);

                if ~isempty(tl_checks)
                    title = join(tl_checks,', ');

                    if size(ml_checks) == 1
                        title = [ml_checks, title];
                    end

                    title = ['Various', title, 'Neurons'];

                elseif ~isempty(ml_checks)
                    title = join(ml_checks,', ');
                    title = ['Various', title, 'Neurons'];

                else
                    title = 'Various Neurons';

                end
            else
                title = join(neurons,', ');
            end
        end


        function legend = legendGen(app, ax)
            %% Generate legend for browser plot.
        end

    end
    
    methods (Access = public)
        
        %% NeuroPAL ID Functions
        function UpdateFromChild(app, image_data, image_neurons, image_gamma)
            
            if ~exist('image_data','var')
                image_data = app.image_data;
            end

            if ~exist('image_neurons','var')
                image_neurons = app.image_neurons;
            end

            if ~exist('image_gamma','var')
                image_gamma = app.image_gamma;
            end

            if size(app.image_data,3) ~= size(image_data,3)

                num_z_slices = size(image_data, 3);
                z_slices = 1:num_z_slices;

                if num_z_slices <= 1
                    uialert(app.CELL_ID, 'The image is not a volume!', ...
                        'Image Not a Volume', 'Icon', 'error');
                    return;
                else
                    z_labels = arrayfun(@(z) num2str(z, '%.1f'), ...
                        (z_slices-1) * app.image_info.scale(3), 'UniformOutput', false);
                end

                app.ZSlider.Limits = [1, num_z_slices];
                app.ZSlider.MajorTicks = z_slices;
                app.ZSlider.MajorTickLabels = z_labels;
    
                %{
                % Change the neuron properties (mean and covariance) according
                % to user selected parameters.
                for neuron=1:length(app.image_neurons.neurons)
                    app.image_neurons.neurons(neuron).position = ...
                        app.image_neurons.neurons(neuron).position/( app.image_neurons.neurons(neuron).position/(size(app.image_data,3)/size(image_data,3)) );
                    app.image_neurons.neurons(neuron).covariance = ...
                        app.image_neurons.neurons(neuron).covariance/( app.image_neurons.neurons(neuron).position/(size(app.image_data,3)/size(image_data,3)) );
                end
                %}

            else
                app.image_neurons = image_neurons;
            end

            app.image_data = image_data;
            app.image_gamma = image_gamma;
            
            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            info.scale = app.image_um_scale;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Draw the neurons.
            app.ZSlider.Value = round(app.ZSlider.Limits(2)/2);
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
            app.DrawImageData();
        end


        function ImpNeurons(app,auto_neurons)
            for r = 1:size(auto_neurons, 1)
                app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                [auto_neurons(r,1) auto_neurons(r,2) auto_neurons(r,3)], app.mp_params.hnsz, app.image_um_scale');
            end
        end


        function eyedrop(app, Red, Green, Blue, Background, White, GFP, Lyso, Gut)
        
            color_struct = struct('red',Red,'green',Green,'blue',Blue,'background',Background,'white',White,'GFP',GFP,'Lyso',Lyso,'Gut',Gut);
            imwrite(squeeze(max(app.image_view,[],3)), 'cache.png');
            im = imread('cache.png');
            color_roi = drawpoint(app.MaxProjection);
            pos = round(color_roi.Position);
        
            for color = fieldnames(color_struct)'
                if color_struct.(color{1})
                    uiwait(msgbox(sprintf('In the maximum projection, click on the region that best represents %s.', color{1})))
                    color_values = impixel(im,pos(2), pos(1));
                    color_struct.(color{1}) = color_values;
                    delete(color_roi)
                end
            end
        
            delete('cache.png')
        
        end


        % Define the function to return the activity of a particular neuron over time.
        function activity = get_neuron_activity(worldline_id)
            % Get the indices of the annotations for the specified worldline_id.
            indices = find(annotations.worldline_id == worldline_id);
        
            % Extract the corresponding activity values.
            activity = app.neuron_activity(indices, :);
        end


        % Check if drag & drop ended on target component.
        function target = grab_land(app, figure, pos, parent_class, parent_tag, class, tag)
            try
                comp_array = findobj(figure, '-depth', inf,'-function','Position', @(x) (pos(1)>x(1)&pos(1)<(x(1)+x(3))&pos(2)>x(2)&pos(2)<(x(2)+x(4))));
                mid_idx = find(arrayfun(@(y) isa(y, parent_class)&strcmp(y.Tag, parent_tag), comp_array), 5);

                if ~isempty(mid_idx)
                    middleman = comp_array(mid_idx);
                    pos(1) = pos(1)-middleman.Position(1);
                    pos(2) = pos(2)-middleman.Position(2)-5;

                    deep_array = findobj(middleman.Children.Children, '-depth', inf,'-function','Position', @(x) (pos(1)>x(1)&pos(1)<(x(1)+x(3))&pos(2)>x(2)&pos(2)<(x(2)+x(4))));
                    target_idx = find(arrayfun(@(y) isa(y, class)&strcmp(y.Tag, tag), deep_array), 5);

                    if ~isempty(target_idx)
                        target = deep_array(target_idx);
                    else
                        target = [];
                    end
                else
                    target = [];
                end
            catch
                target = [];
            end
        end


        % Manage click & drag events.
        function DragManager (app, mode, event)
            if app.DisplayNeuronActivityMenu.Checked == 1
                pos = get(app.CELL_ID, 'CurrentPoint');
                switch mode
                    case 'down'
                        target = app.grab_land(app.NeuroPALIDTab, pos, 'matlab.ui.container.Panel', 'side-panel', 'matlab.ui.control.ListBox', 'neuron-selector');
                        if ~isempty(target)
                            app.HoverLabel.Position = [pos(1)-app.HoverLabel.Position(3)/2 pos(2)+1 app.HoverLabel.Position(3) app.HoverLabel.Position(4)];
                            app.HoverLabel.Text = char(target.Value);
                            app.HoverLabel.Position(3) = app.HoverLabel.FontSize*size(target.Value,2);
                            app.CELL_ID.WindowButtonMotionFcn = @(src, event) app.DragManager('move', event);
                            app.HoverLabel.Visible = "on";
                        end
                    case 'move'
                        app.HoverLabel.Position = [pos(1)-app.HoverLabel.Position(3)/2 pos(2)+1 app.HoverLabel.Position(3) app.HoverLabel.Position(4)];
                    case 'up'
                        if strcmp(app.HoverLabel.Visible,'on')
                            app.CELL_ID.WindowButtonMotionFcn = @(src, event) 1+1;
                            set(app.HoverLabel, 'Visible', 'off');
    
                            target = app.grab_land(app.NeuroPALIDTab, pos, 'matlab.ui.container.Tab', 'neuron-activity-tab', 'matlab.ui.container.GridLayout', 'browser_trace');
                            if ~isempty(target)
                                target.Children(1).Units = 'pixels';
                                total_x = target.Children(1).InnerPosition;
                                num_plots = size(target.Children(1).DisplayVariables,2);
    
                                selected_plot = 0;
                                y_divs = total_x(4) / num_plots;
                                for n=1:num_plots
                                    x = total_x;
                                    y = y_divs * (n-1);
                                    x(2) = x(2) + y;
                                    x(4) = y_divs;
                                    % sprintf('Cursor y: %d\nSubplot #%d y: %.2f through %.2f', pos(2), n, x(2), x(2) + x(4))
                                    if (pos(1)>x(1)&pos(1)<(x(1)+x(3))&pos(2)>x(2)&pos(2)<(x(2)+x(4)))
                                        selected_plot = num_plots - (n-1);
                                        break
                                    end
                                end

                                % Strip all non-alphanumeric characters from HoverLabel.Text
                                cleanText = regexprep(app.HoverLabel.Text, '[^a-zA-Z0-9]', '');
                                
                                % Pass the cleaned text to updateBrowser
                                app.updateBrowser(cleanText, selected_plot);
                            end
                        end
                end
            end
        end


        function fullPath = checkFileExistence(app, fileName)
            if exist(fileName, 'file') == 2
                fullPath = fileName;
            else
                fullPath = 'False';
            end
        end


        function load_neurons_from_nwb(app, nwb_data)
                d = uiprogressdlg(app.CELL_ID,'Title','Importing neurons from NWB file...','Indeterminate','off');
                app.logEvent('Main','Placing neuron dots...', 1)
                if any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'NeuroPALSegmentation'))
                    neurons = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').voxel_mask.data.load();
                    neuron_ids = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.get('ID_labels').data.load();
                    if size(neuron_ids,1) == size(neurons.x, 1)
                        full_ids = 1;
                    else

                        if any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.keys, 'ID_labels_index'))
                            id_indices = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.get('ID_labels_index').data.load();
                        elseif any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.keys, 'ROI_IDs'))
                            id_indices = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('NeuroPALSegmentation').planesegmentation.get('NeuroPALNeurons').vectordata.get('ROI_IDs').data.load();
                        end
                        full_ids = max(cellfun(@(c) numel(c), neuron_ids)) > 1;
                        id_indices(find(id_indices == 0)) = 1;
                    end
                elseif any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'VolumeSegmentation'))
                    neurons = nwb_data.processing.get('NeuroPAL').dynamictable.get('VolumeSegmentation').voxel_mask.data.load();
                    full_ids = 2;
                elseif any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'NeuroPALNeurons'))
                    neurons = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').voxel_mask.data.load();
                    neuron_ids = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.get('ID_labels').data.load();
                    if any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.keys, 'ID_labels_index'))
                        id_indices = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.get('ID_labels_index').data.load();
                    elseif any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.keys, 'ROI_IDs'))
                        id_indices = nwb_data.processing.get('NeuroPAL').dynamictable.get('NeuroPALNeurons').vectordata.get('ROI_IDs').data.load();
                    end
                    full_ids = 1;
                elseif any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'ImageSegmentation'))
                    neurons = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('ImageSegmentation').planesegmentation.get('PlaneSegmentation').voxel_mask.data.load();
                    neurons = struct('x', neurons(:, 2), 'y', neurons(:, 1), 'z', neurons(:, 3));
                    neuron_ids = nwb_data.processing.get('NeuroPAL').nwbdatainterface.get('SegmentationLabels').labels.load();
                    full_ids = 1;
                else
                    uiconfirm(app.CELL_ID, "No neurons found. You shouldn't be seeing this message!");
                end
    
                % Iterate over each row
                for i = 1:size(neurons.x)
                    d.Value = i / size(neurons.x, 1);
                    app.logEvent('Main',sprintf('Loading neuron #%s...', string(i)), 0);
    
                    if full_ids == 1
                        name = neuron_ids{i};
                    elseif full_ids == 2
                        name = neurons.ID{i};
                    else
                        if i ~= 1
                            last_name = id_indices(i-1)+1;
                        else
                            last_name = 1;
                        end
    
                        if last_name == id_indices(i) && i > 1
                            name = '';
                        else
                            name = join(neuron_ids(last_name:id_indices(i)));
                            name = name{1};
                        end
                    end
    
                    name = upper(erase(name," "));
                    x = double(neurons.x(i));
                    y = double(neurons.y(i));
                    z = double(neurons.z(i));
    
                    app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                        [y x z], [13, 13, 3], app.image_um_scale');
    
                    if app.neuron_info.isCell(name)
                        app.logEvent('Main',sprintf('-Neuron #%s is %s...', string(i), name), 0);

                        app.is_saving_ID = true;
                        [neuron, is_on] = app.neuron_info.stripOnOff(name);
                        app.image_neurons.neurons(i).annotation = char(neuron);
                        app.image_neurons.neurons(i).is_annotation_on = is_on;

                        if name(end) == '!' | name(end) == '?'
                            app.image_neurons.neurons(i).is_emphasized = true;
                            app.image_neurons.neurons(i).annotation_confidence = 0.5;
                        else
                            app.image_neurons.neurons(i).annotation_confidence = 1;
                        end
    
                        %{
                        if ~isempty(app.image_neurons.neurons(i).probabilistic_probs)
                            app.image_neurons.neurons(i).deterministic_id = neuron;
                            app.image_neurons.neurons(i).probabilistic_ids{1} = neuron;
                            app.image_neurons.neurons(i).probabilistic_probs(1) = 1;
                            app.image_neurons.neurons(i).probabilistic_probs(2:end) = 0;
                        end
                        %}
    
                        if ~isempty(app.image_neurons.neurons(i).rank)
                            app.image_neurons.rank_neuron_max(i);
                        end
    
                        app.is_saving_ID = false;
                    else
                        fprintf('%s (#%.f) is not a valid neuron.\n', name, i)
                    end
                end
                        
                % Update the auto-ID model.
                UpdateAutoIDModel(app);
    
                % Update the ID'd neuron count.
                UpdateNeuronLists(app);
    
                % Save the updated IDs.
                SaveIDToFile(app);
    
                close(d)   
        end


        function checkedNodes = dependencyChecker(app, mode, checkedNodes, dependency, node)
            switch mode
                case 'python'
                    pip_check = system(['pip show',dependency]);
                    if ~pip_check
                        checkedNodes{end+1} = node;
                    end
                case 'toolbox'
                    toolboxes = ver;
                    toolboxes = toolboxes.Name;
                    if contains(toolboxes, dependency)
                        checkedNodes{end+1} = node;
                    end
                case 'folder'
                    if isfolder(dependency)
                        checkedNodes{end+1} = node;
                    end
                case 'var'
                    if ~isempty(dependency)
                        checkedNodes{end+1} = node;
                    end
                case 'file'
                    if isfile(dependency)
                        checkedNodes{end+1} = node;
                    end
            end
        end

        %% Tracking Functions
        
        function [struct, arr] = load_h5(app, path)
            info = h5info(path);

            if ~any(strcmp({info.Datasets.Name}, 'data')) && ~any(strcmp({info.Datasets.Name}, 'times'))
                uiconfirm(app.CELL_ID,'.h5 file structure is invalid. Please refer to documentation.','Error');
                return
            end

            size_info = h5info(path,'/data');
            arr = h5read(path,'/data',[1 1 1 1 1], size_info.ChunkSize);

            struct = {};
            struct.file = size_info.Filename;           % Path to file
            struct.nx = size_info.Dataspace.Size(2);    % Height
            struct.ny = size_info.Dataspace.Size(1);    % Width
            struct.nz = size_info.Dataspace.Size(3);    % Slice count
            struct.nc = size_info.Dataspace.Size(4);    % Channel count
            struct.nt = size_info.Dataspace.Size(5);  % Frame count
            struct.aspect_ratio = struct.ny/struct.nx;  % Aspect Ratio
            struct.ChunkSize = size_info.ChunkSize;     % Chunk Size
            struct.cached = 1;                          % Frames cached in app.video_array

            app.video_array = arr;
            app.video_info = struct;

            if struct.nt < 10
                uiconfirm(app.CELL_ID,'Your video contains too few frames for filters to be applied during activity extraction. ZephIR will skip these when you run the extraction, which may affect your results.','Warning!');
            end

            [parent_path, ~, ~] = fileparts(path);
            fileType = exist(fullfile(parent_path, 'annotations.h5'), 'file');

            if fileType == 2
                check = uiconfirm(app.CELL_ID, "Existing annotations.h5 file detected. Load annotations?", "NeuroPAL_ID","Options",["Yes","No"]);
                if strcmp(check, 'Yes')
                    app.load_annotations();
                end
            end
        end


        function arr = load_nwb(app, path)
            app.compatibility_handler(path);
        end
        

        function arr = load_nd2(app, path)
            app.compatibility_handler(path);
        end


        function arr = searchNWB(app, whole_file, picked, prev_fig, val)
            all_properties = properties(whole_file);
            
            if ~exist('picked', 'var')
                delete(prev_fig)
                fig = figure('Position', [100, 100, 300, 400], ... % Position and size: [left bottom width height]
                             'Name', 'NWB Browser', ...
                             'NumberTitle', 'off');
    
                listbox = uicontrol('Style', 'listbox', ...
                                    'Units', 'normalized', ...
                                    'Position', [0.1, 0.1, 0.8, 0.8], ... % Position and size: [left bottom width height]
                                    'String', all_properties);
            else
                if class(whole_file.(val)) == types.untyped.Set
                    delete(prev_fig)
                else
                    arr = whole_file.(val);
                    delete(prev_fig)
                end
            end
        end


        function code = compatibility_handler(app, path)
            check = uiconfirm(app.CELL_ID,"The tracking algorithm assumes that your video file is an HDF5 file with two datasets: 'times', featuring time stamps, and 'data', featuring a 5D (t, c, z, y, x) array. You need to either edit the retrieval function or convert your video file. Convert video file now?","Warning!","Options",["Yes","No"]);
            switch check
                case 'Yes'
                    app.convert_HDF5(path);
                case 'No'
                    uiconfirm(app.CELL_ID,"Tracking will not work unless you edit the retrieval function. You can do so by enabling advanced parameters and clicking into the appropriate tab.")
            end
        end


        function convert_HDF5(app, path)
            [filepath, name, format] = fileparts(path);

            title = sprintf('Building data structure from %s...', path);
            d = uiprogressdlg(app.CELL_ID,'Title',title,'Indeterminate','off');

            switch format
                case '.h5'
                    % This should never happen, but we're thorough.
                    check = uiconfirm(app.CELL_ID,"File is already in HDF5 format. If you're seeing this, something has gone very wrong.","Error!","Options", ["OK"]);
                case '.nwb'        
                    % Read NWB file
                    whole_file = nwbRead(path);

                    if any(ismember(whole_file.acquisition.keys, 'CalciumImageSeries'))
                        gc_data = whole_file.acquisition.get('CalciumImageSeries').data;
                    elseif any(ismember(whole_file.processing.keys,'GCaMPVideo'))
                        gc_data = whole_file.processing.get('GCaMPVideo').nwbdatainterface.get('CalciumImageSeries').data;
                    else
                        uiconfirm(app.CELL_ID, sprintf('Could not find video in %s', path), 'Error');
                        delete(app);
                        return

                        %{
                        check = uiconfirm(app.CELL_ID, sprintf('Could not find video in %s', path), 'Error','Options',["Manually specify volume","Cancel"]);
                        
                        switch check
                            case 'Cancel'
                                delete(app);
                                return
                            otherwise
                                gc_data = app.searchNWB(whole_file);
                        end
                        %}
                    end

                    data_pipe = gc_data.internal;

                    % Get dimensions
                    bitDepth = str2num(data_pipe.dataType(5:end));
                    dims = data_pipe.dims;
                    nt = dims(5);
                    nc = dims(1);
                    nz = dims(2);
                    ny = dims(3);
                    nx = dims(4);

                    % Create the HDF5 dataset
                    h5create(fullfile(filepath, 'data.h5'), '/data', [ny nx nz nc nt], 'ChunkSize', [ny nx nz nc 1], 'Datatype', 'uint8');
                    h5create(fullfile(filepath, 'data.h5'), '/times', nt, 'Datatype', 'double');

                    switch bitDepth
                        case {8, 16, 32, 64}
                            scale_factor = double(intmax('uint8')) / double(intmax(sprintf('uint%.f', bitDepth)));
                        case 12
                            scale_factor = double(intmax('uint8')) / 4096;
                        case 20
                            scale_factor = double(intmax('uint8')) / 1048576;
                        case 24
                            scale_factor = double(intmax('uint8')) / 16777216;
                        otherwise
                            error('Bit depth %s failed conversion to uint8.', data_pipe.dataType);
                    end
                    
                    d.Message = 'Processing frames...';
                    d.Value = 0;

                    for t = 1:nt
                        d.Value = t / nt;

                        % Read chunk
                        %chunk = uint8(gc_data(1:nc, 1:nz, 1:ny, 1:nx, t) * scale_factor);
                        chunk = uint8(gc_data(1:nc, 1:nz, 1:ny, 1:nx, t));
                        chunk = permute(chunk, [3 4 2 1 5]);

                        % Write chunk to HDF5
                        h5write(fullfile(filepath, 'data.h5'), '/data', chunk, [1, 1, 1, 1, t], [ny nx nz nc 1]);

                        if d.CancelRequested
                            break;
                        end
                    end
                case '.nd2'
                    bfImg = BioformatsImage(path);
                    nt = bfImg.sizeT;
                    nc = bfImg.sizeC;
                    nz = bfImg.sizeZ;
                    ny = bfImg.height;
                    nx = bfImg.width;

                    % Create the HDF5 dataset
                    h5create(fullfile(filepath, 'data.h5'), '/data', [ny nx nz nc nt], 'ChunkSize', [ny nx nz nc 1], 'Datatype', 'uint8');
                    h5create(fullfile(filepath, 'data.h5'), '/times', nt, 'Datatype', 'double');
            
                    % Initialize the entire dataset array with correct dimension order
                    all_data = zeros(ny, nx, nz, nc, nt, 'uint8');
                    
                    % Collect data for all time points
                    channelNames = bfImg.channelNames;

                    switch bfImg.bitDepth
                        case {8, 16, 32, 64}
                            scale_factor = double(intmax('uint8')) / double(intmax(sprintf('uint%.f', bfImg.bitDepth)));
                        case 12
                            scale_factor = double(intmax('uint8')) / 4096;
                        case 20
                            scale_factor = double(intmax('uint8')) / 1048576;
                        case 24
                            scale_factor = double(intmax('uint8')) / 16777216;
                        otherwise
                            error('Bit depth %.f failed conversion to uint8.', bfImg.bitDepth);
                    end
                    
                    d.Message = 'Processing frames...';
                    d.Value = 0;

                    for t = 1:nt
                        d.Value = t / nt;
                        for c = 1:nc
                            channel = channelNames{c};
                            for z = 1:nz
                                % Read and scale the data
                                plane_data = getPlane(bfImg, z, channel, t);
                                all_data(:, :, z, c, t) = uint8(plane_data * scale_factor);
                            end
                        end
                        
                        if d.CancelRequested
                            break;
                        end
                    end

                    % Write the entire dataset at once to HDF5 with corrected dimension order
                    h5write(fullfile(filepath, 'data.h5'), '/data', all_data);
            end
            close(d)

            app.video_path = fullfile(filepath, 'data.h5');
            [app.video_info, app.video_array] = app.load_h5(app.video_path);
        end


        function load_annotations(app)
            d = uiprogressdlg(app.CELL_ID,'Title','Processing results...','Indeterminate','on');
            
            if isempty(app.video_neurons)
                app.video_neurons = struct('provenance', {}, 'worldline', {}, 'rois', {});
            end

            [path, ~, ~] = fileparts(app.video_path);
            results_info = h5info(fullfile(path, 'annotations.h5'));

            frame_results = h5read(fullfile(path, 'annotations.h5'), '/t_idx');
            prov_results = h5read(fullfile(path, 'annotations.h5'), '/provenance');
            wlid_results = h5read(fullfile(path, 'annotations.h5'), '/worldline_id');
            x_results = h5read(fullfile(path, 'annotations.h5'), '/x');
            y_results = h5read(fullfile(path, 'annotations.h5'), '/y');
            z_results = h5read(fullfile(path, 'annotations.h5'), '/z');
    
            wl_idx = h5read(fullfile(path, 'worldlines.h5'), '/id');
            wl_name = h5read(fullfile(path, 'worldlines.h5'), '/name');
            wl_col = h5read(fullfile(path, 'worldlines.h5'), '/color');
    
            for n=1:results_info.Datasets(1).Dataspace.Size
                worldline = struct('name', {{}}, 'node', {{}}, 'color', {{}}, 'style', {{}}, 'id', {{}});
                rois = struct();
                idx = size(app.video_neurons, 2) + 1;
                existing_worldline = 0;
    
                target_wl = wl_idx(wl_idx == wlid_results(n)) + 1;
                target_frame = frame_results(n)+1;
                target_x = round(x_results(n) * app.video_info.nx);
                target_y = round(y_results(n) * app.video_info.ny);
                target_z = round(z_results(n));
    
                worldline.name = wl_name(target_wl);
                worldline.color = wl_col(target_wl);

                if isstring(worldline.color)
                    worldline.color = [0.8, 0.8, 0.8];
                end
                
                rois(target_frame).x_slice = target_x;
                rois(target_frame).y_slice = target_y;
                rois(target_frame).z_slice = target_z;
                
                rois(target_frame).xy_pos = [target_x target_y];
                rois(target_frame).xz_pos = [target_x target_z];
                rois(target_frame).yz_pos = [target_z target_y];
                
                rois(target_frame).xy = drawpoint(app.xyAxes, 'Position', [target_x target_y], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(target_frame).xz = drawpoint(app.xzAxes, 'Position', [target_x target_z], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(target_frame).yz = drawpoint(app.yzAxes, 'Position', [target_z target_y], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);                    
    
                for i=1:size(app.video_neurons, 2)
                    if strcmp(app.video_neurons(i).worldline.name, worldline.name)
                        idx = i;
                        existing_worldline = 1;
                        break
                    end
                end
    
                if existing_worldline
                    app.video_neurons(idx).rois(target_frame) = rois(target_frame);
                else
                    provenance = prov_results(n);
                    worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);
                    worldline.style = uistyle("FontColor", worldline.color);
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    app.video_neurons(end+1) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});
                end

                if ~any(app.id_frames == app.tSlider.Value)
                    app.id_frames = [app.id_frames; target_frame];
                end
            end

            app.visual_composer();
            close(d)
        end


        function create_worldlines(app)
        end


        function create_metadata(app)
            % Extract necessary information from the video_info structure
            metadata.shape_t = app.video_info.nt;
            metadata.shape_c = app.video_info.nc;
            metadata.shape_z = app.video_info.nz;
            metadata.shape_y = app.video_info.ny;
            metadata.shape_x = app.video_info.nx;
            
            % Convert the MATLAB structure to JSON text
            jsonText = jsonencode(metadata);
            
            [path, name, ext] = fileparts(app.video_path);

            % Write the JSON text to a file
            fileId = fopen(fullfile(path, 'metadata.json'), 'w');
            if fileId == -1
                error('Failed to open file for writing.');
            end
            fprintf(fileId, '%s', jsonText);
            fclose(fileId);
        end

        function arg_string = compile_arguments(app)
            app.arguments = struct( ...
                'dataset', app.video_path, ...
                'load_checkpoint', 0, ...
                'load_args', 1, ...
                'allow_rotation', app.AllowRotationsSwitch.Value, ...
                'clip_grad', app.clip_grad.Value, ...
                'cuda', app.UseGPUSwitch.Value, ...
                'dimmer_ratio', app.dimmer_ratio.Value, ...
                'exclude_self', 1, ...
                'fovea_sigma', app.fovea_sigma.Value, ...
                'gamma', 2, ...
                'grid_shape', app.grid_shape.Value, ...
                'include_all', 1, ...
                'lambda_d', app.l_d.Value, ...
                'lambda_n', app.l_n.Value, ...
                'lambda_n_mode', app.l_n_mode.Value, ...
                'lambda_t', app.l_t.Value, ...
                'load_nn', 1, ...
                'lr_ceiling', app.lr_ceiling.Value, ...
                'lr_coef', app.lr_coeff.Value, ...
                'lr_floor', app.lr_floor.Value, ...
                'motion_predict', app.MotionPredictionSwitch.Value, ...
                'n_chunks', app.n_chunks.Value, ...
                'n_epoch', app.n_epoch.Value, ...
                'n_epoch_d', app.n_epoch_d.Value, ...
                'n_frame', 1, ...
                'nn_max', app.nn_max.Value, ...
                'save_mode', 'o', ...
                'sort_mode', app.sort_mode.Value, ...
                'z_compensator', app.z_comp.Value);


            % Initialize an empty string for the command line arguments
            arg_string = '';
            
            % Retrieve all field names from the struct
            fields = fieldnames(app.arguments);
            
            % Iterate through each field
            for i = 1:numel(fields)
                field = fields{i}; % Current field name
                value = app.arguments.(field); % Value of the current field
                
                % Check if the value is a numeric type
                if isnumeric(value)
                    valueStr = num2str(value);
                elseif islogical(value)
                    valueStr = num2str(value * 1);
                else
                    valueStr = value;
                end
                
                % Append the current argument to the command line string
                if ischar(value) || isstring(value)
                    arg_string = [arg_string, ' --', field, ' "', valueStr, '"'];
                else
                    arg_string = [arg_string, ' --', field, '=', valueStr];
                end
            end
            
            % Remove the leading space
            if ~isempty(arg_string)
                arg_string = strtrim(arg_string);
            end
        end


        function visual_composer(app, t, z, y, x)

            if ~exist ('t', 'var')
                t = round(app.tSlider.Value);
            end

            if app.OverlaylastIDdframeCheckBox_2.Value
                earlier_frames = app.id_frames(app.id_frames < app.tSlider.Value);
                t = max(earlier_frames);
            end

            if ~exist ('z', 'var')
                z = round(app.hor_zSlider.Value);
            end

            if ~exist('y', 'var')
                y = round(app.xSlider.Value);
            end

            if ~exist ('x', 'var')
                x = round(app.video_info.ny-app.ySlider.Value);
            end

            %fprintf("Loading Frame %.f with Slice %.f where x = %.f and y = %.f.\n", t, z, y, x)

            if t > app.video_info.nt || t < 0
                uiconfirm(app.CELL_ID,'Target frame exceeds video frame count.','Error');
                return
            elseif z > app.video_info.nz || z < 0
                uiconfirm(app.CELL_ID,'Target slice exceeds video slice count.','Error');
                return
            end

            target_frame = h5read(app.video_info.file,'/data',[1 1 1 1 t], app.video_info.ChunkSize);
            %target_frame = permute(target_frame, [2 1 3 4 5]);

            switch app.video_info.nc
                case 1
                    zero_frame = zeros([app.video_info.nx, app.video_info.ny, app.video_info.nz, 3]);
                    zero_frame(:, :, :, 2) = target_frame;
                    target_frame = zero_frame;
                case 2
                    target_frame = cat(4, target_frame, zeros([app.video_info.ny, app.video_info.nx, app.video_info.nz, 1]));
            end

            if app.OverlayFrameMIPCheckBox.Value
                xy_arr = target_frame(:, :, 1, :);

                for j=2:app.video_info.nz
                    xy_arr = xy_arr + target_frame(:, :, j, :);
                end

                xz_arr = target_frame(:, y, :, :);
                yz_arr = target_frame(x, :, :, :);
            else
                xy_arr = target_frame(:, :, z, :);
                xz_arr = target_frame(:, y, :, :);
                yz_arr = target_frame(x, :, :, :);

                xy_arr(:, :, :, 1) = xy_arr(:, :, :, 1) * app.RSlider.Value;
                xy_arr(:, :, :, 2) = xy_arr(:, :, :, 2) * app.GSlider.Value;
                xy_arr(:, :, :, 3) = xy_arr(:, :, :, 3) * app.BSlider.Value;
                
                xz_arr(:, :, :, 1) = xz_arr(:, :, :, 1) * app.RSlider.Value;
                xz_arr(:, :, :, 2) = xz_arr(:, :, :, 2) * app.GSlider.Value;
                xz_arr(:, :, :, 3) = xz_arr(:, :, :, 3) * app.BSlider.Value;
                
                yz_arr(:, :, :, 1) = yz_arr(:, :, :, 1) * app.RSlider.Value;
                yz_arr(:, :, :, 2) = yz_arr(:, :, :, 2) * app.GSlider.Value;
                yz_arr(:, :, :, 3) = yz_arr(:, :, :, 3) * app.BSlider.Value;
            end

            xy_arr = squeeze(xy_arr);
            xz_arr = squeeze(xz_arr);
            yz_arr = permute(squeeze(yz_arr),[2, 1, 3]);

            xy_img = image(app.xyAxes, xy_arr);
            xz_img = image(app.xzAxes, yz_arr);
            yz_img = image(app.yzAxes, xz_arr);

            app.xEditField.Value = app.xSlider.Value;
            app.yEditField.Value = app.video_info.ny-app.ySlider.Value;
            app.zEditField.Value = round(app.hor_zSlider.Value);
            app.tEditField.Value = app.tSlider.Value;

            xy_img.ButtonDownFcn = {@app.ImageClicked};
            xz_img.ButtonDownFcn = {@app.ImageClicked};
            yz_img.ButtonDownFcn = {@app.ImageClicked};

            app.xyAxes.XLim = [1, size(xy_arr, 2)];
            app.xyAxes.YLim = [1, size(xy_arr, 1)];
            app.xzAxes.XLim = [1, size(yz_arr, 2)];
            app.xzAxes.YLim = [1, size(yz_arr, 1)];
            app.yzAxes.XLim = [1, size(xz_arr, 2)];
            app.yzAxes.YLim = [1, size(xz_arr, 1)];

            delete(app.xy_yline)
            delete(app.yz_yline)
            delete(app.xz_yline)

            delete(app.xy_xline)
            delete(app.yz_xline)
            delete(app.xz_xline)

            %app.xzAxes.Position(3) = app.xyAxes.Position(3);
            %app.yzAxes.Position(4) = app.xyAxes.Position(4);

            app.xy_yline = yline(app.xyAxes, x, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.yz_yline = yline(app.yzAxes, x, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.xz_yline = yline(app.xzAxes, app.xzAxes.YLim(2)*(z/app.video_info.nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);

            app.xy_xline = xline(app.xyAxes, y, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.yz_xline = xline(app.yzAxes, app.yzAxes.XLim(2)*(z/app.video_info.nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.xz_xline = xline(app.xzAxes, y, '--', 'color', '#9c9c9c', 'LineWidth', 0.2);

            delete(findobj(app.xyAxes,'Type','images.roi.Point'));
            delete(findobj(app.yzAxes,'Type','images.roi.Point'));
            delete(findobj(app.xzAxes,'Type','images.roi.Point'));

            if any(app.id_frames == app.tSlider.Value)
                app.roi_draw(app.xSlider.Value, app.ySlider.Value, z, t)
            end
        end


        function roi_draw(app, x, y, z, t)
            if app.ShowallneuronsCheckBox.Value
                threshold = 999;
            else
                threshold = 2;
            end

            for n=1:size(app.video_neurons, 2)
                if t <= size(app.video_neurons(n).rois, 2)
                    if z <= app.video_neurons(n).rois(t).z_slice + threshold && z >= app.video_neurons(n).rois(t).z_slice - threshold
                        app.video_neurons(n).rois(t).xy = drawpoint(app.xyAxes, 'Position', app.video_neurons(n).rois(t).xy_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);
                        addlistener(app.video_neurons(n).rois(t).xy, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));
                        addlistener(app.video_neurons(n).rois(t).xy, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));
                    end
    
                    if y <= app.video_neurons(n).rois(t).y_slice + threshold && y >= app.video_neurons(n).rois(t).y_slice - threshold
                        app.video_neurons(n).rois(t).xz = drawpoint(app.xzAxes, 'Position', app.video_neurons(n).rois(t).xz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);
                        addlistener(app.video_neurons(n).rois(t).xz, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));
                        addlistener(app.video_neurons(n).rois(t).xz, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));
                    end
    
                    if x <= app.video_neurons(n).rois(t).x_slice + threshold && x >= app.video_neurons(n).rois(t).x_slice - threshold
                        app.video_neurons(n).rois(t).yz = drawpoint(app.yzAxes, 'Position', app.video_neurons(n).rois(t).yz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', app.video_neurons(n).worldline.color);
                        addlistener(app.video_neurons(n).rois(t).yz, 'ROIClicked', @(src, evt)app.edit_worldline(n, 'select', 0));
                        addlistener(app.video_neurons(n).rois(t).yz, 'ROIMoved', @(src, evt)app.roi_moved(src, n, evt));
                    end
                end
            end
        end


        function arr_mixer(app, arr1, alpha1, arr2, alpha2)
        end


        function frame_state(app)
        end


        function output = query_state(app, obj, mode, state)
        end


        function isNodeFound = app.searchUITreeNodeByName(app, node, nodeName)
            % searchUITreeNodeByName Recursively searches for a node with the given name
            % within a uitreenode structure.
            %
            % Arguments:
            % node     - The current uitreenode to inspect (start with the root node).
            % nodeName - The name of the node to search for.
            %
            % Returns:
            % isNodeFound - A logical value indicating whether the node was found (true) or not (false).
        
            % Initialize the flag to false.
            isNodeFound = [false, ''];
        
            % Check if the current node's name matches the target name.
            if strcmp(node.Text, nodeName)
                % If a match is found, set the flag to true and return.
                isNodeFound = [true, node];
                return;
            end
        
            % If the current node has children, recursively search each child.
            for i = 1:numel(node.Children)
                isNodeFound = app.searchUITreeNodeByName(node.Children(i), nodeName);
                % If the node is found in a subtree, break the loop and return.
                if isNodeFound(1)
                    break;
                end
            end
        end


        function track_InitClickStates(app)
            app.mouse_clicked.double_click_delay = 0.9;
            app.mouse_clicked.click = false;
        end


        function track_ImageClicked(app, src, event)
            point = event.IntersectionPoint;
            double_click = false;

            switch src.Parent.Tag
                case 'xy'
                    app.xSlider.Value = point(1) + 2;
                    app.ySlider.Value = app.video_info.ny-point(2)+3;
                case 'xz'
                    app.xSlider.Value = point(1) + 1.5;
                    app.hor_zSlider.Value = point(2);
                case 'yz'
                    app.ySlider.Value = app.video_info.ny-point(2) + 3;
                    app.hor_zSlider.Value = point(1);
            end

            switch event.Button
                case 1
                    if app.mouse_clicked.click
                        disp('case 1, app.mouse_clicked.click = True, upper!');
                        double_click = true;
                    else
                        app.mouse_clicked.click = true;
                        pause(app.mouse_clicked.double_click_delay);
                        if app.mouse_clicked.click
                            app.visual_composer();
                        end
                    end
                    app.mouse_clicked.click = false;

                case 2
                    double_click = true;
                    disp('case 2');
            end

            % Add a neuron where the user clicked.
            if double_click
                disp('double_click = True');
                app.add_worldline();
            end

            app.visual_composer();
        end


        function add_worldline(app, neuron)
            if isempty(app.video_neurons)
                app.video_neurons = struct('provenance', {}, 'worldline', {}, 'rois', {});
            end

            provenance = 'NPAL';
            worldline = struct('name', {{}}, 'node', {{}}, 'color', {{}}, 'style', {{}}, 'id', {{}});
            rois = struct();
            idx = size(app.video_neurons, 2) + 1;
            existing_worldline = 0;


            % Generate or extract worldline name
            if ~exist('neuron','var')
                worldline.name = string(size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1)+1);
            else
                if ~isempty(neuron.annotation)
                    worldline.name = neuron.annotation;
                else
                    worldline.name = string(size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1)+1);
                end
            end

            % Check if this worldline already exists
            for n=1:size(app.video_neurons, 2)
                if strcmp(app.video_neurons(n).worldline.name, worldline.name)
                    idx = n;
                    existing_worldline = 1;
                    break
                end
            end

            % Populate worldline info
            if ~exist('neuron','var')

                rois(app.tSlider.Value).x_slice = app.xSlider.Value;
                rois(app.tSlider.Value).y_slice = app.ySlider.Value;
                rois(app.tSlider.Value).z_slice = app.hor_zSlider.Value;
                
                rois(app.tSlider.Value).xy_pos = [app.xSlider.Value app.video_info.ny-app.ySlider.Value];
                rois(app.tSlider.Value).xz_pos = [app.xSlider.Value app.hor_zSlider.Value];
                rois(app.tSlider.Value).yz_pos = [app.hor_zSlider.Value app.video_info.ny-app.ySlider.Value];
    
                rois(app.tSlider.Value).xy = drawpoint(app.xyAxes, 'Position', [app.xSlider.Value app.video_info.ny-app.ySlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).xz = drawpoint(app.xzAxes, 'Position', [app.xSlider.Value app.hor_zSlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).yz = drawpoint(app.yzAxes, 'Position', [app.hor_zSlider.Value app.video_info.ny-app.ySlider.Value], 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);

                if existing_worldline
                    app.video_neurons(idx).rois(app.tSlider.Value) = rois(app.tSlider.Value);
                else
                    worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);
                    worldline.color = [0.8, 0.8, 0.8];
                    worldline.style = uistyle("FontColor", worldline.color);
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    app.video_neurons(idx) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});
                end

                if app.AutojumponIDCheckBox.Value
                    app.jumpToUnIDd('frame');
                else
                    app.edit_worldline(size(app.video_neurons, 2), 'select');
                end
            else
                % Extract neuron color. If invalid, use color_readout.
                % If also invalid, use default worldline color (grey).
                if ~isnan(neuron.color(1)) && max(neuron.color) <= 1 && min(neuron.color) >= 0
                    worldline.color = neuron.color(1:3);
                elseif ~isnan(neuron.color_readout(1)) && max(neuron.color_readout) <= 1 && min(neuron.color_readout) >= 0
                    worldline.color = neuron.color_readout(1:3);
                else
                    worldline.color = [0.8, 0.8, 0.8];
                end

                rois(app.tSlider.Value).x_slice = neuron.position(2);
                rois(app.tSlider.Value).y_slice = neuron.position(1);
                rois(app.tSlider.Value).z_slice = neuron.position(3);
                
                rois(app.tSlider.Value).xy_pos = [neuron.position(2) neuron.position(1)];
                rois(app.tSlider.Value).xz_pos = [neuron.position(2) neuron.position(3)];
                rois(app.tSlider.Value).yz_pos = [neuron.position(3) neuron.position(1)];
    
                rois(app.tSlider.Value).xy = drawpoint(app.xyAxes, 'Position', rois(app.tSlider.Value).xy_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).xz = drawpoint(app.xzAxes, 'Position', rois(app.tSlider.Value).xz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                rois(app.tSlider.Value).yz = drawpoint(app.yzAxes, 'Position', rois(app.tSlider.Value).yz_pos, 'MarkerSize', app.MarkerSizeSlider.Value, 'Color', worldline.color);
                
                if existing_worldline
                    app.video_neurons(idx).rois(app.tSlider.Value) = rois(app.tSlider.Value);
                else
                    if Neurons.Hermaphrodite.isCell(worldline.name)
                        worldline.node = uitreenode(app.IDdNode, "Text", worldline.name);
                    else
                        worldline.node = uitreenode(app.UnIDdNode, "Text", worldline.name);
                    end

                    worldline.style = uistyle("FontColor", worldline.color);
                    addStyle(app.WorldlineTree, worldline.style, "node", worldline.node);
                    worldline.node.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    worldline.id = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
                    app.video_neurons(idx) = struct('provenance', {provenance}, 'worldline', {worldline}, 'rois', {rois});
                end
            end

            if ~any(app.id_frames == app.tSlider.Value)
                app.id_frames = [app.id_frames; app.tSlider.Value];
            end
        end


        function edit_worldline(app, idx, mode, new_val)
            app.visual_composer();

            switch mode
                case 'select'
                    app.NameEditField.Value = app.video_neurons(idx).worldline.name;
                    app.XCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos(1);
                    app.YCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos(2);
                    app.ZCoordinateEditField.Value = app.video_neurons(idx).rois(app.tSlider.Value).z_slice;

                    app.xSlider.Value = app.XCoordinateEditField.Value;
                    app.ySlider.Value = app.video_info.ny - app.YCoordinateEditField.Value;
                    app.hor_zSlider.Value = app.ZCoordinateEditField.Value;

                    app.WorldlineIDEditField.Value = idx;
                    app.ColorButton.BackgroundColor = app.video_neurons(idx).worldline.color;
                    app.ProvenanceEditField.Value = app.video_neurons(idx).provenance;
                case 'save'
                    d = uiprogressdlg(app.CELL_ID,'Title','Saving worldline...','Indeterminate','on');
                    existing_idx = -1;

                    for i = 1:length(app.video_neurons)
                        if strcmp(app.video_neurons(i).worldline.name{1}, app.NameEditField.Value)
                            existing_idx = i;
                            break;
                        end
                    end

                    if existing_idx == -1
                        app.video_neurons(idx).worldline.name = app.NameEditField.Value;
                        app.video_neurons(idx).worldline.node.Text = app.video_neurons(idx).worldline.name;
                        app.video_neurons(idx).provenance = app.ProvenanceEditField.Value;
                        app.edit_worldline(idx, 'position', [app.XCoordinateEditField.Value app.YCoordinateEditField.Value app.ZCoordinateEditField.Value])

                        if Neurons.Hermaphrodite.isCell(app.video_neurons(idx).worldline.name)
                            move(app.video_neurons(idx).worldline.node, app.WorldlineTree.Children(1).Children(end), 'after');
                        else
                            move(app.video_neurons(idx).worldline.node, app.WorldlineTree.Children(2).Children(end), 'after');
                        end
                    else
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).x_slice = app.video_neurons(idx).rois(app.tSlider.Value).x_slice;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).y_slice = app.video_neurons(idx).rois(app.tSlider.Value).y_slice;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).z_slice = app.video_neurons(idx).rois(app.tSlider.Value).z_slice;
                        
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).xy_pos = app.video_neurons(idx).rois(app.tSlider.Value).xy_pos;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).xz_pos = app.video_neurons(idx).rois(app.tSlider.Value).xz_pos;
                        app.video_neurons(existing_idx).rois(app.tSlider.Value).yz_pos = app.video_neurons(idx).rois(app.tSlider.Value).yz_pos;

                        delete(app.video_neurons(idx).worldline.node);
                        app.video_neurons(idx) = [];
                    end
                    close(d)
                case 'color'
                    app.video_neurons(idx).worldline.color = new_val;
                    app.video_neurons(idx).worldline.style = uistyle("FontColor", app.video_neurons(idx).worldline.color);
                    addStyle(app.WorldlineTree, app.video_neurons(idx).worldline.style, "node", app.video_neurons(idx).worldline.node);
                case 'name'
                    app.video_neurons(idx).worldline.name = new_val;
                    app.video_neurons(idx).worldline.node.Text = new_val;
                case 'position'
                    app.video_neurons(idx).rois(app.tSlider.Value).x_slice = new_val(1);
                    app.video_neurons(idx).rois(app.tSlider.Value).y_slice = new_val(2);
                    app.video_neurons(idx).rois(app.tSlider.Value).z_slice = new_val(3);
                    
                    app.video_neurons(idx).rois(app.tSlider.Value).xy_pos = [new_val(1) new_val(2)];
                    app.video_neurons(idx).rois(app.tSlider.Value).xz_pos = [new_val(1) new_val(3)];
                    app.video_neurons(idx).rois(app.tSlider.Value).yz_pos = [new_val(3) new_val(2)];
                case 'delete'
                    app.video_neurons(idx) = [];
            end
            
            app.visual_composer();
        end


        function roi_moved(app, src, n, evt)
            switch src
                case app.xyAxes
                    app.edit_worldline(n, 'position', [evt.CurrentPosition(1), evt.CurrentPosition(2), app.video_neurons(n).rois(app.tSlider.Value).z_slice]);
                case app.xzAxes
                    app.edit_worldline(n, 'position', [evt.CurrentPosition(1), app.video_neurons(n).rois(app.tSlider.Value).y_slice, evt.CurrentPosition(2)]);
                case app.yzAxes
                    app.edit_worldline(n, 'position', [app.video_neurons(n).rois(app.tSlider.Value).x_slice, evt.CurrentPosition(2), evt.CurrentPosition(1)]);
            end
        end


        function jumpToUnIDd(app, mode)
            switch mode
                case 'frame'
                case 'tree'
                case 'video'
            end
        end


        function export_arguments(app, filename)
            % Compile arguments.
            app.compile_arguments();

            % Convert the app.arguments struct to a JSON string
            jsonStr = jsonencode(app.arguments);
            
            % Open a file for writing.
            fileId = fopen(filename, 'wt');
            
            % Check if the file was opened successfully
            if fileId == -1
                error('Failed to open file for writing: %s', filename);
            end
            
            % Write the JSON string to the file
            fwrite(fileId, jsonStr, 'char');
            
            % Close the file
            fclose(fileId);
        end


        function import_arguments(app, json)
            % Read the entire content of the JSON file into a string
            jsonStr = fileread(json);
            
            % Check if the JSON string is empty
            if isempty(jsonStr)
                error('The JSON file is empty: %s', json);
            end
            
            % Convert the JSON string back into a MATLAB struct
            app.arguments = jsondecode(jsonStr);
        end

        function saveButtonPushed(app, src, fig, colorButton, targetNode)
            newStyle = uistyle("FontColor",colorButton.BackgroundColor);
            addStyle(app.WorldlineTree,newStyle,"node", app.WorldlineTree.SelectedNodes);
            delete(fig)
        end

        
        function cancelButtonPushed(app, src, fig)
            delete(fig)
        end


        %% Log Functions
        function initLog(app)

            % Grab System Information
            [status,app.OperatingSystemEditField.Value] = system('ver'); %COMPATWARN
            app.MATLABEditField.Value = version;
            app.PythonEditField.Value = pyenv().Version;
            app.NeuroPALEditField.Value = string(Program.ProgramInfo.version);

            %app.ZephIREditField.Value = pyrunfile([pwd,'/External_Dependencies/get_version.py zephir'], 'ver'); %COMPATWARN
            %app.CellTrackerEditField.Value = pyrunfile([pwd,'/External_Dependencies/get_version.py 3dct'], 'ver'); %COMPATWARN

            % Check Files
            %{
            checkedNodes = {};
            checkedNodes = app.dependencyChecker('var', checkedNodes, app.video_file, app.VideoNode); % GCaMP video
            checkedNodes = app.dependencyChecker('var', checkedNodes, app.annotation_file, app.AnnotationsNode); % Annotations.h5
            checkedNodes = app.dependencyChecker('var', checkedNodes, app.metadata_file, app.ZephIRMetaDataNode); % ZephIR metadata
            checkedNodes = app.dependencyChecker('var', checkedNodes, app.activity_file, app.ActivityCSVNode); % Activity CSV
            checkedNodes = app.dependencyChecker('var', checkedNodes, app.model_file, app.ModelNode); % UNET model
            checkedNodes = app.dependencyChecker('var', checkedNodes, app.segmentation_csv, app.NeuronInfoNode); % Segmentation output
            checkedNodes = app.dependencyChecker('var', checkedNodes, app.image_file, app.NeuroPALArrayNode); % NeuroPAL .mat
            checkedNodes = app.dependencyChecker('var', checkedNodes, app.id_file, app.NeuroPALNeuronIDsNode); % NeuroPAL IDs .mat
            app.FileListTree.CheckedNodes = checkedNodes;
            %}

            % Check Dependencies
            checkedNodes = {};
            checkedNodes = app.dependencyChecker('python', checkedNodes, 'zephir', app.ZephIRDependencyNode);
            checkedNodes = app.dependencyChecker('python', checkedNodes, 'pynwb', app.pyNWBNode);

            checkedNodes = app.dependencyChecker('toolbox', checkedNodes, 'Image Processing Toolbox', app.ImageProcessingToolboxNode);
            checkedNodes = app.dependencyChecker('toolbox', checkedNodes, 'Statistics and Machine Learning Toolbox', app.StatisticsandMachineLearningToolboxNode);

            checkedNodes = app.dependencyChecker('folder', checkedNodes, [pwd,'\External_Dependencies\bfmatlab'], app.bioformatsToolboxNode);
            checkedNodes = app.dependencyChecker('folder', checkedNodes, [pwd,'\External_Dependencies\mlapptools-develop'], app.mlapptoolsNode);
            checkedNodes = app.dependencyChecker('folder', checkedNodes, [pwd,'\External_Dependencies\3dct\CellTracker'], app.DCellTrackerNode);

            checkedNodes = app.dependencyChecker('file', checkedNodes, [pwd,'\External_Dependencies\ZephIR\web-wrap'], app.WebWrapNode);
            %app.RequirementsTree.CheckedNodes = checkedNodes;
        end


        function logEvent(app, source, event, notify)
            if app.EnablelogCheckBox.Value
                if ~exist('notify','var')
                    notify = 0;
                end
            
                time = datetime("now");
                log_string = sprintf("%s (%s): %s", time, source, event);
            
                app.Log.Value = [app.Log.Value(:); log_string(:)];
            
                if ~ismac
                    log_file = fopen([pwd,'/log.txt'], 'a+'); % COMPATWARN
                    fclose(log_file);
                end
            
                if notify==1 && app.EnablelognotificationsCheckBox.Value
                    hLabel = uilabel('Parent', app.CELL_ID, 'Text', log_string, 'Position', [app.CELL_ID.Position(3)-500, app.TabGroup.Position(4)-23, 500, 23], 'FontColor', [0 0 0], 'HorizontalAlignment', 'right');
                    
                    t = timer('TimerFcn', @(myTimerObj, thisEvent) app.fadeLog(myTimerObj, hLabel), ...
                              'Period', 0.1, ...
                              'ExecutionMode', 'fixedRate', ...
                              'TasksToExecute', 50);
                    
                    start(t);
                end
            end
        end


        function fadeLog(app, t, hLabel)
            currentColor = hLabel.FontColor;
            newColor = min(currentColor + [0.02 0.02 0.02], [0.9 0.9 0.9]);
            hLabel.FontColor = newColor;
        
            if all(newColor == [0.9 0.9 0.9])
                delete(hLabel);
                stop(t);
                delete(t);
            end
        end

        
        % Helper function that passes nwbsave.mlapp data back to CELL_ID
        % POLISHWARN
        function process_nwb_data(app, input)
            app.nwb_data = input;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hack: close that weird window that pops up for no reason.
            % Note: I have to do this because Matlab sucks.
            close all;

            % Using mlapptools because Matlab refuses to give me focus control.
            % This warning needs to shut up :-)
            % mlapptools.getWebWindow (line 337)
            warning off MATLAB:ui:javaframe:PropertyToBeRemoved;

            d = uiprogressdlg(app.CELL_ID,"Title","NeuroPAL ID","Message","Starting NeuroPAL_ID...",'Indeterminate','on');
            app.logEvent('Main','Starting NeuroPAL_ID...', 1);
            app.logEvent('Main','Resizing NeuroPAL_ID...', 1);

            % Resize the figure to fit most of the screen size.
            screen_size = get(groot, 'ScreenSize');
            screen_size = screen_size(3:4);
            screen_margin = floor(screen_size .* [0.07,0.05]);
            figure_size(1:2) = screen_margin / 2;
            figure_size(3) = screen_size(1) - screen_margin(1);
            figure_size(4) = screen_size(2) - 2*screen_margin(2);
            drawnow; % need to draw before resizing
            app.CELL_ID.Position = figure_size;

            % Set up placeholder buttons
            button_locations = app.CELL_ID.Position-app.ImageProcessingTab.InnerPosition;
            button_locations(1:2) = [1 1];
            
            app.ProcessingButton.Parent = app.ProcessingGridLayout.Parent;
            app.ProcessingButton.Position = button_locations;
            set(app.ProcessingButton, 'Visible', 'on');

            app.IdButton.Parent = app.IdGridLayout.Parent;
            app.IdButton.Position = button_locations;
            set(app.IdButton, 'Visible', 'on');

            app.TrackingButton.Parent = app.VideoGridLayout.Parent;
            app.TrackingButton.Position = button_locations;
            set(app.TrackingButton, 'Visible', 'on');

            % Load the application preferences.
            is_loaded = Program.GUIPreferences.load();

            % Add the path corresponding to the models.
            if ~isdeployed
                [path, ~, ~] = fileparts(mfilename('fullpath'));
                addpath(genpath([path, filesep, 'Data']));
            end

            % Log the change.
            if exist('TraceHistory', 'class')
                app.log = TraceHistory.Instance;
                app.log.setup({'startupFcn'});
            end

            % Are we using the parallelization toolbox?
            parallel_tb = ver('parallel');
            if isempty(parallel_tb)
                app.is_parallel = false;
            end

            % Initialize the structured properties.
            InitClickStates(app);
            InitNeuronMarker(app);
            DisableImageGUI(app);

            % Initialize the neuron birth times.
            [app.hermaphrodite_neurons.names, ...
                app.hermaphrodite_neurons.birth_stages, ~] = ...
                Neurons.NeuronBirth.getHermaphroditeBirthText();
            [app.male_neurons.names, ...
                app.male_neurons.birth_stages, ~] = ...
                Neurons.NeuronBirth.getMaleBirthText();

            app.logEvent('Main','Setting up GUI...', 1);
            % Setup the GUI.
            if is_loaded
                GUIPrefs = Program.GUIPreferences.instance();

                % Are we showing the neuron birth times?
                if GUIPrefs.is_show_birth_times
                    app.ToggleBirthTimesMenu.Text = 'Hide Birth Times';
                end

                % Are auto-completing neuron names?
                app.AutoNameCheckBox.Value = GUIPrefs.is_auto_name;

                % Are we auto-updating neuron IDs?
                if ~GUIPrefs.is_autoID_updates
                    app.ToggleAutoIDUpdatesMenu.Text = 'Enable Auto-ID Updates';
                end

                % Are we using matching pursuit (MP) to detect neurons?
                if ~GUIPrefs.is_MP_detect
                    app.ToggleNeuronDetectionMenu.Text = 'Use MP-Detect Neurons';
                end

                % This is a new user, give them some help!
            else

                answer = uiconfirm(app.CELL_ID, ...
                    {['Welcome to NeuroPAL ID!'], ...
                    ['Please read the instructions very carefully.']}, ...
                    'NeuroPAL ID Instructions', 'Options', {'OK'}, ...
                    'DefaultOption', 1, 'CancelOption', 1, 'Icon', 'info');

                % Show the instructions.
                try
                    if isdeployed
                        DataHandling.PNGViewer.show('Instructions.png', ...
                            'Software Instructions');
                    else
                        DataHandling.PNGViewer.show('./Data/Documents/Instructions.png', ...
                            'Software Instructions');
                    end
                catch ME
                    msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                    uialert(app.CELL_ID, ...
                        {'Cannot open instructions!', '', 'Error:', msg}, ...
                        'Display Instructions', 'Icon', 'error');
                    return;
                end

                % Save the GUI preferences.
                Program.GUIPreferences.save();
            end

            close(d);
        end

        % Callback function: IdButton, OpenButton, OpenMenu
        function OpenFile(app, event)

            % Are we already opening a file?
            if app.is_opening_file
                return;
            end
            app.is_opening_file = true;
            
            % Unselect any neurons.
            UnselectNeuron(app);
            if ~isempty(app.id_file) && exist(app.id_file, 'file')
                app.SaveIDToFile();
            end

            % Setup the file chooser's path.
            %path = '../';
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.mat;*.czi;*.nd2;*.tif;*.tiff;*.h5;*.nwb'];
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            [name, path, ~] = uigetfile(file_info, 'Select Worm Image');
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if name == 0
                app.is_opening_file = false;
                return; % user cancelled
            end
            filename = [path, name];
            app.logEvent('Main',sprintf('Loading file from %s...', filename), 1)

            % Save the path in our preferences.
            GUI_prefs.image_dir = path;
            GUI_prefs.save();

            % Load the file.
            d = uiprogressdlg(app.CELL_ID,'Title','Loading file...',...
        'Indeterminate','on');
            try                
                [data, info, prefs, worm, mp, neurons, np_file, id_file] = ...
                    DataHandling.NeuroPALImage.open(filename);
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {['Cannot read "' filename '"!'], ['Error:' msg]}, ...
                    'Image File Failure', 'Icon', 'error');
                return;
            end

            % Check the worm info.
            if ~any(strcmp(worm.body, app.BodyDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm body region "' worm.body '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                return;
            end
            if ~any(strcmp(worm.age, app.AgeDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm age region "' worm.age '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                return;
            end
            if ~any(strcmp(worm.sex, app.SexDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm sex region "' worm.sex '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                return;
            end

            % Fix the prefs for z-axis orientation.
            if ~isfield(prefs, 'z_center')
                prefs.z_center = ceil(size(data,3) / 2);
                prefs.is_Z_LR = true;
                prefs.is_Z_flip = true;
            end

            % Setup the file.
            app.image_file = np_file;
            app.id_file = [];
            app.image_prefs = prefs;

            % Setup the image.
            app.image_name = name; %strrep(name, '_', '\_');
            app.image_data = data;

            % Z-score the image.
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Load and update the gamma.
            gamma_size = length(app.gamma_RGBW_DIC_GFP_index);
            if length(prefs.gamma) == 1
                app.image_gamma = ones(gamma_size, 1);
                app.image_gamma(1:3) = prefs.gamma;
                app.image_prefs.gamma = app.image_gamma;
            elseif length(prefs.gamma) < gamma_size
                app.image_gamma = ones(gamma_size, 1);
                app.image_gamma(1:length(prefs.gamma)) = prefs.gamma;
                app.image_prefs.gamma = app.image_gamma;
            else
                app.image_gamma = prefs.gamma;
            end

            % Load the image scale and info.
            app.image_um_scale = info.scale;
            app.image_info = info;

            % Setup the color channels.
            RGBW = prefs.RGBW;
            RGBW_nan = isnan(RGBW);
            RGBW(RGBW_nan) = 1; % default unassigned colors to channel 1
            channels_str = arrayfun(@num2str, 1:size(app.image_data, 4), 'UniformOutput', false);
            % Red.
            app.RDropDown.Items = channels_str;
            app.RDropDown.Value = app.RDropDown.Items{RGBW(1)};
            app.RCheckBox.Value = true;
            % Green.
            app.GDropDown.Items = channels_str;
            app.GDropDown.Value = app.GDropDown.Items{RGBW(2)};
            app.GCheckBox.Value = true;
            % Blue.
            app.BDropDown.Items = channels_str;
            app.BDropDown.Value = app.BDropDown.Items{RGBW(3)};
            app.BCheckBox.Value = true;
            % White.
            app.WDropDown.Items = channels_str;
            if size(app.image_data, 4)>3
                app.WDropDown.Value = app.WDropDown.Items{RGBW(4)};
            end
            app.WCheckBox.Value = false;
            % DIC.
            app.DICDropDown.Items = channels_str;
            if ~isnan(prefs.DIC)
                app.DICDropDown.Value = app.DICDropDown.Items{prefs.DIC};
            end
            app.DICCheckBox.Value = false;
            % GFP.
            app.GFPDropDown.Items = channels_str;
            if ~isnan(prefs.GFP)
                app.GFPDropDown.Value = app.GFPDropDown.Items{prefs.GFP};
            end
            app.GFPCheckBox.Value = false;

            % Setup the worm info.
            app.worm = worm;
            app.BodyDropDown.Value = worm.body;
            app.AgeDropDown.Value = worm.age;
            app.SexDropDown.Value = worm.sex;
            app.StrainEditField.Value = worm.strain;
            app.NotesEditField.Value = worm.notes;

            % Which part of the worm is this?
            %if isempty(worm.body)
            %    worm.body = uiconfirm(app.CELL_ID, ...
            %        'Which part of the worm is in the image?', ...
            %        'Worm Body Part', 'Options', {'Head', 'Tail'}, ...
            %        'DefaultOption', 1, 'Icon', 'question');
            %   	%'Worm Body Part', 'Head', 'Midbody', 'Tail', 'Head');
            %    save(app.image_file, 'prefs', '-append');
            %end

            % Enable the image GUI.
            EnableImageGUI(app);
            DisableNeuronGUI(app);

            % Determine the image scale.
            scale = ones(1,3);
            if ~isempty(info.scale)
                scale = info.scale;
            end

            % Did we detect neurons?
            app.id_file = id_file;
            app.mp_params = mp;
            read_nwb_neurons = 0;
            if ~isempty(neurons)
                app.image_neurons = neurons;
            elseif contains(filename,'.nwb')
                
                nwb_data = nwbRead(filename);

                if any(ismember(nwb_data.processing.keys, 'NeuroPAL')) & (any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'NeuroPALSegmentation')) | any(ismember(nwb_data.processing.get('NeuroPAL').nwbdatainterface.keys, 'ImageSegmentation')) | any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'VolumeSegmentation')) | any(ismember(nwb_data.processing.get('NeuroPAL').dynamictable.keys, 'NeuroPALNeurons')))
                    read_nwb_neurons = 1;
                end             

                app.image_neurons = Neurons.Image([], worm.body, 'scale', app.image_um_scale');
            else
                app.image_neurons = Neurons.Image([], worm.body, 'scale', app.image_um_scale');
            end

            % Enable the neuron GUI.
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                EnableNeuronGUI(app);
            end

            % Restrict the slider to the z stack.
            num_z_slices = size(app.image_data, 3);
            z_slices = 1:num_z_slices;
            if num_z_slices <= 1
                uialert(app.CELL_ID, 'The image is not a volume!', ...
                    'Image Not a Volume', 'Icon', 'error');
                return;
            else
                z_labels = arrayfun(@(z) num2str(z, '%.1f'), ...
                    (z_slices-1) * info.scale(3), 'UniformOutput', false);
            end
            app.ZSlider.Limits = [1, num_z_slices];
            app.ZSlider.MajorTicks = z_slices;
            app.ZSlider.MajorTickLabels = z_labels;

            % Setup the z-axis orientation.
            app.ZCenterEditField.Value = round((prefs.z_center - 1) * info.scale(3), 1);
            if prefs.is_Z_LR
                app.ZAxisDropDown.Value = 'L/R';
                app.ZLeftLabel.Text = 'LEFT';
                app.ZRightLabel.Text = 'RIGHT';
            else
                app.ZAxisDropDown.Value = 'D/V';
                app.ZLeftLabel.Text = 'DORSAL';
                app.ZRightLabel.Text = 'VENTRAL';
            end

            % Setup the max projection.
            daspect(app.XY,[1 1 1]);
            daspect(app.MaxProjection,[1 1 1]);
            axis(app.MaxProjection, 'off');

            % Constrain the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];

            % Label the image.
            app.XY.Title.Interpreter = 'none';
            app.XY.Title.String = app.image_name;
            app.XY.TitleFontSizeMultiplier = 2;
            app.XY.TitleFontWeight = 'bold';
            x_ticks = linspace(0, size(app.image_data, 2), 15);
            y_ticks = linspace(0, size(app.image_data, 1), 5);
            app.XY.XTick = x_ticks;
            app.XY.YTick = y_ticks;
            x_labels = arrayfun(@(x) num2str(x, '%.1f'), x_ticks * info.scale(1), 'UniformOutput', false);
            y_labels = arrayfun(@(y) num2str(y, '%.1f'), y_ticks * info.scale(2), 'UniformOutput', false);
            x_labels{1} = '0';
            y_labels{1} = '0';
            app.XY.XTickLabel = x_labels;
            app.XY.YTickLabel = flip(y_labels);

            % Select no neurons.
            app.selected_neuron = [];

            % Draw everything.
            app.UnselectNeuron();
            app.UserNeuronIDsListBox.Items = {};
            app.UserNeuronIDsListBox.ItemsData = [];
            app.UserNeuronIDsListBox.Value = {};
            app.DrawImageData();
            app.UpdateSexNeuronLists();
            if ~isempty(app.image_neurons)
                app.UpdateNeuronLists();
                app.DrawAutoIDList();
            end

            if read_nwb_neurons == 1
                app.load_neurons_from_nwb(nwb_data);
            end

            % Go to the middle.
            zEvent.Value = round(num_z_slices / 2);
            app.ZSliderValueChanging(zEvent);
            close(d)

            addlistener(app.CELL_ID, 'WindowMousePress', @(src, event) app.DragManager('down', event));
            addlistener(app.CELL_ID, 'WindowMouseRelease', @(src, event) app.DragManager('up', event));
            
            % Done.
            app.is_opening_file = false;

            set(app.ProcColormapButton, 'Enable', 'on');
            app.ProcColormapButton.Value = 1;
            set(app.IdGridLayout, 'Visible', 'on');
            set(app.ProcessingGridLayout, 'Visible', 'on');
            set(app.IdButton, 'Visible', 'off');
            set(app.ProcessingButton, 'Visible', 'off');
        end

        % Menu selected function: DownsampleImageMenu
        function DecimateButtonPushed(app, event)
            downsample_window(app, app.image_data, app.image_view, app.image_gamma, app.image_neurons);        
        end

        % Menu selected function: ThresholdImageMenu
        function AdaptThreshButtonPushed(app, event)
            % Open a propt to ask the adaptive thresholding parameters
            % from user.
            prompt = {'Enter the method (adaptive/fixed/otsu):', 'Enter the parameter for the model:'};
            title = 'Thresholding Parameters';
            dims = [1 35];
            definput = {'adaptive', '0.9'};
            answer = inputdlg(prompt,title,dims,definput);

            % Run thresholding based on user input.
            app.image_data = Methods.Preprocess.threshold_frame(app.image_data, answer{1}, str2double(answer{2}));
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            
            % Update the view using the thresholded image.
            app.DrawImageData();
        end

        % Callback function: HistogramMatchingButton, HistogramMatchingMenu
        function HistMatchButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,"Indeterminate","on", "Message", 'Matching Histograms');
            % Histogram matching for color normalization. The function
            % has no parameters.
            app.image_data(:, :, :, app.image_info.RGBW(1:3)) = Methods.run_histmatch(app.image_data, app.image_info.RGBW);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            
            % Update the view using the histogram matched image.
            app.DrawImageData();
            close(d)
        end

        % Callback function: AutoHistogramButton, AutoHistogramMenu
        function AutoHistogram(app, event)
            % Auto Adjust the histogram.
            RGB = app.image_prefs.RGBW(1:3);
            app.image_data(:,:,:,RGB) = imadjustn(app.image_data(:,:,:,RGB));
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            
            % Update the view using the histogram matched image.
            app.DrawImageData();
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)

            % Snap the slider into place.
            z = round(event.Value);
            app.ZSlider.Value = z;

            % Draw the neurons in this z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end

        % Button pushed function: AutoDetectButton
        function AutoDetectButtonPushed(app, event)

            % Determine the number of neurons.
            body = app.BodyDropDown.Value;
            num_neurons = app.neuron_info.numNeurons(body);
            if isempty(num_neurons)
                return;
            end

            % Do we already have neurons detected?
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                answer = uiconfirm(app.CELL_ID, ...
                    'Neurons have already been marked, would you like to overwrite them?', ...
                    'Neurons Already Marked', ...
                    'Options', {'Overwrite', 'Cancel'}, 'DefaultOption', 2, 'CancelOption', 2, ...
                    'Icon', 'warning');
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Unselect neurons.
            UnselectNeuron(app);
            app.UserNeuronIDsListBox.Items = {};
            app.UserNeuronIDsListBox.ItemsData = [];
            app.UserNeuronIDsListBox.Value = {};

            % Detect the neurons.
            GUI_prefs = Program.GUIPreferences.instance();
            if GUI_prefs.is_MP_detect
                [sp, app.mp_params] = Methods.AutoDetect.instance().detect(app.image_file, ...
                    app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                    num_neurons, 1.0, app.image_um_scale', app.worm, 2);
            else
                [sp, app.mp_params] = Methods.NNDetect.detect(app.image_file, ...
                    app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));
            end

            % Do we have any neuron predictions?
            if isempty(sp)

                % Warn the user.
                uiconfirm(app.CELL_ID, ...
                    'Auto detect neurons failed to find any neurons!', ...
                    'Auto Detect Neurons Failed', ...
                    'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'error');

                % Enable the neuron GUI.
                if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                    EnableNeuronGUI(app);
                end
                return;
            end

            % Add the detected neurons to the image.
            app.image_neurons = Neurons.Image(sp, app.worm.body, 'scale', app.image_um_scale');

            % Remove duplicate detections.
            % Neurons within 2 microns of each other.
            Methods.Utils.removeNearbyNeurons(app.image_neurons, 2, 2);

            % Save the IDs.
            SaveIDToFile(app);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Enable the neuron GUI.
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                EnableNeuronGUI(app);
            end

            % Draw the image.
            app.DrawImageData();

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Auto detect neurons completed successfully.', 'Auto Detect Neurons Completed', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Button pushed function: AutoIDAllButton
        function AutoIDAllButtonPushed(app, event)

            % Are there any neurons?
            if isempty(app.image_neurons) || app.image_neurons.num_neurons() < 1
                uiconfirm(app.CELL_ID, ...
                    'There are no neurons to auto ID!', 'No Neurons to ID', ...
                    'Options', {'Cancel'}, 'DefaultOption', 1, 'CancelOption', 1, ...
                    'Icon', 'warning');
                return;
            end

            % Can we handle this body part?
            %body = app.image_neurons.bodypart; DEPRECATED
            body = app.worm.body;
            switch lower(body)
                case 'head'
                    % all good
                case 'tail'
                    % all good
                otherwise
                    uiconfirm(app.CELL_ID, ...
                        {['Presently, there is no atlas to auto ID the "' body '".'], ...
                        'Please manually ID this region.'}, ...
                        'No Atlas for Auto ID', 'Options', {'Cancel'}, ...
                        'DefaultOption', 1, 'CancelOption', 1, 'Icon', 'error');
                    return;
            end

            % Are the neurons already ID'd?
            if ~isempty(app.image_neurons.get_probabilistic_probs())
                answer = uiconfirm(app.CELL_ID, ...
                    'The neurons have already been auto ID''d, would you like to re-ID them?', ...
                    'Neurons Already Auto ID''d', ...
                    'Options', {'Re-ID', 'Cancel'}, 'DefaultOption', 2, 'CancelOption', 2, ...
                    'Icon', 'warning');
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Unselect neurons.
            UnselectNeuron(app);

            % Auto ID the neurons.
            try
                Methods.AutoId.instance().id(app.image_file, ...
                    app.image_neurons, app.worm);
            catch E
                uiconfirm(app.CELL_ID, ...
                    {E.message}, ...
                    'Error', 'Options', {'Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 1, 'Icon', 'error');
                return;
            end

            % Update the auto ID'd neuron list.
            DrawAutoIDList(app);

            % Draw the image.
            app.DrawImageData();

            % Save the IDs.
            SaveIDToFile(app);

            % Update ID confidences
            UpdateNeuronIDConfidence(app);

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Auto ID neurons completed successfully.', 'Auto ID Neurons Completed', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Callback function
        function AutoNameNeurons(app, event)
            % Save the GUI preferences.
            GUI_prefs = Program.GUIPreferences.instance();
            GUI_prefs.is_auto_name = app.AutoNameCheckBox.Value;
            GUI_prefs.save();
        end

        % Callback function: NormalizeColorsButton, NormalizeColorsMenu
        function ZScoringMenuSelected(app, event)
            app.image_data = Methods.Preprocess.zscore_frame(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            app.DrawImageData();
        end

        % Menu selected function: RotateAllHorizontalMenu
        function RotateAllHorizontal(app, event)
            % Rotate the image and neurons pi degrees around x-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [pi,0,0]);
            app.image_data = app.image_neurons.rotate_X_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateAllVerticalMenu
        function RotateAllVertical(app, event)
            % Rotate the image and neurons pi degrees around y-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [0,pi,0]);
            app.image_data = app.image_neurons.rotate_Y_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateAllClockwiseMenu
        function RotateAllClockwise(app, event)
            % Rotate the image and neurons pi/2 degrees around z-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [pi/2,0,0]);
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_Z_90(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateAllAntiClockwiseMenu
        function RotateAllAntiClockwise(app, event)
            % Rotate the image and neurons -pi/2 degrees around z-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [-pi/2,0,0]);
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_Z_270(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateImageHorizontalMenu
        function RotateImageHorizontal(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi degrees around x-axis.
            app.image_data = app.image_neurons.rotate_image_X_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateImageVerticalMenu
        function RotateImageVertical(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi degrees around y-axis.
            app.image_data = app.image_neurons.rotate_image_Y_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateImageClockwiseMenu
        function RotateImageClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi/2 degrees around z-axis.
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_image_Z_90(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateImageAntiClockwiseMenu
        function RotateImageAntiClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image -pi/2 degrees around z-axis.
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_image_Z_270(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsHorizontalMenu
        function RotateNeuronsHorizontal(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons pi degrees around x-axis.
            app.image_neurons.rotate_neurons_X_180(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsVerticalMenu
        function RotateNeuronsVertical(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons pi degrees around x-axis.
            app.image_neurons.rotate_neurons_Y_180(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsClockwiseMenu
        function RotateNeuronsClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons pi/2 degrees around z-axis.
            app.image_neurons.rotate_neurons_Z_90(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsAntiClockwiseMenu
        function RotateNeuronsAntiClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons -pi/2 degrees around z-axis.
            app.image_neurons.rotate_neurons_Z_270(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: MirrorImageMenu
        function MirrorImage(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to mirror the image separately from the neurons?', ...
                'Mirror Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Mirror the image around the z-axis.
            app.image_data = app.image_neurons.mirror_image_Z(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: MirrorNeuronsMenu
        function MirrorNeurons(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to mirror the neurons separately from the image?', ...
                'Mirror Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Mirror the neurons around the z-axis.
            app.image_neurons.mirror_neurons_Z(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Value changed function: AutoIDDropDown
        function AutoIDDropDownValueChanged(app, event)
            % Update the user ID field with the chosen value.
            value = app.AutoIDDropDown.Value;
            names = strsplit(value,'=');
            app.IDEditField.Value = names{1};

            % Send focus to the user ID field.
            SendFocus(app, app.IDEditField);
        end

        % Value changed function: UserNeuronIDsListBox
        function AutoNeuronIDListBoxValueChanged(app, event)

            % Find the neuron's rank.
            neuron_rank = app.UserNeuronIDsListBox.Value;

            % Select the neuron.
            [~, neuron_i] = app.image_neurons.find_rank(neuron_rank);
            SelectNeuron(app, neuron_i);

            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            SendFocus(app, app.IDEditField);
        end

        % Key press function: CELL_ID
        function CELL_IDKeyPress(app, event)

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % What did the user press?
            key = event.Key;
            switch key

                case '0' % Toggle the ID atlas.
                    app.ColorAtlasCheckBox.Value = ~app.ColorAtlasCheckBox.Value;
                    app.DrawImageData();

                case '1' % Toggle the red channel.
                    app.RCheckBox.Value = ~app.RCheckBox.Value;
                    app.DrawImageData();

                case '2' % Toggle the green channel.
                    app.GCheckBox.Value = ~app.GCheckBox.Value;
                    app.DrawImageData();

                case '3' % Toggle the blue channel.
                    app.BCheckBox.Value = ~app.BCheckBox.Value;
                    app.DrawImageData();

                case '4' % Toggle the white channel.
                    app.WCheckBox.Value = ~app.WCheckBox.Value;
                    app.DrawImageData();

                case '5' % Toggle the DIC channel.
                    app.DICCheckBox.Value = ~app.DICCheckBox.Value;
                    app.DrawImageData();

                case '6' % Toggle the GFP channel.
                    app.GFPCheckBox.Value = ~app.GFPCheckBox.Value;
                    app.DrawImageData();

                case 'leftarrow' % Go up in the z stack.
                    if app.ZSlider.Value > 1
                        zEvent.Value = app.ZSlider.Value - 1;
                        app.ZSliderValueChanging(zEvent);
                    end
                    %disp(key);

                case 'rightarrow' % Go down in the z stack.
                    if app.ZSlider.Value < app.ZSlider.Limits(2)
                        zEvent.Value = app.ZSlider.Value + 1;
                        app.ZSliderValueChanging(zEvent);
                    end
                    %disp(key);

                case 'uparrow' % Zoom in.
                    zoom(app.XY, 1 + app.zoom_percent);

                case 'downarrow' % Zoom out.
                    zoom(app.XY, 1 - app.zoom_percent);

                case {'h','H'} % Toggle half zoom.
                    % Reset x-axis limits.
                    app.XY.XLim = [1, size(app.image_data, 2)];

                    % Upper -> lower half.
                    ylims = app.XY.YLim;
                    if ylims(1) < size(app.image_data, 1)/2
                        app.XY.YLim = [ceil(size(app.image_data, 1)/2), ...
                            size(app.image_data, 1)];

                        % Lower -> upper half.
                    else
                        app.XY.YLim = [1, floor(size(app.image_data, 1)/2)];
                    end

                case {'j','J'} % Toggle third zoom.
                    % Reset x-axis limits.
                    app.XY.XLim = [1, size(app.image_data, 2)];

                    % Upper -> middle third.
                    ylims = app.XY.YLim;
                    if ylims(1) < size(app.image_data, 1)/3
                        app.XY.YLim = [ceil(size(app.image_data, 1)/3), ...
                            floor(size(app.image_data, 1)*2/3)];

                        % Middle -> lower third.
                    elseif ylims(1) < size(app.image_data, 1)*2/3
                        app.XY.YLim = [ceil(size(app.image_data, 1)*2/3), ...
                            size(app.image_data, 1)];

                        % Lower -> upper third.
                    else
                        app.XY.YLim = [1, floor(size(app.image_data, 1)/3)];
                    end

                case {'k','K'} % Toggle quarter zoom.
                    % Reset x-axis limits.
                    app.XY.XLim = [1, size(app.image_data, 2)];

                    % Upper -> upper-middle quarter.
                    ylims = app.XY.YLim;
                    if ylims(1) < size(app.image_data, 1)/4
                        app.XY.YLim = [ceil(size(app.image_data, 1)/4), ...
                            floor(size(app.image_data, 1)*2/4)];

                        % Upper-middle -> lower-middle quarter.
                    elseif ylims(1) < size(app.image_data, 1)*2/4
                        app.XY.YLim = [ceil(size(app.image_data, 1)*2/4), ...
                            floor(size(app.image_data, 1)*3/4)];

                        % Lower-middle -> lower quarter.
                    elseif ylims(1) < size(app.image_data, 1)*3/4
                        app.XY.YLim = [ceil(size(app.image_data, 1)*3/4), ...
                            size(app.image_data, 1)];

                        % Lower -> upper quarter.
                    else
                        app.XY.YLim = [1, floor(size(app.image_data, 1)/4)];
                    end

                case {'p','P'} % Toggle image panning.

                    % Toggle image panning.
                    if app.is_panning
                        pan(app.XY,'off');
                        pause(0.5);
                        RestorePointer(app);
                        app.is_panning = false;
                    else
                        pan(app.XY,'on');
                        pause(0.5);
                        app.is_panning = true;
                    end

                case {'r','R'} % Reset the figure to center.
                    app.XY.XLim = [1, size(app.image_data, 2)];
                    app.XY.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XLim = [1, size(app.image_data, 2)];
                    %app.XY.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XAxisLocation = 'bottom';
                    %app.XY.YAxisLocation = 'left';

                    %case {'x','X'} % Switch mouse to crosshairs.
                    %zoom(app.XY,'on');
                    %pause(0.2);
                    %zoom(app.XY,'off');

                case {'i','I'} % Toggle showing neuron IDs.
                    app.show_labels = ~app.show_labels;
                    DrawZSlice(app, app.ZSlider, app.image_view, app.XY);

                case {'a', 'A'} % Toggle autocompleting neuron names.
                    app.AutoNameCheckBox.Value = ~app.AutoNameCheckBox.Value;
                    AutoNameNeurons(app, event);

                case {'b','B'} % Toggle showing neuron birth times.
                    ToggleNeuronBirthTimes(app, event);

                otherwise
                    % Do nothing.
            end

        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            % ZSliderValueChanging(app, event);
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            app.DrawImageData();
        end

        % Close request function: CELL_ID
        function CELL_IDCloseRequest(app, event)

            % Unselect any neurons.
            app.UnselectNeuron(false);
            if ~isempty(app.id_file) && exist(app.id_file, 'file')
                app.SaveIDToFile();
            end

            % Save the log file for further 'number of user clicks' analysis.
            if ~isempty(app.log)
                log = app.log;
                [path, name, ~] = fileparts(app.image_file);
                directory = [path, filesep, 'logs', filesep];
                if ~exist(directory, 'dir')
                    mkdir(directory);
                end
                save([directory,name, '_',strrep(datestr(datetime('now')),':','_'), '.mat'], 'log');
            end

            % Done.
            delete(app);
        end

        % Callback function
        function SaveButtonPushed(app, event)
            % Save the final result of running MP and annotations in file.
            SaveIDToFile(app);
        end

        % Callback function
        function RotateSliderValueChanged(app, event)
            % Read the rotation angle.
            value = app.RotateSlider.Value;

            % Rotate image and all the neurons -> see Image.m
            app.image_data = app.image_neurons.rotate(app.image_data, [value,0,0]);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            % Rotated image might have different dimensions so here we are updating the ZLimits.
            app.ZSlider.Limits = [1, size(app.image_data, 3)];
            app.ZSliderS.Limits = [1, size(app.image_data, 3)];

            % Bring the rotate slider back to zero.
            app.RotateSlider.Value = 0;

            % Update the views.
            app.DrawImageData();
        end

        % Callback function
        function AutoDetectCheckBoxValueChanged(app, event)
            value = app.AutoDetectCheckBox.Value;
            app.image_neurons.add_meta_data('auto_detect', value);
        end

        % Value changed function: BDropDown, DICDropDown, GDropDown, 
        % ...and 3 other components
        function UpdateColorChannels(app, event)
            app.logEvent('Main','Updating color channels...', 1);

            % Are we opening a file?
            if app.is_opening_file
                return;
            end

            % Redraw the image.
            app.DrawImageData();

            app.ProcRDropDown.Value = app.RDropDown.Value;
            app.ProcGDropDown.Value = app.GDropDown.Value;
            app.ProcBDropDown.Value = app.BDropDown.Value;
            app.ProcWDropDown.Value = app.WDropDown.Value;
            app.ProcDICDropDown.Value = app.DICDropDown.Value;
            app.ProcGFPDropDown.Value = app.GFPDropDown.Value;

            % Initialize the updated color channels.
            red = str2double(app.RDropDown.Value);
            green = str2double(app.GDropDown.Value);
            blue = str2double(app.BDropDown.Value);
            white = str2double(app.WDropDown.Value);
            dic = str2double(app.DICDropDown.Value);
            gfp = str2double(app.GFPDropDown.Value);
            old_RGBW = app.image_prefs.RGBW;
            new_RGBW = [red, green, blue, white];
            app.image_prefs.RGBW = new_RGBW;
            app.image_prefs.DIC = dic;
            app.image_prefs.GFP = gfp;

            % Update the neurons.
            app.image_neurons.update_colors(app.image_data_zscored, old_RGBW, new_RGBW);
            if ~isequaln(old_RGBW, new_RGBW)
                app.SaveIDToFile();
            end

            % Save the updated preferences.
            prefs = app.image_prefs;
            save(app.image_file, 'prefs', '-append');
        end

        % Value changing function: IDEditField
        function IDEditFieldChanging(app, event)
            value = event.Value;

            % Space means done.
            if length(value) > 0 && isspace(value(end))
                SaveUserID(app, value);
            end
        end

        % Button pushed function: UserIDButton
        function UserIDButtonPushed(app, event)
            SaveUserID(app, app.IDEditField.Value);
        end

        % Selection changed function: GanglionTree
        function GanglionTreeSelectionChanged(app, event)

            % Update the ID'd & UnID'd neurons list.
            app.IDdNeuronsListBox.Value = {};
            app.IDdLabel.BackgroundColor = 'none';
            app.IDdLabel.FontColor = [0,0,0];
            app.UnIDdNeuronsListBox.Value = {};
            app.UnIDdLabel.BackgroundColor = 'none';
            app.UnIDdLabel.FontColor = [0,0,0];
            UnselectNeuron(app);
            UpdateNeuronLists(app);
        end

        % Value changed function: IDdNeuronsListBox
        function IDdNeuronsListChanged(app, event)

            % Show the selected NeuroPAL neuron.
            value = app.IDdNeuronsListBox.Value;
            if ~isempty(value)

                % Get the neuron name.
                % Remove birth_time, confidence, and ON/OFF.
                space_i = find(value == ' ', 1);
                if ~isempty(space_i)
                    value = value(1:space_i-1);
                end
                if endsWith(value, ' ?')
                    value = value(1:(end-2));
                end
                value = app.neuron_info.stripOnOff(value);

                % No color available.
                color = app.neuron_info.getNeuronColor(value);
                if isempty(color)
                    app.IDdLabel.BackgroundColor = [0.95,0.95,0.95];
                    app.IDdLabel.FontColor = [0,0,0];

                    % Show the selected NeuroPAL neuron's color.
                else
                    app.IDdLabel.BackgroundColor = app.neuron_info.getNeuronColor(value);

                    % Make the font visible against the background color.
                    if mean(color) > 0.5
                        app.IDdLabel.FontColor = [0,0,0];
                    else
                        app.IDdLabel.FontColor = [1,1,1];
                    end
                end

                % Select the neuron & show its name.
                [neuron, neuron_i] = app.image_neurons.find_user_id_neuron(value);
                if ~isempty(neuron_i)

                    % Select the neuron.
                    SelectNeuron(app, neuron_i);

                    % Is the neuron ON/OFF?
                    name = neuron.annotation;

                    switch neuron.is_annotation_on
                        case false
                            name = [name '-OFF'];
                        case true
                            name = [name '-ON'];
                    end

                    % Is the user uncertain about the ID?
                    if neuron.annotation_confidence <= 0.5
                        name = [name '?'];
                    end

                    % Is the neuron emphasized?
                    if neuron.is_emphasized
                        name = [name '!'];
                    end

                    % Show the neuron's ID.
                    DrawImageLabels(app, neuron.position, {name});
                end
            end
        end

        % Double-clicked callback: IDdNeuronsListBox
        function IDdNeuronsListBoxDoubleClicked(app, event)
            name = app.IDdNeuronsListBox.Items{event.InteractionInformation.Item};

            if strcmp(app.VolTrace.Visible,'on')
                app.updateBrowser(name);
            end
            
        end

        % Value changed function: UnIDdNeuronsListBox
        function UnIDdNeuronsListChanged(app, event)

            % Show the selected NeuroPAL neuron's color.
            value = app.UnIDdNeuronsListBox.Value;
            if ~isempty(value)

                % Get the neuron name. Remove birth_time.
                space_i = find(value == ' ', 1);
                if ~isempty(space_i)
                    value = value(1:space_i-1);
                end

                % Get the neuron color.
                color = app.neuron_info.getNeuronColor(value);

                % No color available.
                if isempty(color)
                    app.UnIDdLabel.BackgroundColor = [0.95,0.95,0.95];
                    app.UnIDdLabel.FontColor = [0,0,0];

                    % Set the color.
                else
                    app.UnIDdLabel.BackgroundColor = app.neuron_info.getNeuronColor(value);

                    % Make the font visible against the background color.
                    if mean(color) > 0.5
                        app.UnIDdLabel.FontColor = [0,0,0];
                    else
                        app.UnIDdLabel.FontColor = [1,1,1];
                    end
                end
            end
        end

        % Button pushed function: AutoIDButton
        function AutoIDButtonPushed(app, event)
            % Save the chosen value.
            value = app.AutoIDDropDown.Value;
            if isempty(value)
                return;
            end
            names = upper(strsplit(value,'='));
            SaveAutoID(app, names{1});
        end

        % Menu selected function: DeleteUserIDsMenu
        function DeleteUserIDs(app, event)
            answer = uiconfirm(app.CELL_ID, ...
                {'Delete all the neuron''s user IDs???', 'You will lose all your work!'}, ...
                'Delete All Neuron User IDs', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Delete all user IDs.
            if strcmpi(answer, 'yes')

                % Delete all user IDs.
                UnselectNeuron(app);
                app.image_neurons.delete_annotations();

                % Save the updated IDs.
                SaveIDToFile(app);

                % Redraw the Z-slice.
                DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
            end
        end

        % Menu selected function: DeleteModelIDsMenu
        function DeleteModelIDs(app, event)
            answer = uiconfirm(app.CELL_ID, ...
                'Delete all the neuron''s model IDs?', ...
                'Delete All Neuron Model IDs', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Delete all user IDs.
            if strcmpi(answer, 'yes')

                % Delete all model IDs.
                UnselectNeuron(app);
                app.image_neurons.delete_model_IDs();

                % Save the updated IDs.
                SaveIDToFile(app);

                % Uncheck the atlas.
                app.ColorAtlasCheckBox.Value = false;

                % Draw the auto ID neuron uncertainty list.
                app.UserNeuronIDsListBox.Items = {};
                app.UserNeuronIDsListBox.Value = {};

                % Redraw the Z-slice.
                DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
            end
        end

        % Menu selected function: ToggleAutoIDUpdatesMenu
        function ToggleAutoIDUpdates(app, event)
            % Toggle disabling or enabling neuron auto-ID updates.
            GUI_prefs = Program.GUIPreferences.instance();
            if contains(lower(app.ToggleAutoIDUpdatesMenu.Text), 'disable')
                GUI_prefs.is_autoID_updates = false;
                app.ToggleAutoIDUpdatesMenu.Text = 'Enable Auto-ID Updates';
            else
                GUI_prefs.is_autoID_updates = true;
                app.ToggleAutoIDUpdatesMenu.Text = 'Disable Auto-ID Updates';
            end

            % Save the GUI preferences.
            GUI_prefs.save();
        end

        % Menu selected function: ToggleNeuronDetectionMenu
        function ToggleNeuronDetection(app, event)
            % Toggle matching-pursuit (MP) vs neural-network (NN)
            % neuron detection.
            GUI_prefs = Program.GUIPreferences.instance();
            if contains(lower(app.ToggleNeuronDetectionMenu.Text), 'mp')
                GUI_prefs.is_MP_detect = true;
                app.ToggleNeuronDetectionMenu.Text = 'Use NN-Detect Neurons';
            else
                GUI_prefs.is_MP_detect = false;
                app.ToggleNeuronDetectionMenu.Text = 'Use MP-Detect Neurons';
            end

            % Save the GUI preferences.
            GUI_prefs.save();
        end

        % Value changed function: ZAxisDropDown
        function ZAxisChanged(app, event)

            % Are we opening a file?
            if app.is_opening_file
                return;
            end

            % Set the Z-axis to be L/R.
            value = app.ZAxisDropDown.Value;
            if strcmp(value, 'L/R')
                app.image_prefs.is_Z_LR = true;
                app.ZLeftLabel.Text = 'LEFT';
                app.ZRightLabel.Text = 'RIGHT';

                % Set the Z-axis to be D/V.
            else
                app.image_prefs.is_Z_LR = false;
                app.ZLeftLabel.Text = 'DORSAL';
                app.ZRightLabel.Text = 'VENTRAL';
            end

            % Save the update.
            if ~app.is_opening_file
                prefs = app.image_prefs;
                save(app.image_file, 'prefs', '-append');
            end
        end

        % Button pushed function: FlipZButton
        function FlipZButtonPushed(app, event)

            % Flip the Z-axis.
            app.image_prefs.is_Z_flip = ~app.image_prefs.is_Z_flip;

            % Save the update.
            prefs = app.image_prefs;
            save(app.image_file, 'prefs', '-append');

            % Redraw the Z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end

        % Value changed function: ZCenterEditField
        function ZCenterChanged(app, event)

            % Are we opening a file?
            if app.is_opening_file
                return;
            end

            % Did the center change?
            value = app.ZCenterEditField.Value;
            z_center = round(value / app.image_um_scale(3)) + 1;
            if z_center == app.image_prefs.z_center
                return;
            end

            % Is the center in range?
            min_z = round(app.image_um_scale(3), 1);
            max_z = round((size(app.image_data,3) - 2) * app.image_um_scale(3), 1);
            if value < min_z || value > max_z
                uiconfirm(app.CELL_ID, ...
                    {[num2str(value, '%.1f') ' is out of range!'], ...
                    ['The z-axis center must be between [' num2str(min_z, '%.1f') ',' num2str(max_z, '%.1f')  '].']}, ...
                    'Z-Axis Center Error', 'Options', {'Ok'}, 'DefaultOption', 1, 'CancelOption', 1, ...
                    'Icon', 'error');

                % Restore the previous value.
                value = round((app.image_prefs.z_center - 1) * app.image_um_scale(3), 1);
                app.ZCenterEditField.Value = value;
            end

            % Change the center of the Z-axis.
            app.image_prefs.z_center = z_center;

            % Save the update.
            prefs = app.image_prefs;
            save(app.image_file, 'prefs', '-append');

            % Redraw the Z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end

        % Menu selected function: ManualMenu
        function ManualArtifactRemoval(app, event)
            % Give the user instruction on how to remove artifacts.
            answer = uiconfirm(app.CELL_ID, ...
                {'To manually remove artifacts:', ...
                '1. Use the cursor to add vertices, drawing', ...
                '   a polygon around the image artifacts.', ...
                '2. When done, click the 1st point of your polygon', ...
                '   or hit the return key on your keyboard.'}, ...
                'Manually Remove Artifacts', 'Options', {'Remove Artifacts', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'info');

            % Keep the existing file.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Unselect any neurons.
            app.UnselectNeuron(false);

            % Manually trace the artifacts.
            mask = Methods.Preprocess.manual_artifact_removal(app.image_view);
            if isempty(mask)
                return;
            end

            % Remove any neurons in the artifact polygon.
            if ~isempty(app.image_neurons) && ~isempty(app.image_neurons.neurons)

                % Remove the neurons in the polygon.
                neurons = app.image_neurons.neurons;
                positions = app.image_neurons.get_positions();
                z = app.ZSlider.Value;
                if app.image_prefs.is_Z_flip
                    z = size(app.image_data,3) - z + 1;
                end
                inside_mask = mask(sub2ind(size(mask), round(positions(:,1)), round(positions(:,2))));
                app.image_neurons.neurons = neurons(~(positions(:,3) < z+1.5 & positions(:,3) > z-1.5 & inside_mask));

                % Redraw the auto ID list.
                app.DrawAutoIDList();
            end
            mask = repmat(mask,1,1,size(app.image_data,3),length(app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));

            % Remove artifacts.
            tmp = app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW)));
            tmp(mask) = 0;
            app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))) = tmp;

            % Z-score the image.
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            app.DrawImageData();
        end

        % Menu selected function: AutomaticMenu
        function AutomaticArtifactRemoval(app, event)

            % Find artifacts.
            [~, mask_raw] = Methods.Preprocess.filter_gut_lysosomes(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));
            mask = repmat(mask_raw,1,1,1,length(app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));

            % Remove artifacts.
            tmp = app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW)));
            tmp(mask) = 0;
            app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))) = tmp;
            app.image_data(mask) = 0;

            % Z-score the image.
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            app.DrawImageData();

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Automatic artifact removal completed successfully.', ...
                'Automatically Remove Artifacts', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Menu selected function: BatchDetectIDNeuronsMenu
        function BatchIDNeurons(app, event)
            %% Purpose: batch detect+ID neurons for all image files in a directory.

            % Setup the file chooser's path.
            % Note: don't change the user's preferred path.
            %path = '../../';
            path = Program.GUIPreferences.instance().image_dir;

            % Ask the user which folder they want to use.
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            folder = uigetdir(path, 'Select Worm Image Folder');
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if folder == 0
                return; % user cancelled
            end

            % Determine the image files.
            dir_files = dir(folder);
            files = {dir_files.name};
            formats = {'.czi', '.nd2', '.lif', '.tif', '.tiff', '.h5'};
            files_i = find(endsWith(files, formats, 'IgnoreCase', true));
            dir_files = dir_files(files_i);
            num_files = length(dir_files);

            % Are there any files?
            if num_files < 1
                uiconfirm(app.CELL_ID, ...
                    {'No image file present in:', folder}, ...
                    'No Image Files Present', ...
                    'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'error');
                return;
            end

            % Did we already detect & ID neurons in these files?
            for i = num_files

                % Construct the ID file.
                name = dir_files(i).name;
                name_dot_i = strfind(name, '.');
                name_dot_i = name_dot_i(end);
                name = [name(1:(name_dot_i - 1)), '_ID.mat'];
                id_file = [dir_files(i).folder '/' name];

                % Does the ID file exist?
                if exist(id_file, 'file')
                    answer = uiconfirm(app.CELL_ID, ...
                        {'Some of the ID files already exist!', ' ', 'Do you want to overwrite them?'}, ...
                        'Overwrite ID Files', 'Options', {'Yes', 'Cancel'}, ...
                        'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                    % Keep the existing file.
                    if strcmpi(answer, 'cancel')
                        return;
                    end

                    % Done.
                    break;
                end
            end

            % What is the age of these worms?
            title = 'Worm Age';
            prompt  = 'What is the age of these worms?';
            list = app.AgeDropDown.Items;
            list = list(~cellfun(@isempty, list));
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            [age_i,~] = listdlg('PromptString', prompt, 'Name', title, ...
                'SelectionMode', 'single', 'ListString', list);
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if isempty(age_i)
                return;
            end
            age = list{age_i};

            % What sex are these worms?
            title = 'Worm Sex';
            prompt  = 'What is the sex of these worms?';
            list = app.SexDropDown.Items;
            list = list(~cellfun(@isempty, list));
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            [sex_i,~] = listdlg('PromptString', prompt, 'Name', title, ...
                'SelectionMode', 'single', 'ListString', list);
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if isempty(sex_i)
                return;
            end
            sex = list{sex_i};

            % Which section of the body are we detecting?
            title = 'Worm Body Section';
            prompt  = 'Which section of the worm is in these images?';
            list = app.BodyDropDown.Items;
            list = list(~cellfun(@isempty, list));
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            [body_i,~] = listdlg('PromptString', prompt, 'Name', title, ...
                'SelectionMode', 'single', 'ListString', list);
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if isempty(body_i)
                return;
            end
            body = list{body_i};

            % Create the worm info.
            worm_info.age = age;
            worm_info.sex = sex;
            worm_info.body = body;
            worm_info.strain = '';
            worm_info.notes = '';

            % Determine the number of neurons.
            GUI_prefs = Program.GUIPreferences.instance();
            num_neurons = app.neuron_info.numNeurons(body);
            if isempty(num_neurons) && GUI_prefs.is_MP_detect
                uialert(app.CELL_ID, ...
                    {['The body section "' body '" is not yet supported.'], ' ', ...
                    'Please use the "Neurons" menu to select "Use NN-Detect Neurons".'}, ...
                    'Unsupported Body Section');
                return;
            end

            % Setup the file names.
            files = cell(length(dir_files),1);
            for i = 1:length(files)
                files{i} = [dir_files(i).folder '/' dir_files(i).name];
            end

            % Batch detect the neurons.
            is_parallel = app.is_parallel;
            if is_parallel

                % Start the parallel pool.
                if isempty(app.parallel_pool) || ...
                        ~isvalid(app.parallel_pool) || ...
                        ~app.parallel_pool.Connected
                    app.parallel_pool = parpool('local');
                end

                % Setup the progress bar.
                wait_title = 'Detecting Neurons';
                wb = waitbar(0, 'Initializing ...', 'Name', wait_title);
                wb.Children.Title.Interpreter = 'none';
            end
            for i = 1:num_files

                % Batch detect the neurons.
                if is_parallel
                    if GUI_prefs.is_MP_detect
                        funcs(i) = parfeval(... %app.parallel_pool, ...
                            @Methods.AutoDetect.BatchDetect, 0, files{i}, worm_info, num_neurons);
                    else
                        funcs(i) = parfeval(... %app.parallel_pool, ...
                            @Methods.NNDetect.BatchDetect, 0, files{i}, worm_info, num_neurons);
                    end
                    waitbar(0, wb, ...
                        ['Detecting neurons in ' num2str(i) ' files.'], ...
                        'Name', wait_title);
                else
                    if GUI_prefs.is_MP_detect
                        Methods.AutoDetect.BatchDetect(files{i}, worm_info, num_neurons);
                    else
                        Methods.NNDetect.BatchDetect(files{i}, worm_info, num_neurons);
                    end
                end
            end

            % Wait for each task to complete.
            if is_parallel
                for i = 1:num_files
                    funcs_i = fetchNext(funcs);

                    % Update the progress.
                    progress = double(i)/num_files;
                    waitbar(progress, wb, {['Completed: ' files{funcs_i}], ...
                        sprintf('%d%% completed ...', progress*100)}, ...
                        'Name', wait_title);
                end

                % Terminate the existing parallel session.
                delete(gcp('nocreate'));

                % Done.
                try
                    close(wb);
                catch
                    warning('The detection was canceled.');
                end
            end

            % Can we ID this body part?
            if ~Methods.AutoId.isBodyID(sex, body)
                uialert(app.CELL_ID, ...
                    {['The body section "' body '" is not yet supported.'], ' ', ...
                    'Neurons in this section of the body cannot yet be identified.'}, ...
                    'No ID for Body Section');
                return;
            end

            % Batch ID the neurons.
            is_parallel = false; % auto ID uses its own parallelization & cannot be called with a parallel pool.
            if is_parallel
                delete funcs;
                %pool = gcp(); % may want to have a ready-use pool
            end
            for i = 1:num_files

                % Batch detect the neurons.
                if is_parallel
                    funcs(i) = parfeval(... %app.parallel_pool, ...
                        @Methods.AutoId.BatchId, 0, files{i}, worm_info);
                else
                    Methods.AutoId.BatchId(files{i}, worm_info);
                end
            end

            % Wait for each task to complete.
            if is_parallel
                for i = 1:num_files
                    fetchNext(funcs);
                end

                % Terminate the existing parallel session.
                delete(gcp('nocreate'));
            end

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Batch auto detect + ID neurons completed successfully.', ...
                'Batch Auto Detect + ID Neurons', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Callback function: SaveIDInfoMenu, SaveIDsButton
        function SaveIDs2CSV(app, event)
            %%  Purpose: save the IDs, position, & colors to a CSV file.

            % Do we have any neurons ID'd?
            if ~app.image_neurons.is_any_auto_ID() && ~app.image_neurons.is_any_annotated()
                uialert(app.CELL_ID, ...
                    'No neurons have been assigned an ID!',...
                    'No Neurons ID''d', 'Icon', 'warning');
                return;
            end

            % Warn the user to align the neurons.
            answer = uiconfirm(app.CELL_ID, ...
                {'Aligned neuron colors + positions REQUIRE', ...
                'correct auto ID''s for analysis!', ' ', ...
                'Do you want to (re-)run auto ID prior to saving the IDs?'}, ...
                'Neuron Color + Position', 'Options', {'Yes', 'No', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 3, 'Icon', 'info');
            if strcmpi(answer, 'cancel')
                return;
            elseif strcmpi(answer, 'yes')
                app.AutoIDAllButtonPushed(event);
            end

            % Create the filename "<filename>_IDs.csv".
            % Is there already a file with this name & does the user want to overwrite it?
            % *** if the file exists & they don't want to overwrite, cancel the operation.
            filename = app.image_file;
            suffixI = strfind(filename, '.');
            suffixI = suffixI(end);
            csvfile = cat(2, filename(1:(suffixI - 1)), '.csv');
            if exist(csvfile, 'file')
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' csvfile '" already exists!'], ' ', 'Do you want to overwrite it?'}, ...
                    'Overwrite ID File', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Keep the existing file.
                if strcmpi(answer, 'cancel')
                    return;
                end
            end
            delete(csvfile);

            % Write the CSV file.
            try
                Output.ImageAnalysis.saveID2CSV(csvfile, app.image_prefs, ...
                    app.image_data, app.image_data_zscored, app.image_neurons, app.image_um_scale);
            catch ME
                % Error.
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {['Failed to write neuron IDs to "' csvfile '"!'], ['Error:' msg]}, ...
                    'Neuron ID File Failure', 'Icon', 'error');
            end

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Saving neuron IDs completed successfully.', ...
                'Save Neurons IDs', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Callback function
        function ColorAtlasCheckBoxValueChanged(app, event)
            app.DrawImageData();
        end

        % Value changed function: BodyDropDown
        function BodyDropDownValueChanged(app, event)

            % Update the neurons.
            value = app.BodyDropDown.Value;
            if isempty(app.image_neurons)
                app.image_neurons = Neurons.Image([], value, 'scale', app.image_um_scale');
                %else DEPRECATED
                %    app.image_neurons.bodypart = value;
            end

            % Save the changes.
            app.worm.body = value;
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
            app.SaveIDToFile();
        end

        % Value changed function: AgeDropDown
        function AgeDropDownValueChanged(app, event)

            % Update the worm's age.
            app.worm.age = app.AgeDropDown.Value;
            %UpdateSexNeuronLists(app);

            % Save the changes.
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Value changed function: SexDropDown
        function SexDropDownValueChanged(app, event)

            % Update the worm's sex.
            app.worm.sex = app.SexDropDown.Value;
            UpdateSexNeuronLists(app);

            % Save the changes.
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Value changed function: StrainEditField
        function StrainEditFieldValueChanged(app, event)
            value = app.StrainEditField.Value;
            app.worm.strain = value;
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Value changed function: NotesEditField
        function NotesEditFieldValueChanged(app, event)
            value = app.NotesEditField.Value;
            app.worm.notes = value;
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Menu selected function: CheckforUpdatesMenu
        function CheckforUpdatesMenuSelected(app, event)
            % Get the latest version info.
            prog_ver = Program.ProgramInfo.version;
            try
                latest_ver = Program.ProgramInfo.checkUpdates();
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot check online version!', '', 'Error:', msg}, ...
                    'Software Update Check', 'Icon', 'error');
                return;
            end

            % The software is out of date.
            if latest_ver > prog_ver
                answer = uiconfirm(app.CELL_ID, ...
                    {'A newer version of NeuroPAL ID is available!', 'Please download it.'}, ...
                    'Software Update Check', 'Options', {'Download', 'OK'}, ...
                    'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'info');

                % Go to the website.
                if strcmp(answer, 'Download')
                    web(Program.ProgramInfo.website_URL, '-browser');
                end

                % The software is up-to-date.
            else
                uialert(app.CELL_ID, ...
                    'You have the latest version of NeuroPAL ID.', ...
                    'Software Update Check', 'Icon', 'success');
            end
        end

        % Menu selected function: SaveIDImageMenu
        function SaveIDImageMenuSelected(app, event)
            % Is there an image loaded?
            data = app.image_view;
            if isempty(data)
                uialert(app.CELL_ID, 'No image!', 'Save ID Image', 'Icon', 'error');
                return;
            end

            % Initialize the neurons & scale.
            neurons = app.image_neurons.user_id_neurons();
            um_scale = app.image_um_scale;

            % Determine the filename.
            image_filename = app.image_file;
            file = strrep(image_filename, '.mat', '');

            % Is there already a file with this name & does the user want to overwrite it?
            % *** if the file exists & they don't want to overwrite, cancel the operation.
            pdf_file = [file '_1.pdf'];
            if exist(pdf_file, 'file')
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' pdf_file '" already exists!'], ' ', 'Do you want to overwrite it?'}, ...
                    'Overwrite ID Image File', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Keep the existing file.
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Print the neuron ID image(s).
            is_error = false;
            try
                Output.Illustration.saveIDImage(file, data, ...
                    neurons, um_scale, app.image_prefs.is_Z_flip);
            catch ME
                % Error.
                is_error = true;
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {['Failed to save ID images to "' file '"!'], ['Error:' msg]}, ...
                    'Save ID Image', 'Icon', 'error');
            end

            % Done.
            if ~is_error
                uiconfirm(app.CELL_ID, ...
                    'Neuron ID images saved.', ...
                    'Save Neuron ID Images', ...
                    'Options', {'OK'}, 'DefaultOption', 1, ...
                    'Icon', 'success');
            end
        end

        % Callback function
        function SaveIDCartoonMenuSelected(app, event)
            % Is there an image loaded?
            if isempty(app.image_data)
                uialert(app.CELL_ID, 'No image!', 'Save ID Cartoon', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            CELL_IDCloseRequest(app, event);
        end

        % Menu selected function: KeyboardShortcutsMenu
        function KeyboardShortcutsMenuSelected(app, event)
            try
                if isdeployed
                    DataHandling.PNGViewer.show('Keyboard.png', ...
                        'Keyboard Shortcuts');
                else
                    DataHandling.PNGViewer.show('./Data/Documents/Keyboard.png', ...
                        'Keyboard Shortcuts');
                end
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot open instructions!', '', 'Error:', msg}, ...
                    'Display Instructions', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            try
                if isdeployed
                    DataHandling.PNGViewer.show('About.png', ...
                        'About NeuroPAL ID');
                else
                    DataHandling.PNGViewer.show('./Data/Documents/About.png', ...
                        'About NeuroPAL ID');
                end
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot open instructions!', '', 'Error:', msg}, ...
                    'Display Instructions', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: InstructionsMenu
        function InstructionsMenuSelected(app, event)
            try
                if isdeployed
                    DataHandling.PNGViewer.show('Instructions.png', ...
                        'Software Instructions');
                else
                    DataHandling.PNGViewer.show('./Data/Documents/Instructions.png', ...
                        'Software Instructions');
                end
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot open instructions!', '', 'Error:', msg}, ...
                    'Display Instructions', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: GFPColorMenu
        function GFPColorMenuSelected(app, event)
            color = Program.GUIPreferences.inputGFPColor();
            if ~isempty(color)

                % Save the preferences.
                Program.GUIPreferences.save();

                % Redraw the image.
                if app.GFPCheckBox.Value
                    app.DrawImageData();
                end
            end
        end

        % Menu selected function: NeuronDotsMenu
        function NeuronDotsMenuSelected(app, event)
            dot = Program.GUIPreferences.inputNeuronDot();
            if ~isempty(dot)
                Program.GUIPreferences.save();

                % Redraw the Z-slice.
                DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
            end
        end

        % Menu selected function: ReportaBugMenu
        function ReportBug(app, event)
            answer = uiconfirm(app.CELL_ID, ...
                {'We welcome bug reports to improve the software.', ...
                ' ', ...
                'You MUST follow these instructions for bug reports:', ...
                '1. Ensure you have the latest software.', ...
                '   Your bug may have already been fixed.', ...
                '2. Review the software instructions.', ...
                '   Your issue may not be a bug.', ...
                '3. Ensure the bug has NOT already been reported.', ...
                '4. Report EXACTLY what happened on the bug website:', ...
                '  A. Your OS and the NeuroPAL ID software version.', ...
                '  B. What were you doing when the bug occurred?', ...
                '  C. What was the software supposed to do?', ...
                '  D. What did the software actually do?', ...
                '  E. Provide both the "*.mat" and "*_ID.mat"', ...
                '     files associated with the bug.', ...
                '  F. If you received an error message, provide it.'}, ...
                'Report a Bug', 'Options', {'Report', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'info');

            % Go to the website.
            if strcmp(answer, 'Report')
                web(Program.ProgramInfo.bug_URL, '-browser');
            end
        end

        % Menu selected function: NeuroPALWebsiteMenu
        function NeuroPALWebsiteMenuSelected(app, event)
            web(Program.ProgramInfo.website_URL, '-browser');
        end

        % Menu selected function: AllChannels
        function AllChannelsMenuSelected(app, event)
            % Ask the user for the gamma adjustment.
            prompt = {'Gamma adjustment:'};
            dlgtitle = 'Adjust Gamma';
            dims = [1,20];
            definput = {num2str(mean(app.image_gamma))};
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            % Adjust the gamma.
            if ~isempty(answer)

                % Sanitize the input.
                gamma = str2double(answer);
                gamma = max(gamma,0);
                gamma = min(gamma,1);

                % Save the new gamma.
                app.image_gamma(1:end) = gamma;
                app.image_prefs.gamma(1:end) = gamma;
                prefs = app.image_prefs;
                save(app.image_file, 'prefs', '-append');

                % Update the views.
                app.DrawImageData();
            end
        end

        % Menu selected function: IndividualChannels
        function IndividualChannelsMenuSelected(app, event)
            % Ask the user for the gamma adjustment.
            prompt = {'R:', 'G:', 'B:', 'W:', 'DIC:', 'GFP:'};
            dlgtitle = 'Adjust Gamma by Channel';
            dims = [1,20];
            gamma = app.image_gamma;
            prompt = prompt(1:length(gamma));
            definput = arrayfun(@(x) num2str(x), gamma, 'UniformOutput', false);
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            % Adjust the gamma.
            if ~isempty(answer)

                % Sanitize the input.
                gamma = str2double(answer);
                if size(gamma,1) < size(gamma,2)
                    gamma = gamma';
                end
                gamma = max(gamma,0);
                gamma = min(gamma,1);

                % Save the new gamma.
                app.image_gamma = gamma;
                app.image_prefs.gamma = gamma;
                prefs = app.image_prefs;
                save(app.image_file, 'prefs', '-append');

                % Update the views.
                app.DrawImageData();
            end
        end

        % Menu selected function: ToggleBirthTimesMenu
        function ToggleNeuronBirthTimes(app, event)
            % Toggle showing or hiding neuron birth times.
            GUI_prefs = Program.GUIPreferences.instance();
            if contains(lower(app.ToggleBirthTimesMenu.Text), 'show')
                GUI_prefs.is_show_birth_times = true;
                app.ToggleBirthTimesMenu.Text = 'Hide Birth Times';
            else
                GUI_prefs.is_show_birth_times = false;
                app.ToggleBirthTimesMenu.Text = 'Show Birth Times';
            end

            % Save the preferences.
            GUI_prefs.save();

            % Update the display.
            UpdateNeuronLists(app)
        end

        % Callback function
        function ImportfromcsvMenuSelected(app, event)
            
            % Display expected formatting.
            DataHandling.PNGViewer.show('./Data/Documents/csv-format.png', 'Expected CSV formatting');
            uiwait(msgbox('Please note that depending on the number of neurons being imported, this may take a while.','NeuroPAL'));

            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Select CSV file.');

            imported_neurons = readtable(join([path,name]),'NumHeaderLines',1);

            for r = 1:size(imported_neurons.Var4)
                adjusted_coords = flip([imported_neurons.Var5(r) imported_neurons.Var4(r)]) + app.mouse_offset;
                app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                    [adjusted_coords imported_neurons.Var3(r)], app.mp_params.hnsz, app.image_um_scale');
            end

            msgbox("Finished importing neurons.","Success");

            SaveIDToFile(app);

        end

        % Callback function: AdjustHistogramMenu, AdjustHistogramsButton
        function AdjustHistogramMenuSelected(app, event)
            app.logEvent('Main','Loading histogram GUI...', 1);
            histogram_window(app, app.image_data, app.image_neurons, app.image_view, app.image_gamma, Program.GUIPreferences.instance(), app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.DICDropDown.Value, app.GFPDropDown.Value);
        end

        % Menu selected function: AutoSegmentationMenu
        function AutoSegmentationMenuSelected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
            app.logEvent('Main','Loading auto segmentation GUI...', 1);
            autoseg_window(app, app.image_data, app.image_neurons, app.image_view, app.image_gamma, app.image_um_scale, app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.DICDropDown.Value, app.GFPDropDown.Value, path, app.gamma_RGBW_DIC_GFP_index)
        end

        % Callback function: ColorRegisterMenu, RegisterColorsButton
        function ColorRegisterMenuSelected(app, event)
            app.logEvent('Main','Loading color registration GUI...', 1);
            channel_selector(app, app.image_data, app.image_gamma, app.image_prefs, app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.GFPDropDown.Value, app.DICDropDown.Value)
        end

        % Menu selected function: FlipLRNeuronsMenu
        function FlipLRNeuronsMenuSelected(app, event)
            if contains(lower(app.worm.sex), 'xo') % Check if male or hermaphrodite
                for n=1:size(app.image_neurons.neurons,2) % Go through all neurons
                    app.image_neurons.neurons(n).annotation = Neurons.Male.flipLR(app.image_neurons.neurons(n).annotation); % Run every neuron through L/R flip appropriate for gender.
                end
            else
                for n=1:size(app.image_neurons.neurons,2) % Go through all neurons
                    app.image_neurons.neurons(n).annotation = Neurons.Hermaphrodite.flipLR(app.image_neurons.neurons(n).annotation); % Run every neuron through L/R flip appropriate for gender.
                end
            end            
        end

        % Callback function: TraceActivityMenu_2, TrackingButton
        function TraceActivityMenu_2Selected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
            fullpath = join([GUI_prefs.image_dir,app.image_name]);
            
            app.logEvent('Main','Loading tracking GUI...', 1)

            file_info = [fullpath ';*.h5;*.nd2'];
            [name, path, ~] = uigetfile(file_info, 'Select video array.');
            app.video_path = [path, name];

            d = uiprogressdlg(app.CELL_ID,'Title','Loading video...','Indeterminate','on');
            app.parent_app = app;

            if ~isdeployed
                app.script_dir = fullfile(pwd, '/+Wrapper/');
                app.script_ext = '.py';
            else
                if ispc
                    app.script_dir = fullfile(pwd, '\lib\bin\windows\');
                    app.script_ext = '.exe';
                elseif ismac
                    ctfroot_path = ctfroot;
                    for i = 1:4
                        ctfroot_path = fileparts(ctfroot_path);
                    end
                    app.script_dir = fullfile(ctfroot_path, 'lib/bin/macos/');
                    app.script_ext = '';
                end
            end

            %% GUI Initialization

            % Click Handler
            app.InitClickStates();

            app.AdjustNeuronMarkerAlignmentPanel.Parent = app.DefaultTabGroup.Parent;
            app.AdjustNeuronMarkerAlignmentPanel.Layout = app.DefaultTabGroup.Layout;

            app.AdvancedParameterPanel.Parent = app.BasicParameterPanel.Parent;
            app.AdvancedParameterPanel.Layout = app.BasicParameterPanel.Layout;
            
            app.GridSearchPanel.Parent = app.AdvancedParameterPanel.Parent;
            app.GridSearchPanel.Layout = app.AdvancedParameterPanel.Layout;

            %% Load Video

            % Isolate file format
            format = strsplit(app.video_path,'.');
            format = format(end);

            % Select loading function based on file format
            switch char(format)
                case 'h5'
                    app.load_h5(app.video_path);
                case 'nwb'
                    app.load_nwb(app.video_path);
                case 'nd2'
                    app.load_nd2(app.video_path);
            end

            app.xyAxes.XLim = [1, app.video_info.nx];
            app.xyAxes.YLim = [1, app.video_info.ny];
            %xy_aspectRatio = app.video_info.nx / app.video_info.ny;
            %app.xyAxes.DataAspectRatio = [1, 1/xy_aspectRatio, 1];

            % Define slider limits and values based on video
            app.tSlider.Limits = [1, app.video_info.nt];
            app.ActivityAxes.XTick = 0:app.video_info.nt;
            app.ActivityAxes.XTickLabel = 0:app.video_info.nt;
            app.tSlider.MinorTicks = [];
            app.tSlider.Value = 1;

            app.vert_zSlider.Limits = [1, app.video_info.nz];
            app.vert_zSlider.Value = round(app.video_info.nz/2);

            app.hor_zSlider.Limits = [1, app.video_info.nz];
            app.hor_zSlider.Value = round(app.video_info.nz/2);

            app.xSlider.Limits = [1, app.video_info.nx];
            app.xSlider.Value = round(app.video_info.nx/2);

            app.ySlider.Limits = [1, app.video_info.ny];
            app.ySlider.Value = round(app.video_info.ny/2);

            % Render Frame 1
            app.visual_composer(1, round(app.video_info.nz/2), round(app.video_info.ny/2), round(app.video_info.nx/2));
            set(app.TrackingButton, 'Visible', 'off');
            set(app.VideoGridLayout, 'Visible', 'on');

            %newtrace(app, video_array, app.image_data, app.image_view, app.image_neurons)
            %trace_window(app,app.image_data,app.image_view,app.image_neurons, fullpath, GUI_prefs.image_dir, app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.DICDropDown.Value, app.GFPDropDown.Value, app.image_gamma);
        end

        % Callback function
        function TL_ButtonPushed(app, event)
            app.DispNeuron(event)
        end

        % Callback function
        function ButtonPushed(app, event)
            app.DispNeuron(event)
        end

        % Callback function
        function Button_2Pushed(app, event)
            app.DispNeuron(event)
        end

        % Menu selected function: DisplayNeuronActivityMenu
        function DisplayNeuronActivityMenuSelected(app, event)
            if app.DisplayNeuronActivityMenu.Checked == 1
                set(app.VolTraceParent,'Visible','Off')
                set(app.LoadNeuronActivityButton,'Visible','On')
                set(app.DisplayNeuronActivityMenu,'Checked','off')
            else
                d = uiprogressdlg(app.CELL_ID,'Title','Loading data...',...
        'Indeterminate','on');
                app.initBrowser()
                set(app.VolTraceParent,'Parent',app.NeuroPALIDTab)
                set(app.LoadNeuronActivityButton,'Visible','Off')
                set(app.VolTraceParent,'Visible','On')
                app.VolTraceParent.Parent = app.LoadNeuronActivityButton.Parent;
                app.VolTraceParent.Layout = app.LoadNeuronActivityButton.Layout;
                set(app.DisplayNeuronActivityMenu,'Checked','on')
                close(d)
            end
        end

        % Menu selected function: CSVfileMenu
        function CSVfileMenuSelected(app, event)
                        
            % Display expected formatting.
            DataHandling.PNGViewer.show('./Data/Documents/csv-format.png', 'Expected CSV formatting');

            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Select CSV file.');

            app.logEvent('Main',sprintf('Loading activity from %s...', join(path, name)), 1);
            imported_neurons = readtable(join([path,name]),'NumHeaderLines',1);

            d = uiprogressdlg(app.CELL_ID,'Title','Loading neurons...','Indeterminate','on');
            for r = 1:size(imported_neurons.Var4)
                adjusted_coords = flip([imported_neurons.Var5(r) imported_neurons.Var4(r)]) + app.mouse_offset;
                app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                    [adjusted_coords imported_neurons.Var3(r)], app.mp_params.hnsz, app.image_um_scale');
            end
            
            SaveIDToFile(app);
            app.DrawImageData()
            close(d)

        end

        % Menu selected function: NWBfileMenu
        function NWBfileMenuSelected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.nwb'];
            [name, path, ~] = uigetfile(file_info, 'Select NWB file.');
            nwb = nwbRead(join([path,name]));
            app.load_neurons_from_nwb(nwb);
        end

        % Menu selected function: SaveNWBMenu
        function SaveNWBMenuSelected(app, event)
            filename = app.image_file;
            suffixI = strfind(filename, '.');
            suffixI = suffixI(end);
            csvfile = cat(2, filename(1:(suffixI - 1)), '.csv');
            waitfor(nwbsave(app, app.image_neurons, app.neuron_activity_by_name, app.image_file, csvfile, app.image_prefs, app.image_data_zscored, app.image_um_scale, app.image_data));
        end

        % Callback function
        function h5fileMenuSelected(app, event)
            % Prompt the user to select the directory containing the data and annotations files.
            directory = uigetdir('.', 'Select the directory');
            
            % Define file paths
            annotations_file = fullfile(directory, 'annotations.h5');
            data_file = fullfile(directory, 'data.h5');
            
            % Load files
            anno_x = h5read(annotations_file, '/x');
            anno_y = h5read(annotations_file, '/y');
            anno_z = h5read(annotations_file, '/z');
            anno_id = h5read(annotations_file, '/id');
            anno_tidx = h5read(annotations_file, '/t_idx');
            anno_provenance = h5read(annotations_file, '/provenance');
            anno_worldline = h5read(annotations_file, '/worldline_id');
            times = h5read(data_file, '/times');
            
            % Initialize neuron activity array
            app.neuron_activity = zeros(size(anno_id, 1), 2);
            
            % Get data dimensions
            data_info = h5info(data_file, '/data');
            data_size = data_info.Dataspace.Size;
            
            % Get the scale based on the size of the data
            scale = data_size(1:3);
            
            last_t = -1;
            start_time = times(1);
            
            for i = 1:size(anno_id, 1)
                if strcmp(anno_provenance(i), 'ZEIR')
                    x = round(anno_x(i) * scale(1)) + 1;
                    y = round(anno_y(i) * scale(2)) + 1;
                    z = round(anno_z(i) * scale(3)) + 1;
                    t = find(times >= anno_tidx(i), 1);
            
                    % Validate indices
                    if x > 0 && y > 0 && z > 0 && t > 0 && ...
                       x <= data_size(1) && y <= data_size(2) && ...
                       z <= data_size(3) && t <= data_size(5)
                        try
                            % Debug prints
                            fprintf('Processing index: x=%d, y=%d, z=%d, t=%d\n', x, y, z, t);
                            
                            % Only load new time-slice if necessary
                            if t ~= last_t
                                current_time_slice = h5read(data_file, '/data', [1, 1, 1, 1, t], [512, 400, 23, 3, 1]);
                                last_t = t;
                            end
                            
                            % Store intensity and time in neuron_activity
                            app.neuron_activity(i, 1) = current_time_slice(x, y, z, 1);
                            app.neuron_activity(i, 2) = (times(t) - start_time) / 1000;  % convert to seconds
            
                            % Debug print for activity data and time
                            fprintf('Activity data: %f, Time: %f\n', app.neuron_activity(i, 1), app.neuron_activity(i, 2));
            
                        catch ME
                            fprintf('Error while reading file: %s\n', ME.message);
                            fprintf('Invalid index: x=%d, y=%d, z=%d, t=%d\n', x, y, z, t);
                        end
                    else
                        fprintf('Invalid index: x=%d, y=%d, z=%d, t=%d\n', x, y, z, t);
                    end
                end
            end

            % Extract activity of the 5th neuron
            neuron_activity = app.neuron_activity(5, :);
            
            % Plot neuron activity over time
            figure; % Create a new figure
            plot(neuron_activity(:, 2), neuron_activity(:, 1));
            xlabel('Time');
            ylabel('Activity');
            title('Neuron 5 Activity Over Time');

            d = uiprogressdlg(app.CELL_ID,'Title','Pulling worldline...','Indeterminate','on');

            % Load additional worldline data file
            worldline_file = fullfile(directory, 'worldlines.h5');
            worldline_id = h5read(worldline_file, '/id');
            worldline_name = strtrim(h5read(worldline_file, '/name'));

            % Assuming worldline_name is your 1x165 cell array of names
            for i = 1:length(worldline_name)
                worldline_name{i} = regexprep(worldline_name{i}, '[^a-zA-Z0-9]', '');  % Keep only alphanumeric characters
            end

            % Initialize the neuron_activity_by_name as a Map
            app.neuron_activity_by_name = containers.Map('KeyType', 'char', 'ValueType', 'any');
            
            % Build the neuron_activity_by_name map
            for i = 1:numel(worldline_id)
                % Find matching worldline ids in anno_worldline
                idx = anno_worldline == worldline_id(i);
                
                % Use logical indexing to pull corresponding activity and time data
                % for each matching neuron
                neuron_activity = app.neuron_activity(idx, :);
                
                % Use the worldline_name as a key in the map.
                app.neuron_activity_by_name(worldline_name{i}) = neuron_activity;
            end
            close(d)

            if app.DisplayNeuronActivityMenu.Checked == 0
                open_view = uiconfirm(app.CELL_ID,'Activity loaded!','Success!','Options',{'View now', 'Skip view'},'DefaultOption','Skip view');
                switch open_view
                    case 'View now'
                        app.DisplayNeuronActivityMenuSelected();
                    case 'Skip view'
                        return
                end
            end
        end

        % Menu selected function: ZephIRoutputMenu
        function ZephIRoutputMenuSelected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Select CSV file.');
            fileName = [path,name];

            % Read the CSV file
            dataTable = readtable(fileName);
        
            % Initialize the map
            app.neuron_activity_by_name = containers.Map('KeyType', 'char', 'ValueType', 'any');
        
            d = uiprogressdlg(app.CELL_ID,'Title','Loading activity...', 'Indeterminate','on');
            % Iterate through the rows
            act_length = 0;
            for i = 1:size(dataTable, 1)
                % Get the neuron name
                try
                    neuronName = dataTable.Var2{i};
                catch
                    %fprintf('Error accessing Var2 at row %d\n', i);
                    continue
                end
        
                % Debugging print statement
                %fprintf('Processing neuron: %s\n', neuronName);
        
                % Initialize an array to store the neuron activity
                neuronActivity = [];
        
                % Iterate through the columns starting from column 6
                for j = 6:size(dataTable, 2)
                    % Append the activity to the array if it's not a NaN
                    if ~isnan(dataTable{i,j})
                        neuronActivity = [neuronActivity, dataTable{i, j}];
                        act_length = size(dataTable{i, j});
                    end
                end
        
                % If the neuron has activity, save it to the map
                if ~isempty(neuronActivity)
                    app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                    app.neuron_activity_by_name(neuronName) = neuronActivity;
                    %fprintf('Saved activity for neuron: %s\n', neuronName);
                end
            end

            app.neuron_activity_by_name('null') = zeros(act_length);

            neuron_names = keys(app.neuron_activity_by_name);
            neuron_activity = values(app.neuron_activity_by_name);
        
            % Determine the length of the activity arrays
            n = length(neuron_activity{1});
        
            % Initialize an empty matrix for activities
            activity_matrix = zeros(n, numel(neuron_activity));
        
            % Fill the activity matrix
            for i = 1:numel(neuron_activity)
                activity_matrix(:, i) = neuron_activity{i};
            end
        
            % Create a table with neuron names as variable names
            app.activity_table = array2table(activity_matrix, 'VariableNames', matlab.lang.makeValidName(neuron_names));

            app.EnableActivityGUI();
            close(d)

            if app.DisplayNeuronActivityMenu.Checked == 0
                open_view = uiconfirm(app.CELL_ID,'Activity loaded!','Success!','Options',{'View in main app', 'View in activity browser', 'Skip view'},'DefaultOption','Skip view');
                switch open_view
                    case 'View in main app'
                        app.DisplayNeuronActivityMenuSelected();
                    case 'View in activity browser'
                        app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                    case 'Skip view'
                        return
                end
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'Log'
                    app.initLog();
                case 'Video Tracking'
                    if ~app.TrackingButton.Visible & strcmp(app.VideoTrackingTab.Tag, 'raw')
                        app.VideoTrackingTab.Tag = 'rendered';
                    end
                case 'Image Processing'
                    if ~app.ProcessingButton.Visible & strcmp(app.ImageProcessingTab.Tag, 'raw')
                        d = uiprogressdlg(app.CELL_ID,"Title","NeuroPAL ID","Message","Initializing Processing Tab...",'Indeterminate','off');

                        d.Value = 1 / 5;
                        d.Message = sprintf('Setting channel indices...');

                        % Set channel indices
                        app.ProcRDropDown.Value = app.RDropDown.Value;
                        app.ProcGDropDown.Value = app.GDropDown.Value;
                        app.ProcBDropDown.Value = app.BDropDown.Value;
                        app.ProcWDropDown.Value = app.WDropDown.Value;
                        app.ProcDICDropDown.Value = app.DICDropDown.Value;
                        app.ProcGFPDropDown.Value = app.GFPDropDown.Value;

                        d.Value = 2 / 5;
                        d.Message = sprintf('Configuring GUI...');

                        % Set slider limits
                        app.proc_hor_zSlider.Limits = [1 size(app.image_data, 3)];
                        app.proc_vert_zSlider.Limits = app.proc_hor_zSlider.Limits;
                        app.proc_xSlider.Limits = [1 size(app.image_data, 2)];
                        app.proc_ySlider.Limits = [1 size(app.image_data, 1)];

                        % Set slider values to half their max
                        app.proc_hor_zSlider.Value = round(app.proc_hor_zSlider.Limits(2)/2);
                        app.proc_xSlider.Value = round(app.proc_xSlider.Limits(2)/2);
                        app.proc_ySlider.Value = round(app.proc_ySlider.Limits(2)/2);

                        % Resize Histogram grid if only one row is required
                        if size(app.image_data, 4) < 4
                            app.HistogramGrid.RowHeight = {'1x'};
                        end

                        d.Value = 3 / 5;
                        d.Message = sprintf('Mapping channels...');

                        % Create a map from dropdown values to channel strings
                        app.dropdownMap = containers.Map( ...
                            {app.ProcRDropDown.Value, app.ProcGDropDown.Value, app.ProcBDropDown.Value, ...
                             app.ProcWDropDown.Value, app.ProcDICDropDown.Value, app.ProcGFPDropDown.Value}, ...
                            {'Red', 'Green', 'Blue', 'White', 'DIC', 'GFP'} ...
                        );

                        app.colorMap = containers.Map( ...
                            {app.ProcRDropDown.Value, app.ProcGDropDown.Value, app.ProcBDropDown.Value, ...
                             app.ProcWDropDown.Value, app.ProcDICDropDown.Value, app.ProcGFPDropDown.Value}, ...
                            {'r', 'g', 'b', 'k', 'k', 'y'} ...
                        );

                        d.Value = 4 / 5;
                        d.Message = sprintf('Calculating threshold...');
                        
                        % Set max threshold
                        max_val = double(max(app.image_data, [], 'all'));
                        app.NoisethresholdKnob.Limits = [1 max_val];

                        d.Value = 5 / 5;
                        d.Message = sprintf('Drawing image...');

                        % Draw processed image
                        app.drawProcImage();

                        % Mark first-time set-up complete
                        app.ImageProcessingTab.Tag = 'rendered';

                        close(d)
                    end
            end
        end

        % Callback function
        function BrowserTreeCheckedNodesChanged(app, event)
            checkedNodes = app.BrowserTree.CheckedNodes;
            plot_neurons = {};
            plot_features = {};

            for i = 1:length(checkedNodes)
                node = checkedNodes(i);
                if node.NodeData == 1
                    plot_neurons{end+1} = node.Text;
                    plot_features{end+1} = node.Parent.Text;
                    plot_features{end+1} = node.Parent.Parent.Text;
                end
            end

            plot_features = unique(plot_features);
            app.updateNeurons(plot_neurons, plot_features); 
        end

        % Callback function
        function ClearSelectionButtonPushed(app, event)
            for n=1:size(app.BrowserTrace.Children)
                app.BrowserTrace.Children(n).Color = app.BrowserTrace.Children(n).UserData;
            end

            app.BrowserTrace.Title.String = app.BrowserTrace.UserData;
            set(app.ClearSelectionButton, 'Visible', 'off');
            set(app.ClearSelectionButton, 'Enable', 'off');
        end

        % Button pushed function: AddPlotButton
        function AddPlotButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Adding plot...', 'Indeterminate','on');
            app.VolTrace.DisplayVariables{end+1} = {'null'};
            drawnow;
            app.drawStimuli();
            app.color_tracker{end+1} = [];
            app.style_tracker{end+1} = [];
            if size(app.VolTrace.DisplayVariables,2)>1
                set(app.DeletePlotDropDown,'Enable','on')
                item_labels = string(1:size(app.VolTrace.DisplayVariables,2));
                idx = 1:size(app.VolTrace.DisplayVariables,2);
                app.DeletePlotDropDown.Items = string(arrayfun(@(x) strcat('Plot #', num2str(x)), item_labels(idx), 'UniformOutput', false));
                app.DeletePlotDropDown.ItemsData = num2cell(1:size(app.VolTrace.DisplayVariables,2));
            else
                set(app.DeletePlotDropDown,'Enable','off')
            end
            close(d)
        end

        % Clicked callback: IDdNeuronsListBox
        function IDdNeuronsListBoxClicked(app, event)
            %{
            
            name = app.IDdNeuronsListBox.Items{event.InteractionInformation.Item};

            % Get the current mouse position within the app
            mousePos = app.CELL_ID.CurrentPoint;

            % Update HoverLabel text
            app.HoverLabel.Text = name;
            
            % Make the label visible
            app.HoverLabel.Visible = 'on';

            %}
        end

        % Value changed function: DeletePlotDropDown
        function DeletePlotDropDownValueChanged(app, event)
            app.VolTrace.DisplayVariables(app.DeletePlotDropDown.Value) = [];
            app.color_tracker{app.DeletePlotDropDown.Value} = [];
            app.style_tracker{app.DeletePlotDropDown.Value} = [];
            if size(app.VolTrace.DisplayVariables,2)>1
                set(app.DeletePlotDropDown,'Enable','on')
                app.DeletePlotDropDown.Value = app.DeletePlotDropDown.ItemsData{app.DeletePlotDropDown.Value-1};
                item_labels = string(1:size(app.VolTrace.DisplayVariables,2));
                idx = 1:size(app.VolTrace.DisplayVariables,2);
                app.DeletePlotDropDown.Items = string(arrayfun(@(x) strcat('Plot #', num2str(x)), item_labels(idx), 'UniformOutput', false));
                app.DeletePlotDropDown.ItemsData = num2cell(1:size(app.VolTrace.DisplayVariables,2));
            else
                set(app.DeletePlotDropDown,'Enable','off')
                app.DeletePlotDropDown.Items = {};
                app.DeletePlotDropDown.ItemsData = [];
            end
            app.drawStimuli();
        end

        % Value changed function: DeleteNeuronDropDown
        function DeleteNeuronDropDownValueChanged(app, event)
            value = app.DeleteNeuronDropDown.Value;
            
        end

        % Button pushed function: LoadStimuliButton
        function LoadStimuliButtonPushed(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
        
            % Ask the user which file they want.
            file_info = [path ';*.txt;*.nwb'];
            [name, path, ~] = uigetfile(file_info, 'Select stimulus file.');
            fileName = [path,name];
            
            if endsWith(fileName, '.nwb')
                stim_file = nwbRead(fileName);
                if any(ismember(stim_file.processing.get('CalciumActivity').nwbdatainterface.keys, 'StimulusInfo'))
                    labels = stim_file.processing.get('CalciumActivity').nwbdatainterface.get('StimulusInfo').data.load();
                    timestamps = stim_file.processing.get('CalciumActivity').nwbdatainterface.get('StimulusInfo').timestamps.load();
                    app.stimuli = table(timestamps, labels);
                else
                    uiconfirm(app.CELL_ID, 'No stimulus info in this file.', 'Error');
                end
            else        
                app.stimuli = readtable(fileName, 'Delimiter', ',', 'Format', '%d%s');
            end
        
            app.drawStimuli();
        end

        % Button pushed function: OpenRepositoryButton
        function OpenRepositoryButtonPushed(app, event)
            web("https://github.com/Yemini-Lab/NeuroPAL_ID")
        end

        % Button pushed function: SubmitbugreportButton
        function SubmitbugreportButtonPushed(app, event)
            ver_support = ver;
            ver_support = strcat(ver_support.Name, ver_support.Version, ver_support.Release, ver_support.Date);
            ver_support = replace(ver_support,' ','%20');
            ver_support = replace(ver_support,'\n','%0D%0A');
            app.Log.Value
            log = strcat(app.Log.Value);
            %web(sprintf("https://github.com/Yemini-Lab/NeuroPAL_ID/issues/new?title=foo&body=**Description:**%0D%0AEnter%20your%20issue%20details.%0D%0A%0D%0A**System%20Info:**%0D%0A%s%0D%0A%0D%0A**MATLAB%20ver%20-support:**%0D%0A%s%0D%0A%0D%0A**Log:**%0D%0A%s",app.OperatingSystemEditField.Value,ver_support,log))
        end

        % Menu selected function: LogtesteventMenu
        function LogtesteventMenuSelected(app, event)
            app.logEvent('Segmentation','Placing neuron dots...', 0)
        end

        % Menu selected function: LogfadeeventMenu
        function LogfadeeventMenuSelected(app, event)
            app.logEvent('Segmentation','Placing neuron dots...', 1)
        end

        % Menu selected function: NWBfileMenu_2
        function NWBfileMenu_2Selected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
            
            file_info = [path, ';*.nwb'];
            [name, path, ~] = uigetfile(file_info, 'Select NWB file.');
            spec_labels = 0;

            nwb_file = nwbRead(fullfile(path, name));

            % Initialize the map
            app.neuron_activity_by_name = containers.Map('KeyType', 'char', 'ValueType', 'any');
    
            d = uiprogressdlg(app.CELL_ID,'Title','Loading activity...', 'Indeterminate','off');
            if any(ismember(nwb_file.processing.keys, 'CalciumActivity'))
                if any(ismember(nwb_file.processing.get('CalciumActivity').nwbdatainterface.keys, 'SignalRawFluor'))
                    activity = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('SignalRawFluor').roiresponseseries.get('SignalCalciumImResponseSeries').data.load();
                else
                    activity = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('ActivityDfOverF').roiresponseseries.get(nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('ActivityDfOverF').roiresponseseries.keys{1}).data.load();
                    activity = activity';
                end

                if any(ismember(nwb_file.processing.get('CalciumActivity').nwbdatainterface.keys, 'NeuronIDs'))
                    labels = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('NeuronIDs').labels.load();
                else
                    labels = nwb_file.processing.get('CalciumActivity').dynamictable.get('ActivityLabels').vectordata.get('neuron').data.load();
                    spec_labels = 1;
                end
            else
                'debug'
                error('Could not find Calcium Activity in %s.', fullfile(path, name));
            end

            if spec_labels ~=1
                for n=1:size(labels, 1)
                    d.Value = n / size(labels, 1);
                    if strcmp(labels{n}, 'unknown') || strcmp(labels{n}, '')
                        continue
                    else
                        % Get the neuron name
                        try
                            neuronName = labels{n};
                        catch
                            %fprintf('Error accessing Var2 at row %d\n', i);
                            continue
                        end
    
                        d.Message = sprintf('Processing neuron: %s\n', neuronName);
                
                        % Initialize an array to store the neuron activity
                        neuronActivity = [];
                        neuronActivity = activity(n, :);
                
                        % If the neuron has activity, save it to the map
                        if ~isempty(neuronActivity)
                            app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                            app.neuron_activity_by_name(neuronName) = neuronActivity;
                        end
                    end
                end
            else
                for n=1:size(activity, 1)
                    d.Value = n / size(activity, 1);
                    if strcmp(labels{n}, 'unknown') || strcmp(labels{n}, '')
                        continue
                    else
                        % Get the neuron name
                        try
                            neuronName = labels{n};
                        catch
                            %fprintf('Error accessing Var2 at row %d\n', i);
                            continue
                        end
    
                        d.Message = sprintf('Processing neuron: %s\n', neuronName);
                
                        % Initialize an array to store the neuron activity
                        neuronActivity = [];
                        neuronActivity = activity(n, :);
                
                        % If the neuron has activity, save it to the map
                        if ~isempty(neuronActivity)
                            app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                            app.neuron_activity_by_name(neuronName) = neuronActivity;
                        end
                    end
                end
            end
            
            app.neuron_activity_by_name('null') = zeros(1, size(neuronActivity, 2));

            neuron_names = keys(app.neuron_activity_by_name);
            neuron_activity = values(app.neuron_activity_by_name);
        
            % Determine the length of the activity arrays
            n = length(neuron_activity{2});
        
            % Initialize an empty matrix for activities
            activity_matrix = zeros(n, size(neuron_activity, 2));
        
            % Fill the activity matrix
            for i = 1:numel(neuron_activity)
                activity_matrix(:, i) = neuron_activity{i}';
            end
        
            % Create a table with neuron names as variable names
            app.activity_table = array2table(activity_matrix, 'VariableNames', matlab.lang.makeValidName(neuron_names));

            app.EnableActivityGUI();
            close(d)

            if app.DisplayNeuronActivityMenu.Checked == 0
                open_view = uiconfirm(app.CELL_ID,'Activity loaded!','Success!','Options',{'View now', 'Skip view'},'DefaultOption','Skip view');
                switch open_view
                    case 'View now'
                        app.DisplayNeuronActivityMenuSelected();
                    case 'Skip view'
                        return
                end
            end
        end

        % Callback function
        function DiagnoseButtonPushed(app, event)
            [state, load] = app.engineCheck();
            app.wrapHandler(state, load);
        end

        % Callback function
        function SelectpythonexecutableButtonPushed(app, event)
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            if ~isdeployed
                if ispc
                    [name, path, ~] = uigetfile([app.path ';*.exe;'], 'Select python executable');
                elseif ismac
                    [name, path, ~] = uigetfile([app.path ';*.app;'], 'Select python executable');
                end
            end
            pyversion([path, name])
            app.CELL_ID.Visible = 'on'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
        end

        % Callback function
        function ShellEditFieldValueChanged(app, event)
            [status, output] = system(app.ShellEditField.Value);
            output = string(output); % Convert output to string
            
            % If app.ShellLog.Value is a string array or a character vector
            if isstring(app.ShellLog.Value) || ischar(app.ShellLog.Value)
                app.ShellLog.Value = [app.ShellLog.Value; output]; % Vertically concatenate
            elseif iscell(app.ShellLog.Value)
                % Convert output to cell array of character vectors, then concatenate
                outputCell = cellstr(output);
                app.ShellLog.Value = [app.ShellLog.Value; outputCell];
            else
                error('Unsupported type for app.ShellLog.Value');
            end
            
            scroll(app.ShellLog, 'bottom');
            app.ShellEditField.Value = '';
        end

        % Callback function
        function generateCoreMenuSelected(app, event)
            generateCore()
        end

        % Callback function
        function Neuron1MenuSelected(app, event)
            app.image_neurons.neurons(1)
            app.image_neurons.neurons(end)
        end

        % Value changed function: EnabledebugmenuCheckBox
        function EnabledebugmenuCheckBoxValueChanged(app, event)
            value = app.EnabledebugmenuCheckBox.Value;
            if value
                set(app.DebugMenu, 'Visible', 'on')
                set(app.DebugMenu, 'Enable', 'on')
            else
                set(app.DebugMenu, 'Visible', 'off')
                set(app.DebugMenu, 'Enable', 'off')
            end
        end

        % Button pushed function: LoadNeuronActivityButton
        function LoadNeuronActivityButtonPushed(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
            
            file_info = [path, ';*.nwb;*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Select activity file.');

            d = uiprogressdlg(app.CELL_ID,'Title','Loading activity...', 'Indeterminate','off');

            % Initialize the map
            app.neuron_activity_by_name = containers.Map('KeyType', 'char', 'ValueType', 'any');

            if endsWith(name, '.nwb')
                spec_labels = 0;
    
                nwb_file = nwbRead(fullfile(path, name));
        
                if any(ismember(nwb_file.processing.keys, 'CalciumActivity'))
                    if any(ismember(nwb_file.processing.get('CalciumActivity').nwbdatainterface.keys, 'SignalRawFluor'))
                        activity = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('SignalRawFluor').roiresponseseries.get('SignalCalciumImResponseSeries').data.load();
                    else
                        activity = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('ActivityDfOverF').roiresponseseries.get(nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('ActivityDfOverF').roiresponseseries.keys{1}).data.load();
                        activity = activity';
                    end
    
                    if any(ismember(nwb_file.processing.get('CalciumActivity').nwbdatainterface.keys, 'NeuronIDs'))
                        labels = nwb_file.processing.get('CalciumActivity').nwbdatainterface.get('NeuronIDs').labels.load();
                    else
                        labels = nwb_file.processing.get('CalciumActivity').dynamictable.get('ActivityLabels').vectordata.get('neuron').data.load();
                            spec_labels = 1;
                        end
                else
                    'debug'
                    error('Could not find Calcium Activity in %s.', fullfile(path, name));
                end
    
                if spec_labels ~=1
                    for n=1:size(labels, 1)
                        d.Value = n / size(labels, 1);
                        if strcmp(labels{n}, 'unknown') || strcmp(labels{n}, '')
                            continue
                        else
                            % Get the neuron name
                            try
                                neuronName = labels{n};
                            catch
                                %fprintf('Error accessing Var2 at row %d\n', i);
                                continue
                            end
        
                            d.Message = sprintf('Processing neuron: %s\n', neuronName);
                    
                            % Initialize an array to store the neuron activity
                            neuronActivity = [];
                            neuronActivity = activity(n, :);
                    
                            % If the neuron has activity, save it to the map
                            if ~isempty(neuronActivity)
                                app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                                app.neuron_activity_by_name(neuronName) = neuronActivity;
                            end
                        end
                    end
                else
                    for n=1:size(activity, 1)
                        d.Value = n / size(activity, 1);
                        if strcmp(labels{n}, 'unknown') || strcmp(labels{n}, '')
                            continue
                        else
                            % Get the neuron name
                            try
                                neuronName = labels{n};
                            catch
                                %fprintf('Error accessing Var2 at row %d\n', i);
                                continue
                            end
        
                            d.Message = sprintf('Processing neuron: %s\n', neuronName);
                    
                            % Initialize an array to store the neuron activity
                            neuronActivity = [];
                            neuronActivity = activity(n, :);
                    
                            % If the neuron has activity, save it to the map
                            if ~isempty(neuronActivity)
                                app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                                app.neuron_activity_by_name(neuronName) = neuronActivity;
                            end
                        end
                    end
                end
                
                app.neuron_activity_by_name('null') = zeros(1, size(neuronActivity, 2));
    
                neuron_names = keys(app.neuron_activity_by_name);
                neuron_activity = values(app.neuron_activity_by_name);
            
                % Determine the length of the activity arrays
                n = length(neuron_activity{2});
            
                % Initialize an empty matrix for activities
                activity_matrix = zeros(n, size(neuron_activity, 2));
            
                % Fill the activity matrix
                for i = 1:numel(neuron_activity)
                    activity_matrix(:, i) = neuron_activity{i}';
                end
            
                % Create a table with neuron names as variable names
                app.activity_table = array2table(activity_matrix, 'VariableNames', matlab.lang.makeValidName(neuron_names));
            elseif endsWith(name, '.csv')
                % Read the CSV file
                dataTable = readtable(fileName);
            
                % Iterate through the rows
                act_length = 0;
                for i = 1:size(dataTable, 1)
                    % Get the neuron name
                    try
                        neuronName = dataTable.Var2{i};
                    catch
                        %fprintf('Error accessing Var2 at row %d\n', i);
                        continue
                    end
            
                    % Debugging print statement
                    %fprintf('Processing neuron: %s\n', neuronName);
            
                    % Initialize an array to store the neuron activity
                    neuronActivity = [];
            
                    % Iterate through the columns starting from column 6
                    for j = 6:size(dataTable, 2)
                        % Append the activity to the array if it's not a NaN
                        if ~isnan(dataTable{i,j})
                            neuronActivity = [neuronActivity, dataTable{i, j}];
                            act_length = size(dataTable{i, j});
                        end
                    end
            
                    % If the neuron has activity, save it to the map
                    if ~isempty(neuronActivity)
                        app.logEvent('NeuroPAL_ID', sprintf('Loading %s activity...', neuronName), 1)
                        app.neuron_activity_by_name(neuronName) = neuronActivity;
                        %fprintf('Saved activity for neuron: %s\n', neuronName);
                    end
                end
    
                app.neuron_activity_by_name('null') = zeros(act_length);
    
                neuron_names = keys(app.neuron_activity_by_name);
                neuron_activity = values(app.neuron_activity_by_name);
            
                % Determine the length of the activity arrays
                n = length(neuron_activity{1});
            
                % Initialize an empty matrix for activities
                activity_matrix = zeros(n, numel(neuron_activity));
            
                % Fill the activity matrix
                for i = 1:numel(neuron_activity)
                    activity_matrix(:, i) = neuron_activity{i};
                end
            
                % Create a table with neuron names as variable names
                app.activity_table = array2table(activity_matrix, 'VariableNames', matlab.lang.makeValidName(neuron_names));
            end
    
            app.DisplayNeuronActivityMenuSelected();
            app.EnableActivityGUI();
            close(d)
        end

        % Value changed function: BCheckBox, DICCheckBox, GCheckBox, 
        % ...and 3 other components
        function RCheckBoxValueChanged(app, event)
            app.ProcRCheckBox.Value = app.RCheckBox.Value;
            app.ProcGCheckBox.Value = app.GCheckBox.Value;
            app.ProcBCheckBox.Value = app.BCheckBox.Value;
            app.ProcWCheckBox.Value = app.WCheckBox.Value;
            app.ProcDICCheckBox.Value = app.DICCheckBox.Value;
            app.ProcGFPCheckBox.Value = app.GFPCheckBox.Value;
        end

        % Value changed function: ProcColormapButton
        function ProcColormapButtonValueChanged(app, event)
            if app.ProcColormapButton.Value
                nx = size(app.image_data, 1);
                ny = size(app.image_data, 2);
                nz = size(app.image_data, 3);
    
                app.proc_xyAxes.XLim = [1, nx];
                app.proc_xyAxes.YLim = [1, ny];

                app.proc_xSlider.Limits = [1, nx];
                app.proc_ySlider.Limits = [1, ny];
                app.proc_hor_zSlider.Limits = [1, nz];
                app.proc_vert_zSlider.Limits = [1, nz];

                app.proc_xSlider.Value = round(nx/2);
                app.proc_ySlider.Value = round(ny/2);
                app.proc_hor_zSlider.Value = round(nz/2);
                app.proc_vert_zSlider.Value = round(nz/2);
            else
                app.ProcVideoButton.Value = 1;
            end
        end

        % Value changed function: ProcVideoButton
        function ProcVideoButtonValueChanged(app, event)
            if app.ProcVideoButton.Value    
                app.proc_xyAxes.XLim = [1, app.video_info.nx];
                app.proc_xyAxes.YLim = [1, app.video_info.ny];
                %xy_aspectRatio = app.video_info.nx / app.video_info.ny;
                %app.xyAxes.DataAspectRatio = [1, 1/xy_aspectRatio, 1];
    
                % Define slider limits and values based on video
                app.proc_tSlider.Limits = [1, app.video_info.nt];
                app.proc_tSlider.MinorTicks = [];
                app.proc_tSlider.Value = 1;
    
                app.proc_vert_zSlider.Limits = [1, app.video_info.nz];
                app.proc_vert_zSlider.Value = round(app.video_info.nz/2);
    
                app.proc_hor_zSlider.Limits = [1, app.video_info.nz];
                app.proc_hor_zSlider.Value = round(app.video_info.nz/2);
    
                app.proc_xSlider.Limits = [1, app.video_info.nx];
                app.proc_xSlider.Value = round(app.video_info.nx/2);
    
                app.proc_ySlider.Limits = [1, app.video_info.ny];uiconfirmapp.Pr
                app.proc_ySlider.Value = round(app.video_info.ny/2);
            else
                app.ProcColormapButton.Value = 1;
            end
        end

        % Value changed function: yFactorEditField
        function yFactorEditFieldValueChanged(app, event)
            if app.MaintainaspectratioCheckBox.Value
                app.xFactorEditField.Value = app.yFactorEditField.Value;
            end
        end

        % Value changed function: xFactorEditField
        function xFactorEditFieldValueChanged(app, event)
            if app.MaintainaspectratioCheckBox.Value
                app.yFactorEditField.Value = app.xFactorEditField.Value;
            end
        end

        % Value changed function: zSlicesEditField
        function zSlicesEditFieldValueChanged(app, event)
            if app.zSlicesEditField.Value > size(app.image_data, 3)
                uialert(app.UIFigure,"Can't set more z-slices than are present in the original image.","Error!")
                app.zSlicesEditField.Value = size(app.image_data, 3);
            end
        end

        % Value changed function: proc_xSlider
        function proc_xSliderValueChanged(app, event)
            app.proc_xSlider.Value = round(app.proc_xSlider.Value);
            app.drawProcImage();
        end

        % Value changed function: proc_hor_zSlider
        function proc_hor_zSliderValueChanged(app, event)
            app.proc_hor_zSlider.Value = round(app.proc_hor_zSlider.Value);
            app.proc_vert_zSlider.Value = round(app.proc_hor_zSlider.Value);
            app.drawProcImage();
        end

        % Value changed function: proc_vert_zSlider
        function proc_vert_zSliderValueChanged(app, event)
            app.proc_vert_zSlider.Value = round(app.proc_vert_zSlider.Value);
            app.proc_hor_zSlider.Value = round(app.proc_vert_zSlider.Limits(2)-app.proc_vert_zSlider.Value);
            app.drawProcImage();
        end

        % Value changed function: proc_ySlider
        function proc_ySliderValueChanged(app, event)
            app.proc_ySlider.Value = round(app.proc_ySlider.Value);
            app.drawProcImage();
        end

        % Value changing function: proc_xSlider
        function proc_xSliderValueChanging(app, event)
            delete(app.proc_xz_xline);
            delete(app.proc_xy_xline);
            app.proc_xz_xline = xline(app.proc_xzAxes, round(event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_xy_xline = xline(app.proc_xyAxes, round(event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Value changing function: proc_hor_zSlider
        function proc_hor_zSliderValueChanging(app, event)
            delete(app.proc_yz_xline);
            app.proc_yz_xline = xline(app.proc_yzAxes, app.proc_yzAxes.XLim(2)*(round(event.Value)/size(app.image_data, 3)), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Value changing function: proc_ySlider
        function proc_ySliderValueChanging(app, event)
            delete(app.proc_yz_yline)
            delete(app.proc_xy_yline)

            app.proc_yz_yline = yline(app.proc_yzAxes, round(size(app.image_data, 1)-event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.proc_xy_yline = yline(app.proc_xyAxes, round(size(app.image_data, 1)-event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Value changing function: proc_vert_zSlider
        function proc_vert_zSliderValueChanging(app, event)
            delete(app.proc_xz_yline);
            app.proc_xz_yline = yline(app.proc_xzAxes, app.proc_xzAxes.YLim(2)-app.proc_xzAxes.YLim(2)*(round(event.Value)/size(app.image_data, 3)), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Value changed function: NoisethresholdKnob
        function NoisethresholdKnobValueChanged(app, event)
            app.drawProcImage();
        end

        % Button pushed function: InsertNeuroPALNeuronsButton
        function InsertNeuroPALNeuronsButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Importing neurons...','Indeterminate','on');
            
            target_neurons = app.image_neurons.neurons;
            target_positions = app.image_neurons.get_positions;
            
            if ~all(inpolygon(app.video_info.nx, app.video_info.ny, target_positions(2), target_positions(1)))
                uiconfirm(app.CELL_ID, 'Volume dimensions do not match video dimensions. Imported array will be resized.', 'Warning!');

                vol_nx = size(app.image_view, 2);
                vol_ny = size(app.image_view, 1);

                x_ratio = app.video_info.nx / vol_nx;
                y_ratio = app.video_info.ny / vol_ny;

                for n=1:size(target_neurons, 2)
                    target_neurons(n).position(2) = target_neurons(n).position(2) * x_ratio;
                    target_neurons(n).position(1) = target_neurons(n).position(1) * y_ratio;
                    app.add_worldline(target_neurons(n))
                    target_neurons(n).position(2) = target_neurons(n).position(2) / x_ratio;
                    target_neurons(n).position(1) = target_neurons(n).position(1) / y_ratio;
                end
            else
                for n=1:size(app.image_neurons.neurons, 2)
                    app.add_worldline(target_neurons(n))
                end
            end

            app.visual_composer();
            close(d)
        end

        % Button pushed function: InsertlastIDdFrameButton
        function InsertlastIDdFrameButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title',"Importing from last ID'd frame...",'Indeterminate','on');
            earlier_frames = app.id_frames(app.id_frames < app.tSlider.Value);

            for n=1:size(app.video_neurons, 2)
                app.video_neurons(n).rois(app.tSlider.Value) = app.video_neurons(n).rois(max(earlier_frames));  
                app.id_frames = [app.id_frames; app.tSlider.Value];
            end

            app.visual_composer();
            close(d)
        end

        % Button pushed function: AssignrandomcolorstoblanksButton
        function AssignrandomcolorstoblanksButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Updating colors...','Indeterminate','on');
            for n=1:size(app.video_neurons, 2)
                if app.video_neurons(n).worldline.color == [0.8, 0.8, 0.8]
                    app.video_neurons(n).worldline.color = rand(1, 3);
                    app.video_neurons(n).worldline.style = uistyle("FontColor", app.video_neurons(n).worldline.color);
                    addStyle(app.WorldlineTree, app.video_neurons(n).worldline.style, "node", app.video_neurons(n).worldline.node);
                end
            end
            app.visual_composer();
            close(d)
        end

        % Button pushed function: ManipulateNeuronsButton
        function ManipulateNeuronsButtonPushed(app, event)
            set(app.AdjustNeuronMarkerAlignmentPanel, 'Visible', 'on');
            set(app.DefaultTabGroup, 'Visible', 'off');
            
            app.repo_neurons = app.video_neurons;
            app.sel_neurons = 1:size(app.video_neurons, 2);
            app.sel_neurons = app.sel_neurons';
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            app.visual_composer();
        end

        % Value changed function: GSlider
        function GSliderValueChanged(app, event)
            app.visual_composer();
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            app.visual_composer();
        end

        % Value changed function: tSlider
        function tSliderValueChanged(app, event)
            app.tSlider.Value = round(app.tSlider.Value);
            app.visual_composer();
        end

        % Button pushed function: SetBookmarkButton
        function SetBookmarkButtonPushed(app, event)
            bm_frame = round(app.tSlider.Value);

            if ~isempty(app.BookmarkNameEditField.Value)
                bm_name = app.BookmarkNameEditField.Value;
            else
                bm_name = sprintf('Bookmark #%.f', size(app.BookmarkListBox.Items,2)+1);
            end

            app.BookmarkListBox.Items = [app.BookmarkListBox.Items, sprintf("%s (Frame %.f)", bm_name, bm_frame)];

            if size(app.BookmarkListBox.ItemsData)>0
                app.BookmarkListBox.ItemsData = [app.BookmarkListBox.ItemsData, bm_frame];
            else
                app.BookmarkListBox.ItemsData = [bm_frame];
            end

            app.BookmarkNameEditField.Value = '';
        end

        % Button pushed function: GotoBookmarkButton
        function GotoBookmarkButtonPushed(app, event)
            if ~isempty(app.BookmarkListBox.Value)
                app.tSlider.Value = app.BookmarkListBox.Value;
                app.visual_composer();
            else
                uiconfirm(app.CELL_ID,"Error", 'No bookmark selected.');
            end
        end

        % Button pushed function: DeleteBookmarkButton
        function DeleteBookmarkButtonPushed(app, event)
            if ~isempty(app.BookmarkListBox.Value)
                [~,idx] = ismember(app.BookmarkListBox.Value, app.BookmarkListBox.Items);
                app.BookmarkListBox.Items(idx) = [];
            else
                uiconfirm(app.CELL_ID,"Error", 'No bookmark selected.');
            end
        end

        % Value changed function: OverlayFrameMIPCheckBox
        function OverlayFrameMIPCheckBoxValueChanged(app, event)
            app.OverlayFrameMIPCheckBox_2.Value = app.OverlayFrameMIPCheckBox.Value;
            app.visual_composer();
        end

        % Value changed function: OverlaylastIDdframeCheckBox
        function OverlaylastIDdframeCheckBoxValueChanged(app, event)
            app.OverlaylastIDdframeCheckBox_2.Value = app.OverlaylastIDdframeCheckBox.Value;
            app.visual_composer();
        end

        % Button pushed function: RecommendFramesButton
        function RecommendFramesButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Analyzing frames...','Indeterminate','on');
            
            if isdeployed
                cmd = [fullfile(app.script_dir, sprintf('recommend_frames%s', app.script_ext)),sprintf(' --channel=1 --dataset="%s" --nx=%.f --ny=%.f --nz=%.f --nc=%.f --nt=%.f',app.video_path, app.video_info.nx, app.video_info.ny, app.video_info.nz, app.video_info.nc, app.video_info.nt)]
                [status, output] = system(sprintf('%s', cmd));
                t_ref = splitlines(output);
                t_ref = t_ref(end-1);
                t_ref = t_ref{1};
                t_ref = t_ref(2:end-1);
                t_ref = split(t_ref, ', ');
                close(d)
    
                d = uiprogressdlg(app.CELL_ID,'Title','Creating bookmarks...','Indeterminate','on');
    
                for n=1:size(t_ref,1)
                    bmLabel = sprintf('Recommended Frame #%.f (Frame %.f)', n, str2num(t_ref{n})+1);
                    bmFrame = str2num(t_ref{n});
                    
                    app.BookmarkListBox.Items = [app.BookmarkListBox.Items, bmLabel];
                    if size(app.BookmarkListBox.ItemsData)>0
                        app.BookmarkListBox.ItemsData = [app.BookmarkListBox.ItemsData, bmFrame+1];
                    else
                        app.BookmarkListBox.ItemsData = [bmFrame+1];
                    end
                end

                close(d)
            else
                t_ref = pyrunfile([fullfile(app.script_dir, sprintf('recommend_frames%s', app.script_ext)),sprintf(' --channel=1 --dataset="%s" --nx=%.f --ny=%.f --nz=%.f --nc=%.f --nt=%.f',app.video_path, app.video_info.nx, app.video_info.ny, app.video_info.nz, app.video_info.nc, app.video_info.nt)],'t_ref');
                close(d)
    
                d = uiprogressdlg(app.CELL_ID,'Title','Creating bookmarks...','Indeterminate','on');
    
                for n=1:size(t_ref,2)
                    bmLabel = sprintf('Recommended Frame #%.f (Frame %.f)', n, double(t_ref(n))+1);
                    bmFrame = double(t_ref(n));
                    
                    app.BookmarkListBox.Items = [app.BookmarkListBox.Items, bmLabel];
                    if size(app.BookmarkListBox.ItemsData)>0
                        app.BookmarkListBox.ItemsData = [app.BookmarkListBox.ItemsData, bmFrame+1];
                    else
                        app.BookmarkListBox.ItemsData = [bmFrame+1];
                    end
                end
                close(d)
            end
        end

        % Button pushed function: TrackNeuronsButton
        function TrackNeuronsButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Saving your edits...','Indeterminate','on');
            annos = app.video_neurons; 
            info = app.video_info;
            save(fullfile(pwd, 'cache.mat'), 'annos');
            save(fullfile(pwd, 'meta_cache.mat'), 'info');
            app.create_metadata();
            close(d)
            
            d = uiprogressdlg(app.CELL_ID,'Title','Converting annotations...','Indeterminate','on');
            if isdeployed
                sprintf('%s %s %s %s', fullfile(app.script_dir, sprintf('converter%s', app.script_ext)), fullfile(pwd, 'cache.mat'), fullfile(pwd, 'meta_cache.mat'), app.video_path)
            else 
                py_cmd = sprintf('%s %s %s %s', fullfile(app.script_dir, sprintf('converter%s', app.script_ext)), fullfile(pwd, 'cache.mat'), fullfile(pwd, 'meta_cache.mat'), app.video_path)
                pyrunfile(py_cmd);
            end
            close(d)

            d = uiprogressdlg(app.CELL_ID,'Title','Compiling arguments...','Indeterminate','on');
            args = app.compile_arguments();
            close(d)

            d = uiprogressdlg(app.CELL_ID,'Title','Tracking neurons...','Indeterminate','on');
            if isdeployed
                [status, ~] = system(sprintf('%s %s', fullfile(app.script_dir, sprintf('main%s', app.script_ext)), args));
            else
                py_cmd = sprintf('python %s %s', fullfile(app.script_dir, sprintf('main%s', app.script_ext)), args)
                [status, ~] = system(py_cmd, '-echo');
                status
            end
            app.load_annotations();
            close(d)

            switch status
                case 0
                    app.load_annotations();
                otherwise
                    error('Failed to run tracking routine.');
            end
        end

        % Button pushed function: ExtractActivityButton
        function ExtractActivityButtonPushed(app, event)
            d = uiprogressdlg(app.CELL_ID,'Title','Extracting activity...','Indeterminate','on');
            if isdeployed
                system(sprintf('%s --channel=1 --dataset=%s', fullfile(app.script_dir, sprintf('extract_traces%s', app.script_ext)), app.video_path))
            else
                py_cmd = sprintf('%s --channel=1 --dataset=%s', fullfile(app.script_dir, sprintf('extract_traces%s', app.script_ext)), app.video_path)
                pyrunfile(py_cmd);            
            end

            d = uiprogressdlg(app.CELL_ID,'Title','Loading activity...', 'Indeterminate','on');
            app.activity = struct();

            [path, ~, ~] = fileparts(app.video_path);

            % Read the CSV file into a table
            tbl = readtable(fullfile(path, 'results.csv'), ReadVariableNames=true);
            tbl
            
            % Loop through each row of the table
            for i = 1:height(tbl)
                % Ensure the worldline name is a string and valid field name
                worldlineName = string(tbl.worldline{i});

                if ~isfield(app.activity, worldlineName)
                    % If this worldline name is not yet a key in the struct, add it
                    app.activity.(worldlineName) = [];
                end

                T = tbl(i, 4:end);
                app.activity.(worldlineName) = table2array(T(1, :));
                app.ActivityListBox.Items = [app.ActivityListBox.Items, worldlineName];
    
                if size(app.ActivityListBox.ItemsData)>0
                    app.ActivityListBox.ItemsData = [app.ActivityListBox.ItemsData, worldlineName];
                else
                    app.ActivityListBox.ItemsData = [worldlineName];
                end
            end

            app.activity

            close(d)
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            app.ySlider.Value = round(app.ySlider.Value);
            app.visual_composer();
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)
            app.xSlider.Value = round(app.xSlider.Value);
            app.visual_composer();
        end

        % Value changed function: hor_zSlider
        function hor_zSliderValueChanged(app, event)
            app.hor_zSlider.Value = round(app.hor_zSlider.Value);
            app.vert_zSlider.Value = round(app.hor_zSlider.Value);
            app.visual_composer();
        end

        % Value changed function: vert_zSlider
        function vert_zSliderValueChanged(app, event)
            app.vert_zSlider.Value = round(app.vert_zSlider.Value);
            app.hor_zSlider.Value = round(app.vert_zSlider.Limits(2)-app.vert_zSlider.Value);
            app.visual_composer();
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            delete(app.xz_xline);
            delete(app.xy_xline);
            app.xz_xline = xline(app.xzAxes, round(event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.xy_xline = xline(app.xyAxes, round(event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Value changing function: hor_zSlider
        function hor_zSliderValueChanging(app, event)
            delete(app.yz_xline);
            app.yz_xline = xline(app.yzAxes, app.yzAxes.XLim(2)*(round(event.Value)/app.video_info.nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Value changing function: vert_zSlider
        function vert_zSliderValueChanging(app, event)
            delete(app.xz_yline);
            app.xz_yline = yline(app.xzAxes, app.xzAxes.YLim(2)-app.xzAxes.YLim(2)*(round(event.Value)/app.video_info.nz), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            delete(app.yz_yline)
            delete(app.xy_yline)
            app.yz_yline = yline(app.yzAxes, round(app.video_info.ny-event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
            app.xy_yline = yline(app.xyAxes, round(app.video_info.ny-event.Value), '--', 'color', '#9c9c9c', 'LineWidth', 0.2);
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            app.ColorButton.BackgroundColor = uisetcolor(app.ColorButton.BackgroundColor, 'Select Color');
            app.edit_worldline(app.WorldlineIDEditField.Value, 'color', app.ColorButton.BackgroundColor);
        end

        % Selection changed function: WorldlineTree
        function WorldlineTreeSelectionChanged(app, event)
            app.edit_worldline(app.WorldlineTree.SelectedNodes.NodeData, 'select');
        end

        % Button pushed function: Button_2
        function Button_2Pushed2(app, event)
            app.edit_worldline(app.WorldlineIDEditField.Value, 'delete');
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            app.edit_worldline(app.WorldlineIDEditField.Value, 'save');
        end

        % Button pushed function: ViewPreprintButton
        function ViewPreprintButtonPushed(app, event)
            web('https://www.biorxiv.org/content/10.1101/2022.07.18.500485v1')
        end

        % Button pushed function: GithubRepositoryButton
        function GithubRepositoryButtonPushed(app, event)
            web('https://github.com/venkatachalamlab/ZephIR')
        end

        % Button pushed function: LaboratoryPageButton
        function LaboratoryPageButtonPushed(app, event)
            web('https://venkatachalamlab.org/')
        end

        % Value changed function: MarkerSizeSlider
        function MarkerSizeSliderValueChanged(app, event)
            app.visual_composer();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CELL_ID = uifigure('Visible', 'off');
            app.CELL_ID.Name = 'NeuroPAL ID';
            app.CELL_ID.CloseRequestFcn = createCallbackFcn(app, @CELL_IDCloseRequest, true);
            app.CELL_ID.KeyPressFcn = createCallbackFcn(app, @CELL_IDKeyPress, true);
            app.FileMenu = uimenu(app.CELL_ID);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFile, true);
            app.OpenMenu.Text = 'Open';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.PreprocessingMenu = uimenu(app.CELL_ID);
            app.PreprocessingMenu.Text = 'Preprocessing';
            app.ArtifactRemovalMenu = uimenu(app.PreprocessingMenu);
            app.ArtifactRemovalMenu.Text = 'Artifact Removal';
            app.ManualMenu = uimenu(app.ArtifactRemovalMenu);
            app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualArtifactRemoval, true);
            app.ManualMenu.Text = 'Manual';
            app.AutomaticMenu = uimenu(app.ArtifactRemovalMenu);
            app.AutomaticMenu.MenuSelectedFcn = createCallbackFcn(app, @AutomaticArtifactRemoval, true);
            app.AutomaticMenu.Text = 'Automatic';
            app.DownsampleImageMenu = uimenu(app.PreprocessingMenu);
            app.DownsampleImageMenu.MenuSelectedFcn = createCallbackFcn(app, @DecimateButtonPushed, true);
            app.DownsampleImageMenu.Text = 'Downsample Image';
            app.ThresholdImageMenu = uimenu(app.PreprocessingMenu);
            app.ThresholdImageMenu.MenuSelectedFcn = createCallbackFcn(app, @AdaptThreshButtonPushed, true);
            app.ThresholdImageMenu.Text = 'Threshold Image';
            app.HistogramMatchingMenu = uimenu(app.PreprocessingMenu);
            app.HistogramMatchingMenu.MenuSelectedFcn = createCallbackFcn(app, @HistMatchButtonPushed, true);
            app.HistogramMatchingMenu.Text = 'Histogram Matching';
            app.AutoHistogramMenu = uimenu(app.PreprocessingMenu);
            app.AutoHistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoHistogram, true);
            app.AutoHistogramMenu.Text = 'Auto Histogram';
            app.ImageMenu = uimenu(app.CELL_ID);
            app.ImageMenu.Text = 'Image';
            app.NormalizeColorsMenu = uimenu(app.ImageMenu);
            app.NormalizeColorsMenu.MenuSelectedFcn = createCallbackFcn(app, @ZScoringMenuSelected, true);
            app.NormalizeColorsMenu.Text = 'Normalize Colors';
            app.ColorRegisterMenu = uimenu(app.ImageMenu);
            app.ColorRegisterMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorRegisterMenuSelected, true);
            app.ColorRegisterMenu.Text = 'Color Register';
            app.AdjustGammaMenu = uimenu(app.ImageMenu);
            app.AdjustGammaMenu.Text = 'Adjust Gamma';
            app.AllChannels = uimenu(app.AdjustGammaMenu);
            app.AllChannels.MenuSelectedFcn = createCallbackFcn(app, @AllChannelsMenuSelected, true);
            app.AllChannels.Text = 'All Channels';
            app.IndividualChannels = uimenu(app.AdjustGammaMenu);
            app.IndividualChannels.MenuSelectedFcn = createCallbackFcn(app, @IndividualChannelsMenuSelected, true);
            app.IndividualChannels.Text = 'Individual Channels';
            app.AdjustBrightnessMenu = uimenu(app.ImageMenu);
            app.AdjustBrightnessMenu.Enable = 'off';
            app.AdjustBrightnessMenu.Text = 'Adjust Brightness';
            app.AdjustHistogramMenu = uimenu(app.ImageMenu);
            app.AdjustHistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustHistogramMenuSelected, true);
            app.AdjustHistogramMenu.Text = 'Adjust Histogram';
            app.RotateAllMenu = uimenu(app.ImageMenu);
            app.RotateAllMenu.Text = 'Rotate All';
            app.RotateAllHorizontalMenu = uimenu(app.RotateAllMenu);
            app.RotateAllHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllHorizontal, true);
            app.RotateAllHorizontalMenu.Text = 'Horizontal';
            app.RotateAllVerticalMenu = uimenu(app.RotateAllMenu);
            app.RotateAllVerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllVertical, true);
            app.RotateAllVerticalMenu.Text = 'Vertical';
            app.RotateAllClockwiseMenu = uimenu(app.RotateAllMenu);
            app.RotateAllClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllClockwise, true);
            app.RotateAllClockwiseMenu.Text = 'Clockwise';
            app.RotateAllAntiClockwiseMenu = uimenu(app.RotateAllMenu);
            app.RotateAllAntiClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllAntiClockwise, true);
            app.RotateAllAntiClockwiseMenu.Text = 'Anti-Clockwise';
            app.RotateImageMenu = uimenu(app.ImageMenu);
            app.RotateImageMenu.Text = 'Rotate Image';
            app.RotateImageHorizontalMenu = uimenu(app.RotateImageMenu);
            app.RotateImageHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageHorizontal, true);
            app.RotateImageHorizontalMenu.Text = 'Horizontal';
            app.RotateImageVerticalMenu = uimenu(app.RotateImageMenu);
            app.RotateImageVerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageVertical, true);
            app.RotateImageVerticalMenu.Text = 'Vertical';
            app.RotateImageClockwiseMenu = uimenu(app.RotateImageMenu);
            app.RotateImageClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageClockwise, true);
            app.RotateImageClockwiseMenu.Text = 'Clockwise';
            app.RotateImageAntiClockwiseMenu = uimenu(app.RotateImageMenu);
            app.RotateImageAntiClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageAntiClockwise, true);
            app.RotateImageAntiClockwiseMenu.Text = 'Anti-Clockwise';
            app.RotateNeuronsMenu = uimenu(app.ImageMenu);
            app.RotateNeuronsMenu.Text = 'Rotate Neurons';
            app.RotateNeuronsHorizontalMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsHorizontal, true);
            app.RotateNeuronsHorizontalMenu.Text = 'Horizontal';
            app.RotateNeuronsVerticalMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsVerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsVertical, true);
            app.RotateNeuronsVerticalMenu.Text = 'Vertical';
            app.RotateNeuronsClockwiseMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsClockwise, true);
            app.RotateNeuronsClockwiseMenu.Text = 'Clockwise';
            app.RotateNeuronsAntiClockwiseMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsAntiClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsAntiClockwise, true);
            app.RotateNeuronsAntiClockwiseMenu.Text = 'Anti-Clockwise';
            app.MirrorImageMenu = uimenu(app.ImageMenu);
            app.MirrorImageMenu.MenuSelectedFcn = createCallbackFcn(app, @MirrorImage, true);
            app.MirrorImageMenu.Text = 'Mirror Image';
            app.MirrorNeuronsMenu = uimenu(app.ImageMenu);
            app.MirrorNeuronsMenu.MenuSelectedFcn = createCallbackFcn(app, @MirrorNeurons, true);
            app.MirrorNeuronsMenu.Text = 'Mirror Neurons';
            app.NeuronsMenu = uimenu(app.CELL_ID);
            app.NeuronsMenu.Text = 'Neurons';
            app.ImportneuronsfromMenu = uimenu(app.NeuronsMenu);
            app.ImportneuronsfromMenu.Text = 'Import neurons from...';
            app.CSVfileMenu = uimenu(app.ImportneuronsfromMenu);
            app.CSVfileMenu.MenuSelectedFcn = createCallbackFcn(app, @CSVfileMenuSelected, true);
            app.CSVfileMenu.Text = '... CSV file.';
            app.NWBfileMenu = uimenu(app.ImportneuronsfromMenu);
            app.NWBfileMenu.MenuSelectedFcn = createCallbackFcn(app, @NWBfileMenuSelected, true);
            app.NWBfileMenu.Text = '... NWB file.';
            app.ImportactivityfromMenu = uimenu(app.NeuronsMenu);
            app.ImportactivityfromMenu.Text = 'Import activity from...';
            app.NWBfileMenu_2 = uimenu(app.ImportactivityfromMenu);
            app.NWBfileMenu_2.MenuSelectedFcn = createCallbackFcn(app, @NWBfileMenu_2Selected, true);
            app.NWBfileMenu_2.Text = '... NWB file.';
            app.ZephIRoutputMenu = uimenu(app.ImportactivityfromMenu);
            app.ZephIRoutputMenu.MenuSelectedFcn = createCallbackFcn(app, @ZephIRoutputMenuSelected, true);
            app.ZephIRoutputMenu.Text = '... ZephIR output.';
            app.FlipLRNeuronsMenu = uimenu(app.NeuronsMenu);
            app.FlipLRNeuronsMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipLRNeuronsMenuSelected, true);
            app.FlipLRNeuronsMenu.Text = 'Flip L/R Neurons';
            app.AutoSegmentationMenu = uimenu(app.NeuronsMenu);
            app.AutoSegmentationMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoSegmentationMenuSelected, true);
            app.AutoSegmentationMenu.Text = 'Auto-Segmentation';
            app.NeuronActivityMenu = uimenu(app.NeuronsMenu);
            app.NeuronActivityMenu.Text = 'Neuron Activity';
            app.TraceActivityMenu_2 = uimenu(app.NeuronActivityMenu);
            app.TraceActivityMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TraceActivityMenu_2Selected, true);
            app.TraceActivityMenu_2.Text = 'Trace Activity';
            app.DisplayNeuronActivityMenu = uimenu(app.NeuronActivityMenu);
            app.DisplayNeuronActivityMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplayNeuronActivityMenuSelected, true);
            app.DisplayNeuronActivityMenu.Enable = 'off';
            app.DisplayNeuronActivityMenu.Text = 'Display Neuron Activity';
            app.BatchDetectIDNeuronsMenu = uimenu(app.NeuronsMenu);
            app.BatchDetectIDNeuronsMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchIDNeurons, true);
            app.BatchDetectIDNeuronsMenu.Text = 'Batch Detect + ID Neurons';
            app.DeleteUserIDsMenu = uimenu(app.NeuronsMenu);
            app.DeleteUserIDsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteUserIDs, true);
            app.DeleteUserIDsMenu.Text = 'Delete User IDs';
            app.DeleteModelIDsMenu = uimenu(app.NeuronsMenu);
            app.DeleteModelIDsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteModelIDs, true);
            app.DeleteModelIDsMenu.Text = 'Delete Model IDs';
            app.ToggleAutoIDUpdatesMenu = uimenu(app.NeuronsMenu);
            app.ToggleAutoIDUpdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleAutoIDUpdates, true);
            app.ToggleAutoIDUpdatesMenu.Text = 'Disable Auto-ID Updates';
            app.ToggleNeuronDetectionMenu = uimenu(app.NeuronsMenu);
            app.ToggleNeuronDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleNeuronDetection, true);
            app.ToggleNeuronDetectionMenu.Text = 'Use NN-Detect Neurons';
            app.DisplayMenu = uimenu(app.CELL_ID);
            app.DisplayMenu.Text = 'Display';
            app.ToggleBirthTimesMenu = uimenu(app.DisplayMenu);
            app.ToggleBirthTimesMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleNeuronBirthTimes, true);
            app.ToggleBirthTimesMenu.Text = 'Show Birth Times';
            app.NeuronDotsMenu = uimenu(app.DisplayMenu);
            app.NeuronDotsMenu.MenuSelectedFcn = createCallbackFcn(app, @NeuronDotsMenuSelected, true);
            app.NeuronDotsMenu.Text = 'Neuron Dots';
            app.GFPColorMenu = uimenu(app.DisplayMenu);
            app.GFPColorMenu.MenuSelectedFcn = createCallbackFcn(app, @GFPColorMenuSelected, true);
            app.GFPColorMenu.Text = 'GFP Color';
            app.AnalysisMenu = uimenu(app.CELL_ID);
            app.AnalysisMenu.Text = 'Analysis';
            app.SaveIDInfoMenu = uimenu(app.AnalysisMenu);
            app.SaveIDInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveIDs2CSV, true);
            app.SaveIDInfoMenu.Text = 'Save ID Info';
            app.SaveIDImageMenu = uimenu(app.AnalysisMenu);
            app.SaveIDImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveIDImageMenuSelected, true);
            app.SaveIDImageMenu.Text = 'Save ID Image';
            app.SaveNWBMenu = uimenu(app.AnalysisMenu);
            app.SaveNWBMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveNWBMenuSelected, true);
            app.SaveNWBMenu.Text = 'Save .NWB';
            app.HelpMenu = uimenu(app.CELL_ID);
            app.HelpMenu.Text = 'Help';
            app.CheckforUpdatesMenu = uimenu(app.HelpMenu);
            app.CheckforUpdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforUpdatesMenuSelected, true);
            app.CheckforUpdatesMenu.Text = 'Check for Updates';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.InstructionsMenu = uimenu(app.HelpMenu);
            app.InstructionsMenu.MenuSelectedFcn = createCallbackFcn(app, @InstructionsMenuSelected, true);
            app.InstructionsMenu.Text = 'Instructions';
            app.KeyboardShortcutsMenu = uimenu(app.HelpMenu);
            app.KeyboardShortcutsMenu.MenuSelectedFcn = createCallbackFcn(app, @KeyboardShortcutsMenuSelected, true);
            app.KeyboardShortcutsMenu.Text = 'Keyboard Shortcuts';
            app.NeuroPALWebsiteMenu = uimenu(app.HelpMenu);
            app.NeuroPALWebsiteMenu.MenuSelectedFcn = createCallbackFcn(app, @NeuroPALWebsiteMenuSelected, true);
            app.NeuroPALWebsiteMenu.Text = 'NeuroPAL Website';
            app.ReportaBugMenu = uimenu(app.HelpMenu);
            app.ReportaBugMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportBug, true);
            app.ReportaBugMenu.Text = 'Report a Bug';
            app.DebugMenu = uimenu(app.CELL_ID);
            app.DebugMenu.Visible = 'off';
            app.DebugMenu.Enable = 'off';
            app.DebugMenu.Text = 'Debug';
            app.LogtesteventMenu = uimenu(app.DebugMenu);
            app.LogtesteventMenu.MenuSelectedFcn = createCallbackFcn(app, @LogtesteventMenuSelected, true);
            app.LogtesteventMenu.Text = 'Log test event';
            app.LogfadeeventMenu = uimenu(app.DebugMenu);
            app.LogfadeeventMenu.MenuSelectedFcn = createCallbackFcn(app, @LogfadeeventMenuSelected, true);
            app.LogfadeeventMenu.Text = 'Log fade event';
            app.VolTraceParent = uipanel(app.CELL_ID);
            app.VolTraceParent.Visible = 'off';
            app.VolTraceHelperGrid = uigridlayout(app.VolTraceParent);
            app.VolTraceHelperGrid.Tag = 'browser_trace';
            app.Panel = uipanel(app.VolTraceHelperGrid);
            app.GridLayout = uigridlayout(app.Panel);
            app.DeleteNeuronDropDown = uidropdown(app.GridLayout);
            app.DeleteNeuronDropDown.Items = {};
            app.DeleteNeuronDropDown.ValueChangedFcn = createCallbackFcn(app, @DeleteNeuronDropDownValueChanged, true);
            app.DeleteNeuronDropDown.Placeholder = 'Delete Neuron';
            app.DeleteNeuronDropDown.Value = {};
            app.DeletePlotDropDown = uidropdown(app.GridLayout);
            app.DeletePlotDropDown.Items = {};
            app.DeletePlotDropDown.ValueChangedFcn = createCallbackFcn(app, @DeletePlotDropDownValueChanged, true);
            app.DeletePlotDropDown.Placeholder = 'Delete Plot';
            app.DeletePlotDropDown.Value = {};
            app.AddPlotButton = uibutton(app.GridLayout, 'push');
            app.AddPlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlotButtonPushed, true);
            app.AddPlotButton.Text = 'Add Plot';
            app.LoadStimuliButton = uibutton(app.GridLayout, 'push');
            app.LoadStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStimuliButtonPushed, true);
            app.LoadStimuliButton.Text = 'Load Stimuli';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {};
            app.DropDown.Placeholder = 'Normalize...';
            app.DropDown.Value = {};
            app.GridLayout59 = uigridlayout(app.CELL_ID);
            app.TabGroup = uitabgroup(app.GridLayout59);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.NeuroPALIDTab = uitab(app.TabGroup);
            app.NeuroPALIDTab.Title = 'NeuroPAL ID';
            app.IdGridLayout = uigridlayout(app.NeuroPALIDTab);
            app.IdGridLayout.Visible = 'off';
            app.SidePanel = uipanel(app.IdGridLayout);
            app.SidePanel.Tag = 'side-panel';
            app.SideGrid = uigridlayout(app.SidePanel);
            app.SideGrid.Tag = 'side-grid';
            app.NeuronListBoxPanel = uipanel(app.SideGrid);
            app.NeuronListBoxPanel.Tag = 'id-list-boxes';
            app.NeuronListBoxGrid = uigridlayout(app.NeuronListBoxPanel);
            app.UnIDdNeuronsListBox = uilistbox(app.NeuronListBoxGrid);
            app.UnIDdNeuronsListBox.Items = {};
            app.UnIDdNeuronsListBox.ValueChangedFcn = createCallbackFcn(app, @UnIDdNeuronsListChanged, true);
            app.UnIDdNeuronsListBox.Value = {};
            app.IDdLabel = uilabel(app.NeuronListBoxGrid);
            app.IDdLabel.Text = '     ID''d     ';
            app.IDdNeuronsListBox = uilistbox(app.NeuronListBoxGrid);
            app.IDdNeuronsListBox.Items = {''};
            app.IDdNeuronsListBox.ValueChangedFcn = createCallbackFcn(app, @IDdNeuronsListChanged, true);
            app.IDdNeuronsListBox.Tag = 'neuron-selector';
            app.IDdNeuronsListBox.ClickedFcn = createCallbackFcn(app, @IDdNeuronsListBoxClicked, true);
            app.IDdNeuronsListBox.DoubleClickedFcn = createCallbackFcn(app, @IDdNeuronsListBoxDoubleClicked, true);
            app.IDdNeuronsListBox.Value = '';
            app.UnIDdLabel = uilabel(app.NeuronListBoxGrid);
            app.UnIDdLabel.Text = '     Un-ID''d     ';
            app.NeuronRankPanel = uipanel(app.SideGrid);
            app.NeuronRankGrid = uigridlayout(app.NeuronRankPanel);
            app.Panel_25 = uipanel(app.NeuronRankGrid);
            app.GridLayout22 = uigridlayout(app.Panel_25);
            app.UserNeuronIDsListBox = uilistbox(app.GridLayout22);
            app.UserNeuronIDsListBox.Items = {};
            app.UserNeuronIDsListBox.ValueChangedFcn = createCallbackFcn(app, @AutoNeuronIDListBoxValueChanged, true);
            app.UserNeuronIDsListBox.Value = {};
            app.UserNeuronIDsListBoxLabel = uilabel(app.GridLayout22);
            app.UserNeuronIDsListBoxLabel.Text = 'User Neuron IDs';
            app.NeuronRankedConfidenceLabel = uilabel(app.GridLayout22);
            app.NeuronRankedConfidenceLabel.Text = 'Neuron Ranked Confidence';
            app.Panel_17 = uipanel(app.NeuronRankGrid);
            app.GridLayout21 = uigridlayout(app.Panel_17);
            app.NextNeuronLabel = uilabel(app.GridLayout21);
            app.NextNeuronLabel.Text = 'Next Neuron:';
            app.NextNeuronDropDown = uidropdown(app.GridLayout21);
            app.NextNeuronDropDown.Items = {'Nearest', 'Probabilistic'};
            app.NextNeuronDropDown.Value = 'Nearest';
            app.ChannelPanel = uipanel(app.SideGrid);
            app.ChannelGrid = uigridlayout(app.ChannelPanel);
            app.Panel_23 = uipanel(app.ChannelGrid);
            app.GridLayout26_5 = uigridlayout(app.Panel_23);
            app.DICDropDown = uidropdown(app.GridLayout26_5);
            app.DICDropDown.Items = {'1', '2', '3', '4', '5'};
            app.DICDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.DICDropDown.Value = '1';
            app.DICCheckBox = uicheckbox(app.GridLayout26_5);
            app.DICCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.DICCheckBox.Text = '';
            app.DICLabel = uilabel(app.GridLayout26_5);
            app.DICLabel.Text = 'DIC:';
            app.Panel_19 = uipanel(app.ChannelGrid);
            app.GridLayout26 = uigridlayout(app.Panel_19);
            app.GLabel = uilabel(app.GridLayout26);
            app.GLabel.Text = 'G:';
            app.GCheckBox = uicheckbox(app.GridLayout26);
            app.GCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.GCheckBox.Text = '';
            app.GCheckBox.Value = true;
            app.GDropDown = uidropdown(app.GridLayout26);
            app.GDropDown.Items = {'1', '2', '3', '4', '5', ''};
            app.GDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.GDropDown.Value = '1';
            app.Panel_24 = uipanel(app.ChannelGrid);
            app.GridLayout26_6 = uigridlayout(app.Panel_24);
            app.GFPLabel = uilabel(app.GridLayout26_6);
            app.GFPLabel.Text = 'GFP:';
            app.GFPCheckBox = uicheckbox(app.GridLayout26_6);
            app.GFPCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.GFPCheckBox.Text = '';
            app.GFPDropDown = uidropdown(app.GridLayout26_6);
            app.GFPDropDown.Items = {'1', '2', '3', '4', '5'};
            app.GFPDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.GFPDropDown.Value = '1';
            app.Panel_20 = uipanel(app.ChannelGrid);
            app.GridLayout26_2 = uigridlayout(app.Panel_20);
            app.BLabel = uilabel(app.GridLayout26_2);
            app.BLabel.Text = 'B:';
            app.BCheckBox = uicheckbox(app.GridLayout26_2);
            app.BCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.BCheckBox.Text = '';
            app.BCheckBox.Value = true;
            app.BDropDown = uidropdown(app.GridLayout26_2);
            app.BDropDown.Items = {'1', '2', '3', '4', '5', ''};
            app.BDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.BDropDown.Value = '1';
            app.Panel_21 = uipanel(app.ChannelGrid);
            app.GridLayout26_3 = uigridlayout(app.Panel_21);
            app.WCheckBox = uicheckbox(app.GridLayout26_3);
            app.WCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.WCheckBox.Text = '';
            app.WDropDown = uidropdown(app.GridLayout26_3);
            app.WDropDown.Items = {'1', '2', '3', '4', '5'};
            app.WDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.WDropDown.Value = '1';
            app.WLabel = uilabel(app.GridLayout26_3);
            app.WLabel.Text = 'W:';
            app.Panel_18 = uipanel(app.ChannelGrid);
            app.GridLayout25 = uigridlayout(app.Panel_18);
            app.RLabel = uilabel(app.GridLayout25);
            app.RLabel.Text = 'R:';
            app.RCheckBox = uicheckbox(app.GridLayout25);
            app.RCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.RCheckBox.Text = '';
            app.RCheckBox.Value = true;
            app.RDropDown = uidropdown(app.GridLayout25);
            app.RDropDown.Items = {'1', '2', '3', '4', '5', ''};
            app.RDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.RDropDown.Value = '1';
            app.Panel_6 = uipanel(app.IdGridLayout);
            app.GridLayout19 = uigridlayout(app.Panel_6);
            app.GanglionTree = uitree(app.GridLayout19);
            app.GanglionTree.Multiselect = 'on';
            app.GanglionTree.SelectionChangedFcn = createCallbackFcn(app, @GanglionTreeSelectionChanged, true);
            app.PharynxNode = uitreenode(app.GanglionTree);
            app.PharynxNode.Text = 'Pharynx';
            app.AnteriorNode = uitreenode(app.PharynxNode);
            app.AnteriorNode.Text = 'Anterior';
            app.PosteriorNode = uitreenode(app.PharynxNode);
            app.PosteriorNode.Text = 'Posterior';
            app.HeadNode = uitreenode(app.GanglionTree);
            app.HeadNode.Text = 'Head';
            app.AnteriorLNode = uitreenode(app.HeadNode);
            app.AnteriorLNode.Text = 'Anterior (L)';
            app.AnteriorRNode = uitreenode(app.HeadNode);
            app.AnteriorRNode.Text = 'Anterior (R)';
            app.DorsalNode = uitreenode(app.HeadNode);
            app.DorsalNode.Text = 'Dorsal';
            app.LateralLNode = uitreenode(app.HeadNode);
            app.LateralLNode.Text = 'Lateral (L)';
            app.LateralRNode = uitreenode(app.HeadNode);
            app.LateralRNode.Text = 'Lateral (R)';
            app.VentralNode = uitreenode(app.HeadNode);
            app.VentralNode.Text = 'Ventral';
            app.RetroVesicularNode = uitreenode(app.HeadNode);
            app.RetroVesicularNode.Text = 'Retro-Vesicular';
            app.MidbodyNode = uitreenode(app.GanglionTree);
            app.MidbodyNode.Text = 'Midbody';
            app.AnteriorNode_2 = uitreenode(app.MidbodyNode);
            app.AnteriorNode_2.Text = 'Anterior';
            app.CentralNode = uitreenode(app.MidbodyNode);
            app.CentralNode.Text = 'Central';
            app.PosteriorNode_2 = uitreenode(app.MidbodyNode);
            app.PosteriorNode_2.Text = 'Posterior';
            app.VentralCordNode = uitreenode(app.MidbodyNode);
            app.VentralCordNode.Text = 'Ventral Cord';
            app.TailNode = uitreenode(app.GanglionTree);
            app.TailNode.Text = 'Tail';
            app.PreAnalNode = uitreenode(app.TailNode);
            app.PreAnalNode.Text = 'Pre-Anal';
            app.DorsoRectalNode = uitreenode(app.TailNode);
            app.DorsoRectalNode.Text = 'Dorso-Rectal';
            app.LumbarLNode = uitreenode(app.TailNode);
            app.LumbarLNode.Text = 'Lumbar (L)';
            app.LumbarRNode = uitreenode(app.TailNode);
            app.LumbarRNode.Text = 'Lumbar (R)';
            app.GanglionNeuronIDLabel = uilabel(app.GridLayout19);
            app.GanglionNeuronIDLabel.Text = 'Ganglion Neuron ID';
            app.Panel_7 = uipanel(app.IdGridLayout);
            app.GridLayout14 = uigridlayout(app.Panel_7);
            app.TabGroup4 = uitabgroup(app.GridLayout14);
            app.MaximumIntensityProjectionTab = uitab(app.TabGroup4);
            app.MaximumIntensityProjectionTab.Title = 'Maximum Intensity Projection';
            app.GridLayout76 = uigridlayout(app.MaximumIntensityProjectionTab);
            app.MaxProjection = uiaxes(app.GridLayout76);
            title(app.MaxProjection, 'Maximum Intensity Projection')
            app.NeuronActivityTab = uitab(app.TabGroup4);
            app.NeuronActivityTab.Title = 'Neuron Activity';
            app.NeuronActivityTab.Tag = 'neuron-activity-tab';
            app.GridLayout75 = uigridlayout(app.NeuronActivityTab);
            app.LoadNeuronActivityButton = uibutton(app.GridLayout75, 'push');
            app.LoadNeuronActivityButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNeuronActivityButtonPushed, true);
            app.LoadNeuronActivityButton.Text = 'Load Neuron Activity';
            app.Panel_8 = uipanel(app.IdGridLayout);
            app.GridLayout11 = uigridlayout(app.Panel_8);
            app.Panel_10 = uipanel(app.GridLayout11);
            app.GridLayout13 = uigridlayout(app.Panel_10);
            app.ZLeftLabel = uilabel(app.GridLayout13);
            app.ZLeftLabel.Text = 'LEFT';
            app.ZAxisDropDown = uidropdown(app.GridLayout13);
            app.ZAxisDropDown.Items = {'L/R', 'D/V'};
            app.ZAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ZAxisChanged, true);
            app.ZAxisDropDown.Value = 'L/R';
            app.Panel_11 = uipanel(app.GridLayout11);
            app.GridLayout12 = uigridlayout(app.Panel_11);
            app.FlipZButton = uibutton(app.GridLayout12, 'push');
            app.FlipZButton.ButtonPushedFcn = createCallbackFcn(app, @FlipZButtonPushed, true);
            app.FlipZButton.Text = 'Flip Z';
            app.ZRightLabel = uilabel(app.GridLayout12);
            app.ZRightLabel.Text = 'RIGHT';
            app.CenterZLabel = uilabel(app.GridLayout12);
            app.CenterZLabel.Text = 'Center Z:';
            app.ZCenterEditField = uieditfield(app.GridLayout12, 'numeric');
            app.ZCenterEditField.ValueChangedFcn = createCallbackFcn(app, @ZCenterChanged, true);
            app.ZCenterEditField.Value = 10;
            app.Panel_16 = uipanel(app.GridLayout11);
            app.GridLayout20 = uigridlayout(app.Panel_16);
            app.ZSliderLabel = uilabel(app.GridLayout20);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.GridLayout20);
            app.ZSlider.Limits = [1 20];
            app.ZSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ZSlider.MinorTicks = [];
            app.ZSlider.Value = 1;
            app.XYPanel = uipanel(app.IdGridLayout);
            app.GridLayout10 = uigridlayout(app.XYPanel);
            app.XY = uiaxes(app.GridLayout10);
            app.Panel_9 = uipanel(app.IdGridLayout);
            app.IdMetadataGridLayout = uigridlayout(app.Panel_9);
            app.Panel_30 = uipanel(app.IdMetadataGridLayout);
            app.GridLayout24_2 = uigridlayout(app.Panel_30);
            app.AutoIDDropDown = uidropdown(app.GridLayout24_2);
            app.AutoIDDropDown.Items = {};
            app.AutoIDDropDown.ValueChangedFcn = createCallbackFcn(app, @AutoIDDropDownValueChanged, true);
            app.AutoIDDropDown.Enable = 'off';
            app.AutoIDDropDown.Value = {};
            app.NeuronsLabel = uilabel(app.GridLayout24_2);
            app.NeuronsLabel.Text = 'Neurons:';
            app.AutoDetectButton = uibutton(app.GridLayout24_2, 'push');
            app.AutoDetectButton.ButtonPushedFcn = createCallbackFcn(app, @AutoDetectButtonPushed, true);
            app.AutoDetectButton.Text = 'Auto-Detect';
            app.AutoIDAllButton = uibutton(app.GridLayout24_2, 'push');
            app.AutoIDAllButton.ButtonPushedFcn = createCallbackFcn(app, @AutoIDAllButtonPushed, true);
            app.AutoIDAllButton.Text = 'Auto-ID All';
            app.AutoLabel = uilabel(app.GridLayout24_2);
            app.AutoLabel.Text = 'Auto:';
            app.AutoIDButton = uibutton(app.GridLayout24_2, 'push');
            app.AutoIDButton.ButtonPushedFcn = createCallbackFcn(app, @AutoIDButtonPushed, true);
            app.AutoIDButton.Text = 'Auto ID';
            app.UserLabel = uilabel(app.GridLayout24_2);
            app.UserLabel.Text = 'User:';
            app.IDEditField = uieditfield(app.GridLayout24_2, 'text');
            app.IDEditField.ValueChangingFcn = createCallbackFcn(app, @IDEditFieldChanging, true);
            app.IDEditField.Enable = 'off';
            app.UserIDButton = uibutton(app.GridLayout24_2, 'push');
            app.UserIDButton.ButtonPushedFcn = createCallbackFcn(app, @UserIDButtonPushed, true);
            app.UserIDButton.Text = 'User ID';
            app.ClicksLabel = uilabel(app.GridLayout24_2);
            app.ClicksLabel.Text = 'Clicks:';
            app.MouseClickDropDown = uidropdown(app.GridLayout24_2);
            app.MouseClickDropDown.Items = {'Add Neuron', 'Detect Neuron'};
            app.MouseClickDropDown.Value = 'Add Neuron';
            app.AutoNameLabel = uilabel(app.GridLayout24_2);
            app.AutoNameLabel.Text = 'Auto Name:';
            app.AutoNameCheckBox = uicheckbox(app.GridLayout24_2);
            app.AutoNameCheckBox.Text = '';
            app.AutoNameCheckBox.Value = true;
            app.ColorAtlasCheckBoxLabel = uilabel(app.GridLayout24_2);
            app.ColorAtlasCheckBoxLabel.Text = 'Color Atlas:';
            app.ColorAtlasCheckBox = uicheckbox(app.GridLayout24_2);
            app.ColorAtlasCheckBox.Text = '';
            app.Panel_26 = uipanel(app.IdMetadataGridLayout);
            app.GridLayout24 = uigridlayout(app.Panel_26);
            app.OpenButton = uibutton(app.GridLayout24, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFile, true);
            app.OpenButton.Text = 'Open';
            app.FileLabel = uilabel(app.GridLayout24);
            app.FileLabel.Text = 'File:';
            app.SaveIDsButton = uibutton(app.GridLayout24, 'push');
            app.SaveIDsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIDs2CSV, true);
            app.SaveIDsButton.Text = 'IDs';
            app.SaveLabel = uilabel(app.GridLayout24);
            app.SaveLabel.Text = 'Save:';
            app.BodyLabel = uilabel(app.GridLayout24);
            app.BodyLabel.Text = 'Body:';
            app.BodyDropDown = uidropdown(app.GridLayout24);
            app.BodyDropDown.Items = {'Whole Worm', 'Head', 'Midbody', 'Anterior Midbody', 'Central Midbody', 'Posterior Midbody', 'Tail'};
            app.BodyDropDown.ValueChangedFcn = createCallbackFcn(app, @BodyDropDownValueChanged, true);
            app.BodyDropDown.Value = 'Whole Worm';
            app.SexLabel = uilabel(app.GridLayout24);
            app.SexLabel.Text = 'Sex:';
            app.SexDropDown = uidropdown(app.GridLayout24);
            app.SexDropDown.Items = {'XX', 'XO'};
            app.SexDropDown.ValueChangedFcn = createCallbackFcn(app, @SexDropDownValueChanged, true);
            app.SexDropDown.Value = 'XX';
            app.AgeDropDownLabel = uilabel(app.GridLayout24);
            app.AgeDropDownLabel.Text = 'Age:';
            app.AgeDropDown = uidropdown(app.GridLayout24);
            app.AgeDropDown.Items = {'Adult', 'L4', 'L3', 'L2', 'L1', '3-Fold'};
            app.AgeDropDown.ValueChangedFcn = createCallbackFcn(app, @AgeDropDownValueChanged, true);
            app.AgeDropDown.Value = 'Adult';
            app.StrainEditFieldLabel = uilabel(app.GridLayout24);
            app.StrainEditFieldLabel.Text = 'Strain:';
            app.StrainEditField = uieditfield(app.GridLayout24, 'text');
            app.StrainEditField.ValueChangedFcn = createCallbackFcn(app, @StrainEditFieldValueChanged, true);
            app.NotesEditFieldLabel = uilabel(app.GridLayout24);
            app.NotesEditFieldLabel.Text = 'Notes:';
            app.NotesEditField = uieditfield(app.GridLayout24, 'text');
            app.NotesEditField.ValueChangedFcn = createCallbackFcn(app, @NotesEditFieldValueChanged, true);
            app.VideoTrackingTab = uitab(app.TabGroup);
            app.VideoTrackingTab.Title = 'Video Tracking';
            app.VideoTrackingTab.Tag = 'raw';
            app.VideoGridLayout = uigridlayout(app.VideoTrackingTab);
            app.VideoGridLayout.Visible = 'off';
            app.NeuronInformationPanel = uipanel(app.VideoGridLayout);
            app.NeuronInformationPanel.Title = 'Neuron Information';
            app.GridLayout9_2 = uigridlayout(app.NeuronInformationPanel);
            app.XCoordinateEditFieldLabel = uilabel(app.GridLayout9_2);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.GridLayout9_2, 'numeric');
            app.YCoordinateEditFieldLabel = uilabel(app.GridLayout9_2);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.GridLayout9_2, 'numeric');
            app.ZCoordinateEditFieldLabel = uilabel(app.GridLayout9_2);
            app.ZCoordinateEditFieldLabel.Text = 'Z Coordinate';
            app.ZCoordinateEditField = uieditfield(app.GridLayout9_2, 'numeric');
            app.Button = uibutton(app.GridLayout9_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '✔';
            app.Button_2 = uibutton(app.GridLayout9_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed2, true);
            app.Button_2.Text = '🗑';
            app.ColorLabel = uilabel(app.GridLayout9_2);
            app.ColorLabel.Text = 'Color';
            app.ColorButton = uibutton(app.GridLayout9_2, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = '';
            app.WorldlineIDEditFieldLabel = uilabel(app.GridLayout9_2);
            app.WorldlineIDEditFieldLabel.Text = 'Worldline ID';
            app.WorldlineIDEditField = uieditfield(app.GridLayout9_2, 'numeric');
            app.ProvenanceEditFieldLabel = uilabel(app.GridLayout9_2);
            app.ProvenanceEditFieldLabel.Text = 'Provenance';
            app.ProvenanceEditField = uieditfield(app.GridLayout9_2, 'text');
            app.ProvenanceEditField.Placeholder = 'NPAL';
            app.NameEditFieldLabel = uilabel(app.GridLayout9_2);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout9_2, 'text');
            app.NameEditField.Placeholder = 'Neuron Name';
            app.WorldlineInformationPanel = uipanel(app.VideoGridLayout);
            app.WorldlineInformationPanel.Title = 'Worldline Information';
            app.GridLayout51 = uigridlayout(app.WorldlineInformationPanel);
            app.Panel_32 = uipanel(app.GridLayout51);
            app.GridLayout52 = uigridlayout(app.Panel_32);
            app.AssignrandomcolorstoblanksButton = uibutton(app.GridLayout52, 'push');
            app.AssignrandomcolorstoblanksButton.ButtonPushedFcn = createCallbackFcn(app, @AssignrandomcolorstoblanksButtonPushed, true);
            app.AssignrandomcolorstoblanksButton.Text = 'Assign random colors to blanks';
            app.WorldlineTree = uitree(app.GridLayout51, 'checkbox');
            app.WorldlineTree.SelectionChangedFcn = createCallbackFcn(app, @WorldlineTreeSelectionChanged, true);
            app.IDdNode = uitreenode(app.WorldlineTree);
            app.IDdNode.Text = 'ID''d';
            app.UnIDdNode = uitreenode(app.WorldlineTree);
            app.UnIDdNode.Text = 'Un-ID''d';
            app.WorkflowPanel = uipanel(app.VideoGridLayout);
            app.WorkflowPanel.Title = 'Workflow';
            app.GridLayout13_2 = uigridlayout(app.WorkflowPanel);
            app.InsertNeuroPALNeuronsButton = uibutton(app.GridLayout13_2, 'push');
            app.InsertNeuroPALNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @InsertNeuroPALNeuronsButtonPushed, true);
            app.InsertNeuroPALNeuronsButton.Text = 'Insert NeuroPAL Neurons';
            app.SaveResultsButton = uibutton(app.GridLayout13_2, 'push');
            app.SaveResultsButton.Text = 'Save Results';
            app.Panel_33 = uipanel(app.GridLayout13_2);
            app.GridLayout14_2 = uigridlayout(app.Panel_33);
            app.RecommendFramesButton = uibutton(app.GridLayout14_2, 'push');
            app.RecommendFramesButton.ButtonPushedFcn = createCallbackFcn(app, @RecommendFramesButtonPushed, true);
            app.RecommendFramesButton.Text = 'Recommend Frames';
            app.TrackNeuronsButton = uibutton(app.GridLayout14_2, 'push');
            app.TrackNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @TrackNeuronsButtonPushed, true);
            app.TrackNeuronsButton.Text = 'Track Neurons';
            app.ExtractActivityButton = uibutton(app.GridLayout14_2, 'push');
            app.ExtractActivityButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractActivityButtonPushed, true);
            app.ExtractActivityButton.Text = 'Extract Activity';
            app.InsertlastIDdFrameButton = uibutton(app.GridLayout13_2, 'push');
            app.InsertlastIDdFrameButton.ButtonPushedFcn = createCallbackFcn(app, @InsertlastIDdFrameButtonPushed, true);
            app.InsertlastIDdFrameButton.Text = 'Insert last ID''d Frame';
            app.Panel_34 = uipanel(app.VideoGridLayout);
            app.GridLayout40 = uigridlayout(app.Panel_34);
            app.Panel_35 = uipanel(app.GridLayout40);
            app.GridLayout41 = uigridlayout(app.Panel_35);
            app.xEditFieldLabel = uilabel(app.GridLayout41);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GridLayout41, 'numeric');
            app.yEditFieldLabel = uilabel(app.GridLayout41);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.GridLayout41, 'numeric');
            app.zEditFieldLabel = uilabel(app.GridLayout41);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.GridLayout41, 'numeric');
            app.Panel_36 = uipanel(app.GridLayout40);
            app.GridLayout42 = uigridlayout(app.Panel_36);
            app.Panel_37 = uipanel(app.GridLayout42);
            app.GridLayout43 = uigridlayout(app.Panel_37);
            app.tEditFieldLabel = uilabel(app.GridLayout43);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.GridLayout43, 'numeric');
            app.Panel_38 = uipanel(app.GridLayout42);
            app.GridLayout44 = uigridlayout(app.Panel_38);
            app.tSlider = uislider(app.GridLayout44);
            app.tSlider.ValueChangedFcn = createCallbackFcn(app, @tSliderValueChanged, true);
            app.Panel_55 = uipanel(app.VideoGridLayout);
            app.GridLayout2_2 = uigridlayout(app.Panel_55);
            app.yzAxes = uiaxes(app.GridLayout2_2);
            app.yzAxes.XTickLabel = '';
            app.yzAxes.YTickLabel = '';
            app.yzAxes.Tag = 'yz';
            app.xzAxes = uiaxes(app.GridLayout2_2);
            app.xzAxes.XTickLabel = '';
            app.xzAxes.YTickLabel = '';
            app.xzAxes.Tag = 'xz';
            app.xyAxes = uiaxes(app.GridLayout2_2);
            app.xyAxes.XTickLabel = '';
            app.xyAxes.YTickLabel = '';
            app.xyAxes.Tag = 'xy';
            app.xSlider = uislider(app.GridLayout2_2);
            app.xSlider.MajorTicks = [];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.xSlider.MinorTicks = [];
            app.xSlider.Value = 50;
            app.hor_zSlider = uislider(app.GridLayout2_2);
            app.hor_zSlider.MajorTicks = [];
            app.hor_zSlider.ValueChangedFcn = createCallbackFcn(app, @hor_zSliderValueChanged, true);
            app.hor_zSlider.ValueChangingFcn = createCallbackFcn(app, @hor_zSliderValueChanging, true);
            app.hor_zSlider.MinorTicks = [];
            app.hor_zSlider.Value = 50;
            app.ySlider = uislider(app.GridLayout2_2);
            app.ySlider.MajorTicks = [];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.ySlider.MinorTicks = [];
            app.ySlider.Value = 50;
            app.vert_zSlider = uislider(app.GridLayout2_2);
            app.vert_zSlider.MajorTicks = [];
            app.vert_zSlider.ValueChangedFcn = createCallbackFcn(app, @vert_zSliderValueChanged, true);
            app.vert_zSlider.ValueChangingFcn = createCallbackFcn(app, @vert_zSliderValueChanging, true);
            app.vert_zSlider.MinorTicks = [];
            app.vert_zSlider.Value = 50;
            app.DefaultTabGroup = uitabgroup(app.VideoGridLayout);
            app.AnnotationSupportTab = uitab(app.DefaultTabGroup);
            app.AnnotationSupportTab.Title = 'Annotation Support';
            app.GridLayout7_2 = uigridlayout(app.AnnotationSupportTab);
            app.BookmarkedFramesPanel = uipanel(app.GridLayout7_2);
            app.BookmarkedFramesPanel.Title = 'Bookmarked Frames';
            app.GridLayout8_2 = uigridlayout(app.BookmarkedFramesPanel);
            app.GotoBookmarkButton = uibutton(app.GridLayout8_2, 'push');
            app.GotoBookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @GotoBookmarkButtonPushed, true);
            app.GotoBookmarkButton.Text = 'Go to Bookmark';
            app.SetBookmarkButton = uibutton(app.GridLayout8_2, 'push');
            app.SetBookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @SetBookmarkButtonPushed, true);
            app.SetBookmarkButton.Text = 'Set Bookmark';
            app.BookmarkNameEditField = uieditfield(app.GridLayout8_2, 'text');
            app.BookmarkNameEditField.Placeholder = 'Bookmark Name';
            app.DeleteBookmarkButton = uibutton(app.GridLayout8_2, 'push');
            app.DeleteBookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBookmarkButtonPushed, true);
            app.DeleteBookmarkButton.Text = 'Delete Bookmark';
            app.BookmarkListBox = uilistbox(app.GridLayout8_2);
            app.BookmarkListBox.Items = {};
            app.BookmarkListBox.Value = {};
            app.ColorControlPanel = uipanel(app.GridLayout7_2);
            app.ColorControlPanel.Title = 'Color Control';
            app.GridLayout16_2 = uigridlayout(app.ColorControlPanel);
            app.RSliderLabel = uilabel(app.GridLayout16_2);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.GridLayout16_2);
            app.RSlider.Limits = [0 10];
            app.RSlider.MajorTicks = [];
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.MinorTicks = [];
            app.RSlider.Value = 1;
            app.GSliderLabel = uilabel(app.GridLayout16_2);
            app.GSliderLabel.Text = 'G';
            app.GSlider = uislider(app.GridLayout16_2);
            app.GSlider.Limits = [0 10];
            app.GSlider.MajorTicks = [];
            app.GSlider.ValueChangedFcn = createCallbackFcn(app, @GSliderValueChanged, true);
            app.GSlider.MinorTicks = [];
            app.GSlider.Value = 1;
            app.BSliderLabel = uilabel(app.GridLayout16_2);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.GridLayout16_2);
            app.BSlider.Limits = [0 10];
            app.BSlider.MajorTicks = [];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.BSlider.MinorTicks = [];
            app.BSlider.Value = 1;
            app.HotkeysPanel = uipanel(app.GridLayout7_2);
            app.HotkeysPanel.Title = 'Hotkeys';
            app.GridLayout47 = uigridlayout(app.HotkeysPanel);
            app.Frame1HKButton = uibutton(app.GridLayout47, 'state');
            app.Frame1HKButton.Text = 'CTRL+1';
            app.JmpFrameEditField = uieditfield(app.GridLayout47, 'numeric');
            app.JmpFrameEditField.Value = 1;
            app.Frame2HKButton = uibutton(app.GridLayout47, 'state');
            app.Frame2HKButton.Text = 'CTRL+2';
            app.JmpFrameEditField_2 = uieditfield(app.GridLayout47, 'numeric');
            app.JmpFrameEditField_2.Value = 2;
            app.DeleteHKButton = uibutton(app.GridLayout47, 'state');
            app.DeleteHKButton.Text = 'CTRL+a';
            app.JumpHKButton = uibutton(app.GridLayout47, 'state');
            app.JumpHKButton.Text = 'CTRL+0';
            app.TextArea = uitextarea(app.GridLayout47);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Value = {'Jump'; 'to Frame'};
            app.TextArea_2 = uitextarea(app.GridLayout47);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Enable = 'off';
            app.TextArea_2.Value = {'Jump'; 'to Frame'};
            app.TextArea_3 = uitextarea(app.GridLayout47);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Enable = 'off';
            app.TextArea_3.Value = {'Jump'; 'to Un-ID''d'};
            app.TextArea_4 = uitextarea(app.GridLayout47);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Enable = 'off';
            app.TextArea_4.Value = {'Backward n'; 'Frames'};
            app.DropDown_2 = uidropdown(app.GridLayout47);
            app.DropDown_2.Items = {'In Frame', 'Cross Frame'};
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = 'In Frame';
            app.InsertHKButton = uibutton(app.GridLayout47, 'state');
            app.InsertHKButton.Text = 'CTRL+d';
            app.TextArea_5 = uitextarea(app.GridLayout47);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Enable = 'off';
            app.TextArea_5.Value = {'Forward n'; 'Frames'};
            app.FwdFramesEditField = uieditfield(app.GridLayout47, 'numeric');
            app.FwdFramesEditField.Value = 10;
            app.BwdFramesEditField = uieditfield(app.GridLayout47, 'numeric');
            app.BwdFramesEditField.Value = 10;
            app.AutosegmentFrameButton = uibutton(app.GridLayout7_2, 'push');
            app.AutosegmentFrameButton.Enable = 'off';
            app.AutosegmentFrameButton.Text = 'Autosegment Frame';
            app.Panel_39 = uipanel(app.GridLayout7_2);
            app.GridLayout48 = uigridlayout(app.Panel_39);
            app.AutojumponIDCheckBox = uicheckbox(app.GridLayout48);
            app.AutojumponIDCheckBox.Text = 'Auto-jump on ID.';
            app.OverlaylastIDdframeCheckBox = uicheckbox(app.GridLayout48);
            app.OverlaylastIDdframeCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlaylastIDdframeCheckBoxValueChanged, true);
            app.OverlaylastIDdframeCheckBox.Text = 'Overlay last ID''d frame.';
            app.OverlayNeuroPALvolCheckBox = uicheckbox(app.GridLayout48);
            app.OverlayNeuroPALvolCheckBox.Enable = 'off';
            app.OverlayNeuroPALvolCheckBox.Text = 'Overlay NeuroPAL vol.';
            app.OverlayFrameMIPCheckBox = uicheckbox(app.GridLayout48);
            app.OverlayFrameMIPCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayFrameMIPCheckBoxValueChanged, true);
            app.OverlayFrameMIPCheckBox.Text = 'Overlay Frame MIP.';
            app.ShowallneuronsCheckBox = uicheckbox(app.GridLayout48);
            app.ShowallneuronsCheckBox.Text = 'Show all neurons.';
            app.Panel_40 = uipanel(app.GridLayout7_2);
            app.GridLayout53 = uigridlayout(app.Panel_40);
            app.MarkerSizeLabel = uilabel(app.GridLayout53);
            app.MarkerSizeLabel.Text = 'Marker Size';
            app.MarkerSizeSlider = uislider(app.GridLayout53);
            app.MarkerSizeSlider.Limits = [1 30];
            app.MarkerSizeSlider.MajorTicks = [];
            app.MarkerSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSliderValueChanged, true);
            app.MarkerSizeSlider.MinorTicks = [];
            app.MarkerSizeSlider.Value = 5;
            app.ManipulateNeuronsButton = uibutton(app.GridLayout7_2, 'push');
            app.ManipulateNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @ManipulateNeuronsButtonPushed, true);
            app.ManipulateNeuronsButton.Text = 'Manipulate Neurons';
            app.ParametersTab = uitab(app.DefaultTabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout58 = uigridlayout(app.ParametersTab);
            app.BasicParameterPanel = uipanel(app.GridLayout58);
            app.ParameterGridLayout = uigridlayout(app.BasicParameterPanel);
            app.EnableadvancedparametersCheckBox = uicheckbox(app.ParameterGridLayout);
            app.EnableadvancedparametersCheckBox.Text = 'Enable advanced parameters.';
            app.ImportparametersButton = uibutton(app.ParameterGridLayout, 'push');
            app.ImportparametersButton.Text = 'Import parameters';
            app.EditadvancedparametersButton = uibutton(app.ParameterGridLayout, 'push');
            app.EditadvancedparametersButton.Enable = 'off';
            app.EditadvancedparametersButton.Text = 'Edit advanced parameters';
            app.ExportparametersButton = uibutton(app.ParameterGridLayout, 'push');
            app.ExportparametersButton.Text = 'Export parameters';
            app.AdvSetTab = uitabgroup(app.ParameterGridLayout);
            app.GeneralTab = uitab(app.AdvSetTab);
            app.GeneralTab.Title = 'General';
            app.GridLayout31 = uigridlayout(app.GeneralTab);
            app.AllowRotationsSwitchLabel = uilabel(app.GridLayout31);
            app.AllowRotationsSwitchLabel.Text = 'Allow Rotations';
            app.AllowRotationsSwitch = uiswitch(app.GridLayout31, 'slider');
            app.MotionPredictionSwitchLabel = uilabel(app.GridLayout31);
            app.MotionPredictionSwitchLabel.Text = 'Motion Prediction';
            app.MotionPredictionSwitch = uiswitch(app.GridLayout31, 'slider');
            app.UseGPUSwitchLabel = uilabel(app.GridLayout31);
            app.UseGPUSwitchLabel.Text = 'Use GPU';
            app.UseGPUSwitch = uiswitch(app.GridLayout31, 'slider');
            app.TrackingSelectionPanel = uipanel(app.GridLayout31);
            app.TrackingSelectionPanel.Title = 'Tracking Selection';
            app.GridLayout49 = uigridlayout(app.TrackingSelectionPanel);
            app.EditField = uieditfield(app.GridLayout49, 'numeric');
            app.EndFrameLabel = uilabel(app.GridLayout49);
            app.EndFrameLabel.Text = 'End Frame:';
            app.EditField_2 = uieditfield(app.GridLayout49, 'numeric');
            app.StartingFrameLabel = uilabel(app.GridLayout49);
            app.StartingFrameLabel.Text = 'Starting Frame:';
            app.TrackspecificregionButton = uibutton(app.GridLayout49, 'push');
            app.TrackspecificregionButton.Text = 'Track specific region';
            app.TrackspecificneuronsButton = uibutton(app.GridLayout49, 'push');
            app.TrackspecificneuronsButton.Text = 'Track specific neurons';
            app.ImageMotionTab = uitab(app.AdvSetTab);
            app.ImageMotionTab.Title = 'Image & Motion';
            app.GridLayout34 = uigridlayout(app.ImageMotionTab);
            app.foveasigmaLabel = uilabel(app.GridLayout34);
            app.foveasigmaLabel.Text = 'fovea sigma';
            app.EditFieldLabel = uilabel(app.GridLayout34);
            app.EditFieldLabel.Text = 'Sigma for Gaussian mask over foveated regions of the descriptors.';
            app.fovea_sigma = uieditfield(app.GridLayout34, 'numeric');
            app.fovea_sigma.Tooltip = {'Decreasing this can help prioritize keeping a keypoint at the center. Increase to a large number or set at -1 to disable.'};
            app.fovea_sigma.Value = 2.5;
            app.grid_shapeLabel = uilabel(app.GridLayout34);
            app.grid_shapeLabel.Text = 'grid_shape';
            app.SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel = uilabel(app.GridLayout34);
            app.SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel.Text = 'Size of the image descriptors in the xy-plane in pixels.';
            app.grid_shape = uieditfield(app.GridLayout34, 'numeric');
            app.grid_shape.Value = 25;
            app.dimmer_ratioLabel = uilabel(app.GridLayout34);
            app.dimmer_ratioLabel.Text = 'dimmer_ratio';
            app.CoefficientfordimmingnonfoveatedregionsEditFieldLabel = uilabel(app.GridLayout34);
            app.CoefficientfordimmingnonfoveatedregionsEditFieldLabel.Text = 'Coefficient for dimming non-foveated regions.';
            app.dimmer_ratio = uieditfield(app.GridLayout34, 'numeric');
            app.ComputationTab = uitab(app.AdvSetTab);
            app.ComputationTab.Title = 'Computation';
            app.GridLayout31_2 = uigridlayout(app.ComputationTab);
            app.n_epochLabel = uilabel(app.GridLayout31_2);
            app.n_epochLabel.Text = 'n_epoch';
            app.NumberofiterationsforimageregistrationLREditFieldLabel = uilabel(app.GridLayout31_2);
            app.NumberofiterationsforimageregistrationLREditFieldLabel.Text = 'Number of iterations for image registration, LR.';
            app.n_epoch_dLabel = uilabel(app.GridLayout31_2);
            app.n_epoch_dLabel.Text = 'n_epoch_d';
            app.n_epoch = uieditfield(app.GridLayout31_2, 'numeric');
            app.n_epoch.ValueDisplayFormat = '%.0f';
            app.n_epoch.Value = 40;
            app.EditField2_2Label = uilabel(app.GridLayout31_2);
            app.EditField2_2Label.Text = 'Number of iterations for feature detection regularization, LD.';
            app.n_epoch_d = uieditfield(app.GridLayout31_2, 'numeric');
            app.n_epoch_d.ValueDisplayFormat = '%.0f';
            app.n_epoch_d.Value = 10;
            app.n_chunksLabel = uilabel(app.GridLayout31_2);
            app.n_chunksLabel.Text = 'n_chunks';
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel = uilabel(app.GridLayout31_2);
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel.Text = 'Number of steps to divide the forward pass into.';
            app.n_chunks = uieditfield(app.GridLayout31_2, 'numeric');
            app.n_chunks.ValueDisplayFormat = '%.0f';
            app.n_chunks.Value = 10;
            app.sort_modeLabel = uilabel(app.GridLayout31_2);
            app.sort_modeLabel.Text = 'sort_mode';
            app.DropDown_2Label = uilabel(app.GridLayout31_2);
            app.DropDown_2Label.Text = 'Method for sorting frames and determining parent-child branches.';
            app.sort_mode = uidropdown(app.GridLayout31_2);
            app.sort_mode.Items = {'similarity', 'linear', 'depth'};
            app.sort_mode.Editable = 'on';
            app.sort_mode.Tooltip = {'''similarity'' minimizes distance between parent and child.'; '''linear'' branches out from reference frames linearly forwards and backwards, with every parent-child one frame apart, until it reaches the first frame, last frame, or another branch. Simplest and fastest.'; '''depth'' uses shortest-path grid search, then sorts frames based on depth in the resulting parent-child tree. This can scale up to O(n4) in computation with number of frames.'};
            app.sort_mode.Value = 'similarity';
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel = uilabel(app.ParameterGridLayout);
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel.Text = 'Hover over labels for further details or guidance.';
            app.WormMotionPanel = uipanel(app.ParameterGridLayout);
            app.WormMotionPanel.Title = 'Worm Motion';
            app.GridLayout5 = uigridlayout(app.WormMotionPanel);
            app.MotionSlider = uislider(app.GridLayout5);
            app.MotionSlider.Limits = [0 3];
            app.MotionSlider.MajorTicks = [0 1 2 3];
            app.MotionSlider.MinorTicks = [];
            app.ActivityTab = uitab(app.DefaultTabGroup);
            app.ActivityTab.Title = 'Activity';
            app.GridLayout3 = uigridlayout(app.ActivityTab);
            app.Panel_41 = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.Panel_41);
            app.ScanforIssuesButton = uibutton(app.GridLayout4, 'push');
            app.ScanforIssuesButton.Text = 'Scan for Issues';
            app.SaveExitButton = uibutton(app.GridLayout4, 'push');
            app.SaveExitButton.Text = 'Save & Exit';
            app.LoadStimulusFileButton = uibutton(app.GridLayout4, 'push');
            app.LoadStimulusFileButton.Text = 'Load Stimulus File';
            app.ActivityListBox = uilistbox(app.GridLayout4);
            app.ActivityListBox.Items = {};
            app.ActivityListBox.Value = {};
            app.Panel_42 = uipanel(app.GridLayout3);
            app.GridLayout5_2 = uigridlayout(app.Panel_42);
            app.ActivityAxes = uiaxes(app.GridLayout5_2);
            title(app.ActivityAxes, 'Neuron {x} Activity')
            xlabel(app.ActivityAxes, 'Frame')
            ylabel(app.ActivityAxes, 'df/f')
            zlabel(app.ActivityAxes, 'Z')
            app.ActivityAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ActivityAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.CreditTab = uitab(app.DefaultTabGroup);
            app.CreditTab.Title = 'Credit';
            app.GridLayout14_3 = uigridlayout(app.CreditTab);
            app.Label = uilabel(app.GridLayout14_3);
            app.Label.Text = 'Tracing relies on a modified version of Zephir, a multiple object tracking algorithm based on image registration and developed by James Yu et al. ZephIR tracks keypoints in a 2D or 3D movie by registering image descriptors sampled around each keypoint. ';
            app.LaboratoryPageButton = uibutton(app.GridLayout14_3, 'push');
            app.LaboratoryPageButton.ButtonPushedFcn = createCallbackFcn(app, @LaboratoryPageButtonPushed, true);
            app.LaboratoryPageButton.Text = 'Laboratory Page';
            app.ViewPreprintButton = uibutton(app.GridLayout14_3, 'push');
            app.ViewPreprintButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPreprintButtonPushed, true);
            app.ViewPreprintButton.Text = 'View Preprint';
            app.GithubRepositoryButton = uibutton(app.GridLayout14_3, 'push');
            app.GithubRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @GithubRepositoryButtonPushed, true);
            app.GithubRepositoryButton.Text = 'Github Repository';
            app.Image2 = uiimage(app.GridLayout14_3);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Data', 'Images', 'ZephIR_Logo.png');
            app.CitationTextAreaLabel = uilabel(app.GridLayout14_3);
            app.CitationTextAreaLabel.Text = 'Citation';
            app.CitationTextArea = uitextarea(app.GridLayout14_3);
            app.CitationTextArea.Value = {'Versatile Multiple Object Tracking in Sparse 2D/3D Videos Via Diffeomorphic Image Registration'; 'James Yu, Amin Nejatbakhsh, Mahdi Torkashvand, Sahana Gangadharan, Maedeh Seyedolmohadesin, Jinmahn Kim, Liam Paninski, Vivek Venkatachalam bioRxiv 2022.07.18.500485; doi: https://doi.org/10.1101/2022.07.18.500485'; 'This article is a preprint and has not been certified by peer review'};
            app.ImageProcessingTab = uitab(app.TabGroup);
            app.ImageProcessingTab.Title = 'Image Processing';
            app.ImageProcessingTab.Tag = 'raw';
            app.ProcessingGridLayout = uigridlayout(app.ImageProcessingTab);
            app.ProcessingGridLayout.Visible = 'off';
            app.Panel_50 = uipanel(app.ProcessingGridLayout);
            app.GridLayout63 = uigridlayout(app.Panel_50);
            app.Panel_52 = uipanel(app.GridLayout63);
            app.GridLayout65 = uigridlayout(app.Panel_52);
            app.SaveButton_3 = uibutton(app.GridLayout65, 'push');
            app.SaveButton_3.Text = 'Save';
            app.ResetButton_2 = uibutton(app.GridLayout65, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.Panel_57 = uipanel(app.GridLayout63);
            app.GridLayout64 = uigridlayout(app.Panel_57);
            app.Panel_56 = uipanel(app.GridLayout64);
            app.GridLayout69 = uigridlayout(app.Panel_56);
            app.Panel_72 = uipanel(app.GridLayout69);
            app.GridLayout73 = uigridlayout(app.Panel_72);
            app.NoisethresholdKnobLabel = uilabel(app.GridLayout73);
            app.NoisethresholdKnobLabel.Text = 'Noise threshold';
            app.NoisethresholdKnob = uiknob(app.GridLayout73, 'continuous');
            app.NoisethresholdKnob.ValueChangedFcn = createCallbackFcn(app, @NoisethresholdKnobValueChanged, true);
            app.Panel_73 = uipanel(app.GridLayout69);
            app.GridLayout74 = uigridlayout(app.Panel_73);
            app.AdjustHistogramsButton = uibutton(app.GridLayout74, 'push');
            app.AdjustHistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustHistogramMenuSelected, true);
            app.AdjustHistogramsButton.Text = 'Adjust Histograms';
            app.HistogramMatchingButton = uibutton(app.GridLayout74, 'push');
            app.HistogramMatchingButton.ButtonPushedFcn = createCallbackFcn(app, @HistMatchButtonPushed, true);
            app.HistogramMatchingButton.Text = 'Histogram Matching';
            app.AutoHistogramButton = uibutton(app.GridLayout74, 'push');
            app.AutoHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @AutoHistogram, true);
            app.AutoHistogramButton.Text = 'Auto Histogram';
            app.SingleHistogramCheckBox = uicheckbox(app.GridLayout74);
            app.SingleHistogramCheckBox.Enable = 'off';
            app.SingleHistogramCheckBox.Text = 'Single Histogram';
            app.Panel_59 = uipanel(app.GridLayout64);
            app.GridLayout78 = uigridlayout(app.Panel_59);
            app.TabGroup3 = uitabgroup(app.GridLayout78);
            app.ChannelHistogramsTab = uitab(app.TabGroup3);
            app.ChannelHistogramsTab.Title = 'Channel Histograms';
            app.HistogramGrid = uigridlayout(app.ChannelHistogramsTab);
            app.a_hist_ax = uiaxes(app.HistogramGrid);
            title(app.a_hist_ax, '{Ch1} Histogram')
            zlabel(app.a_hist_ax, 'Z')
            app.a_hist_ax.XLim = [1 255];
            app.a_hist_ax.YScale = 'log';
            app.b_hist_ax = uiaxes(app.HistogramGrid);
            title(app.b_hist_ax, '{Ch2} Histogram')
            zlabel(app.b_hist_ax, 'Z')
            app.b_hist_ax.XLim = [1 255];
            app.b_hist_ax.YScale = 'log';
            app.c_hist_ax = uiaxes(app.HistogramGrid);
            title(app.c_hist_ax, '{Ch3} Histogram')
            zlabel(app.c_hist_ax, 'Z')
            app.c_hist_ax.XLim = [1 255];
            app.c_hist_ax.YScale = 'log';
            app.d_hist_ax = uiaxes(app.HistogramGrid);
            title(app.d_hist_ax, '{Ch4} Histogram')
            zlabel(app.d_hist_ax, 'Z')
            app.d_hist_ax.XLim = [1 255];
            app.d_hist_ax.YScale = 'log';
            app.d_hist_ax.Visible = 'off';
            app.e_hist_ax = uiaxes(app.HistogramGrid);
            title(app.e_hist_ax, '{Ch5} Histogram')
            zlabel(app.e_hist_ax, 'Z')
            app.e_hist_ax.XLim = [1 255];
            app.e_hist_ax.YScale = 'log';
            app.e_hist_ax.Visible = 'off';
            app.f_hist_ax = uiaxes(app.HistogramGrid);
            title(app.f_hist_ax, '{Ch6} Histogram')
            zlabel(app.f_hist_ax, 'Z')
            app.f_hist_ax.XLim = [1 255];
            app.f_hist_ax.YScale = 'log';
            app.f_hist_ax.Visible = 'off';
            app.ChannelGammasTab = uitab(app.TabGroup3);
            app.ChannelGammasTab.Title = 'Channel Gammas';
            app.GridLayout71 = uigridlayout(app.ChannelGammasTab);
            app.RedGamma_6 = uiaxes(app.GridLayout71);
            xlabel(app.RedGamma_6, 'Pixel Value')
            ylabel(app.RedGamma_6, 'Pixel Value')
            zlabel(app.RedGamma_6, 'Z')
            app.RedGamma_6.XLim = [0 255];
            app.RedGamma_6.YLim = [0 255];
            app.RedGamma_6.Visible = 'off';
            app.RedGamma_5 = uiaxes(app.GridLayout71);
            xlabel(app.RedGamma_5, 'Pixel Value')
            ylabel(app.RedGamma_5, 'Pixel Value')
            zlabel(app.RedGamma_5, 'Z')
            app.RedGamma_5.XLim = [0 255];
            app.RedGamma_5.YLim = [0 255];
            app.RedGamma_5.Visible = 'off';
            app.RedGamma_4 = uiaxes(app.GridLayout71);
            xlabel(app.RedGamma_4, 'Pixel Value')
            ylabel(app.RedGamma_4, 'Pixel Value')
            zlabel(app.RedGamma_4, 'Z')
            app.RedGamma_4.XLim = [0 255];
            app.RedGamma_4.YLim = [0 255];
            app.RedGamma_4.Visible = 'off';
            app.RedGamma_3 = uiaxes(app.GridLayout71);
            xlabel(app.RedGamma_3, 'Pixel Value')
            ylabel(app.RedGamma_3, 'Pixel Value')
            zlabel(app.RedGamma_3, 'Z')
            app.RedGamma_3.XLim = [0 255];
            app.RedGamma_3.YLim = [0 255];
            app.RedGamma_2 = uiaxes(app.GridLayout71);
            xlabel(app.RedGamma_2, 'Pixel Value')
            ylabel(app.RedGamma_2, 'Pixel Value')
            zlabel(app.RedGamma_2, 'Z')
            app.RedGamma_2.XLim = [0 255];
            app.RedGamma_2.YLim = [0 255];
            app.RedGamma = uiaxes(app.GridLayout71);
            xlabel(app.RedGamma, 'Pixel Value')
            ylabel(app.RedGamma, 'Pixel Value')
            zlabel(app.RedGamma, 'Z')
            app.RedGamma.XLim = [0 255];
            app.RedGamma.YLim = [0 255];
            app.Panel_60 = uipanel(app.GridLayout71);
            app.GridLayout72 = uigridlayout(app.Panel_60);
            app.RedEditFieldLabel = uilabel(app.GridLayout72);
            app.RedEditFieldLabel.Text = 'Red: ';
            app.RedEditField = uieditfield(app.GridLayout72, 'numeric');
            app.Panel_67 = uipanel(app.GridLayout71);
            app.GridLayout72_2 = uigridlayout(app.Panel_67);
            app.GreenLabel = uilabel(app.GridLayout72_2);
            app.GreenLabel.Text = 'Green: ';
            app.GreenEditField = uieditfield(app.GridLayout72_2, 'numeric');
            app.Panel_68 = uipanel(app.GridLayout71);
            app.GridLayout72_3 = uigridlayout(app.Panel_68);
            app.BlueEditFieldLabel = uilabel(app.GridLayout72_3);
            app.BlueEditFieldLabel.Text = 'Blue: ';
            app.BlueEditField = uieditfield(app.GridLayout72_3, 'numeric');
            app.Panel_69 = uipanel(app.GridLayout71);
            app.Panel_69.Visible = 'off';
            app.GridLayout72_4 = uigridlayout(app.Panel_69);
            app.WhiteEditFieldLabel = uilabel(app.GridLayout72_4);
            app.WhiteEditFieldLabel.Text = 'White: ';
            app.WhiteEditField = uieditfield(app.GridLayout72_4, 'numeric');
            app.Panel_70 = uipanel(app.GridLayout71);
            app.Panel_70.Visible = 'off';
            app.GridLayout72_5 = uigridlayout(app.Panel_70);
            app.DICEditFieldLabel = uilabel(app.GridLayout72_5);
            app.DICEditFieldLabel.Text = 'DIC: ';
            app.DICEditField = uieditfield(app.GridLayout72_5, 'numeric');
            app.Panel_71 = uipanel(app.GridLayout71);
            app.Panel_71.Visible = 'off';
            app.GridLayout72_6 = uigridlayout(app.Panel_71);
            app.GFPEditFieldLabel = uilabel(app.GridLayout72_6);
            app.GFPEditFieldLabel.Text = 'GFP: ';
            app.GFPEditField = uieditfield(app.GridLayout72_6, 'numeric');
            app.ProcSidePanel = uipanel(app.ProcessingGridLayout);
            app.ProcSideGrid = uigridlayout(app.ProcSidePanel);
            app.DownsamplingPanel = uipanel(app.ProcSideGrid);
            app.DownsamplingPanel.Title = 'Downsampling';
            app.GridLayout_2 = uigridlayout(app.DownsamplingPanel);
            app.Panel_49 = uipanel(app.GridLayout_2);
            app.GridLayout62 = uigridlayout(app.Panel_49);
            app.yFactorEditFieldLabel = uilabel(app.GridLayout62);
            app.yFactorEditFieldLabel.Text = 'y-Factor';
            app.yFactorEditField = uieditfield(app.GridLayout62, 'numeric');
            app.yFactorEditField.ValueChangedFcn = createCallbackFcn(app, @yFactorEditFieldValueChanged, true);
            app.yFactorEditField.Value = 1;
            app.xFactorEditFieldLabel = uilabel(app.GridLayout62);
            app.xFactorEditFieldLabel.Text = 'x-Factor';
            app.xFactorEditField = uieditfield(app.GridLayout62, 'numeric');
            app.xFactorEditField.ValueChangedFcn = createCallbackFcn(app, @xFactorEditFieldValueChanged, true);
            app.xFactorEditField.Tooltip = {'Not the one with Simon Cowell... thankfully.'};
            app.xFactorEditField.Value = 1;
            app.zSlicesEditFieldLabel = uilabel(app.GridLayout62);
            app.zSlicesEditFieldLabel.Text = 'z -Slices';
            app.zSlicesEditField = uieditfield(app.GridLayout62, 'numeric');
            app.zSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @zSlicesEditFieldValueChanged, true);
            app.MaintainaspectratioCheckBox = uicheckbox(app.GridLayout62);
            app.MaintainaspectratioCheckBox.Text = ' Maintain aspect ratio.';
            app.ResetButton = uibutton(app.GridLayout_2, 'push');
            app.ResetButton.Text = 'Reset';
            app.ApplyButton = uibutton(app.GridLayout_2, 'push');
            app.ApplyButton.Text = 'Apply';
            app.ToggleChannelsPanel = uipanel(app.ProcSideGrid);
            app.ToggleChannelsPanel.Title = 'Toggle Channels';
            app.ToggleChannelGrid = uigridlayout(app.ToggleChannelsPanel);
            app.Panel_61 = uipanel(app.ToggleChannelGrid);
            app.GridLayout26_7 = uigridlayout(app.Panel_61);
            app.ProcDICDropDown = uidropdown(app.GridLayout26_7);
            app.ProcDICDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ProcDICDropDown.Value = '1';
            app.ProcDICCheckBox = uicheckbox(app.GridLayout26_7);
            app.ProcDICCheckBox.Text = '';
            app.ProcDICLabel = uilabel(app.GridLayout26_7);
            app.ProcDICLabel.Text = 'DIC:';
            app.Panel_63 = uipanel(app.ToggleChannelGrid);
            app.GridLayout26_9 = uigridlayout(app.Panel_63);
            app.ProcGFPLabel = uilabel(app.GridLayout26_9);
            app.ProcGFPLabel.Text = 'GFP:';
            app.ProcGFPCheckBox = uicheckbox(app.GridLayout26_9);
            app.ProcGFPCheckBox.Text = '';
            app.ProcGFPDropDown = uidropdown(app.GridLayout26_9);
            app.ProcGFPDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ProcGFPDropDown.Value = '1';
            app.Panel_65 = uipanel(app.ToggleChannelGrid);
            app.GridLayout26_11 = uigridlayout(app.Panel_65);
            app.ProcWCheckBox = uicheckbox(app.GridLayout26_11);
            app.ProcWCheckBox.Text = '';
            app.ProcWDropDown = uidropdown(app.GridLayout26_11);
            app.ProcWDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ProcWDropDown.Value = '1';
            app.ProcWLabel = uilabel(app.GridLayout26_11);
            app.ProcWLabel.Text = 'W:';
            app.Panel_62 = uipanel(app.ToggleChannelGrid);
            app.GridLayout26_8 = uigridlayout(app.Panel_62);
            app.ProcGLabel = uilabel(app.GridLayout26_8);
            app.ProcGLabel.Text = 'G:';
            app.ProcGCheckBox = uicheckbox(app.GridLayout26_8);
            app.ProcGCheckBox.Text = '';
            app.ProcGCheckBox.Value = true;
            app.ProcGDropDown = uidropdown(app.GridLayout26_8);
            app.ProcGDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ProcGDropDown.Value = '1';
            app.Panel_64 = uipanel(app.ToggleChannelGrid);
            app.GridLayout26_10 = uigridlayout(app.Panel_64);
            app.ProcBLabel = uilabel(app.GridLayout26_10);
            app.ProcBLabel.Text = 'B:';
            app.ProcBCheckBox = uicheckbox(app.GridLayout26_10);
            app.ProcBCheckBox.Text = '';
            app.ProcBCheckBox.Value = true;
            app.ProcBDropDown = uidropdown(app.GridLayout26_10);
            app.ProcBDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ProcBDropDown.Value = '1';
            app.Panel_66 = uipanel(app.ToggleChannelGrid);
            app.GridLayout25_2 = uigridlayout(app.Panel_66);
            app.ProcRLabel = uilabel(app.GridLayout25_2);
            app.ProcRLabel.Text = 'R:';
            app.ProcRCheckBox = uicheckbox(app.GridLayout25_2);
            app.ProcRCheckBox.Text = '';
            app.ProcRCheckBox.Value = true;
            app.ProcRDropDown = uidropdown(app.GridLayout25_2);
            app.ProcRDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ProcRDropDown.Value = '1';
            app.ToggleVolumePanel = uipanel(app.ProcSideGrid);
            app.ToggleVolumePanel.Title = 'Toggle Volume';
            app.GridLayout77 = uigridlayout(app.ToggleVolumePanel);
            app.ProcColormapButton = uibutton(app.GridLayout77, 'state');
            app.ProcColormapButton.ValueChangedFcn = createCallbackFcn(app, @ProcColormapButtonValueChanged, true);
            app.ProcColormapButton.Enable = 'off';
            app.ProcColormapButton.Text = 'Colormap';
            app.ProcVideoButton = uibutton(app.GridLayout77, 'state');
            app.ProcVideoButton.ValueChangedFcn = createCallbackFcn(app, @ProcVideoButtonValueChanged, true);
            app.ProcVideoButton.Enable = 'off';
            app.ProcVideoButton.Text = 'Video';
            app.ImageManipulationPanel = uipanel(app.ProcSideGrid);
            app.ImageManipulationPanel.Title = 'Image Manipulation';
            app.GridLayout66 = uigridlayout(app.ImageManipulationPanel);
            app.Panel_54 = uipanel(app.GridLayout66);
            app.GridLayout68 = uigridlayout(app.Panel_54);
            app.NormalizeColorsButton = uibutton(app.GridLayout68, 'push');
            app.NormalizeColorsButton.ButtonPushedFcn = createCallbackFcn(app, @ZScoringMenuSelected, true);
            app.NormalizeColorsButton.Text = 'Normalize Colors';
            app.RegisterColorsButton = uibutton(app.GridLayout68, 'push');
            app.RegisterColorsButton.ButtonPushedFcn = createCallbackFcn(app, @ColorRegisterMenuSelected, true);
            app.RegisterColorsButton.Text = 'Register Colors';
            app.CropImageButton = uibutton(app.GridLayout68, 'push');
            app.CropImageButton.Text = 'Crop Image';
            app.ProcAxPanel = uipanel(app.ProcessingGridLayout);
            app.ProcAxGrid = uigridlayout(app.ProcAxPanel);
            app.proc_yzAxes = uiaxes(app.ProcAxGrid);
            app.proc_yzAxes.XTickLabel = '';
            app.proc_yzAxes.YTickLabel = '';
            app.proc_yzAxes.Tag = 'yz';
            app.proc_xzAxes = uiaxes(app.ProcAxGrid);
            app.proc_xzAxes.XTickLabel = '';
            app.proc_xzAxes.YTickLabel = '';
            app.proc_xzAxes.Tag = 'xz';
            app.proc_xyAxes = uiaxes(app.ProcAxGrid);
            app.proc_xyAxes.XTickLabel = '';
            app.proc_xyAxes.YTickLabel = '';
            app.proc_xyAxes.Tag = 'xy';
            app.proc_xSlider = uislider(app.ProcAxGrid);
            app.proc_xSlider.MajorTicks = [];
            app.proc_xSlider.ValueChangedFcn = createCallbackFcn(app, @proc_xSliderValueChanged, true);
            app.proc_xSlider.ValueChangingFcn = createCallbackFcn(app, @proc_xSliderValueChanging, true);
            app.proc_xSlider.MinorTicks = [];
            app.proc_xSlider.Value = 1;
            app.proc_hor_zSlider = uislider(app.ProcAxGrid);
            app.proc_hor_zSlider.MajorTicks = [];
            app.proc_hor_zSlider.ValueChangedFcn = createCallbackFcn(app, @proc_hor_zSliderValueChanged, true);
            app.proc_hor_zSlider.ValueChangingFcn = createCallbackFcn(app, @proc_hor_zSliderValueChanging, true);
            app.proc_hor_zSlider.MinorTicks = [];
            app.proc_hor_zSlider.Value = 1;
            app.proc_ySlider = uislider(app.ProcAxGrid);
            app.proc_ySlider.MajorTicks = [];
            app.proc_ySlider.ValueChangedFcn = createCallbackFcn(app, @proc_ySliderValueChanged, true);
            app.proc_ySlider.ValueChangingFcn = createCallbackFcn(app, @proc_ySliderValueChanging, true);
            app.proc_ySlider.MinorTicks = [];
            app.proc_ySlider.Value = 1;
            app.proc_vert_zSlider = uislider(app.ProcAxGrid);
            app.proc_vert_zSlider.MajorTicks = [];
            app.proc_vert_zSlider.ValueChangedFcn = createCallbackFcn(app, @proc_vert_zSliderValueChanged, true);
            app.proc_vert_zSlider.ValueChangingFcn = createCallbackFcn(app, @proc_vert_zSliderValueChanging, true);
            app.proc_vert_zSlider.MinorTicks = [];
            app.proc_vert_zSlider.Value = 1;
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.LogGrid = uigridlayout(app.LogTab);
            app.UpperLogPanel = uipanel(app.LogGrid);
            app.UpperLogGrid = uigridlayout(app.UpperLogPanel);
            app.LogLists = uitabgroup(app.UpperLogGrid);
            app.SystemInfoTab = uitab(app.LogLists);
            app.SystemInfoTab.Title = 'System Info';
            app.SystemInfoGrid = uigridlayout(app.SystemInfoTab);
            app.OperatingSystemEditFieldLabel = uilabel(app.SystemInfoGrid);
            app.OperatingSystemEditFieldLabel.Text = 'Operating System:';
            app.OperatingSystemEditField = uieditfield(app.SystemInfoGrid, 'text');
            app.OperatingSystemEditField.Enable = 'off';
            app.OperatingSystemEditField.Placeholder = 'N/A';
            app.MATLABEditField = uieditfield(app.SystemInfoGrid, 'text');
            app.MATLABEditField.Enable = 'off';
            app.MATLABEditField.Placeholder = 'N/A';
            app.MATLABEditFieldLabel = uilabel(app.SystemInfoGrid);
            app.MATLABEditFieldLabel.Text = 'MATLAB:';
            app.PythonEditField = uieditfield(app.SystemInfoGrid, 'text');
            app.PythonEditField.Enable = 'off';
            app.PythonEditField.Placeholder = 'N/A';
            app.PythonEditFieldLabel = uilabel(app.SystemInfoGrid);
            app.PythonEditFieldLabel.Text = 'Python:';
            app.NeuroPALEditField = uieditfield(app.SystemInfoGrid, 'text');
            app.NeuroPALEditField.Enable = 'off';
            app.NeuroPALEditField.Placeholder = 'N/A';
            app.NeuroPALLabel = uilabel(app.SystemInfoGrid);
            app.NeuroPALLabel.Text = 'NeuroPAL:';
            app.ZephIREditField = uieditfield(app.SystemInfoGrid, 'text');
            app.ZephIREditField.Enable = 'off';
            app.ZephIREditField.Placeholder = 'N/A';
            app.ZephIRLabel = uilabel(app.SystemInfoGrid);
            app.ZephIRLabel.Text = 'ZephIR:';
            app.CellTrackerEditField = uieditfield(app.SystemInfoGrid, 'text');
            app.CellTrackerEditField.Enable = 'off';
            app.CellTrackerEditField.Placeholder = 'N/A';
            app.DCTEditFieldLabel = uilabel(app.SystemInfoGrid);
            app.DCTEditFieldLabel.Text = '3DCT:';
            app.DependenciesPanel_2 = uipanel(app.SystemInfoGrid);
            app.DependenciesPanel_2.Title = 'Dependencies';
            app.GridLayout7 = uigridlayout(app.DependenciesPanel_2);
            app.RequirementsTree = uitree(app.GridLayout7, 'checkbox');
            app.MATLABNode = uitreenode(app.RequirementsTree);
            app.MATLABNode.Text = 'MATLAB';
            app.mlapptoolsNode = uitreenode(app.MATLABNode);
            app.mlapptoolsNode.Text = 'mlapptools';
            app.bioformatsToolboxNode = uitreenode(app.MATLABNode);
            app.bioformatsToolboxNode.Text = 'bioformats Toolbox';
            app.ImageProcessingToolboxNode = uitreenode(app.MATLABNode);
            app.ImageProcessingToolboxNode.Text = 'Image Processing Toolbox';
            app.StatisticsandMachineLearningToolboxNode = uitreenode(app.MATLABNode);
            app.StatisticsandMachineLearningToolboxNode.Text = 'Statistics and Machine Learning Toolbox';
            app.PythonNode = uitreenode(app.RequirementsTree);
            app.PythonNode.Text = 'Python';
            app.pyNWBNode = uitreenode(app.PythonNode);
            app.pyNWBNode.Text = 'pyNWB';
            app.ZephIRDependencyNode = uitreenode(app.PythonNode);
            app.ZephIRDependencyNode.Text = 'ZephIR';
            app.DCellTrackerNode = uitreenode(app.PythonNode);
            app.DCellTrackerNode.Text = '3DCellTracker';
            app.MiscellaneousNode = uitreenode(app.RequirementsTree);
            app.MiscellaneousNode.Text = 'Miscellaneous';
            app.WebWrapNode = uitreenode(app.MiscellaneousNode);
            app.WebWrapNode.Text = 'WebWrap';
            app.LogControlPanel = uigridlayout(app.UpperLogGrid);
            app.OpenRepositoryButton = uibutton(app.LogControlPanel, 'push');
            app.OpenRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRepositoryButtonPushed, true);
            app.OpenRepositoryButton.Text = 'Open Repository.';
            app.SubmitbugreportButton = uibutton(app.LogControlPanel, 'push');
            app.SubmitbugreportButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitbugreportButtonPushed, true);
            app.SubmitbugreportButton.Text = 'Submit bug report.';
            app.LogFilterButtonGroup = uibuttongroup(app.LogControlPanel);
            app.LogFilterButtonGroup.Title = 'Log Filter';
            app.ShowalleventsButton = uiradiobutton(app.LogFilterButtonGroup);
            app.ShowalleventsButton.Text = 'Show all events';
            app.ShowalleventsButton.Value = true;
            app.SystemeventsonlyButton = uiradiobutton(app.LogFilterButtonGroup);
            app.SystemeventsonlyButton.Text = 'System events only';
            app.MATLABeventsonlyButton = uiradiobutton(app.LogFilterButtonGroup);
            app.MATLABeventsonlyButton.Text = 'MATLAB events only';
            app.PythoneventsonlyButton = uiradiobutton(app.LogFilterButtonGroup);
            app.PythoneventsonlyButton.Text = 'Python events only';
            app.OpenlogfileButton = uibutton(app.LogControlPanel, 'push');
            app.OpenlogfileButton.Text = 'Open log file.';
            app.Panel_2 = uipanel(app.LogControlPanel);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.EnablelogCheckBox = uicheckbox(app.GridLayout2);
            app.EnablelogCheckBox.Text = 'Enable log.';
            app.EnablelogCheckBox.Value = true;
            app.EnablelognotificationsCheckBox = uicheckbox(app.GridLayout2);
            app.EnablelognotificationsCheckBox.Text = 'Enable log notifications.';
            app.EnabledebugmenuCheckBox = uicheckbox(app.GridLayout2);
            app.EnabledebugmenuCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnabledebugmenuCheckBoxValueChanged, true);
            app.EnabledebugmenuCheckBox.Text = 'Enable debug menu.';
            app.LoadedFilesPanel_2 = uipanel(app.LogControlPanel);
            app.LoadedFilesPanel_2.Title = 'Loaded Files';
            app.GridLayout8 = uigridlayout(app.LoadedFilesPanel_2);
            app.FileListTree = uitree(app.GridLayout8, 'checkbox');
            app.DataNode = uitreenode(app.FileListTree);
            app.DataNode.Text = 'Data';
            app.DataMetadataNode = uitreenode(app.DataNode);
            app.DataMetadataNode.Text = 'Metadata';
            app.VolumeNode = uitreenode(app.DataNode);
            app.VolumeNode.Text = 'Volume';
            app.NeuronsNode = uitreenode(app.DataNode);
            app.NeuronsNode.Text = 'Neurons';
            app.ProcessingNode = uitreenode(app.FileListTree);
            app.ProcessingNode.Text = 'Processing';
            app.DownsamplingNode = uitreenode(app.ProcessingNode);
            app.DownsamplingNode.Text = 'Downsampling';
            app.SegmentationNode = uitreenode(app.ProcessingNode);
            app.SegmentationNode.Text = 'Segmentation';
            app.ModelNode = uitreenode(app.SegmentationNode);
            app.ModelNode.Text = 'Model';
            app.NeuronInfoNode = uitreenode(app.SegmentationNode);
            app.NeuronInfoNode.Text = 'Neuron Info';
            app.ActivityNode = uitreenode(app.ProcessingNode);
            app.ActivityNode.Text = 'Activity';
            app.ZephIRNode = uitreenode(app.ActivityNode);
            app.ZephIRNode.Text = 'ZephIR';
            app.VideoNode = uitreenode(app.ZephIRNode);
            app.VideoNode.Text = 'Video';
            app.AnnotationsNode = uitreenode(app.ZephIRNode);
            app.AnnotationsNode.Text = 'Annotations';
            app.ZephIRMetaDataNode = uitreenode(app.ZephIRNode);
            app.ZephIRMetaDataNode.Text = 'Metadata';
            app.ActivityCSVNode = uitreenode(app.ActivityNode);
            app.ActivityCSVNode.Text = 'Activity CSV';
            app.OutputNode = uitreenode(app.FileListTree);
            app.OutputNode.Text = 'Output';
            app.NeuroPALArrayNode = uitreenode(app.OutputNode);
            app.NeuroPALArrayNode.Text = 'NeuroPAL Array';
            app.NeuroPALNeuronIDsNode = uitreenode(app.OutputNode);
            app.NeuroPALNeuronIDsNode.Text = 'NeuroPAL Neuron IDs';
            app.Log = uitextarea(app.LogGrid);
            app.Log.Placeholder = '(Empty Log.)';
            app.HoverLabel = uilabel(app.CELL_ID);
            app.HoverLabel.Visible = 'off';
            app.HoverLabel.Text = 'HoverLabel (Don''t Delete)';
            app.AdjustNeuronMarkerAlignmentPanel = uipanel(app.CELL_ID);
            app.AdjustNeuronMarkerAlignmentPanel.Title = 'Adjust Neuron Marker Alignment';
            app.AdjustNeuronMarkerAlignmentPanel.Visible = 'off';
            app.NeuronMarkerGrid = uigridlayout(app.AdjustNeuronMarkerAlignmentPanel);
            app.DeformNeuronMarkersPanel = uipanel(app.NeuronMarkerGrid);
            app.DeformNeuronMarkersPanel.Title = 'Deform Neuron Markers';
            app.GridLayout17_2 = uigridlayout(app.DeformNeuronMarkersPanel);
            app.HeightSpinnerLabel = uilabel(app.GridLayout17_2);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout17_2);
            app.WidthSpinnerLabel = uilabel(app.GridLayout17_2);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.GridLayout17_2);
            app.ScaleEditFieldLabel = uilabel(app.GridLayout17_2);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.GridLayout17_2, 'numeric');
            app.SaveButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.SaveButton.Text = 'Save';
            app.MoveNeuronMarkersPanel = uipanel(app.NeuronMarkerGrid);
            app.MoveNeuronMarkersPanel.Title = 'Move Neuron Markers';
            app.GridLayout16_3 = uigridlayout(app.MoveNeuronMarkersPanel);
            app.FlipVerticallyButton = uibutton(app.GridLayout16_3, 'push');
            app.FlipVerticallyButton.Text = 'Flip Vertically';
            app.CenterVerticallyButton = uibutton(app.GridLayout16_3, 'push');
            app.CenterVerticallyButton.Text = 'Center Vertically';
            app.CenterHorizontallyButton = uibutton(app.GridLayout16_3, 'push');
            app.CenterHorizontallyButton.Text = 'Center Horizontally';
            app.Panel_45 = uipanel(app.GridLayout16_3);
            app.GridLayout27_2 = uigridlayout(app.Panel_45);
            app.CW = uibutton(app.GridLayout27_2, 'push');
            app.CW.Text = '↻';
            app.CCW = uibutton(app.GridLayout27_2, 'push');
            app.CCW.Text = '↺';
            app.Down = uibutton(app.GridLayout27_2, 'push');
            app.Down.Text = '⬇️';
            app.Right = uibutton(app.GridLayout27_2, 'push');
            app.Right.Text = '➡️';
            app.Up = uibutton(app.GridLayout27_2, 'push');
            app.Up.Text = '⬆️';
            app.Left = uibutton(app.GridLayout27_2, 'push');
            app.Left.Text = '⬅️';
            app.FlipHorizontallyButton = uibutton(app.GridLayout16_3, 'push');
            app.FlipHorizontallyButton.Text = 'Flip Horizontally';
            app.Panel_46 = uipanel(app.GridLayout16_3);
            app.GridLayout18_2 = uigridlayout(app.Panel_46);
            app.MoveEditField = uieditfield(app.GridLayout18_2, 'numeric');
            app.MoveEditField.ValueDisplayFormat = '%.0f';
            app.MoveEditField.Value = 5;
            app.MoveLabel = uilabel(app.GridLayout18_2);
            app.MoveLabel.Text = 'Move ';
            app.pixelsandLabel = uilabel(app.GridLayout18_2);
            app.pixelsandLabel.Text = 'pixels and';
            app.RotateEditField = uieditfield(app.GridLayout18_2, 'numeric');
            app.RotateEditField.Value = 2;
            app.degreesatatimeLabel = uilabel(app.GridLayout18_2);
            app.degreesatatimeLabel.Text = 'degrees at a time.';
            app.ImportNeuronsfromCSVButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.ImportNeuronsfromCSVButton.Text = 'Import Neurons from CSV';
            app.CancelButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.CancelButton.Text = 'Cancel';
            app.SelectNeuronSubsetButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.SelectNeuronSubsetButton.Text = 'Select Neuron Subset';
            app.Panel_43 = uipanel(app.NeuronMarkerGrid);
            app.GridLayout53_2 = uigridlayout(app.Panel_43);
            app.MarkerSizeLabel_2 = uilabel(app.GridLayout53_2);
            app.MarkerSizeLabel_2.Text = 'Marker Size';
            app.MarkerSizeSlider_2 = uislider(app.GridLayout53_2);
            app.MarkerSizeSlider_2.Limits = [1 30];
            app.MarkerSizeSlider_2.MajorTicks = [];
            app.MarkerSizeSlider_2.MinorTicks = [];
            app.MarkerSizeSlider_2.Value = 5;
            app.ResetMarkerPositionsButton = uibutton(app.NeuronMarkerGrid, 'push');
            app.ResetMarkerPositionsButton.Text = 'Reset Marker Positions';
            app.Panel_44 = uipanel(app.NeuronMarkerGrid);
            app.GridLayout48_2 = uigridlayout(app.Panel_44);
            app.AutojumponIDCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.AutojumponIDCheckBox_2.Text = 'Auto-jump on ID.';
            app.OverlaylastIDdframeCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.OverlaylastIDdframeCheckBox_2.Text = 'Overlay last ID''d frame.';
            app.OverlayNeuroPALvolCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.OverlayNeuroPALvolCheckBox_2.Text = 'Overlay NeuroPAL vol.';
            app.OverlayFrameMIPCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.OverlayFrameMIPCheckBox_2.Text = 'Overlay Frame MIP.';
            app.ShowallneuronsCheckBox_2 = uicheckbox(app.GridLayout48_2);
            app.ShowallneuronsCheckBox_2.Text = 'Show all neurons.';
            app.EnableClickDragCheckBox = uicheckbox(app.GridLayout48_2);
            app.EnableClickDragCheckBox.Text = 'Enable Click & Drag.';
            app.EnableinvolumeeditingCheckBox = uicheckbox(app.GridLayout48_2);
            app.EnableinvolumeeditingCheckBox.Text = 'Enable in-volume editing.';
            app.EnableinvolumeeditingCheckBox.Value = true;
            app.AdvancedParameterPanel = uipanel(app.CELL_ID);
            app.AdvancedParameterPanel.Visible = 'off';
            app.NeuronMarkerGrid_2 = uigridlayout(app.AdvancedParameterPanel);
            app.OpenGridSearchMenuButton = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.OpenGridSearchMenuButton.Text = 'Open Grid Search Menu';
            app.SaveButton_2 = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.SaveButton_2.Text = 'Save';
            app.CancelButton_2 = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.CancelButton_2.Text = 'Cancel';
            app.OpenZephIRdocumentationButton = uibutton(app.NeuronMarkerGrid_2, 'push');
            app.OpenZephIRdocumentationButton.Text = 'Open ZephIR documentation';
            app.TabGroup2 = uitabgroup(app.NeuronMarkerGrid_2);
            app.RegularizationLossCoefficientsTab = uitab(app.TabGroup2);
            app.RegularizationLossCoefficientsTab.Title = 'Regularization & Loss Coefficients';
            app.GridLayout35 = uigridlayout(app.RegularizationLossCoefficientsTab);
            app.dLabel = uilabel(app.GridLayout35);
            app.dLabel.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.dLabel.Text = 'λd';
            app.EditField3Label = uilabel(app.GridLayout35);
            app.EditField3Label.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.EditField3Label.Text = 'Coefficient for feature detection loss, λD. ';
            app.l_d = uieditfield(app.GridLayout35, 'numeric');
            app.l_d.ValueDisplayFormat = '%.2f';
            app.l_d.Enable = 'off';
            app.l_d.Value = -1;
            app.tLabel = uilabel(app.GridLayout35);
            app.tLabel.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.tLabel.Text = 'λt';
            app.EditField2Label = uilabel(app.GridLayout35);
            app.EditField2Label.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.EditField2Label.Text = 'Coefficient for temporal smoothing loss, λT, enforcing a 0th-order linear fit for intensity over n_frame frames.';
            app.l_t = uieditfield(app.GridLayout35, 'numeric');
            app.l_t.ValueDisplayFormat = '%.2f';
            app.l_t.Enable = 'off';
            app.l_t.Value = -1;
            app.NLabel = uilabel(app.GridLayout35);
            app.NLabel.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.NLabel.Text = 'λN';
            app.EditField4Label = uilabel(app.GridLayout35);
            app.EditField4Label.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.EditField4Label.Text = 'Coefficient for spring constant for intra-keypoint spatial regularization, λN.';
            app.l_n = uieditfield(app.GridLayout35, 'numeric');
            app.l_n.ValueDisplayFormat = '%.2f';
            app.l_n.Enable = 'off';

            app.l_n.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.l_n.Value = 5;
            app.NmodeLabel = uilabel(app.GridLayout35);
            app.NmodeLabel.Tooltip = {'''disp'': use inter-keypoint displacements'; '''norm'': use inter-keypoint distances (rotation is not penalized)'; '''ljp'': use a Lenard-Jones potential on inter-keypoint distances (collapsing onto the same position is highly penalized)'; 'default: ''disp'''};
            app.NmodeLabel.Text = 'λN mode';
            app.MethodtouseforcalculatingNLabel = uilabel(app.GridLayout35);
            app.MethodtouseforcalculatingNLabel.Text = 'Method to use for calculating LN.';
            app.l_n_mode = uidropdown(app.GridLayout35);
            app.l_n_mode.Items = {'disp', 'norm', 'ljp'};
            app.l_n_mode.Editable = 'on';
            app.l_n_mode.Enable = 'off';
            app.l_n_mode.Value = 'disp';
            app.nn_maxLabel = uilabel(app.GridLayout35);
            app.nn_maxLabel.Tooltip = {'default: 5'};
            app.nn_maxLabel.Text = 'nn_max';
            app.EditFieldLabel_2 = uilabel(app.GridLayout35);
            app.EditFieldLabel_2.Tooltip = {'default: 5'};
            app.EditFieldLabel_2.Text = 'Maximum number of neighboring keypoints to be connected by springs for calculating λN.';
            app.nn_max = uieditfield(app.GridLayout35, 'numeric');
            app.nn_max.ValueDisplayFormat = '%.2f';
            app.nn_max.Enable = 'off';
            app.nn_max.Tooltip = {'default: 5'};
            app.l_d_grid = uicheckbox(app.GridLayout35);
            app.l_d_grid.Text = '';
            app.l_t_grid = uicheckbox(app.GridLayout35);
            app.l_t_grid.Text = '';
            app.l_n_grid = uicheckbox(app.GridLayout35);
            app.l_n_grid.Text = '';
            app.l_n_mode_grid = uicheckbox(app.GridLayout35);
            app.l_n_mode_grid.Text = '';
            app.nn_max_grid = uicheckbox(app.GridLayout35);
            app.nn_max_grid.Text = '';
            app.GridSearchLabel = uilabel(app.GridLayout35);
            app.GridSearchLabel.Text = 'Grid Search?';
            app.ParameterLabel = uilabel(app.GridLayout35);
            app.ParameterLabel.Text = 'Parameter';
            app.DescriptionLabel = uilabel(app.GridLayout35);
            app.DescriptionLabel.Text = 'Description';
            app.GradientLearningRateTab = uitab(app.TabGroup2);
            app.GradientLearningRateTab.Title = 'Gradient & Learning Rate';
            app.GridLayout32 = uigridlayout(app.GradientLearningRateTab);
            app.lr_ceilingLabel = uilabel(app.GridLayout32);
            app.lr_ceilingLabel.Text = 'lr_ceiling';
            app.EditFieldLabel_3 = uilabel(app.GridLayout32);
            app.EditFieldLabel_3.Text = 'Maximum value for initial learning rate. Note that learning rate decays by a factor of 0.5 every 10 epochs.';
            app.lr_ceiling = uieditfield(app.GridLayout32, 'numeric');
            app.lr_ceiling.Enable = 'off';
            app.lr_ceiling.Value = 0.2;
            app.LRFloorLabel = uilabel(app.GridLayout32);
            app.LRFloorLabel.Text = 'LR Floor';
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel = uilabel(app.GridLayout32);
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel.Text = 'Minimum value for initial learning rate. default: 0.02';
            app.lr_floor = uieditfield(app.GridLayout32, 'numeric');
            app.lr_floor.ValueDisplayFormat = '%.2f';
            app.lr_floor.Enable = 'off';
            app.lr_floor.Value = 0.02;
            app.LRCoeffLabel = uilabel(app.GridLayout32);
            app.LRCoeffLabel.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.LRCoeffLabel.Text = 'LR Coeff';
            app.EditField6Label = uilabel(app.GridLayout32);
            app.EditField6Label.Text = 'Initial learning rate, times the distance between current frame and its parent.';
            app.lr_coeff = uieditfield(app.GridLayout32, 'numeric');
            app.lr_coeff.ValueDisplayFormat = '%.2f';
            app.lr_coeff.Enable = 'off';
            app.lr_coeff.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.lr_coeff.Value = 2;
            app.clip_gradLabel = uilabel(app.GridLayout32);
            app.clip_gradLabel.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.clip_gradLabel.Text = 'clip_grad';
            app.EditFieldLabel_4 = uilabel(app.GridLayout32);
            app.EditFieldLabel_4.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.EditFieldLabel_4.Text = 'Max value for gradients for gradient descent. -1 to uncap.';
            app.clip_grad = uieditfield(app.GridLayout32, 'numeric');
            app.clip_grad.ValueDisplayFormat = '%.2f';
            app.clip_grad.Enable = 'off';
            app.clip_grad.Value = -1;
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel = uilabel(app.GridLayout32);
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Text = 'Multiply gradients in the z-axis by (1 + z_compensator).';
            app.z_comp = uieditfield(app.GridLayout32, 'numeric');
            app.z_comp.ValueDisplayFormat = '%.2f';
            app.z_comp.Enable = 'off';
            app.z_comp.Value = -1;
            app.ZCompLabel = uilabel(app.GridLayout32);
            app.ZCompLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.ZCompLabel.Text = 'Z Comp.';
            app.clip_grad_grid = uicheckbox(app.GridLayout32);
            app.clip_grad_grid.Text = '';
            app.lr_coeff_grid = uicheckbox(app.GridLayout32);
            app.lr_coeff_grid.Text = '';
            app.lr_floor_grid = uicheckbox(app.GridLayout32);
            app.lr_floor_grid.Text = '';
            app.lr_ceil_grid = uicheckbox(app.GridLayout32);
            app.lr_ceil_grid.Text = '';
            app.z_comp_grid = uicheckbox(app.GridLayout32);
            app.z_comp_grid.Text = '';
            app.GridSearchLabel_2 = uilabel(app.GridLayout32);
            app.GridSearchLabel_2.Text = 'Grid Search?';
            app.ParameterLabel_2 = uilabel(app.GridLayout32);
            app.ParameterLabel_2.Text = 'Parameter';
            app.DescriptionLabel_2 = uilabel(app.GridLayout32);
            app.DescriptionLabel_2.Text = 'Description';
            app.RetrievalFunctionTab = uitab(app.TabGroup2);
            app.RetrievalFunctionTab.Title = 'Retrieval Function';
            app.GridLayout50 = uigridlayout(app.RetrievalFunctionTab);
            app.TextArea_6 = uitextarea(app.GridLayout50);
            app.TextArea_6.Value = {'Test'};
            app.GridSearchPanel = uipanel(app.CELL_ID);
            app.GridSearchPanel.Visible = 'off';
            app.GridLayout54 = uigridlayout(app.GridSearchPanel);
            app.TrackingSelectionPanel_2 = uipanel(app.GridLayout54);
            app.TrackingSelectionPanel_2.Title = 'Tracking Selection';
            app.GridLayout49_2 = uigridlayout(app.TrackingSelectionPanel_2);
            app.EditField_3 = uieditfield(app.GridLayout49_2, 'numeric');
            app.EndFrameLabel_2 = uilabel(app.GridLayout49_2);
            app.EndFrameLabel_2.Text = 'End Frame:';
            app.EditField_4 = uieditfield(app.GridLayout49_2, 'numeric');
            app.StartingFrameLabel_2 = uilabel(app.GridLayout49_2);
            app.StartingFrameLabel_2.Text = 'Starting Frame:';
            app.ListBox = uilistbox(app.GridLayout49_2);
            app.WorldlinestoincludeLabel = uilabel(app.GridLayout49_2);
            app.WorldlinestoincludeLabel.Text = 'Worldlines to include:';
            app.TrackspecificregionButton_2 = uibutton(app.GridLayout49_2, 'push');
            app.TrackspecificregionButton_2.Text = 'Track specific region';
            app.VideoPreprocessingPanel = uipanel(app.GridLayout54);
            app.VideoPreprocessingPanel.Title = 'Video Pre-processing';
            app.GridLayout55 = uigridlayout(app.VideoPreprocessingPanel);
            app.DownsamplingFactorxyEditFieldLabel = uilabel(app.GridLayout55);
            app.DownsamplingFactorxyEditFieldLabel.Text = 'Downsampling Factor (xy)';
            app.DownsamplingFactorxyEditField = uieditfield(app.GridLayout55, 'numeric');
            app.DownsamplingFactorzEditFieldLabel = uilabel(app.GridLayout55);
            app.DownsamplingFactorzEditFieldLabel.Text = 'Downsampling Factor (z)';
            app.DownsamplingFactorzEditField = uieditfield(app.GridLayout55, 'numeric');
            app.DownsamplingFactortEditFieldLabel = uilabel(app.GridLayout55);
            app.DownsamplingFactortEditFieldLabel.Text = 'Downsampling Factor (t)';
            app.DownsamplingFactortEditField = uieditfield(app.GridLayout55, 'numeric');
            app.OnlyappliestogridsearchLabel = uilabel(app.GridLayout55);
            app.OnlyappliestogridsearchLabel.Text = '(Only applies to grid search.)';
            app.ListBox_2 = uilistbox(app.GridLayout55);
            app.ChannelstoincludeLabel = uilabel(app.GridLayout55);
            app.ChannelstoincludeLabel.Text = 'Channels to include:';
            app.SearchrangesPanel = uipanel(app.GridLayout54);
            app.SearchrangesPanel.Title = 'Search ranges';
            app.GridLayout56 = uigridlayout(app.SearchrangesPanel);
            app.UITable = uitable(app.GridLayout56);
            app.UITable.ColumnName = {'Parameter'; 'Low End'; 'High End'};
            app.UITable.RowName = {};
            app.EstimatedSearchtimeLabel = uilabel(app.GridLayout54);
            app.EstimatedSearchtimeLabel.Text = 'Estimated Search time:';
            app.CalculateButton = uibutton(app.GridLayout54, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.Panel_47 = uipanel(app.GridLayout54);
            app.GridLayout57 = uigridlayout(app.Panel_47);
            app.EditField_5 = uieditfield(app.GridLayout57, 'numeric');
            app.minutesLabel = uilabel(app.GridLayout57);
            app.minutesLabel.Text = 'minutes.';
            app.StartGridSearchButton = uibutton(app.GridLayout54, 'push');
            app.StartGridSearchButton.Text = 'Start Grid Search';
            app.CancelGridSearchButton = uibutton(app.GridLayout54, 'push');
            app.CancelGridSearchButton.Text = 'Cancel Grid Search';
            app.TrackingButton = uibutton(app.CELL_ID, 'push');
            app.TrackingButton.ButtonPushedFcn = createCallbackFcn(app, @TraceActivityMenu_2Selected, true);
            app.TrackingButton.Visible = 'off';
            app.TrackingButton.Text = {'Click to'; 'load Video'};
            app.IdButton = uibutton(app.CELL_ID, 'push');
            app.IdButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFile, true);
            app.IdButton.Visible = 'off';
            app.IdButton.Text = {'Click to'; 'load Volume'};
            app.ProcessingButton = uibutton(app.CELL_ID, 'push');
            app.ProcessingButton.Visible = 'off';
            app.ProcessingButton.Text = {'Click to'; 'load Volume'};
            app.PlaceholderButtonsCovertabsifnodataloadedLabel = uilabel(app.CELL_ID);
            app.PlaceholderButtonsCovertabsifnodataloadedLabel.Visible = 'off';
            app.PlaceholderButtonsCovertabsifnodataloadedLabel.Text = 'Placeholder Buttons (Cover tabs if no data loaded)';
            app.PlaceholderTrackingtabsLabel = uilabel(app.CELL_ID);
            app.PlaceholderTrackingtabsLabel.Visible = 'off';
            app.PlaceholderTrackingtabsLabel.Text = 'Placeholder Tracking tabs';
            app.PlaceholderActivitytabLabel = uilabel(app.CELL_ID);
            app.PlaceholderActivitytabLabel.Visible = 'off';
            app.PlaceholderActivitytabLabel.Text = 'Placeholder Activity tab';
            % Show the figure after all components are created
            app.CELL_ID.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualize_light

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CELL_ID)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CELL_ID)
        end
    end
end